classdef storage_manager_item < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout_Main           matlab.ui.container.GridLayout
        GridLayout_PercentageBar  matlab.ui.container.GridLayout
        Label_PercentageLeft      matlab.ui.control.Label
        Label_PercentageUsed      matlab.ui.control.Label
        DropDown_StorageLocation  matlab.ui.control.DropDown
        Label_Tag                 matlab.ui.control.Label
    end

    properties (Access = public, Constant)
        NodeTag (1,1) string {mustBeNonmissing(NodeTag)} = 1; % Node tag to display
    end
    
    properties (AbortSet, Access = public, GetObservable, SetObservable)
        Storage (1,1) string {mustBeMember(Storage, ["MEM","HDD"])} = "MEM"; % The storage location
        Value (1,1) double {mustBeInRange(Value, 0,1)} = 0.5; % The percentage value, 0 - 1
    end
    
    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % 使用此函数更新底层组件
            
        end

        % 创建底层组件
        function setup(comp)

            comp.Position = [1 1 260 25];

            % Create GridLayout_Main
            comp.GridLayout_Main = uigridlayout(comp);
            comp.GridLayout_Main.ColumnWidth = {40, '1x', 60};
            comp.GridLayout_Main.RowHeight = {'1x'};
            comp.GridLayout_Main.ColumnSpacing = 5;
            comp.GridLayout_Main.Padding = [0 0 0 0];
            comp.GridLayout_Main.Scrollable = 'on';

            % Create Label_Tag
            comp.Label_Tag = uilabel(comp.GridLayout_Main);
            comp.Label_Tag.HorizontalAlignment = 'center';
            comp.Label_Tag.FontSize = 14;
            comp.Label_Tag.FontWeight = 'bold';
            comp.Label_Tag.Layout.Row = 1;
            comp.Label_Tag.Layout.Column = 1;
            comp.Label_Tag.Text = '1';

            % Create DropDown_StorageLocation
            comp.DropDown_StorageLocation = uidropdown(comp.GridLayout_Main);
            comp.DropDown_StorageLocation.Items = {'MEM', 'HDD'};
            comp.DropDown_StorageLocation.ItemsData = {'MEM', 'HDD'};
            comp.DropDown_StorageLocation.Layout.Row = 1;
            comp.DropDown_StorageLocation.Layout.Column = 3;
            comp.DropDown_StorageLocation.Value = 'MEM';

            % Create GridLayout_PercentageBar
            comp.GridLayout_PercentageBar = uigridlayout(comp.GridLayout_Main);
            comp.GridLayout_PercentageBar.ColumnWidth = {'3x', '1x'};
            comp.GridLayout_PercentageBar.RowHeight = {'1x'};
            comp.GridLayout_PercentageBar.ColumnSpacing = 0;
            comp.GridLayout_PercentageBar.Padding = [0 8 0 7];
            comp.GridLayout_PercentageBar.Layout.Row = 1;
            comp.GridLayout_PercentageBar.Layout.Column = 2;

            % Create Label_PercentageUsed
            comp.Label_PercentageUsed = uilabel(comp.GridLayout_PercentageBar);
            comp.Label_PercentageUsed.BackgroundColor = [0.302 0.7451 0.9333];
            comp.Label_PercentageUsed.Layout.Row = 1;
            comp.Label_PercentageUsed.Layout.Column = 1;
            comp.Label_PercentageUsed.Text = '';

            % Create Label_PercentageLeft
            comp.Label_PercentageLeft = uilabel(comp.GridLayout_PercentageBar);
            comp.Label_PercentageLeft.BackgroundColor = [0.902 0.902 0.902];
            comp.Label_PercentageLeft.Layout.Row = 1;
            comp.Label_PercentageLeft.Layout.Column = 2;
            comp.Label_PercentageLeft.Text = '';
        end
    end
end