classdef Register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Reg3DV500WittgensteinUIFigure  matlab.ui.Figure
        Menu_File                      matlab.ui.container.Menu
        Menu_NewProject                matlab.ui.container.Menu
        Menu_OpenProject               matlab.ui.container.Menu
        Menu_CloseProject              matlab.ui.container.Menu
        Menu_Save                      matlab.ui.container.Menu
        Menu_SaveAs                    matlab.ui.container.Menu
        Menu_Import                    matlab.ui.container.Menu
        Menu_ImportImage               matlab.ui.container.Menu
        Menu_ImportTime                matlab.ui.container.Menu
        Menu_Export                    matlab.ui.container.Menu
        Menu_ExportMovie               matlab.ui.container.Menu
        Menu_ExportTime                matlab.ui.container.Menu
        Menu_Quit                      matlab.ui.container.Menu
        Menu_Image                     matlab.ui.container.Menu
        Menu_LightContrast             matlab.ui.container.Menu
        Menu_ZProjection               matlab.ui.container.Menu
        Menu_MaxZProjection            matlab.ui.container.Menu
        Menu_MinZProjection            matlab.ui.container.Menu
        Menu_MedianZProjection         matlab.ui.container.Menu
        Menu_MeanZProjection           matlab.ui.container.Menu
        Menu_ChannelAlignment          matlab.ui.container.Menu
        Menu_Comments                  matlab.ui.container.Menu
        Menu_AddTimeStamp              matlab.ui.container.Menu
        Menu_AddScaleBar               matlab.ui.container.Menu
        Menu_AddMetaData               matlab.ui.container.Menu
        Menu_Animation                 matlab.ui.container.Menu
        Menu_1F                        matlab.ui.container.Menu
        Menu_5F                        matlab.ui.container.Menu
        Menu_15F                       matlab.ui.container.Menu
        Menu_PlayMode                  matlab.ui.container.Menu
        Menu_Direct                    matlab.ui.container.Menu
        Menu_Loop                      matlab.ui.container.Menu
        Menu_Crop3D                    matlab.ui.container.Menu
        Menu_CropXY                    matlab.ui.container.Menu
        Menu_CropZ                     matlab.ui.container.Menu
        Menu_CropT                     matlab.ui.container.Menu
        Menu_Flip                      matlab.ui.container.Menu
        Menu_FlipHorizontal            matlab.ui.container.Menu
        Menu_FlipVertical              matlab.ui.container.Menu
        Menu_Rotate                    matlab.ui.container.Menu
        Menu_RotateClockwise90         matlab.ui.container.Menu
        Menu_RotateCounterclockwise90  matlab.ui.container.Menu
        Menu_Analyze                   matlab.ui.container.Menu
        Menu_Registration              matlab.ui.container.Menu
        Menu_RegOptions                matlab.ui.container.Menu
        Menu_RegRange                  matlab.ui.container.Menu
        Menu_RegEvaluate               matlab.ui.container.Menu
        Menu_CellSegmentor             matlab.ui.container.Menu
        Menu_SignalExtractor           matlab.ui.container.Menu
        Menu_Advanced                  matlab.ui.container.Menu
        Menu_ResourceManager           matlab.ui.container.Menu
        Menu_HistoryManager            matlab.ui.container.Menu
        Menu_PluginsManager            matlab.ui.container.Menu
        Menu_Help                      matlab.ui.container.Menu
        Menu_About                     matlab.ui.container.Menu
        Menu_Documents                 matlab.ui.container.Menu
        GridLayout_RegView             matlab.ui.container.GridLayout
        GridLayout_RegBody             matlab.ui.container.GridLayout
        Panel_Viewer                   matlab.ui.container.Panel
        GridLayout_ImageViewer         matlab.ui.container.GridLayout
        Label_ImageInfo                matlab.ui.control.Label
        Slider_Time                    matlab.ui.control.Slider
        Label                          matlab.ui.control.Label
        GridLayout_ViewBody            matlab.ui.container.GridLayout
        GridLayout_MainWin             matlab.ui.container.GridLayout
        GridLayout_OuterPB             matlab.ui.container.GridLayout
        GridLayout_ProgressBar         matlab.ui.container.GridLayout
        Label_ProgressBar              matlab.ui.control.Label
        GridLayout_SidebarRegion       matlab.ui.container.GridLayout
        Slider_HistoryViewSplitter     matlab.ui.control.Slider
        GridLayout6                    matlab.ui.container.GridLayout
        Image_HMIndicator              matlab.ui.control.Image
        Image_Update                   matlab.ui.control.Image
        Spinner_Slice                  matlab.ui.control.Spinner
        GridLayout                     matlab.ui.container.GridLayout
        Spinner_Time                   matlab.ui.control.Spinner
        Slider_Slice                   matlab.ui.control.Slider
        Label_2                        matlab.ui.control.Label
        Panel_Control                  matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        DropDown_Source                matlab.ui.control.DropDown
        Label_19                       matlab.ui.control.Label
        Button_Run                     matlab.ui.control.StateButton
        GridLayout3                    matlab.ui.container.GridLayout
        Label_20                       matlab.ui.control.Label
        TextArea_Log                   matlab.ui.control.TextArea
        Switch_Log                     matlab.ui.control.RockerSwitch
        GridLayout_HistoryManager      matlab.ui.container.GridLayout
        Panel_StorageViewer            matlab.ui.container.Panel
        GridLayout_StorageViewer       matlab.ui.container.GridLayout
        Panel_OperationsViewer         matlab.ui.container.Panel
        GridLayout_OperationViewer     matlab.ui.container.GridLayout
        Tree_Operations                matlab.ui.container.Tree
        Panel_OperationSnapshot        matlab.ui.container.Panel
        GridLayout_OperationSnapshot   matlab.ui.container.GridLayout
        TextArea_OperationSnapshot     matlab.ui.control.TextArea
        ContextMenu_Player             matlab.ui.container.ContextMenu
        Menu_ROI                       matlab.ui.container.Menu
        Menu_RectangleROI              matlab.ui.container.Menu
        Menu_CircleROI                 matlab.ui.container.Menu
        Menu_PolygonROI                matlab.ui.container.Menu
        Menu_FreehandROI               matlab.ui.container.Menu
        Menu_Channel                   matlab.ui.container.Menu
        Menu_DisplayMode               matlab.ui.container.Menu
        Menu_View2D                    matlab.ui.container.Menu
        Menu_View3D                    matlab.ui.container.Menu
        ContextMenu_Controller         matlab.ui.container.ContextMenu
        Menu_ExportLog                 matlab.ui.container.Menu
        Menu_ClearLog                  matlab.ui.container.Menu
        ContextMenu_NodeOperation      matlab.ui.container.ContextMenu
        Menu_ActivateNode              matlab.ui.container.Menu
        Menu_DeleteNode                matlab.ui.container.Menu
        ContextMenu_BranchOperation    matlab.ui.container.ContextMenu
        Menu_MergeRegNodes             matlab.ui.container.Menu
    end

    properties (Constant)
        VER = "5.0.0"
        RELEASE_CODE = "Wittgenstein"
        RELEASE_STATUS = "debug"
        PROFILE_FOLDER = ['Reg3D', filesep]
    end

    properties(Constant, Hidden)
        SUBAPP_SNAME = ["BC", "CC", "CS", "EV", "FS", "ME", "RM", "RS", ...
            "SB", "SE"]
        SUBAPP_SNAME_DATA_RELATED = ["BC", "CC", "CS", "EV", "FS", "ME", ...
            "RS", "SB", "SE"]
        SUBAPP_SNAME_MESSAGE_RELATED = ["CS", "SE"]
        SUBAPP_SNAME_TIPS_RELATED = ["CS", "RM", "RS"]
        
        INIT_TIME_CONST = [0.1, 0.8, 0.9, 1]  % experience value

        DAEMON_TIMER_PERIOD = 5             % second, no more than 60
        PLAYER_TIMER_PERIOD = 1             % second
        PROGRESSBAR_TIMER_PERIOD = 0.01     % second

        LAYOUT_WIDTH_INIT = 740
        LAYOUT_HEIGHT_INIT = 740
        RIGHT_SIDEBAR_WIDTH = 260
        SLIDER_TOP_CONST = 70
        SLIDER_BOT_CONST = 90
        SNAPSHOT_HEIGHT = 150
    end

    properties (Access = public)
        % dynamic components
        Menu_MergeSource            matlab.ui.container.Menu
        Menu_MergeRB                matlab.ui.container.Menu
        Menu_MergeGR                matlab.ui.container.Menu
        Menu_Red                    matlab.ui.container.Menu
        Menu_Green                  matlab.ui.container.Menu
        Menu_Red_Green              matlab.ui.container.Menu
        Menu_CellManager            matlab.ui.container.Menu
        Menu_AddCell                matlab.ui.container.Menu
        Menu_RefreshLabel           matlab.ui.container.Menu
        Menu_AnomalyDetection       matlab.ui.container.Menu
        UIAxes_Slice                matlab.ui.control.UIAxes
        UIViewer_Vol                images.ui.graphics3d.Viewer3D
        StorageManager              storage_manager
    end

    properties (GetAccess = public, Dependent)

    end

    % nonpersistent data, reset after loading
    properties (Access = private)
        % This variables contains the highest controller in app
        % - Controller: 1-by-1 struct with following variables
        %   |- AppDaemonTimer: 1-by-1 timer for app management
        %   |- Logger: 1-by-1 InformationCollector object
        %   |- ProjectManager: 1-by-1 regproj object
        %   |- ProgressTimer: 1-by-1 timer for progress bar running indeterminately
        %   |- RegisterKernel: 1-by-1 RegisterController object
        %   |- ServerFlag: 1-by-1 logical, the flag for estimating if running on server
        Controller  (1,1)   struct


        % These variables could change by user modified only in app, which
        % stored in PIVs, no need to store these variables in project object and file
        %
        % - PIVs: 1-by-1 struct with project independent variables
        %   |- ApplicationOptions: app basic options
        %   |- UpdateOptions: app update options
        %   |- RegionConfig: app region configuration
        %   |- SliceScrollCursorLimit: the y position limit for scrolling slices
        %   |- PreviousProgressBarValue: the old progress value
        %   |- MoviePlayingFlag: flag for movie play right now
        %   |- ViewerLock: locker for imaging view ability
        %   |- PreviousRecycleStatus: record the recycle state
        %   |- AppMessage: record system messages history, [cmd, sfx, time]
        %   |- ProjectProfile: the project profile with some settings history
        %   |- PluginMenus: the Menu objects to invoke plugin running
        %   |- PluginConfigs: the struct of plugin configurations
        %   |- ChannelAligningLock: the flag for channel align status
        %   |- ImageImportConfig: image importer options
        %   |- RegisterOptionsLock: table of register options status
        %   |- SegmentAreaHandle: cell with each cropped segment area
        %   |- NewProjectFlag: indicate create a new project
        %   |- ImageImportPostponeFlag: indicate if import image later
        %   |- PreviousSavedPath: the previous saved path (image and time file)
        PIVs        (1,1)   struct


        % These variables will be auto reset if:
        % [1.1] new project is created
        % [1.2] current project is closed
        % [2] exist project is opened
        % which stored in PDVs, they need to follow the project status
        % - PDVs: 1-by-1 struct with project dependent variables
        %   |- ...
        PDVs        (1,1)   struct


        % sub app handles are stored in SUBAPPs, they need to follow the project status
        % - SUBAPPs: 1-by-1 struct with sub app handles
        SUBAPPs     (1,1)   struct

    end

   %=================== INTERFACE FOR SUBAPP ============================
   methods(Access = ?ResourceManager)
       function r = GetEngineStatus(app)
           r = app.Controller.RegisterKernel.State;
       end

       function r = GetEngineInfo(app)
           r = app.Controller.RegisterKernel.SysInfo;
       end

       function SetNWorkersProtected(app, x)
           arguments
               app
               x   (1,2)   double  {mustBeNonnegative, mustBeInteger}
           end
           app.Controller.RegisterKernel.NWorkersProtected = x;
       end

       function r = GetProfile(app)
           r = app.PIVs.ApplicationOptions;
       end

       function SetProfile(app, x)
           arguments
               app
               x    (1,1)   struct
           end

           app.PIVs.ApplicationOptions = x;

           % apply appearance options
           UpdateAppProfiles(app);
       end

       function r = GetProfileFullPath(app)
           pfpath = [userpath, filesep, app.PROFILE_FOLDER];
           r = fullfile(pfpath, constdef.APP_PROFILE_FILE_NAME);
       end
   end

   methods (Access = ?NewProjectConfigurator)
       function r = GetProjectProfile(app)
           r = app.PIVs.ProjectProfile;
       end
   end

   methods(Access = {?ResourceManager, ?NewProjectConfigurator})
       function r = GetProjectConfig(app)
           r = app.PDVs.ProjectConfig;
       end

       function SetProjectConfig(app, r, proj_flag, import_delay)
           arguments
               app
               r            (1,1)   struct
               proj_flag    (1,:)   logical = []
               import_delay (1,:)   logical = []
           end

           if ~isempty(proj_flag) && isscalar(proj_flag)
               app.PIVs.NewProjectFlag = proj_flag;
           end
           if ~isempty(import_delay) && isscalar(import_delay)
               app.PIVs.ImageImportPostponeFlag = import_delay;
           end

           app.PDVs.ProjectConfig = r;
       end

       function r = GetProjectProfileFullPath(app)
           pfpath = [userpath, filesep, app.PROFILE_FOLDER];
           r = fullfile(pfpath, constdef.PROJECT_PROFILE_FILE_NAME);
       end
   end

   methods(Access = ?ImageImporter)
       function r = GetImporterPreset(app)
           r = app.PIVs.ImageImportProfile;
       end

       function SetImporterPreset(app, r)
           arguments
               app
               r    (1,1)   struct
           end

           app.PIVs.ImageImportProfile = r;
       end
   end
   
   methods(Access = {?BCAdjuster, ?BCAdjuster_old})
       function SetBCG(app, params)
            app.PDVs.GraphicsOptions = params;

            % refresh viewer for real time applying
            RefreshViewer(app);
        end

        function params = GetBCG(app)
            params = app.PDVs.GraphicsOptions;
        end

        function img = GetCurrentImg(app)
            switch app.PDVs.DisplayViewMode
                case "2D"
                    img = GetSliceImgAt(app);
                case "3D"
                    img = GetVolumeImgAt(app);
                otherwise
            end
        end
   end

   methods(Access = {?OCRegSetter, ?TCRegSetter})
       function SetRegOptions(app, regopt_, fixdef_, locked_)
            app.Controller.ProjectManager.Project.opt_reg = regopt_;

            if fixdef_.Channel ~= app.Controller.ProjectManager.Project.fixdef.Channel
                [~, cidx] = ismember(fixdef_.Channel, app.Controller.ProjectManager.Project.img_cur.MetaData.cOrder);
                cmin = app.Controller.ProjectManager.Project.img_cur.EMin(cidx); cmax = app.Controller.ProjectManager.Project.img_cur.EMax(cidx);
                switch app.PDVs.DisplayViewMode
                    case "2D"
                        clim(app.UIAxes_Slice, [cmin, cmax]);
                    case "3D"
                        clim(app.UIViewer_Vol, [cmin, cmax]);
                end
            end

            app.Controller.ProjectManager.Project.fixdef = fixdef_;
            app.PIVs.RegisterOptionsLock = locked_;
            app.Controller.ProjectManager.Project.refvol = regtmpl(app.Controller.ProjectManager.Project.img_cur, app.Controller.ProjectManager.Project.fixdef);
        end

        function [regopt_, fixdef_, locked_] = GetRegOptions(app)
            regopt_ = app.Controller.ProjectManager.Project.opt_reg;
            fixdef_ = app.Controller.ProjectManager.Project.fixdef;
            locked_ = app.PIVs.RegisterOptionsLock;
        end

        function AppendPreprocessSource(app)
            if ~ismember(app.PIVs.RegionConfig.chmap.PREPROCESSING, app.DropDown_Source.Items)
                app.DropDown_Source.ItemsData = ...
                    [app.DropDown_Source.ItemsData, {char(constdef.SOURCE_MERGE_PREPROCESS)}];
                app.DropDown_Source.Items = ...
                    [app.DropDown_Source.Items, app.PIVs.RegionConfig.chmap.PREPROCESSING];

                % force to select "MASK"
                app.PDVs.DisplaySource = constdef.SOURCE_MERGE_PREPROCESS;
                app.DropDown_Source.Value = constdef.SOURCE_MERGE_PREPROCESS;
                UpdateMergeSource(app);
            end

            RefreshViewer(app);
        end

        function RemovePreprocessSource(app)
            if ismember(app.PIVs.RegionConfig.chmap.PREPROCESSING, app.DropDown_Source.Items)
                app.DropDown_Source.Items(app.DropDown_Source.Items == ...
                    app.PIVs.RegionConfig.chmap.PREPROCESSING) = [];
                tf = cellfun(@(x)(x==constdef.SOURCE_MERGE_PREPROCESS), app.DropDown_Source.ItemsData, ...
                    "UniformOutput",true);
                app.DropDown_Source.ItemsData(tf) = [];

                % force to select "NORMAL"
                app.PDVs.DisplaySource = constdef.SOURCE_MERGE_NORMAL;
                app.DropDown_Source.Value = constdef.SOURCE_MERGE_NORMAL;
                UpdateMergeSource(app);
            end

            RefreshViewer(app);
        end
   end

   methods(Access = ?CellSegmentor)
       function SetSegOptions(app, segopt_)
           arguments
               app
               segopt_  (1,1)   segopt
           end

           app.Controller.ProjectManager.Project.opt_seg = segopt_;
       end

       function r = GetSegOptions(app)
           r = app.Controller.ProjectManager.Project.opt_seg;
       end
       
       function r = GetVolOptions(app)
           r = struct("width",  app.Controller.ProjectManager.Project.img_cur.MetaData.width, ...
                      "height", app.Controller.ProjectManager.Project.img_cur.MetaData.height, ...
                      "slices", app.Controller.ProjectManager.Project.img_cur.MetaData.slices, ...
                      "xRes",   app.Controller.ProjectManager.Project.img_cur.MetaData.xRes, ...
                      "yRes",   app.Controller.ProjectManager.Project.img_cur.MetaData.yRes, ...
                      "zRes",   app.Controller.ProjectManager.Project.img_cur.MetaData.zRes);

           r = struct2table(r);
       end

       function z = GetCurrentSliceIndex(app)
           z = app.Controller.ProjectManager.Project.sidx_cur;
       end

       function SetBoundingBoxes(app, n)
           arguments
               app
               n    (1,1)   double  {mustBePositive, mustBeInteger} = 1
           end

           if ~isempty(app.Controller.ProjectManager.Project.opt_seg.bdbox), app.Controller.ProjectManager.Project.opt_seg.bdbox = []; end

           % draw rectangle segmentation region
           for k = 1:n
               app.PIVs.SegmentAreaHandle{k} = drawrectangle(app.UIAxes_Slice, ...
                   "Label", sprintf("%s:%d", app.PIVs.RegionConfig.chmap.SEGMENTATION_AREA, k), ...
                   "Color","g", "FaceAlpha",0.2, "Deletable",true, ...
                   "UserData", k);

               addlistener(app.PIVs.SegmentAreaHandle{k}, "ROIMoved", @app.ModifySegmentArea);
               bdbox = [ceil(app.PIVs.SegmentAreaHandle{k}.Vertices(1,:)+0.5), 1, ...
                   ceil(app.PIVs.SegmentAreaHandle{k}.Vertices(3,1) ...
                        - app.PIVs.SegmentAreaHandle{k}.Vertices(2,1)-1), ...
                   ceil(app.PIVs.SegmentAreaHandle{k}.Vertices(2,2) ...
                        - app.PIVs.SegmentAreaHandle{k}.Vertices(1,2)-1), ...
                   app.Controller.ProjectManager.Project.img_cur.MetaData.slices-1];
               app.Controller.ProjectManager.Project.opt_seg.bdbox = [app.Controller.ProjectManager.Project.opt_seg.bdbox; bdbox];
           end
       end

       function RemoveBoundingBoxes(app)
           % free and set as empty
           if ~isempty(app.PIVs.SegmentAreaHandle)
               cellfun(@delete, app.PIVs.SegmentAreaHandle);
           end

           app.PIVs.SegmentAreaHandle = cell(1, 0);

           app.Controller.ProjectManager.Project.opt_seg.bdbox = [];
       end

       function SetBoundingBoxesState(app, state)
           arguments
               app
               state    string  {mustBeMember(state, ["on", "off"])} = "on"
           end

           switch state
               case "on"
                   for k = 1:numel(app.PIVs.SegmentAreaHandle)
                       if isvalid(app.PIVs.SegmentAreaHandle{k})
                           app.PIVs.SegmentAreaHandle{k}.Label = ...
                               sprintf("%s:%d", app.PIVs.RegionConfig.chmap.SEGMENTATION_AREA, k);
                           app.PIVs.SegmentAreaHandle{k}.Color = "g";
                       end
                   end
               case "off"
                   for k = 1:numel(app.PIVs.SegmentAreaHandle)
                       if isvalid(app.PIVs.SegmentAreaHandle{k})
                           app.PIVs.SegmentAreaHandle{k}.Label = ...
                               sprintf("%s:%d", app.PIVs.RegionConfig.chmap.SEGMENTATION_AREA_LOCK, k);
                           app.PIVs.SegmentAreaHandle{k}.Color = "y";
                       end
                   end
               otherwise
           end
       end

       function r = GetCurrentDisplayPosition(app)
           if ~isempty(app.PDVs.UROIHandle) && isvalid(app.PDVs.UROIHandle)
               rect = ROI2Rectangle(app.PDVs.UROIHandle);
               %        X  Y            W                   H
               r = [rect(1,1:2), rect(2,1)-rect(1,1), rect(2,2)-rect(1,2)];
           else
               r = zeros(0, 4);
           end
       end

       function ResetCurrentDisplayPosition(app)
           if ~isempty(app.PDVs.UROIHandle) && isvalid(app.PDVs.UROIHandle)
               delete(app.PDVs.UROIHandle);
           end

           app.PDVs.UROIHandle = [];
       end

       function SetMaskVol(app, mask)
           app.PDVs.Mask = regmask.recover(mask, size(app.Controller.ProjectManager.Project.refvol.RefVol));
           app.PDVs.MaskedFlag = true;
           app.Menu_SignalExtractor.Enable = "on";
       end

       function AddCellManagerMenu(app)
           AppendCellManagerMenu(app);
       end

       function DeleteCellManagerMenu(app)
           RemoveCellManagerMenu(app);
       end

       function SetSigEstimatorState(app, state)
           arguments
               app
               state    string  {mustBeMember(state, ["on", "off"])} = "on"
           end

           switch state
               case "on"
                   % enable the estimator menu
                   app.Menu_SignalExtractor.Enable = "on";
               case "off"
                   % close estimator
                   CloseSubApp(app, "SE");

                   % disable the estimator menu
                   app.Menu_SignalExtractor.Enable = "off";
               otherwise
           end
       end

       function RestoreStatusBeforeSegmentation(app)
           app.Controller.ProjectManager.OperationManager.ActivatePreviousNode();

           % restore system status
           nd = app.Controller.ProjectManager.OperationManager.ActiveNodeData;

           app.RestoreBasicSystemBy(nd);
       end

       function AppendSegmentHistory(app, ncbptr)
           arguments
               app  
               ncbptr   (1,1)   NuclearCtrlBot 
           end

           %% Adjust if operation is valid (space limitation)
           tf = app.Controller.ProjectManager.OperationManager.isaddable(constdef.OP_SEGMENT);

           if tf == false   % always pass
               % ERROR: display at anytime
               uialert(app.Reg3DV500WittgensteinUIFigure, ...
                   app.PIVs.RegionConfig.chmap.STORAGE_INSUFFICIENT, ...
                   app.PIVs.RegionConfig.chmap.ERROR, "Icon","error","Modal",true);
               return;
           end

           %%
           % !!! add history node
           [args, others] = app.TakeSystemBasicSnap();
           app.Controller.ProjectManager.OperationManager.AddNode([], constdef.OP_SEGMENT, args, ...
               "Data",app.Controller.ProjectManager.Project.img_cur, "Segmentor",ncbptr, "Others",others);
           app.Controller.ProjectManager.Project.img_cur = app.Controller.ProjectManager.OperationManager.CurrentImagePtr;
       end
   end

   methods(Access = {?OCRegSetter, ?TCRegSetter, ?CellSegmentor})
       function refvol = GetRefVol(app)
           refvol = regtmpl(app.Controller.ProjectManager.Project.img_cur, app.Controller.ProjectManager.Project.fixdef);
       end
   end

   methods(Access = {?scalebar, ?ScaleBar})
       function SetScaleBar(app, scale, bar, font)
            % update current image resolution
            if ~isempty(app.Controller.ProjectManager.Project.img_cur)
                app.Controller.ProjectManager.Project.img_cur.MetaData.xRes = scale.xr;
                app.Controller.ProjectManager.Project.img_cur.MetaData.yRes = scale.yr;
                app.Controller.ProjectManager.Project.img_cur.MetaData.zRes = scale.zr;
            end
            
            % set scale bar information
            app.PDVs.ScaleBar.Scale = scale;
            app.PDVs.ScaleBar.Bar = bar;
            app.PDVs.ScaleBar.Font = font;

            % refresh scalebar for real time applying
            app.PDVs.ScaleBar.clear();
            app.PDVs.ScaleBar.draw();
        end

        function sbar = GetScaleBar(app)
            sbar = app.PDVs.ScaleBar;
        end
   end

   methods(Access = ?SignalEstimator)
       function SetSigOptions(app, sigopt_)
           arguments
               app
               sigopt_  (1,1)   sigopt
           end

           app.Controller.ProjectManager.Project.opt_sig = sigopt_;
       end

       function r = GetSigOptions(app)
           r = app.Controller.ProjectManager.Project.opt_sig;
       end

       function r = GetSignalChannel(app)
           r = app.Controller.ProjectManager.Project.opt_reg.Options.FC;
       end

       function r = GetCurrentSource(app)
           r = app.Controller.ProjectManager.Project.img_cur;
       end

       function RenameROI(app, ridx, rname)
           arguments
               app
               ridx (1,1)   double {mustBePositive, mustBeInteger}
               rname(1,1)   string
           end

           app.PDVs.Mask.MaskLabel(ridx) = rname;
       end
   end

   methods(Access = {?SignalEstimator, ?CellSegmentor})
       function r = GetMask(app)
           if app.PDVs.MaskedFlag == true
               r = app.PDVs.Mask;
           else
               r = [];
           end
       end

       function HighlightROI(app, r)
           % This function highlight the ROI with r marked
           arguments
               app
               r    (1,:)   double {mustBePositive, mustBeInteger}
           end

           % call cell segmentor subfunction
           if ~isempty(app.SUBAPPs.CS) && isvalid(app.SUBAPPs.CS)
               app.SUBAPPs.CS.UpdateHighlights(r);
               app.SUBAPPs.CS.UpdateMultiplyROIs(app.Controller.ProjectManager.Project.sidx_cur);
           end
       end
   end

   methods(Access = ?Evaluator)
       function tform = GetTransformation(app)
           tform = app.Controller.ProjectManager.Project.img_cur.Transformation;
       end

       function [mov_raw, mov_align] = GetMoviePair(app)
           % TODO: decrease memory use
           rpt = app.Controller.ProjectManager.OperationManager.CurrentRSPoint;
           rpt_pre = app.Controller.ProjectManager.OperationManager.GetpreviousNodeBefore();

           if ~isempty(rpt)
               switch rpt.Operator
                   case constdef.OP_REGISTER
                       mov_align = squeeze(rpt.getData().Movie(:,:,app.Controller.ProjectManager.Project.cidx_cur,:,:));
                       mov_raw = squeeze(rpt_pre.getData().Movie(:,:,app.Controller.ProjectManager.Project.cidx_cur,:,:));
                   otherwise
                       mov_raw = [];
                       mov_align = [];
               end
           end
       end

       function r = GetTemplate(app)
           r = app.Controller.ProjectManager.Project.refvol.RefVol;
       end
   end

   methods(Access = {?Evaluator, ?BCAdjuster, ?BCAdjuster_old})
       function r = GetCurrentChannel(app)
           r = app.Controller.ProjectManager.Project.c_order(app.Controller.ProjectManager.Project.cidx_cur);
       end
   end

   methods(Access = ?FrameSelecter)
       function r = GetCurrentFrameIndex(app)
           r = app.Controller.ProjectManager.Project.fidx_cur;
       end

       function SetFrames(app, indices)
           app.Controller.ProjectManager.Project.opt_crop.f = indices;
       end
   end

   methods(Access = {?MovieExporter, ?SignalEstimator})
       function r = GetFileName(app)
           r = string(app.Controller.ProjectManager.Project.img_fname);
       end
   end

   methods(Access = ?ChannelCorrector)
       function SetChannelAlignmentView(app, shift)
           UpdateCurrentSlice(app, "XY", shift);
           DrawCurrentSlice(app);
       end

       function SetChannelAlignment(app, shift)
           if any(shift ~= 0)
               selection = uiconfirm(app.Reg3DV500WittgensteinUIFigure,...
                   app.PIVs.RegionConfig.chmap.IRREVERSIBLE_CA,...
                   app.PIVs.RegionConfig.chmap.WARNING, "Icon","warning");
               if selection ~= "OK"
                   return;
               else
                   PanelWaiting(app);

                   % apply channel shift on data
                   RA = imref3d([app.Controller.ProjectManager.Project.img_cur.MetaData.height, ...
                       app.Controller.ProjectManager.Project.img_cur.MetaData.width, ...
                       app.Controller.ProjectManager.Project.img_cur.MetaData.slices], ...
                       app.Controller.ProjectManager.Project.img_cur.MetaData.xRes, ...
                       app.Controller.ProjectManager.Project.img_cur.MetaData.yRes, ...
                       app.Controller.ProjectManager.Project.img_cur.MetaData.zRes);
                   rot = [cosd(shift(3)), sind(shift(3)), 0; ...
                       -sind(shift(3)), cosd(shift(3)), 0; ...
                       0, 0, 1];
                   tform = rigidtform3d(rot, [shift(1:2), 0]);
                   [~, cidx] = ismember(app.Controller.ProjectManager.Project.opt_reg.Options.FC, app.Controller.ProjectManager.Project.img_cur.MetaData.cOrder);
                   fill_val = prctile(app.Controller.ProjectManager.Project.img_cur.Movie(:,:,cidx,:,1), 10, "all");
                   t_span = sprintf("1:%d",app.Controller.ProjectManager.Project.img_cur.MetaData.frames);

                   bar = uiprogressdlg(app.Reg3DV500WittgensteinUIFigure,...
                       "Title",app.PIVs.RegionConfig.chmap.INFORMATION, ...
                       "Message",app.PIVs.RegionConfig.chmap.CHANNEL_ALIGNING,...
                       "Indeterminate","on", "Icon","info");
                   drawnow;

                   parpool("Threads");

                   % align functional channel to structured channel
                   tmpmov = grv(app.Controller.ProjectManager.Project.img_cur, ["none", t_span], ...
                       app.Controller.ProjectManager.Project.opt_reg.Options.FC);

                   parfor t = 1:app.Controller.ProjectManager.Project.img_cur.MetaData.frames
                       tmp_data = tmpmov(:,:,:,t);
                       tmpmov(:,:,:,t) =  imwarp(tmp_data, RA, tform,...
                           "linear","FillValues",fill_val, "OutputView",RA);
                   end

                   srv(app.Controller.ProjectManager.Project.img_cur, tmpmov, ["none", t_span], ...
                       app.Controller.ProjectManager.Project.opt_reg.Options.FC);

                   delete(gcp("nocreate"));

                   close(bar);

                   % Synchronize z projection
                   SourceZProjection(app, [], app.PDVs.ZProjectionMode);

                   PanelRecovered(app);

                   % RefreshViewer for viewing recovery
                   RefreshViewer(app);
               end
           end
       end
   end

   methods(Access = ?MovieExporter)
       function r = GetMovieConfig(app)
           r = app.Controller.ProjectManager.Project.opt_movout;
       end
       function SetMovieConfig(app, x)
           arguments
               app
               x    (1,:)   struct
           end

           app.Controller.ProjectManager.Project.opt_movout = x;
       end
   end

   methods(Access = ?TaskManager)
       function UpdateRegParams(app, task_)
           arguments
               app
               task_ (1,1)  task
           end

           % update regopt by information in task
           app.Controller.ProjectManager.Project.opt_reg = task_.RegOptions;

           switch app.Controller.ProjectManager.Project.opt_reg.Algorithm
               case "LTREG"
                   if app.Controller.ProjectManager.Project.opt_reg.Options.RegChain.isValid
                       % update fixdef
                       app.Controller.ProjectManager.Project.fixdef = app.Controller.ProjectManager.Project.opt_reg.Options.RegChain.Template.FixDef;
                   end
               otherwise
           end

           if ~isempty(app.SUBAPPs.RS) && isvalid(app.SUBAPPs.RS)
               app.SUBAPPs.RS.Refresh(app);
           end
       end
   end

   methods (Access = public)
       function fig = GetUIFigure(app)
           fig = app.Reg3DV500WittgensteinUIFigure;
       end

       function info = GetImgInfo(app)
           info = app.Controller.ProjectManager.Project.img_cur.MetaData;
       end

       function [lang, mapping] = GetLangsInfo(app)
           lang = app.PIVs.RegionConfig.lang;
           mapping = app.PIVs.RegionConfig.chmap;
       end

       function pos = GetAppPosition(app)
           pos = app.Reg3DV500WittgensteinUIFigure.Position;
       end

       function stl = GetAppStyle(app)
           stl = app.PIVs.ApplicationOptions.Style;
       end

       function tl = GetAppTipsLevel(app)
           tl = app.PIVs.ApplicationOptions.TooltipsLevel;
       end

       function ml = GetAppMessageLevel(app)
           ml = app.PIVs.ApplicationOptions.MessageLevel;
       end

       function PushInfo(app, subapp, obj, exception)
           arguments
               app
               subapp      (1,1)   string
               obj         (1,1)   string
               exception   (1,1)   string  = "None"
           end

           app.Controller.Logger.Push(subapp, obj, exception);
       end

       function SendMessage(app, id, timeStamp)
           arguments
               app
               id          (1,2)   string
               timeStamp   (1,1)   logical = true
           end

           GenSysMsg(app, id, true, timeStamp);
       end

       function SetProgressBar(app, x, iterinf)
           arguments
               app
               x       (1,1)   double  {mustBeInRange(x, 0, 1)} = 0
               iterinf (1,1)   logical = false
           end

           if ~iterinf
               stop(app.Controller.ProgressTimer);
               app.PIVs.PreviousProgressBarValue = x;
               len = app.Reg3DV500WittgensteinUIFigure.Position(3) - (60*2+10*4+7*2);
               app.GridLayout_ProgressBar.ColumnWidth = {0, round(x*len)};
               drawnow
           else
               if x == 0
                   stop(app.Controller.ProgressTimer);
                   start(app.Controller.ProgressTimer);
               end
           end
       end
   end
    
    %=========================== APP METHODS =============================
    methods (Access = private)
        %====================== INIT COMPONENTS ==========================
        
        function InitAppProperties(app)
            % Init project independent variables
            InitProjectIndependentVars(app);

            % Init project depedent variables
            ResetProjectDependentVars(app);

            % Install Additional Components
            InstallAdditionalComponents(app);

            % Activate app controller
            ActivateAppController(app);
        end

        function ResetProjectDependentVars(app)
            %% [Display Control] (option)
            % - GraphicsOptions: 1-by-1 struct with field {r, b, c, ga}, the viewer options
            app.PDVs.GraphicsOptions = struct("r",[0,65535], "b",0, "c",1, "ga",1);

            % - ZProjectionMode: 1-by-1 string, the flag for current viewing mode, ["none", "max","min","mean","median"]
            app.PDVs.ZProjectionMode = "none";

            % - DisplayLoopMode: 1-by-1 string, the viewing loop mode, ["one-step", "loop"]
            app.PDVs.DisplayLoopMode = "one-step";

            % - DisplayViewMode: 1-by-1 string, the viewer display mode, ["2D", "3D"]
            app.PDVs.DisplayViewMode = "2D";

            % - DisplaySource: 1-by-1 string, the display source option, ["NORMAL","PRE_PROCESS","FUSED",""]
            app.PDVs.DisplaySource = "";

            if isempty(fieldnames(app.Controller)) ...
                    || isempty(app.Controller.ProjectManager.Project)
                % create new project or current project is closed
                %% [Display Control] (controller)
                % - MoviePlayerTimer: 1-by-1 timer, the movie play controller
                app.PDVs.MoviePlayerTimer = timer("Period",app.PLAYER_TIMER_PERIOD,...
                    "ExecutionMode","fixedSpacing","TasksToExecute",inf,...
                    "Name","Timer_PlayControl","TimerFcn",@app.Play, ...
                    "StopFcn",@app.Stop, "BusyMode","queue");


                %% [Display Control] (data)
                % - CurrentImageSlice: m-by-n-by-3 uint8 array, the current slice image(transformed) for viewing
                app.PDVs.CurrentImageSlice = [];

                % - CurrentImageVolume: m-by-n-by-p-by-3 uint8 array, the current volume image(transformed) for viewing
                app.PDVs.CurrentImageVolume = [];  % black screen

                % - Mask: m-by-n-by-p regmask object, with mROI mask
                app.PDVs.Mask = [];

                % - MaskedFlag: 1-by-1 logical, indicate mask already exist
                app.PDVs.MaskedFlag = false;

                %% [Display Control] (handle)
                % - ImageHandle: 1-by-1 image object
                app.PDVs.ImageHandle = [];

                % - VolumeHandle: 1-by-1 Volume object
                app.PDVs.VolumeHandle = [];

                % - UROIHandle: 1-by-1 images.roi object
                app.PDVs.UROIHandle = [];

                % - TimeTextObject: 1-by-1 struct, the time text, with {handle, text, ismoving}
                app.PDVs.TimeTextObject = struct("handle",[], "text",[], "ismoving",[]);

                % - MetaTextObject: 1-by-1 struct, the meta text info struct, with {handle, text, ismoving}
                app.PDVs.MetaTextObject = struct("handle",[], "text",[], "ismoving",[]);

                % - ScaleBar: 1-by-1 scalebar object
                app.PDVs.ScaleBar = [];
            else
                % an exist project is loaded
                %% [Display Control] (controller)
                % - MoviePlayerTimer: 1-by-1 timer, the movie play controller
                app.PDVs.MoviePlayerTimer = timer("Period",app.PLAYER_TIMER_PERIOD,...
                    "ExecutionMode","fixedSpacing","TasksToExecute",inf,...
                    "Name","Timer_PlayControl","TimerFcn",@app.Play, ...
                    "StopFcn",@app.Stop, "BusyMode","queue");
                app.PDVs.MoviePlayerTimer.Period = ...
                    round(1/(app.Controller.ProjectManager.Project.fps.base*app.Controller.ProjectManager.Project.fps.fold), 3);

                %% [Display Control] (data)
                % - CurrentImageSlice: m-by-n-by-3 uint8 array, the current slice image(transformed) for viewing
                width = app.PIVs.Project.img_cur.MetaData.width;
                height = app.PIVs.Project.img_cur.MetaData.height;
                app.PDVs.CurrentImageSlice = zeros(height, width, 3, "uint8");  % black screen

                % - CurrentImageVolume: m-by-n-by-p-by-3 uint8 array, the current volume image(transformed) for viewing
                slices = app.PIVs.Project.img_cur.MetaData.slices;
                app.PDVs.CurrentImageVolume = zeros(height, width, slices, 3, "uint8");  % black screen

                % - Mask: m-by-n-by-p regmask object, with mROI mask
                app.PDVs.Mask = [];

                %% [Display Control] (handle)
                % - ImageHandle: 1-by-1 image object
                app.PDVs.ImageHandle = image(app.UIAxes_Slice,...
                    [0.5 width-0.5], [0.5 height-0.5], [], ...
                    "Interpolation","bilinear");

                % - VolumeHandle: 1-by-1 Volume object
                % rs = min(app.Controller.ProjectManager.Project.img_cur.MetaData.xRes, app.Controller.ProjectManager.Project.img_cur.MetaData.yRes);
                % app.PDVs.VolumeHandle = volshow([], "Parent", app.UIViewer_Vol, ...
                %     "RenderingStyle", "MaximumIntensityProjection", ...
                %     "Transformation", affinetform3d(diag([rs,rs,rs,1])));
                app.PDVs.VolumeHandle = [];

                % - UROIHandle: 1-by-1 images.roi object
                app.PDVs.UROIHandle = [];

                % - TimeTextObject: 1-by-1 struct, the time text, with {handle, text, ismoving}
                app.PDVs.TimeTextObject = struct("handle",[], "text",[], "ismoving",[]);

                % - MetaTextObject: 1-by-1 struct, the meta text info struct, with {handle, text, ismoving}
                app.PDVs.MetaTextObject = struct("handle",[], "text",[], "ismoving",[]);

                % - ScaleBar: 1-by-1 scalebar object
                app.PDVs.ScaleBar = [];
            end

            %% [Workflow]
            % - ProjectConfig: 1-by-1 struct with project properties
            [~, app.PDVs.ProjectConfig] = GetInitProjectOptions(app);

            %% [SubApps]
            % - SUBAPPs: 1-by-1 struct with sub app handles
            %   |- 
            app.SUBAPPs = struct("BC", [], ...  % 1-by-1 handle, the brightness and contrast setting handle
                                 "CC", [], ...  % 1-by-1 handle, the channel alignment handle
                                 "CS", [], ...  % 1-by-1 handle, the cell segmentor handle
                                 "EV", [], ...  % 1-by-1 handle, the evaluation handle
                                 "FS", [], ...  % 1-by-1 handle, the frames selector handle
                                 "ME", [], ...  % 1-by-1 handle, the movie exporter handle
                                 "RM", [], ...  % 1-by-1 handle, the resource manager handle
                                 "RS", [], ...  % 1-by-1 handle, the registration setting handle
                                 "SB", [], ...  % 1-by-1 handle, the scale bar setting handle
                                 "SE", [], ...  % 1-by-1 handle, the signal estimator handle
                                 "CP", {{}});   % 1-by-1 handle, the custom plugin sub app handles cell

        end

        function InitProjectIndependentVars(app)
            %% Initialize Normal Variables
            % - ApplicationOptions: 1-by-1 struct with app basic options
            %   |- Language: 
            %   |- ...
            % see also: constdef
            app.PIVs.ApplicationOptions = GetInitialApplicationProfile(app);

            % - UpdateOptions: 1-by-1 struct, the options for app update
            app.PIVs.UpdateOptions = struct("is_update",false, "repository","","auto_clean",false);

            % - RegionConfig: 1-by-1 struct, the region setting, {plat, lang, chmap}
            %   |- plat:
            %   |- lang:
            %   |- chmap:
            app.PIVs.RegionConfig = Register.GetRegionConfig();

            % - SliceScrollCursorLimit: 1-by-2 double, the y position limit for scrolling slices
            app.PIVs.SliceScrollCursorLimit = double.empty(0,2);

            % - PreviousProgressBarValue: 1-by-1 double, the old progress value
            app.PIVs.PreviousProgressBarValue = 0;

            % - MoviePlayingFlag: 1-by-1 logical, flag for movie play right now
            app.PIVs.MoviePlayingFlag = false;

            % - ViewerLock: 1-by-1 logical, locker for imaging view ability
            app.PIVs.ViewerLock = true;

            % - PreviousRecycleStatus: 1-by-1 string, record the recycle
            % status when app initialize
            app.PIVs.PreviousRecycleStatus = '';

            % - AppMessage: 1-by-3 string array, record system messages history, [cmd, sfx, time]
            app.PIVs.AppMessage = strings(0, 3);

            % - ProjectProfile: 1-by-1 struct, with {ProjectFolders, SourceFolders}
            [app.PIVs.ProjectProfile, ~] = GetInitProjectOptions(app);

            % - PluginMenus: n-by-1 cell with menu objects
            app.PIVs.PluginMenus = cell(0, 1);

            % - PluginConfigs: n-by-1 struct array with plugin configurations
            app.PIVs.PluginConfigs = struct([]);

            % - ChannelAligningLock: 1-by-1 logical, indicate if channel is aligning
            app.PIVs.ChannelAligningLock = false;

            % - ImageImportProfile: 1-by-1 struct, image importer options
            app.PIVs.ImageImportProfile = GetInitImporterProfile(app);

            % - RegisterOptionsLock: 1-by-p table, flag for registration options setting done
            app.PIVs.RegisterOptionsLock = table('Size', [1,4], ...
                'VariableTypes', repmat({'logical'},1,4), ...
                'VariableNames', {'Global','Local','MGlobal','LTGlobal'});

            % - SegmentAreaHandle: 1-by-s cell, each item is images.roi object, cropped segment area
            app.PIVs.SegmentAreaHandle = cell(1, 0);

            % - AxesToolBar: 1-by-1 Axes ToolBar object, control the 2D view
            app.PIVs.AxesToolBar = [];

            % - NewProjectFlag: indicate create a new project
            app.PIVs.NewProjectFlag = false;

            % - ImageImportPostponeFlag: indicate if import image later
            app.PIVs.ImageImportPostponeFlag = false;

            % - PreviousSavedPath: % 1-by-1 string, the previous saved file path
            app.PIVs.PreviousSavedPath = "";
        end

        function ActivateAppController(app)
            %% Initialize Immutable Variables
            % AppDaemonTimer: 1-by-1 timer for app management
            app.Controller.AppDaemonTimer = timer("Period",app.DAEMON_TIMER_PERIOD, ...
                "ExecutionMode","fixedSpacing", "TasksToExecute",inf, ...
                "Name","Timer_RegDaemon", "TimerFcn",@app.RegDaemonTask, ...
                "StartDelay",3, "BusyMode","queue");

            % Logger: 1-by-1 InformationCollector object, record system log
            switch app.PIVs.ApplicationOptions.InformationCollection
                case "ON"
                    app.Controller.Logger = InformationCollector("ONLINE");
                case "OFF"
                    app.Controller.Logger = InformationCollector("OFFLINE");
            end

            % ProjectManager: 1-by-1 regproj object, manage registration project
            pmopt = struct("HardDriveCapacity", app.PIVs.ApplicationOptions.HardDriveCapacity, ...
                           "MemoryCapacity",    app.PIVs.ApplicationOptions.MemoryCapacity);
            app.Controller.ProjectManager = regpm(app, pmopt);

            % ProgressTimer: 1-by-1 timer for progress bar running indeterminately
            app.Controller.ProgressTimer = timer("Period",app.PROGRESSBAR_TIMER_PERIOD,...
                "ExecutionMode","fixedSpacing","TasksToExecute",inf,...
                "Name", "Timer_ProgressControl","TimerFcn",@app.IndeteminateProgressBar, ...
                "BusyMode","queue");

            % - RegisterKernel: 1-by-1 RegisterController object
            app.Controller.RegisterKernel = RegisterController(app, regopt(), ...
                [app.PIVs.ApplicationOptions.NumProtectedCPU, ...
                app.PIVs.ApplicationOptions.NumProtectedGPU]);

            % - ServerFlag: 1-by-1 logical, the flag for estimating if running on server
            app.Controller.ServerFlag = false;
        end

        function DeactivateAppController(app)
            stop(app.Controller.AppDaemonTimer);
            delete(app.Controller.AppDaemonTimer);

            app.Controller.Logger.Stop();
            delete(app.Controller.Logger);

            delete(app.Controller.ProjectManager);

            stop(app.Controller.ProgressTimer);
            delete(app.Controller.ProgressTimer);

            delete(app.Controller.RegisterKernel);
        end

        function InstallAdditionalComponents(app)
            % Install Storage Manager
            % storage manager: help user to manage temporary files
            InstallStorageManager(app);

            % Install Custom Plugins
            % custom plugins: provide extensions for user advanced using
            InstallCustomPlugins(app);
        end

        function InstallStorageManager(app)
            % append storage_manager dynamically
            app.StorageManager = storage_manager(app.GridLayout_StorageViewer);
            app.StorageManager.Layout.Row = 1;
            app.StorageManager.Layout.Column = 1;

            % set caller
            app.StorageManager.SetCaller(app);
        end

        function InstallCustomPlugins(app)
            app.PIVs.PluginConfigs = struct("name",           {}, ...
                                            "version",        {}, ...
                                            "author",         {}, ...
                                            "company",        {}, ...
                                            "release_date",   {}, ...
                                            "description",    {}, ...
                                            "identifier",     {});
            app.PIVs.PluginMenus = cell(0, 1);
            app.SUBAPPs.CP = {};
            switch app.PIVs.ApplicationOptions.DeveloperMode
                case "ON"
                    app.Menu_PluginsManager.Enable = "on";
                    % set plugin configurations
                    ValidateCustomPlugins(app);

                    %update plugins menu dynamically
                    if ~isempty(app.PIVs.PluginMenus)
                        cellfun(@(x)delete(x), app.PIVs.PluginMenus);
                    end
                    app.PIVs.PluginMenus = cell(numel(app.PIVs.PluginConfigs), 1);
                    for p = 1:numel(app.PIVs.PluginConfigs)
                        app.PIVs.PluginMenus{p} = uimenu(app.Menu_PluginsManager);
                        app.PIVs.PluginMenus{p}.MenuSelectedFcn = ...
                            createCallbackFcn(app, @Menu_PluginSelected, true);
                        app.PIVs.PluginMenus{p}.UserData = {app.PIVs.PluginConfigs(p).identifier, p}; % raw file name
                        app.PIVs.PluginMenus{p}.Text = app.PIVs.PluginConfigs(p).name;                % user label
                    end
                case "OFF"
                    app.Menu_PluginsManager.Enable = "off";
            end
        end

        function UninstallCustomPlugins(app)
            % close exist plugins
            CloseAllPlugins(app);

            % remove appended menu
            if ~isempty(app.PIVs.PluginMenus)
                cellfun(@delete, app.PIVs.PluginMenus)
            end

            % reset
            app.PIVs.PluginConfigs = struct("name",           {}, ...
                                            "version",        {}, ...
                                            "author",         {}, ...
                                            "company",        {}, ...
                                            "release_date",   {}, ...
                                            "description",    {}, ...
                                            "identifier",     {});
            app.PIVs.PluginMenus = cell(0, 1);
            app.SUBAPPs.CP = {};
        end

        function ValidateCustomPlugins(app)
            % This function check all valid plugins under folder \plugins
            % and update information
            PathToMLAPP = fileparts(mfilename("fullpath"));
            plugin_folder = [PathToMLAPP, filesep, 'plugins'];
            list = struct2table(dir(plugin_folder)); list = list(3:end, :);
            list = list(list.isdir, :);      % keep folders only
            if isempty(list)
                return;
            end

            % extract informations
            nn = 0;
            for k = 1:size(list, 1)
                plugin_file = string([list.folder{k}, filesep, list.name{k}, ...
                    filesep, list.name{k}]) + [".m"; ".p"; ".mlapp"];
                info_file = [list.folder{k}, filesep, list.name{k}, ...
                    filesep, list.name{k}, '.xml'];

                if all(~isfile(plugin_file))
                    warning("ReTiNA:pluginLost", "Plugin [%s] executable " + ...
                        "file lost. Jumping over this plugin user declared.", list.name{k});
                    continue; 
                end

                nn = nn + 1;        % add new item
                if isfile(info_file)
                    plugin_info = readstruct(info_file, "FileType","xml");
                else
                    plugin_info = struct("name",        list.name{k}, ...
                                         "version",     "", ...
                                         "author",      "", ...
                                         "company",     "", ...
                                         "release_date","", ...
                                         "description", "");
                    warning("ReTiNA:infoLost", "Plugin [%s] information " + ...
                        "file lost. Empty information will replace as default.", list.name{k});
                end

                plugin_info.identifier = list.name{k};
                app.PIVs.PluginConfigs(nn) = plugin_info;
            end
        end

        function [opt, others] = TakeSystemBasicSnap(app)
            opt = struct(constdef.OP_CROP,      app.Controller.ProjectManager.Project.opt_crop, ...
                         constdef.OP_LOAD,      [], ...
                         constdef.OP_REGISTER,  app.Controller.ProjectManager.Project.opt_reg, ...
                         constdef.OP_SEGMENT,   app.Controller.ProjectManager.Project.opt_seg);

            others = struct("text_time",    app.PDVs.TimeTextObject, ...
                            "text_meta",    app.PDVs.TimeTextObject, ...
                            "fixdef",       app.Controller.ProjectManager.Project.fixdef, ...
                            "frames_reg",   app.Controller.ProjectManager.Project.frames_reg, ...
                            "mask_reg",     app.PDVs.Mask, ...
                            "masked_flag",  app.PDVs.MaskedFlag, ...
                            "mode_zproj",   app.PDVs.ZProjectionMode);
        end

        function RestoreBasicSystemBy(app, nd)
            % unlock the display locker
            app.PIVs.ViewerLock = true;
            PanelWaiting(app);

            %% restore data and basic options
            % update current image source
            app.Controller.ProjectManager.Project.img_cur = nd.data;

            % restore basic options
            app.Controller.ProjectManager.Project.opt_crop = nd.arg.(constdef.OP_CROP);
            app.Controller.ProjectManager.Project.opt_reg = nd.arg.(constdef.OP_REGISTER);
            app.Controller.ProjectManager.Project.opt_seg = nd.arg.(constdef.OP_SEGMENT);

            %% synchronize other variables
            % restore variables
            app.PDVs.TimeTextObject = nd.others.text_time;
            app.PDVs.TimeTextObject = nd.others.text_meta;
            app.Controller.ProjectManager.Project.fixdef = nd.others.fixdef;
            app.Controller.ProjectManager.Project.frames_reg = nd.others.frames_reg;
            app.PDVs.Mask = nd.others.mask_reg;
            app.PDVs.MaskedFlag = nd.others.masked_flag;
            app.PDVs.ZProjectionMode = nd.others.mode_zproj;
            
            % reset variables
            app.Controller.ProjectManager.Project.fidx_cur = 1;
            app.Controller.ProjectManager.Project.sidx_cur = 1;

            % update template definition
            app.Controller.ProjectManager.Project.refvol = regtmpl(app.Controller.ProjectManager.Project.img_cur, app.Controller.ProjectManager.Project.fixdef);

            % synchronize img_zproj.cur, img_zproj.ref
            % !!! must run after recover refvol
            SourceZProjection(app, [], app.PDVs.ZProjectionMode);

            %% update system appearance
            % refresh the slice viewer display controller
            SetSlider_Slice(app);
            SetSlider_Time(app, [1, app.Controller.ProjectManager.Project.img_cur.MetaData.frames]);

            % update z projection menu status
            app.Menu_MaxZProjection.Checked = (app.PDVs.ZProjectionMode=="max");
            app.Menu_MinZProjection.Checked = (app.PDVs.ZProjectionMode=="min");
            app.Menu_MeanZProjection.Checked = (app.PDVs.ZProjectionMode=="mean");
            app.Menu_MedianZProjection.Checked = (app.PDVs.ZProjectionMode=="median");
            if app.PDVs.ZProjectionMode == "none"
                SetSlider_Slice(app, "on");
            else
                SetSlider_Slice(app, "freeze");
            end

            % set registration evaluation
            optr = app.Controller.ProjectManager.OperationManager.ActiveNodeData.optr;
            app.Menu_RegEvaluate.Enable = ...
                (~isempty(optr) && optr==constdef.OP_REGISTER);

            % update the scalebar
            app.PDVs.ScaleBar.update(app.Controller.ProjectManager.Project.img_cur.MetaData);

            % refresh viewer
            RefreshImagingRange2D(app);
            RefreshViewer(app, "XY");
            UpdateYScrollLim(app);

            % update sub apps
            UpdateSubAppsBy(app, nd);

            % unlock the display locker
            PanelRecovered(app);
            app.PIVs.ViewerLock = false;
        end

        function UpdateSubAppsBy(app, nd)
            %% Required: restart registration setting
            if ~isempty(app.SUBAPPs.RS) && isvalid(app.SUBAPPs.RS)
                % update sub apps data
                app.SUBAPPs.RS.Refresh(app);
            end

            %% Optional: restart CS or FS
            switch nd.optr
                case constdef.OP_SEGMENT
                    % call segmenter handle (restart)
                    if isempty(app.SUBAPPs.CS) || ~isvalid(app.SUBAPPs.CS)
                        app.SUBAPPs.CS = CellSegmentor(app);
                    else
                        app.SUBAPPs.CS.Refresh(app);
                    end
                    app.SUBAPPs.CS.Recover(nd.cbot, app.Controller.ProjectManager.Project.sidx_cur);

                case constdef.OP_CROP
                    CloseSubApp(app, "FS");

                otherwise
                    % skip
            end
        end

        function opt = GetInitialApplicationProfile(app)
            opt = constdef.PROFILE_DEFAULT;

            % read from userpath for profiles
            prf_file = GetProfileFullPath(app);

            if isfile(prf_file)
                try
                    popt = readstruct(prf_file, "FileType","xml");
                    
                    % replace the same field in opt by popt
                    fns = reshape(string(fieldnames(opt)), 1, []);
                    pfns = string(fieldnames(popt));
                    for ns = fns
                        % TODO: Validate value
                        if ismember(ns, pfns), opt.(ns) = popt.(ns); end
                    end
                catch
                    throw(MException("Register:permissionDenied", ...
                        "Permission denied, please contact the administrator."));
                end
            else
                % no previous profile, use default profile
                % modify the external library
                pe = pyenv; 
                opt.PythonPath = pe.Executable;
            end
        end

        function [prof, conf] = GetInitProjectOptions(app)
            % read for saved profiles
            prf_file = GetProjectProfileFullPath(app);
            if isfile(prf_file)
                try
                    prof = readstruct(prf_file, "FileType","xml");
                catch
                    throw(MException("Register:permissionDenied", ...
                        "Permission denied, please contact the administrator."));
                end
            else
                prof = constdef.PROJECT_PROFILE_DEFAULT;
                prof.ProjectFolders = string(userpath) + filesep + constdef.PROJECT_FOLDER_NAME_DEFAULT;
            end

            conf = constdef.PROJECT_CONFIG_DEFAULT;    % new configuration
        end

        function opt = GetInitImporterProfile(app)
            opt = struct("ImageFile",       "", ...
                         "TimeStampFile",   "", ...
                         "ImportFolder",    "", ...
                         "SkipImport",      false, ...
                         "IndependentTime", false);

           switch app.PIVs.ApplicationOptions.ImportSourcePreset
               case "LAST"
                   opt.ImportFolder = app.PIVs.ProjectProfile.SourceFolders(end);
               case "MODE"
                   fds = categorical(app.PIVs.ProjectProfile.SourceFolders);
                   [n, c] = histcounts(fds);
                   opt.ImportFolder = string(c{n==max(n)});
               case "HOME"
                   opt.ImportFolder = userpath;
               otherwise
           end
        end
        
        function status = DeployEnvConfig(app)
            % check java path
            % check C/C++ compiler
            javachk('jvm');
            javachk('swing');

            mex -setup C

            [~, computerName] = system('hostname');
            computerName = strtrim(computerName);
            if (feature('numCores') > 16) && (GetAvailableMemory() > 1.375e11) ...
                    && isunix() && isequal(computerName, constdef.SERVER_HOST_NAME)
                % validate if user belongs to regusers group because ./rcfs
                % folder needs regusers privacy, otherwise user can't write
                % file to this folder
                [~, groups] = system('groups');
                groups = string(groups).split(" ").erase(newline);
                if ~ismember(constdef.REGISTRATION_GROUP_NAME, groups)
                    warning("Register:invalidUser", "You are not the member of 'regusers' group. " + ...
                        "Please contact server administrator to solve this issue.");
                    status = -1;
                    return;
                end

                % more than 16 cores and 128 GB available memory
                % server must use 'distributed' mode
                app.Controller.ServerFlag = true;
                fprintf("[Host Name] %s\n", computerName);
                fprintf("[Server Mode] Enabled\n");
            else
                app.Controller.ServerFlag = false;
                fprintf("[Host Name] %s\n", computerName);
                fprintf("[Server Mode] Disabled\n");
            end

            pe = pyenv;
            if app.Controller.ServerFlag == true
                % try to make the python environment configuration
                try
                    pyenv(Version="/usr/share/miniconda3/envs/registration_matlab_env/bin/python3", ...
                        ExecutionMode="OutOfProcess");
                catch
                    warning("Register:pyenvConfigFailed", ...
                        "Can't configurate python environment, try to restart MATLAB.");
                    status = -1;
                    return;
                end
            else
                if pe.Version ~= ""
                    try
                        pyenv(ExecutionMode="OutOfProcess");
                    catch
                        warning("Register:pyenvConfigFailed", ...
                            "Can't configurate python environment, try to restart MATLAB.");
                        status = -1;
                        return;
                    end
                end
            end

            % close some warning
            warning('off', 'MATLAB:timer:miliSecPrecNotAllowed');

            % store the recycle configuration
            app.PIVs.PreviousRecycleStatus = recycle();
            recycle("off");     % save disk space: DO NOT drop files into "Recycle Bin"

            status = 0;
        end

        function status = LoadImageData(app, df_)
            arguments
                app
                df_     (1,1)   string  = ""
            end
            %% Adjust if operation is valid (space limitation)
            tf = app.Controller.ProjectManager.OperationManager.isaddable(constdef.OP_LOAD);

            if tf == false  % always pass
                % ERROR: display at anytime
                uialert(app.Reg3DV500WittgensteinUIFigure, ...
                    app.PIVs.RegionConfig.chmap.STORAGE_INSUFFICIENT, ...
                    app.PIVs.RegionConfig.chmap.ERROR, "Icon","error","Modal",true);
                status = -1;
                return;
            end

            %%
            % load raw data must put on hard-drive
            imreader = ImageReader(app);

            app.Reg3DV500WittgensteinUIFigure.Resize = "off";

            PanelWaiting(app);

            % loading data
            if isfile(app.PIVs.ImageImportProfile.ImageFile)
                imreader.load(df_, app.PIVs.ImageImportProfile.ImageFile);
            else
                % user select file from userpath as default
                imreader.load(df_);
            end

            PanelRecovered(app);

            app.Reg3DV500WittgensteinUIFigure.Resize = "on";

            % close all data related panel to avoid viewing is not
            % synchronized
            CloseDataRelatedSubApp(app);

            opts = imreader.MetaData;
            rt = imreader.T;
            mov = imreader.Data;

            if isempty(rt), app.Menu_ImportTime.Enable = "on";
            else, app.Menu_ImportTime.Enable = "off";
            end

            % load variables to app workspace
            status = DataConstructor(app, mov, opts, rt);
            if status ~= 0
                return;
            end
            app.Controller.ProjectManager.Project.img_fname = imreader.FileName;

            % !!! add history node
            [args, others] = app.TakeSystemBasicSnap();
            app.Controller.ProjectManager.OperationManager.AddNode([], constdef.OP_LOAD, args, "Data",app.Controller.ProjectManager.Project.img_cur, ...
                "Others",others);
            app.Controller.ProjectManager.Project.img_cur = app.Controller.ProjectManager.OperationManager.CurrentImagePtr;
        end

        function LoadTimeStamp(app)
            if ~isempty(app.Controller.ProjectManager.Project.img_cur)    % correspond to current image
                % load time
                if ~isfile(app.PIVs.ImageImportProfile.TimeStampFile)
                    [file, path] = uigetfile(...
                        {['*', char(constdef.TIME_FILE_EXT)], ...
                        [app.PIVs.RegionConfig.chmap.TIME_SERIES_FILE.char(), ...
                        ' (*', char(constdef.TIME_FILE_EXT) ,')']},...
                        app.PIVs.RegionConfig.chmap.LOAD_TIME, "camera_time.tim");

                    HoldWindowOn(app);

                    if ~isnumeric(file)
                        tim_file = fullfile(path, file);
                    else
                         return;
                    end
                else
                    tim_file = app.PIVs.ImageImportProfile.TimeStampFile;
                end

                rt = readmatrix(tim_file, "FileType","text");

                % set time
                app.Controller.ProjectManager.Project.img_cur.Time = rt;
                SetAppSavedStatus(app, "unsaved");

                GenSysMsg(app, ["TIME_LOADING_COMPLETE", " "+fullfile(path, file)]);
            else
                uialert(app.Reg3DV500WittgensteinUIFigure, app.PIVs.RegionConfig.chmap.TIP_ADD_TIMESTAMPS, ...
                    app.PIVs.RegionConfig.chmap.INFORMATION, "Icon","info");
            end
        end

        function UpdateRegOptionsAfterLoading(app)
            if app.Controller.ProjectManager.Project.img_cur.MetaData.channels == 1
                app.Controller.ProjectManager.Project.opt_reg = regopt("OCREG", "global");
                app.Controller.ProjectManager.Project.opt_reg = set(app.Controller.ProjectManager.Project.opt_reg, "FC", app.Controller.ProjectManager.Project.c_order);  % fixed as view
                app.Controller.ProjectManager.Project.opt_reg = set(app.Controller.ProjectManager.Project.opt_reg, "SC", app.Controller.ProjectManager.Project.c_order);  % same as functional channel
                app.PIVs.RegisterOptionsLock = table('Size', [1,2], ...
                    'VariableTypes', repmat({'logical'},1,2), ...
                    'VariableNames', {'Global','MGlobal'});
            else
                app.Controller.ProjectManager.Project.opt_reg = regopt("TCREG", "global");
                app.Controller.ProjectManager.Project.opt_reg = set(app.Controller.ProjectManager.Project.opt_reg, "SC", app.Controller.ProjectManager.Project.c_order(1));
                app.Controller.ProjectManager.Project.opt_reg = set(app.Controller.ProjectManager.Project.opt_reg, "FC", app.Controller.ProjectManager.Project.c_order(2));
                app.PIVs.RegisterOptionsLock = table('Size', [1,4], ...
                    'VariableTypes', repmat({'logical'},1,4), ...
                    'VariableNames', {'Global','Local','MGlobal','LTGlobal'});

            end
            app.Controller.ProjectManager.Project.opt_reg = set(app.Controller.ProjectManager.Project.opt_reg, ...
                "glVPL", floor(log(app.Controller.ProjectManager.Project.img_cur.MetaData.slices)/log(4))+1);

            app.Controller.ProjectManager.Project.refvol = regtmpl(app.Controller.ProjectManager.Project.img_cur, app.Controller.ProjectManager.Project.fixdef);
        end

        function InitAppearance(app)
            InitLayout(app);

            InitMenu(app);

            InitSlider_Slice(app);

            InitSlider_Time(app);

            InitContextMenu(app);

            InitControlPanel(app);

            InitStorageViewer(app);

            SetProgressBar(app, 0);
        end

        function UpdateAppearanceAfterLoading(app)
            % dynamic context menu
            RemoveMergeChannelMenu(app);
            RemoveColorChannelMenu(app);
            AppendColorChannelMenu(app);
            RemoveCellManagerMenu(app);

            % dynamic viewer
            RemoveViewer(app);
            AppendViewer(app);

            % unlock some menu
            app.Menu_Save.Enable = "on";
            app.Menu_SaveAs.Enable = "on";
            app.Menu_ExportTime.Enable = "on";
            app.Menu_ExportMovie.Enable = "on";

            app.Menu_Image.Enable = "on";
            app.Menu_Analyze.Enable = "on";

            if isscalar(app.Controller.ProjectManager.Project.c_order)
                app.Menu_ChannelAlignment.Enable = "off";
            else
                app.Menu_ChannelAlignment.Enable = "on";
            end

            app.Menu_ROI.Enable = "on";
            app.Menu_Channel.Enable = "on";
            app.Menu_MaxZProjection.Checked = "off";
            app.Menu_MinZProjection.Checked = "off";
            app.Menu_MeanZProjection.Checked = "off";
            app.Menu_MedianZProjection.Checked = "off";
            app.Menu_AddTimeStamp.Checked = "off";
            app.Menu_AddMetaData.Checked = "off";
            app.Menu_Direct.Checked = "on";
            app.Menu_1F.Checked = "on";
            app.Menu_5F.Checked = "off";
            app.Menu_15F.Checked = "off";
            app.Menu_HistoryManager.Enable = "on";
            app.DropDown_Source.Enable = "on";
            app.DropDown_Source.ItemsData = {char(constdef.SOURCE_MERGE_NORMAL)};
            app.DropDown_Source.Items = [app.PIVs.RegionConfig.chmap.NORMAL];
            app.Button_Run.Enable = "on";
            app.SetProgressBar(0);

            UpdateYScrollLim(app);

            GenSysMsg(app, ["IMAGE_LOADING_COMPLETE", " "+app.Controller.ProjectManager.Project.img_fname]);
        end

        function SetSystemLanguage(app)
            % set language by ApplicationOptions defination
            switch app.PIVs.ApplicationOptions.Language
                case "CN"
                    UpdateRegion(app, "zh_CN");
                case "US"
                    UpdateRegion(app, "en_US");
                otherwise
                    % follow system
                    lang = GetLocalhostLanguage();
                    UpdateRegion(app, lang);
            end

            % apply menu language
            app.Menu_File.Text = app.PIVs.RegionConfig.chmap.FILE;
            app.Menu_Image.Text = app.PIVs.RegionConfig.chmap.IMAGE;
            app.Menu_Analyze.Text = app.PIVs.RegionConfig.chmap.ANALYZE;
            app.Menu_Advanced.Text = app.PIVs.RegionConfig.chmap.ADVANCED;
            app.Menu_Help.Text = app.PIVs.RegionConfig.chmap.HELP;
            app.Menu_NewProject.Text = app.PIVs.RegionConfig.chmap.NEW_PROJECT;
            app.Menu_OpenProject.Text = app.PIVs.RegionConfig.chmap.OPEN_PROJECT;
            app.Menu_CloseProject.Text = app.PIVs.RegionConfig.chmap.CLOSE_PROJECT;
            app.Menu_Save.Text = app.PIVs.RegionConfig.chmap.SAVE;
            app.Menu_SaveAs.Text = app.PIVs.RegionConfig.chmap.SAVE_AS;
            app.Menu_Import.Text = app.PIVs.RegionConfig.chmap.IMPORT;
            app.Menu_ImportImage.Text = app.PIVs.RegionConfig.chmap.IMAGE;
            app.Menu_ImportTime.Text = app.PIVs.RegionConfig.chmap.TIME_STAMP;
            app.Menu_Export.Text = app.PIVs.RegionConfig.chmap.EXPORT;
            app.Menu_ExportMovie.Text = app.PIVs.RegionConfig.chmap.MOVIE;
            app.Menu_ExportTime.Text = app.PIVs.RegionConfig.chmap.TIME_STAMP;
            app.Menu_Quit.Text = app.PIVs.RegionConfig.chmap.EXIT;
            app.Menu_LightContrast.Text = app.PIVs.RegionConfig.chmap.LIGHT_CONTRAST;
            app.Menu_ChannelAlignment.Text = app.PIVs.RegionConfig.chmap.CHANNEL_ALIGNMENT;
            app.Menu_ZProjection.Text = app.PIVs.RegionConfig.chmap.Z_PROJECTION;
            app.Menu_Comments.Text = app.PIVs.RegionConfig.chmap.APPEND_COMMENTS;
            app.Menu_Crop3D.Text = app.PIVs.RegionConfig.chmap.CROP_VOLUME;
            app.Menu_CropT.Text = app.PIVs.RegionConfig.chmap.CROP_FRAME;
            app.Menu_Flip.Text = app.PIVs.RegionConfig.chmap.FLIP;
            app.Menu_Rotate.Text = app.PIVs.RegionConfig.chmap.ROTATE;
            app.Menu_Animation.Text = app.PIVs.RegionConfig.chmap.ANIMATION;
            app.Menu_PlayMode.Text = app.PIVs.RegionConfig.chmap.PLAY_MODE;
            app.Menu_Registration.Text = app.PIVs.RegionConfig.chmap.REGISTRATOR;
            app.Menu_ResourceManager.Text = app.PIVs.RegionConfig.chmap.RESOURCE_MANAGER;
            app.Menu_HistoryManager.Text = app.PIVs.RegionConfig.chmap.HISTORY_MANAGER;
            app.Menu_PluginsManager.Text = app.PIVs.RegionConfig.chmap.PLUGINS_MANAGER;
            app.Menu_CellSegmentor.Text = app.PIVs.RegionConfig.chmap.CELL_SEGMENTOR;
            app.Menu_SignalExtractor.Text = app.PIVs.RegionConfig.chmap.SIGNAL_EXTRACTOR;
            app.Menu_About.Text = app.PIVs.RegionConfig.chmap.ABOUT;
            app.Menu_Documents.Text = app.PIVs.RegionConfig.chmap.DOCUMENT;
            app.Menu_MaxZProjection.Text = app.PIVs.RegionConfig.chmap.MAX_Z_PROJECTION;
            app.Menu_MinZProjection.Text = app.PIVs.RegionConfig.chmap.MIN_Z_PROJECTION;
            app.Menu_MedianZProjection.Text = app.PIVs.RegionConfig.chmap.MEDIAN_Z_PROJECTION;
            app.Menu_MeanZProjection.Text = app.PIVs.RegionConfig.chmap.MEAN_Z_PROJECTION;
            app.Menu_AddTimeStamp.Text = app.PIVs.RegionConfig.chmap.TIME_STAMP;
            app.Menu_AddScaleBar.Text = app.PIVs.RegionConfig.chmap.SCALE_RATIO;
            app.Menu_AddMetaData.Text = app.PIVs.RegionConfig.chmap.METADATA;
            app.Menu_CropXY.Text = app.PIVs.RegionConfig.chmap.CROP_XY;
            app.Menu_CropZ.Text = app.PIVs.RegionConfig.chmap.CROP_Z;
            app.Menu_FlipHorizontal.Text = app.PIVs.RegionConfig.chmap.HORIZONTAL;
            app.Menu_FlipVertical.Text = app.PIVs.RegionConfig.chmap.VERTICAL;
            app.Menu_RotateClockwise90.Text = app.PIVs.RegionConfig.chmap.CLOCKWISE_90;
            app.Menu_RotateCounterclockwise90.Text = app.PIVs.RegionConfig.chmap.COUNTERCLOCKWISE_90;
            app.Menu_Direct.Text = app.PIVs.RegionConfig.chmap.DIRECT;
            app.Menu_Loop.Text = app.PIVs.RegionConfig.chmap.RECYCLE;
            app.Menu_RegOptions.Text = app.PIVs.RegionConfig.chmap.REGISTRATION_SETTING;
            app.Menu_RegRange.Text = app.PIVs.RegionConfig.chmap.REGISTRATION_RANGE;
            app.Menu_RegEvaluate.Text = app.PIVs.RegionConfig.chmap.REGISTRATION_EVALUATION;

            % apply main panel language
            switch app.PDVs.DisplayViewMode
                case "2D"
                    app.Panel_Viewer.Title = app.PIVs.RegionConfig.chmap.SLICE_VIEW;
                case "3D"
                    app.Panel_Viewer.Title = app.PIVs.RegionConfig.chmap.VOLUME_VIEW;
                otherwise
            end
            app.Panel_Control.Title = app.PIVs.RegionConfig.chmap.CONTROL_PANEL;
            app.Panel_OperationsViewer.Title = app.PIVs.RegionConfig.chmap.OPERATION_VIEW;
            app.Panel_StorageViewer.Title = app.PIVs.RegionConfig.chmap.STORAGE_VIEW;
            app.Panel_OperationSnapshot.Title = app.PIVs.RegionConfig.chmap.OPERATION_SNAPSHOT;
            app.Label_2.Text = app.PIVs.RegionConfig.chmap.SLICE;
            
            app.Label.Text = app.PIVs.RegionConfig.chmap.TIME_FRAME;
            
            app.Label_19.Text = app.PIVs.RegionConfig.chmap.SOURCE;
            app.Label_20.Text = app.PIVs.RegionConfig.chmap.LOG;
            app.Switch_Log.Items = [app.PIVs.RegionConfig.chmap.OFF, app.PIVs.RegionConfig.chmap.ON];
            app.Button_Run.Text = app.PIVs.RegionConfig.chmap.RUN;

            % apply static context menu language
            % ContextMenu_Player
            app.Menu_ROI.Text = app.PIVs.RegionConfig.chmap.ROI_SETTING;
            app.Menu_Channel.Text = app.PIVs.RegionConfig.chmap.COLOR_CHANNEL;
            app.Menu_DisplayMode.Text = app.PIVs.RegionConfig.chmap.DISPLAY_MODE;
            app.Menu_RectangleROI.Text = app.PIVs.RegionConfig.chmap.RECTANGLE;
            app.Menu_CircleROI.Text = app.PIVs.RegionConfig.chmap.CIRCLE;
            app.Menu_PolygonROI.Text = app.PIVs.RegionConfig.chmap.POLYGON;
            app.Menu_FreehandROI.Text = app.PIVs.RegionConfig.chmap.REGION_FREE;
            % ContextMenu Controller
            app.Menu_ExportLog.Text = app.PIVs.RegionConfig.chmap.EXPORT;
            app.Menu_ClearLog.Text = app.PIVs.RegionConfig.chmap.CLEAR;
            % ContextMenu Node Operation
            app.Menu_DeleteNode.Text = app.PIVs.RegionConfig.chmap.DELETE_NODE;
            app.Menu_ActivateNode.Text = app.PIVs.RegionConfig.chmap.ACTIVATE_NODE;
            % ContextMenu Branch Operation
            app.Menu_MergeRegNodes.Text = app.PIVs.RegionConfig.chmap.MERGE_NODES;

            % apply dynamic context menu language
            if ~isempty(app.Menu_MergeSource) && isvalid(app.Menu_MergeSource)
                app.Menu_MergeSource.Text = app.PIVs.RegionConfig.chmap.MERGE_SOURCE;
                app.Menu_MergeRB.Text = app.PIVs.RegionConfig.chmap.RED_CYAN;
                app.Menu_MergeGR.Text = app.PIVs.RegionConfig.chmap.GREEN_MAGENTA;
            end
            if ~isempty(app.Menu_Red) && isvalid(app.Menu_Red)
                app.Menu_Red.Text = app.PIVs.RegionConfig.chmap.RED;
            end
            if ~isempty(app.Menu_Green) && isvalid(app.Menu_Green)
                app.Menu_Green.Text = app.PIVs.RegionConfig.chmap.GREEN;
            end
            if ~isempty(app.Menu_Red_Green) && isvalid(app.Menu_Red_Green)
                app.Menu_Red_Green.Text = app.PIVs.RegionConfig.chmap.RED_GREEN;
            end

            % translate the log message
            if ~isempty(app.TextArea_Log.Value{1}) && app.TextArea_Log.Value{1} ~= ""
                for k = 1:numel(app.TextArea_Log.Value)
                    if app.PIVs.AppMessage(k, 3) ~= ""
                        app.TextArea_Log.Value{k} = [app.PIVs.AppMessage(k, 3).char(), ...
                            ' ', app.PIVs.RegionConfig.chmap.(app.PIVs.AppMessage(k, 1)).char(), ...
                            app.PIVs.AppMessage(k, 2).char()];
                    else
                        app.TextArea_Log.Value{k} = ...
                            [app.PIVs.RegionConfig.chmap.(app.PIVs.AppMessage(k, 1)).char(), ...
                            app.PIVs.AppMessage(k, 2).char()];
                    end
                end
            end

            % apply sub app language
            RefreshAllSubAppLanguage(app);
        end

        function RefreshSystemStyle(app)
            style = app.PIVs.ApplicationOptions.Style;

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr > 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.GridLayout_RegBody.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_Viewer.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_ImageViewer.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_ViewBody.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_MainWin.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_OuterPB.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_SidebarRegion.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_Control.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Tree_Operations.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_OperationsViewer.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_StorageViewer.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_StorageViewer.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_OperationSnapshot.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                case "DARK"
                    app.GridLayout_RegBody.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_Viewer.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_ImageViewer.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_ViewBody.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_MainWin.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_OuterPB.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_SidebarRegion.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_Control.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Tree_Operations.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_OperationsViewer.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_StorageViewer.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_StorageViewer.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_OperationSnapshot.BackgroundColor = constdef.GRAYSCALE_DARK;
                otherwise
            end

            RefreshAllSubAppStyle(app);
        end

        function RefreshSystemTooltips(app)
            % refresh register tooltips
            switch app.PIVs.ApplicationOptions.TooltipsLevel
                case constdef.TOOLTIP_SIMPLE
                    app.Spinner_Slice.Tooltip = app.PIVs.RegionConfig.chmap.TIP_SPN_SLICE;
                    app.Spinner_Time.Tooltip = app.PIVs.RegionConfig.chmap.TIP_SPN_TIME;
                    app.GridLayout_OuterPB.Tooltip = app.PIVs.RegionConfig.chmap.TIP_SLR_PROGRESS;
                    app.Slider_Time.Tooltip = app.PIVs.RegionConfig.chmap.TIP_SLR_TIME;
                case constdef.TOOLTIP_DETAIL
                    app.Spinner_Slice.Tooltip = app.PIVs.RegionConfig.chmap.TIP_SPN_SLICE;
                    app.Spinner_Time.Tooltip = app.PIVs.RegionConfig.chmap.TIP_SPN_TIME;
                    app.GridLayout_OuterPB.Tooltip = app.PIVs.RegionConfig.chmap.TIP_SLR_PROGRESS;
                    app.Slider_Time.Tooltip = app.PIVs.RegionConfig.chmap.TIP_SLR_TIME;
                case constdef.TOOLTIP_NONE
                    app.Spinner_Slice.Tooltip = '';
                    app.Spinner_Time.Tooltip = '';
                    app.GridLayout_OuterPB.Tooltip = '';
                    app.Slider_Time.Tooltip = '';
                otherwise
            end

            RefreshAllSubAppTooltips(app)
        end

        function RefreshSystemMessageLevel(app)
            % refresh register message level ...

            RefreshAllSubAppMessageLevel(app);
        end

        function UpdateAppProfiles(app)
            %% Update Appearance
            % update language
            SetSystemLanguage(app);

            % refresh toolbar
            if app.PDVs.DisplayViewMode == "2D"
                AppendToolBar2D(app);
            end

            % reinstall plugins
            UninstallCustomPlugins(app);    % uninstall first
            InstallCustomPlugins(app);
            switch app.PIVs.ApplicationOptions.DeveloperMode
                case "ON"
                    app.Menu_PluginsManager.Enable = "on";
                case "OFF"
                    app.Menu_PluginsManager.Enable = "off";
            end

            % refresh style
            RefreshSystemStyle(app);

            % add tool tips
            RefreshSystemTooltips(app);

            % refresh message level to control message flow
            RefreshSystemMessageLevel(app);

            % set progress bar color
            app.Label_ProgressBar.BackgroundColor = app.PIVs.ApplicationOptions.ProgressBarColor;

            %% Update Operation History Manager
            app.Controller.ProjectManager.OperationManager.CacheSizeMEM = ...
                app.PIVs.ApplicationOptions.MemoryCapacity;
            app.Controller.ProjectManager.OperationManager.CacheSizeHDD = ...
                app.PIVs.ApplicationOptions.HardDriveCapacity;
            InitStorageViewer(app);

            %% Update Kernel Option
            app.Controller.RegisterKernel.NWorkersProtected = ...
                [app.PIVs.ApplicationOptions.NumProtectedCPU, ...
                 app.PIVs.ApplicationOptions.NumProtectedGPU];

        end

        function OpenProject(app, file)
            arguments
                app
                file    (1,1)   string  {mustBeFile}
            end

            % lock image interaction
            app.PIVs.ViewerLock = true;

            % load the file to construct project
            [status, pfile] = app.Controller.ProjectManager.OpenProject(file);

            if status == -1
                % there exist nonempty project, can't create new
                switch app.PIVs.ApplicationOptions.MessageLevel
                    case [constdef.MESSAGE_WARNING, constdef.MESSAGE_INFO]
                        uialert(app.Reg3DV500WittgensteinUIFigure, ...
                            app.PIVs.RegionConfig.chmap.OPEN_PROJECT_EXIST, ...
                            app.PIVs.RegionConfig.chmap.WARNING, "Icon","warning");
                    otherwise
                end

                return;
            end

            [pfolder, ~, ~] = fileparts(pfile);
            % logger start if project is loaded
            app.Controller.Logger.Stop();
            app.Controller.Logger.Start(pfolder);

            % update the system after loading
            UpdateSystemAfterLoading(app);

            % unlock image interaction
            app.PIVs.ViewerLock = false;

            % Update app title
            SetAppSavedStatus(app, "saved");
        end

        function CloseProject(app)
            %% validate if project is saved
            if app.Controller.ProjectManager.Project.saved_flag == false
                slt = uiconfirm(app.Reg3DV500WittgensteinUIFigure, ...
                    app.PIVs.RegionConfig.chmap.CLOSE_PROJECT_CONFIRMATION, ...
                    app.PIVs.RegionConfig.chmap.INFORMATION, ...
                    "Options",[app.PIVs.RegionConfig.chmap.SAVE, ...
                    app.PIVs.RegionConfig.chmap.DO_NOT_SAVE, ...
                    app.PIVs.RegionConfig.chmap.CANCEL], ...
                    "DefaultOption", app.PIVs.RegionConfig.chmap.SAVE, ...
                    "Icon","question");

                switch slt
                    case app.PIVs.RegionConfig.chmap.SAVE
                        SaveProject(app);
                    case app.PIVs.RegionConfig.chmap.DO_NOT_SAVE
                        % skip save
                    otherwise
                        return;
                end
            end

            %% call the project manager to close project
            app.PIVs.ViewerLock = true;
            app.Controller.ProjectManager.CloseProject();

            %% reset properties and appearance
            ResetProjectDependentVars(app);

            app.PIVs.ViewerLock = false;
        end

        function CreateNewProject(app)
            % validate DataRootFolder
            if ~isfolder(app.PIVs.ApplicationOptions.DataRootFolder)
                % ERROR: display at anytime
                uialert(app.Reg3DV500WittgensteinUIFigure, ...
                    app.PIVs.RegionConfig.chmap.INVALID_DATA_ROOT_FOLDER, ...
                    app.PIVs.RegionConfig.chmap.ERROR, "Icon","error");
                return;
            end

            %% call project configuration UI to get options
            npc = NewProjectConfigurator(app);  % modal window
            waitfor(npc);   % wait for closing

            %% use options to create new empty project (by regpm agent)
            if app.PIVs.NewProjectFlag == true
                [status, pfile, dst] = app.Controller.ProjectManager.CreateNewProject(...
                    app.PIVs.ApplicationOptions, app.PDVs.ProjectConfig);

                if status == -1
                    % there exist nonempty project, can't create new
                    switch app.PIVs.ApplicationOptions.MessageLevel
                        case [constdef.MESSAGE_WARNING, constdef.MESSAGE_INFO]
                            uialert(app.Reg3DV500WittgensteinUIFigure, ...
                                app.PIVs.RegionConfig.chmap.OPEN_PROJECT_EXIST, ...
                                app.PIVs.RegionConfig.chmap.WARNING, "Icon","warning");
                        otherwise
                    end

                    return;
                end
                % else,
                app.Controller.ProjectManager.Project.proj_fname = pfile;
                [pfolder, ~, ~] = fileparts(pfile);

                % Update app title
                SetAppSavedStatus(app, "saved");
            else
                % cancel create, skip
                return;
            end

            % logger start if pfolder was created
            app.Controller.Logger.Stop();
            app.Controller.Logger.Start(pfolder);

            %% if import immediately, call import configuration UI to get options
            if app.PIVs.ImageImportPostponeFlag == false
                imgipr = ImageImporter(app);
                waitfor(imgipr);

                % load data if no skip flag
                if app.PIVs.ImageImportProfile.SkipImport == false
                    % lock image interaction
                    app.PIVs.ViewerLock = true;

                    status = LoadImageData(app, dst);

                    if status == 0  % normal image loading exit
                        % append load folder and save profile
                        app.PIVs.ProjectProfile.SourceFolders = ...
                            [app.PIVs.ProjectProfile.SourceFolders, ...
                            app.PIVs.ImageImportProfile.ImportFolder];
                        prf_file = GetProjectProfileFullPath(app);
                        writestruct(app.PIVs.ProjectProfile, prf_file, "FileType","xml");

                        if app.PIVs.ImageImportProfile.IndependentTime == true
                            LoadTimeStamp(app);
                        end
                    end

                    % update the system after loading
                    UpdateSystemAfterLoading(app);

                    % unlock image interaction
                    app.PIVs.ViewerLock = false;

                    SetAppSavedStatus(app, "unsaved");
                else
                    return;
                end
            end
        end

        function SaveProject(app)
            if ~isempty(fieldnames(app.Controller)) ...
                    && ~isempty(app.Controller.ProjectManager)
                app.Controller.ProjectManager.SaveProject();

                SetAppSavedStatus(app, "saved");
            end
        end

        function SetAppSavedStatus(app, sflag)
            arguments
                app
                sflag  (1,1)   string  {mustBeMember(sflag, ["unsaved", "saved"])}
            end

            basic_title = sprintf("Reg3D V%s (%s) - ", app.VER, app.RELEASE_CODE);
            switch sflag
                case "unsaved"
                    status_suffix = "*";
                    app.Controller.ProjectManager.Project.saved_flag = false;
                case "saved"
                    status_suffix = "";
                    app.Controller.ProjectManager.Project.saved_flag = true;
                otherwise
            end

            app.Reg3DV500WittgensteinUIFigure.Name = basic_title ...
                + app.Controller.ProjectManager.Project.proj_fname + status_suffix;
        end

        % This function loop by regdaemon every 5s
        function RegDaemonTask(app, ~, ~)
            persistent ups
            if isempty(ups), ups = 0; end

            %% 1. Validate if "Appearance" need to change (if follow system)
            % usual take < 0.1s
            switch app.PIVs.ApplicationOptions.Style
                case "FOLLOW"
                    % refresh at only two time points
                    tcode = str2double(string(datetime("now","Format","HH:mm:ss")).split(":"));
                    if (tcode(1)==7 || tcode(1)==18) && (tcode(2)==0) ...
                            && (tcode(3)>0 && tcode(3)<=app.DAEMON_TIMER_PERIOD)
                        RefreshSystemStyle(app);
                    end
                otherwise
            end

            %% 2. Validate if "Update" need to run
            % when goes false, usual take ~ 3s
            switch app.PIVs.ApplicationOptions.AutoUpdate
                case "EVERYDAY"
                    % refresh at only one time point
                    tcode = str2double(string(datetime("now","Format","HH:mm:ss")).split(":"));
                    if (tcode(1)==0 && tcode(2)==0 && tcode(3)>0 && tcode(3)<=app.DAEMON_TIMER_PERIOD)
                        ValidateUpdate(app);
                    end
                case "RT"
                    % break, avoid UI operation overflow
                    if ups ~= -1, ups = ValidateUpdate(app); end

                otherwise
                    ups = 0;        % reset update profile status
            end
        end

        function InitLayout(app)
            if app.Menu_HistoryManager.Checked == false
                app.Reg3DV500WittgensteinUIFigure.Position(3) = app.LAYOUT_WIDTH_INIT;
                app.Reg3DV500WittgensteinUIFigure.Position(4) = app.LAYOUT_HEIGHT_INIT;
                app.GridLayout_RegView.ColumnWidth = {'1x', 0};
            else
                app.Reg3DV500WittgensteinUIFigure.Position(3) = ...
                    app.LAYOUT_WIDTH_INIT + app.RIGHT_SIDEBAR_WIDTH;
                app.Reg3DV500WittgensteinUIFigure.Position(4) = app.LAYOUT_HEIGHT_INIT;
                app.GridLayout_RegView.ColumnWidth = {'1x', app.RIGHT_SIDEBAR_WIDTH};
            end
        end

        function InitMenu(app)
            app.Menu_Save.Enable = "off";
            app.Menu_SaveAs.Enable = "off";
            app.Menu_ExportMovie.Enable = "off";
            app.Menu_ExportTime.Enable = "off";
            app.Menu_Image.Enable = "off";
            app.Menu_Analyze.Enable = "off";
            app.Menu_SignalExtractor.Enable = "off";
            app.Menu_RegEvaluate.Enable = "off";
            app.Menu_HistoryManager.Enable = "off";
            app.Menu_MaxZProjection.Checked = "off";
            app.Menu_MinZProjection.Checked = "off";
            app.Menu_MeanZProjection.Checked = "off";
            app.Menu_MedianZProjection.Checked = "off";
            app.Menu_HistoryManager.Checked = "off";
        end

        function InitContextMenu(app)
            app.Menu_ROI.Enable = "off";
            app.Menu_Channel.Enable = "off";
            app.Menu_DisplayMode.Enable = "on";

            % dynamic context menu
            RemoveMergeChannelMenu(app);
            RemoveColorChannelMenu(app);

            app.Menu_View2D.Checked = "off";
            app.Menu_View3D.Checked = "off";
            switch app.PDVs.DisplayViewMode
                case "2D"
                    app.Menu_View2D.Checked = "on";
                case "3D"
                    app.Menu_View3D.Checked = "on";
                otherwise
            end

            app.Menu_MergeRegNodes.Enable = "off";
        end

        function InitSlider_Time(app)
            app.Slider_Time.Limits = [0,1];
            app.Slider_Time.Value = 0;
            app.Slider_Time.Enable = "off";
            app.Spinner_Time.Enable = "off";
        end

        function InitControlPanel(app)
            app.DropDown_Source.Items = {};
            app.DropDown_Source.ItemsData = {};
            app.DropDown_Source.Enable = "off";
            app.Button_Run.Enable = "off";
            app.TextArea_Log.Placeholder = app.PIVs.RegionConfig.chmap.WELCOME;
        end

        function InitStorageViewer(app)
            %
            switch app.PIVs.ApplicationOptions.StorageViewStyle
                case "CLASSICAL"
                    app.StorageManager.State = "full";
                case "MODERN"
                    if app.Slider_HistoryViewSplitter.Value ...
                            > app.StorageManager.HEIGHT_DONUTVIEW/2
                        app.StorageManager.State = "pro";
                    else
                        app.StorageManager.State = "simple";
                    end
                otherwise
            end

            % 
            if ~isempty(app.Controller.ProjectManager.OperationManager)
                app.StorageManager.UpdateAppearanceWithoutData()
            end

            if isempty(app.Controller.ProjectManager.OperationManager)
                app.Slider_HistoryViewSplitter.Value = 0;
                app.Slider_HistoryViewSplitter.Enable = "off";
            end
        end

        function AppendViewer2D(app)
            app.UIAxes_Slice = uiaxes(app.GridLayout_MainWin);
            xlabel(app.UIAxes_Slice, 'X')
            ylabel(app.UIAxes_Slice, 'Y')
            zlabel(app.UIAxes_Slice, 'Z')

            app.UIAxes_Slice.Visible = 'off';
        end

        function AppendViewer3D(app)
            if isMATLABReleaseOlderThan("R2023b")
                app.UIViewer_Vol = viewer3d(app.GridLayout_MainWin, "BackgroundColor","black", ...
                    "Box","on","BackgroundGradient","off","Lighting","on", ...
                    "Interactions",["zoom","rotate","axes","pan"]);
            elseif isMATLABReleaseOlderThan("R2024a")
                app.UIViewer_Vol = viewer3d(app.GridLayout_MainWin, "BackgroundColor","black", ...
                    "Box","on","BackgroundGradient","off","Denoising","on","Lighting","on",...
                    "Interactions",["zoom","rotate","axes","pan"]);
            else
                app.UIViewer_Vol = viewer3d(app.GridLayout_MainWin, "BackgroundColor","black", ...
                    "Box","on","BackgroundGradient","off","Denoising","on","Lighting","on",...
                    "Interactions",["zoom","rotate","axes","pan","annotate"]);
            end
        end

        function InitSlider_Slice(app)
            app.Slider_Slice.Limits = [0,1];
            app.Slider_Slice.Value = 0;
            app.Slider_Slice.Enable = "off";
            app.Spinner_Slice.Enable = "off";
        end
        
        % This function for 2d image display
        function im_H = InitUIAxes_Slice(app)
            % add customized toolbar on axes
            AppendToolBar2D(app);

            % Note that image can change the figure properties, so that
            % figure setting must after the image setting
            % initialize image handle
            im_H = image(app.UIAxes_Slice,...
                [0.5 app.Controller.ProjectManager.Project.img_cur.MetaData.width-0.5],...
                [0.5 app.Controller.ProjectManager.Project.img_cur.MetaData.height-0.5],...
                [],"Interpolation","bilinear"); % bilinear offer better visual effort

            app.PDVs.ImageHandle = im_H;

            % set coordinate
            RefreshImagingRange2D(app);

            % set colormap
            colormap(app.UIAxes_Slice, "gray");
            clim(app.UIAxes_Slice, [app.Controller.ProjectManager.Project.img_cur.EMin(1), ...
                app.Controller.ProjectManager.Project.img_cur.EMax(1)]);

            drawnow nocallbacks

            % show the viewer
            app.UIAxes_Slice.Visible = "on";

            UpdateYScrollLim(app);

            app.Label_ImageInfo.Visible = "on";
        end

        % This function for 3d volume display
        function vol_H = InitUIViewer_Vol(app)
            rs = min(app.Controller.ProjectManager.Project.img_cur.MetaData.xRes, app.Controller.ProjectManager.Project.img_cur.MetaData.yRes);
            vol_H = volshow([], "Parent", app.UIViewer_Vol, ...
                "RenderingStyle", "MaximumIntensityProjection", ...
                "Transformation", affinetform3d(diag([rs,rs,rs,1])));

            app.PDVs.VolumeHandle = vol_H;

            UpdateYScrollLim(app);

            app.UIViewer_Vol.ScaleBarUnits = "um";
            app.UIViewer_Vol.Visible = "on";
            app.Label_ImageInfo.Visible = "on";
        end

        function iv_H = InitViewer(app)
            switch app.PDVs.DisplayViewMode
                case "2D"
                    iv_H = InitUIAxes_Slice(app);
                case "3D"
                    iv_H = InitUIViewer_Vol(app);
                otherwise
            end
        end

        function AppendToolBar2D(app)
            if ~isempty(app.UIAxes_Slice) && isvalid(app.UIAxes_Slice)
                pathToMLAPP = fileparts(mfilename('fullpath'));

                % append initialized toolbar with:
                app.PIVs.AxesToolBar = []; % remove previous toolbar
                app.PIVs.AxesToolBar = axtoolbar(app.UIAxes_Slice, {'export','zoomin','zoomout','pan'});

                if app.PIVs.ApplicationOptions.SimpleStatistics == "ON"
                    % append simple function
                    btn_stat = axtoolbarbtn(app.PIVs.AxesToolBar, "push");
                    btn_stat.Icon = fullfile(pathToMLAPP, 'sources', 'stat_icon.png');
                    btn_stat.Tooltip = app.PIVs.RegionConfig.chmap.SIMPLE_STAT;
                    btn_stat.ButtonPushedFcn = @app.SimpleStat_CallBack;
                end

                if app.PIVs.ApplicationOptions.AutoTemplate == "ON"
                    % append auto template function only if red channel exist
                    if any(app.Controller.ProjectManager.Project.img_cur.MetaData.cOrder=="r")
                        btn_autotmpl = axtoolbarbtn(app.PIVs.AxesToolBar, "push");
                        btn_autotmpl.Icon = fullfile(pathToMLAPP, 'sources', 'autotmpl_icon.png');
                        btn_autotmpl.Tooltip = app.PIVs.RegionConfig.chmap.AUTO_TEMPLATE;
                        btn_autotmpl.ButtonPushedFcn = @app.FindTemplateAuto_CallBack;
                    end
                end

                % append overload "restore view" function
                btn_rsc = axtoolbarbtn(app.PIVs.AxesToolBar, "push");
                btn_rsc.Icon = fullfile(pathToMLAPP, 'sources', 'restore_view_icon.png');
                btn_rsc.Tooltip = app.PIVs.RegionConfig.chmap.RESTORE_VIEW;
                btn_rsc.ButtonPushedFcn = @app.RestoreView2D_CallBack;

                % append registration pre-solve function
                if app.PIVs.ApplicationOptions.PreprocessEvaluation == "ON"
                    btn_psolve = axtoolbarbtn(app.PIVs.AxesToolBar, "push");
                    btn_psolve.Icon = fullfile(pathToMLAPP, 'sources', 'preprocess_eval_icon.png');
                    btn_psolve.Tooltip = app.PIVs.RegionConfig.chmap.PREPROCESSING_EVALUATION;
                    btn_psolve.ButtonPushedFcn = @app.PreprocessEvaluation_CallBack;
                end
            end
        end

        function RefreshImagingRange2D(app)
            % initialize figure
            app.UIAxes_Slice.XLim = [0.5, app.Controller.ProjectManager.Project.img_cur.MetaData.width-0.5];
            app.UIAxes_Slice.YLim = [0.5, app.Controller.ProjectManager.Project.img_cur.MetaData.height-0.5];
            app.UIAxes_Slice.XTick = [];
            app.UIAxes_Slice.YTick = [];

            set(app.PDVs.ImageHandle, 'XData', [0.5, app.Controller.ProjectManager.Project.img_cur.MetaData.width-0.5]);
            set(app.PDVs.ImageHandle, 'YData', [0.5, app.Controller.ProjectManager.Project.img_cur.MetaData.height-0.5]);

            pbaspect(app.UIAxes_Slice, [app.Controller.ProjectManager.Project.img_cur.MetaData.width, ...
                                        app.Controller.ProjectManager.Project.img_cur.MetaData.height,...
                                        1]);
        end
        
        %======================= PLAYER CONTROL ==========================
        function UpdateYScrollLim(app)
            switch app.PDVs.DisplayViewMode
                case "2D"
                    % calculate scroll y limitation
                    tp = app.UIAxes_Slice.tightPosition();
                    % xr = diff(app.UIAxes_Slice.XLim)/tp(3)*app.Panel_Control.Position(3);
                    yr = diff(app.UIAxes_Slice.YLim)/tp(4)*app.Panel_Viewer.Position(4);
                    % xvalid = 0.5*diff(app.UIAxes_Slice.XLim) + 0.5*[-xr, xr];
                    tiset = app.UIAxes_Slice.TightInset;
                    app.PIVs.SliceScrollCursorLimit = 0.5*diff(app.UIAxes_Slice.YLim) + ...
                        0.5*[-yr, yr] + 0.5*[tiset(2)+20, tiset(4)];
                case "3D"
                    % slice locked, Y scroll limit can be omit
                otherwise
            end
        end

        function UpdateHistoryManagerYSliderLim(app)
            % modify the slider limits and value according to window size
            H = app.Reg3DV500WittgensteinUIFigure.Position(4);

            app.Slider_HistoryViewSplitter.Limits = [0, ...
                max(1, H-app.SLIDER_TOP_CONST-app.SLIDER_BOT_CONST-app.SNAPSHOT_HEIGHT)];

            app.Slider_HistoryViewSplitter.Value = ...
                min(max(0, app.GridLayout_HistoryManager.RowHeight{2}-app.SLIDER_BOT_CONST), ...
                app.Slider_HistoryViewSplitter.Limits(2));
        end

        function ScalingProgressBar(app)
            % update progress bar
            figwc = app.Reg3DV500WittgensteinUIFigure.Position(3);
            lnew = (figwc - (60*2+10*4+7*2))*app.PIVs.PreviousProgressBarValue;
            app.GridLayout_ProgressBar.ColumnWidth{2} = lnew;
            drawnow
        end

        function UpdatePlayerTimer(app, nframes, fps)
            if ~isempty(app.PDVs.MoviePlayerTimer) ...
                    && isvalid(app.PDVs.MoviePlayerTimer)
                app.PDVs.MoviePlayerTimer.Period = round(1/fps, 3);
                app.PDVs.MoviePlayerTimer.TasksToExecute = nframes;
            end
        end
        
        function NextFramePtr(app)
            if ~isempty(app.Controller.ProjectManager.Project.fidx_cur) && app.PIVs.ChannelAligningLock == false
                if app.Controller.ProjectManager.Project.fidx_cur < app.Slider_Time.Limits(end)
                    app.Controller.ProjectManager.Project.fidx_cur = app.Controller.ProjectManager.Project.fidx_cur + 1;
                else
                    switch app.PDVs.DisplayLoopMode
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.Controller.ProjectManager.Project.fidx_cur = app.Slider_Time.Limits(1);
                    end
                end
                app.Slider_Time.Value = app.Controller.ProjectManager.Project.fidx_cur;
                app.Spinner_Time.Value = app.Controller.ProjectManager.Project.fidx_cur;
                app.Controller.ProjectManager.Project.frames_reg(2) = string(app.Controller.ProjectManager.Project.fidx_cur);
            end
        end

        function PrevFramePtr(app)
            if ~isempty(app.Controller.ProjectManager.Project.fidx_cur) && app.PIVs.ChannelAligningLock == false
                if app.Controller.ProjectManager.Project.fidx_cur > app.Slider_Time.Limits(1)
                    app.Controller.ProjectManager.Project.fidx_cur = app.Controller.ProjectManager.Project.fidx_cur - 1;
                else
                    switch app.PDVs.DisplayLoopMode
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.Controller.ProjectManager.Project.fidx_cur = app.Slider_Time.Limits(end);
                    end
                end
                app.Slider_Time.Value = app.Controller.ProjectManager.Project.fidx_cur;
                app.Spinner_Time.Value = app.Controller.ProjectManager.Project.fidx_cur;
                app.Controller.ProjectManager.Project.frames_reg(2) = string(app.Controller.ProjectManager.Project.fidx_cur);
            end
        end

        function NextSlicePtr(app)
            if ~isempty(app.Controller.ProjectManager.Project.sidx_cur) && app.PDVs.ZProjectionMode == "none" ...
                    && app.PIVs.ChannelAligningLock == false
                if app.Controller.ProjectManager.Project.sidx_cur < app.Slider_Slice.Limits(end)
                    app.Controller.ProjectManager.Project.sidx_cur = app.Controller.ProjectManager.Project.sidx_cur + 1;
                else
                    switch app.PDVs.DisplayLoopMode
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.Controller.ProjectManager.Project.sidx_cur = app.Slider_Slice.Limits(1);
                    end
                end
                app.Slider_Slice.Value = app.Controller.ProjectManager.Project.sidx_cur;
                app.Spinner_Slice.Value = app.Controller.ProjectManager.Project.sidx_cur;
            end
        end

        function PrevSlicePtr(app)
            if ~isempty(app.Controller.ProjectManager.Project.sidx_cur) && app.PDVs.ZProjectionMode == "none" ...
                    && app.PIVs.ChannelAligningLock == false
                if app.Controller.ProjectManager.Project.sidx_cur > app.Slider_Slice.Limits(1)
                    app.Controller.ProjectManager.Project.sidx_cur = app.Controller.ProjectManager.Project.sidx_cur - 1;
                else
                    switch app.PDVs.DisplayLoopMode
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.Controller.ProjectManager.Project.sidx_cur = app.Slider_Slice.Limits(end);
                    end
                end
                app.Slider_Slice.Value = app.Controller.ProjectManager.Project.sidx_cur;
                app.Spinner_Slice.Value = app.Controller.ProjectManager.Project.sidx_cur;
            end
        end

        function Play(app, src, event)
            src; %#ok<VUNUS>
            event; %#ok<VUNUS>
            if ~app.PIVs.ChannelAligningLock
                NextFramePtr(app);
                RefreshViewer(app, "T");
            end
        end

        function Stop(app, src, event)
            src; %#ok<VUNUS>
            event; %#ok<VUNUS>
            UnlockPlaySetting(app);
        end

        function RefreshViewer(app, dview)
            arguments
                app
                dview   (1,1)   string {mustBeMember(dview, ["XY", "Z","T"])} = "T"
            end
            switch app.PDVs.DisplayViewMode
                case "2D"
                    RefreshViewer2D(app, dview);
                case "3D"
                    RefreshViewer3D(app);
                otherwise
            end
        end

        function RefreshViewer2D(app, dview)
            if ~app.PIVs.ChannelAligningLock
                % update and draw current slice
                UpdateCurrentSlice(app, dview);

                DrawCurrentSlice(app);

                % update and draw mROI objects, text objects
                DrawUtilsComponents(app, dview);

                % follow the mouse
                Reg3DV500WittgensteinUIFigureWindowButtonMotion(app);
            end
        end

        function RefreshViewer3D(app)
            % only support "T" mode
            UpdateCurrentVolume(app);

            DrawCurrentVolume(app);

            UpdateViewerTitle(app);

            DrawUtilsComponents(app, "T");      % "T" can be omitted
        end

        function LockViewerController(app)
            app.Slider_Slice.Enable = "off";
            app.Spinner_Slice.Enable = "off";
            app.Slider_Time.Enable = "off";
            app.Spinner_Time.Enable = "off";
            app.Menu_Animation.Enable = "off";
            app.Menu_Channel.Enable = "off";
        end

        function UnlockViewerController(app)
            if app.PDVs.ZProjectionMode == "none"
                app.Slider_Slice.Enable = "on";
                app.Spinner_Slice.Enable = "on";
            end
            app.Slider_Time.Enable = "on";
            app.Spinner_Time.Enable = "on";
            app.Menu_Animation.Enable = "on";
            app.Menu_Channel.Enable = "on";
        end

        function LockComponents_3DView(app)
            app.Menu_ZProjection.Enable = "off";
            app.Menu_ChannelAlignment.Enable = "off";
            app.Menu_Comments.Enable = "off";
            app.Menu_Crop3D.Enable = "off";
            app.Menu_Flip.Enable = "off";
            app.Menu_Rotate.Enable = "off";
            app.Menu_CellSegmentor.Enable = "off";
            app.Menu_SignalExtractor.Enable = "off";

            app.Slider_Slice.Enable = "off";
            app.Spinner_Slice.Enable = "off";

            app.Menu_ROI.Enable = "off";
        end

        function UnlockComponents_2DView(app)
            app.Menu_ZProjection.Enable = "on";
            if ~isscalar(app.Controller.ProjectManager.Project.c_order)
                app.Menu_ChannelAlignment.Enable = "on";
            end
            app.Menu_Comments.Enable = "on";
            app.Menu_Crop3D.Enable = "on";
            app.Menu_Flip.Enable = "on";
            app.Menu_Rotate.Enable = "on";
            app.Menu_CellSegmentor.Enable = "on";

            app.Menu_ROI.Enable = "on";
        end

        function UpdateMergeSource(app)
            switch app.PDVs.DisplaySource
                case constdef.SOURCE_MERGE_NORMAL
                    if ~isempty(app.Menu_MergeSource) && isvalid(app.Menu_MergeSource)
                        app.Menu_MergeSource.Enable = "off";
                    end
                    if ~isempty(app.Menu_Red) && isvalid(app.Menu_Red)
                        app.Menu_Red.Enable = "on";
                    end
                    if ~isempty(app.Menu_Green) && isvalid(app.Menu_Green)
                        app.Menu_Green.Enable = "on";
                    end
                    if ~isempty(app.Menu_Red_Green) && isvalid(app.Menu_Red_Green)
                        app.Menu_Red_Green.Enable = "on";
                    end

                    % remove colorbar
                    switch app.PDVs.DisplayViewMode
                        case "2D"
                            colorbar(app.UIAxes_Slice, "off");
                        otherwise
                            % not support, skip
                    end
                case constdef.SOURCE_MERGE_FUSED
                    if ~isempty(app.Menu_MergeSource) && isvalid(app.Menu_MergeSource)
                        app.Menu_MergeSource.Enable = "on";
                    end
                    if ~isempty(app.Menu_Red) && isvalid(app.Menu_Red)
                        app.Menu_Red.Enable = "on";
                    end
                    if ~isempty(app.Menu_Green) && isvalid(app.Menu_Green)
                        app.Menu_Green.Enable = "on";
                    end
                    if ~isempty(app.Menu_Red_Green) && isvalid(app.Menu_Red_Green)
                        app.Menu_Red_Green.Enable = "off";
                    end

                    % remove colorbar
                    switch app.PDVs.DisplayViewMode
                        case "2D"
                            colorbar(app.UIAxes_Slice, "off");
                        otherwise
                            % not support, skip
                    end
                case constdef.SOURCE_MERGE_PREPROCESS
                    if ~isempty(app.Menu_MergeSource) && isvalid(app.Menu_MergeSource)
                        app.Menu_MergeSource.Enable = "off";
                    end
                    if ~isempty(app.Menu_Red) && isvalid(app.Menu_Red)
                        app.Menu_Red.Enable = "off";
                    end
                    if ~isempty(app.Menu_Green) && isvalid(app.Menu_Green)
                        app.Menu_Green.Enable = "off";
                    end
                    if ~isempty(app.Menu_Red_Green) && isvalid(app.Menu_Red_Green)
                        app.Menu_Red_Green.Enable = "off";
                    end

                    % append colorbar
                    switch app.PDVs.DisplayViewMode
                        case "2D"
                            colorbar(app.UIAxes_Slice, "eastoutside");
                        otherwise
                            % not support, skip
                    end
                otherwise
            end
        end
        
        %===================== PLAYER SETTING ============================
        
        % player components: lock play mode menu
        function LockPlaySetting(app)
            app.Menu_Loop.Enable = "off";
            app.Menu_Direct.Enable = "off";
            app.Menu_1F.Enable = "off";
            app.Menu_5F.Enable = "off";
            app.Menu_15F.Enable = "off";
        end

        % player components: unlock play mode menu
        function UnlockPlaySetting(app)
            app.Menu_Loop.Enable = "on";
            app.Menu_Direct.Enable = "on";
            app.Menu_1F.Enable = "on";
            app.Menu_5F.Enable = "on";
            app.Menu_15F.Enable = "on";
        end

        % player components: time slider
        function SetSlider_Time(app, limits)
            LABEL_COUNTS = 20;
            app.Slider_Time.Enable = "on";
            app.Spinner_Time.Enable = "on";

            if ~isempty(app.Controller.ProjectManager.Project.img_cur) && app.Slider_Time.Enable == true
                if ~isempty(limits)
                    app.Slider_Time.Limits = limits;
                    app.Spinner_Time.Limits = limits;
                else
                    % keep the limits
                    limits = app.Slider_Time.Limits;
                end

                deltaT = round((limits(2)-limits(1))/LABEL_COUNTS);
                if ~isequal(app.Slider_Time.MajorTicks,limits(1):deltaT:limits(2))
                    app.Slider_Time.MajorTicks = limits(1):deltaT:limits(2);
                    app.Slider_Time.MajorTickLabels = string(app.Slider_Time.MajorTicks);
                end

                app.Slider_Time.Value = app.Controller.ProjectManager.Project.fidx_cur;
                app.Spinner_Time.Value = app.Controller.ProjectManager.Project.fidx_cur;
            end
        end

        % player components: slice slider
        function SetSlider_Slice(app, state)
            if exist("state","var") && ~isempty(state)
                switch state
                    case 'on'
                        app.Slider_Slice.Enable = "on";
                        app.Spinner_Slice.Enable = "on";
                    case 'off'
                        app.Slider_Slice.Limits = [0,1];
                        app.Slider_Slice.Value = 0;
                        app.Slider_Slice.Enable = "off";
                        app.Spinner_Slices.Limits = [0,1];
                        app.Spinner_Slices.Value = 0;
                        app.Spinner_Slice.Enable = "off";
                    case 'freeze'
                        app.Slider_Slice.Enable = "off";
                        app.Spinner_Slice.Enable = "off";
                end
            else
                % keep the state
            end

            if ~isempty(app.Controller.ProjectManager.Project.img_cur)
                app.Slider_Slice.Limits = [1, app.Controller.ProjectManager.Project.img_cur.MetaData.slices];
                app.Spinner_Slice.Limits = [1, app.Controller.ProjectManager.Project.img_cur.MetaData.slices];

                app.Slider_Slice.MajorTicks = 1:5:app.Controller.ProjectManager.Project.img_cur.MetaData.slices;
                app.Slider_Slice.MinorTicks = 1:app.Controller.ProjectManager.Project.img_cur.MetaData.slices;

                app.Slider_Slice.Value = app.Controller.ProjectManager.Project.sidx_cur;
                app.Spinner_Slice.Value = app.Controller.ProjectManager.Project.sidx_cur;
            end
        end

        function IndeteminateProgressBar(app, obj, event)
            % This function set the progress bar running indeteminatlly
            obj; %#ok<VUNUS>
            event; %#ok<VUNUS>
            persistent x;
            if isempty(x)
                x = 0;
            else
                x = mod(x+1, 60);
            end
            len = app.Reg3DV500WittgensteinUIFigure.Position(3) - (60*2+10*4+7*2);
            barl = round(min([len/5, x/50*len, (60-x)*len/50]));
            bars = round(max(x*len/50-len/5, 0));

            app.GridLayout_ProgressBar.ColumnWidth = {bars, barl};
            drawnow nocallbacks
        end

        % player components: viewer body
        function AppendViewer(app)
            % append main display window body
            switch app.PDVs.DisplayViewMode
                case "2D"
                    AppendViewer2D(app);
                    
                    % attach scale bar
                    app.PDVs.ScaleBar = scalebar(app.UIAxes_Slice);
                    app.PDVs.ScaleBar.update(app.Controller.ProjectManager.Project.img_cur.MetaData);
                case "3D"
                    AppendViewer3D(app);

                    app.UIViewer_Vol.ScaleBar = "on";
                otherwise
                    throw(MException("invalidViewMode", ...
                        "Error using of InitViewer: unsupported viewing dimension."));
            end

            % append progress bar (removed), instead of fixed location
        end

        function RemoveViewer(app)
            if ~isempty(app.UIAxes_Slice) && isvalid(app.UIAxes_Slice)
                delete(app.UIAxes_Slice);
            end
            if ~isempty(app.UIViewer_Vol) && isvalid(app.UIViewer_Vol)
                delete(app.UIViewer_Vol);
            end
        end

        % player components: viewer window
        function DrawCurrentSlice(app)
            if ~isempty(app.PDVs.CurrentImageSlice)
                % primary version for fast rendering
                % fill the new image
                set(app.PDVs.ImageHandle,'CData',app.PDVs.CurrentImageSlice);

                % update image immediately
                % limit as 20 fps
                drawnow limitrate nocallbacks
            end
        end

        function DrawCurrentVolume(app)
            if ~isempty(app.PDVs.CurrentImageVolume)
                % input data and review
                app.PDVs.VolumeHandle.Data = app.PDVs.CurrentImageVolume;

                % update image immediately
                drawnow nocallbacks
            end
        end

        % player components: viewer title
        function UpdateViewerTitle(app, cursorPos, grayVal)
            if ~exist("cursorPos","var") || isempty(cursorPos)
                cursorPos = 'None';
            else
                cursorPos = ['(',num2str(cursorPos(1)),',',...
                    num2str(cursorPos(2)),')'];
            end
            if ~exist("grayVal","var") || isempty(grayVal)
                grayVal = 'None';
            else
                grayVal = num2str(grayVal);
            end

            % auto generated by exist uROI
            if isempty(app.PDVs.UROIHandle) || ~isvalid(app.PDVs.UROIHandle)
                roi = "None";
            else
                R = ROI2Rectangle(app.PDVs.UROIHandle);
                roi = diff(ceil(R), 1, 1) + 1;
                roi = ['w = ',num2str(roi(1)),', h = ',num2str(roi(2))];
            end

            app.Label_ImageInfo.Text = "Cursor: " + cursorPos + ...
                ",   Value: " + grayVal + ...
                ",   Time: "  + sprintf("%.1f", app.Controller.ProjectManager.Project.img_cur.Time(app.Controller.ProjectManager.Project.fidx_cur)) + " s" + ...
                ",   ROI: "   + roi;
        end

        function RemoveViewerTitle(app)
            app.Label_ImageInfo.Text = "";
        end

        % player components: dynamic add merge channel
        function AppendMergeChannelMenu(app, state)
            arguments
                app 
                state   (1,1)   string  {mustBeMember(state, ["on", "off"])} = "off" 
            end
            % construct the parent menu
            app.Menu_MergeSource = uimenu(app.ContextMenu_Player);
            app.Menu_MergeSource.Enable = state;
            app.Menu_MergeSource.Text = app.PIVs.RegionConfig.chmap.MERGE_SOURCE;

            % construct the children menu
            %
            app.Menu_MergeRB = uimenu(app.Menu_MergeSource);
            app.Menu_MergeRB.MenuSelectedFcn = createCallbackFcn(app, @Menu_MergeRBSelected, true);
            app.Menu_MergeRB.Enable = "on";
            app.Menu_MergeRB.Text = app.PIVs.RegionConfig.chmap.RED_CYAN;
            app.Menu_MergeRB.Checked = "on";
            %
            app.Menu_MergeGR = uimenu(app.Menu_MergeSource);
            app.Menu_MergeGR.MenuSelectedFcn = createCallbackFcn(app, @Menu_MergeGRSelected, true);
            app.Menu_MergeGR.Enable = "on";
            app.Menu_MergeGR.Text = app.PIVs.RegionConfig.chmap.GREEN_MAGENTA;
            app.Menu_MergeGR.Checked = "off";
        end

        % player components: dynamic remove merge channel
        function RemoveMergeChannelMenu(app)
            if ~isempty(app.Menu_MergeSource) ...
                    && isvalid(app.Menu_MergeSource)
                delete(app.Menu_MergeSource);
            end
        end

        % player components: dynamic add color channel
        function AppendColorChannelMenu(app)
            % append red channel menu
            if ismember("r", app.Controller.ProjectManager.Project.c_order)
                app.Menu_Red = uimenu(app.Menu_Channel);
                app.Menu_Red.MenuSelectedFcn = createCallbackFcn(app, @Menu_RedSelected, true);
                app.Menu_Red.Enable = "on";
                app.Menu_Red.Text = app.PIVs.RegionConfig.chmap.RED;
                app.Menu_Red.Checked = "off";
            end
            
            % append green channel menu
            if ismember("g", app.Controller.ProjectManager.Project.c_order)
                app.Menu_Green = uimenu(app.Menu_Channel);
                app.Menu_Green.MenuSelectedFcn = createCallbackFcn(app, @Menu_GreenSelected, true);
                app.Menu_Green.Enable = "on";
                app.Menu_Green.Text = app.PIVs.RegionConfig.chmap.GREEN;
                app.Menu_Green.Checked = "off";
            end

            % append merged channel menu
            if all(ismember(["r","g"], app.Controller.ProjectManager.Project.c_order))
                app.Menu_Red_Green = uimenu(app.Menu_Channel);
                app.Menu_Red_Green.MenuSelectedFcn = createCallbackFcn(app, @Menu_Red_GreenSelected, true);
                switch app.PDVs.DisplaySource
                    case constdef.SOURCE_MERGE_NORMAL
                        app.Menu_Red_Green.Enable = "on";
                    case constdef.SOURCE_MERGE_FUSED
                        app.Menu_Red_Green.Enable = "off";
                    otherwise
                        app.Menu_Red_Green.Enable = "off";
                end
                app.Menu_Red_Green.Text = app.PIVs.RegionConfig.chmap.RED_GREEN;
                app.Menu_Red_Green.Checked = "off";
            end

            c_cur = app.Controller.ProjectManager.Project.c_order(app.Controller.ProjectManager.Project.cidx_cur);
            if isscalar(c_cur)
                switch c_cur
                    case "r"
                        app.Menu_Red.Checked = "on";
                    case "g"
                        app.Menu_Green.Checked = "on";
                    otherwise
                end
            elseif numel(c_cur) == 2
                app.Menu_Red_Green.Checked = "on";
            else
                error(app.PIVs.RegionConfig.chmap.UNSUPPORTED_CHANNEL_NUMBER);
            end
        end

        % player components: dynamic remove color channel
        function RemoveColorChannelMenu(app)
            if ~isempty(app.Menu_Red) && isvalid(app.Menu_Red)
                delete(app.Menu_Red);
            end
            if ~isempty(app.Menu_Green) && isvalid(app.Menu_Green)
                delete(app.Menu_Green);
            end
            if ~isempty(app.Menu_Red_Green) && isvalid(app.Menu_Red_Green)
                delete(app.Menu_Red_Green);
            end
        end

        % player components: dynamic add cell management menu
        function AppendCellManagerMenu(app)
            % construct the parent menu
            app.Menu_CellManager = uimenu(app.ContextMenu_Player);
            app.Menu_CellManager.Text = app.PIVs.RegionConfig.chmap.CELL_MANAGEMENT;

            % construct the children menu
            % [Add Cell]
            app.Menu_AddCell = uimenu(app.Menu_CellManager);
            app.Menu_AddCell.MenuSelectedFcn = createCallbackFcn(app, @Menu_AddCellSelected, true);
            app.Menu_AddCell.Accelerator = 'Q';
            app.Menu_AddCell.Text = app.PIVs.RegionConfig.chmap.ADD_CELL;
            % [Refresh Label]
            app.Menu_RefreshLabel = uimenu(app.Menu_CellManager);
            app.Menu_RefreshLabel.MenuSelectedFcn = createCallbackFcn(app, @Menu_RefreshLabelSelected, true);
            app.Menu_RefreshLabel.Text = app.PIVs.RegionConfig.chmap.REFRESH_LABEL;
            % [Anomaly Detection]
            app.Menu_AnomalyDetection = uimenu(app.Menu_CellManager);
            app.Menu_AnomalyDetection.MenuSelectedFcn = createCallbackFcn(app, @Menu_AnomalyDetectionSelected, true);
            app.Menu_AnomalyDetection.Text = app.PIVs.RegionConfig.chmap.ANOMALY_DETECTION;
        end

        % player components: dynamic remove cell management menu
        function RemoveCellManagerMenu(app)
            if ~isempty(app.Menu_CellManager) && isvalid(app.Menu_CellManager)
                delete(app.Menu_CellManager);
            end
        end

        %==================== CONTROL PANEL SETTING ======================

        function ClearTextArea_Log(app)
            % clear log buffer
            if ~isempty(app.TextArea_Log.Value)
                app.TextArea_Log.Value = "";
            end
        end

        function ExportTextArea_Log(app, path)
            t = datetime('now','TimeZone','local','Format','yyMMdd_HHmmss');
            fname = fullfile(path, ['log_',char(t), char(constdef.HLOG_FILE_EXT)]);
            writecell(app.TextArea_Log.Value, fname);
        end

        %====================== INDICATE SET/GET =========================
        function timeMsg = AppendTextArea_Log(app, msg, usingTimeStamp)
            if app.Switch_Log.Value == "off"
                return;
            end

            if ~exist('usingTimeStamp','var')
                usingTimeStamp = false;
            end

            if usingTimeStamp == true
                % Append message on text area
                timeMsg =string(datetime("now")).char();
                Msg = [timeMsg, ' ', char(msg)];
            else
                timeMsg = '';
                Msg = char(msg);
            end
            if isempty(app.TextArea_Log.Value{1})
                app.TextArea_Log.Value={Msg};
            else
                app.TextArea_Log.Value = [app.TextArea_Log.Value;{Msg}];
            end

            % scroll to bottom auto
            scroll(app.TextArea_Log, "bottom");
        end

        %========================== DATA UPDATE ==========================

        function status = DataConstructor(app, mov, opts, rt)
            % This function load data to app workspace
            % =============== VALIDATE DATA FORMATION ================
            if isempty(opts) || isempty(mov), status = -1; return; end

            if isempty(rt), rt = 1:opts.frames; end

            if opts.frames < 2
                % ERROR: display at anytime
                uialert(app.Reg3DV500WittgensteinUIFigure,...
                    app.PIVs.RegionConfig.chmap.UNSUPPORTED_FEW_FRAMES,...
                    app.PIVs.RegionConfig.chmap.ERROR,"Icon","error");
                status = -2;
                return;
            end

            if opts.slices < 5
                % ERROR: display at anytime
                uialert(app.Reg3DV500WittgensteinUIFigure,...
                    app.PIVs.RegionConfig.chmap.UNSUPPORTED_FEW_LAYERS,...
                    app.PIVs.RegionConfig.chmap.ERROR,"Icon","error");
                status = -3;
                return;
            end

            % =========================================================
            % reset the data in memory
            PanelWaiting(app);  %

            app.Controller.ProjectManager.Project.img_cur = regmov(mov, opts, rt);

            % set data loading flag
            app.Controller.ProjectManager.Project.loaded_flag = true;

            % init start volume at channel 1
            app.Controller.ProjectManager.Project.c_order = opts.cOrder;
            app.Controller.ProjectManager.Project.cidx_cur = false(1, app.Controller.ProjectManager.Project.img_cur.MetaData.channels);
            app.Controller.ProjectManager.Project.cidx_cur(1) = true;
            switch app.Controller.ProjectManager.Project.c_order(1)
                case "r"
                    app.Controller.ProjectManager.Project.pcolor.normal = constdef.COLOR_IMAGING_RED;
                case "g"
                    app.Controller.ProjectManager.Project.pcolor.normal = constdef.COLOR_IMAGING_GREEN;
                otherwise
            end

            % init view parameters
            app.Controller.ProjectManager.Project.sidx_cur = 1;
            app.Controller.ProjectManager.Project.fidx_cur = 1;
            app.Controller.ProjectManager.Project.opt_crop.xy = [1, 1; ...
                app.Controller.ProjectManager.Project.img_cur.MetaData.width, app.Controller.ProjectManager.Project.img_cur.MetaData.height];
            app.Controller.ProjectManager.Project.opt_crop.f = 1:app.Controller.ProjectManager.Project.img_cur.MetaData.frames;
            app.Controller.ProjectManager.Project.opt_crop.z = [1, app.Controller.ProjectManager.Project.img_cur.MetaData.slices];
            app.Controller.ProjectManager.Project.fps.base = opts.frames/(rt(end)-rt(1)); % real time fps
            app.Controller.ProjectManager.Project.fps.fold = 1;                           % play speed fold

            % init registration parameters
            app.Controller.ProjectManager.Project.fixdef = struct("Sampling", ["mean", "1"], ...
                    "Channel",  app.Controller.ProjectManager.Project.c_order(1));

            app.Controller.ProjectManager.Project.frames_reg = ["1:end", "1"];

            UpdateRegOptionsAfterLoading(app);

            % update player timer
            UpdatePlayerTimer(app, opts.frames, app.Controller.ProjectManager.Project.fps.base);

            PanelRecovered(app);

            status = 0;
        end

        function Free(app)
            %% close project
            % reset the locker
            app.PIVs.ViewerLock = true;
            pause(0.1);

            % close project if it's exist
            if ~isempty(fieldnames(app.Controller)) ...
                    && ~isempty(app.Controller.ProjectManager.Project)
                CloseProject(app);
            end

            %% delete app
            ud = uiprogressdlg(app.Reg3DV500WittgensteinUIFigure, ...
                "Title",app.PIVs.RegionConfig.chmap.INFORMATION, ...
                "Message",app.PIVs.RegionConfig.chmap.EXIT_FREE, ...
                "Indeterminate","on");

            % close all sub handles
            CloseAllSubApp(app);

            % remove controller
            DeactivateAppController(app);

            % remove movie player control
            if ~isempty(app.PDVs.MoviePlayerTimer)
                stop(app.PDVs.MoviePlayerTimer);
                delete(app.PDVs.MoviePlayerTimer);
            end

            % remove ROIs
            delete(app.PDVs.UROIHandle);

            % remove scale bar
            delete(app.PDVs.ScaleBar);

            
            % unload external library
            Register.UnloadExternalLib();

            % recover recycle status
            recycle(app.PIVs.PreviousRecycleStatus);

            delete(ud);

            % delete this at last
            delete(app);
        end

        function CloseApp(app)
            % question dialog to avoid inadvertent operation
            slt = uiconfirm(app.Reg3DV500WittgensteinUIFigure, app.PIVs.RegionConfig.chmap.CLOSE_APP_CONFIRMATION, ...
                app.PIVs.RegionConfig.chmap.NOTIFICATION, "Icon","question","DefaultOption","Cancel");

            switch slt
                case "OK"
                    % finish the update setting
                    is_update = app.PIVs.UpdateOptions.is_update;

                    % free this app
                    Free(app);

                    % check the update option
                    if is_update == true
                        run(fullfile(userpath, "auto_update.m"));
                        delete(fullfile(userpath, "auto_update.m"));
                    end
                otherwise
                    % skip
            end
        end

        function UpdateCurrentSlice(app, dview, shift)
            % This function update the current slice: app.sliceimg_cur for
            % viewing, where the data type is m*n*3 true color uint16 image
            % if loading the data info
            arguments
                app
                dview   (1,1)   string  {mustBeMember(dview, ["XY", "Z","T"])} = "XY"
                shift   (1,3)   double = [0, 0, 0]      % dx, dy, da
            end
            
            if app.Controller.ProjectManager.Project.loaded_flag == true
                if any(shift ~= [0, 0, 0])
                    % structured channel as slice_s, functional channel as
                    % slice_f
                    if app.PDVs.ZProjectionMode == "none"
                        if ~isempty(app.Controller.ProjectManager.Project.img_cur)
                            [~, cidx] = ismember(app.Controller.ProjectManager.Project.opt_reg.Options.FC, ...
                                app.Controller.ProjectManager.Project.img_cur.MetaData.cOrder);
                            slice_f = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, cidx, ...
                                app.Controller.ProjectManager.Project.sidx_cur, app.Controller.ProjectManager.Project.fidx_cur);
                            slice_f = TransformBCG(slice_f, app.PDVs.GraphicsOptions);
                            [~, cidx] = ismember(app.Controller.ProjectManager.Project.opt_reg.Options.SC, ...
                                app.Controller.ProjectManager.Project.img_cur.MetaData.cOrder);
                            slice_s = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, cidx, ...
                                app.Controller.ProjectManager.Project.sidx_cur, app.Controller.ProjectManager.Project.fidx_cur);
                            slice_s = TransformBCG(slice_s, app.PDVs.GraphicsOptions);
                        end
                    else
                        if ~isempty(app.Controller.ProjectManager.Project.img_zproj.cur)
                            [~, cidx] = ismember(app.Controller.ProjectManager.Project.opt_reg.Options.FC, ...
                                app.Controller.ProjectManager.Project.img_cur.MetaData.cOrder);
                            slice_f = app.Controller.ProjectManager.Project.img_zproj.cur(:, :, ...
                                cidx, app.Controller.ProjectManager.Project.fidx_cur);
                            slice_f = TransformBCG(slice_f, app.PDVs.GraphicsOptions);
                            [~, cidx] = ismember(app.Controller.ProjectManager.Project.opt_reg.Options.SC, ...
                                app.Controller.ProjectManager.Project.img_cur.MetaData.cOrder);
                            slice_s = app.Controller.ProjectManager.Project.img_zproj.cur(:, :, ...
                                cidx, app.Controller.ProjectManager.Project.fidx_cur);
                            slice_s = TransformBCG(slice_s, app.PDVs.GraphicsOptions);
                        end
                    end
                    % do imwarp for slice_s motion
                    rot = [cosd(shift(3)),  sind(shift(3)); ...
                           -sind(shift(3)), cosd(shift(3))];
                    RA = imref2d(size(slice_f), app.Controller.ProjectManager.Project.img_cur.MetaData.xRes, ...
                                                app.Controller.ProjectManager.Project.img_cur.MetaData.yRes);
                    tform = rigid2d(rot, shift(1:2));
                    slice_f_tf = imwarp(slice_f, RA, tform, "linear", ...
                        "FillValues",prctile(slice_f, 5, "all"), ...
                        "OutputView", RA);
                    switch app.Controller.ProjectManager.Project.opt_reg.Options.FC
                        case "g"
                            slice_cur = CompositeC(slice_s, slice_f_tf, [], ...
                                app.Controller.ProjectManager.Project.pcolor.normal);
                        case "r"
                            slice_cur = CompositeC(slice_f_tf, slice_s, [], ...
                                app.Controller.ProjectManager.Project.pcolor.normal);
                        otherwise
                    end
                else % if no shift
                    switch app.PDVs.DisplaySource
                        case constdef.SOURCE_MERGE_NORMAL
                            % only normal image need view
                            if all(app.Controller.ProjectManager.Project.c_order(app.Controller.ProjectManager.Project.cidx_cur)=="r")
                                % red view
                                if app.PDVs.ZProjectionMode == "none"
                                    slice_s = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, ...
                                        app.Controller.ProjectManager.Project.cidx_cur, app.Controller.ProjectManager.Project.sidx_cur, app.Controller.ProjectManager.Project.fidx_cur);
                                else
                                    slice_s = app.Controller.ProjectManager.Project.img_zproj.cur(:, :, ...
                                        app.Controller.ProjectManager.Project.cidx_cur, app.Controller.ProjectManager.Project.fidx_cur);
                                end
                                slice_s = TransformBCG(slice_s, app.PDVs.GraphicsOptions);
                                slice_f = [];
                                slice_b = [];
                            elseif all(app.Controller.ProjectManager.Project.c_order(app.Controller.ProjectManager.Project.cidx_cur)=="g")
                                % green view
                                if app.PDVs.ZProjectionMode == "none"
                                    slice_f = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, ...
                                        app.Controller.ProjectManager.Project.cidx_cur, app.Controller.ProjectManager.Project.sidx_cur, app.Controller.ProjectManager.Project.fidx_cur);
                                else
                                    slice_f = app.Controller.ProjectManager.Project.img_zproj.cur(:, :, ...
                                        app.Controller.ProjectManager.Project.cidx_cur, app.Controller.ProjectManager.Project.fidx_cur);
                                end
                                slice_s = [];
                                slice_f = TransformBCG(slice_f, app.PDVs.GraphicsOptions);
                                slice_b = [];
                            elseif all(app.Controller.ProjectManager.Project.c_order(app.Controller.ProjectManager.Project.cidx_cur)==["r","g"]) ...
                                    || all(app.Controller.ProjectManager.Project.c_order(app.Controller.ProjectManager.Project.cidx_cur)==["g","r"])
                                % merge dual color
                                if app.PDVs.ZProjectionMode == "none"
                                    slice_s = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, ...
                                        app.Controller.ProjectManager.Project.c_order=="r", app.Controller.ProjectManager.Project.sidx_cur, app.Controller.ProjectManager.Project.fidx_cur);
                                    slice_f = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, ...
                                        app.Controller.ProjectManager.Project.c_order=="g", app.Controller.ProjectManager.Project.sidx_cur, app.Controller.ProjectManager.Project.fidx_cur);
                                else
                                    slice_s = app.Controller.ProjectManager.Project.img_zproj.cur(:, :, ...
                                        app.Controller.ProjectManager.Project.c_order=="r", app.Controller.ProjectManager.Project.fidx_cur);
                                    slice_f = app.Controller.ProjectManager.Project.img_zproj.cur(:, :, ...
                                        app.Controller.ProjectManager.Project.c_order=="g", app.Controller.ProjectManager.Project.fidx_cur);
                                end
                                slice_s = TransformBCG(slice_s, app.PDVs.GraphicsOptions);
                                slice_f = TransformBCG(slice_f, app.PDVs.GraphicsOptions);
                                slice_b = [];
                            else
                            end
                            slice_cur = CompositeC(slice_s, slice_f, slice_b, ...
                                app.Controller.ProjectManager.Project.pcolor.normal);
                        case constdef.SOURCE_MERGE_FUSED
                            % fuse template image and current channel
                            if app.PDVs.ZProjectionMode == "none"
                                slice_s = app.Controller.ProjectManager.Project.refvol.RefVol(:, :, app.Controller.ProjectManager.Project.sidx_cur);
                                slice_f = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, app.Controller.ProjectManager.Project.cidx_cur, ...
                                    app.Controller.ProjectManager.Project.sidx_cur, app.Controller.ProjectManager.Project.fidx_cur);
                            else
                                slice_s = app.Controller.ProjectManager.Project.img_zproj.ref;
                                slice_f = app.Controller.ProjectManager.Project.img_zproj.cur(:, :, app.Controller.ProjectManager.Project.cidx_cur, ...
                                    app.Controller.ProjectManager.Project.fidx_cur);
                            end

                            slice_s = TransformBCG(slice_s, app.PDVs.GraphicsOptions);
                            slice_f = TransformBCG(slice_f, app.PDVs.GraphicsOptions);
                            slice_b = [];
                            slice_cur = CompositeC(slice_s, slice_f, slice_b, ...
                                app.Controller.ProjectManager.Project.pcolor.fused);
                        case constdef.SOURCE_MERGE_PREPROCESS
                            [~, cidx] = ismember(app.Controller.ProjectManager.Project.opt_reg.Options.SC, ...
                                app.Controller.ProjectManager.Project.img_cur.MetaData.cOrder);
                            vol = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, cidx, :, app.Controller.ProjectManager.Project.fidx_cur);
                            vol = squeeze(vol);

                            % real-time generation
                            if isempty(app.Controller.ProjectManager.Project.mimg_cur) || (dview=="T")
                                app.Controller.ProjectManager.Project.mimg_cur = GenPreProcVol(vol, app.Controller.ProjectManager.Project.opt_reg);
                            end

                            % take piece
                            if app.PDVs.ZProjectionMode == "none"
                                slice_s = app.Controller.ProjectManager.Project.mimg_cur(:, :, app.Controller.ProjectManager.Project.sidx_cur);
                            else
                                slice_s = Projection(app.Controller.ProjectManager.Project.mimg_cur, app.PDVs.ZProjectionMode, 3);
                            end
                            slice_s = TransformBCG(slice_s, app.PDVs.GraphicsOptions);
                            slice_cur = CompositeC(slice_s, slice_s, slice_s, ...
                                constdef.COLOR_IMAGIMG_GRAY);
                        otherwise
                    end 
                end

                app.PDVs.CurrentImageSlice = slice_cur;
            else
                app.PDVs.CurrentImageSlice = [];
            end
        end

        function UpdateCurrentVolume(app)
            % This function update the current volume: app.sliceimg_cur for
            % viewing, where the data type is m*n*p*3 true color uint16 image
            % if loading the data info
            if app.Controller.ProjectManager.Project.loaded_flag == true
                % calculate rescale resolution
                rs = min(app.Controller.ProjectManager.Project.img_cur.MetaData.xRes, app.Controller.ProjectManager.Project.img_cur.MetaData.yRes);
                sc_xyz = [app.Controller.ProjectManager.Project.img_cur.MetaData.xRes, app.Controller.ProjectManager.Project.img_cur.MetaData.yRes, ...
                    app.Controller.ProjectManager.Project.img_cur.MetaData.zRes]./rs;

                switch app.PDVs.DisplaySource
                    case constdef.SOURCE_MERGE_NORMAL
                        % only current image need view
                        if all(app.Controller.ProjectManager.Project.c_order(app.Controller.ProjectManager.Project.cidx_cur)=="r")
                            % red view
                            volume_r = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, ...
                                app.Controller.ProjectManager.Project.cidx_cur, :, app.Controller.ProjectManager.Project.fidx_cur);
                            volume_r = TransformBCG(volume_r, app.PDVs.GraphicsOptions);
                            % resize for better viewing effort
                            volume_r = imresize3(volume_r, "Scale", sc_xyz, ...
                                "Method","linear");
                            volume_g = [];
                            volume_b = [];
                        elseif all(app.Controller.ProjectManager.Project.c_order(app.Controller.ProjectManager.Project.cidx_cur)=="g")
                            % green view
                            volume_g = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, ...
                                app.Controller.ProjectManager.Project.cidx_cur, :, app.Controller.ProjectManager.Project.fidx_cur);
                            volume_r = [];
                            volume_g = TransformBCG(volume_g, app.PDVs.GraphicsOptions);
                            volume_g = imresize3(volume_g, "Scale", sc_xyz, ...
                                "Method","linear");
                            volume_b = [];
                        elseif all(app.Controller.ProjectManager.Project.c_order(app.Controller.ProjectManager.Project.cidx_cur)==["r","g"]) ...
                                || all(app.Controller.ProjectManager.Project.c_order(app.Controller.ProjectManager.Project.cidx_cur)==["g","r"])
                            % merge dual color
                            volume_r = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, ...
                                    app.Controller.ProjectManager.Project.c_order=="r", :, app.Controller.ProjectManager.Project.fidx_cur);
                                volume_g = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, ...
                                    app.Controller.ProjectManager.Project.c_order=="g", :, app.Controller.ProjectManager.Project.fidx_cur);
                            volume_r = TransformBCG(volume_r, app.PDVs.GraphicsOptions);
                            volume_r = imresize3(volume_r, "Scale", sc_xyz, ...
                                "Method","linear");
                            volume_g = TransformBCG(volume_g, app.PDVs.GraphicsOptions);
                            volume_g = imresize3(volume_g, "Scale", sc_xyz, ...
                                "Method","linear");
                            volume_b = [];
                        else
                        end
                        app.PDVs.CurrentImageVolume = CompositeC(volume_r, ...
                            volume_g, volume_b, app.Controller.ProjectManager.Project.pcolor.normal);
                    case constdef.SOURCE_MERGE_FUSED
                        volume_r = app.Controller.ProjectManager.Project.refvol.RefVol;
                        volume_g = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, app.Controller.ProjectManager.Project.cidx_cur, ...
                            :, app.Controller.ProjectManager.Project.fidx_cur);
                        volume_r = TransformBCG(volume_r, app.PDVs.GraphicsOptions);
                        volume_r = imresize3(volume_r, "Scale", sc_xyz, ...
                            "Method","linear");
                        volume_g = TransformBCG(volume_g, app.PDVs.GraphicsOptions);
                        volume_g = imresize3(volume_g, "Scale", sc_xyz, ...
                            "Method","linear");
                        volume_b = [];
                        app.PDVs.CurrentImageVolume = CompositeC(volume_r, ...
                            volume_g, volume_b, app.Controller.ProjectManager.Project.pcolor.fused);
                    case constdef.SOURCE_MERGE_PREPROCESS
                        [~, cidx] = ismember(app.Controller.ProjectManager.Project.opt_reg.Options.SC, ...
                            app.Controller.ProjectManager.Project.img_cur.MetaData.cOrder);
                        volume_r = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, cidx, :, app.Controller.ProjectManager.Project.fidx_cur);
                        volume_r = squeeze(volume_r);

                        % real-time generation
                        if isempty(app.Controller.ProjectManager.Project.mimg_cur) || ...
                                any(size(app.Controller.ProjectManager.Project.mimg_cur)~=size(volume_r))
                            app.Controller.ProjectManager.Project.mimg_cur = GenPreProcVol(volume_r, app.Controller.ProjectManager.Project.opt_reg);
                        end
                        volume_s = TransformBCG(app.Controller.ProjectManager.Project.mimg_cur, app.PDVs.GraphicsOptions);
                        volume_s = imresize3(volume_s, "Scale", sc_xyz, ...
                            "Method","linear");
                        app.PDVs.CurrentImageVolume = CompositeC(volume_s, ...
                            volume_s, volume_s, constdef.COLOR_IMAGIMG_GRAY);
                    otherwise
                end
            else
                app.PDVs.CurrentImageVolume = [];
            end
        end

        function UpdateMultiplyROIs(app, dview)
            if app.Controller.ProjectManager.Project.loaded_flag == true ...
                    && app.PDVs.MaskedFlag == true
                switch dview
                    case {'XY', 'Z'}
                        % most time occupied
                        % refresh only if cell segmentator exist because it
                        % holds the ROIs controller
                        if ~isempty(app.SUBAPPs.CS) && isvalid(app.SUBAPPs.CS)
                            app.SUBAPPs.CS.UpdateMultiplyROIs(app.Controller.ProjectManager.Project.sidx_cur);
                        end
                    otherwise
                end
            end
        end

        function DrawUtilsComponents(app, dview)
            if ~isempty(app.PDVs.CurrentImageSlice)
                % draw information text
                if ~isempty(app.PDVs.TimeTextObject.handle) ...
                        && isvalid(app.PDVs.TimeTextObject.handle)
                    app.PDVs.TimeTextObject.handle.String = ...
                        app.PDVs.TimeTextObject.text(app.Controller.ProjectManager.Project.fidx_cur);
                end
                if ~isempty(app.PDVs.TimeTextObject.handle) ...
                        && isvalid(app.PDVs.TimeTextObject.handle)
                    app.PDVs.TimeTextObject.handle.String = ...
                        app.PDVs.TimeTextObject.text{app.Controller.ProjectManager.Project.fidx_cur};
                end

                % ROI display supported only on 2D view mode
                if app.PDVs.DisplayViewMode == "2D"
                    % draw ROIs(multiply)
                    UpdateMultiplyROIs(app, dview);
                end
            end
        end

        function SourceZProjection(app, frame, method)
            % setup the z projection method
            if ~exist("method","var") || isempty(method)
                method = "max";
            else
                if method == "none"
                    % no update and quit immediately
                    return;
                end
            end

            %% much time causing block
            app.Controller.ProjectManager.Project.img_cur.ZProjMethod = method;

            % call z projection property
            if ~exist("frame","var") || isempty(frame)
                app.Controller.ProjectManager.Project.img_zproj.cur = app.Controller.ProjectManager.Project.img_cur.MovieZProj;
            else
                % increasing update
                app.Controller.ProjectManager.Project.img_zproj.cur(:,:,:,frame) = ...
                    app.Controller.ProjectManager.Project.img_cur.tcrop(frame).MovieZProj;
            end
            %%

            % update the refvol z projection
            app.Controller.ProjectManager.Project.img_zproj.ref = Projection(app.Controller.ProjectManager.Project.refvol.RefVol, method, 3);
        end

        function VolumePostureAdjustment(app, op)
            %note that flip does not change X or Y size, but rotate will
            switch op
                case "fliplr"
                    app.Controller.ProjectManager.Project.img_cur.fliplr();
                    if app.PDVs.ZProjectionMode ~= "none"
                        app.Controller.ProjectManager.Project.img_zproj.cur ...
                            = fliplr(app.Controller.ProjectManager.Project.img_zproj.cur);
                    end
                    app.Controller.ProjectManager.Project.img_zproj.ref ...
                        = fliplr(app.Controller.ProjectManager.Project.img_zproj.ref);
                case "flipud"
                    app.Controller.ProjectManager.Project.img_cur.flipud();
                    if app.PDVs.ZProjectionMode ~= "none"
                        app.Controller.ProjectManager.Project.img_zproj.cur ...
                            = flipud(app.Controller.ProjectManager.Project.img_zproj.cur);
                    end
                    app.Controller.ProjectManager.Project.img_zproj.ref ...
                        = flipud(app.Controller.ProjectManager.Project.img_zproj.ref);
                case "rot90"
                    app.Controller.ProjectManager.Project.img_cur.rot90();
                    if app.PDVs.ZProjectionMode ~= "none"
                        app.Controller.ProjectManager.Project.img_zproj.cur ...
                            = rot90(app.Controller.ProjectManager.Project.img_zproj.cur);
                    end
                    app.Controller.ProjectManager.Project.img_zproj.ref ...
                        = rot90(app.Controller.ProjectManager.Project.img_zproj.ref);
                case "-rot90"
                    app.Controller.ProjectManager.Project.img_cur.rot90(3);
                    if app.PDVs.ZProjectionMode ~= "none"
                        app.Controller.ProjectManager.Project.img_zproj.cur ...
                            = rot90(app.Controller.ProjectManager.Project.img_zproj.cur, 3);
                    end
                    app.Controller.ProjectManager.Project.img_zproj.ref ...
                        = rot90(app.Controller.ProjectManager.Project.img_zproj.ref, 3);
                otherwise
            end
        end

        function status = UpdateRegion(app, lang)
            if ismember(lang, ["zh_CN", "en_US"])
                app.PIVs.RegionConfig.lang = lang;
                app.PIVs.RegionConfig.chmap = readstruct(['lang', filesep, char(lang), ...
                    char(constdef.LANGUAGE_FILE_EXT)], ...
                    "FileType","xml");
                status = 0;
            else
                status = -1;
            end
        end

        function r = RegisterFrameAdapter(app)
            % This adapter translate the reg-frame to kernel inner format
            switch app.Controller.ProjectManager.Project.opt_reg.Algorithm
                case "MANREG"
                    r = str2double(app.Controller.ProjectManager.Project.frames_reg(2));
                otherwise
                    r = app.Controller.ProjectManager.Project.frames_reg(1).replace("end", ...
                        string(app.Controller.ProjectManager.Project.img_cur.MetaData.frames));
                    r = str2num(r); %#ok<ST2NM>
            end
        end

        function val = GetPixelValueAt(app, x, y)
            arguments
                app (1,1)   Register
                x   (1,1)   double  {mustBeInteger, mustBePositive}
                y   (1,1)   double  {mustBeInteger, mustBePositive}
            end

            if app.PDVs.ZProjectionMode == "none"
                val = app.Controller.ProjectManager.Project.img_cur.Movie(y, x, app.Controller.ProjectManager.Project.cidx_cur,...
                    app.Controller.ProjectManager.Project.sidx_cur, app.Controller.ProjectManager.Project.fidx_cur);
            else
                val = app.Controller.ProjectManager.Project.img_zproj.cur(y, x, app.Controller.ProjectManager.Project.cidx_cur, app.Controller.ProjectManager.Project.fidx_cur);
            end
        end

        function slice_img = GetSliceImgAt(app, z, t)
            arguments
                app (1,1) Register
                z   (1,1) double    = app.Controller.ProjectManager.Project.sidx_cur
                t   (1,1) double    = app.Controller.ProjectManager.Project.fidx_cur
            end

            if app.PDVs.ZProjectionMode == "none"
                slice_img = app.Controller.ProjectManager.Project.img_cur.Movie(:, :, app.Controller.ProjectManager.Project.cidx_cur, z, t);
            else
                slice_img = app.Controller.ProjectManager.Project.img_zproj.cur(:, :, app.Controller.ProjectManager.Project.cidx_cur, t);
            end
        end

        function volume_img = GetVolumeImgAt(app, t)
            arguments
                app (1,1)   Register
                t   (1,1)   double      = app.Controller.ProjectManager.Project.fidx_cur
            end

            volume_img = squeeze(app.Controller.ProjectManager.Project.img_cur.Movie(:, :, app.Controller.ProjectManager.Project.cidx_cur, :, t));
        end
        
        %================= USER INTERFSACE OPTIMIZATION ==================
        
        function PanelWaiting(app)
            % set the pointer for better visual effort
            set(app.Reg3DV500WittgensteinUIFigure,'Pointer','watch');
            focus(app.Reg3DV500WittgensteinUIFigure);
            drawnow
        end

        function PanelRecovered(app)
            % set the pointer back
            set(app.Reg3DV500WittgensteinUIFigure,'Pointer','arrow');
            focus(app.Reg3DV500WittgensteinUIFigure);
            drawnow
        end

        %==================== SYSTEM PRESET SET/GET ======================
       
        function SavePreset(app,filename)
            % generate a struct contain part of stable variables as preset
            preset_save = app.Controller.ProjectManager.Project.opt_reg.Options;
            preset_save.alg = app.Controller.ProjectManager.Project.opt_reg.Algorithm;
            preset_save.mode = app.Controller.ProjectManager.Project.opt_reg.Mode;
            writestruct(preset_save,filename,"FileType","xml");
            GenSysMsg(app, ["CONFIGURATION_EXPORTED",""]);
        end

        function preset_load = LoadPreset(app, filename)
            preset_load = readstruct(filename,"FileType","xml");

            % check the fields
            fields_name = fieldnames(preset_load);
            reg_fieldsname = fieldnames(app.Controller.ProjectManager.Project.opt_reg.Options);
            fields_apdx = {'alg'; 'mode'};
            if all(ismember(fields_name,union(reg_fieldsname,fields_apdx)))...
                    && numel(fields_name) == numel(reg_fieldsname) + numel(fields_apdx)
                % change the algorithm and mode at first
                app.Controller.ProjectManager.Project.opt_reg.Algorithm = preset_load.alg;
                app.Controller.ProjectManager.Project.opt_reg.Mode = preset_load.mode;

                % append other parameters
                for k = 1:numel(reg_fieldsname)
                    if strcmp(reg_fieldsname{k}, 'Hardware') == true
                        % skip hardware information(hardware independent)
                        continue;
                    end
                    app.Controller.ProjectManager.Project.opt_reg = set(app.Controller.ProjectManager.Project.opt_reg, reg_fieldsname{k}, ...
                        preset_load.(reg_fieldsname{k}));
                end
                
                GenSysMsg(app, ["CONFGURATION_LOADED",""]);
            else
                % ERROR: display at anytime
                uialert(app.Reg3DV500WittgensteinUIFigure, ...
                    app.PIVs.RegionConfig.CONFIGURATION_CORRUPTED,...
                    app.PIVs.RegionConfig.ERROR, "Icon","error");
                preset_load = [];
                return;
            end
        end

        %===================== SYSTEM CONTROLLER =========================

        function UpdateSystemAfterLoading(app)
            % update panels status
            UpdateAppearanceAfterLoading(app);

            % update viewer
            InitViewer(app);

            % refresh the slice viewer display and mode selection
            switch app.PDVs.DisplayViewMode
                case "2D"
                    SetSlider_Slice(app, 'on');
                    app.Menu_View2D.Checked = "on";
                    app.Menu_View3D.Checked = "off";
                case "3D"
                    SetSlider_Slice(app, 'freeze');
                    app.Menu_View3D.Checked = "on";
                    app.Menu_View2D.Checked = "off";
                otherwise
            end

            SetSlider_Time(app, [1, app.Controller.ProjectManager.Project.img_cur.MetaData.frames]);
            
            % set display options
            app.PDVs.DisplaySource = constdef.SOURCE_MERGE_NORMAL;

            RefreshViewer(app);
        end

        function UpdateAppearanceBeforeRegistration(app)
            app.Menu_File.Enable = "off";
            app.Menu_Image.Enable = "off";
            app.Menu_Analyze.Enable = "off";
            app.Spinner_Slice.Enable = "off";
            app.Slider_Slice.Enable = "off";
            app.Spinner_Time.Enable = "off";
            app.Slider_Time.Enable = "off";
            app.DropDown_Source.Enable = "off";
            app.Menu_RectangleROI.Enable = "off";
            app.Menu_CircleROI.Enable = "off";
            app.Menu_PolygonROI.Enable = "off";
            app.Menu_FreehandROI.Enable = "off";
            app.Menu_ExportLog.Enable = "off";
            app.Menu_ClearLog.Enable = "off";
            app.TextArea_Log.Enable = "off";
            app.Switch_Log.Enable = "off";
            app.Tree_Operations.Enable = "off";
            app.TextArea_OperationSnapshot.Enable = "off";

            UpdateYScrollLim(app);
            
            drawnow
        end

        function UpdateAppearanceAfterRegistration(app)
            app.Menu_File.Enable = "on";
            app.Menu_Image.Enable = "on";
            app.Menu_Analyze.Enable = "on";
            app.Menu_RegEvaluate.Enable = "on";
            if app.PDVs.ZProjectionMode == "none"
                app.Spinner_Slice.Enable = "on";
                app.Slider_Slice.Enable = "on";
            end
            app.Spinner_Time.Enable = "on";
            app.Slider_Time.Enable = "on";
            app.DropDown_Source.Enable = "on";
            app.Menu_RectangleROI.Enable = "on";
            app.Menu_CircleROI.Enable = "on";
            app.Menu_PolygonROI.Enable = "on";
            app.Menu_FreehandROI.Enable = "on";
            app.Menu_ExportLog.Enable = "on";
            app.Menu_ClearLog.Enable = "on";
            app.TextArea_Log.Enable = "on";
            app.Switch_Log.Enable = "on";
            app.Tree_Operations.Enable = "on";
            app.TextArea_OperationSnapshot.Enable = "on";

            UpdateYScrollLim(app);

            drawnow
        end

        %%
        function CloseSubApp(app, h_name) %#ok<INUSD>
            h = eval("app.SUBAPPs."+h_name+";");

            if ~isempty(h) && isvalid(h), h.Close(); end

            eval("app.SUBAPPs."+h_name+" = [];");
        end

        function CloseAllSubApp(app)
            % close register sub apps
            for name = app.SUBAPP_SNAME
                CloseSubApp(app, name);
            end

            % close plugins
            CloseAllPlugins(app);
        end

        function CloseAllPlugins(app)
            for k = 1:numel(app.SUBAPPs.CP)
                if ~isempty(app.SUBAPPs.CP{k}) && isvalid(app.SUBAPPs.CP{k})
                    try
                        if ismethod(app.SUBAPPs.CP{k}, constdef.SUBAPP_CLOSE_IMPLEMENT_NAME)
                            app.SUBAPPs.CP{k}.(constdef.SUBAPP_CLOSE_IMPLEMENT_NAME);
                        else
                            delete(app.SUBAPPs.CP{k});
                        end
                    catch ME
                        app.Controller.Logger.Push("Register", "Close_Plugin_" ...
                            + app.PIVs.PluginConfigs(k).identifier, ME.identifier);
                    end
                end
            end
        end

        function CloseDataRelatedSubApp(app)
            for name = app.SUBAPP_SNAME_DATA_RELATED
                CloseSubApp(app, name);
            end
        end

        %%

        function RefreshSubAppLanguage(app, h_name) %#ok<INUSD>
            h = eval("app.SUBAPPs."+h_name+";");

            if ~isempty(h) && isvalid(h)
                h.RefreshPanelLanguage();
            end
        end

        function RefreshAllSubAppLanguage(app)
            % refresh static objects
            app.StorageManager.RefreshPanelLanguage();

            % refresh dynamic objects
            for name = app.SUBAPP_SNAME
                RefreshSubAppLanguage(app, name);
            end

            % refresh plugins app language
            RefreshAllPluginsLanguage(app);
        end

        function RefreshAllPluginsLanguage(app)
            for k = 1:numel(app.SUBAPPs.CP)
                if ~isempty(app.SUBAPPs.CP{k}) && isvalid(app.SUBAPPs.CP{k})
                    % validate if update language could be called
                    if ismethod(app.SUBAPPs.CP{k}, ...
                            constdef.SUBAPP_UPDATE_LANGUAGE_IMPLEMENT_NAME)
                        app.SUBAPPs.CP{k}.(constdef.SUBAPP_UPDATE_LANGUAGE_IMPLEMENT_NAME);
                    end
                end
            end
        end

        %%

        function RefreshSubAppStyle(app, h_name) %#ok<INUSD>
            h = eval("app.SUBAPPs."+h_name+";");

            if ~isempty(h) && isvalid(h)
                h.RefreshPanelStyle();
            end
        end

        function RefreshAllSubAppStyle(app)
            % refresh static objects
            app.StorageManager.RefreshPanelStyle();

            % refresh dynamic objects
            for name = app.SUBAPP_SNAME
                RefreshSubAppStyle(app, name);
            end

            % refresh plugins app style
            RefreshAllPluginsStyle(app);
        end

        function RefreshAllPluginsStyle(app)
            for k = 1:numel(app.SUBAPPs.CP)
                if ~isempty(app.SUBAPPs.CP{k}) && isvalid(app.SUBAPPs.CP{k})
                    % validate if update style could be called
                    if ismethod(app.SUBAPPs.CP{k}, ...
                            constdef.SUBAPP_UPDATE_STYLE_IMPLEMENT_NAME)
                        app.SUBAPPs.CP{k}.(constdef.SUBAPP_UPDATE_STYLE_IMPLEMENT_NAME);
                    end
                end
            end
        end

        %%

        function RefreshSubAppTips(app, h_name) %#ok<INUSD>
            h = eval("app.SUBAPPs."+h_name+";");

            if ~isempty(h) && isvalid(h)
                h.RefreshTooltips();
            end
        end
        
        function RefreshAllSubAppTooltips(app)

            % refresh dynamic objects
            for name = app.SUBAPP_SNAME_TIPS_RELATED
                RefreshSubAppTips(app, name);
            end

            % refresh plugins app tooltips
            RefreshAllPluginsTooltips(app);
        end

        function RefreshAllPluginsTooltips(app)
            for k = 1:numel(app.SUBAPPs.CP)
                if ~isempty(app.SUBAPPs.CP{k}) && isvalid(app.SUBAPPs.CP{k})
                    % validate if update tooltips could be called
                    if ismethod(app.SUBAPPs.CP{k}, ...
                            constdef.SUBAPP_UPDATE_TOOLTIP_IMPLEMENT_NAME)
                        app.SUBAPPs.CP{k}.(constdef.SUBAPP_UPDATE_TOOLTIP_IMPLEMENT_NAME);
                    end
                end
            end
        end

        %%

        function RefreshSubAppMessageLevel(app, h_name) %#ok<INUSD>
            h = eval("app.SUBAPPs."+h_name+";");

            if ~isempty(h) && isvalid(h)
                h.RefreshMessageLevel();
            end
        end

        function RefreshAllSubAppMessageLevel(app)

            % refresh dynamic objects
            for name = app.SUBAPP_SNAME_MESSAGE_RELATED
                RefreshSubAppMessageLevel(app, name);
            end

            % refresh plugins app message level
            RefreshAllPluginsMessageLevel(app);
        end

        function RefreshAllPluginsMessageLevel(app)
            for k = 1:numel(app.SUBAPPs.CP)
                if ~isempty(app.SUBAPPs.CP{k}) && isvalid(app.SUBAPPs.CP{k})
                    % validate if update message level could be called
                    if ismethod(app.SUBAPPs.CP{k}, ...
                            constdef.SUBAPP_UPDATE_MESSAGE_IMPLEMENT_NAME)
                        app.SUBAPPs.CP{k}.(constdef.SUBAPP_UPDATE_MESSAGE_IMPLEMENT_NAME);
                    end
                end
            end
        end

        %%

        function RegisterGo(app)
            %% Adjust if operation is valid (space limitation)
            tf = app.Controller.ProjectManager.OperationManager.isaddable(constdef.OP_REGISTER);

            if tf == false
                % restore button state
                app.Button_Run.Value = false;

                % ERROR: display at any time
                uialert(app.Reg3DV500WittgensteinUIFigure, ...
                    app.PIVs.RegionConfig.chmap.STORAGE_INSUFFICIENT, ...
                    app.PIVs.RegionConfig.chmap.ERROR, "Icon","error","Modal",true);
                return;
            end

            %%
            % update button status
            app.Button_Run.BackgroundColor = [0.4, 1, 0];
            app.Button_Run.Text = app.PIVs.RegionConfig.chmap.IN_PROGRESS;
            drawnow

            % update controller arguments
            app.Controller.RegisterKernel.RegisterOptions = app.Controller.ProjectManager.Project.opt_reg;
            app.Controller.RegisterKernel.Distributed = app.Controller.ServerFlag;
            regfr = RegisterFrameAdapter(app);

            % lock Panels
            CloseSubApp(app, "RS");     % close setting panel
            RemovePreprocessSource(app);
            UpdateAppearanceBeforeRegistration(app);
            
            % generate new image as destination 
            img_reg = app.Controller.ProjectManager.Project.img_cur.copy();   % deep copy

            % update template and run
            app.Controller.ProjectManager.Project.refvol = regtmpl(app.Controller.ProjectManager.Project.img_cur, app.Controller.ProjectManager.Project.fixdef);

            % !!! take system snapshot before registration
            [args, others] = app.TakeSystemBasicSnap();

            app.PIVs.ViewerLock = true;     % lock display callbacks
            app.Controller.RegisterKernel.run(app.Controller.ProjectManager.Project.img_cur, img_reg, app.Controller.ProjectManager.Project.refvol, regfr);
            
            % add history node
            app.Controller.ProjectManager.OperationManager.AddNode([], constdef.OP_REGISTER, args, ...
                "Data",img_reg, "Transform",img_reg.Transformation, ...
                "Others",others);
            app.Controller.ProjectManager.Project.img_cur = app.Controller.ProjectManager.OperationManager.CurrentImagePtr;

            % Synchronize z projection
            PanelWaiting(app);
            SourceZProjection(app, [], app.PDVs.ZProjectionMode);
            PanelRecovered(app);

            app.PIVs.ViewerLock = false;      % unlock display callbacks

            % append the merge menu
            if isempty(app.Menu_MergeSource) ...
                    || ~isvalid(app.Menu_MergeSource)
                app.Controller.ProjectManager.Project.pcolor.fused = constdef.COLOR_IMAGING_RED_CYAN;
                AppendMergeChannelMenu(app, "off");
            end

            RefreshViewer(app);

            % update main panel appearance
            app.Button_Run.Value = false;
            app.Button_Run.Text = app.PIVs.RegionConfig.chmap.RUN;
            app.Button_Run.BackgroundColor = [0.96,0.96,0.96];
            app.DropDown_Source.ItemsData = {'NORMAL', 'FUSED'};
            app.DropDown_Source.Items = [app.PIVs.RegionConfig.chmap.NORMAL, ...
                app.PIVs.RegionConfig.chmap.FUSED];    % append fused item

            % unlock options
            UpdateAppearanceAfterRegistration(app);
        end

        function RegNotReadyWarn(app)
            if ~isempty(app.SUBAPPs.RS) && isvalid(app.SUBAPPs.RS)
                switch numel(app.Controller.ProjectManager.Project.c_order)
                    case 1
                        app.SUBAPPs.RS.FontBlink("CheckBox_Lock_" + ...
                            ["Global","Local","MGlobal"]);
                    case 2
                        app.SUBAPPs.RS.FontBlink("CheckBox_Lock_" + ...
                            ["Global","Local","MGlobal","LTGlobal"]);
                    otherwise
                end
            end
            app.Button_Run.Value = false;
            GenSysMsg(app, ["REGISTRATION_SOLUTION_ERROR",""]);
        end

        function RemoveTimer(app)
            if ~isempty(app.Controller.AppDaemonTimer)
                stop(app.Controller.AppDaemonTimer);
                delete(app.Controller.AppDaemonTimer);
            end
            if ~isempty(app.PDVs.MoviePlayerTimer)
                stop(app.PDVs.MoviePlayerTimer);
                delete(app.PDVs.MoviePlayerTimer);
            end
            if ~isempty(app.Controller.ProgressTimer)
                stop(app.Controller.ProgressTimer);
                delete(app.Controller.ProgressTimer);
            end
        end

        function status = GenSysMsg(app, cmdstrs, out, timestamp)
            arguments
                app         (1,1)   Register
                cmdstrs     (1,2)   string
                out         (1,1)   logical     = true
                timestamp   (1,1)   logical     = true
            end

            if app.Switch_Log.Value == "off"
                status = size(app.PIVs.AppMessage, 1);
                return;
            end

            % record the message
            if timestamp == true
                msg_new = [cmdstrs(1), cmdstrs(2), string(datetime("now"))];
            else
                msg_new = [cmdstrs(1), cmdstrs(2), ""];
            end
            % local record
            app.PIVs.AppMessage = [app.PIVs.AppMessage; msg_new];

            % apply output
            if out == true
                t_msg = AppendTextArea_Log(app, ...
                    app.PIVs.RegionConfig.chmap.(cmdstrs(1))+cmdstrs(2),...
                    timestamp);
                app.PIVs.AppMessage(end, 3) = string(t_msg);
            end

            status = size(app.PIVs.AppMessage, 1);
        end

        function fid = ValidateUpdate(app)
            % This function read the update profile and set options
            fid = fopen(['startup_plugins', filesep, ...
                'update_setting.ini'], "r");

            if fid == -1
                switch app.PIVs.ApplicationOptions.MessageLevel
                    case [constdef.MESSAGE_WARNING, constdef.MESSAGE_INFO]
                        uialert(app.Reg3DV500WittgensteinUIFigure,...
                            app.PIVs.RegionConfig.chmap.UNABLE_ACCESS_UPDATE_SETTING,...
                            app.PIVs.RegionConfig.chmap.WARNING, "Icon","warning");
                    otherwise
                end
                return;
            end
            setting_profile = textscan(fid, "%s",'Delimiter','\n');
            fclose(fid);
            setting_profile = string(setting_profile{1});

            for k = 1:numel(setting_profile)
                if setting_profile(k).replace(" ","") == "" ...
                        || iscomment(setting_profile(k))
                    % skip this line
                    continue;
                end
                key = setting_profile(k).extractBefore(" = ");

                switch upper(key)
                    case "REPOSITORY_PC"
                        if ispc()
                            value = setting_profile(k).extractAfter(" = ");
                            if isfolder(value)
                                app.PIVs.UpdateOptions.repository = value;
                            else
                                warning(app.PIVs.RegionConfig.chmap.INVALID_UPDATE_REPOSITORY);
                            end
                        end
                    case "REPOSITORY_UNIX"
                        if isunix()
                            value = setting_profile(k).extractAfter(" = ");
                            if isfolder(value)
                                app.PIVs.UpdateOptions.repository = value;
                            else
                                warning(app.PIVs.RegionConfig.chmap.INVALID_UPDATE_REPOSITORY);
                            end
                        end
                    case "AUTO_CLEAN"
                        value = lower(setting_profile(k).extractAfter(" = "));
                        if value == "0" || value == "off" || value == "false"
                            app.PIVs.UpdateOptions.auto_clean = false;
                        elseif value == "1" || value == "on" || value == "true"
                            app.PIVs.UpdateOptions.auto_clean= true;
                        else
                            warning(app.PIVs.RegionConfig.chmap.UNRECOGNIZED_UPDATE_IDENTIFIER);
                        end
                    otherwise
                        if ~ismissing(key)
                            warning(app.PIVs.RegionConfig.chmap.UNRECOGNIZED_UPDATE_IDENTIFIER);
                        end
                end
            end

            % set the update image visiable
            [status, ~, ~, ch] = acquire_update(app.PIVs.UpdateOptions.repository, Register.VER);

            switch status
                case "UPDATABLE"
                    pathToMLAPP = fileparts(mfilename('fullpath'));
                    switch upper(ch)
                        case "RELEASE"
                            app.Image_Update.ImageSource = ...
                                fullfile(pathToMLAPP, 'sources', 'update_release.png');
                            app.Image_Update.Visible = "on";
                        case "PREVIEW"
                            if ismember(app.PIVs.ApplicationOptions.UpdateChannel, ["PRE", "ALL"])
                                app.Image_Update.ImageSource = ...
                                    fullfile(pathToMLAPP, 'sources', 'update_preview.png');
                                app.Image_Update.Visible = "on";
                            else
                                app.Image_Update.Visible = "off";
                            end
                        case "DEBUG"
                            if app.PIVs.ApplicationOptions.UpdateChannel == "ALL"
                                app.Image_Update.ImageSource = ...
                                    fullfile(pathToMLAPP, 'sources', 'bug.png');
                                app.Image_Update.Visible = "on";
                            else
                                app.Image_Update.Visible = "off";
                            end
                        otherwise
                    end
                case "ACCESS_FAILED"
                    app.Image_Update.Visible = "off";
                    switch app.PIVs.ApplicationOptions.MessageLevel
                        case [constdef.MESSAGE_WARNING, constdef.MESSAGE_INFO]
                            uialert(app.Reg3DV500WittgensteinUIFigure,...
                                app.PIVs.RegionConfig.chmap.UNABLE_ACCESS_SERVER, ...
                                app.PIVs.RegionConfig.chmap.WARNING, "Icon","warning");
                        otherwise
                    end
                    return;
                otherwise
                    app.Image_Update.Visible = "off";
            end

            function r = iscomment(str, marker)
                if ~exist("marker", "var")
                    marker = "#";
                end
                % comment means the marker is header
                r = (string(str).extractBefore(marker).replace(" ","") == "");
            end
        end

        function HoldWindowOn(app)
            % Use this function after uigetfile/uiputfile/uigetdir/uiopen
            % ... file operations, etc
            app.Reg3DV500WittgensteinUIFigure.WindowStyle = "alwaysontop";
            pause(0.5);
            app.Reg3DV500WittgensteinUIFigure.WindowStyle = "normal";
        end

        %================== SELF DEFINATION CALL BACK ====================
       
        function uROI_Action(app, ~, ~)
            UpdateViewerTitle(app);
        end

        function TimeText_ButtonDownFcn(app, event)
            switch event.Button
                case 1
                    % left button for motion tracking
                    % switch the moving status
                    app.PDVs.TimeTextObject.ismoving = ~app.PDVs.TimeTextObject.ismoving;
                case 2
                    % middle button for font setting
                    uisetfont(app.PDVs.TimeTextObject.handle);
                case 3
                    % right button for color setting
                    uisetcolor(app.PDVs.TimeTextObject.handle);
                otherwise
            end
        end

        function MetaText_ButtonDownFcn(app, event)
            switch event.Button
                case 1
                    % left button for motion tracking
                    % switch the moving status
                    app.PDVs.TimeTextObject.ismoving = ~app.PDVs.TimeTextObject.ismoving;
                case 2
                    uisetfont(app.PDVs.TimeTextObject.handle);
                case 3
                    uisetcolor(app.PDVs.TimeTextObject.handle);
                otherwise
            end
        end

        % This function is plugin menu common callback
        function Menu_PluginSelected(app, event)
            % Polymorphism implementation comes from identity(inner)
            % This callback call plugins run with non-await (external) modal
            
            % Security Validation
            plugin_file = event.Source.UserData{1};
            plugin_id = event.Source.UserData{2};

            is_safe = ValidatePluginSecurity(string(plugin_file));  % external code analyzer

            if is_safe
                try
                    cph = eval([plugin_file, '(app);']);
                    app.SUBAPPs.CP{plugin_id} = cph;
                catch ME
                    rethrow(ME);
                end
            else
                throw(MException("Register:unsafePlugin", "Plugin [%s] may include " + ...
                    "unsafe code, which could destroy Register system.", plugin_file));
            end
        end

        function Menu_RedSelected(app, ~)
            try
                app.Controller.ProjectManager.Project.cidx_cur = ("r" == app.Controller.ProjectManager.Project.c_order);
                app.DropDown_Source.Enable = "on";
                switch app.PDVs.DisplaySource
                    case "NORMAL"
                        app.Controller.ProjectManager.Project.pcolor.normal = constdef.COLOR_IMAGING_RED;
                    otherwise
                        % keep
                end

                if numel(app.Controller.ProjectManager.Project.c_order) == 2
                    app.Menu_Red.Checked = "on";
                    app.Menu_Green.Checked = "off";
                    app.Menu_Red_Green.Checked = "off";
                end

                RefreshViewer(app);

                app.Controller.Logger.Push("Register", "Menu_RedSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_RedSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        function Menu_GreenSelected(app, ~)
            try
                app.Controller.ProjectManager.Project.cidx_cur = ("g" == app.Controller.ProjectManager.Project.c_order);
                app.DropDown_Source.Enable = "on";
                switch app.PDVs.DisplaySource
                    case "NORMAL"
                        app.Controller.ProjectManager.Project.pcolor.normal = constdef.COLOR_IMAGING_GREEN;
                    otherwise
                        % keep
                end

                if numel(app.Controller.ProjectManager.Project.c_order) == 2
                    app.Menu_Red.Checked = "off";
                    app.Menu_Green.Checked = "on";
                    app.Menu_Red_Green.Checked = "off";
                end

                RefreshViewer(app);

                app.Controller.Logger.Push("Register", "Menu_GreenSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_GreenSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        function Menu_Red_GreenSelected(app, ~)
            try
                app.Controller.ProjectManager.Project.cidx_cur = true(1, numel(app.Controller.ProjectManager.Project.c_order));
                app.DropDown_Source.Enable = "off";
                switch app.PDVs.DisplaySource
                    case "NORMAL"
                        app.Controller.ProjectManager.Project.pcolor.normal = constdef.COLOR_IMAGING_RED_GREEN;
                    otherwise
                        % keep
                end

                if numel(app.Controller.ProjectManager.Project.c_order) == 2
                    app.Menu_Red.Checked = "off";
                    app.Menu_Green.Checked = "off";
                    app.Menu_Red_Green.Checked = "on";
                end

                RefreshViewer(app);

                app.Controller.Logger.Push("Register", "Menu_Red_GreenSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_Red_GreenSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        function Menu_MergeRBSelected(app, ~)
            try
                app.Menu_MergeRB.Checked = "on";
                app.Menu_MergeGR.Checked = "off";

                app.Controller.ProjectManager.Project.pcolor.fused = constdef.COLOR_IMAGING_RED_CYAN;

                RefreshViewer(app);

                app.Controller.Logger.Push("Register", "Menu_MergeRBSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_MergeRBSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        function Menu_MergeGRSelected(app, ~)
            try
                app.Menu_MergeRB.Checked = "off";
                app.Menu_MergeGR.Checked = "on";

                app.Controller.ProjectManager.Project.pcolor.fused = constdef.COLOR_IMAGING_GREEN_MAGENTA;

                RefreshViewer(app);

                app.Controller.Logger.Push("Register", "Menu_MergeGRSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_MergeGRSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        function Menu_AddCellSelected(app, ~)
            % call nuclear control bot to add new circle
            if ~isempty(app.SUBAPPs.CS) && isvalid(app.SUBAPPs.CS)
                app.SUBAPPs.CS.AddCell();
            end
        end

        function Menu_RefreshLabelSelected(app, ~)
            % call nuclear control bot to refresh label
            if ~isempty(app.SUBAPPs.CS) && isvalid(app.SUBAPPs.CS)
                app.SUBAPPs.CS.RefreshLabel();
            end
        end

        function Menu_AnomalyDetectionSelected(app, ~)
            % call nuclear control bot to do anomaly detection
            if ~isempty(app.SUBAPPs.CS) && isvalid(app.SUBAPPs.CS)
                app.SUBAPPs.CS.AnomalyDetection();
            end
        end

        function ModifySegmentArea(app, src, evt)
            bdbox_index = src.UserData;
            if evt.EventName == "ROIMoved"
                app.Controller.ProjectManager.Project.opt_seg.bdbox(bdbox_index, :) = ...
                    [ceil(src.Vertices(1,:)+0.5), 1, ...
                     ceil(src.Vertices(3,1)-src.Vertices(2,1)-1), ...
                     ceil(src.Vertices(2,2)-src.Vertices(1,2)-1), ...
                     app.Controller.ProjectManager.Project.img_cur.MetaData.slices-1];
            end
        end

        function SimpleStat_CallBack(app, ~, ~)
            % this function does simple statistics, export by message box
            try
                if isempty(app.PDVs.UROIHandle) || ~isvalid(app.PDVs.UROIHandle)
                    switch app.PIVs.ApplicationOptions.MessageLevel
                        case [constdef.MESSAGE_WARNING, constdef.MESSAGE_INFO]
                            uialert(app.Reg3DV500WittgensteinUIFigure,...
                                app.PIVs.RegionConfig.chmap.CONFIRM_ROI_RANGE,...
                                app.PIVs.RegionConfig.chmap.WARNING, "Icon","warning");
                        otherwise
                    end
                else
                    mask = createMask(app.PDVs.UROIHandle);
                    slice_img = GetSliceImgAt(app);
                    data = double(slice_img(mask));
                    stat = regionprops("table", mask, "Area","Perimeter");

                    % some stat
                    data_min = min(data);
                    data_max = max(data);
                    data_mean = mean(data);
                    data_median = median(data);
                    data_mode = mode(data);
                    data_std = std(data);
                    area_size = stat.Area*app.Controller.ProjectManager.Project.img_cur.MetaData.xRes ...
                        * app.Controller.ProjectManager.Project.img_cur.MetaData.yRes;    % um^2 as usual
                    perimeter_length = stat.Perimeter*sqrt(app.Controller.ProjectManager.Project.img_cur.MetaData.xRes ...
                        * app.Controller.ProjectManager.Project.img_cur.MetaData.yRes);   % um as usual

                    msg = sprintf("%s: %.2f\n" + ...
                        "%s: %.2f\n" + ...
                        "%s: %.2f\n" + ...
                        "%s: %.2f\n" + ...
                        "%s: %.2f\n" + ...
                        "%s: %.2f\n" + ...
                        "%s: %.2f um\n" + ...
                        "%s: %.2f um^2", ...
                        app.PIVs.RegionConfig.chmap.INTENSITY_MIN, data_min, ...
                        app.PIVs.RegionConfig.chmap.INTENSITY_MAX, data_max, ...
                        app.PIVs.RegionConfig.chmap.INTENSITY_MEAN, data_mean, ...
                        app.PIVs.RegionConfig.chmap.INTENSITY_MEDIAN, data_median, ...
                        app.PIVs.RegionConfig.chmap.INTENSITY_MODE, data_mode, ...
                        app.PIVs.RegionConfig.chmap.INTENSITY_STD, data_std, ...
                        app.PIVs.RegionConfig.chmap.AREA_PERIMETER, perimeter_length, ...
                        app.PIVs.RegionConfig.chmap.AREA_SIZE, area_size);

                    uialert(app.Reg3DV500WittgensteinUIFigure, msg, ...
                        app.PIVs.RegionConfig.chmap.NOTIFICATION, "Icon", "info");
                end

                app.Controller.Logger.Push("Register", "SimpleStat_CallBack");
            catch ME
                app.Controller.Logger.Push("Register", "SimpleStat_CallBack", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        function FindTemplateAuto_CallBack(app, ~, ~)
            % estimate template index on current channel
            try
                if ~isempty(app.Controller.ProjectManager.Project.img_cur)
                    upd = uiprogressdlg(app.Reg3DV500WittgensteinUIFigure, ...
                        "Indeterminate","on", "Message",app.PIVs.RegionConfig.chmap.TEMPLATE_VOLUME_SEARCHING, ...
                        "Icon","info");

                    r = app.Controller.ProjectManager.Project.img_cur.ETmplIdx;

                    if r ~= 0
                        % shift slider to given index
                        app.Slider_Time.Value = r;

                        % Synchronize the time spinner
                        app.Spinner_Time.Value = r;
                        app.Controller.ProjectManager.Project.fidx_cur = r;
                        app.Controller.ProjectManager.Project.frames_reg(2) = string(r);

                        % update current refvol
                        app.Controller.ProjectManager.Project.fixdef.Sampling(2) = string(r);
                        app.Controller.ProjectManager.Project.refvol = regtmpl(app.Controller.ProjectManager.Project.img_cur, app.Controller.ProjectManager.Project.fixdef);
                        if ~isempty(app.SUBAPPs.RS) && isvalid(app.SUBAPPs.RS)
                            app.SUBAPPs.RS.Refresh(app);
                        end

                        RefreshViewer(app, "T");
                    end

                    delete(upd);
                end

                app.Controller.Logger.Push("Register", "FindTemplateAuto_CallBack");
            catch ME
                app.Controller.Logger.Push("Register", "FindTemplateAuto_CallBack", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        function PreprocessEvaluation_CallBack(app, ~, ~)
            try
                if any(app.PIVs.RegisterOptionsLock{1,:}) == true
                    % validate if coarse reg is using
                    if (ismember(app.Controller.ProjectManager.Project.opt_reg.Algorithm, ["OCREG", "TCREG"]) && ...
                            (app.Controller.ProjectManager.Project.opt_reg.Mode=="global") && (app.Controller.ProjectManager.Project.opt_reg.Options.CoarseAlg~="none")) ...
                            || (app.Controller.ProjectManager.Project.opt_reg.Algorithm == "LTREG")
                        upd = uiprogressdlg(app.Reg3DV500WittgensteinUIFigure, ...
                            "Indeterminate","off", "Icon","info", ...
                            "Message",app.PIVs.RegionConfig.chmap.EVALUATE_PREPROCESSING_QUALITY);

                        % find if volume lost foreground because bad
                        % filter setting in given registration range
                        regfrs = RegisterFrameAdapter(app);
                        [~, cidx] = ismember(app.Controller.ProjectManager.Project.opt_reg.Options.SC, app.Controller.ProjectManager.Project.img_cur.MetaData.cOrder);

                        fids_invalid = [];
                        n = 1;
                        for k = regfrs
                            vol = squeeze(app.Controller.ProjectManager.Project.img_cur.Movie(:,:,cidx,:,k));
                            vol = GenPreProcVol(vol, app.Controller.ProjectManager.Project.opt_reg);
                            if ~any(vol)
                                fids_invalid = [fids_invalid, k]; %#ok<AGROW>
                            end
                            upd.Message = sprintf("%s (%d/%d)", app.PIVs.RegionConfig.chmap.EVALUATE_PREPROCESSING_QUALITY, ...
                                n, numel(regfrs));
                            upd.Value = n / numel(regfrs);
                            n = n + 1;
                        end

                        % report
                        if ~isempty(fids_invalid)
                            optf.f = fids_invalid;
                            r = regohm.reformat_time(optf);
                            GenSysMsg(app, ["PREPROCESSOR_EXCEPTION",string(r).join(":").join(",")]);
                        else
                            GenSysMsg(app, ["PREPROCESSOR_EXCEPTION","0"]);
                        end

                        delete(upd);
                    else
                        switch app.PIVs.ApplicationOptions.MessageLevel
                            case [constdef.MESSAGE_WARNING, constdef.MESSAGE_INFO]
                                uialert(app.Reg3DV500WittgensteinUIFigure, ...
                                    app.PIVs.RegionConfig.chmap.PREPROCESSOR_LOST, ...
                                    app.PIVs.RegionConfig.chmap.WARNING, "Icon","warning");
                            otherwise
                        end
                    end
                else
                    switch app.PIVs.ApplicationOptions.MessageLevel
                        case [constdef.MESSAGE_WARNING, constdef.MESSAGE_INFO]
                            uialert(app.Reg3DV500WittgensteinUIFigure, ...
                                app.PIVs.RegionConfig.chmap.CONFIRM_REG_SOLUTION, ...
                                app.PIVs.RegionConfig.chmap.WARNING, "Icon","warning");
                        otherwise
                    end
                end

                app.Controller.Logger.Push("Register", "PreprocessEvaluation_CallBack");
            catch ME
                app.Controller.Logger.Push("Register", "PreprocessEvaluation_CallBack", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        function RestoreView2D_CallBack(app, ~, ~)
            %
            RefreshImagingRange2D(app);
        end
    end

    %========================== CLASS METHODS ============================
    
    methods(Static)
        function status = DeployParallelConfig()
            % load the parallel setting on different platform
            % note that app will not recover the configuration
            pinfo = struct2table(ver);
            par_flag = any(string(pinfo.Name).contains(...
                "Parallel Computing Toolbox"));

            if par_flag == true
                profs = string(parallel.listProfiles);  % >=R2022b
                if ismember("Reg3D_Local", profs) ...
                        || ismember("Reg3D_Server", profs)
                    if ismember("Reg3D_Local", profs) ...
                        && ismember("Reg3D_Server", profs)
                        error("Reg3D:invalidParallelConfiguration", ...
                            "Please keep only one parallel profile in " + ...
                            "'Cluster Profile Manager' manually.");
                    end
                    
                    if ismember("Reg3D_Local", profs) ...
                            && (parallel.defaultProfile()~="Reg3D_Local")
                        fprintf("Parallel profile changed: %s -> %s\n", ...
                            parallel.defaultProfile(), "Reg3D_Local");
                        parallel.defaultProfile("Reg3D_Local");
                    elseif ismember("Reg3D_Server", profs) ...
                            && (parallel.defaultProfile()~="Reg3D_Server")
                        fprintf("Parallel profile changed: %s -> %s\n", ...
                            parallel.defaultProfile(), "Reg3D_Server");
                        parallel.defaultProfile("Reg3D_Server");
                    end
                    status = 0;
                else
                    % get platform information and select the better
                    % configuration
                    pathToMLAPP = fileparts(mfilename('fullpath'));
                    local_setting_path = [pathToMLAPP, filesep, 'bin',filesep, ...
                        'Reg3D_Local.mlsettings'];
                    server_setting_path = [pathToMLAPP, filesep, 'bin',filesep, ...
                        'Reg3D_Server.mlsettings'];
                    disp("Auto deploy the parallel pool configuration at first use...");
                    sinfo = getCpuInfo();
                    if sinfo.NumProcessors > 16
                        if sinfo.OSType == "Linux"
                            disp("Reg3D_Server profile is enabled...");
                            % this machine is linux server
                            pf = parallel.importProfile(server_setting_path);
                            parallel.defaultProfile(pf); % set as default
                        else
                            % unknown work station
                            warning("Reg3D:unknownHPC",...
                                "Unknown HPC. Reg3D_Local profile enabled. " + ...
                                "You can modify it manually.");
                            pf = parallel.importProfile(local_setting_path);
                            parallel.defaultProfile(pf); % set as default
                        end
                    else
                        % no matter local machine OS
                        disp("Reg3D_Local profile is enabled...");
                        pf = parallel.importProfile(local_setting_path);
                        parallel.defaultProfile(pf); % set as default
                    end
                    disp("Done.");
                    status = 0;
                end
            else
                status = -1;
            end
        end

        function config = GetRegionConfig(lang)
            arguments
                lang    (1,1)   string  {mustBeMember(lang, ["zh_CN", "en_US"])} = "en_US"
            end

            if ispc(), config.plat = "Windows";
            else, config.plat = "Unix";
            end
            config.lang = lang;
            config.chmap = readstruct(['lang', filesep, char(lang), ...
                char(constdef.LANGUAGE_FILE_EXT)], "FileType","xml");
        end

        function status = UnloadExternalLib()
            % all external calling must be included in try...catch...
            %% ND2 library
            try
                if libisloaded('Nd2ReadSdk')
                    unloadlibrary('Nd2ReadSdk');
                end
                if libisloaded('libNd2ReadSdk')
                    unloadlibrary('libNd2ReadSdk');
                end
            catch ME
                rethrow(ME);
            end

            %% Python Environment
            try
                terminate(pyenv);
            catch ME
                rethrow(ME);
            end

            status = 0;
        end

        function r = GetAppPath()
            r = fileparts(mfilename("fullpath"));
        end

        function vs = ReformatUpdateText(vs)
            arguments
                vs  (:,1)   string
            end

            % begin at text line
            for k = constdef.REG3D_UPDATE_FILE_TITLE_ROW_SIZE+1:numel(vs)
                type = vs(k).extractBetween("[","]");
                if isKey(constdef.UPDATE_TEXT_COLORMAP, lower(type))
                    vs(k) = sprintf("\color{%s}", ...
                        constdef.UPDATE_TEXT_COLORMAP(lower(type))) + vs(k);
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % NOTE: This app can run on MATLAB version >= R2022b
            try
                %% Validate and Set up Running Environment
                if isMATLABReleaseOlderThan("R2022b")
                    disp("Your MATLAB version is older than R2022b, please update " + ...
                        "your MATLAB >= R2022b to continue.");
                    delete(app);
                    return;
                elseif isMATLABReleaseOlderThan("R2023b")
                    disp("Your MATLAB version is older than R2023b, " + ...
                        "and some components or the appearance may be outdated.");
                end

                %% !!! Init App Data
                InitAppProperties(app);

                pd = uiprogressdlg(app.Reg3DV500WittgensteinUIFigure, "Cancelable","off", ...
                    "Icon","info","ShowPercentage","on","Title","System Initializing...", ...
                    "Value",0);

                pd.Message = "Deploy environment configuration...";
                status_parpool = Register.DeployParallelConfig();
                if status_parpool == -1
                    pd.Message = "Parallel configuration failed.";
                    pd.Icon = "error";
                    Free(app);
                    return;
                end
                pd.Value = app.INIT_TIME_CONST(1);  % 0.1

                status_ienv = DeployEnvConfig(app);
                if status_ienv == -1
                    pd.Message = "Environment configuration failed.";
                    pd.Icon = "error";
                    Free(app);
                    return;
                end

                pd.Value = app.INIT_TIME_CONST(2);  % 0.8

                %% Initialize App Profile and Appearance

                pd.Message = "Set system appearance...";

                UpdateAppProfiles(app);

                InitAppearance(app);

                pd.Value = app.INIT_TIME_CONST(3);  % 0.9

                %% Validate App Update Status
                pd.Title = app.PIVs.RegionConfig.chmap.SYS_INITIALIZING;
                switch app.PIVs.ApplicationOptions.AutoUpdate
                    case "STARTUP"
                        pd.Message = app.PIVs.RegionConfig.chmap.VALIDATE_UPDATE;
                        pause(1);
                        ValidateUpdate(app);
                    otherwise
                end
                pd.Value = app.INIT_TIME_CONST(4);  % 1.0

                pd.Icon = "success";
                pd.Message = app.PIVs.RegionConfig.chmap.COMPLETE;
                pause(0.5);

                delete(pd);

                start(app.Controller.AppDaemonTimer);
                GenSysMsg(app, ["SYS_INITIALIZE_SUCCESS",""]);
            catch ME
                rethrow(ME);
            end
        end

        % Menu selected function: Menu_CropT
        function Menu_CropTSelected(app, event)
            try
                % call the frames selector
                if isempty(app.SUBAPPs.FS) || ~isvalid(app.SUBAPPs.FS)
                    app.SUBAPPs.FS = FrameSelecter(app);
                    waitfor(app.SUBAPPs.FS);
                end

                % validate selected frames
                if numel(app.Controller.ProjectManager.Project.opt_crop.f) < 2
                    uialert(app.Reg3DV500WittgensteinUIFigure, ...
                        app.PIVs.RegionConfig.chmap.FRAME_FEW, ...
                        app.PIVs.RegionConfig.chmap.ERROR, "Icon","error");
                    return;
                elseif numel(app.Controller.ProjectManager.Project.opt_crop.f) > app.Controller.ProjectManager.Project.img_cur.MetaData.frames
                    uialert(app.Reg3DV500WittgensteinUIFigure, ...
                        app.PIVs.RegionConfig.chmap.FRAME_OVERFLOW, ...
                        app.PIVs.RegionConfig.chmap.ERROR, "Icon","error");
                    return;
                elseif numel(app.Controller.ProjectManager.Project.opt_crop.f) == app.Controller.ProjectManager.Project.img_cur.MetaData.frames
                    switch app.PIVs.ApplicationOptions.MessageLevel
                        case constdef.MESSAGE_INFO
                            uialert(app.Reg3DV500WittgensteinUIFigure, ...
                                app.PIVs.RegionConfig.chmap.FULL_FRAMES, ...
                                app.PIVs.RegionConfig.chmap.INFORMATION, "Icon","info");
                        otherwise
                    end
                    return;
                end

                %% Adjust if operation is valid (space limitation)
                tf = app.Controller.ProjectManager.OperationManager.isaddable(constdef.OP_CROP, {"T", app.Controller.ProjectManager.Project.opt_crop.f});

                if tf == false
                    % restore opt_crop
                    app.Controller.ProjectManager.Project.opt_crop.f = 1:app.Controller.ProjectManager.Project.img_cur.MetaData.frames;

                    % ERROR: display at anytime
                    uialert(app.Reg3DV500WittgensteinUIFigure, ...
                        app.PIVs.RegionConfig.chmap.STORAGE_INSUFFICIENT, ...
                        app.PIVs.RegionConfig.chmap.ERROR, "Icon","error","Modal",true);
                    return;
                end

                %%
                % !!! take system snapshot before T crop
                [args, others] = app.TakeSystemBasicSnap();

                PanelWaiting(app);  %

                % crop the source movie
                app.Controller.ProjectManager.Project.img_cur =  app.Controller.ProjectManager.Project.img_cur.tcrop(app.Controller.ProjectManager.Project.opt_crop.f);

                % add history node
                app.Controller.ProjectManager.OperationManager.AddNode([], constdef.OP_CROP, args, ...
                    "Data",app.Controller.ProjectManager.Project.img_cur, "CropDim","T", "Others",others);
                app.Controller.ProjectManager.Project.img_cur = app.Controller.ProjectManager.OperationManager.CurrentImagePtr;

                % crop text vector if is needed
                if ~isempty(app.PDVs.TimeTextObject.text)
                    app.PDVs.TimeTextObject.text = app.PDVs.TimeTextObject.text(app.Controller.ProjectManager.Project.opt_crop.f);
                end
                if ~isempty(app.PDVs.TimeTextObject.text)
                    app.PDVs.TimeTextObject.text = app.PDVs.TimeTextObject.text(app.Controller.ProjectManager.Project.opt_crop.f);
                end

                % crop the z-projection
                if ~isempty(app.Controller.ProjectManager.Project.img_zproj.cur)
                    [app.Controller.ProjectManager.Project.img_zproj.cur, ~] = ...
                        CropT(app.Controller.ProjectManager.Project.img_zproj.cur, app.Controller.ProjectManager.Project.opt_crop.f);
                end

                % reorder time axis
                app.Controller.ProjectManager.Project.fidx_cur = 1;
                SetSlider_Time(app, [1, app.Controller.ProjectManager.Project.img_cur.MetaData.frames]);

                % Synchronize z projection
                SourceZProjection(app, [], app.PDVs.ZProjectionMode);

                % Synchronize registration frames
                app.Controller.ProjectManager.Project.frames_reg = ["1:end", "1"];
                GenSysMsg(app, ["RESET_REGISTRATION_FRAMES",""]);

                % Synchronize template frames
                tmpl = str2num(app.Controller.ProjectManager.Project.fixdef.Sampling(2)); %#ok<ST2NM>
                tmpl(~ismember(tmpl, app.Controller.ProjectManager.Project.opt_crop.f)) = [];
                if isempty(tmpl)
                    app.Controller.ProjectManager.Project.fixdef.Sampling(2) = "1";
                else
                    for k = 1:numel(tmpl)
                        tmpl(k) = tmpl(k) - ...
                            numel(setdiff(1:tmpl(k), app.Controller.ProjectManager.Project.opt_crop.f));
                    end
                    app.Controller.ProjectManager.Project.fixdef.Sampling(2) = string(tmpl).join(",");
                end
                if ~isempty(app.SUBAPPs.RS) && isvalid(app.SUBAPPs.RS)
                    app.SUBAPPs.RS.Refresh(app);
                end

                % update template
                app.Controller.ProjectManager.Project.refvol = regtmpl(app.Controller.ProjectManager.Project.img_cur, app.Controller.ProjectManager.Project.fixdef);

                PanelRecovered(app);    %

                GenSysMsg(app, ["T_CROPPED",""]);

                RefreshViewer(app);

                % set project saved status
                SetAppSavedStatus(app, "unsaved");

                app.Controller.Logger.Push("Register", "Menu_CropTSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_CropTSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_About
        function Menu_AboutSelected(app, event)
            msg = sprintf("Register3D Graphics User Interface (RGUI) \n" + ...
                "Full function volume series register \n" + ...
                "Version: %s (%s) %s @si-lab \n" + ...
                "Author: Weihan Li \n" + ...
                "E-mail: liweihan20@mails.ucas.ac.cn", ...
                app.VER, app.RELEASE_CODE, app.RELEASE_STATUS);
            uialert(app.Reg3DV500WittgensteinUIFigure, msg, ...
                app.PIVs.RegionConfig.chmap.INFORMATION, "Icon","info");
        end

        % Menu selected function: Menu_Documents
        function Menu_DocumentsSelected(app, event)
            try
                pathToMLAPP = fileparts(mfilename('fullpath'));
                help_file = [pathToMLAPP, filesep, 'help', filesep, '0.html'];

                if ~exist(help_file, "file")
                    % ERROR: display at anytime
                    uialert(app.Reg3DV500WittgensteinUIFigure, ...
                        app.PIVs.RegionConfig.chmap.MISSING_HELP, ...
                        app.PIVs.RegionConfig.chmap.ERROR, "Icon","error");
                    return;
                else
                    web(help_file);
                end
            catch ME
                rethrow(ME);
            end
        end

        % Value changed function: Spinner_Slice
        function Spinner_SliceValueChanged(app, event)
            try
                value = round(app.Spinner_Slice.Value);
                app.Spinner_Slice.Value = value;

                % Synchronize the slice slider
                app.Slider_Slice.Value = value;
                app.Controller.ProjectManager.Project.sidx_cur = value;

                RefreshViewer(app, "Z");

                % too many loop, omit logger 
                % ~
            catch ME
                app.Controller.Logger.Push("Register", "Spinner_SliceValueChanged", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Value changed function: Spinner_Time
        function Spinner_TimeValueChanged(app, event)
            try
                value = round(app.Spinner_Time.Value);
                app.Spinner_Time.Value = value;

                % Synchronize the slice slider
                app.Slider_Time.Value = value;
                app.Controller.ProjectManager.Project.fidx_cur = value;
                app.Controller.ProjectManager.Project.frames_reg(2) = string(value);

                RefreshViewer(app, "T");

                % too many loop, omit logger 
                % ~
            catch ME
                app.Controller.Logger.Push("Register", "Spinner_TimeValueChanged", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_RectangleROI
        function Menu_RectangleROISelected(app, event)
            try
                if ~isempty(app.PDVs.UROIHandle) && isvalid(app.PDVs.UROIHandle)
                    delete(app.PDVs.UROIHandle);
                end
                app.PDVs.UROIHandle = drawrectangle(app.UIAxes_Slice);
                addlistener(app.PDVs.UROIHandle,'MovingROI',   @app.uROI_Action);
                addlistener(app.PDVs.UROIHandle,'DeletingROI', @app.uROI_Action);

                app.Controller.Logger.Push("Register", "Menu_RectangleROISelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_RectangleROISelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_CircleROI
        function Menu_CircleROISelected(app, event)
            try
                if ~isempty(app.PDVs.UROIHandle) && isvalid(app.PDVs.UROIHandle)
                    delete(app.PDVs.UROIHandle);
                end
                app.PDVs.UROIHandle = drawcircle(app.UIAxes_Slice);
                addlistener(app.PDVs.UROIHandle,'MovingROI',   @app.uROI_Action);
                addlistener(app.PDVs.UROIHandle,'DeletingROI', @app.uROI_Action);

                app.Controller.Logger.Push("Register", "Menu_CircleROISelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_CircleROISelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_PolygonROI
        function Menu_PolygonROISelected(app, event)
            try
                if ~isempty(app.PDVs.UROIHandle) && isvalid(app.PDVs.UROIHandle)
                    delete(app.PDVs.UROIHandle);
                end
                app.PDVs.UROIHandle = drawpolygon(app.UIAxes_Slice);
                addlistener(app.PDVs.UROIHandle,'MovingROI',   @app.uROI_Action);
                addlistener(app.PDVs.UROIHandle,'DeletingROI', @app.uROI_Action);

                app.Controller.Logger.Push("Register", "Menu_PolygonROISelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_PolygonROISelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_FreehandROI
        function Menu_FreehandROISelected(app, event)
            try
                if ~isempty(app.PDVs.UROIHandle) && isvalid(app.PDVs.UROIHandle)
                    delete(app.PDVs.UROIHandle);
                end
                app.PDVs.UROIHandle = drawfreehand(app.UIAxes_Slice);
                addlistener(app.PDVs.UROIHandle,'MovingROI',   @app.uROI_Action);
                addlistener(app.PDVs.UROIHandle,'DeletingROI', @app.uROI_Action);

                app.Controller.Logger.Push("Register", "Menu_FreehandROISelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_FreehandROISelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_1F
        function Menu_1FSelected(app, event)
            try
                app.Controller.ProjectManager.Project.fps.fold = 1;
                app.PDVs.MoviePlayerTimer.Period = round(1/(app.Controller.ProjectManager.Project.fps.base*app.Controller.ProjectManager.Project.fps.fold), 3);
                app.Menu_1F.Checked = "on";
                app.Menu_5F.Checked = "off";
                app.Menu_15F.Checked = "off";

                app.Controller.Logger.Push("Register", "Menu_1FSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_1FSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_5F
        function Menu_5FSelected(app, event)
            try
                app.Controller.ProjectManager.Project.fps.fold = 5;
                app.PDVs.MoviePlayerTimer.Period = round(1/(app.Controller.ProjectManager.Project.fps.base*app.Controller.ProjectManager.Project.fps.fold), 3);
                app.Menu_1F.Checked = "off";
                app.Menu_5F.Checked = "on";
                app.Menu_15F.Checked = "off";
                
                app.Controller.Logger.Push("Register", "Menu_5FSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_5FSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Key press function: Reg3DV500WittgensteinUIFigure
        function Reg3DV500WittgensteinUIFigureKeyPress(app, event)
            try
                key = event.Key;            % base key
                modifier = event.Modifier;  % modifier, can be any combination
                % comes from ["control","alt","shift"]

                if ~isempty(modifier) && all(modifier=="control")
                    switch key
                        case {"a","A"}
                            % append frames to frames selector app
                            if ~isempty(app.SUBAPPs.FS) && isvalid(app.SUBAPPs.FS)
                                app.SUBAPPs.FS.AppendFrame(app.Controller.ProjectManager.Project.fidx_cur);
                            end
                        case "tab"
                            % change the selected node in operation viewer
                            if ~isempty(app.Controller.ProjectManager.Project.img_cur)
                                app.Controller.ProjectManager.OperationManager.SelectNextNode(true);
                                RefreshViewer(app, "T");
                            end
                        otherwise
                    end
                elseif isempty(modifier)
                    switch key
                        case {"d","D","rightarrow"}
                            NextFramePtr(app);
                            RefreshViewer(app, "T");
                        case {"a","A","leftarrow"}
                            PrevFramePtr(app);
                            RefreshViewer(app, "T");
                        case {"w","W","uparrow"}
                            NextSlicePtr(app);
                            RefreshViewer(app, "Z");
                        case {"s","S","downarrow"}
                            PrevSlicePtr(app);
                            RefreshViewer(app, "Z");
                        case "space"
                            if app.PIVs.MoviePlayingFlag
                                stop(app.PDVs.MoviePlayerTimer);
                                app.PIVs.MoviePlayingFlag = false;
                            else
                                LoopN = app.Slider_Time.Limits(end) - app.Controller.ProjectManager.Project.fidx_cur;
                                switch app.PDVs.DisplayLoopMode
                                    case "one-step"
                                        if isempty(LoopN) || (LoopN==0)
                                            % reject start timer
                                            return;
                                        end
                                    case "loop"
                                        LoopN = Inf;
                                end

                                app.PDVs.MoviePlayerTimer.TasksToExecute = LoopN;
                                LockPlaySetting(app);

                                app.PIVs.MoviePlayingFlag = true;
                                start(app.PDVs.MoviePlayerTimer);
                            end
                            RefreshViewer(app, "T");
                        otherwise
                    end
                end

                % too many loop, omit logger 
                % ~
            catch ME
                app.Controller.Logger.Push ("Register", "UIFigureKeyPress", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_Direct
        function Menu_DirectSelected(app, event)
            try
                app.Menu_Direct.Checked = true;
                app.Menu_Loop.Checked = false;
                app.PDVs.DisplayLoopMode = "one-step";
                app.PDVs.MoviePlayerTimer.TasksToExecute = app.Slider_Time.Limits(end) ...
                    - app.Controller.ProjectManager.Project.fidx_cur;

                app.Controller.Logger.Push("Register", "Menu_DirectSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_DirectSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_Loop
        function Menu_LoopSelected(app, event)
            try
                app.Menu_Direct.Checked = false;
                app.Menu_Loop.Checked = true;
                app.PDVs.DisplayLoopMode = "loop";
                app.PDVs.MoviePlayerTimer.TasksToExecute = Inf;

                app.Controller.Logger.Push("Register", "Menu_LoopSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_LoopSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_CropXY
        function Menu_CropXYSelected(app, event)
            try
                % validate if ROI exist
                if ~isempty(app.PDVs.UROIHandle) && isvalid(app.PDVs.UROIHandle)
                    roiXY_coor = ROI2Rectangle(app.PDVs.UROIHandle);
                    % xy := [x1,y1;x2,y2] in pixel
                    app.Controller.ProjectManager.Project.opt_crop.xy = ceil(roiXY_coor);

                    %% Adjust if operation is valid (space limitation)
                    tf = app.Controller.ProjectManager.OperationManager.isaddable(constdef.OP_CROP, {"XY", app.Controller.ProjectManager.Project.opt_crop.xy});

                    if tf == false
                        % restore opt_crop
                        app.Controller.ProjectManager.Project.opt_crop.xy = [1, 1; ...
                            app.Controller.ProjectManager.Project.img_cur.MetaData.width, app.Controller.ProjectManager.Project.img_cur.MetaData.height];

                        % ERROR: display at anytime
                        uialert(app.Reg3DV500WittgensteinUIFigure, ...
                            app.PIVs.RegionConfig.chmap.STORAGE_INSUFFICIENT, ...
                            app.PIVs.RegionConfig.chmap.ERROR, "Icon","error","Modal",true);
                        return;
                    end

                    %%
                    % !!! take system snapshot before XY crop
                    [args, others] = app.TakeSystemBasicSnap();

                    PanelWaiting(app);  %

                    % crop image
                    app.Controller.ProjectManager.Project.img_cur = ...
                        app.Controller.ProjectManager.Project.img_cur.vcrop("XY", app.Controller.ProjectManager.Project.opt_crop.xy');

                    % add history node
                    app.Controller.ProjectManager.OperationManager.AddNode([], constdef.OP_CROP, args, ...
                        "Data",app.Controller.ProjectManager.Project.img_cur, "CropDim","XY", "Others",others);
                    app.Controller.ProjectManager.Project.img_cur = app.Controller.ProjectManager.OperationManager.CurrentImagePtr;

                    % update template definition
                    app.Controller.ProjectManager.Project.refvol = regtmpl(app.Controller.ProjectManager.Project.img_cur, app.Controller.ProjectManager.Project.fixdef);

                    % crop the z-projection
                    if ~isempty(app.Controller.ProjectManager.Project.img_zproj.cur)
                        [app.Controller.ProjectManager.Project.img_zproj.cur, ~] = ...
                            CropXY(app.Controller.ProjectManager.Project.img_zproj.cur, app.Controller.ProjectManager.Project.opt_crop.xy);
                    end

                    % Synchronize z projection
                    SourceZProjection(app, [], app.PDVs.ZProjectionMode);

                    % Synchronize mROI mask
                    if ~isempty(app.PDVs.Mask)
                        app.PDVs.Mask = ...
                            app.PDVs.Mask.crop("XY", app.Controller.ProjectManager.Project.opt_crop.xy');
                    end

                    % remove the exist ROIs if possible
                    delete(app.PDVs.UROIHandle);

                    % update the scalebar
                    app.PDVs.ScaleBar.update(app.Controller.ProjectManager.Project.img_cur.MetaData);

                    % reg3d post process
                    GenSysMsg(app, ["XY_CROPPED",""]);

                    PanelRecovered(app);

                    % refresh current view
                    RefreshImagingRange2D(app);
                    RefreshViewer(app, "XY");
                    UpdateYScrollLim(app);

                    % set project saved status
                    SetAppSavedStatus(app, "unsaved");
                else
                    % ERROR: display at anytime
                    uialert(app.Reg3DV500WittgensteinUIFigure,...
                        app.PIVs.RegionConfig.chmap.CONFIRM_ROI_RANGE,...
                        app.PIVs.RegionConfig.chmap.ERROR, "Icon","error");
                    return;
                end

                app.Controller.Logger.Push("Register", "Menu_CropXYSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_CropXYSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_CropZ
        function Menu_CropZSelected(app, event)
            try
                % set z crop range dialog
                prompt = {['\fontsize{12} ', app.PIVs.RegionConfig.chmap.THICKNESS_RANGE.char()]};
                dlgtitle = app.PIVs.RegionConfig.chmap.CROP_Z.char();
                dims = [1 40];
                definput = {'1:end'};
                dlgopts.WindowStyle = 'normal';
                dlgopts.Interpreter = "tex";
                dlgout = inputdlg(prompt,dlgtitle,dims,definput,dlgopts);
                if isempty(dlgout)
                    return;
                else
                    dlgout=dlgout{1};
                end
                dlgout = string(dlgout).replace("end",num2str(app.Controller.ProjectManager.Project.img_cur.MetaData.slices));
                app.Controller.ProjectManager.Project.opt_crop.z = [min(str2num(dlgout)), max(str2num(dlgout))]; %#ok<ST2NM>

                if diff(app.Controller.ProjectManager.Project.opt_crop.z) == app.Controller.ProjectManager.Project.img_cur.MetaData.slices-1
                    switch app.PIVs.ApplicationOptions.MessageLevel
                        case constdef.MESSAGE_INFO
                            uialert(app.Reg3DV500WittgensteinUIFigure, ...
                                app.PIVs.RegionConfig.chmap.FULL_SLICES, ...
                                app.PIVs.RegionConfig.chmap.INFORMATION, "Icon","info");
                        otherwise
                    end

                    return;
                end

                if diff(app.Controller.ProjectManager.Project.opt_crop.z) < 2
                    % ERROR: display at anytime
                    uialert(app.Reg3DV500WittgensteinUIFigure, ...
                        app.PIVs.RegionConfig.chmap.SLICE_FEW,...
                        app.PIVs.RegionConfig.chmap.ERROR, "Icon","error");
                    return;
                elseif diff(app.Controller.ProjectManager.Project.opt_crop.z) < 5
                    switch app.PIVs.ApplicationOptions.MessageLevel
                        case [constdef.MESSAGE_WARNING, constdef.MESSAGE_INFO]
                            slt = uiconfirm(app.Reg3DV500WittgensteinUIFigure, ...
                                app.PIVs.RegionConfig.chmap.SLICES_FEW_FOR_NEXT_STEP, ...
                                app.PIVs.RegionConfig.chmap.WARNING, "Icon","warning");
                            if slt ~= "OK"
                                return;
                            end
                        otherwise
                    end
                elseif diff(app.Controller.ProjectManager.Project.opt_crop.z) >= app.Controller.ProjectManager.Project.img_cur.MetaData.slices
                    % ERROR: display at anytime
                    uialert(app.Reg3DV500WittgensteinUIFigure, ...
                        app.PIVs.RegionConfig.chmap.SLICES_OVERFLOW,...
                        app.PIVs.RegionConfig.chmap.ERROR, "Icon","error");
                    return;
                end

                %% Adjust if operation is valid (space limitation)
                tf = app.Controller.ProjectManager.OperationManager.isaddable(constdef.OP_CROP, {"Z", app.Controller.ProjectManager.Project.opt_crop.z});

                if tf == false
                    % restore opt_crop
                    app.Controller.ProjectManager.Project.opt_crop.z = [1, app.Controller.ProjectManager.Project.img_cur.MetaData.slices];

                    % ERROR: display at anytime
                    uialert(app.Reg3DV500WittgensteinUIFigure, ...
                        app.PIVs.RegionConfig.chmap.STORAGE_INSUFFICIENT, ...
                        app.PIVs.RegionConfig.chmap.ERROR, "Icon","error","Modal",true);
                    return;
                end

                %%
                % !!! take system snapshot before Z crop
                [args, others] = app.TakeSystemBasicSnap();

                PanelWaiting(app);  %

                % cropping two sources if it's possible
                app.Controller.ProjectManager.Project.img_cur = app.Controller.ProjectManager.Project.img_cur.vcrop("Z", app.Controller.ProjectManager.Project.opt_crop.z);

                % add history node
                app.Controller.ProjectManager.OperationManager.AddNode([], constdef.OP_CROP, args, ...
                    "Data",app.Controller.ProjectManager.Project.img_cur, "CropDim","Z", "Others",others);
                app.Controller.ProjectManager.Project.img_cur = app.Controller.ProjectManager.OperationManager.CurrentImagePtr;

                % update template definition
                app.Controller.ProjectManager.Project.refvol = regtmpl(app.Controller.ProjectManager.Project.img_cur, app.Controller.ProjectManager.Project.fixdef);

                % reorder slice axis
                app.Controller.ProjectManager.Project.sidx_cur = 1;
                SetSlider_Slice(app);

                % reset valid vpl
                app.Controller.ProjectManager.Project.opt_reg = set(app.Controller.ProjectManager.Project.opt_reg, "glVPL",  ...
                    floor(log(app.Controller.ProjectManager.Project.img_cur.MetaData.slices)/log(4))+1);
                if isequal(app.Controller.ProjectManager.Project.opt_reg.Algorithm, "TCREG")
                    app.Controller.ProjectManager.Project.opt_reg = set(app.Controller.ProjectManager.Project.opt_reg, "dfVPL",  ...
                        floor(log2(app.Controller.ProjectManager.Project.img_cur.MetaData.slices/0.7)));
                    app.Controller.ProjectManager.Project.opt_reg = set(app.Controller.ProjectManager.Project.opt_reg, "dmVPL",  ...
                        floor(app.Controller.ProjectManager.Project.img_cur.MetaData.slices/2));
                end

                % Synchronize z projection
                SourceZProjection(app, [], app.PDVs.ZProjectionMode);

                % Synchronize mROI mask
                if ~isempty(app.PDVs.Mask)
                    app.PDVs.Mask = ...
                        app.PDVs.Mask.crop("Z", app.Controller.ProjectManager.Project.opt_crop.z);
                end

                % Refresh RS handle
                if ~isempty(app.SUBAPPs.RS) && isvalid(app.SUBAPPs.RS)
                    app.SUBAPPs.RS.Refresh(app);
                end

                GenSysMsg(app, ["Z_CROPPED",""]);

                PanelRecovered(app);

                RefreshViewer(app, "Z");

                % set project saved status
                SetAppSavedStatus(app, "unsaved");

                app.Controller.Logger.Push("Register", "Menu_CropZSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_CropZSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_LightContrast
        function Menu_LightContrastSelected(app, event)
            try
                if isempty(app.SUBAPPs.BC) || ~isvalid(app.SUBAPPs.BC)
                    if isMATLABReleaseOlderThan("R2023b")
                        app.SUBAPPs.BC = BCAdjuster_old(app);
                    else
                        app.SUBAPPs.BC = BCAdjuster(app);
                    end
                end

                app.Controller.Logger.Push("Register", "Menu_LightContrastSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_LightContrastSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Window button motion function: Reg3DV500WittgensteinUIFigure
        function Reg3DV500WittgensteinUIFigureWindowButtonMotion(app, event)
            try
                if isempty(fieldnames(app.PIVs)) || isempty(fieldnames(app.PDVs))
                    return;
                end

                % This callback offer realtime cursor position
                if ~isempty(app.PIVs.ViewerLock) && ~app.PIVs.ViewerLock && ...
                        ~isempty(app.PDVs.DisplayViewMode) && (app.PDVs.DisplayViewMode=="2D")
                    % % ROI exist, hold on ROI information
                    % if ~isempty(app.hdle_uROI) && isvalid(app.hdle_uROI)
                    % end
                    mouse_pos_cur = app.UIAxes_Slice.CurrentPoint(1,1:2);
                    if any(mouse_pos_cur < 0) ...
                            || any(mouse_pos_cur>[app.Controller.ProjectManager.Project.img_cur.MetaData.width,...
                            app.Controller.ProjectManager.Project.img_cur.MetaData.height])
                        mouse_pos_cur = [];
                    else
                        mouse_pos_cur = ceil(mouse_pos_cur);
                    end

                    if isempty(mouse_pos_cur)
                        % recover the none title
                        UpdateViewerTitle(app);
                        return;
                    else
                        % readout pixel intensity from slice
                        x = mouse_pos_cur(1); y = mouse_pos_cur(2);
                        pixIxy = GetPixelValueAt(app, x, y);
                    end

                    UpdateViewerTitle(app,mouse_pos_cur,pixIxy);

                    % update the screen utils components
                    if ~isempty(app.PDVs.TimeTextObject.ismoving) ...
                            && app.PDVs.TimeTextObject.ismoving
                        app.PDVs.TimeTextObject.handle.Position = [mouse_pos_cur, 0];
                    end
                    if ~isempty(app.PDVs.TimeTextObject.ismoving) ...
                            && app.PDVs.TimeTextObject.ismoving
                        app.PDVs.TimeTextObject.handle.Position = [mouse_pos_cur, 0];
                    end
                    if ~isempty(app.PDVs.ScaleBar.Moving) && app.PDVs.ScaleBar.Moving
                        app.PDVs.ScaleBar.move(mouse_pos_cur);
                    end
                end

                % too many loop, omit logger 
                % ~
            catch ME
                % logger push only if exception triggered
                app.Controller.Logger.Push("Register", "UIFigureWindowButtonMotion", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Value changed function: Switch_Log
        function Switch_LogValueChanged(app, event)
            value = app.Switch_Log.Value;

            app.TextArea_Log.Enable = value;
        end

        % Menu selected function: Menu_RegEvaluate
        function Menu_RegEvaluateSelected(app, event)
            try
                if isempty(app.SUBAPPs.EV) || ~isvalid(app.SUBAPPs.EV)
                    app.SUBAPPs.EV = Evaluator(app);
                end

                app.Controller.Logger.Push("Register", "Menu_RegEvaluateSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_RegEvaluateSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_ExportLog
        function Menu_ExportLogSelected(app, event)
            try
                % Get the file path for locating images
                pathToMLAPP = fileparts(mfilename('fullpath'));
                path = [pathToMLAPP, filesep, 'log'];
                if ~isfolder(path)
                    mkdir(path);
                end

                ExportTextArea_Log(app, path);

                GenSysMsg(app, ["LOGFILE_EXPORT_TO", " "+path]);

                app.Controller.Logger.Push("Register", "Menu_ExportLogSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_ExportLogSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_ClearLog
        function Menu_ClearLogSelected(app, event)
            try
                ClearTextArea_Log(app);

                % clear system message
                app.PIVs.AppMessage = [];

                app.Controller.Logger.Push("Register", "Menu_ClearLogSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_ClearLogSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Value changed function: Button_Run
        function Button_RunValueChanged(app, event)
            try
                value = app.Button_Run.Value;

                if value == true
                    if any(app.PIVs.RegisterOptionsLock{1,:}) == true
                        RegisterGo(app);
                    else
                        RegNotReadyWarn(app);
                    end
                else
                    % stop the registration
                    switch app.Controller.RegisterKernel.State
                        case app.Controller.RegisterKernel.KERNEL_ON
                            % turn off the kernel
                            % change the button status
                            app.Button_Run.BackgroundColor = [1, 1, 0];
                            app.Button_Run.Text = app.PIVs.RegionConfig.chmap.BREAK;

                            % send the stop signal
                            app.Controller.RegisterKernel.stop();
                        case app.Controller.RegisterKernel.KERNEL_OFF

                        otherwise
                            app.Button_Run.Text = app.PIVs.RegionConfig.chmap.RUN;
                            app.Button_Run.BackgroundColor = [0.96,0.96,0.96];
                    end
                end

                app.Controller.Logger.Push("Register", "Button_RunValueChanged");
            catch ME
                app.Controller.Logger.Push("Register", "Button_RunValueChanged", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_AddTimeStamp
        function Menu_AddTimeStampSelected(app, event)
            try
                % switch the checked status
                value = app.Menu_AddTimeStamp.Checked;

                if value == false
                    % new text object on screen
                    app.PDVs.TimeTextObject.handle = text(app.UIAxes_Slice, ...
                        1, 10, 0, "00:00:00.000", "FontSize",14, ...
                        "FontName","Times New Roman","Color",[1,1,0],...
                        "HorizontalAlignment","left");
                    time = seconds(app.Controller.ProjectManager.Project.img_cur.Time);
                    time.Format = 'hh:mm:ss.SSS';
                    app.PDVs.TimeTextObject.text = string(time);
                    app.PDVs.TimeTextObject.handle.String = app.PDVs.TimeTextObject.text(app.Controller.ProjectManager.Project.fidx_cur);
                    app.PDVs.TimeTextObject.ismoving = false;
                    app.PDVs.TimeTextObject.handle.ButtonDownFcn = ...
                        createCallbackFcn(app,@TimeText_ButtonDownFcn,true);
                    app.Menu_AddTimeStamp.Checked = "on";
                else
                    delete(app.PDVs.TimeTextObject.handle);
                    app.PDVs.TimeTextObject.handle = [];
                    app.PDVs.TimeTextObject.ismoving = [];
                    app.Menu_AddTimeStamp.Checked = "off";
                end

                app.Controller.Logger.Push("Register", "Menu_TimeStampSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_TimeStampSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_AddMetaData
        function Menu_AddMetaDataSelected(app, event)
            try
                % switch the checked status
                value = app.Menu_AddMetaData.Checked;

                if value == false
                    % load the meta data file
                    metadata = LoadMetaData(app.Controller.ProjectManager.Project.img_cur.Time);
                    if ~isempty(metadata)
                        % new text object on screen
                        app.PDVs.TimeTextObject.handle = text(app.UIAxes_Slice, ...
                            app.Controller.ProjectManager.Project.img_cur.MetaData.width-50,10,0,"water",...
                            "FontSize",14,"FontName","Times New Roman","Color",[1,1,0],...
                            "HorizontalAlignment","right");
                        app.PDVs.TimeTextObject.text = metadata;
                        app.PDVs.TimeTextObject.ismoving = false;
                        app.PDVs.TimeTextObject.handle.ButtonDownFcn = ...
                            createCallbackFcn(app,@MetaText_ButtonDownFcn,true);
                        app.Menu_AddMetaData.Checked = "on";
                    end
                else
                    delete(app.PDVs.TimeTextObject.handle);
                    app.PDVs.TimeTextObject.handle = [];
                    app.PDVs.TimeTextObject.text = [];
                    app.PDVs.TimeTextObject.ismoving = [];
                    app.Menu_AddMetaData.Checked = "off";
                end

                app.Controller.Logger.Push("Register", "Menu_MetaMarkerSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_MetaMarkerSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_RegRange
        function Menu_RegRangeSelected(app, event)
            try
                prompt = {['\fontsize{12} ', app.PIVs.RegionConfig.chmap.TIME_RANGE.char()]};
                dlgtitle = app.PIVs.RegionConfig.chmap.UNREGISTRATERED_FRAMES.char();
                dims = [1 40];
                definput = {char(app.Controller.ProjectManager.Project.frames_reg(1))};
                dlgopts.WindowStyle = 'normal';
                dlgopts.Interpreter = "tex";
                dlgout = inputdlg(prompt,dlgtitle,dims,definput,dlgopts);
                if isempty(dlgout)
                    return;
                else
                    dlgout=dlgout{1};
                end

                % validate inputs updation
                rf = str2num(strrep(dlgout,'end', ...
                    num2str(app.Controller.ProjectManager.Project.img_cur.MetaData.frames))); %#ok<ST2NM>
                if isempty(rf)
                    % ERROR: display at anytime
                    uialert(app.Reg3DV500WittgensteinUIFigure, ...
                        app.PIVs.RegionConfig.chmap.INVALID_FRAME_ORDER, ...
                        app.PIVs.RegionConfig.chmap.ERROR, "Icon","error");
                else
                    if numel(rf) > app.Controller.ProjectManager.Project.img_cur.MetaData.frames
                        % ERROR: display at anytime
                        uialert(app.Reg3DV500WittgensteinUIFigure, ...
                            app.PIVs.RegionConfig.chmap.FRAME_OVERFLOW, ...
                            app.PIVs.RegionConfig.chmap.ERROR, "Icon","error");
                    else
                        app.Controller.ProjectManager.Project.frames_reg(1) = string(dlgout);
                    end
                end

                app.Controller.Logger.Push("Register", "Menu_RegRangeSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_RegRangeSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_AddScaleBar
        function Menu_AddScaleBarSelected(app, event)
            try
                app.PDVs.ScaleBar.Locked = true;

                % disable the view mode
                app.Menu_DisplayMode.Enable = "off";

                app.SUBAPPs.SB = ScaleBar(app);
                % hold the handle until end setting
                uiwait(app.SUBAPPs.SB.ScaleBarUIFigure);

                % enable the view mode after modifying
                app.Menu_DisplayMode.Enable = "on";

                app.PDVs.ScaleBar.Locked = false;

                app.Controller.Logger.Push("Register", "Menu_ScaleBarSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_ScaleBarSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Close request function: Reg3DV500WittgensteinUIFigure
        function Reg3DV500WittgensteinUIFigureCloseRequest(app, event)
            try
                % close this
                CloseApp(app);
            catch ME
                if ~isempty(app.Controller.Logger) && isvalid(app.Controller.Logger)
                    app.Controller.Logger.Push("Register", "UIFigureCloseRequest", ...
                        ME.identifier);
                end

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_RegOptions
        function Menu_RegOptionsSelected(app, event)
            % This function assigns different registration setting by
            % using channel(s) number
            try
                if isempty(app.SUBAPPs.RS) || ~isvalid(app.SUBAPPs.RS)
                    if isscalar(app.Controller.ProjectManager.Project.c_order)
                        % calling the one channel setting subapp
                        app.SUBAPPs.RS = OCRegSetter(app);
                    elseif numel(app.Controller.ProjectManager.Project.c_order) == 2
                        % calling the two channels setting subapp
                        app.SUBAPPs.RS = TCRegSetter(app);
                    else
                        app.SUBAPPs.RS = [];
                        % ERROR: display at anytime
                        uialert(app.Reg3DV500WittgensteinUIFigure, ...
                            app.PIVs.RegionConfig.chmap.UNSUPPORTED_CHANNEL_NUMBER, ...
                            app.PIVs.RegionConfig.chmap.ERROR, "Icon","error");
                        return;
                    end
                end

                app.Controller.Logger.Push("Register", "Menu_RegOptionsSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_RegOptionsSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_MaxZProjection
        function Menu_MaxZProjectionSelected(app, event)
            try
                switch app.Menu_MaxZProjection.Checked
                    case "on"
                        app.Menu_MaxZProjection.Checked = "off";
                        app.PDVs.ZProjectionMode = "none";
                        SetSlider_Slice(app,'on');
                    case "off"
                        app.Menu_MaxZProjection.Checked = "on";
                        app.Menu_MinZProjection.Checked = "off";
                        app.Menu_MedianZProjection.Checked = "off";
                        app.Menu_MeanZProjection.Checked = "off";

                        app.PDVs.ZProjectionMode = "max";
                        SetSlider_Slice(app,'freeze');

                        PanelWaiting(app);
                        SourceZProjection(app,[],"max");
                        PanelRecovered(app);
                    otherwise
                end

                RefreshViewer(app, "Z");

                app.Controller.Logger.Push("Register", "Menu_MaxZProjectionSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_MaxZProjectionSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_MinZProjection
        function Menu_MinZProjectionSelected(app, event)
            try
                switch app.Menu_MinZProjection.Checked
                    case "on"
                        app.Menu_MinZProjection.Checked = "off";
                        app.PDVs.ZProjectionMode = "none";
                        SetSlider_Slice(app,'on');
                    case "off"
                        app.Menu_MaxZProjection.Checked = "off";
                        app.Menu_MinZProjection.Checked = "on";
                        app.Menu_MedianZProjection.Checked = "off";
                        app.Menu_MeanZProjection.Checked = "off";

                        app.PDVs.ZProjectionMode = "min";
                        SetSlider_Slice(app,'freeze');

                        PanelWaiting(app);
                        SourceZProjection(app,[],"min");
                        PanelRecovered(app);
                    otherwise
                end

                RefreshViewer(app, "Z");

                app.Controller.Logger.Push("Register", "Menu_MinZProjectionSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_MinZProjectionSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_MedianZProjection
        function Menu_MedianZProjectionSelected(app, event)
            try
                switch app.Menu_MedianZProjection.Checked
                    case "on"
                        app.Menu_MedianZProjection.Checked = "off";
                        app.PDVs.ZProjectionMode = "none";
                        SetSlider_Slice(app,'on');
                    case "off"
                        app.Menu_MaxZProjection.Checked = "off";
                        app.Menu_MinZProjection.Checked = "off";
                        app.Menu_MedianZProjection.Checked = "on";
                        app.Menu_MeanZProjection.Checked = "off";

                        app.PDVs.ZProjectionMode = "median";
                        SetSlider_Slice(app,'freeze');

                        PanelWaiting(app);
                        SourceZProjection(app,[],"median");
                        PanelRecovered(app);
                    otherwise
                end

                RefreshViewer(app, "Z");

                app.Controller.Logger.Push("Register", "Menu_MedianZProjectionSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_MedianZProjectionSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_MeanZProjection
        function Menu_MeanZProjectionSelected(app, event)
            try
                switch app.Menu_MeanZProjection.Checked
                    case "on"
                        app.Menu_MeanZProjection.Checked = "off";
                        app.PDVs.ZProjectionMode = "none";
                        SetSlider_Slice(app,'on');
                    case "off"
                        app.Menu_MaxZProjection.Checked = "off";
                        app.Menu_MinZProjection.Checked = "off";
                        app.Menu_MedianZProjection.Checked = "off";
                        app.Menu_MeanZProjection.Checked = "on";

                        app.PDVs.ZProjectionMode = "mean";
                        SetSlider_Slice(app,'freeze');

                        PanelWaiting(app);
                        SourceZProjection(app,[],"mean");
                        PanelRecovered(app);
                    otherwise
                end

                RefreshViewer(app, "Z");

                app.Controller.Logger.Push("Register", "Menu_MeanZProjectionSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_MeanZProjectionSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_Quit
        function Menu_QuitSelected(app, event)
            try
                % close this
                CloseApp(app);
            catch ME
                if ~isempty(app.Controller.Logger) && isvalid(app.Controller.Logger)
                    app.Controller.Logger.Push("Register", "Menu_QuitSelected", ...
                        ME.identifier);
                end

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_ChannelAlignment
        function Menu_ChannelAlignmentSelected(app, event)
            try
                if app.PIVs.ChannelAligningLock == false
                    app.PIVs.ChannelAligningLock = true;
                    app.Menu_ChannelAlignment.Checked = "on";
                    % lock the player controller
                    LockViewerController(app);
                    % call subapp channel alignment
                    if isempty(app.SUBAPPs.CC) || ~isvalid(app.SUBAPPs.CC)
                        app.SUBAPPs.CC = ChannelCorrector(app);
                        waitfor(app.SUBAPPs.CC);
                    end
                    UnlockViewerController(app);
                    app.PIVs.ChannelAligningLock = false;
                    app.Menu_ChannelAlignment.Checked = "off";
                    RefreshViewer(app);
                else
                    delete(app.SUBAPPs.CC);
                    UnlockViewerController(app);
                    app.PIVs.ChannelAligningLock = false;
                    app.Menu_ChannelAlignment.Checked = "off";
                    RefreshViewer(app);
                end

                app.Controller.Logger.Push("Register", "Menu_ChannelAlignmentSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_ChannelAlignmentSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Window scroll wheel function: Reg3DV500WittgensteinUIFigure
        function Reg3DV500WittgensteinUIFigureWindowScrollWheel(app, event)
            try
                if isempty(fieldnames(app.PDVs)), return; end

                vSC = event.VerticalScrollCount;

                % update the slice viewer
                if ~isempty(app.PDVs.ImageHandle) && isvalid(app.PDVs.ImageHandle)
                    if (app.UIAxes_Slice.CurrentPoint(2,2) > app.PIVs.SliceScrollCursorLimit(1)) ...
                            && (app.UIAxes_Slice.CurrentPoint(2,2) < app.PIVs.SliceScrollCursorLimit(2))
                        dz = 0;
                        if vSC < 0
                            while dz > vSC, NextSlicePtr(app); dz=dz-1; end
                        else
                            while dz < vSC, PrevSlicePtr(app); dz=dz+1; end
                        end
                        RefreshViewer(app, "Z");
                    end
                end

                % too many loop, omit logger 
                % ~
            catch ME
                app.Controller.Logger.Push("Register", "UIFigureWindowScrollWheel", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Size changed function: Panel_Viewer
        function Panel_ViewerSizeChanged(app, event)
            
            if isempty(fieldnames(app.PDVs)), return; end

            if (~isempty(app.PDVs.ImageHandle) && isvalid(app.PDVs.ImageHandle)) ...
                    || (~isempty(app.PDVs.VolumeHandle) && isvalid(app.PDVs.VolumeHandle))
                
                UpdateYScrollLim(app);
                ScalingProgressBar(app);
            end

            UpdateHistoryManagerYSliderLim(app);
        end

        % Image clicked function: Image_Update
        function Image_UpdateClicked(app, event)
            try
                % Click the image for updating
                [~, url, ver] = acquire_update(app.PIVs.UpdateOptions.repository, Register.VER);
                [path, file, ext] = fileparts(url);

                % read the update information from remote host
                fileToUpdateTxt = [path, filesep, 'update ', 'V', ver, ...
                    char(constdef.HLOG_FILE_EXT)];
                if isfile(fileToUpdateTxt)
                    update_info = readlines(fileToUpdateTxt);
                    % text completeness with higher priority
                    if numel(update_info) > 9
                        slt = uiconfirm(app.Reg3DV500WittgensteinUIFigure, string(update_info), ...
                            app.PIVs.RegionConfig.chmap.NOTIFICATION, "Icon","info","Options",{'Update','Cancel'}, ...
                            "Interpreter","none");
                    else    % uiconfirm bug: lost text if #strings > 7 on "tex" interpreter since R2021b
                        update_info = Register.ReformatUpdateText(update_info);
                        slt = uiconfirm(app.Reg3DV500WittgensteinUIFigure, string(update_info), ...
                            app.PIVs.RegionConfig.chmap.NOTIFICATION, "Icon","info","Options",{'Update','Cancel'}, ...
                            "Interpreter","tex");
                    end
                    if ~strcmp(slt, 'Update')
                        return;
                    end
                end

                fileToMLAPP = [file, ext];
                bar = uiprogressdlg(app.Reg3DV500WittgensteinUIFigure,...
                    "Icon","info", "Indeterminate","on",...
                    "Message",app.PIVs.RegionConfig.chmap.DOWNLOADING);

                if ~exist(['downloads', filesep], "dir")
                    mkdir(['downloads', filesep]);
                end
                copyfile(url, ['downloads', filesep, fileToMLAPP]);

                close(bar);

                % call outer script generator
                [~, att] = fileattrib(['downloads', filesep, fileToMLAPP]);

                status = reg3d_update(string(att.Name), app.PIVs.UpdateOptions.auto_clean);

                % set the update flag
                app.PIVs.UpdateOptions.is_update = status;

                if status == true
                    uialert(app.Reg3DV500WittgensteinUIFigure,...
                        app.PIVs.RegionConfig.chmap.FINISH_UPDATE, ...
                        app.PIVs.RegionConfig.chmap.NOTIFICATION,"Icon","success");
                else
                    % ERROR: display at anytime
                    uialert(app.Reg3DV500WittgensteinUIFigure,...
                        app.PIVs.RegionConfig.chmap.UPDATE_SCRIPT_FAILED, ...
                        app.PIVs.RegionConfig.chmap.ERROR, "Icon","error");
                    delete(['downloads', filesep, fileToMLAPP]);
                end

                app.Controller.Logger.Push("Register", "Image_UpdateClicked");
            catch ME
                app.Controller.Logger.Push("Register", "Image_UpdateClicked", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_SignalExtractor
        function Menu_SignalExtractorSelected(app, event)
            try
                if isempty(app.SUBAPPs.SE) || ~isvalid(app.SUBAPPs.SE)
                    app.SUBAPPs.SE = SignalEstimator(app);
                end

                app.Controller.Logger.Push("Register", "Menu_SignalExtractorSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_SignalExtractorSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_FlipHorizontal
        function Menu_FlipHorizontalSelected(app, event)
            try
                app.Menu_FlipHorizontal.Checked = ~app.Menu_FlipHorizontal.Checked;

                PanelWaiting(app);

                VolumePostureAdjustment(app, "fliplr");

                PanelRecovered(app);

                RefreshViewer(app);

                app.Controller.Logger.Push("Register", "Menu_FlipHorizontalSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_FlipHorizontalSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_FlipVertical
        function Menu_FlipVerticalSelected(app, event)
            try
                app.Menu_FlipVertical.Checked = ~app.Menu_FlipVertical.Checked;

                PanelWaiting(app);

                VolumePostureAdjustment(app, "flipud");

                PanelRecovered(app);

                RefreshViewer(app);

                app.Controller.Logger.Push("Register", "Menu_FlipVerticalSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_FlipVerticalSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_RotateClockwise90
        function Menu_RotateClockwise90Selected(app, event)
            try
                PanelWaiting(app);

                VolumePostureAdjustment(app, "-rot90");

                PanelRecovered(app);

                RefreshViewer(app);

                app.Controller.Logger.Push("Register", "Menu_RotateClockwise90Selected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_RotateClockwise90Selected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_RotateCounterclockwise90
        function Menu_RotateCounterclockwise90Selected(app, event)
            try
                PanelWaiting(app);

                VolumePostureAdjustment(app, "rot90");

                PanelRecovered(app);

                RefreshViewer(app);

                app.Controller.Logger.Push("Register", "Menu_RotateCounterclockwise90Selected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_RotateCounterclockwise90Selected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_View2D
        function Menu_View2DSelected(app, event)
            try
                app.PDVs.DisplayViewMode = "2D";

                app.Menu_View2D.Checked = "on";
                app.Menu_View3D.Checked = "off";

                app.Panel_Viewer.Title = app.PIVs.RegionConfig.chmap.SLICE_VIEW;
                if app.PDVs.ZProjectionMode == "none"
                    SetSlider_Slice(app, 'on');
                end

                if app.PIVs.ViewerLock == false
                    RemoveViewer(app);
                    AppendViewer(app);

                    InitViewer(app);

                    RefreshViewer(app);
                end

                UnlockComponents_2DView(app);

                app.Controller.Logger.Push("Register", "Menu_View2DSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_View2DSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_View3D
        function Menu_View3DSelected(app, event)
            try
                if ~isempty(app.SUBAPPs.CS) && isvalid(app.SUBAPPs.CS)
                    slt = uiconfirm(app.Reg3DV500WittgensteinUIFigure, ...
                        app.PIVs.RegionConfig.chmap.SERMENTATION_LOST_WARNING, ...
                        app.PIVs.RegionConfig.chmap.WARNING, "Icon","warning");
                    if slt ~= "OK"
                        return;
                    else
                        CloseSubApp(app, "CS");
                    end
                end

                app.PDVs.DisplayViewMode = "3D";

                app.Menu_View2D.Checked = "off";
                app.Menu_View3D.Checked = "on";

                app.Panel_Viewer.Title = app.PIVs.RegionConfig.chmap.VOLUME_VIEW;
                SetSlider_Slice(app, 'freeze');

                if app.PIVs.ViewerLock == false
                    RemoveViewer(app);
                    AppendViewer(app);

                    InitViewer(app);

                    RefreshViewer(app);
                end

                LockComponents_3DView(app);

                app.Controller.Logger.Push("Register", "Menu_View3DSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_View3DSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_CellSegmentor
        function Menu_CellSegmentorSelected(app, event)
            try
                if isempty(app.SUBAPPs.CS) || ~isvalid(app.SUBAPPs.CS)
                    app.SUBAPPs.CS = CellSegmentor(app);
                end

                app.Controller.Logger.Push("Register", "Menu_CellSegmentorSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_CellSegmentorSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_15F
        function Menu_15FSelected(app, event)
            try
                app.Controller.ProjectManager.Project.fps.fold = 15;
                app.PDVs.MoviePlayerTimer.Period = round(1/(app.Controller.ProjectManager.Project.fps.base*app.Controller.ProjectManager.Project.fps.fold), 3);
                app.Menu_1F.Checked = "off";
                app.Menu_5F.Checked = "off";
                app.Menu_15F.Checked = "on";

                app.Controller.Logger.Push("Register", "Menu_15FSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_15FSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_ResourceManager
        function Menu_ResourceManagerSelected(app, event)
            % This function calls resouerce manager gui
            try
                if isempty(app.SUBAPPs.RM) || ~isvalid(app.SUBAPPs.RM)
                    app.SUBAPPs.RM = ResourceManager(app);
                end
            catch ME
                rethrow(ME);
            end
        end

        % Menu selected function: Menu_DeleteNode
        function Menu_DeleteNodeSelected(app, event)
            % This callback will remove all data which stored in selected
            % branch after the node, note that if active node after the
            % deleted node, active node will be moved automatically

            try
                % lock to avoid motion callback error if no data currently
                app.PIVs.ViewerLock = true;

                % delete node
                app.Controller.ProjectManager.OperationManager.DelNode(event.ContextObject);

                % validate if any data exist
                if isempty(app.Controller.ProjectManager.OperationManager)
                    RemoveViewer(app);
                    RemoveViewerTitle(app);
                    InitAppProperties(app);
                    InitAppearance(app);
                else
                    % restore system status
                    nd = app.Controller.ProjectManager.OperationManager.ActiveNodeData;
                    app.RestoreBasicSystemBy(nd);

                    app.PIVs.ViewerLock = false;
                end

                app.Controller.Logger.Push("Register", "Menu_DeleteNodeSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_DeleteNodeSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_ActivateNode
        function Menu_ActivateNodeSelected(app, event)
            % This function will mark the selected node as active
            try
                % activate node
                app.Controller.ProjectManager.OperationManager.ActivateNode(event.ContextObject);

                % restore system status
                nd = app.Controller.ProjectManager.OperationManager.ActiveNodeData;

                app.RestoreBasicSystemBy(nd);

                app.Controller.Logger.Push("Register", "Menu_ActivateNodeSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_ActivateNodeSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Value changed function: DropDown_Source
        function DropDown_SourceValueChanged(app, event)
            try
                value = app.DropDown_Source.Value;
                app.PDVs.DisplaySource = string(value);

                % update current merge source
                UpdateMergeSource(app);

                % update display
                RefreshViewer(app);

                app.Controller.Logger.Push("Register", "DropDown_SourceValueChanged");
            catch ME
                app.Controller.Logger.Push("Register", "DropDown_SourceValueChanged", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_HistoryManager
        function Menu_HistoryManagerSelected(app, event)
            try
                pathToMLAPP = fileparts(mfilename('fullpath'));
                if app.Menu_HistoryManager.Checked == true
                    % close history manager sidebar
                    app.Reg3DV500WittgensteinUIFigure.Position(3) = app.LAYOUT_WIDTH_INIT;
                    app.GridLayout_RegView.ColumnWidth = {'1x', 0};
                    app.Image_HMIndicator.ImageSource = ...
                        fullfile(pathToMLAPP, 'sources', 'right_arrow_mini.png');
                    app.Menu_HistoryManager.Checked = false;
                else
                    % open history manager sidebar
                    app.Reg3DV500WittgensteinUIFigure.Position(3) = ...
                        app.LAYOUT_WIDTH_INIT + app.RIGHT_SIDEBAR_WIDTH;
                    app.GridLayout_RegView.ColumnWidth = {'1x', app.RIGHT_SIDEBAR_WIDTH};
                    app.Image_HMIndicator.ImageSource = ...
                        fullfile(pathToMLAPP, 'sources', 'left_arrow_mini.png');
                    app.Menu_HistoryManager.Checked = true;
                end

                app.Slider_HistoryViewSplitter.Enable = ...
                    app.Menu_HistoryManager.Checked;
                app.Slider_HistoryViewSplitter.Visible = ...
                    app.Menu_HistoryManager.Checked;

                app.Controller.Logger.Push("Register", "Menu_HistoryManagerSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_HistoryManagerSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Value changing function: Slider_HistoryViewSplitter
        function Slider_HistoryViewSplitterValueChanging(app, event)
            try
                changingValue = event.Value;

                switch app.PIVs.ApplicationOptions.StorageViewStyle
                    case "CLASSICAL"
                        app.StorageManager.State = "full";
                    case "MODERN"
                        % change viewer state dynamically
                        if changingValue > app.StorageManager.HEIGHT_DONUTVIEW/2
                            app.StorageManager.State = "pro";
                        else
                            app.StorageManager.State = "simple";
                        end
                    otherwise
                end

                app.GridLayout_HistoryManager.RowHeight = ...
                    {'1x', ceil(changingValue+app.SLIDER_BOT_CONST), app.SNAPSHOT_HEIGHT};

                % smooth but little slow
                drawnow limitrate nocallbacks

                % too many loop, omit logger 
                % ~
            catch ME
                app.Controller.Logger.Push("Register", "Slider_HistoryViewSplitterValueChanging", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Value changing function: Slider_Slice
        function Slider_SliceValueChanging(app, event)
            try
                value = round(event.Value);
                app.Slider_Slice.Value = value;

                % Synchronize the slice spinner
                app.Spinner_Slice.Value = value;
                app.Controller.ProjectManager.Project.sidx_cur = value;

                RefreshViewer(app, "Z");

                % too many loop, omit logger 
                % ~
            catch ME
                app.Controller.Logger.Push("Register", "Slider_SliceValueChanging", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Value changing function: Slider_Time
        function Slider_TimeValueChanging(app, event)
            value = round(event.Value);
            app.Slider_Time.Value = value;

            % Synchronize the time spinner
            app.Spinner_Time.Value = value;
            app.Controller.ProjectManager.Project.fidx_cur = value;
            app.Controller.ProjectManager.Project.frames_reg(2) = string(value);

            RefreshViewer(app, "T");
        end

        % Menu selected function: Menu_MergeRegNodes
        function Menu_MergeRegNodesSelected(app, event)
            try
                % ask user if keep source branches
                slt = uiconfirm(app.Reg3DV500WittgensteinUIFigure, ...
                    app.PIVs.RegionConfig.chmap.KEEP_ORIGINAL_BRANCHES_OR_NOT, ...
                    app.PIVs.RegionConfig.chmap.NOTIFICATION, "Options", ...
                    [app.PIVs.RegionConfig.chmap.YES, app.PIVs.RegionConfig.chmap.NO], ...
                    "DefaultOption",app.PIVs.RegionConfig.chmap.YES, "Icon","question");

                % call registration history manager to merge branches
                sk_flag = (slt == app.PIVs.RegionConfig.chmap.YES);
                app.Controller.ProjectManager.OperationManager.MergeNodes(sk_flag);

                nd = app.Controller.ProjectManager.OperationManager.ActiveNodeData;

                app.RestoreBasicSystemBy(nd);

                app.Controller.Logger.Push("Register", "Menu_MergeRegNodesSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_MergeRegNodesSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Context menu opening function: ContextMenu_BranchOperation
        function ContextMenu_BranchOperationOpening(app, event)
            try
                % validate if more than or equal two branches selected and need to merge
                if numel(app.Tree_Operations.SelectedNodes) >= 2
                    % validate if nodes are valid (all REGISTER node)
                    for k = 1:numel(app.Tree_Operations.SelectedNodes)
                        if app.Tree_Operations.SelectedNodes(k).NodeData.Operation ...
                                ~= constdef.OP_REGISTER
                            app.Menu_MergeRegNodes.Enable = "off";
                            return;
                        end
                    end
                    app.Menu_MergeRegNodes.Enable = "on";
                else
                    app.Menu_MergeRegNodes.Enable = "off";
                end

                app.Controller.Logger.Push("Register", "ContextMenu_BranchOperationOpening");
            catch ME
                app.Controller.Logger.Push("Register", "ContextMenu_BranchOperationOpening", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_NewProject
        function Menu_NewProjectSelected(app, event)
            try
                % create
                CreateNewProject(app);

                if ~isempty(app.Controller.Logger) && isvalid(app.Controller.Logger) ...
                        && (app.Controller.Logger.Status=="Running")
                    app.Controller.Logger.Push("Register", "Menu_NewProjectSelected");
                end
            catch ME
                if ~isempty(app.Controller.Logger) && isvalid(app.Controller.Logger) ...
                        && (app.Controller.Logger.Status=="Running")
                    app.Controller.Logger.Push("Register", "Menu_NewProjectSelected", ...
                        ME.identifier);
                end

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_OpenProject
        function Menu_OpenProjectSelected(app, event)
            % open project in regproj file
            try
                [file, path] = uigetfile(...
                    {['*', char(constdef.PROJECT_FILE_EXT)], ...
                    [app.PIVs.RegionConfig.chmap.REG_PROJECT_FILE.char(), ...
                    ' (*', char(constdef.PROJECT_FILE_EXT) ,')']},...
                    app.PIVs.RegionConfig.chmap.OPEN_REGISTRATION_PROJECT, ...
                    "Untitled"+constdef.PROJECT_FILE_EXT);

                HoldWindowOn(app);

                if ~isnumeric(file)
                    file = fullfile(path, file);
                    OpenProject(app, file);
                    app.Controller.Logger.Push("Register", "Menu_OpenProjectSelected");
                end
            catch ME
                app.Controller.Logger.Push("Register", "Menu_OpenProjectSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_CloseProject
        function Menu_CloseProjectSelected(app, event)
            try
                CloseProject(app);
            catch ME
                rethrow(ME);
            end
        end

        % Menu selected function: Menu_SaveAs
        function Menu_SaveAsSelected(app, event)
            try
                % get the base file name
                [~, fname, ~] = fileparts(app.Controller.ProjectManager.Project.img_fname);

                optrs_save = string([]);
                optrs = app.Controller.ProjectManager.OperationManager.GetAllPreviousOperatorsAt();
                if ~isempty(optrs)
                    optrs_save = optrs.join("-");
                    fname = fname + "_" + optrs_save;
                end

                c_cur = app.Controller.ProjectManager.Project.c_order(app.Controller.ProjectManager.Project.cidx_cur);
                if isscalar(c_cur)
                    if c_cur == app.Controller.ProjectManager.Project.opt_reg.Options.SC
                        ftype = "structured";
                    else
                        ftype = "functional";
                    end
                else
                    ftype = "mixed";
                end

                app.Reg3DV500WittgensteinUIFigure.Resize = "off";
                PanelWaiting(app);

                if ~isempty(app.Controller.ProjectManager.Project.img_cur)
                    fname_export = fname+"_"+ftype;
                    meta_data = struct("ftype",     ftype, ...
                        "operation", optrs_save);

                    imwriter = ImageWriter(app, fname_export);
                    imwriter.save(app.Controller.ProjectManager.Project.img_cur, c_cur, meta_data);
                end

                if imwriter.Status == 0
                    app.PIVs.PreviousSavedPath = imwriter.Path;
                    GenSysMsg(app, ["IMAGE_EXPORT_SUCCESS",""]);
                else
                    app.PIVs.PreviousSavedPath = "";
                end

                PanelRecovered(app);

                RefreshViewer(app);

                app.Reg3DV500WittgensteinUIFigure.Resize = "on";

                UpdateYScrollLim(app);

                app.Controller.Logger.Push("Register", "Menu_SaveAsSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_SaveAsSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_ExportMovie
        function Menu_ExportMovieSelected(app, event)
            try
                app.SUBAPPs.ME = MovieExporter(app);
                waitfor(app.SUBAPPs.ME);

                if ~isempty(app.Controller.ProjectManager.Project.opt_movout)
                    if ~isempty(app.Controller.ProjectManager.Project.opt_movout.movie_name)
                        fname = app.Controller.ProjectManager.Project.opt_movout.movie_name;
                    else
                        pathToMLAPP = fileparts(mfilename('fullpath'));
                        t = datetime('now','TimeZone','local','Format','yyMMdd');
                        fname = fullfile(pathToMLAPP, string(t)+".avi");
                    end
                    % lock the viewer controller and record status
                    slider_slice_status = app.Slider_Slice.Enable;
                    app.Slider_Slice.Enable = "off";
                    app.Slider_Time.Enable = "off";
                    key_mode_old =  app.PDVs.DisplayLoopMode;
                    app.PDVs.DisplayLoopMode = "one-step";
                    app.Menu_PlayMode.Enable = "off";
                    frame_idx = app.Controller.ProjectManager.Project.fidx_cur;

                    % set the video writer
                    vw = VideoWriter(fname, app.Controller.ProjectManager.Project.opt_movout.movie_type);

                    switch app.Controller.ProjectManager.Project.opt_movout.movie_type
                        case "Motion JPEG 2000"
                            vw.CompressionRatio = app.Controller.ProjectManager.Project.opt_movout.compression_ratio;
                            vw.LosslessCompression = app.Controller.ProjectManager.Project.opt_movout.is_nocompression;
                        otherwise
                            if app.Controller.ProjectManager.Project.opt_movout.movie_type == "Motion JPEG AVI"...
                                    || app.Controller.ProjectManager.Project.opt_movout.movie_type == "MPEG-4"
                                vw.Quality = app.Controller.ProjectManager.Project.opt_movout.quality;
                            elseif app.Controller.ProjectManager.Project.opt_movout.movie_type == "Uncompressed AVI"
                                %
                            end
                    end
                    vw.FrameRate = app.Controller.ProjectManager.Project.opt_movout.fps;

                    % open the video file
                    open(vw);

                    app.SetProgressBar(0);
                    GenSysMsg(app, ["EXPORTING_MOVIE",""]);

                    % setup the capture screen
                    ss = get(0).ScreenSize(3:4);
                    ss_uifig = app.Reg3DV500WittgensteinUIFigure.Position(3:4);
                    ex_fig = figure;
                    setFigure(ex_fig,cat(2,[0,0],ss_uifig./ss));
                    set(ex_fig,"Visible","off");   % hide the window

                    for k = 1:app.Controller.ProjectManager.Project.img_cur.MetaData.frames
                        % reset the frame
                        app.Controller.ProjectManager.Project.fidx_cur = k;
                        RefreshViewer(app);

                        % clear capture figure and copy objects
                        clf(ex_fig);
                        copyobj(app.UIAxes_Slice, ex_fig);
                        ax = get(ex_fig,"CurrentAxes");
                        if ~isempty(app.PDVs.UROIHandle) && isvalid(app.PDVs.UROIHandle)
                            [rect,w,h] = ROI2Rectangle(app.PDVs.UROIHandle);
                            f = getframe(ax,[rect(1,1),rect(2,2),w,h]);
                        else
                            f = getframe(ax);
                        end

                        % resize the rgb
                        if app.Controller.ProjectManager.Project.opt_movout.is_rescale
                            rgb = frame2im(f);
                            rgb = imresize(rgb,...
                                [app.Controller.ProjectManager.Project.opt_movout.height, app.Controller.ProjectManager.Project.opt_movout.width],...
                                app.Controller.ProjectManager.Project.opt_movout.interp_method);
                            f = im2frame(rgb);
                        end

                        % write the frame
                        writeVideo(vw, f);

                        % update bar processing
                        app.SetProgressBar(k/app.Controller.ProjectManager.Project.img_cur.MetaData.frames);
                    end

                    % close the video file
                    close(vw);
                    % close the capture screen
                    close(ex_fig);

                    % unlock the viewer controller and recover key_mode
                    app.Slider_Slice.Enable = slider_slice_status;
                    app.Controller.ProjectManager.Project.fidx_cur = frame_idx;
                    app.Slider_Time.Enable = "on";
                    app.PDVs.DisplayLoopMode = key_mode_old;
                    app.Menu_PlayMode.Enable = "on";

                    RefreshViewer(app);

                    GenSysMsg(app, ["MOVIE_EXPORTED_SUCCESS",""]);
                end

                app.Controller.Logger.Push("Register", "Menu_ExportMovieSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_ExportMovieSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_ImportImage
        function Menu_ImportImageSelected(app, event)
            % if there is empty project, import is valid operation
            if app.PIVs.NewProjectFlag == true ...
                    && app.PIVs.ImageImportPostponeFlag == true ...
                    && isempty(app.Controller.ProjectManager.Project)
                try
                    imgipr = ImageImporter(app);
                    waitfor(imgipr);

                    % load data if no skip flag
                    if app.PIVs.ImageImportProfile.SkipImport == false
                        % lock image interaction
                        app.PIVs.ViewerLock = true;

                        status = LoadImageData(app, dst);

                        if status == 0  % normal image loading exit
                            % append load folder and save profile
                            app.PIVs.ProjectProfile.SourceFolders = ...
                                [app.PIVs.ProjectProfile.SourceFolders, ...
                                app.PIVs.ImageImportProfile.ImportFolder];
                            prf_file = GetProjectProfileFullPath(app);
                            writestruct(app.PIVs.ProjectProfile, prf_file, "FileType","xml");

                            if app.PIVs.ImageImportProfile.IndependentTime == true
                                LoadTimeStamp(app);
                            end
                        end

                        % update the system after loading
                        UpdateSystemAfterLoading(app);

                        % unlock image interaction
                        app.PIVs.ViewerLock = false;

                        SetAppSavedStatus(app, "unsaved");
                    else
                        return;
                    end

                    app.Controller.Logger.Push("Register", "Menu_ImportImageSelected");
                catch ME
                    app.Controller.Logger.Push("Register", "Menu_ImportImageSelected", ...
                        ME.identifier);

                    rethrow(ME);
                end
            else
                % skip
            end
        end

        % Menu selected function: Menu_ImportTime
        function Menu_ImportTimeSelected(app, event)
            try
                if ~isempty(app.Controller.ProjectManager.Project.img_cur)    % correspond to current image
                    % load time
                    [file, path] = uigetfile(...
                        {['*', char(constdef.TIME_FILE_EXT)], ...
                        [app.PIVs.RegionConfig.chmap.TIME_SERIES_FILE.char(), ...
                        ' (*', char(constdef.TIME_FILE_EXT) ,')']},...
                        app.PIVs.RegionConfig.chmap.LOAD_TIME, "camera_time.tim");

                    HoldWindowOn(app);

                    if ~isnumeric(file)
                        rt = readmatrix(fullfile(path, file), "FileType","text");

                        % set time
                        app.Controller.ProjectManager.Project.img_cur.Time = rt;

                        GenSysMsg(app, ["TIME_LOADING_COMPLETE", " "+fullfile(path, file)]);
                    end
                else
                    uialert(app.Reg3DV500WittgensteinUIFigure, app.PIVs.RegionConfig.chmap.TIP_ADD_TIMESTAMPS, ...
                        app.PIVs.RegionConfig.chmap.INFORMATION, "Icon","info");
                end
                app.Controller.Logger.Push("Register", "Menu_ImportImageSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_ImportImageSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_ExportTime
        function Menu_ExportTimeSelected(app, event)
            try
                if ~isempty(app.Controller.ProjectManager.Project.img_cur.Time)
                    if ~isempty(app.Controller.ProjectManager.Project.img_fname) && exist(app.Controller.ProjectManager.Project.img_fname,"file")
                        [~,fname,~] = fileparts(app.Controller.ProjectManager.Project.img_fname);
                    else
                        fname = "processing";
                    end

                    [file, path] = uiputfile(...
                        {['*', char(constdef.TIME_FILE_EXT)], ...
                        [app.PIVs.RegionConfig.chmap.TIME_SERIES_FILE.char(), ...
                        ' (*', char(constdef.TIME_FILE_EXT) ,')']},...
                        app.PIVs.RegionConfig.chmap.EXPORT_TIME,...
                        app.PIVs.PreviousSavedPath + string(fname));

                    HoldWindowOn(app);

                    if ~isnumeric(file)
                        fname = fullfile(path, file);
                        writematrix(app.Controller.ProjectManager.Project.img_cur.Time, fname, "FileType","text");

                        app.PIVs.PreviousSavedPath = path;
                        GenSysMsg(app, ["TIME_EXPORT_SUCCESS",""]);
                    end
                else
                    % ERROR: display at anytime
                    uialert(app.Reg3DV500WittgensteinUIFigure, ...
                        app.PIVs.RegionConfig.chmap.TIME_MISSING, ...
                        app.PIVs.RegionConfig.chmap.ERROR, "Icon","error");
                end

                app.Controller.Logger.Push("Register", "Menu_SaveTimeAsSelected");
            catch ME
                app.Controller.Logger.Push("Register", "Menu_SaveTimeAsSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_Save
        function Menu_SaveSelected(app, event)
            try
                SaveProject(app);
            catch ME
                app.Controller.Logger.Push("Register", "Menu_SaveSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Reg3DV500WittgensteinUIFigure = uifigure('Visible', 'off');
            app.Reg3DV500WittgensteinUIFigure.Name = 'Reg3D V5.0.0 (Wittgenstein)';
            app.Reg3DV500WittgensteinUIFigure.Icon = fullfile(pathToMLAPP, 'sources', 'icon.png');
            app.Reg3DV500WittgensteinUIFigure.CloseRequestFcn = createCallbackFcn(app, @Reg3DV500WittgensteinUIFigureCloseRequest, true);
            app.Reg3DV500WittgensteinUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @Reg3DV500WittgensteinUIFigureWindowButtonMotion, true);
            app.Reg3DV500WittgensteinUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @Reg3DV500WittgensteinUIFigureWindowScrollWheel, true);
            app.Reg3DV500WittgensteinUIFigure.KeyPressFcn = createCallbackFcn(app, @Reg3DV500WittgensteinUIFigureKeyPress, true);
            app.Menu_File = uimenu(app.Reg3DV500WittgensteinUIFigure);
            app.Menu_File.Tooltip = {''};
            app.Menu_File.Text = 'File';
            app.Menu_NewProject = uimenu(app.Menu_File);
            app.Menu_NewProject.MenuSelectedFcn = createCallbackFcn(app, @Menu_NewProjectSelected, true);
            app.Menu_NewProject.Accelerator = 'N';
            app.Menu_NewProject.Text = '新建项目';
            app.Menu_OpenProject = uimenu(app.Menu_File);
            app.Menu_OpenProject.MenuSelectedFcn = createCallbackFcn(app, @Menu_OpenProjectSelected, true);
            app.Menu_OpenProject.Accelerator = 'O';
            app.Menu_OpenProject.Text = '打开项目';
            app.Menu_CloseProject = uimenu(app.Menu_File);
            app.Menu_CloseProject.MenuSelectedFcn = createCallbackFcn(app, @Menu_CloseProjectSelected, true);
            app.Menu_CloseProject.Accelerator = 'P';
            app.Menu_CloseProject.Text = '关闭项目';
            app.Menu_Save = uimenu(app.Menu_File);
            app.Menu_Save.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveSelected, true);
            app.Menu_Save.Separator = 'on';
            app.Menu_Save.Accelerator = 'S';
            app.Menu_Save.Text = '保存';
            app.Menu_SaveAs = uimenu(app.Menu_File);
            app.Menu_SaveAs.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveAsSelected, true);
            app.Menu_SaveAs.Text = '另存为';
            app.Menu_Import = uimenu(app.Menu_File);
            app.Menu_Import.Separator = 'on';
            app.Menu_Import.Text = '导入';
            app.Menu_ImportImage = uimenu(app.Menu_Import);
            app.Menu_ImportImage.MenuSelectedFcn = createCallbackFcn(app, @Menu_ImportImageSelected, true);
            app.Menu_ImportImage.Text = '图像';
            app.Menu_ImportTime = uimenu(app.Menu_Import);
            app.Menu_ImportTime.MenuSelectedFcn = createCallbackFcn(app, @Menu_ImportTimeSelected, true);
            app.Menu_ImportTime.Text = '时间戳';
            app.Menu_Export = uimenu(app.Menu_File);
            app.Menu_Export.Separator = 'on';
            app.Menu_Export.Text = '导出';
            app.Menu_ExportMovie = uimenu(app.Menu_Export);
            app.Menu_ExportMovie.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExportMovieSelected, true);
            app.Menu_ExportMovie.Text = '影片';
            app.Menu_ExportTime = uimenu(app.Menu_Export);
            app.Menu_ExportTime.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExportTimeSelected, true);
            app.Menu_ExportTime.Text = '时间戳';
            app.Menu_Quit = uimenu(app.Menu_File);
            app.Menu_Quit.MenuSelectedFcn = createCallbackFcn(app, @Menu_QuitSelected, true);
            app.Menu_Quit.Separator = 'on';
            app.Menu_Quit.Accelerator = 'X';
            app.Menu_Quit.Text = '退出';
            app.Menu_Image = uimenu(app.Reg3DV500WittgensteinUIFigure);
            app.Menu_Image.Tooltip = {''};
            app.Menu_Image.Text = 'Image';
            app.Menu_LightContrast = uimenu(app.Menu_Image);
            app.Menu_LightContrast.MenuSelectedFcn = createCallbackFcn(app, @Menu_LightContrastSelected, true);
            app.Menu_LightContrast.Accelerator = 'B';
            app.Menu_LightContrast.Text = '亮度/对比度';
            app.Menu_ZProjection = uimenu(app.Menu_Image);
            app.Menu_ZProjection.Text = 'Z投影';
            app.Menu_MaxZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MaxZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MaxZProjectionSelected, true);
            app.Menu_MaxZProjection.Text = '最大值';
            app.Menu_MinZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MinZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MinZProjectionSelected, true);
            app.Menu_MinZProjection.Text = '最小值';
            app.Menu_MedianZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MedianZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MedianZProjectionSelected, true);
            app.Menu_MedianZProjection.Text = '中位值';
            app.Menu_MeanZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MeanZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MeanZProjectionSelected, true);
            app.Menu_MeanZProjection.Text = '平均值';
            app.Menu_ChannelAlignment = uimenu(app.Menu_Image);
            app.Menu_ChannelAlignment.MenuSelectedFcn = createCallbackFcn(app, @Menu_ChannelAlignmentSelected, true);
            app.Menu_ChannelAlignment.Text = '通道对齐';
            app.Menu_Comments = uimenu(app.Menu_Image);
            app.Menu_Comments.Text = '添加注释';
            app.Menu_AddTimeStamp = uimenu(app.Menu_Comments);
            app.Menu_AddTimeStamp.MenuSelectedFcn = createCallbackFcn(app, @Menu_AddTimeStampSelected, true);
            app.Menu_AddTimeStamp.Accelerator = 'T';
            app.Menu_AddTimeStamp.Text = '时间戳';
            app.Menu_AddScaleBar = uimenu(app.Menu_Comments);
            app.Menu_AddScaleBar.MenuSelectedFcn = createCallbackFcn(app, @Menu_AddScaleBarSelected, true);
            app.Menu_AddScaleBar.Accelerator = 'B';
            app.Menu_AddScaleBar.Text = '比例 / 尺';
            app.Menu_AddMetaData = uimenu(app.Menu_Comments);
            app.Menu_AddMetaData.MenuSelectedFcn = createCallbackFcn(app, @Menu_AddMetaDataSelected, true);
            app.Menu_AddMetaData.Accelerator = 'M';
            app.Menu_AddMetaData.Text = '元数据';
            app.Menu_Animation = uimenu(app.Menu_Image);
            app.Menu_Animation.Separator = 'on';
            app.Menu_Animation.Text = '动画';
            app.Menu_1F = uimenu(app.Menu_Animation);
            app.Menu_1F.MenuSelectedFcn = createCallbackFcn(app, @Menu_1FSelected, true);
            app.Menu_1F.Text = '1X';
            app.Menu_5F = uimenu(app.Menu_Animation);
            app.Menu_5F.MenuSelectedFcn = createCallbackFcn(app, @Menu_5FSelected, true);
            app.Menu_5F.Text = '5X';
            app.Menu_15F = uimenu(app.Menu_Animation);
            app.Menu_15F.MenuSelectedFcn = createCallbackFcn(app, @Menu_15FSelected, true);
            app.Menu_15F.Text = '15X';
            app.Menu_PlayMode = uimenu(app.Menu_Image);
            app.Menu_PlayMode.Text = '播放模式';
            app.Menu_Direct = uimenu(app.Menu_PlayMode);
            app.Menu_Direct.MenuSelectedFcn = createCallbackFcn(app, @Menu_DirectSelected, true);
            app.Menu_Direct.Text = '单向';
            app.Menu_Loop = uimenu(app.Menu_PlayMode);
            app.Menu_Loop.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoopSelected, true);
            app.Menu_Loop.Text = '循环';
            app.Menu_Crop3D = uimenu(app.Menu_Image);
            app.Menu_Crop3D.Separator = 'on';
            app.Menu_Crop3D.Text = '体积裁剪';
            app.Menu_CropXY = uimenu(app.Menu_Crop3D);
            app.Menu_CropXY.MenuSelectedFcn = createCallbackFcn(app, @Menu_CropXYSelected, true);
            app.Menu_CropXY.Text = 'XY裁剪';
            app.Menu_CropZ = uimenu(app.Menu_Crop3D);
            app.Menu_CropZ.MenuSelectedFcn = createCallbackFcn(app, @Menu_CropZSelected, true);
            app.Menu_CropZ.Text = 'Z裁剪';
            app.Menu_CropT = uimenu(app.Menu_Image);
            app.Menu_CropT.MenuSelectedFcn = createCallbackFcn(app, @Menu_CropTSelected, true);
            app.Menu_CropT.Text = '帧裁剪';
            app.Menu_Flip = uimenu(app.Menu_Image);
            app.Menu_Flip.Separator = 'on';
            app.Menu_Flip.Text = '翻转';
            app.Menu_FlipHorizontal = uimenu(app.Menu_Flip);
            app.Menu_FlipHorizontal.MenuSelectedFcn = createCallbackFcn(app, @Menu_FlipHorizontalSelected, true);
            app.Menu_FlipHorizontal.Text = '水平';
            app.Menu_FlipVertical = uimenu(app.Menu_Flip);
            app.Menu_FlipVertical.MenuSelectedFcn = createCallbackFcn(app, @Menu_FlipVerticalSelected, true);
            app.Menu_FlipVertical.Text = '垂直';
            app.Menu_Rotate = uimenu(app.Menu_Image);
            app.Menu_Rotate.Text = '旋转';
            app.Menu_RotateClockwise90 = uimenu(app.Menu_Rotate);
            app.Menu_RotateClockwise90.MenuSelectedFcn = createCallbackFcn(app, @Menu_RotateClockwise90Selected, true);
            app.Menu_RotateClockwise90.Text = '顺时针90°';
            app.Menu_RotateCounterclockwise90 = uimenu(app.Menu_Rotate);
            app.Menu_RotateCounterclockwise90.MenuSelectedFcn = createCallbackFcn(app, @Menu_RotateCounterclockwise90Selected, true);
            app.Menu_RotateCounterclockwise90.Text = '逆时针90°';
            app.Menu_Analyze = uimenu(app.Reg3DV500WittgensteinUIFigure);
            app.Menu_Analyze.Tooltip = {''};
            app.Menu_Analyze.Text = 'Analyze';
            app.Menu_Registration = uimenu(app.Menu_Analyze);
            app.Menu_Registration.Text = '图像配准';
            app.Menu_RegOptions = uimenu(app.Menu_Registration);
            app.Menu_RegOptions.MenuSelectedFcn = createCallbackFcn(app, @Menu_RegOptionsSelected, true);
            app.Menu_RegOptions.Text = '参数设置';
            app.Menu_RegRange = uimenu(app.Menu_Registration);
            app.Menu_RegRange.MenuSelectedFcn = createCallbackFcn(app, @Menu_RegRangeSelected, true);
            app.Menu_RegRange.Text = '配准范围';
            app.Menu_RegEvaluate = uimenu(app.Menu_Registration);
            app.Menu_RegEvaluate.MenuSelectedFcn = createCallbackFcn(app, @Menu_RegEvaluateSelected, true);
            app.Menu_RegEvaluate.Text = '结果评估';
            app.Menu_CellSegmentor = uimenu(app.Menu_Analyze);
            app.Menu_CellSegmentor.MenuSelectedFcn = createCallbackFcn(app, @Menu_CellSegmentorSelected, true);
            app.Menu_CellSegmentor.Accelerator = 'D';
            app.Menu_CellSegmentor.Text = '细胞分割';
            app.Menu_SignalExtractor = uimenu(app.Menu_Analyze);
            app.Menu_SignalExtractor.MenuSelectedFcn = createCallbackFcn(app, @Menu_SignalExtractorSelected, true);
            app.Menu_SignalExtractor.Accelerator = 'E';
            app.Menu_SignalExtractor.Text = '信号提取';
            app.Menu_Advanced = uimenu(app.Reg3DV500WittgensteinUIFigure);
            app.Menu_Advanced.Text = 'Advanced';
            app.Menu_ResourceManager = uimenu(app.Menu_Advanced);
            app.Menu_ResourceManager.MenuSelectedFcn = createCallbackFcn(app, @Menu_ResourceManagerSelected, true);
            app.Menu_ResourceManager.Accelerator = 'R';
            app.Menu_ResourceManager.Text = '资源管理';
            app.Menu_HistoryManager = uimenu(app.Menu_Advanced);
            app.Menu_HistoryManager.MenuSelectedFcn = createCallbackFcn(app, @Menu_HistoryManagerSelected, true);
            app.Menu_HistoryManager.Accelerator = 'H';
            app.Menu_HistoryManager.Text = '历史管理';
            app.Menu_PluginsManager = uimenu(app.Menu_Advanced);
            app.Menu_PluginsManager.Text = '插件管理';
            app.Menu_Help = uimenu(app.Reg3DV500WittgensteinUIFigure);
            app.Menu_Help.Tooltip = {''};
            app.Menu_Help.Text = 'Help';
            app.Menu_About = uimenu(app.Menu_Help);
            app.Menu_About.MenuSelectedFcn = createCallbackFcn(app, @Menu_AboutSelected, true);
            app.Menu_About.Text = '关于';
            app.Menu_Documents = uimenu(app.Menu_Help);
            app.Menu_Documents.MenuSelectedFcn = createCallbackFcn(app, @Menu_DocumentsSelected, true);
            app.Menu_Documents.Text = '文档';
            app.GridLayout_RegView = uigridlayout(app.Reg3DV500WittgensteinUIFigure);
            app.GridLayout_HistoryManager = uigridlayout(app.GridLayout_RegView);
            app.Panel_OperationSnapshot = uipanel(app.GridLayout_HistoryManager);
            app.Panel_OperationSnapshot.Title = 'Operation Snapshot';
            app.GridLayout_OperationSnapshot = uigridlayout(app.Panel_OperationSnapshot);
            app.TextArea_OperationSnapshot = uitextarea(app.GridLayout_OperationSnapshot);
            app.TextArea_OperationSnapshot.Editable = 'off';
            app.Panel_OperationsViewer = uipanel(app.GridLayout_HistoryManager);
            app.Panel_OperationsViewer.Title = 'Operation View';
            app.GridLayout_OperationViewer = uigridlayout(app.Panel_OperationsViewer);
            app.Tree_Operations = uitree(app.GridLayout_OperationViewer);
            app.Tree_Operations.Multiselect = 'on';
            app.Tree_Operations.Tooltip = {''};
            app.Panel_StorageViewer = uipanel(app.GridLayout_HistoryManager);
            app.Panel_StorageViewer.Tooltip = {''};
            app.Panel_StorageViewer.Title = 'Storage View';
            app.GridLayout_StorageViewer = uigridlayout(app.Panel_StorageViewer);
            app.GridLayout_RegBody = uigridlayout(app.GridLayout_RegView);
            app.Panel_Control = uipanel(app.GridLayout_RegBody);
            app.Panel_Control.Tooltip = {''};
            app.Panel_Control.Title = 'Control Panel';
            app.GridLayout2 = uigridlayout(app.Panel_Control);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Switch_Log = uiswitch(app.GridLayout3, 'rocker');
            app.Switch_Log.Items = {'off', 'on'};
            app.Switch_Log.ItemsData = {'off', 'on'};
            app.Switch_Log.ValueChangedFcn = createCallbackFcn(app, @Switch_LogValueChanged, true);
            app.Switch_Log.Tooltip = {''};
            app.Switch_Log.Value = 'on';
            app.TextArea_Log = uitextarea(app.GridLayout3);
            app.TextArea_Log.Editable = 'off';
            app.TextArea_Log.Placeholder = 'Welcome to Reg3D App';
            app.Label_20 = uilabel(app.GridLayout3);
            app.Label_20.Text = 'Log';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.Button_Run = uibutton(app.GridLayout4, 'state');
            app.Button_Run.ValueChangedFcn = createCallbackFcn(app, @Button_RunValueChanged, true);
            app.Button_Run.Tooltip = {''};
            app.Button_Run.Icon = fullfile(pathToMLAPP, 'sources', 'run.png');
            app.Button_Run.Text = 'Run';
            app.Label_19 = uilabel(app.GridLayout4);
            app.Label_19.Text = 'Source';
            app.DropDown_Source = uidropdown(app.GridLayout4);
            app.DropDown_Source.Items = {};
            app.DropDown_Source.ValueChangedFcn = createCallbackFcn(app, @DropDown_SourceValueChanged, true);
            app.DropDown_Source.Value = {};
            app.Panel_Viewer = uipanel(app.GridLayout_RegBody);
            app.Panel_Viewer.Title = 'Slice View';
            app.Panel_Viewer.SizeChangedFcn = createCallbackFcn(app, @Panel_ViewerSizeChanged, true);
            app.GridLayout_ImageViewer = uigridlayout(app.Panel_Viewer);
            app.Label_2 = uilabel(app.GridLayout_ImageViewer);
            app.Label_2.Text = 'Slice';
            app.Slider_Slice = uislider(app.GridLayout_ImageViewer);
            app.Slider_Slice.Limits = [0 1];
            app.Slider_Slice.MajorTicks = [];
            app.Slider_Slice.ValueChangingFcn = createCallbackFcn(app, @Slider_SliceValueChanging, true);
            app.Slider_Slice.MinorTicks = [0 1];
            app.Slider_Slice.Tooltip = {''};
            app.GridLayout = uigridlayout(app.GridLayout_ImageViewer);
            app.Spinner_Time = uispinner(app.GridLayout);
            app.Spinner_Time.Limits = [1 Inf];
            app.Spinner_Time.ValueDisplayFormat = '%.0f';
            app.Spinner_Time.ValueChangedFcn = createCallbackFcn(app, @Spinner_TimeValueChanged, true);
            app.Spinner_Time.Tooltip = {''};
            app.Spinner_Time.Value = 1;
            app.Spinner_Slice = uispinner(app.GridLayout_ImageViewer);
            app.Spinner_Slice.Limits = [1 Inf];
            app.Spinner_Slice.ValueDisplayFormat = '%.0f';
            app.Spinner_Slice.ValueChangedFcn = createCallbackFcn(app, @Spinner_SliceValueChanged, true);
            app.Spinner_Slice.Tooltip = {''};
            app.Spinner_Slice.Value = 1;
            app.Image_Update = uiimage(app.GridLayout_ImageViewer);
            app.Image_Update.ImageClickedFcn = createCallbackFcn(app, @Image_UpdateClicked, true);
            app.Image_Update.Visible = 'off';
            app.Image_Update.ImageSource = fullfile(pathToMLAPP, 'sources', 'update_release.png');
            app.GridLayout_SidebarRegion = uigridlayout(app.GridLayout_ImageViewer);
            app.GridLayout6 = uigridlayout(app.GridLayout_SidebarRegion);
            app.Image_HMIndicator = uiimage(app.GridLayout6);
            app.Image_HMIndicator.Enable = 'off';
            app.Image_HMIndicator.ImageSource = fullfile(pathToMLAPP, 'sources', 'right_arrow_mini.png');
            app.Slider_HistoryViewSplitter = uislider(app.GridLayout_SidebarRegion);
            app.Slider_HistoryViewSplitter.Limits = [0 1];
            app.Slider_HistoryViewSplitter.MajorTicks = [];
            app.Slider_HistoryViewSplitter.ValueChangingFcn = createCallbackFcn(app, @Slider_HistoryViewSplitterValueChanging, true);
            app.Slider_HistoryViewSplitter.MinorTicks = [0 1];
            app.Slider_HistoryViewSplitter.Enable = 'off';
            app.GridLayout_ViewBody = uigridlayout(app.GridLayout_ImageViewer);
            app.GridLayout_OuterPB = uigridlayout(app.GridLayout_ViewBody);
            app.GridLayout_ProgressBar = uigridlayout(app.GridLayout_OuterPB);
            app.Label_ProgressBar = uilabel(app.GridLayout_ProgressBar);
            app.Label_ProgressBar.Text = '';
            app.GridLayout_MainWin = uigridlayout(app.GridLayout_ViewBody);
            app.Label = uilabel(app.GridLayout_ImageViewer);
            app.Label.Text = 'Time(Frame)';
            app.Slider_Time = uislider(app.GridLayout_ImageViewer);
            app.Slider_Time.Limits = [0 1];
            app.Slider_Time.MajorTicks = [];
            app.Slider_Time.ValueChangingFcn = createCallbackFcn(app, @Slider_TimeValueChanging, true);
            app.Slider_Time.MinorTicks = [0 1];
            app.Slider_Time.Tooltip = {''};
            app.Label_ImageInfo = uilabel(app.GridLayout_ImageViewer);
            app.Label_ImageInfo.Visible = 'off';
            app.Label_ImageInfo.Text = 'Image Information';
            app.ContextMenu_Player = uicontextmenu(app.Reg3DV500WittgensteinUIFigure);
            app.Menu_ROI = uimenu(app.ContextMenu_Player);
            app.Menu_ROI.Text = '设置ROI';
            app.Menu_RectangleROI = uimenu(app.Menu_ROI);
            app.Menu_RectangleROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_RectangleROISelected, true);
            app.Menu_RectangleROI.Text = '矩形';
            app.Menu_CircleROI = uimenu(app.Menu_ROI);
            app.Menu_CircleROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_CircleROISelected, true);
            app.Menu_CircleROI.Text = '圆形';
            app.Menu_PolygonROI = uimenu(app.Menu_ROI);
            app.Menu_PolygonROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_PolygonROISelected, true);
            app.Menu_PolygonROI.Text = '多边形';
            app.Menu_FreehandROI = uimenu(app.Menu_ROI);
            app.Menu_FreehandROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_FreehandROISelected, true);
            app.Menu_FreehandROI.Text = '自由选择';
            app.Menu_Channel = uimenu(app.ContextMenu_Player);
            app.Menu_Channel.Text = '颜色通道';
            app.Menu_DisplayMode = uimenu(app.ContextMenu_Player);
            app.Menu_DisplayMode.Text = '显示模式';
            app.Menu_View2D = uimenu(app.Menu_DisplayMode);
            app.Menu_View2D.MenuSelectedFcn = createCallbackFcn(app, @Menu_View2DSelected, true);
            app.Menu_View2D.Checked = 'on';
            app.Menu_View2D.Text = '2D';
            app.Menu_View3D = uimenu(app.Menu_DisplayMode);
            app.Menu_View3D.MenuSelectedFcn = createCallbackFcn(app, @Menu_View3DSelected, true);
            app.Menu_View3D.Text = '3D';
            % Assign app.ContextMenu_Player
            app.GridLayout_ImageViewer.ContextMenu = app.ContextMenu_Player;
            app.GridLayout_MainWin.ContextMenu = app.ContextMenu_Player;
            app.ContextMenu_Controller = uicontextmenu(app.Reg3DV500WittgensteinUIFigure);
            app.Menu_ExportLog = uimenu(app.ContextMenu_Controller);
            app.Menu_ExportLog.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExportLogSelected, true);
            app.Menu_ExportLog.Text = '导出';
            app.Menu_ClearLog = uimenu(app.ContextMenu_Controller);
            app.Menu_ClearLog.MenuSelectedFcn = createCallbackFcn(app, @Menu_ClearLogSelected, true);
            app.Menu_ClearLog.Text = '清空';
            % Assign app.ContextMenu_Controller
            app.TextArea_Log.ContextMenu = app.ContextMenu_Controller;
            app.ContextMenu_NodeOperation = uicontextmenu(app.Reg3DV500WittgensteinUIFigure);
            app.Menu_ActivateNode = uimenu(app.ContextMenu_NodeOperation);
            app.Menu_ActivateNode.MenuSelectedFcn = createCallbackFcn(app, @Menu_ActivateNodeSelected, true);
            app.Menu_ActivateNode.Text = '激活节点';
            app.Menu_DeleteNode = uimenu(app.ContextMenu_NodeOperation);
            app.Menu_DeleteNode.MenuSelectedFcn = createCallbackFcn(app, @Menu_DeleteNodeSelected, true);
            app.Menu_DeleteNode.Text = '删除节点';
            app.ContextMenu_BranchOperation = uicontextmenu(app.Reg3DV500WittgensteinUIFigure);
            app.ContextMenu_BranchOperation.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu_BranchOperationOpening, true);
            app.Menu_MergeRegNodes = uimenu(app.ContextMenu_BranchOperation);
            app.Menu_MergeRegNodes.MenuSelectedFcn = createCallbackFcn(app, @Menu_MergeRegNodesSelected, true);
            app.Menu_MergeRegNodes.Text = '合并节点';
            % Assign app.ContextMenu_BranchOperation
            app.Tree_Operations.ContextMenu = app.ContextMenu_BranchOperation;
            % Show the figure after all components are created
            app.Reg3DV500WittgensteinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Register

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Reg3DV500WittgensteinUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.Reg3DV500WittgensteinUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Reg3DV500WittgensteinUIFigure)
        end
    end
end