classdef storage_manager < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout_Main                matlab.ui.container.GridLayout
        GridLayout_ManagerItemsContainer  matlab.ui.container.GridLayout
        GridLayout_ManagerItemsTitle   matlab.ui.container.GridLayout
        Label_ItermsTitle_Storage      matlab.ui.control.Label
        Label_ItemsTitle_Utilization   matlab.ui.control.Label
        Label_ItemsTitle_Node          matlab.ui.control.Label
        GridLayout_PercentageOverview  matlab.ui.container.GridLayout
        GridLayout_HardDriveUsed_ProgressBar  matlab.ui.container.GridLayout
        Label_HardDriveLeftPart        matlab.ui.control.Label
        Label_HardDriveUsedPart        matlab.ui.control.Label
        GridLayout_MemoryUsed_ProgressBar  matlab.ui.container.GridLayout
        Label_MemoryLeftPart           matlab.ui.control.Label
        Label_MemoryUsedPart           matlab.ui.control.Label
        Label_HardDriveUsedPercentage  matlab.ui.control.Label
        Label_MemoryUsedPercentage     matlab.ui.control.Label
        Label_HardDriveUsedTip         matlab.ui.control.Label
        Label_MemoryUsedTip            matlab.ui.control.Label
        Panel_DonutOverview            matlab.ui.container.Panel
    end

    properties (AbortSet, Access = public, GetObservable, SetObservable)
        State (1,1) string {mustBeMember(State, ["simple","pro","full"])} = "full"; % The state of manager, could be "simple" or "pro"
        Style (1,1) string {mustBeMember(Style, ["light", "dark"])} = "light"; % The style of component appearance
    end
    
    properties (Constant)
        VER = "1.0.0"
    end

    properties (Access = public, Constant)
        HEIGHT_OVERVIEW = 70
        HEIGHT_DONUTVIEW = 120
        HEIGHT_DETAILS_TITLE = 25
        HEIGHT_MANAGER_ITEM = 25
        WIDTH_OVERVIEW_BLANK = 5
        WIDTH_OVERVIEW_TAG = 60
        WIDTH_OVERVIEW_DISPBOX = 55
        WIDTH_COMPONENT = 260
    end
    
    properties (Access = private, Hidden)
        caller  (1,1)               % the register object
        boss    (1,1)               % the regohm object
        info    (1,1)   struct      % the storage info struct
        mitems  (:,1)   cell        % cell with storage_manager_item object
        tcl     (1,:)               % TiledChartLayout object

        langmap (1,1)   struct      % mapping for language
    end
    
    methods (Access = ?Register)
        function SetCaller(comp, p)
            arguments
                comp 
                p       (1,1)   Register
            end

           comp.caller = p;
        end

        function RefreshPanelStyle(comp)
            style = comp.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr >= 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    comp.GridLayout_Main.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    comp.GridLayout_PercentageOverview.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    comp.GridLayout_MemoryUsed_ProgressBar.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    comp.GridLayout_HardDriveUsed_ProgressBar.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    comp.Panel_DonutOverview.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    comp.GridLayout_ManagerItemsTitle.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    comp.GridLayout_ManagerItemsContainer.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                case "DARK"
                    comp.GridLayout_Main.BackgroundColor = constdef.GRAYSCALE_DARK;
                    comp.GridLayout_PercentageOverview.BackgroundColor = constdef.GRAYSCALE_DARK;
                    comp.GridLayout_MemoryUsed_ProgressBar.BackgroundColor = constdef.GRAYSCALE_DARK;
                    comp.GridLayout_HardDriveUsed_ProgressBar.BackgroundColor = constdef.GRAYSCALE_DARK;
                    comp.Panel_DonutOverview.BackgroundColor = constdef.GRAYSCALE_DARK;
                    comp.GridLayout_ManagerItemsTitle.BackgroundColor = constdef.GRAYSCALE_DARK;
                    comp.GridLayout_ManagerItemsContainer.BackgroundColor = constdef.GRAYSCALE_DARK;
                otherwise
            end
        end

        function RefreshPanelLanguage(comp)
            [~, comp.langmap] = comp.caller.GetLangsInfo();

            % apply languages
            comp.Label_MemoryUsedTip.Text = comp.langmap.MEMORY;
            comp.Label_HardDriveUsedTip.Text = comp.langmap.HARD_DRIVE;
            comp.Label_ItemsTitle_Node.Text = comp.langmap.NODE;
            comp.Label_ItemsTitle_Utilization.Text = comp.langmap.UTILIZATION;
            comp.Label_ItermsTitle_Storage.Text = comp.langmap.STORAGE;
        end

        function UpdateAppearanceWithoutData(comp, flag)

            % update view
            comp.update_overview();
            comp.update_donuts_view();
            comp.update_details_view(flag);
        end
    end


    methods (Access = ?regohm)
        
        function UpdateView(comp, info, flag, id)
            % regohm invokes this component, update view by tbl
            arguments
                comp 
                info    (1,1)   struct
                flag    (1,1)   logical = true
                id      (1,:)   double  = [1,2,3]
            end

            comp.info = info;

            % update view
            if ismember(1, id)
                comp.update_overview();
            end
            if ismember(2, id)
                comp.update_donuts_view();
            end
            if ismember(3, id)
                comp.update_details_view(flag);
            end
        end

        function SetBoss(comp, p)
            arguments
                comp 
                p       (1,1)   regohm
            end

            comp.boss = p;
        end
    end
    
    methods (Access = private)
        
        function update_overview(comp)
            w = comp.WIDTH_COMPONENT - comp.WIDTH_OVERVIEW_TAG ...
                - comp.WIDTH_OVERVIEW_DISPBOX -4*comp.WIDTH_OVERVIEW_BLANK;

            % set memory used percentage
            x = ceil(comp.info.Overview.MEM(3)*w);
            comp.GridLayout_MemoryUsed_ProgressBar.ColumnWidth = {x, w-x};
            if comp.info.Overview.MEM(3) > 0.1
                comp.Label_MemoryUsedPart.Text = ...
                    sprintf("%.1f%%", round(100*comp.info.Overview.MEM(3)));
            else
                comp.Label_MemoryUsedPart.Text = "";
            end
            comp.Label_MemoryUsedPercentage.Text = sprintf("%.1f GB ", ...
                round(comp.info.Overview.MEM(1)-comp.info.Overview.MEM(2),1));
            if comp.info.Overview.MEM(3) >= 0.95
                comp.Label_MemoryUsedPart.BackgroundColor = constdef.COLOR_PERCENTAGE_URGENT;
            else
                comp.Label_MemoryUsedPart.BackgroundColor = constdef.COLOR_PERCENTAGE_SAFE;
            end

            % set hard drive used percentage
            y = ceil(comp.info.Overview.HDD(3)*w);
            comp.GridLayout_HardDriveUsed_ProgressBar.ColumnWidth = {y, w-y};
            if comp.info.Overview.HDD(3) > 0.1
                comp.Label_HardDriveUsedPart.Text = ...
                    sprintf("%.1f%%", round(100*comp.info.Overview.HDD(3)));
            else
                comp.Label_HardDriveUsedPart.Text = "";
            end
            comp.Label_HardDriveUsedPercentage.Text = sprintf("%.1f GB ", ...
                round(comp.info.Overview.HDD(1)-comp.info.Overview.HDD(2),1));
            if comp.info.Overview.HDD(3) >= 0.95
                comp.Label_HardDriveUsedPart.BackgroundColor = constdef.COLOR_PERCENTAGE_URGENT;
            else
                comp.Label_HardDriveUsedPart.BackgroundColor = constdef.COLOR_PERCENTAGE_SAFE;
            end

        end

        function update_donuts_view(comp)
            % remove previous disp
            if ~isempty(comp.tcl) && isvalid(comp.tcl)
                delete(comp.tcl);
            end

            if ~isempty(comp.info.Details)
                %% summary data
                mem_used = comp.info.Details.Used(comp.info.Details.Location=="MEM");
                mem_left = comp.info.Overview.MEM(1) - sum(mem_used);
                mem_node_tags = [comp.info.Details.NodeTag(comp.info.Details.Location=="MEM"); ...
                    comp.langmap.FREE];

                hdd_used = comp.info.Details.Used(comp.info.Details.Location=="HDD");
                hdd_left = comp.info.Overview.HDD(1) - sum(hdd_used);
                hdd_node_tags = [comp.info.Details.NodeTag(comp.info.Details.Location=="HDD"); ...
                    comp.langmap.FREE];

                %% plot
                % append new tcl
                comp.tcl = tiledlayout(comp.Panel_DonutOverview, 1, 2);

                % if MATLAB version >= R2023b, use modern donutchart
                if ~isMATLABReleaseOlderThan("R2023b")

                    % plot memory donuts on left
                    ax = nexttile(comp.tcl);
                    ax.Toolbar.Visible = 'off';
                    donutchart(comp.tcl, [mem_used; mem_left], mem_node_tags, "StartAngle",270, ...
                        "Direction","clockwise", "ColorOrder",[jet(numel(mem_used)); 0.8,0.8,0.8], ...
                        "InnerRadius",0.7, "Labels",mem_node_tags, ...
                        "Title",comp.langmap.MEMORY);

                    % plot hard drive donuts on left
                    ax = nexttile(comp.tcl);
                    ax.Toolbar.Visible = 'off';
                    donutchart(comp.tcl, [hdd_used; hdd_left], hdd_node_tags, "StartAngle",270, ...
                        "Direction","clockwise", "ColorOrder",[jet(numel(hdd_used)); 0.8,0.8,0.8], ...
                        "InnerRadius",0.7, "Labels",hdd_node_tags, ...
                        "Title",comp.langmap.HARD_DRIVE);
                else    % replace with pie
                    
                    % plot memory pie on left
                    ax = nexttile(comp.tcl);
                    ax.Toolbar.Visible = 'off';
                    p = pie(ax, [mem_used, mem_left], [], mem_node_tags);
                    ColorOrder = [jet(numel(mem_used)); 0.8,0.8,0.8];
                    % remapping patches CData
                    for k = 1:numel(mem_node_tags)
                        % patch at odd index
                        p(2*k-1).FaceColor = ColorOrder(k,:);
                    end

                    title(ax, comp.langmap.MEMORY);

                    % plot hard drive donuts on left
                    ax= nexttile(comp.tcl);
                    ax.Toolbar.Visible = 'off';
                    p = pie(ax, [hdd_used, hdd_left], [], hdd_node_tags);
                    ColorOrder = [jet(numel(hdd_used)); 0.8,0.8,0.8];
                    % remapping patches CData
                    for k = 1:numel(hdd_node_tags)
                        % patch at odd index
                        p(2*k-1).FaceColor = ColorOrder(k,:);
                    end
                    title(ax, comp.langmap.HARD_DRIVE);
                end
            end
        end

        function update_details_view(comp, flag)
            % remove previous disp
            if ~isempty(comp.mitems)
                cellfun(@delete, comp.mitems);
            end
            comp.mitems = {};

            % append manager items
            if ~isempty(comp.info.Details)
                n_items = size(comp.info.Details, 1);

                % resize container
                comp.GridLayout_ManagerItemsContainer.RowHeight ...
                    = repmat({comp.HEIGHT_MANAGER_ITEM}, 1, n_items);

                comp.mitems = cell(n_items, 1);
                for k = 1:size(comp.info.Details, 1)
                    % append items dynamically
                    comp.mitems{k} = storage_manager_item(comp.GridLayout_ManagerItemsContainer);
                    comp.mitems{k}.IsStorageLocked = flag;
                    comp.mitems{k}.Storage = comp.info.Details.Location(k);
                    switch comp.info.Details.Location(k)
                        case "MEM"
                            comp.mitems{k}.Value = ...
                                comp.info.Details.Used(k)/comp.info.Overview.MEM(2);
                        case "HDD"
                            comp.mitems{k}.Value = ...
                                comp.info.Details.Used(k)/comp.info.Overview.HDD(2);
                        otherwise
                    end
                    comp.mitems{k}.NodeTag = comp.info.Details.NodeTag(k);
                    comp.mitems{k}.Layout.Row = k;
                    comp.mitems{k}.Layout.Column = 1;

                    % add storage changed callback
                    comp.mitems{k}.StorageChangedFcn = ...
                        matlab.apps.createCallbackFcn(comp, @StorageChanged_Callback, true);
                end
            end
        end

        function StorageChanged_Callback(comp, event)
            % require boss update storage location
            tag = event.Source.NodeTag;
            storage = event.Source.Storage;
            
            comp.boss.TrySwitchStorage(tag, storage);
        end
    end
    

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % update appearance (state)
            switch comp.State
                case "simple"
                    comp.GridLayout_Main.RowHeight = {comp.HEIGHT_OVERVIEW, ...
                        comp.HEIGHT_DONUTVIEW, 0, 0};
                case "pro"
                    comp.GridLayout_Main.RowHeight = {0, ...
                        comp.HEIGHT_DONUTVIEW, 25, '1x'};
                otherwise
                    comp.GridLayout_Main.RowHeight = {comp.HEIGHT_OVERVIEW, ...
                        comp.HEIGHT_DONUTVIEW, 25, '1x'};

            end
        end

        % 创建底层组件
        function setup(comp)

            comp.Position = [1 1 260 260];
            comp.BackgroundColor = [0.9607843137254902 0.9607843137254902 0.9607843137254902];

            % Create GridLayout_Main
            comp.GridLayout_Main = uigridlayout(comp);
            comp.GridLayout_Main.ColumnWidth = {'1x'};
            comp.GridLayout_Main.RowHeight = {70, 120, 25, '1x'};
            comp.GridLayout_Main.RowSpacing = 5;
            comp.GridLayout_Main.Padding = [0 0 0 0];
            comp.GridLayout_Main.BackgroundColor = [0.96078431372549 0.96078431372549 0.96078431372549];

            % Create Panel_DonutOverview
            comp.Panel_DonutOverview = uipanel(comp.GridLayout_Main);
            comp.Panel_DonutOverview.ForegroundColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Panel_DonutOverview.BackgroundColor = [0.96078431372549 0.96078431372549 0.96078431372549];
            comp.Panel_DonutOverview.Layout.Row = 2;
            comp.Panel_DonutOverview.Layout.Column = 1;

            % Create GridLayout_PercentageOverview
            comp.GridLayout_PercentageOverview = uigridlayout(comp.GridLayout_Main);
            comp.GridLayout_PercentageOverview.ColumnWidth = {60, '1x', 55};
            comp.GridLayout_PercentageOverview.ColumnSpacing = 5;
            comp.GridLayout_PercentageOverview.RowSpacing = 5;
            comp.GridLayout_PercentageOverview.Padding = [5 10 5 5];
            comp.GridLayout_PercentageOverview.Layout.Row = 1;
            comp.GridLayout_PercentageOverview.Layout.Column = 1;
            comp.GridLayout_PercentageOverview.BackgroundColor = [0.96078431372549 0.96078431372549 0.96078431372549];

            % Create Label_MemoryUsedTip
            comp.Label_MemoryUsedTip = uilabel(comp.GridLayout_PercentageOverview);
            comp.Label_MemoryUsedTip.HorizontalAlignment = 'center';
            comp.Label_MemoryUsedTip.FontSize = 14;
            comp.Label_MemoryUsedTip.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Label_MemoryUsedTip.Layout.Row = 1;
            comp.Label_MemoryUsedTip.Layout.Column = 1;
            comp.Label_MemoryUsedTip.Text = 'Memory';

            % Create Label_HardDriveUsedTip
            comp.Label_HardDriveUsedTip = uilabel(comp.GridLayout_PercentageOverview);
            comp.Label_HardDriveUsedTip.HorizontalAlignment = 'center';
            comp.Label_HardDriveUsedTip.FontSize = 14;
            comp.Label_HardDriveUsedTip.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Label_HardDriveUsedTip.Layout.Row = 2;
            comp.Label_HardDriveUsedTip.Layout.Column = 1;
            comp.Label_HardDriveUsedTip.Text = 'Hard Drive';

            % Create Label_MemoryUsedPercentage
            comp.Label_MemoryUsedPercentage = uilabel(comp.GridLayout_PercentageOverview);
            comp.Label_MemoryUsedPercentage.HorizontalAlignment = 'right';
            comp.Label_MemoryUsedPercentage.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Label_MemoryUsedPercentage.Layout.Row = 1;
            comp.Label_MemoryUsedPercentage.Layout.Column = 3;
            comp.Label_MemoryUsedPercentage.Text = '  64.0 GB ';

            % Create Label_HardDriveUsedPercentage
            comp.Label_HardDriveUsedPercentage = uilabel(comp.GridLayout_PercentageOverview);
            comp.Label_HardDriveUsedPercentage.HorizontalAlignment = 'right';
            comp.Label_HardDriveUsedPercentage.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Label_HardDriveUsedPercentage.Layout.Row = 2;
            comp.Label_HardDriveUsedPercentage.Layout.Column = 3;
            comp.Label_HardDriveUsedPercentage.Text = '128.0 GB ';

            % Create GridLayout_MemoryUsed_ProgressBar
            comp.GridLayout_MemoryUsed_ProgressBar = uigridlayout(comp.GridLayout_PercentageOverview);
            comp.GridLayout_MemoryUsed_ProgressBar.ColumnWidth = {0, 125};
            comp.GridLayout_MemoryUsed_ProgressBar.RowHeight = {'1x'};
            comp.GridLayout_MemoryUsed_ProgressBar.ColumnSpacing = 0;
            comp.GridLayout_MemoryUsed_ProgressBar.Padding = [0 5 0 5];
            comp.GridLayout_MemoryUsed_ProgressBar.Layout.Row = 1;
            comp.GridLayout_MemoryUsed_ProgressBar.Layout.Column = 2;
            comp.GridLayout_MemoryUsed_ProgressBar.BackgroundColor = [0.96078431372549 0.96078431372549 0.96078431372549];

            % Create Label_MemoryUsedPart
            comp.Label_MemoryUsedPart = uilabel(comp.GridLayout_MemoryUsed_ProgressBar);
            comp.Label_MemoryUsedPart.BackgroundColor = [0.251 0.749 1];
            comp.Label_MemoryUsedPart.HorizontalAlignment = 'center';
            comp.Label_MemoryUsedPart.FontWeight = 'bold';
            comp.Label_MemoryUsedPart.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Label_MemoryUsedPart.Layout.Row = 1;
            comp.Label_MemoryUsedPart.Layout.Column = 1;
            comp.Label_MemoryUsedPart.Text = '';

            % Create Label_MemoryLeftPart
            comp.Label_MemoryLeftPart = uilabel(comp.GridLayout_MemoryUsed_ProgressBar);
            comp.Label_MemoryLeftPart.BackgroundColor = [0.902 0.902 0.902];
            comp.Label_MemoryLeftPart.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Label_MemoryLeftPart.Layout.Row = 1;
            comp.Label_MemoryLeftPart.Layout.Column = 2;
            comp.Label_MemoryLeftPart.Text = '';

            % Create GridLayout_HardDriveUsed_ProgressBar
            comp.GridLayout_HardDriveUsed_ProgressBar = uigridlayout(comp.GridLayout_PercentageOverview);
            comp.GridLayout_HardDriveUsed_ProgressBar.ColumnWidth = {0, 125};
            comp.GridLayout_HardDriveUsed_ProgressBar.RowHeight = {'1x'};
            comp.GridLayout_HardDriveUsed_ProgressBar.ColumnSpacing = 0;
            comp.GridLayout_HardDriveUsed_ProgressBar.Padding = [0 5 0 5];
            comp.GridLayout_HardDriveUsed_ProgressBar.Layout.Row = 2;
            comp.GridLayout_HardDriveUsed_ProgressBar.Layout.Column = 2;
            comp.GridLayout_HardDriveUsed_ProgressBar.BackgroundColor = [0.96078431372549 0.96078431372549 0.96078431372549];

            % Create Label_HardDriveUsedPart
            comp.Label_HardDriveUsedPart = uilabel(comp.GridLayout_HardDriveUsed_ProgressBar);
            comp.Label_HardDriveUsedPart.BackgroundColor = [1 0.251 0.251];
            comp.Label_HardDriveUsedPart.HorizontalAlignment = 'center';
            comp.Label_HardDriveUsedPart.FontWeight = 'bold';
            comp.Label_HardDriveUsedPart.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Label_HardDriveUsedPart.Layout.Row = 1;
            comp.Label_HardDriveUsedPart.Layout.Column = 1;
            comp.Label_HardDriveUsedPart.Text = '';

            % Create Label_HardDriveLeftPart
            comp.Label_HardDriveLeftPart = uilabel(comp.GridLayout_HardDriveUsed_ProgressBar);
            comp.Label_HardDriveLeftPart.BackgroundColor = [0.902 0.902 0.902];
            comp.Label_HardDriveLeftPart.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Label_HardDriveLeftPart.Layout.Row = 1;
            comp.Label_HardDriveLeftPart.Layout.Column = 2;
            comp.Label_HardDriveLeftPart.Text = '';

            % Create GridLayout_ManagerItemsTitle
            comp.GridLayout_ManagerItemsTitle = uigridlayout(comp.GridLayout_Main);
            comp.GridLayout_ManagerItemsTitle.ColumnWidth = {40, '1x', 60};
            comp.GridLayout_ManagerItemsTitle.RowHeight = {'1x'};
            comp.GridLayout_ManagerItemsTitle.ColumnSpacing = 5;
            comp.GridLayout_ManagerItemsTitle.Padding = [0 0 0 0];
            comp.GridLayout_ManagerItemsTitle.Layout.Row = 3;
            comp.GridLayout_ManagerItemsTitle.Layout.Column = 1;
            comp.GridLayout_ManagerItemsTitle.BackgroundColor = [0.96078431372549 0.96078431372549 0.96078431372549];

            % Create Label_ItemsTitle_Node
            comp.Label_ItemsTitle_Node = uilabel(comp.GridLayout_ManagerItemsTitle);
            comp.Label_ItemsTitle_Node.HorizontalAlignment = 'center';
            comp.Label_ItemsTitle_Node.FontSize = 14;
            comp.Label_ItemsTitle_Node.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Label_ItemsTitle_Node.Layout.Row = 1;
            comp.Label_ItemsTitle_Node.Layout.Column = 1;
            comp.Label_ItemsTitle_Node.Text = 'Node';

            % Create Label_ItemsTitle_Utilization
            comp.Label_ItemsTitle_Utilization = uilabel(comp.GridLayout_ManagerItemsTitle);
            comp.Label_ItemsTitle_Utilization.HorizontalAlignment = 'center';
            comp.Label_ItemsTitle_Utilization.FontSize = 14;
            comp.Label_ItemsTitle_Utilization.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Label_ItemsTitle_Utilization.Layout.Row = 1;
            comp.Label_ItemsTitle_Utilization.Layout.Column = 2;
            comp.Label_ItemsTitle_Utilization.Text = 'Utilization';

            % Create Label_ItermsTitle_Storage
            comp.Label_ItermsTitle_Storage = uilabel(comp.GridLayout_ManagerItemsTitle);
            comp.Label_ItermsTitle_Storage.HorizontalAlignment = 'center';
            comp.Label_ItermsTitle_Storage.FontSize = 14;
            comp.Label_ItermsTitle_Storage.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Label_ItermsTitle_Storage.Layout.Row = 1;
            comp.Label_ItermsTitle_Storage.Layout.Column = 3;
            comp.Label_ItermsTitle_Storage.Text = 'Storage';

            % Create GridLayout_ManagerItemsContainer
            comp.GridLayout_ManagerItemsContainer = uigridlayout(comp.GridLayout_Main);
            comp.GridLayout_ManagerItemsContainer.ColumnWidth = {'1x'};
            comp.GridLayout_ManagerItemsContainer.RowHeight = {25};
            comp.GridLayout_ManagerItemsContainer.Padding = [0 0 0 0];
            comp.GridLayout_ManagerItemsContainer.Layout.Row = 4;
            comp.GridLayout_ManagerItemsContainer.Layout.Column = 1;
            comp.GridLayout_ManagerItemsContainer.Scrollable = 'on';
            comp.GridLayout_ManagerItemsContainer.BackgroundColor = [0.96078431372549 0.96078431372549 0.96078431372549];
        end
    end
end