classdef storage_manager_item < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout_Main           matlab.ui.container.GridLayout
        GridLayout_PercentageBar  matlab.ui.container.GridLayout
        Label_PercentageLeft      matlab.ui.control.Label
        Label_PercentageUsed      matlab.ui.control.Label
        DropDown_StorageLocation  matlab.ui.control.DropDown
        Label_Tag                 matlab.ui.control.Label
    end

    % 具有关联的公共回调的事件
    events (HasCallbackProperty, NotifyAccess = private)
        StorageChanged % Storage location changed callback
    end

    properties (AbortSet, Access = public, GetObservable, SetObservable)
        IsStorageLocked (1,1) logical {mustBeNonempty(IsStorageLocked)} = true; % The lock for storage changing lock
        NodeTag (1,1) string {mustBeNonmissing(NodeTag)} = 1; % Node tag to display
        PBackgroundColor (1,3) double {mustBeInRange(PBackgroundColor, 0,1)} = [0.94 0.94 0.94]; % The background color of percentage bar
        PForegroundColor (1,3) double {mustBeInRange(PForegroundColor, 0,1)} = [0.30,0.75,0.93]; % The foreground color of percentage bar
        Storage (1,1) string {mustBeMember(Storage, ["MEM","HDD"])} = "MEM"; % The storage location
        Value (1,1) double {mustBeInRange(Value, 0,1)} = 0.5; % The percentage value, 0 - 1
    end
    
    properties (Access = private, Constant)
        WIDTH_BLANK (1,1) double = 5; % Column with of grid blank
        WIDTH_COMPONENT (1,1) double = 260; % Width of this components
        WIDTH_STORAGE (1,1) double = 65; % The width of storage dropdown component
        WIDTH_TAG (1,1) double = 40; % The width of tag label
    end
    
    methods (Access = private)
        
        function SetPercentageBar(comp, x)
            arguments
                comp 
                x       (1,1)   double  {mustBeInRange(x, 0, 1)} = 0
            end

            w0 = comp.WIDTH_COMPONENT - comp.WIDTH_TAG -comp.WIDTH_STORAGE ...
                - 2*comp.WIDTH_BLANK;

            comp.GridLayout_PercentageBar.ColumnWidth ...
                = {ceil(x*w0), w0 - ceil(x*w0)};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown_StorageLocation
        function DropDown_StorageLocationValueChanged(comp, event)
            comp.Storage = comp.DropDown_StorageLocation.Value;

            notify(comp, 'StorageChanged');

            
        end
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % update node tag
            comp.Label_Tag.Text = comp.NodeTag;

            % update storage state
            comp.DropDown_StorageLocation.Value = comp.Storage;
            comp.DropDown_StorageLocation.Enable = ~comp.IsStorageLocked;

            % update percentage bar
            comp.SetPercentageBar(comp.Value);
            comp.Label_PercentageUsed.BackgroundColor = comp.PForegroundColor;
            comp.Label_PercentageLeft.BackgroundColor = comp.PBackgroundColor;
            comp.GridLayout_PercentageBar.Tooltip ...
                = sprintf("%.1f%%", round(100*comp.Value, 1));
        end

        % 创建底层组件
        function setup(comp)

            comp.Position = [1 1 260 25];
            comp.BackgroundColor = [0.94 0.94 0.94];

            % Create GridLayout_Main
            comp.GridLayout_Main = uigridlayout(comp);
            comp.GridLayout_Main.ColumnWidth = {40, '1x', 65};
            comp.GridLayout_Main.RowHeight = {'1x'};
            comp.GridLayout_Main.ColumnSpacing = 5;
            comp.GridLayout_Main.Padding = [0 0 0 0];
            comp.GridLayout_Main.Scrollable = 'on';
            comp.GridLayout_Main.BackgroundColor = [0.96078431372549 0.96078431372549 0.96078431372549];

            % Create Label_Tag
            comp.Label_Tag = uilabel(comp.GridLayout_Main);
            comp.Label_Tag.HorizontalAlignment = 'center';
            comp.Label_Tag.FontSize = 14;
            comp.Label_Tag.FontWeight = 'bold';
            comp.Label_Tag.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Label_Tag.Layout.Row = 1;
            comp.Label_Tag.Layout.Column = 1;
            comp.Label_Tag.Text = '1';

            % Create DropDown_StorageLocation
            comp.DropDown_StorageLocation = uidropdown(comp.GridLayout_Main);
            comp.DropDown_StorageLocation.Items = {'MEM', 'HDD'};
            comp.DropDown_StorageLocation.ItemsData = {'MEM', 'HDD'};
            comp.DropDown_StorageLocation.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DropDown_StorageLocationValueChanged, true);
            comp.DropDown_StorageLocation.Enable = 'off';
            comp.DropDown_StorageLocation.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.DropDown_StorageLocation.BackgroundColor = [0.96078431372549 0.96078431372549 0.96078431372549];
            comp.DropDown_StorageLocation.Layout.Row = 1;
            comp.DropDown_StorageLocation.Layout.Column = 3;
            comp.DropDown_StorageLocation.Value = 'MEM';

            % Create GridLayout_PercentageBar
            comp.GridLayout_PercentageBar = uigridlayout(comp.GridLayout_Main);
            comp.GridLayout_PercentageBar.ColumnWidth = {72.5, 72.5};
            comp.GridLayout_PercentageBar.RowHeight = {'1x'};
            comp.GridLayout_PercentageBar.ColumnSpacing = 0;
            comp.GridLayout_PercentageBar.Padding = [0 8 0 7];
            comp.GridLayout_PercentageBar.Tooltip = {'50.0%'};
            comp.GridLayout_PercentageBar.Layout.Row = 1;
            comp.GridLayout_PercentageBar.Layout.Column = 2;
            comp.GridLayout_PercentageBar.BackgroundColor = [0.9412 0.9412 0.9412];

            % Create Label_PercentageUsed
            comp.Label_PercentageUsed = uilabel(comp.GridLayout_PercentageBar);
            comp.Label_PercentageUsed.BackgroundColor = [0.302 0.7451 0.9333];
            comp.Label_PercentageUsed.HorizontalAlignment = 'center';
            comp.Label_PercentageUsed.FontSize = 10;
            comp.Label_PercentageUsed.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Label_PercentageUsed.Layout.Row = 1;
            comp.Label_PercentageUsed.Layout.Column = 1;
            comp.Label_PercentageUsed.Text = '';

            % Create Label_PercentageLeft
            comp.Label_PercentageLeft = uilabel(comp.GridLayout_PercentageBar);
            comp.Label_PercentageLeft.BackgroundColor = [0.902 0.902 0.902];
            comp.Label_PercentageLeft.FontColor = [0.129411764705882 0.129411764705882 0.129411764705882];
            comp.Label_PercentageLeft.Layout.Row = 1;
            comp.Label_PercentageLeft.Layout.Column = 2;
            comp.Label_PercentageLeft.Text = '';
        end
    end
end