classdef BCAdjuster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BCAdjusterUIFigure      matlab.ui.Figure
        GridLayout7             matlab.ui.container.GridLayout
        Panel_Control           matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        GridLayout8             matlab.ui.container.GridLayout
        Button_GammaPlus        matlab.ui.control.Button
        Button_GammaMinus       matlab.ui.control.Button
        Slider_Gamma            matlab.ui.control.Slider
        Slider_4Label_2         matlab.ui.control.Label
        GridLayout6             matlab.ui.container.GridLayout
        Button_ContrastPlus     matlab.ui.control.Button
        Button_ContrastMinus    matlab.ui.control.Button
        Slider_Contrast         matlab.ui.control.Slider
        Slider_4Label_3         matlab.ui.control.Label
        GridLayout5             matlab.ui.container.GridLayout
        Button_BrightnessPlus   matlab.ui.control.Button
        Button_BrightnessMinus  matlab.ui.control.Button
        Slider_Brightness       matlab.ui.control.Slider
        Slider_3Label           matlab.ui.control.Label
        GridLayout3             matlab.ui.container.GridLayout
        GridLayout4             matlab.ui.container.GridLayout
        Label_MaxValue          matlab.ui.control.Label
        Label_MinValue          matlab.ui.control.Label
        Slider_IntensityRange   matlab.ui.control.RangeSlider
        Panel_View              matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        UIAxes_Hist             matlab.ui.control.UIAxes
    end

    properties (Constant)
        VER = "1.1.0"
    end

    properties(Constant, Hidden)
        GAMMA_SCALE = 100
    end

    properties (Access = private)
        caller          % the parent app

        hdle_hist       % the histogram handle
        hdle_line       % the line handles, cell with {left bound, tuning curve, right bound}

        % image intensity model: y = c*x^ga+b
        img_params      % struct, field {r, c, b, ga}, r for intensity range, 
                        % c for contrast coefficient, b for brightness coefficient,
                        % ga for gamma value.

        intensity       % the image intensity
        iminmax         % the intensity min and max
        c_angle         % the contrast transform angle
        channel_cur     % the current channel, could be "r","g"

        langmap         % mapping for language
    end

    methods(Access=?Register)
        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr >= 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.Panel_View.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_Control.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout7.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout8.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.UIAxes_Hist.Color = constdef.GRAYSCALE_AXES_LIGHT;
                case "DARK"
                    app.Panel_View.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_Control.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout7.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout8.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.UIAxes_Hist.Color = constdef.GRAYSCALE_AXES_DARK;
                otherwise
            end
        end

        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            app.Panel_View.Title = app.langmap.PIXEL_HISTOGRAM;
            app.Panel_Control.Title = app.langmap.CONTROL_PANEL;
            app.Label_MinValue.Text = app.langmap.MIN;
            app.Label_MaxValue.Text = app.langmap.MAX;
            app.Slider_3Label.Text = app.langmap.LIGHT;
            app.Slider_4Label_3.Text = app.langmap.CONTRAST;
            app.Slider_4Label_2.Text = app.langmap.GAMMA;
        end

        function Refresh(app)
            InitData(app);

            InitViewerPanel(app);

            InitControlPanel(app);
        end

        function RefreshTooltips(app)

        end

        function Close(app)
            delete(app);
        end
    end
    
    methods (Access = private)

        function results = InitViewerPanel(app)
            app.UIAxes_Hist.Toolbar = [];
            % disable UIAxes interaction
            disableDefaultInteractivity(app.UIAxes_Hist);

            UpdateUIAxes_Hist(app);

            set(app.UIAxes_Hist,"FontSize",16);

            results = 0;
        end

        function InitControlPanel(app)
            %
            UpdateDisplayRange(app);

            %
            app.Slider_Brightness.Limits = [-app.iminmax(2), 65535-app.iminmax(2)];
            app.Slider_Brightness.Value = app.img_params.b;
            %
            app.Slider_Contrast.Limits = [0, 90];
            app.c_angle = atand(app.img_params.c);
            app.Slider_Contrast.Value = app.c_angle;    % contrast slider use angle, from 0 to 90
            
            %
            app.Slider_Gamma.Limits = [0,2];
            app.Slider_Gamma.Value = app.img_params.ga;
        end

        function UpdateUIAxes_Hist(app)
            hold(app.UIAxes_Hist,"on");

            % histogram
            if ~isempty(app.hdle_hist) && isvalid(app.hdle_hist)
                % set data
                app.hdle_hist.Data = app.intensity;
                app.hdle_hist.FaceColor = app.channel_cur;
            else
                % new handle
                app.hdle_hist = histogram(app.UIAxes_Hist,app.intensity,...
                    "Normalization","probability",...
                    "FaceColor",app.channel_cur,"EdgeColor","none");
            end

            % plot intensity bound
            if ~isempty(app.hdle_line{1}) && isvalid(app.hdle_line{1})
                app.hdle_line{1}.XData = app.img_params.r(1)*[1,1];
                app.hdle_line{1}.YData = [0, 1];
            else
                app.hdle_line{1} = plot(app.UIAxes_Hist, app.img_params.r(1)*[1,1], ...
                    [0, 1],"LineWidth",2,"Color",[0.7,0.7,0.7],"LineStyle",":");
            end
            if ~isempty(app.hdle_line{3}) && isvalid(app.hdle_line{3})
                app.hdle_line{3}.XData = app.img_params.r(2)*[1,1];
                app.hdle_line{3}.YData = [0, 1];
            else
                app.hdle_line{3} = plot(app.UIAxes_Hist, app.img_params.r(2)*[1,1], ...
                    [0, 1],"LineWidth",2,"Color",[0.7,0.7,0.7],"LineStyle",":");
            end
            
            % generate the curve
            x = app.img_params.r(1):app.img_params.r(2);
            xx = rescale(x, 0, intmax("uint16"));
            y = app.img_params.c * xx.^app.img_params.ga + app.img_params.b;

            % rescale y value for displaying at same screen
            y = y/65536*1.05*max(app.hdle_hist.Values);

            if ~isempty(app.hdle_line{2}) && isvalid(app.hdle_line{2})
                app.hdle_line{2}.XData = x;
                app.hdle_line{2}.YData = y;
            else
                app.hdle_line{2} = plot(app.UIAxes_Hist,x,y,"LineWidth",2, ...
                    "LineStyle","-","Color","k");
            end
            
            hold(app.UIAxes_Hist,"off");

            if app.img_params.r(1)>0.1*diff(app.iminmax)+app.iminmax(1) ...
                    && app.img_params.r(2)<0.9*diff(app.iminmax)+app.iminmax(1)
                xticks(app.UIAxes_Hist, [app.iminmax(1), app.img_params.r, app.iminmax(2)]);
            else
                if app.img_params.r(1)<=0.1*diff(app.iminmax)+app.iminmax(1) ...
                        && app.img_params.r(2)<=0.9*diff(app.iminmax)+app.iminmax(1)
                    xticks(app.UIAxes_Hist, [app.img_params.r, app.iminmax(2)]);
                elseif app.img_params.r(1)>0.1*diff(app.iminmax)+app.iminmax(1) ...
                        && app.img_params.r(2)>=0.9*diff(app.iminmax)+app.iminmax(1)
                    xticks(app.UIAxes_Hist, [app.iminmax(1), app.img_params.r]);
                else
                    xticks(app.UIAxes_Hist, app.img_params.r);
                end
            end
            xlim(app.UIAxes_Hist, app.iminmax);
            ylim(app.UIAxes_Hist, [0 1.05*max(app.hdle_hist.Values)]);

            drawnow
        end

        function UpdateDisplayRange(app)
            app.Slider_IntensityRange.Limits = app.iminmax;

            if ~isMATLABReleaseOlderThan("R2024a")
                app.Slider_IntensityRange.Step = 1;
            end

            app.Slider_IntensityRange.Value = ...
                [max(app.img_params.r(1),app.iminmax(1)), ...
                 min(app.img_params.r(2),app.iminmax(2))];
        end
        
        function InitData(app)
            app.img_params = app.caller.GetBCG();

            app.hdle_hist = [];
            app.hdle_line = cell(1,3);

            UpdateData(app);
        end

        function UpdateData(app)
            image_cur = app.caller.GetCurrentImg();
            app.intensity = reshape(image_cur,[],1);
            app.iminmax = double([min(app.intensity,[],"all"), ...
                                  max(app.intensity,[],"all")]);

            app.img_params.r(1) = max(app.img_params.r(1), app.iminmax(1));
            app.img_params.r(2) = min(app.img_params.r(2), app.iminmax(2));
            
            app.channel_cur = app.caller.GetCurrentChannel();
        end

        function UpdateSetting(app)
            % update the BC_GUI Setting
            % note: SetBCG function also called caller refresh the viewer
            % immediately
            app.caller.SetBCG(app.img_params);
            UpdateData(app);
            UpdateUIAxes_Hist(app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.caller = mainapp;
            
            InitData(app);

            InitViewerPanel(app);

            InitControlPanel(app);

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);

            RefreshTooltips(app);
        end

        % Value changing function: Slider_Brightness
        function Slider_BrightnessValueChanging(app, event)
            try
                changingValue = event.Value;
                app.img_params.b = changingValue;

                UpdateSetting(app);
            catch ME
                app.caller.PushInfo("BCAdjuster", "Slider_BrightnessValueChanging", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Button down function: Panel_View
        function Panel_ViewButtonDown(app, event)
            % if button down, we need to get the current image setting 
            % info from the caller
            UpdateData(app);
            UpdateUIAxes_Hist(app);
            UpdateDisplayRange(app);
        end

        % Button pushed function: Button_BrightnessMinus
        function Button_BrightnessMinusPushed(app, event)
            try
                db = double(intmax("uint16"))/100;
                if app.img_params.b - db >= app.Slider_Brightness.Limits(1)
                    app.img_params.b = app.img_params.b - db;
                    app.Slider_Brightness.Value = app.img_params.b;
                end

                UpdateSetting(app);
            catch ME
                app.caller.PushInfo("BCAdjuster", "Button_BrightnessMinusPushed", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Button pushed function: Button_BrightnessPlus
        function Button_BrightnessPlusPushed(app, event)
            try
                db = double(intmax("uint16"))/100;
                if app.img_params.b + db <= app.Slider_Brightness.Limits(end)
                    app.img_params.b = app.img_params.b + db;
                    app.Slider_Brightness.Value = app.img_params.b;
                end

                UpdateSetting(app);
            catch ME
                app.caller.PushInfo("BCAdjuster", "Button_BrightnessPlusPushed", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Button pushed function: Button_ContrastMinus
        function Button_ContrastMinusPushed(app, event)
            try
                if app.c_angle - 1 >= app.Slider_Contrast.Limits(1)
                    app.c_angle = app.c_angle - 1;
                    app.Slider_Contrast.Value = app.c_angle;
                    app.img_params.c = tand(app.c_angle);
                end

                UpdateSetting(app);
            catch ME
                app.caller.PushInfo("BCAdjuster", "Button_ContrastMinusPushed", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Button pushed function: Button_ContrastPlus
        function Button_ContrastPlusPushed(app, event)
            try
                if app.c_angle + 1 <= app.Slider_Contrast.Limits(end)
                    app.c_angle = app.c_angle + 1;
                    app.Slider_Contrast.Value = app.c_angle;
                    app.img_params.c = tand(app.c_angle);
                end

                UpdateSetting(app);
            catch ME
                app.caller.PushInfo("BCAdjuster", "Button_ContrastPlusPushed", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Value changing function: Slider_Contrast
        function Slider_ContrastValueChanging(app, event)
            try
                changingValue = event.Value;
                app.c_angle = changingValue;
                app.img_params.c = tand(app.c_angle);

                UpdateSetting(app);
            catch ME
                app.caller.PushInfo("BCAdjuster", "Slider_ContrastValueChanging", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Button pushed function: Button_GammaMinus
        function Button_GammaMinusPushed(app, event)
            try
                dga = 1/app.GAMMA_SCALE*diff(app.Slider_Gamma.Limits);

                if app.img_params.ga - dga >= app.Slider_Gamma.Limits(1)
                    app.img_params.ga = app.img_params.ga - ...
                        1/app.GAMMA_SCALE*diff(app.Slider_Gamma.Limits);
                    app.Slider_Gamma.Value = app.img_params.ga;
                end

                UpdateSetting(app);
            catch ME
                app.caller.PushInfo("BCAdjuster", "Button_GammaMinusPushed", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Button pushed function: Button_GammaPlus
        function Button_GammaPlusPushed(app, event)
            try
                dga = 1/app.GAMMA_SCALE*diff(app.Slider_Gamma.Limits);

                if app.img_params.ga +dga <= app.Slider_Gamma.Limits(end)
                    app.img_params.ga = app.img_params.ga + ...
                        1/app.GAMMA_SCALE*diff(app.Slider_Gamma.Limits);
                    app.Slider_Gamma.Value = app.img_params.ga;
                end

                UpdateSetting(app);
            catch ME
                app.caller.PushInfo("BCAdjuster", "Button_GammaPlusPushed", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Value changing function: Slider_Gamma
        function Slider_GammaValueChanging(app, event)
            try
                changingValue = event.Value;
                app.img_params.ga = changingValue;

                UpdateSetting(app);
            catch ME
                app.caller.PushInfo("BCAdjuster", "Slider_GammaValueChanging", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Button down function: Panel_Control
        function Panel_ControlButtonDown(app, event)
            % if button down, we need to get the current image setting
            % info from the caller
            UpdateData(app);
            UpdateUIAxes_Hist(app);
            UpdateDisplayRange(app);
        end

        % Close request function: BCAdjusterUIFigure
        function BCAdjusterUIFigureCloseRequest(app, event)
            Close(app);
            
        end

        % Value changing function: Slider_IntensityRange
        function Slider_IntensityRangeValueChanging(app, event)
            try
                changingValue = event.Value;
                changingValue = [max(app.Slider_IntensityRange.Limits(1), floor(changingValue(1))-1), ...
                    min(app.Slider_IntensityRange.Limits(2),  ceil(changingValue(2))+1)];

                app.img_params.r = changingValue;
                app.Slider_IntensityRange.Value = changingValue;

                UpdateSetting(app);
            catch ME
               app.caller.PushInfo("BCAdjuster", "Slider_IntensityRangeValueChanging", ...
                   ME.identifier);

               rethrow(ME);
            end
        end

        % Button down function: BCAdjusterUIFigure
        function BCAdjusterUIFigureButtonDown(app, event)
            % if button down, we need to get the current image setting 
            % info from the caller
            UpdateData(app);
            UpdateUIAxes_Hist(app);
            UpdateDisplayRange(app);
        end

        % Button down function: UIAxes_Hist
        function UIAxes_HistButtonDown(app, event)
            UpdateData(app);
            UpdateUIAxes_Hist(app);
            UpdateDisplayRange(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BCAdjusterUIFigure = uifigure('Visible', 'off');
            app.BCAdjusterUIFigure.Name = 'BC Adjuster';
            app.BCAdjusterUIFigure.Icon = 'image_gui.png';
            app.BCAdjusterUIFigure.CloseRequestFcn = createCallbackFcn(app, @BCAdjusterUIFigureCloseRequest, true);
            app.BCAdjusterUIFigure.ButtonDownFcn = createCallbackFcn(app, @BCAdjusterUIFigureButtonDown, true);
            app.GridLayout7 = uigridlayout(app.BCAdjusterUIFigure);
            app.Panel_View = uipanel(app.GridLayout7);
            app.Panel_View.Title = '像素直方图';
            app.Panel_View.ButtonDownFcn = createCallbackFcn(app, @Panel_ViewButtonDown, true);
            app.GridLayout2 = uigridlayout(app.Panel_View);
            app.UIAxes_Hist = uiaxes(app.GridLayout2);
            app.UIAxes_Hist.XTick = [];
            app.UIAxes_Hist.YTick = [];
            app.UIAxes_Hist.ButtonDownFcn = createCallbackFcn(app, @UIAxes_HistButtonDown, true);
            app.Panel_Control = uipanel(app.GridLayout7);
            app.Panel_Control.Title = '控制面板';
            app.Panel_Control.ButtonDownFcn = createCallbackFcn(app, @Panel_ControlButtonDown, true);
            app.GridLayout = uigridlayout(app.Panel_Control);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Slider_IntensityRange = uislider(app.GridLayout3, 'range');
            app.Slider_IntensityRange.ValueChangingFcn = createCallbackFcn(app, @Slider_IntensityRangeValueChanging, true);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Label_MinValue = uilabel(app.GridLayout4);
            app.Label_MinValue.Text = '最小值';
            app.Label_MaxValue = uilabel(app.GridLayout4);
            app.Label_MaxValue.Text = '最大值';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Slider_3Label = uilabel(app.GridLayout5);
            app.Slider_3Label.Text = '亮度';
            app.Slider_Brightness = uislider(app.GridLayout5);
            app.Slider_Brightness.Limits = [-1 1];
            app.Slider_Brightness.MajorTicks = [];
            app.Slider_Brightness.ValueChangingFcn = createCallbackFcn(app, @Slider_BrightnessValueChanging, true);
            app.Slider_Brightness.MinorTicks = [];
            app.Button_BrightnessMinus = uibutton(app.GridLayout5, 'push');
            app.Button_BrightnessMinus.ButtonPushedFcn = createCallbackFcn(app, @Button_BrightnessMinusPushed, true);
            app.Button_BrightnessMinus.Text = '◀';
            app.Button_BrightnessPlus = uibutton(app.GridLayout5, 'push');
            app.Button_BrightnessPlus.ButtonPushedFcn = createCallbackFcn(app, @Button_BrightnessPlusPushed, true);
            app.Button_BrightnessPlus.Text = '▶';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.Slider_4Label_3 = uilabel(app.GridLayout6);
            app.Slider_4Label_3.Text = '对比度';
            app.Slider_Contrast = uislider(app.GridLayout6);
            app.Slider_Contrast.MajorTicks = [];
            app.Slider_Contrast.ValueChangingFcn = createCallbackFcn(app, @Slider_ContrastValueChanging, true);
            app.Slider_Contrast.MinorTicks = [];
            app.Slider_Contrast.Value = 50;
            app.Button_ContrastMinus = uibutton(app.GridLayout6, 'push');
            app.Button_ContrastMinus.ButtonPushedFcn = createCallbackFcn(app, @Button_ContrastMinusPushed, true);
            app.Button_ContrastMinus.Text = '◀';
            app.Button_ContrastPlus = uibutton(app.GridLayout6, 'push');
            app.Button_ContrastPlus.ButtonPushedFcn = createCallbackFcn(app, @Button_ContrastPlusPushed, true);
            app.Button_ContrastPlus.Text = '▶';
            app.GridLayout8 = uigridlayout(app.GridLayout);
            app.Slider_4Label_2 = uilabel(app.GridLayout8);
            app.Slider_4Label_2.Text = '伽马';
            app.Slider_Gamma = uislider(app.GridLayout8);
            app.Slider_Gamma.MajorTicks = [];
            app.Slider_Gamma.ValueChangingFcn = createCallbackFcn(app, @Slider_GammaValueChanging, true);
            app.Slider_Gamma.MinorTicks = [];
            app.Slider_Gamma.Value = 50;
            app.Button_GammaMinus = uibutton(app.GridLayout8, 'push');
            app.Button_GammaMinus.ButtonPushedFcn = createCallbackFcn(app, @Button_GammaMinusPushed, true);
            app.Button_GammaMinus.Text = '◀';
            app.Button_GammaPlus = uibutton(app.GridLayout8, 'push');
            app.Button_GammaPlus.ButtonPushedFcn = createCallbackFcn(app, @Button_GammaPlusPushed, true);
            app.Button_GammaPlus.Text = '▶';
            % Show the figure after all components are created
            app.BCAdjusterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BCAdjuster(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BCAdjusterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BCAdjusterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BCAdjusterUIFigure)
        end
    end
end