classdef BugReporter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BugReporterUIFigure          matlab.ui.Figure
        Menu_Help                    matlab.ui.container.Menu
        Menu_CommonProblem           matlab.ui.container.Menu
        Menu_About                   matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        Panel_ProblemComponents      matlab.ui.container.Panel
        GridLayout_PC                matlab.ui.container.GridLayout
        Tree_ProblemComponents       matlab.ui.container.CheckBoxTree
        GridLayout4                  matlab.ui.container.GridLayout
        GridLayout9                  matlab.ui.container.GridLayout
        Button_SelectFolder          matlab.ui.control.Button
        EditField_Folder             matlab.ui.control.EditField
        Label_4                      matlab.ui.control.Label
        Panel_ProblemDescription     matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        TextArea_ProblemDescription  matlab.ui.control.TextArea
        GridLayout7                  matlab.ui.container.GridLayout
        ButtonGroup_ProblemType      matlab.ui.container.ButtonGroup
        Button_Suggested             matlab.ui.control.RadioButton
        Button_Ordinary              matlab.ui.control.RadioButton
        Button_Urgent                matlab.ui.control.RadioButton
        GridLayout8                  matlab.ui.container.GridLayout
        Button_Submit                matlab.ui.control.Button
        GridLayout2                  matlab.ui.container.GridLayout
        Hyperlink_Privacy            matlab.ui.control.Hyperlink
        Label_3                      matlab.ui.control.Label
        Label_RemoteHostStatus       matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
    end

    properties (Constant, Hidden)
        VER = "1.1.0"
        SKIP_KEYWORD = ["CHINESE","ENGLISH","1X","5X","25X"]
    end

    properties (Access = private)
        caller          % the parent app
        lan_status      % the Local Area Network, LAN connection status
        root_bug        % record the problems components, uitreenode struct
        node_bug_slt    % the nodes selected
        sftp_handle     % handle of sftp object
        langmap         % mapping for language
    end

    methods (Access=?Register)
        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();

            app.Menu_Help.Text = app.langmap.HELP;
            app.Menu_CommonProblem.Text = app.langmap.COMMON_PROBLEM;
            app.Menu_About.Text = app.langmap.ABOUT;

            app.Panel_ProblemComponents.Title = app.langmap.COMPONENT_PROBLEM;
            app.Tree_ProblemComponents.Tooltip = app.langmap.CHECK_COMPONENT_BUG;
            app.Panel_ProblemDescription.Title = app.langmap.COMPONENT_DESCRIPTION;
            app.Label_4.Text = app.langmap.ATTACH_FOLDER;
            app.EditField_Folder.Placeholder = app.langmap.ATTACH_FILE_HERE;
            app.ButtonGroup_ProblemType.Title = app.langmap.BUG_TYPE;
            app.Button_Submit.Text = app.langmap.SUBMIT;
            app.Button_Urgent.Text = app.langmap.URGENT;
            app.Button_Urgent.Tooltip = app.langmap.TIP_BTN_URGENT;
            app.Button_Ordinary.Text = app.langmap.ORDINARY;
            app.Button_Ordinary.Tooltip = app.langmap.TIP_BTN_ORDINARY;
            app.Button_Suggested.Text = app.langmap.SUGGESTION;
            app.Button_Suggested.Tooltip = app.langmap.TIP_BTN_SUGGESTION;

            app.Label_2.Text = app.langmap.PRP;
            app.Label_3.Text = app.langmap.PRP_PRIVACY_FRIENDLY;
            app.Label_RemoteHostStatus.Text = app.langmap.OFFLINE;
            app.Hyperlink_Privacy.Text = app.langmap.PRIVACY;

            RefreshTreeLang(app)
        end

        function Close(app)
            BugReporterUIFigureCloseRequest(app, [])
        end

        function Refresh(app)
            
        end
    end
    
    methods (Access = private)

        function InitPanel(app)
            % use uitree to select the components that relates to bugs
            if app.lan_status == 1
                app.Label_RemoteHostStatus.Text ...
                    = app.langmap.ONLINE;
                app.Label_RemoteHostStatus.FontColor = [0, 1, 0];
                app.Button_Submit.Enable = "on";
            elseif app.lan_status == 0
                app.Label_RemoteHostStatus.Text ...
                    = app.langmap.OFFLINE;
                app.Label_RemoteHostStatus.FontColor = [1, 0, 0];
                app.Button_Submit.Enable = "off";
            end

            app.Button_Ordinary.UserData = "Ordinary";
            app.Button_Suggested.UserData = "Suggested";
            app.Button_Urgent.UserData = "Urgent";
            app.Button_Ordinary.Value = true;

            InitComponentsTree(app);
        end

        function InitComponentsTree(app)
            delete(app.Tree_ProblemComponents.Children);

            % generate the problem components tree
            % - Root: MainApp
            Node_MainApp = uitreenode(app.Tree_ProblemComponents);
            Node_MainApp.Text = app.langmap.MAIN_APP;
            Node_MainApp.Icon = 'icon.png';
            Node_MainApp.NodeData = "0:MAIN_APP";
            app.root_bug.MA = Node_MainApp;
            %   - Sub Nodes
            AddCompNode_FM(app);
            AddCompNode_IM(app);
            AddCompNode_OM(app);
            AddCompNode_AM(app);
            AddCompNode_HM(app);
            AddCompNode_CC(app);

            % - Root: SubApp
            Node_SubApp = uitreenode(app.Tree_ProblemComponents);
            Node_SubApp.Text = app.langmap.SUB_APP;
            Node_SubApp.Icon = 'subapp.png';
            Node_SubApp.NodeData = "0:SUB_APP";
            app.root_bug.SA = Node_SubApp;
            %   - Sub Nodes
            AddCompNode_BC(app);
            AddCompNode_BR(app);
            AddCompNode_CA(app);
            AddCompNode_EM(app);
            AddCompNode_EV(app);
            AddCompNode_FS(app);
            AddCompNode_LNL(app);
            AddCompNode_MG(app);
            AddCompNode_OCRS(app);
            AddCompNode_TCRS(app);
            AddCompNode_SB(app);
            AddCompNode_SE(app);
            AddCompNode_TV(app);
        end

        % use breadth-first traversal to modify the languages of uitree
        function RefreshTreeLang(app)
            root = fieldnames(app.root_bug);
            if ~isempty(root)
                for k = 1:numel(root)
                    queue = app.root_bug.(root{k});
                    while ~isempty(queue)
                        children = queue(1).Children;
                        IDs = strfind(queue(1).NodeData,":");
                        if ~isempty(IDs)
                            IDs = queue(1).NodeData.extractAfter(IDs(end));
                            if ~ismember(IDs, BugReporter.SKIP_KEYWORD)
                                queue(1).Text = app.langmap.(IDs);
                            end
                        end
                        queue = [queue; children]; %#ok<AGROW>
                        queue(1) = [];
                    end
                end
            end
        end

        % Node for main app: File Menu
        function AddCompNode_FM(app)
            % Create Root
            Node_FileMenu = uitreenode(app.root_bug.MA);
            Node_FileMenu.Text = app.langmap.FILE;
            Node_FileMenu.Icon = 'file.png';
            Node_FileMenu.NodeData = "main:FILE";

            % Create Child Node
            % - load
            node_load = uitreenode(Node_FileMenu);
            node_load.Text = app.langmap.LOAD_FILE;
            node_load.NodeData = "main:FILE:LOAD_FILE";

            % - export images stack
            node_exportimg = uitreenode(Node_FileMenu);
            node_exportimg.Text = app.langmap.EXPORT_IMAGES;
            node_exportimg.NodeData = "main:FILE:EXPORT_IMAGES";

            % - export time
            node_exporttime = uitreenode(Node_FileMenu);
            node_exporttime.Text = app.langmap.EXPORT_TIME;
            node_exporttime.NodeData = "main:FILE:EXPORT_TIME";

            % - export movie
            node_exportmov = uitreenode(Node_FileMenu);
            node_exportmov.Text = app.langmap.EXPORT_MOVIE;
            node_exportmov.NodeData = "main:FILE:EXPORT_MOVIE";
        end

        % Node for main app: Image Menu
        function AddCompNode_IM(app)
            % Create Root
            Node_ImageMenu = uitreenode(app.root_bug.MA);
            Node_ImageMenu.Text = app.langmap.IMAGE;
            Node_ImageMenu.Icon = 'image_process.png';
            Node_ImageMenu.NodeData = "main:IMAGE";

            % Create Child Node
            % - bright and contrast adjust
            node_bc = uitreenode(Node_ImageMenu);
            node_bc.Text = app.langmap.LIGHT_CONTRAST;
            node_bc.NodeData = "main:IMAGE:LIGHT_CONTRAST";

            % - intensity z projection
            node_zproj = uitreenode(Node_ImageMenu);
            node_zproj.Text = app.langmap.Z_PROJECTION;
            node_zproj.NodeData = "main:IMAGE:Z_PROJECTION";
            %   - maximum intensity z projection
            node_zproj_max = uitreenode(node_zproj);
            node_zproj_max.Text = app.langmap.MAX_Z_PROJECTION;
            node_zproj_max.NodeData = "main:IMAGE:Z_PROJECTION:MAX_Z_PROJECTION";
            %   - minimum intensity z projection
            node_zproj_min = uitreenode(node_zproj);
            node_zproj_min.Text = app.langmap.MIN_Z_PROJECTION;
            node_zproj_min.NodeData = "main:IMAGE:Z_PROJECTION:MIN_Z_PROJECTION";
            %   - median intensity z projection
            node_zproj_median = uitreenode(node_zproj);
            node_zproj_median.Text = app.langmap.MEDIAN_Z_PROJECTION;
            node_zproj_median.NodeData = "main:IMAGE:Z_PROJECTION:MEDIAN_Z_PROJECTION";
            %   - mean intensity z projection
            node_zproj_mean = uitreenode(node_zproj);
            node_zproj_mean.Text = app.langmap.MEAN_Z_PROJECTION;
            node_zproj_mean.NodeData = "main:IMAGE:Z_PROJECTION:MEAN_Z_PROJECTION";

            % - channel alignment
            node_calign = uitreenode(Node_ImageMenu);
            node_calign.Text = app.langmap.CHANNEL_ALIGNMENT;
            node_calign.NodeData = "main:IMAGE:CHANNEL_ALIGNMENT";

            % - remove background
            node_rmbkg = uitreenode(Node_ImageMenu);
            node_rmbkg.Text = app.langmap.REMOVE_BACKGROUND;
            node_rmbkg.NodeData = "main:IMAGE:REMOVE_BACKGROUND";
            %   - auto remove background
            node_rmbkg_auto = uitreenode(node_rmbkg);
            node_rmbkg_auto.Text = app.langmap.AUTO;
            node_rmbkg_auto.NodeData = "main:IMAGE:REMOVE_BACKGROUND:AUTO";
            %   - manual remove background
            node_rmbkg_fixed = uitreenode(node_rmbkg);
            node_rmbkg_fixed.Text = app.langmap.FIXED;
            node_rmbkg_fixed.NodeData = "main:IMAGE:REMOVE_BACKGROUND:FIXED";

            % - simply connected domain mask
            node_mask = uitreenode(Node_ImageMenu);
            node_mask.Text = app.langmap.MASK_SIMPLY;
            node_mask.NodeData = "main:IMAGE:MASK_SIMPLY";
            %   - generate mask
            node_mask_gen = uitreenode(node_mask);
            node_mask_gen.Text = app.langmap.GENERATE_MASK;
            node_mask_gen.NodeData = "main:IMAGE:MASK:GENERATE_MASK";
            %   - import mask
            node_mask_import = uitreenode(node_mask);
            node_mask_import.Text = app.langmap.IMPORT_MASK;
            node_mask_import.NodeData = "main:IMAGE:MASK:IMPORT_MASK";

            % - append comments
            node_comments = uitreenode(Node_ImageMenu);
            node_comments.Text = app.langmap.APPEND_COMMENTS;
            node_comments.NodeData = "main:IMAGE:APPEND_COMMENTS";
            %   - time stamp
            node_comments_timestamp = uitreenode(node_comments);
            node_comments_timestamp.Text = app.langmap.TIME_STAMP;
            node_comments_timestamp.NodeData = "main:IMAGE:APPEND_COMMENTS:TIME_STAMP";
            %   - scale bar
            node_comments_scalebar = uitreenode(node_comments);
            node_comments_scalebar.Text = app.langmap.SCALE_RATIO;
            node_comments_scalebar.NodeData = "main:IMAGE:APPEND_COMMENTS:SCALE_RATIO";
            %   - metadata
            node_comments_metadata = uitreenode(node_comments);
            node_comments_metadata.Text = app.langmap.METADATA;
            node_comments_metadata.NodeData = "main:IMAGE:APPEND_COMMENTS:METADATA";

            % - volume crop/deplicate
            node_crop3d = uitreenode(Node_ImageMenu);
            node_crop3d.Text = app.langmap.CROP_VOLUME;
            node_crop3d.NodeData = "main:IMAGE:CROP_VOLUME";
            %   - xy crop
            node_crop3d_xy = uitreenode(node_crop3d);
            node_crop3d_xy.Text = app.langmap.CROP_XY;
            node_crop3d_xy.NodeData = "main:IMAGE:CROP_VOLUME:CROP_XY";
            %   - z crop
            node_crop3d_z = uitreenode(node_crop3d);
            node_crop3d_z.Text = app.langmap.CROP_Z;
            node_crop3d_z.NodeData = "main:IMAGE:CROP_VOLUME:CROP_Z";

            % - frames cut
            node_crop_t = uitreenode(Node_ImageMenu);
            node_crop_t.Text = app.langmap.CROP_FRAME;
            node_crop_t.NodeData = "main:IMAGE:CROP_FRAME";

            % - flip
            node_flip = uitreenode(Node_ImageMenu);
            node_flip.Text = app.langmap.FLIP;
            node_flip.NodeData = "main:IMAGE:FLIP";
            %   - flip horizontal
            node_fliplr = uitreenode(node_flip);
            node_fliplr.Text = app.langmap.HORIZONTAL;
            node_fliplr.NodeData = "main:IMAGE:FLIP:HORIZONTAL";
            %   - flip vertical
            node_flipud = uitreenode(node_flip);
            node_flipud.Text = app.langmap.VERTICAL;
            node_flipud.NodeData = "main:IMAGE:FLIP:VERTICAL";

            % - rotate
            node_rotate = uitreenode(Node_ImageMenu);
            node_rotate.Text = app.langmap.ROTATE;
            node_rotate.NodeData = "main:IMAGE:ROTATE";
            %   - rotate clockwise 90 degree
            node_rotate90 = uitreenode(node_rotate);
            node_rotate90.Text = app.langmap.CLOCKWISE_90;
            node_rotate90.NodeData = "main:IMAGE:ROTATE_CLOCKWISE_90";
            %   - flip counter clockwise 90 degree
            node_rotate270 = uitreenode(node_rotate);
            node_rotate270.Text = app.langmap.COUNTERCLOCKWISE_90;
            node_rotate270.NodeData = "main:IMAGE:ROTATE:COUNTERCLOCKWISE_90";
        end

        % Node for main app: Options Menu
        function AddCompNode_OM(app)
            % Create Root
            Node_OptionMenu = uitreenode(app.root_bug.MA);
            Node_OptionMenu.Text = app.langmap.OPTION;
            Node_OptionMenu.Icon = 'setting.png';
            Node_OptionMenu.NodeData = "main:OPTION";

            % Create Child Node
            % - export configuration
            node_exportconf = uitreenode(Node_OptionMenu);
            node_exportconf.Text = app.langmap.EXPORT_CONFIGURATION;
            node_exportconf.NodeData = "main:OPTION:EXPORT_CONFIGURATION";

            % - load configuration
            node_loadconf = uitreenode(Node_OptionMenu);
            node_loadconf.Text = app.langmap.LOAD_CONFIGURATION;
            node_loadconf.NodeData = "main:OPTION:LOAD_CONFIGURATION";

            % - animation(play)
            node_animation = uitreenode(Node_OptionMenu);
            node_animation.Text = app.langmap.ANIMATION;
            node_animation.NodeData = "main:OPTION:ANIMATION";
            %   - 1X play speed
            node_animation_1X = uitreenode(node_animation);
            node_animation_1X.Text = "1X";
            node_animation_1X.NodeData = "main:OPTION:ANIMATION:1X";
            %   - 5X play speed
            node_animation_5X = uitreenode(node_animation);
            node_animation_5X.Text = "5X";
            node_animation_5X.NodeData = "main:OPTION:ANIMATION:5X";
            %   - 25X play speed
            node_animation_25X = uitreenode(node_animation);
            node_animation_25X.Text = "25X";
            node_animation_25X.NodeData = "main:OPTION:ANIMATION:25X";

            % - play mode
            node_playmode = uitreenode(Node_OptionMenu);
            node_playmode.Text = app.langmap.PLAY_MODE;
            node_playmode.NodeData = "main:OPTION:PLAY_MODE";
            %   - direct
            node_playmode_direct = uitreenode(node_playmode);
            node_playmode_direct.Text = app.langmap.DIRECT;
            node_playmode_direct.NodeData = "main:OPTION:PLAY_MODE:DIRECT";
            %   - recycle
            node_playmode_recycle = uitreenode(node_playmode);
            node_playmode_recycle.Text = app.langmap.RECYCLE;
            node_playmode_recycle.NodeData = "main:OPTION:PLAY_MODE:RECYCLE";

            % - registrator
            node_registrator = uitreenode(Node_OptionMenu);
            node_registrator.Text = app.langmap.REGISTRATOR;
            node_registrator.NodeData = "main:OPTION:REGISTRATOR";
            %   - registration setting
            node_registrator_setting = uitreenode(node_registrator);
            node_registrator_setting.Text = app.langmap.REGISTRATION_SETTING;
            node_registrator_setting.NodeData = "main:OPTION:REGISTRATOR:REGISTRATION_SETTING";
            %   - registration range
            node_registrator_range = uitreenode(node_registrator);
            node_registrator_range.Text = app.langmap.REGISTRATION_RANGE;
            node_registrator_range.NodeData = "main:OPTION:REGISTRATOR:REGISTRATION_RANGE";
            %   - registration evaluation
            node_registrator_eva = uitreenode(node_registrator);
            node_registrator_eva.Text = app.langmap.REGISTRATION_EVALUATION;
            node_registrator_eva.NodeData = "main:OPTION:REGISTRATOR:REGISTRATION_EVALUATION";

            % - run modal
            node_runmodal = uitreenode(Node_OptionMenu);
            node_runmodal.Text = app.langmap.RUN_MODAL;
            node_runmodal.NodeData = "main:OPTION:RUN_MODAL";
            %   - distributed modal
            node_distributed = uitreenode(node_runmodal);
            node_distributed.Text = app.langmap.DISTRIBUTED;
            node_distributed.NodeData = "main:OPTION:DISTRIBUTED";
            %   - exclusive modal
            node_exclusive = uitreenode(node_runmodal);
            node_exclusive.Text = app.langmap.EXCLUSIVE;
            node_exclusive.NodeData = "main:OPTION:EXCLUSIVE";

            % - task viewer
            node_taskviewer = uitreenode(Node_OptionMenu);
            node_taskviewer.Text = app.langmap.TASK_VIEWER;
            node_taskviewer.NodeData = "main:OPTION:TASK_VIEWER";
        end

        % Node for main app: Advanced Menu
        function AddCompNode_AM(app)
            % Create Root
            Node_AdvancedMenu = uitreenode(app.root_bug.MA);
            Node_AdvancedMenu.Text = app.langmap.ADVANCED;
            Node_AdvancedMenu.Icon = 'advanced.png';
            Node_AdvancedMenu.NodeData = "main:ADVANCED";

            % Create Child Node
            % - Python interpreter
            node_pyinterpreter = uitreenode(Node_AdvancedMenu);
            node_pyinterpreter.Text = app.langmap.PYTHON_INTERPRETER;
            node_pyinterpreter.NodeData = "main:ADVANCED:PYTHON_INTERPRETER";

            % - Multiply connected domain mask
            node_mask = uitreenode(Node_AdvancedMenu);
            node_mask.Text = app.langmap.MASK_MULTIPLY;
            node_mask.NodeData = "main:ADVANCED:MASK_MULTIPLY";
            %   - generate mask
            node_mask_gen = uitreenode(node_mask);
            node_mask_gen.Text = app.langmap.GENERATE_MASK;
            node_mask_gen.NodeData = "main:ADVANCED:MASK:GENERATE_MASK";
            %   - import mask
            node_mask_import = uitreenode(node_mask);
            node_mask_import.Text = app.langmap.IMPORT_MASK;
            node_mask_import.NodeData = "main:ADVANCED:MASK:IMPORT_MASK";

            % - Signal extracter
            node_sigext = uitreenode(Node_AdvancedMenu);
            node_sigext.Text = app.langmap.SIGNAL_EXTRACTOR;
            node_sigext.NodeData = "main:ADVANCED:SIGNAL_EXTRACTOR";
        end

        % Node for main app: Help Menu
        function AddCompNode_HM(app)
            % Create Root
            Node_HelpMenu = uitreenode(app.root_bug.MA);
            Node_HelpMenu.Text = app.langmap.HELP;
            Node_HelpMenu.Icon = 'help.png';
            Node_HelpMenu.NodeData = "main:HELP";

            % Create Child Node
            % - about
            node_about = uitreenode(Node_HelpMenu);
            node_about.Text = app.langmap.ABOUT;
            node_about.NodeData = "main:HELP:ABOUT";

            % - document
            node_doc = uitreenode(Node_HelpMenu);
            node_doc.Text = app.langmap.DOCUMENT;
            node_doc.NodeData = "main:HELP:DOCUMENT";

            % - language
            node_lang = uitreenode(Node_HelpMenu);
            node_lang.Text = app.langmap.LANGUAGE;
            node_lang.NodeData = "main:HELP:LANGUAGE";
            %   - Chinese
            node_lang_cn = uitreenode(node_lang);
            node_lang_cn.Text = "中文";
            node_lang_cn.NodeData = "main:HELP:LANGUAGE:CHINESE";
            %   - English
            node_lang_us = uitreenode(node_lang);
            node_lang_us.Text = "English";
            node_lang_us.NodeData = "main:HELP:LANGUAGE:ENGLISH";

            % - Submit bugs
            node_submitbugs = uitreenode(Node_HelpMenu);
            node_submitbugs.Text = app.langmap.SUBMIT_BUG;
            node_submitbugs.NodeData = "main:HELP:SUBMIT_BUG";
        end

        % Node for main app: Control Components
        function AddCompNode_CC(app)
            % Create Root
            Node_IfceCtrls = uitreenode(app.root_bug.MA);
            Node_IfceCtrls.Text = app.langmap.INTERFACE_CONTROL;
            Node_IfceCtrls.Icon = 'controls.png';
            Node_IfceCtrls.NodeData = "main:INTERFACE_CONTROL";

            % Create Child Node
            % - Slice Viewer
            node_viewer = uitreenode(Node_IfceCtrls);
            node_viewer.Text = app.langmap.SLICE_VIEWER;
            node_viewer.NodeData = "main:INTERFACE_CONTROL:SLICE_VIEWER";
            %   - display area
            node_viewer_disparea = uitreenode(node_viewer);
            node_viewer_disparea.Text = app.langmap.DISPLAY_AREA;
            node_viewer_disparea.NodeData = "main:INTERFACE_CONTROL:SLICE_VIEWER:DISPLAY_AREA";
            %   - time bar
            node_viewer_timerbar = uitreenode(node_viewer);
            node_viewer_timerbar.Text = app.langmap.TIP_SPN_TIME;
            node_viewer_timerbar.NodeData = "main:INTERFACE_CONTROL:SLICE_VIEWER:TIP_SPN_TIME";
            %   - slice bar
            node_viewer_slicebar = uitreenode(node_viewer);
            node_viewer_slicebar.Text = app.langmap.TIP_SPN_SLICE;
            node_viewer_slicebar.NodeData = "main:INTERFACE_CONTROL:SLICE_VIEWER:TIP_SPN_SLICE";

            % - Control Panel
            node_ctrlpanel = uitreenode(Node_IfceCtrls);
            node_ctrlpanel.Text = app.langmap.CONTROL_PANEL;
            node_ctrlpanel.NodeData = "main:INTERFACE_CONTROL:CONTROL_PANEL";
            %   - log area
            node_ctrlpanel_logarea = uitreenode(node_ctrlpanel);
            node_ctrlpanel_logarea.Text = app.langmap.LOG;
            node_ctrlpanel_logarea.NodeData = "main:INTERFACE_CONTROL:CONTROL_PANEL:LOG";
            %   - source
            node_ctrlpanel_source = uitreenode(node_ctrlpanel);
            node_ctrlpanel_source.Text = app.langmap.SOURCE;
            node_ctrlpanel_source.NodeData = "main:INTERFACE_CONTROL:CONTROL_PANEL:SOURCE";
            %   - run button
            node_ctrlpanel_run = uitreenode(node_ctrlpanel);
            node_ctrlpanel_run.Text = app.langmap.RUN;
            node_ctrlpanel_run.NodeData = "main:INTERFACE_CONTROL:CONTROL_PANEL:RUN";
        end

        % Node for sub app: BC_GUI
        function AddCompNode_BC(app)
            % Create Root
            Node_BC = uitreenode(app.root_bug.SA);
            Node_BC.Text = app.langmap.LIGHT_CONTRAST;
            Node_BC.Icon = 'image_gui.png';
            Node_BC.NodeData = "sub:LIGHT_CONTRAST";

            % Create Child Node
            % - Pixel Histogram
            node_pixelhist = uitreenode(Node_BC);
            node_pixelhist.Text = app.langmap.PIXEL_HISTOGRAM;
            node_pixelhist.NodeData = "sub:IMAGE:PIXEL_HISTOGRAM";
            
            % - Control Panel
            node_ctrlpanel = uitreenode(Node_BC);
            node_ctrlpanel.Text = app.langmap.CONTROL_PANEL;
            node_ctrlpanel.NodeData = "sub:IMAGE:CONTROL_PANEL";
        end

        function AddCompNode_BR(app)
            % Create Root
            Node_BR = uitreenode(app.root_bug.SA);
            Node_BR.Text = app.langmap.SUBMIT_BUG;
            Node_BR.Icon = 'bug.png';
            Node_BR.NodeData = "sub:SUBMIT_BUG";

            % Create Child Node
            % - Components with Problems
            node_cwp = uitreenode(Node_BR);
            node_cwp.Text = app.langmap.COMPONENT_PROBLEM;
            node_cwp.NodeData = "sub:SUBMIT_BUG:COMPONENT_PROBLEM";
            
            % - Problems Description Area
            node_pda = uitreenode(Node_BR);
            node_pda.Text = app.langmap.COMPONENT_DESCRIPTION;
            node_pda.NodeData = "sub:SUBMIT_BUG:COMPONENT_DESCRIPTION";

            % - Other Controls
            node_ocs = uitreenode(Node_BR);
            node_ocs.Text = app.langmap.OTHER;
            node_ocs.NodeData = "sub:SUBMIT_BUG:OTHER";
        end

        function AddCompNode_CA(app)
            % Create Root
            Node_CA = uitreenode(app.root_bug.SA);
            Node_CA.Text = app.langmap.CHANNEL_ALIGNMENT;
            Node_CA.Icon = 'channel_alignment.png';
            Node_CA.NodeData = "sub:CHANNEL_ALIGNMENT";

            % Create Child Node
            % - Translation Controls
            node_tra = uitreenode(Node_CA);
            node_tra.Text = app.langmap.TRANSLATION;
            node_tra.NodeData = "sub:CHANNEL_ALIGNMENT:TRANSLATION";
            
            % - Rotation Controls
            node_rot = uitreenode(Node_CA);
            node_rot.Text = app.langmap.ROTATE;
            node_rot.NodeData = "sub:CHANNEL_ALIGNMENT:ROTATE";

            % - Submit
            node_submit = uitreenode(Node_CA);
            node_submit.Text = app.langmap.SUBMIT;
            node_submit.NodeData = "sub:CHANNEL_ALIGNMENT:SUBMIT";
        end

        function AddCompNode_EM(app)
            % Create Root
            Node_EM = uitreenode(app.root_bug.SA);
            Node_EM.Text = app.langmap.EXPORT_IMAGES;
            Node_EM.Icon = 'exporter.png';
            Node_EM.NodeData = "sub:EXPORT_IMAGES";

            % Create Child Node
            % - Setting Panel
            node_sp = uitreenode(Node_EM);
            node_sp.Text = app.langmap.SETTING_PANEL;
            node_sp.NodeData = "sub:EXPORT_IMAGES:SETTING_PANEL";
        end

        function AddCompNode_EV(app)
            % Create Root
            Node_EV = uitreenode(app.root_bug.SA);
            Node_EV.Text = app.langmap.REGISTRATION_EVALUATION;
            Node_EV.Icon = 'evaluation.png';
            Node_EV.NodeData = "sub:REGISTRATION_EVALUATION";

            % Create Child Node
            % - Display Area
            node_viewer = uitreenode(Node_EV);
            node_viewer.Text = app.langmap.DISPLAY_AREA;
            node_viewer.NodeData = "sub:REGISTRATION_EVALUATION:DISPLAY_AREA";
            
            % - Renderer
            node_render = uitreenode(Node_EV);
            node_render.Text = app.langmap.RENDERER_PARAMETERS;
            node_render.NodeData = "sub:REGISTRATION_EVALUATION:RENDERER_PARAMETERS";

            % - registration goodness
            node_goodness = uitreenode(Node_EV);
            node_goodness.Text = app.langmap.REGISTRATION_GOODNESS;
            node_goodness.NodeData = "sub:REGISTRATION_EVALUATION:REGISTRATION_GOODNESS";
        end

        function AddCompNode_FS(app)
            % Create Root
            Node_FS = uitreenode(app.root_bug.SA);
            Node_FS.Text = app.langmap.CROP_FRAME;
            Node_FS.Icon = 'crop.png';
            Node_FS.NodeData = "sub:CROP_FRAME";

            % Create Child Node
            % - crop frame
            node_crop_t = uitreenode(Node_FS);
            node_crop_t.Text = app.langmap.CROP_FRAME;
            node_crop_t.NodeData = "sub:CROP_FRAME:CROP_FRAME";

            % - Other Controls
            node_ocs = uitreenode(Node_FS);
            node_ocs.Text = app.langmap.OTHER;
            node_ocs.NodeData = "sub:CROP_FRAME:OTHER";
        end

        function AddCompNode_MG(app)
            % Create Root
            Node_GM = uitreenode(app.root_bug.SA);
            Node_GM.Text = app.langmap.GENERATE_MASK;
            Node_GM.Icon = 'mask.png';
            Node_GM.NodeData = "sub:GENERATE_MASK";

            % Create Child Node
            % - region properties(slice)
            node_rps = uitreenode(Node_GM);
            node_rps.Text = app.langmap.REGION_PROPERTY_SLICE;
            node_rps.NodeData = "sub:GENERATE_MASK:REGION_PROPERTY_SLICE";

            % - region properties(volume)
            node_rpv = uitreenode(Node_GM);
            node_rpv.Text = app.langmap.REGION_PROPERTY_VOLUME;
            node_rpv.NodeData = "sub:GENERATE_MASK:REGION_PROPERTY_VOLUME";

            % - Other Controls
            node_ocs = uitreenode(Node_GM);
            node_ocs.Text = app.langmap.OTHER;
            node_ocs.NodeData = "sub:GENERATE_MASK:OTHER";
        end

        % function AddCompNode_HI(app)
        %     % Create Root
        %     Node_GM = uitreenode(app.root_bug.SA);
        %     Node_GM.Text = app.langmap.HISTORY_MANAGER;
        %     Node_GM.Icon = 'mask.png';
        %     Node_GM.NodeData = "sub:HISTORY_MANAGER";
        % end

        function AddCompNode_LNL(app)
            % Create Root
            Node_LNL = uitreenode(app.root_bug.SA);
            Node_LNL.Text = app.langmap.LOGIN_LABNAS;
            Node_LNL.Icon = 'lock.png';
            Node_LNL.NodeData = "sub:LOGIN_LABNAS";
        end

        function AddCompNode_OCRS(app)
            % Create Root
            Node_OCRS = uitreenode(app.root_bug.SA);
            Node_OCRS.Text = app.langmap.SINGLE_CHANNEL;
            Node_OCRS.Icon = 'ocrs.png';
            Node_OCRS.NodeData = "sub:SINGLE_CHANNEL";

            % Create Child Node
            % - global registration (auto)
            node_global = uitreenode(Node_OCRS);
            node_global.Text = app.langmap.GLOBAL;
            node_global.NodeData = "sub:SINGLE_CHANNEL:GLOBAL";

            % - global registration (manual)
            node_manual = uitreenode(Node_OCRS);
            node_manual.Text = app.langmap.MGLOBAL;
            node_manual.NodeData = "sub:SINGLE_CHANNEL:MGLOBAL";
        end

        function AddCompNode_TCRS(app)
            % Create Root
            Node_TCRS = uitreenode(app.root_bug.SA);
            Node_TCRS.Text = app.langmap.DUAL_CHANNEL;
            Node_TCRS.Icon = 'tcrs.png';
            Node_TCRS.NodeData = "sub:DUAL_CHANNEL";

            % Create Child Node
            % - global registration (auto)
            node_global = uitreenode(Node_TCRS);
            node_global.Text = app.langmap.GLOBAL;
            node_global.NodeData = "sub:DUAL_CHANNEL:GLOBAL";

            % - local registration (auto)
            node_local = uitreenode(Node_TCRS);
            node_local.Text = app.langmap.LOCAL;
            node_local.NodeData = "sub:DUAL_CHANNEL:LOCAL";

            % - global registration (manual)
            node_manual = uitreenode(Node_TCRS);
            node_manual.Text = app.langmap.MGLOBAL;
            node_manual.NodeData = "sub:DUAL_CHANNEL:MGLOBAL";

            % - global registration (long-term)
            node_ltglobal = uitreenode(Node_TCRS);
            node_ltglobal.Text = app.langmap.LTGLOBAL;
            node_ltglobal.NodeData = "sub:DUAL_CHANNEL:LTGLOBAL";
        end

        function AddCompNode_SB(app)
            % Create Root
            Node_SB = uitreenode(app.root_bug.SA);
            Node_SB.Text = app.langmap.SCALE_RATIO;
            Node_SB.Icon = 'ruler.png';
            Node_SB.NodeData = "sub:SCALE_RATIO";

            % Create Child Node
            % - ratio panel
            node_ratio = uitreenode(Node_SB);
            node_ratio.Text = app.langmap.RATIO;
            node_ratio.NodeData = "sub:SCALE_RATIO:RATIO";

            % - scale panel
            node_scale = uitreenode(Node_SB);
            node_scale.Text = app.langmap.SCALE;
            node_scale.NodeData = "sub:SCALE_RATIO:SCALE";
        end

        function AddCompNode_SE(app)
            % Create Root
            Node_SE = uitreenode(app.root_bug.SA);
            Node_SE.Text = app.langmap.SIGNAL_EXTRACTOR;
            Node_SE.Icon = 'signal_extractor.png';
            Node_SE.NodeData = "sub:SIGNAL_EXTRACTOR";

            % Create Child Node
            % - mask components
            node_maskcomp = uitreenode(Node_SE);
            node_maskcomp.Text = app.langmap.MASK_COMPONENTS;
            node_maskcomp.NodeData = "sub:SIGNAL_EXTRACTOR:MASK_COMPONENTS";

            % - model parameters
            node_modelargs = uitreenode(Node_SE);
            node_modelargs.Text = app.langmap.MODEL_PARAMETERS;
            node_modelargs.NodeData = "sub:SIGNAL_EXTRACTOR:MODEL_PARAMETERS";

            % - options menu
            node_optmenu = uitreenode(Node_SE);
            node_optmenu.Text = app.langmap.OPTION;
            node_optmenu.NodeData = "sub:SIGNAL_EXTRACTOR:OPTION";
        end

        function AddCompNode_TV(app)
            % Create Root
            Node_TV = uitreenode(app.root_bug.SA);
            Node_TV.Text = app.langmap.TASK_VIEWER;
            Node_TV.Icon = 'task_viewer.png';
            Node_TV.NodeData = "sub:TASK_VIEWER";

            % Create Child Node
            % - real time overview panel
            node_rtov = uitreenode(Node_TV);
            node_rtov.Text = app.langmap.REAL_TIME_OVERVIEW;
            node_rtov.NodeData = "sub:TASK_VIEWER:REAL_TIME_OVERVIEW";

            % - performance panel
            node_perf = uitreenode(Node_TV);
            node_perf.Text = app.langmap.PERFORMANCE;
            node_perf.NodeData = "sub:TASK_VIEWER:PERFORMANCE";

            % - history panel
            node_history = uitreenode(Node_TV);
            node_history.Text = app.langmap.HISTORY_RECORD;
            node_history.NodeData = "sub:TASK_VIEWER:HISTORY_RECORD";

            % - protect cpu cores
            node_npcpu = uitreenode(Node_TV);
            node_npcpu.Text = app.langmap.NPC_CPU;
            node_npcpu.NodeData = "sub:TASK_VIEWER:NPC_CPU";

            % - protect gpu cores
            node_npgpu = uitreenode(Node_TV);
            node_npgpu.Text = app.langmap.NPC_GPU;
            node_npgpu.NodeData = "sub:TASK_VIEWER:NPC_GPU";
        end
    end

    methods (Static)
        function ver = GetVersions()
            % collect all apps version

            if isMATLABReleaseOlderThan("R2023b")
                ver = struct("Reg3D",   Register.VER, ...
                             "BC",      BCAdjuster_old.VER, ...
                             "BR",      BugReporter.VER, ...
                             "CC",      ChannelCorrector.VER, ...
                             "EV",      Evaluator.VER, ...
                             "EM",      MovieExporter.VER, ...
                             "FS",      FrameSelecter.VER, ...
                             "MG",      MaskGenerator.VER, ...
                             "HM",      HistoryManager.VER, ...
                             "LNL",     LabNasLogin.VER, ...
                             "OCRS",    OCRegSetter.VER, ...
                             "TCRS",    TCRegSetter.VER, ...
                             "SB",      ScaleBar.VER, ...
                             "SE",      SignalEstimator.VER, ...
                             "TV",      TaskViewer.VER);
            else    % new support
                ver = struct("Reg3D",   Register.VER, ...
                             "BC",      BCAdjuster.VER, ...
                             "BR",      BugReporter.VER, ...
                             "CC",      ChannelCorrector.VER, ...
                             "EV",      Evaluator.VER, ...
                             "EM",      MovieExporter.VER, ...
                             "FS",      FrameSelecter.VER, ...
                             "MG",      MaskGenerator.VER, ...
                             "HM",      HistoryManager.VER, ...
                             "LNL",     LabNasLogin.VER, ...
                             "OCRS",    OCRegSetter.VER, ...
                             "TCRS",    TCRegSetter.VER, ...
                             "SB",      ScaleBar.VER, ...
                             "SE",      SignalEstimator.VER, ...
                             "TV",      TaskViewer.VER);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, sftp_handle)
            app.caller = mainapp;
            app.sftp_handle = sftp_handle;
            app.lan_status = ~isempty(sftp_handle);
            app.root_bug = struct();

            setSMTP();
            RefreshPanelLanguage(app);
            InitPanel(app);
        end

        % Close request function: BugReporterUIFigure
        function BugReporterUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Menu selected function: Menu_CommonProblem
        function Menu_CommonProblemSelected(app, event)
            
        end

        % Menu selected function: Menu_About
        function Menu_AboutSelected(app, event)
            msg = sprintf(['Register3D Bug Reporter \n' ...
                'Thank you for your help. \n' ...
                'Version: 1.1.0 @si-lab \n' ...
                'Author: Weihan Li \n' ...
                'E-mail: liweihan20@mails.ucas.ac.cn']);
            uialert(app.BugReporterUIFigure, msg, ...
                app.langmap.INFORMATION, "Icon","info");
        end

        % Button pushed function: Button_SelectFolder
        function Button_SelectFolderPushed(app, event)
            folder = uigetdir(userpath, app.langmap.ATTACH_FOLDER);
            app.BugReporterUIFigure.WindowStyle = "alwaysontop";
            app.BugReporterUIFigure.WindowStyle = "normal";

            if folder ~= 0
                app.EditField_Folder.Value = folder;
            end
        end

        % Button pushed function: Button_Submit
        function Button_SubmitPushed(app, event)
            % Extract the bug node data, problem description, and attached
            % folder info
            if isempty(app.node_bug_slt)
                uialert(app.BugReporterUIFigure, app.langmap.CHECK_COMPONENT_BUG, ...
                    app.langmap.INFORMATION, "Icon","info");
                return;
            end
            description_text = ...
                string(app.TextArea_ProblemDescription.Value).join(newline);
            if strlength(description_text) <= 10
                uialert(app.BugReporterUIFigure, app.langmap.REPORT_BUG_DETAIL, ...
                    app.langmap.INFORMATION, "Icon","info");
                return;
            end

            BugInfo.AppVersion = BugReporter.GetVersions();
            BugInfo.Components = strings(size(app.node_bug_slt));
            BugInfo.Description = string(app.TextArea_ProblemDescription.Value);
            BugInfo.AttachFolder = string(app.EditField_Folder.Value);
            BugInfo.BugType = ...
                string(app.ButtonGroup_ProblemType.SelectedObject.UserData);
            [~, BugInfo.LocalMachine] =  GetHardwareInfo();
            BugInfo.SummaryTime = string(datetime);

            for k = 1:numel(app.node_bug_slt)
                if ~isempty(app.node_bug_slt(k).NodeData)
                    BugInfo.Components(k) = string(app.node_bug_slt(k).NodeData);
                end
            end


            filename = BugInfo.SummaryTime.replace("-","").replace(":","").replace(" ","_") ...
                + "_"+string(randi(1e10,1))+".xml";

            if app.lan_status == true
                % save to local folder at first
                file = fullfile(userpath, filename.char);
                try
                    writestruct(BugInfo, file, "FileType","xml");
                catch exception
                    switch exception
                        case ""
                        otherwise
                            throwAsCaller(exception);
                    end
                end

                if app.sftp_handle.RemoteWorkingDirectory ...
                        ~= app.caller.BUG_REPORT_FOLDER
                    % cd remote folder
                    cd(app.sftp_handle, app.caller.BUG_REPORT_FOLDER);
                end

                % upload to remote folder
                mput(app.sftp_handle, file);
            end

            % if it is a urgent bug, try to send Email
            if BugInfo.BugType == "Urgent"
                slt = uiconfirm(app.BugReporterUIFigure, app.langmap.URGENT_EMAIL, ...
                    app.langmap.INFORMATION,"Options",[app.langmap.OK, app.langmap.CANCEL], ...
                    "DefaultOption",app.langmap.OK,"Icon","question");
                if slt == app.langmap.OK
                    try
                    sendmail(app.caller.EMAIL_TO, app.langmap.URGENT_SUBJECT, ...
                        sprintf("Dear maintainer: \r\n" + ...
                        "Your user provided an urgent exception regarding the Reg3D App. \r\n" + ...
                        "Attachments in the following *.xml file. \r\n" + ...
                        "\t\t\t\t\t From Reg3D App@SiLab, IBP, China"), ...
                        file);
                    catch exception
                        switch exception.identifier
                            case 'MATLAB:sendmail:SmtpError'
                                uialert(app.BugReporterUIFigure, app.langmap.EMAIL_FAILED, ...
                                    app.langmap.ERROR, "Icon","error");
                                throwAsCaller(exception);
                            otherwise
                        end
                    end
                    waitfor(uiconfirm(app.BugReporterUIFigure, app.langmap.EMAIL_SUCCESS, ...
                        app.langmap.INFORMATION, "Icon","success","Options",app.langmap.OK));
                end
            end

            slt = uiconfirm(app.BugReporterUIFigure, app.langmap.FEEDBACK_SUCCESS, ...
                app.langmap.INFORMATION,"Options", ...
                [app.langmap.CONTINUE, app.langmap.CLOSE], ...
                "Icon","success");
            if slt == app.langmap.CLOSE
                pause(1);
                BugReporterUIFigureCloseRequest(app, []);
            end
        end

        % Callback function: Tree_ProblemComponents
        function Tree_ProblemComponentsCheckedNodesChanged(app, event)
            app.node_bug_slt = app.Tree_ProblemComponents.CheckedNodes;
            
        end

        % Value changed function: EditField_Folder
        function EditField_FolderValueChanged(app, event)
            value = app.EditField_Folder.Value;
            
            if ~isfolder(value)
                app.EditField_Folder.Tooltip = app.langmap.INVALID_PATH;
                app.EditField_Folder.FontColor = [1, 0, 0];
            else
                app.EditField_Folder.Tooltip = '';
                app.EditField_Folder.FontColor = [0, 0, 0];
            end
        end

        % Callback function: Hyperlink_Privacy
        function Hyperlink_PrivacyClicked(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            privacy_file = [pathToMLAPP, filesep, 'help', filesep, 'privacy.html'];
            
            if ~exist(privacy_file, "file")
                uialert(app.Reg3DV2010WukongUIFigure, ...
                    app.region.chmap.MISSING_PRIVACY, ...
                    app.region.chmap.ERROR, "Icon","error");
                return;
            else
                web(privacy_file);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BugReporterUIFigure = uifigure('Visible', 'off');
            app.BugReporterUIFigure.Name = 'Bug Reporter';
            app.BugReporterUIFigure.Icon = 'bug.png';
            app.BugReporterUIFigure.CloseRequestFcn = createCallbackFcn(app, @BugReporterUIFigureCloseRequest, true);
            app.Menu_Help = uimenu(app.BugReporterUIFigure);
            app.Menu_Help.Text = '帮助';
            app.Menu_CommonProblem = uimenu(app.Menu_Help);
            app.Menu_CommonProblem.MenuSelectedFcn = createCallbackFcn(app, @Menu_CommonProblemSelected, true);
            app.Menu_CommonProblem.Text = '常见问题';
            app.Menu_About = uimenu(app.Menu_Help);
            app.Menu_About.MenuSelectedFcn = createCallbackFcn(app, @Menu_AboutSelected, true);
            app.Menu_About.Text = '关于';
            app.GridLayout = uigridlayout(app.BugReporterUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '问题报告程序：';
            app.Label_RemoteHostStatus = uilabel(app.GridLayout2);
            app.Label_RemoteHostStatus.Text = '脱机';
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '问题报告程序不会收集您的';
            app.Hyperlink_Privacy = uihyperlink(app.GridLayout2);
            app.Hyperlink_Privacy.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_PrivacyClicked, true);
            app.Hyperlink_Privacy.URL = 'privacy.html';
            app.Hyperlink_Privacy.Text = '隐私';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.Button_Submit = uibutton(app.GridLayout8, 'push');
            app.Button_Submit.ButtonPushedFcn = createCallbackFcn(app, @Button_SubmitPushed, true);
            app.Button_Submit.Enable = 'off';
            app.Button_Submit.Text = '提交';
            app.ButtonGroup_ProblemType = uibuttongroup(app.GridLayout7);
            app.ButtonGroup_ProblemType.Title = '问题类型';
            app.Button_Urgent = uiradiobutton(app.ButtonGroup_ProblemType);
            app.Button_Urgent.Tooltip = {'可能诱发对数据定性误判的严重异常'};
            app.Button_Urgent.Text = '紧急';
            app.Button_Ordinary = uiradiobutton(app.ButtonGroup_ProblemType);
            app.Button_Ordinary.Tooltip = {'可能阻碍配准等处理流程的程序异常'};
            app.Button_Ordinary.Text = '普通';
            app.Button_Suggested = uiradiobutton(app.ButtonGroup_ProblemType);
            app.Button_Suggested.Tooltip = {'可能提高程序实用性，易用性或安全性的建议'};
            app.Button_Suggested.Text = '建议';
            app.Button_Suggested.Value = true;
            app.Panel_ProblemDescription = uipanel(app.GridLayout4);
            app.Panel_ProblemDescription.Title = '问题描述';
            app.GridLayout5 = uigridlayout(app.Panel_ProblemDescription);
            app.TextArea_ProblemDescription = uitextarea(app.GridLayout5);
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.Label_4 = uilabel(app.GridLayout9);
            app.Label_4.Text = '附加文件夹';
            app.EditField_Folder = uieditfield(app.GridLayout9, 'text');
            app.EditField_Folder.ValueChangedFcn = createCallbackFcn(app, @EditField_FolderValueChanged, true);
            app.EditField_Folder.Placeholder = '此处包含用于复现问题的附加文件';
            app.Button_SelectFolder = uibutton(app.GridLayout9, 'push');
            app.Button_SelectFolder.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectFolderPushed, true);
            app.Button_SelectFolder.Icon = 'folder.png';
            app.Button_SelectFolder.Text = '';
            app.Panel_ProblemComponents = uipanel(app.GridLayout3);
            app.Panel_ProblemComponents.Title = '问题组件';
            app.GridLayout_PC = uigridlayout(app.Panel_ProblemComponents);
            app.Tree_ProblemComponents = uitree(app.GridLayout_PC, 'checkbox');
            app.Tree_ProblemComponents.Tooltip = {'请勾选出现问题的组件'};
            % Assign Checked Nodes
            app.Tree_ProblemComponents.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_ProblemComponentsCheckedNodesChanged, true);
            % Show the figure after all components are created
            app.BugReporterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BugReporter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BugReporterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BugReporterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BugReporterUIFigure)
        end
    end
end