classdef CellSegmentor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CellSegmentorUIFigure          matlab.ui.Figure
        Menu_File                      matlab.ui.container.Menu
        Menu_ExportMask                matlab.ui.container.Menu
        Menu_ImportMask                matlab.ui.container.Menu
        Menu_Option                    matlab.ui.container.Menu
        Menu_TrainingSamples           matlab.ui.container.Menu
        Menu_AnomalyReport             matlab.ui.container.Menu
        Menu_AnomalyRegion             matlab.ui.container.Menu
        Menu_RadiusPlus                matlab.ui.container.Menu
        Menu_RadiusMinus               matlab.ui.container.Menu
        Menu_AutoFocus                 matlab.ui.container.Menu
        Menu_Help                      matlab.ui.container.Menu
        Menu_About                     matlab.ui.container.Menu
        GridLayout_Main                matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        UITable_ObjectInfo             matlab.ui.control.Table
        GridLayout7                    matlab.ui.container.GridLayout
        Label_Anomaly_Info             matlab.ui.control.Label
        Label_Anomaly_Warning          matlab.ui.control.Label
        Label_Anomaly_Error            matlab.ui.control.Label
        UITable_Anomaly                matlab.ui.control.Table
        GridLayout                     matlab.ui.container.GridLayout
        Panel_CommonParameters         matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        CheckBox_Split                 matlab.ui.control.CheckBox
        Spinner_SubregionNumber        matlab.ui.control.Spinner
        Label_5                        matlab.ui.control.Label
        EditField_Gamma                matlab.ui.control.NumericEditField
        EditField_3Label               matlab.ui.control.Label
        EditField_CameraBackground     matlab.ui.control.NumericEditField
        Label_6                        matlab.ui.control.Label
        GridLayout5                    matlab.ui.container.GridLayout
        DropDown_View                  matlab.ui.control.DropDown
        Button_Segment                 matlab.ui.control.Button
        Button_Train                   matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        Tab_Morphology                 matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        Image_OutlierDirectionTooltip  matlab.ui.control.Image
        EditField_ObjectNumber         matlab.ui.control.EditField
        Label_4                        matlab.ui.control.Label
        DropDown_SegmentAlgorithm      matlab.ui.control.DropDown
        Label_13                       matlab.ui.control.Label
        DropDown_Outlier               matlab.ui.control.DropDown
        Label_3                        matlab.ui.control.Label
        Spinner_SphericityThreshold    matlab.ui.control.Spinner
        Label_8                        matlab.ui.control.Label
        EditField_IntensityThreshold   matlab.ui.control.NumericEditField
        Label_7                        matlab.ui.control.Label
        CheckBox_AutoThreshold         matlab.ui.control.CheckBox
        mumLabel_2                     matlab.ui.control.Label
        mumLabel                       matlab.ui.control.Label
        Spinner_StdRadius_Morphology   matlab.ui.control.Spinner
        Spinner_2Label                 matlab.ui.control.Label
        Spinner_MeanRadius_Morphology  matlab.ui.control.Spinner
        Label                          matlab.ui.control.Label
        Tab_MachineLearning            matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        Image_BatchSizeTooltip         matlab.ui.control.Image
        Image_ThresholdTooltip         matlab.ui.control.Image
        EditField_MaxEpoch             matlab.ui.control.NumericEditField
        Label_16                       matlab.ui.control.Label
        EditField_WeightDecay          matlab.ui.control.NumericEditField
        Label_15                       matlab.ui.control.Label
        EditField_LearnRate            matlab.ui.control.NumericEditField
        Label_12                       matlab.ui.control.Label
        Spinner_BatchSize              matlab.ui.control.Spinner
        Label_11                       matlab.ui.control.Label
        DropDown_Stage                 matlab.ui.control.DropDown
        DropDown_3Label                matlab.ui.control.Label
        DropDown_Model                 matlab.ui.control.DropDown
        Label_9                        matlab.ui.control.Label
        CheckBox_Enhance               matlab.ui.control.CheckBox
        Spinner_Threshold              matlab.ui.control.Spinner
        Label_10                       matlab.ui.control.Label
        Spinner_MinRadius_Cellpose     matlab.ui.control.Spinner
        Label_14                       matlab.ui.control.Label
        Spinner_MeanRadius_Cellpose    matlab.ui.control.Spinner
        Spinner_5Label                 matlab.ui.control.Label
        mumLabel_4                     matlab.ui.control.Label
        mumLabel_3                     matlab.ui.control.Label
    end

    
    properties (Constant)
        VER = "2.0.0"
        COMPONENT_ROW_HEIGHT = 25
        RAW_SIZE = [320, 480]
        EXPAND_SIZE = [640, 480]

        CELL_RADIUS_SIZE = 20
        CELL_RADIUS_INCREMENT = 5
        CELL_RELAXATION_FACTOR = sqrt(2)

        CELL_OUTLIER_IOU_THRESHOLD = 0.25   % IOU threshold (same radius, cross center): (4*pi-3√3)/(8*pi+3√3)
    end

    properties (Access = private, Hidden)
        caller          (1,1)                           % Register object as parent
        seg_opt         (1,1)   segopt                  % segopt object, store segment options
        vol_opt         (1,:)   table                   % image volume options with 6 elements
        vol_ref         (1,1)                           % reference volume object
        mask            (:,1)               = []        % the regmask object
        rois            (1,:)   cell        = {}        % temporary rois on screen
        ncbot           (:,1)               = []        % the nuclear control bot, storage and display management
        with_mask       (1,1)   logical     = false     % flag for mask exist, could be true/false
        rfold           (1,1)   double      = 0         % the radius increment fold, integer

        message_level   (1,1)   string
        langmap         (1,1)   struct                  % 1-by-1 struct, mapping for language
    end
    
    methods (Access = ?Register)
        function Recover(app, ncbot, sid)
            % ncbot(kernel) come from caller
            app.ncbot = ncbot;
            app.with_mask = true;
            app.ncbot.Display(sid);

            % add cell manager menu
            app.caller.DeleteCellManagerMenu();
            app.caller.AddCellManagerMenu();
            % unlock signal estimator
            app.caller.SetSigEstimatorState("on");
        end

        function Refresh(app, mainapp)
            InitData(app, mainapp);
            InitPanel(app);
        end

        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr > 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_CommonParameters.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.UITable_ObjectInfo.BackgroundColor = constdef.GRAYSCALE_AXES_LIGHT;
                case "DARK"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_CommonParameters.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.UITable_ObjectInfo.BackgroundColor = constdef.GRAYSCALE_AXES_DARK;
                otherwise
            end
        end

        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            % apply panel language
            app.Menu_File.Text = app.langmap.FILE;
            app.Menu_Option.Text = app.langmap.OPTION;
            app.Menu_Help.Text = app.langmap.HELP;
            app.Menu_About.Text = app.langmap.ABOUT;
            app.Menu_TrainingSamples.Text = app.langmap.TRAINING_SAMPLES;
            app.Menu_ImportMask.Text = app.langmap.IMPORT_MASK;
            app.Menu_ExportMask.Text = app.langmap.EXPORT_MASK;
            app.Menu_AnomalyReport.Text = app.langmap.ANOMALY_REPORT;
            app.Menu_AnomalyRegion.Text = app.langmap.ANOMALY_REGION;
            app.Menu_RadiusPlus.Text = sprintf("%s (+%d)", app.langmap.PLUS, app.CELL_RADIUS_INCREMENT);
            app.Menu_RadiusMinus.Text = sprintf("%s (-%d)", app.langmap.MINUS, app.CELL_RADIUS_INCREMENT);
            app.Menu_AutoFocus.Text = app.langmap.AUTO_FOCUS;
            app.Panel_CommonParameters.Title = app.langmap.COMMON_PARAMETERS;
            app.Tab_Morphology.Title = app.langmap.MORPHOLOGY;
            app.Tab_MachineLearning.Title = app.langmap.MACHINE_LEARNING;
            app.Label.Text = app.langmap.RADIUS_MEAN;
            app.Spinner_2Label.Text = app.langmap.RADIUS_STD;
            app.Spinner_5Label.Text = app.langmap.RADIUS_MEAN;
            app.EditField_3Label.Text = app.langmap.GAMMA;
            app.DropDown_3Label.Text = app.langmap.STAGE;
            app.Label_3.Text = app.langmap.OUTLIER_DIRECTION;
            app.Label_4.Text = app.langmap.OBJECTS_NUMBER;
            app.Label_5.Text = app.langmap.SUB_AREA_NUMBER;
            app.Label_6.Text = app.langmap.CAMERA_BACKGROUND;
            app.Label_7.Text = app.langmap.INTENSITY_THRESHOLD;
            app.Label_8.Text = app.langmap.SPHERICITY_THRESHOLD;
            app.Label_9.Text = app.langmap.MODEL;
            app.Label_10.Text = app.langmap.THRESHOLD;
            app.Label_11.Text = app.langmap.BATCH_SIZE;
            app.Label_12.Text = app.langmap.LEARN_RATE;
            app.Label_13.Text = app.langmap.SEGMENTATION_ALGORITHM;
            app.Label_14.Text = app.langmap.RADIUS_MIN;
            app.Label_15.Text = app.langmap.WEIGHT_DECAY;
            app.Label_16.Text = app.langmap.MAX_EPOCHS;
            app.CheckBox_AutoThreshold.Text = app.langmap.AUTO;
            app.CheckBox_Split.Text = app.langmap.DELINEATION;
            app.CheckBox_Enhance.Text = app.langmap.ENHANCEMENT;
            app.DropDown_Outlier.Items = [app.langmap.BOTH_SIDE, ...
                app.langmap.LEFT_SIDE, app.langmap.RIGHT_SIDE];
            app.DropDown_SegmentAlgorithm.Items = [app.langmap.WATERSHED, ...
                app.langmap.WATERSHED_OLD];
            app.DropDown_View.Items = [app.langmap.GLOBAL_OBJECTS, ...
                app.langmap.LOCAL_OBJECTS, app.langmap.EXCEPTIONAL_OBJECTS, app.langmap.NONE];
            app.UITable_ObjectInfo.ColumnName = [app.langmap.CENTROID, ...
                app.langmap.EQUIVALENT_RADIUS];
            app.Button_Segment.Text = app.langmap.SEGMENT;
            app.Button_Train.Text = app.langmap.TRAINING;
        end

        function RefreshLabel(app)
            if ~isempty(app.ncbot) && isvalid(app.ncbot)
                % refresh ROIs
                status = app.ncbot.Refresh();

                if status == 0
                    % set new mask
                    mask_ = app.ncbot.GenMaskVol();
                    app.caller.SetMaskVol(mask_);

                    % display ROIs
                    sid = app.caller.GetCurrentSliceIndex();
                    app.ncbot.Clear();
                    app.ncbot.Display(sid);

                    % report
                    ns = sprintf(" %d", app.ncbot.MaxN(1));
                    for k = 2:numel(app.ncbot.MaxN)
                        ns = ns + sprintf(",%d", app.ncbot.MaxN(k));
                    end
                    app.caller.SendMessage(["OBJECTS_FOUND", ns]);
                end
            end
        end

        function RefreshTooltips(app)
            tl = app.caller.GetAppTipsLevel();
            switch tl
                case constdef.TOOLTIP_SIMPLE
                    app.Image_OutlierDirectionTooltip.Tooltip ...
                         = app.langmap.TIP_OUTLIER_DIRECTION;
                    app.Image_ThresholdTooltip.Tooltip ...
                        = app.langmap.TIP_CELLPOSE_THRESHOLD;
                    app.Image_BatchSizeTooltip.Tooltip ...
                        = app.langmap.TIP_CELLPOSE_BATCH_SIZE;
                    app.DropDown_View.Tooltip = app.langmap.DISPLAY_RANGE;
                case constdef.TOOLTIP_DETAIL
                    app.Image_OutlierDirectionTooltip.Tooltip ...
                         = app.langmap.TIP_OUTLIER_DIRECTION;
                    app.Image_ThresholdTooltip.Tooltip ...
                        = app.langmap.TIP_CELLPOSE_THRESHOLD;
                    app.Image_BatchSizeTooltip.Tooltip ...
                        = app.langmap.TIP_CELLPOSE_BATCH_SIZE;
                    app.DropDown_View.Tooltip = app.langmap.DISPLAY_RANGE;
                case constdef.TOOLTIP_NONE
                    app.Image_OutlierDirectionTooltip.Tooltip = '';
                    app.Image_ThresholdTooltip.Tooltip = '';
                    app.Image_BatchSizeTooltip.Tooltip = '';
                    app.DropDown_View.Tooltip = '';
                otherwise
            end
        end

        function RefreshMessageLevel(app)
            app.message_level = app.caller.GetAppMessageLevel();
        end

        function AddCell(app)
            persistent add_lock;
            if isempty(add_lock), add_lock = 0; end
            if ~isempty(app.ncbot) && isvalid(app.ncbot)
                if add_lock == 0
                    add_lock = add_lock + 1;    % protect

                    % ask for identity
                    % 'add new object' as default input
                    id = app.ncbot.MaxN + 1;

                    % auto sensing modifies new identity
                    if ~isempty(app.UITable_Anomaly.Data) ...
                            && (app.DropDown_View.Value=="EXCEPTION")
                        compidx = CellSegmentor.GetIDVec(app.UITable_Anomaly.Data, ...
                            app.UITable_Anomaly.Selection);
                        if isscalar(compidx) && ~ismember(compidx, app.ncbot.CurrentIDs)
                            % 'add current selection' as default input
                            id = compidx;
                        end
                    end

                    id = uigetid(id, "new identity");

                    if isempty(id)
                        add_lock = add_lock - 1;
                        return; 
                    end

                    % draw circle on screen
                    roi = drawcircle(app.caller.UIAxes_Slice, ...
                        "FaceAlpha", 0, ...
                        "StripeColor","y", ...
                        "Label", string(id), ...
                        "LabelAlpha",0, ...
                        "LabelTextColor","w", ...
                        "MarkerSize",0.1, ...
                        "LineWidth",0.5);

                    cinfo = [roi.Center, roi.Radius];

                    sid = app.caller.GetCurrentSliceIndex();
                    status = app.ncbot.AddObject(sid, id, cinfo);

                    if status == 0
                        % store rois temporary
                        app.rois = [app.rois, {roi}];
                    end

                    add_lock = add_lock - 1;
                end
            end
        end

        function AnomalyDetection(app)
            % This function detects the possible anomaly ROIs and advises
            % user to improve segmentation
            % PRINCIPLES:
            % [1.1] (error)   complex connected domain
            % [2.1] (warning) too small or big region as outlier
            % [2.2] (warning) too few or many slices (< 2 | > 4)
            % [2.3] (warning) cells with abnormal geometry
            % [3.1] (info)    may be overlapped domain
            % [3.2] (info)    too bright or dark region as outlier

            if ~isempty(app.ncbot) && isvalid(app.ncbot) && app.with_mask
                app.UITable_Anomaly.Enable = "on";
                app.UITable_ObjectInfo.Enable = "on";
                
                upd = uiprogressdlg(app.CellSegmentorUIFigure, "Indeterminate","on", ...
                    "Message", app.langmap.ANOMALY_DETECTION_RUNNING, "Icon","info");

                % analyze all complex connected region
                mask_ = app.ncbot.GenMaskVol();
                app.mask = regmask.recover(mask_, ...
                    [app.vol_opt.height, app.vol_opt.width, app.vol_opt.slices]);
                mkvol = app.mask.MaskVol;
                V = app.vol_ref.RefVol;
                prop = regionprops3(mkvol, V, "Centroid", "BoundingBox", "Volume", "MeanIntensity");

                report = table('Size', [0,2], 'VariableTypes', {'string', 'string'}, ...
                    'VariableNames', {'ID', 'Comments'});
    
                %           error    warning    info
                report_num = [0;        0;       0];

                %% 1.1 find complex domain
                for k = 1:app.mask.NumComps
                    % find each comp
                    CC = bwconncomp(app.mask.MaskComps(k)==mkvol);
                    ID = string(app.mask.MaskComps(k));
                    if CC.NumObjects > 1
                        % too many components
                        Comments = app.langmap.ANOMALY_CONNECT_COMPLEX;
                        item = table(ID, Comments);
                        report_num(1) = report_num(1) + 1;
                    elseif CC.NumObjects < 1
                        % component lost
                        Comments = app.langmap.ANOMALY_CONNECT_LOST;
                        item = table(ID, Comments);
                        report_num(1) = report_num(1) + 1;
                    else
                        item = [];
                    end
                    report = [report; item]; %#ok<AGROW>
                end

                if size(report, 1) > 0
                    % remove bad domains
                    for r = double(report.ID)', mkvol(mkvol==r) = 0; end
                end

                %% 2.1 find too small or big domains as outliers
                otidx = isoutlier(prop.Volume, "gesd");
                ID = string(find(otidx));
                Comments = repmat(app.langmap.ANOMALY_CONNECT_OUTLIER_VOLUME, numel(ID), 1);
                item = table(ID, Comments);
                report = [report; item];
                report_num(2) = report_num(2) + numel(ID);

                %% 2.2 find too few or many slices domains as outliers
                ID = string(find(prop.BoundingBox(:,end) < 2 | prop.BoundingBox(:,end) > 4));
                Comments = repmat(app.langmap.ANOMALY_CONNECT_OUTLIER_SLICES, numel(ID), 1);
                item = table(ID, Comments);
                report = [report; item];
                report_num(2) = report_num(2) + numel(ID);

                %% 2.3 find cells with abnormal geometry (spacial distribution)
                pSize = nan(size(prop, 1), 1);
                cVar = nan(size(prop, 1), 1);
                minIOU = nan(size(prop, 1), 1);
                r_otidx = false(size(prop, 1), 1);
                for m = 1:size(prop, 1)
                    % calculate z-projected area size and center variance
                    C = prop.Centroid(m, :);

                    if all(~isnan(C), "all")
                        BB = ceil(prop.BoundingBox(m, :));
                        vcrop = mkvol(BB(2):BB(2)+BB(5)-1, BB(1):BB(1)+BB(4)-1, ...
                            BB(3):BB(3)+BB(6)-1);
                        pSize(m) = sum(max(vcrop==m, [], 3), "all");

                        % calculate variance along the z-axis direction
                        cX = (0.5:1:BB(4)-0.5)*(reshape(sum(vcrop==m, 1),BB(4),[]))...
                            ./reshape(sum(vcrop==m, [1,2]),1,[]);
                        cY = (reshape(sum(vcrop==m, 2),[],BB(5))*(0.5:1:BB(5)-0.5)') ...
                            ./reshape(sum(vcrop==m, [1,2]),[],1);
                        cVarX = var(cX); cVarY = var(cY);
                        cVar(m) = cVarX + cVarY;

                        % calculate radius threshold
                        CR = sqrt(sum(vcrop==m, [1,2])/pi);
                        pdX = pdist(cX'); pdY = pdist(cY);
                        r_otidx(m) = any(sqrt(pdX.^2+pdY.^2) > median(CR, "all"));

                        % calculate minimal IOU among cell
                        minIOU(m) = calc_iou_min(vcrop==m);
                    end
                end

                % normalize to same scale
                pSize = normalize(pSize, 1, "range", [-1, 1]);
                cVar = normalize(cVar, 1, "range");

                % high cost may be outlier
                otidx = isoutlier(cVar + abs(pSize), "gesd") ...
                    | r_otidx | (minIOU < app.CELL_OUTLIER_IOU_THRESHOLD);
                ID = string(find(otidx));
                Comments = repmat(app.langmap.ANOMALY_CONNECT_OUTLIER_GEOMETRY, numel(ID), 1);
                item = table(ID, Comments);
                report = [report; item];
                report_num(2) = report_num(2) + numel(ID);

                %% 3.1 find domains may be too bright or dark
                otidx = isoutlier(prop.MeanIntensity, "gesd");
                ID = string(find(otidx));
                Comments = repmat(app.langmap.ANOMALY_CONNECT_OUTLIER_BRIGHTNESS, numel(ID), 1);
                item = table(ID, Comments);
                report = [report; item];
                report_num(3) = report_num(3) + numel(ID);

                %% 3.2 find domains may be overlapped
                for m = 1:size(prop, 1)
                    for n = m+1:size(prop, 1)
                        % calculate each pair overlapped volume over union
                        % volume: as volume IOU
                        C = [prop.Centroid(m, :); ...
                             prop.Centroid(n, :)];

                        if all(~isnan(C), "all")
                            BB = [prop.BoundingBox(m, :); ...
                                  prop.BoundingBox(n, :)];

                            [~, lbbidx] = min(C); [~, rftidx] = max(C);

                            if BB(lbbidx(1),1)+BB(lbbidx(1),4)-1 >= BB(rftidx(1),1) ...
                                    && BB(lbbidx(2),2)+BB(lbbidx(2),5)-1 >= BB(rftidx(2),2) ...
                                    && BB(lbbidx(3),3)+BB(lbbidx(3),6) > BB(rftidx(3),3)

                                % if there is exist box overlap, compare
                                % center distance and radius sum
                                rxy_lbb = sqrt(sum(mkvol==m, "all")/(pi*BB(lbbidx(3),6)));
                                rxy_rft = sqrt(sum(mkvol==n, "all")/(pi*BB(rftidx(3),6)));
                                dr_relax = (rxy_lbb+rxy_rft)/app.CELL_RELAXATION_FACTOR;

                                if  sqrt(sum(diff(C(:,1:2),1,1).^2)) < dr_relax && ...
                                        abs(C(1,3)-C(2,3)) < 0.5*(BB(1,6)+BB(2,6))
                                    ID = sprintf("(%d,%d)", m, n);
                                    Comments = app.langmap.ANOMALY_CONNECT_OVERLAP;
                                    item = table(ID, Comments);
                                    report = [report; item]; %#ok<AGROW>
                                    report_num(3) = report_num(3) + 1;
                                end
                            end
                        end
                    end
                end

                %% set report on anomaly table
                if size(app.UITable_Anomaly.Data, 1) ~= 0
                    app.UITable_Anomaly.Data = [];
                    removeStyle(app.UITable_Anomaly);
                end
                app.UITable_Anomaly.Data = report;
                app.Label_Anomaly_Error.Text = sprintf("Error(%d)", report_num(1));
                app.Label_Anomaly_Warning.Text = sprintf("Warning(%d)", report_num(2));
                app.Label_Anomaly_Info.Text = sprintf("Info(%d)", report_num(3));

                % set the appearance
                s(1) = uistyle("Icon", "error", "IconAlignment", "leftmargin");
                s(2) = uistyle("Icon", "warning", "IconAlignment", "leftmargin");
                s(3) = uistyle("Icon", "info", "IconAlignment", "leftmargin");

                if any(report_num~=0)
                    report_num = cumsum([1; report_num]);   % left close right open
                    for k = 1:numel(report_num)-1
                        cell_ids = [(report_num(k):report_num(k+1)-1)', ...
                                    2*ones(report_num(k+1)-report_num(k), 1)];
                        if ~isempty(cell_ids)
                            addStyle(app.UITable_Anomaly, s(k), "cell", cell_ids);
                        end
                    end
                end

                delete(upd);
            end

            function iou_min = calc_iou_min(vb)
                iou_min = 1;
                for p = 1:size(vb, 3)
                    for q = p+1:size(vb, 3)
                        iou = sum(vb(:,:,p)&vb(:,:,q), "all") / ...
                            sum(vb(:,:,p)|vb(:,:,q), "all");
                        if iou < iou_min, iou_min = iou; end
                    end
                end
            end
        end

        function UpdateHighlights(app, hl)
            arguments
                app 
                hl      (1,:)   double  {mustBePositive, mustBeInteger} = []   % highlight indicator
            end

            if ~isempty(app.ncbot) && isvalid(app.ncbot)
                app.ncbot.HighlightID = hl;
            end
        end

        function UpdateMultiplyROIs(app, sid)
            arguments
                app
                sid     (1,1)   double  {mustBePositive, mustBeInteger}
            end

            % clear temporary rois
            if ~isempty(app.rois)
                cellfun(@delete, app.rois);
            end
            app.rois = {};

            if ~isempty(app.ncbot) && isvalid(app.ncbot)
                app.ncbot.Clear();
                app.ncbot.Display(sid);
            end
        end

        % could be restore
        function PseudoClose(app)
            app.PostProcessWhenPseudoClosing();

            % close window
            delete(app);
        end

        % real close
        function Close(app)
            % remove cell manager menu
            % app.caller.DisableCellManagerMenu();
            app.caller.DeleteCellManagerMenu();

            % lock signal estimator
            app.caller.SetSigEstimatorState("off");

            delete(app.ncbot);

            delete(app);
        end
    end

    methods (Access = private)

        function InitData(app, mainapp)
            app.caller = mainapp;

            app.seg_opt = app.caller.GetSegOptions();
            app.vol_opt = app.caller.GetVolOptions();
            app.vol_ref = app.caller.GetRefVol();

            app.mask = app.caller.GetMask();
            app.with_mask = ~isempty(app.mask);
        end

        function InitPanel(app)
            % update panel use seg_opt
            %% common parameters
            app.EditField_CameraBackground.Value = app.seg_opt.bkg;
            app.EditField_Gamma.Value = app.seg_opt.gamma;
            app.Spinner_SubregionNumber.Value = max(1, size(app.seg_opt.bdbox, 1));
            app.CheckBox_Split.Value = false;

            %% specical parameters
            % morphology
            app.Spinner_MeanRadius_Morphology.Value = app.seg_opt.r_u;
            app.Spinner_StdRadius_Morphology.Value = app.seg_opt.r_s;
            app.EditField_ObjectNumber.Value = string(app.seg_opt.n_max).join(",");
            app.EditField_IntensityThreshold.Value = app.seg_opt.fg_th;
            app.CheckBox_AutoThreshold.Value = app.seg_opt.auto_fg;
            app.Spinner_SphericityThreshold.Value = app.seg_opt.sph_th;
            app.DropDown_Outlier.Value = app.seg_opt.rm_outlier;
            if app.seg_opt.ws_old == true
                app.DropDown_SegmentAlgorithm.Value = "watershed_old";
            else
                app.DropDown_SegmentAlgorithm.Value = "watershed";
            end

            % cellpose
            app.Spinner_MeanRadius_Cellpose.Value = app.seg_opt.r_mean;
            app.Spinner_MinRadius_Cellpose.Value = app.seg_opt.r_min;
            app.DropDown_Model.Value = app.seg_opt.model;
            app.DropDown_Stage.Value = app.seg_opt.stage;
            app.Spinner_Threshold.Value = app.seg_opt.cell_th;
            app.CheckBox_Enhance.Value = app.seg_opt.enhance_ml;
            app.Spinner_BatchSize.Value = app.seg_opt.batch_size;
            app.EditField_LearnRate.Value = app.seg_opt.learn_rate;
            app.EditField_WeightDecay.Value = app.seg_opt.weight_decay;
            app.EditField_MaxEpoch.Value = app.seg_opt.epochs_max;

            % validate if cellpose is enabled
            CellposePanelSwitchTo(app, app.seg_opt.model);

            if ismember("Medical Imaging Toolbox Interface for Cellpose", ...
                    matlab.addons.installedAddons().Name)
                SetSegmentPanelState(app, ["on", "on"]);
                cp_flag = true;
            else
                SetSegmentPanelState(app, ["on", "off"]);
                cp_flag = false;
            end

            %% control panel
            app.Button_Segment.Enable = "on";
            if app.seg_opt.method == "cellpose" && ...
                    app.seg_opt.modal == "train" && cp_flag==true
                app.Button_Train.Enable = "on";
            else
                app.Button_Train.Enable = "off";
            end
            app.DropDown_View.Enable = app.with_mask && ...
                (~isempty(app.ncbot)&&isvalid(app.ncbot));
        end

        function CellposePanelSwitchTo(app, model)
            switch model
                case {'cyto', 'CP', 'nuclei'}
                    app.GridLayout4.RowHeight = app.COMPONENT_ROW_HEIGHT * ...
                        [1, 1, 1, 0, 1, 1, 0, 0, 0];
                case "KC"
                    app.GridLayout4.RowHeight = app.COMPONENT_ROW_HEIGHT * ...
                        [1, 1, 1, 1, 1, 1, 1, 1, 1];
                otherwise
            end
        end

        function SetSegmentPanelState(app, state)
            arguments
                app
                state   (1,2)   string  {mustBeMember(state, ["on", "off"])} = ["on", "on"]
            end

            %% common parameters
            app.EditField_CameraBackground.Enable = any(state=="on");
            app.EditField_Gamma.Enable = any(state=="on");
            app.CheckBox_Split.Enable = any(state=="on");
            if app.CheckBox_Split.Value == false
                app.Spinner_SubregionNumber.Enable = any(state=="on");
            end

            %% special parameters
            % morphology
            app.Spinner_MeanRadius_Morphology.Enable = state(1);
            app.Spinner_StdRadius_Morphology.Enable = state(1);
            app.EditField_ObjectNumber.Enable = state(1);
            app.CheckBox_AutoThreshold.Enable = state(1);
            app.EditField_IntensityThreshold.Enable ...
                = ~app.seg_opt.auto_fg & (state(1)=="on");
            app.Spinner_SphericityThreshold.Enable = state(1);
            app.DropDown_Outlier.Enable = state(1);
            app.DropDown_SegmentAlgorithm.Enable = state(1);

            % cellpose
            app.Spinner_MeanRadius_Cellpose.Enable = state(2);
            app.Spinner_MinRadius_Cellpose.Enable = state(2);
            app.DropDown_Model.Enable = state(2);
            app.DropDown_Stage.Enable = state(2);
            app.Spinner_Threshold.Enable = state(2);
            app.CheckBox_Enhance.Enable = state(2);
            app.Spinner_BatchSize.Enable = state(2);
            app.EditField_LearnRate.Enable = state(2);
            app.EditField_WeightDecay.Enable = state(2);
            app.EditField_MaxEpoch.Enable = state(2);
        end

        function ns = PostProcessAfterBotReady(app)
            % set mask volume
            mask_ = app.ncbot.GenMaskVol();
            app.caller.SetMaskVol(mask_);
            app.mask = app.caller.GetMask();
            app.with_mask = true;

            % append new segment history
            app.caller.AppendSegmentHistory(app.ncbot);

            % display
            app.DropDown_View.Enable = app.with_mask;
            switch app.ncbot.DispFlag
                case "on"
                    app.DropDown_View.Value = "GLOBAL";
                otherwise
                    app.DropDown_View.Value = "NONE";
            end
            app.ncbot.Clear();
            zidx = app.caller.GetCurrentSliceIndex();
            app.ncbot.Display(zidx);

            % remove bounding boxes
            app.caller.RemoveBoundingBoxes();

            % add cell manager menu
            % app.caller.EnableCellManagerMenu();
            app.caller.DeleteCellManagerMenu();
            app.caller.AddCellManagerMenu();
            % unlock signal estimator
            app.caller.SetSigEstimatorState("on");

            % send message: segment result
            ns = sprintf(" %d", app.ncbot.MaxN(1));
            for k = 2:numel(app.ncbot.MaxN)
                ns = ns + sprintf(",%d", app.ncbot.MaxN(k));
            end
        end

        function PostProcessWhenPseudoClosing(app)
            % remove cell manager menu
            % app.caller.DisableCellManagerMenu();
            app.caller.DeleteCellManagerMenu();

            % lock signal estimator
            app.caller.SetSigEstimatorState("off");

            % keep ncbot in Restore Point, remove current display
            % ~
            if ~isempty(app.ncbot) && isvalid(app.ncbot)
                app.ncbot.Clear();
            end

            %
            app.caller.RestoreStatusBeforeSegmentation();
        end

        function stats = UpdateObjInfo(app, comp)
            if isempty(comp)
                Centroid = zeros(0, 3); EquivDiameter = zeros(0, 1);
                stats = table(Centroid, EquivDiameter);
                app.UITable_ObjectInfo.Data = [];
            else
                comp_mask = false(size(app.mask.MaskVol));
                if isscalar(comp)
                    comp_mask = (app.mask.MaskVol == comp);
                else
                    % treat components as one group
                    for k = 1:numel(comp)
                        comp_mask = comp_mask|(app.mask.MaskVol == comp(k));
                    end
                end
                stats = regionprops3(comp_mask, "Centroid", "EquivDiameter");
                
                if ~isscalar(comp) && size(stats, 1) ~= 1
                    % combine multi-domains
                    C = sprintf("(%d, %d, %d)", round(mean(stats.Centroid, 1)));
                    R = sprintf("(%.1f)",  round(sqrt(prod(stats.EquivDiameter))/2, 1));
                else
                    C = sprintf("(%d, %d, %d)", round(stats.Centroid));
                    R = sprintf("(%.1f)",  round(stats.EquivDiameter/2, 1));
                end

                app.UITable_ObjectInfo.Data = table(C, R);
            end
        end
    end

    methods (Static)
        function compidx = GetIDVec(tbl, slt)
            arguments
                tbl (:,2)   table
                slt (:,1)   double  {mustBePositive, mustBeInteger}
            end

            if isempty(slt), compidx = []; return; end

            IDstr = tbl.ID(slt);
            if contains(IDstr, ",")
                % parse ids pair
                compidx = extractBetween(IDstr, "(", ")").split(",");
                compidx = str2double(compidx);
            else
                % transform only one number
                compidx = str2double(IDstr);
            end

            if any(isnan(compidx)), compidx = []; end
        end

        function r = GetDispRangebyCell(vopt, stats, rfold)
            arguments
                vopt    (1,:)   table
                stats   (:,2)   table
                rfold   (1,1)   double  {mustBeInteger} = 0
            end

            % parse center and radius
            C = stats.Centroid; R = stats.EquivDiameter;

            if ~isempty(C) && ~isempty(R)
                rmin = ceil(R/sqrt(vopt.xRes*vopt.yRes));
                hw = max(rmin, CellSegmentor.CELL_RADIUS_SIZE+rfold*CellSegmentor.CELL_RADIUS_INCREMENT);
                x0 = ceil(max(1, C(:,1)-hw)); y0 = ceil(max(1, C(:,2)-hw));
                w = min(2*hw, vopt.width-x0); h = min(2*hw, vopt.height-y0);
                r = [x0, y0, w, h];
            else
                r = zeros(0, 4);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            InitData(app, mainapp);

            InitPanel(app);

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);

            RefreshTooltips(app);

            RefreshMessageLevel(app);
        end

        % Close request function: CellSegmentorUIFigure
        function CellSegmentorUIFigureCloseRequest(app, event)
            % you can not be a died man by yourself
            PseudoClose(app);
        end

        % Menu selected function: Menu_TrainingSamples
        function Menu_TrainingSamplesSelected(app, event)
            
        end

        % Menu selected function: Menu_About
        function Menu_AboutSelected(app, event)
            msg = sprintf("Cell Segmentor for Register\n" + ...
                          "Version: %s @si lab \n" + ...
                          "State: Release (build 0209.19) \n" + ...
                          "Author: Weihan Li \n" + ...
                          "E-mail: liweihan20@mails.ucas.ac.cn \n", app.VER);
            uialert(app.CellSegmentorUIFigure, msg, ...
                app.langmap.INFORMATION, "Icon","info");
        end

        % Value changed function: EditField_CameraBackground
        function EditField_CameraBackgroundValueChanged(app, event)
            value = app.EditField_CameraBackground.Value;
            
            app.seg_opt.bkg = value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: EditField_Gamma
        function EditField_GammaValueChanged(app, event)
            value = app.EditField_Gamma.Value;
            
            app.seg_opt.gamma = value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: CheckBox_Split
        function CheckBox_SplitValueChanged(app, event)
            value = app.CheckBox_Split.Value;
            
            if value == true
                % lock the sub-region controller
                app.Spinner_SubregionNumber.Enable = "off";

                % generate boxes on parent viewer
                n_boxes = app.Spinner_SubregionNumber.Value;
                app.caller.SetBoundingBoxes(n_boxes);

                % update bounding box
                app.seg_opt = app.caller.GetSegOptions();
            else
                % remove boxes
                app.caller.RemoveBoundingBoxes();

                app.seg_opt.bdbox = [];

                % unlock the sub-region controller
                app.Spinner_SubregionNumber.Enable = "on";
            end

        end

        % Value changed function: Spinner_MeanRadius_Morphology
        function Spinner_MeanRadius_MorphologyValueChanged(app, event)
            value = app.Spinner_MeanRadius_Morphology.Value;
            
            app.seg_opt.r_u = value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: Spinner_StdRadius_Morphology
        function Spinner_StdRadius_MorphologyValueChanged(app, event)
            value = app.Spinner_StdRadius_Morphology.Value;
            
            app.seg_opt.r_s = value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: EditField_IntensityThreshold
        function EditField_IntensityThresholdValueChanged(app, event)
            value = app.EditField_IntensityThreshold.Value;
            
            app.seg_opt.fg_th = value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: CheckBox_AutoThreshold
        function CheckBox_AutoThresholdValueChanged(app, event)
            value = app.CheckBox_AutoThreshold.Value;

            app.seg_opt.auto_fg = value;
            app.EditField_IntensityThreshold.Enable = ~value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: Spinner_SphericityThreshold
        function Spinner_SphericityThresholdValueChanged(app, event)
            value = app.Spinner_SphericityThreshold.Value;
            
            app.seg_opt.sph_th = value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: DropDown_Outlier
        function DropDown_OutlierValueChanged(app, event)
            value = app.DropDown_Outlier.Value;
            
            app.seg_opt.rm_outlier = string(value);

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: DropDown_SegmentAlgorithm
        function DropDown_SegmentAlgorithmValueChanged(app, event)
            value = app.DropDown_SegmentAlgorithm.Value;
            
            switch value
                case "watershed"
                    app.seg_opt.ws_old = false;
                case "watershed_old"
                    app.seg_opt.ws_old = true;
                otherwise
            end

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            switch selectedTab
                case app.Tab_Morphology
                    app.seg_opt.method = "morphology";
                case app.Tab_MachineLearning
                    app.seg_opt.method = "cellpose";
                otherwise
            end

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: Spinner_MeanRadius_Cellpose
        function Spinner_MeanRadius_CellposeValueChanged(app, event)
            value = app.Spinner_MeanRadius_Cellpose.Value;
            
            app.seg_opt.r_mean = value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: Spinner_MinRadius_Cellpose
        function Spinner_MinRadius_CellposeValueChanged(app, event)
            value = app.Spinner_MinRadius_Cellpose.Value;
            
            app.seg_opt.r_min = value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: DropDown_Model
        function DropDown_ModelValueChanged(app, event)
            value = app.DropDown_Model.Value;
            
            app.seg_opt.model = string(value);
            switch value
                case "KC"
                    app.Button_Train.Enable = "on";
                otherwise
                    app.Button_Train.Enable = "off";
            end
            
            CellposePanelSwitchTo(app, value);

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: DropDown_Stage
        function DropDown_StageValueChanged(app, event)
            value = app.DropDown_Stage.Value;
            
            app.seg_opt.stage = string(value);

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: Spinner_Threshold
        function Spinner_ThresholdValueChanged(app, event)
            value = app.Spinner_Threshold.Value;
            
            app.seg_opt.cell_th = value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: CheckBox_Enhance
        function CheckBox_EnhanceValueChanged(app, event)
            value = app.CheckBox_Enhance.Value;
            
            app.seg_opt.enhance_ml = value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: Spinner_BatchSize
        function Spinner_BatchSizeValueChanged(app, event)
            value = app.Spinner_BatchSize.Value;
            
            app.seg_opt.batch_size = value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: EditField_LearnRate
        function EditField_LearnRateValueChanged(app, event)
            value = app.EditField_LearnRate.Value;
            
            app.seg_opt.learn_rate = value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: EditField_WeightDecay
        function EditField_WeightDecayValueChanged(app, event)
            value = app.EditField_WeightDecay.Value;
            
            app.seg_opt.weight_decay = value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Value changed function: EditField_MaxEpoch
        function EditField_MaxEpochValueChanged(app, event)
            value = app.EditField_MaxEpoch.Value;
            
            app.seg_opt.epochs_max = value;

            app.caller.SetSegOptions(app.seg_opt);
        end

        % Button pushed function: Button_Segment
        function Button_SegmentPushed(app, event)
            try
                % segment ROIs confirm
                if app.CheckBox_Split.Value == false
                    slt = uiconfirm(app.CellSegmentorUIFigure, ...
                        app.langmap.ROI_ENABLE_WARNING, app.langmap.WARNING, ...
                        "Icon","warning");
                    if slt ~= "OK"
                        return;
                    end
                end

                % lock panel
                SetSegmentPanelState(app, ["off","off"]);

                % lock bounding boxes
                app.caller.SetBoundingBoxesState("off");

                % new splitter and control bot
                if isempty(app.seg_opt.bdbox)
                    app.seg_opt.bdbox = [1, 1, 1, ...
                        app.vol_opt.width-1, app.vol_opt.height-1, app.vol_opt.slices-1];
                    app.caller.SetSegOptions(app.seg_opt);
                end
                nsper = NuclearSplitter(app.seg_opt, app.caller);
                imgvol = app.vol_ref.RefVol;

                upd = uiprogressdlg(app.CellSegmentorUIFigure, "Icon","info", ...
                    "Indeterminate","on", "Message",app.langmap.SEGMENTING);
                nsper.Split(imgvol, app.vol_opt);           % do segmentation
                delete(upd);

                % remove possible old nucleus and redraw
                if ~isempty(app.ncbot) && isvalid(app.ncbot)
                    app.ncbot.Clear();
                    delete(app.ncbot);
                end
                app.ncbot = NuclearCtrlBot(nsper.Results, app.vol_opt, "jet");
                app.ncbot.SetHandle(app.caller);

                ns = PostProcessAfterBotReady(app);

                app.caller.SendMessage(["OBJECTS_FOUND", ns]);
                % unlock panel
                app.CheckBox_Split.Value = false;
                if ismember("Medical Imaging Toolbox Interface for Cellpose", ...
                        matlab.addons.installedAddons().Name)
                    SetSegmentPanelState(app, ["on", "on"]);
                else
                    SetSegmentPanelState(app, ["on", "off"]);
                end

                app.caller.PushInfo("CellSegmentor", "Button_SegmentPushed");
            catch ME
                app.caller.PushInfo("CellSegmentor", "Button_SegmentPushed", ...
                    ME.identifier);

                rethrow(ME.identifier);
            end
        end

        % Button pushed function: Button_Train
        function Button_TrainPushed(app, event)
            
        end

        % Value changed function: EditField_ObjectNumber
        function EditField_ObjectNumberValueChanged(app, event)
            value = app.EditField_ObjectNumber.Value;
            
            value = str2num(value); %#ok<ST2NM>

            if numel(value) ~= app.Spinner_SubregionNumber.Value ...
                    || ~isPositiveIntegerValuedNumeric(value)
                app.seg_opt.n_max = repmat(65535, 1, app.Spinner_SubregionNumber.Value);
                app.EditField_ObjectNumber.Value = string(app.seg_opt.n_max).join(",");
            else
                app.seg_opt.n_max = value;
            end
        end

        % Value changed function: Spinner_SubregionNumber
        function Spinner_SubregionNumberValueChanged(app, event)
            value = app.Spinner_SubregionNumber.Value;
            
            s = "#R1";
            for k = 2:value, s = s + sprintf(",#R%d", k); end

            app.EditField_ObjectNumber.Placeholder = s;
        end

        % Menu selected function: Menu_AnomalyReport
        function Menu_AnomalyReportSelected(app, event)
            try
                app.Menu_AnomalyReport.Checked = ~app.Menu_AnomalyReport.Checked;

                if ~app.Menu_AnomalyReport.Checked
                    % hidden anomaly table
                    app.GridLayout_Main.ColumnWidth = {320, 0};
                    app.CellSegmentorUIFigure.Position(3) = app.RAW_SIZE(1);
                    app.CellSegmentorUIFigure.Position(4) = app.RAW_SIZE(2);
                else
                    % show anomaly table
                    app.GridLayout_Main.ColumnWidth = {320, '1x'};
                    app.CellSegmentorUIFigure.Position(3) = app.EXPAND_SIZE(1);
                    app.CellSegmentorUIFigure.Position(4) = app.EXPAND_SIZE(2);
                end

                app.caller.PushInfo("CellSegmentor", "Menu_AnomalyReportSelected");
            catch ME
                app.caller.PushInfo("CellSegmentor", "Menu_AnomalyReportSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_ImportMask
        function Menu_ImportMaskSelected(app, event)
            try
                [name, path] = uigetfile({'*.mask', ...
                    [app.langmap.REG3D_MASK.char(), '(*.mask)']},...
                    app.langmap.READ_MASK_FILE.char());

                % user cancel
                if isnumeric(path) && path == 0, return; end

                file = fullfile(path, name);

                % new ncbot
                ires = struct("Center",[], "Radius",[], "Nid",[]);
                app.ncbot = NuclearCtrlBot(ires, app.vol_opt, "jet");

                app.ncbot.SetHandle(app.caller);
                app.ncbot.LoadMaskFrom(file);

                ns = PostProcessAfterBotReady(app);

                app.caller.SendMessage(["OBJECTS_LOAD", ns]);

                app.caller.PushInfo("CellSegmentor", "Menu_ImportMaskSelected");
            catch ME
                app.caller.PushInfo("CellSegmentor", "Menu_ImportMaskSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_ExportMask
        function Menu_ExportMaskSelected(app, event)
            try
                if ~isempty(app.ncbot) && isvalid(app.ncbot) ...
                        && app.ncbot.IsMaskExist
                    [name, path] = uiputfile({'*.mask', ...
                        [app.langmap.REG3D_MASK.char(), '(*.mask)']},...
                        app.langmap.SAVE_MASK_FILE.char());

                    % user cancel
                    if isnumeric(path) && path == 0, return; end

                    file = fullfile(path, name);

                    app.ncbot.SaveMaskTo(file);

                    app.caller.SendMessage(["MASK_SAVED", ""]);
                else
                    switch app.message_level
                        case constdef.MESSAGE_INFO
                            uialert(app.CellSegmentorUIFigure, ...
                                app.langmap.MASK_EXPORT_CANCEL, ...
                                app.langmap.INFORMATION, "Icon","info");
                        otherwise
                    end
                end

                app.caller.PushInfo("CellSegmentor", "Menu_ExportMaskSelected");
            catch ME
                app.caller.PushInfo("CellSegmentor", "Menu_ExportMaskSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Selection changed function: UITable_Anomaly
        function UITable_AnomalySelectionChanged(app, event)
            try
                compidx = CellSegmentor.GetIDVec(app.UITable_Anomaly.Data, ...
                    app.UITable_Anomaly.Selection);

                app.UpdateHighlights(compidx);

                stats = app.UpdateObjInfo(compidx);

                switch app.DropDown_View.Value
                    case "EXCEPTION"    % modify disp range only if in 'exception object case'
                        r = CellSegmentor.GetDispRangebyCell(app.vol_opt, stats, app.rfold);
                        if ~isempty(r)
                            app.ncbot.DispRange = r;
                            app.ncbot.DispFlag = "on";
                        else
                            % switch to "None"
                            app.ncbot.DispRange = [];
                            app.ncbot.DispFlag = "off";
                        end
                    otherwise
                        % skip
                end
            catch ME
                app.caller.PushInfo("CellSegmentor", "UITable_AnomalySelectionChanged", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Value changed function: DropDown_View
        function DropDown_ViewValueChanged(app, event)
            try
                value = app.DropDown_View.Value;

                switch value
                    case "GLOBAL"
                        app.ncbot.DispRange = [];
                        app.ncbot.DispFlag = "on";
                    case "LOCAL"
                        % require ROI from caller
                        r = app.caller.GetCurrentDisplayPosition();
                        if ~isempty(r)
                            app.ncbot.DispRange = ceil(r);
                            app.ncbot.DispFlag = "on";
                        else
                            % reset as GLOBAL
                            app.DropDown_View.Value = "GLOBAL";
                            app.ncbot.DispRange = [];
                            app.ncbot.DispFlag = "on";

                            % ERROR: display at anytime
                            uialert(app.CellSegmentorUIFigure,...
                                app.langmap.CONFIRM_ROI_RANGE,...
                                app.langmap.ERROR, "Icon","error", ...
                                "Modal",true);
                            return;
                        end
                        app.caller.ResetCurrentDisplayPosition();
                    case "EXCEPTION"
                        % only display selected 'exception' ROI with 
                        % a constant border
                        if ~isempty(app.UITable_Anomaly.Selection)
                            compidx = CellSegmentor.GetIDVec(app.UITable_Anomaly.Data, ...
                                app.UITable_Anomaly.Selection);
                            stats = app.UpdateObjInfo(compidx);
                            r = CellSegmentor.GetDispRangebyCell(app.vol_opt, stats, app.rfold);
                            if ~isempty(r)
                                app.ncbot.DispRange = r;
                                app.ncbot.DispFlag = "on";
                            else
                                % switch to "None"
                                app.ncbot.DispRange = [];
                                app.ncbot.DispFlag = "off";
                            end
                        else
                            app.ncbot.DispRange = [];
                            app.ncbot.DispFlag = "off";
                        end
                    case "NONE"
                        app.ncbot.DispRange = [];
                        app.ncbot.DispFlag = "off";
                    otherwise
                end

                % refresh ROIs display
                app.ncbot.Clear();

                zidx = app.caller.GetCurrentSliceIndex();

                app.ncbot.Display(zidx);
            catch ME
                app.caller.PushInfo("CellSegmentor", "DropDown_ViewValueChanged", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Menu selected function: Menu_RadiusPlus
        function Menu_RadiusPlusSelected(app, event)
            hmax = floor(min(app.vol_opt.width, app.vol_opt.height)/2);
            
            if app.CELL_RADIUS_SIZE + (app.rfold+1)*app.CELL_RADIUS_INCREMENT ...
                    < hmax
                app.rfold = app.rfold + 1;
            end

            switch app.DropDown_View.Value
                case "EXCEPTION"    % modify disp range only if in 'exception object case'
                    if ~isempty(app.UITable_Anomaly.Selection)
                        compidx = CellSegmentor.GetIDVec(app.UITable_Anomaly.Data, ...
                            app.UITable_Anomaly.Selection);
                        stats = app.UpdateObjInfo(compidx);
                        r = CellSegmentor.GetDispRangebyCell(app.vol_opt, stats, app.rfold);
                        if ~isempty(r)
                            app.ncbot.DispRange = r;
                            app.ncbot.DispFlag = "on";
                        else
                            % switch to "None"
                            app.ncbot.DispRange = [];
                            app.ncbot.DispFlag = "off";
                        end
                    else
                        app.ncbot.DispRange = [];
                        app.ncbot.DispFlag = "off";
                    end

                    % refresh ROIs display
                    app.ncbot.Clear();
                    zidx = app.caller.GetCurrentSliceIndex();
                    app.ncbot.Display(zidx);
                otherwise
                    % skip
            end
        end

        % Menu selected function: Menu_RadiusMinus
        function Menu_RadiusMinusSelected(app, event)
            if app.CELL_RADIUS_SIZE + (app.rfold+1)*app.CELL_RADIUS_INCREMENT ...
                    > 0
                app.rfold = app.rfold - 1;
            end

            switch app.DropDown_View.Value
                case "EXCEPTION"    % modify disp range only if in 'exception object case'
                    if ~isempty(app.UITable_Anomaly.Selection)
                        compidx = CellSegmentor.GetIDVec(app.UITable_Anomaly.Data, ...
                            app.UITable_Anomaly.Selection);
                        stats = app.UpdateObjInfo(compidx);
                        r = CellSegmentor.GetDispRangebyCell(app.vol_opt, stats, app.rfold);
                        if ~isempty(r)
                            app.ncbot.DispRange = r;
                            app.ncbot.DispFlag = "on";
                        else
                            % switch to "None"
                            app.ncbot.DispRange = [];
                            app.ncbot.DispFlag = "off";
                        end
                    else
                        app.ncbot.DispRange = [];
                        app.ncbot.DispFlag = "off";
                    end

                    % refresh ROIs display
                    app.ncbot.Clear();
                    zidx = app.caller.GetCurrentSliceIndex();
                    app.ncbot.Display(zidx);
                otherwise
                    % skip
            end
        end

        % Menu selected function: Menu_AutoFocus
        function Menu_AutoFocusSelected(app, event)
            % This function allows the player focuses on "Exception Region"
            % and zoom in for easy operations
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CellSegmentorUIFigure = uifigure('Visible', 'off');
            app.CellSegmentorUIFigure.Name = 'Cell Segmentor';
            app.CellSegmentorUIFigure.Icon = 'neuron.png';
            app.CellSegmentorUIFigure.CloseRequestFcn = createCallbackFcn(app, @CellSegmentorUIFigureCloseRequest, true);
            app.Menu_File = uimenu(app.CellSegmentorUIFigure);
            app.Menu_File.Text = '文件';
            app.Menu_ExportMask = uimenu(app.Menu_File);
            app.Menu_ExportMask.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExportMaskSelected, true);
            app.Menu_ExportMask.Text = '导出蒙版';
            app.Menu_ImportMask = uimenu(app.Menu_File);
            app.Menu_ImportMask.MenuSelectedFcn = createCallbackFcn(app, @Menu_ImportMaskSelected, true);
            app.Menu_ImportMask.Text = '导入蒙版';
            app.Menu_Option = uimenu(app.CellSegmentorUIFigure);
            app.Menu_Option.Text = '选项';
            app.Menu_TrainingSamples = uimenu(app.Menu_Option);
            app.Menu_TrainingSamples.MenuSelectedFcn = createCallbackFcn(app, @Menu_TrainingSamplesSelected, true);
            app.Menu_TrainingSamples.Separator = 'on';
            app.Menu_TrainingSamples.Text = '训练样本';
            app.Menu_AnomalyReport = uimenu(app.Menu_Option);
            app.Menu_AnomalyReport.MenuSelectedFcn = createCallbackFcn(app, @Menu_AnomalyReportSelected, true);
            app.Menu_AnomalyReport.Text = '异常报告';
            app.Menu_AnomalyRegion = uimenu(app.Menu_Option);
            app.Menu_AnomalyRegion.Text = '异常域';
            app.Menu_RadiusPlus = uimenu(app.Menu_AnomalyRegion);
            app.Menu_RadiusPlus.MenuSelectedFcn = createCallbackFcn(app, @Menu_RadiusPlusSelected, true);
            app.Menu_RadiusPlus.Text = '增加 (+5)';
            app.Menu_RadiusMinus = uimenu(app.Menu_AnomalyRegion);
            app.Menu_RadiusMinus.MenuSelectedFcn = createCallbackFcn(app, @Menu_RadiusMinusSelected, true);
            app.Menu_RadiusMinus.Text = '减小 (-5)';
            app.Menu_AutoFocus = uimenu(app.Menu_AnomalyRegion);
            app.Menu_AutoFocus.MenuSelectedFcn = createCallbackFcn(app, @Menu_AutoFocusSelected, true);
            app.Menu_AutoFocus.Text = '自动聚焦';
            app.Menu_Help = uimenu(app.CellSegmentorUIFigure);
            app.Menu_Help.Text = '帮助';
            app.Menu_About = uimenu(app.Menu_Help);
            app.Menu_About.MenuSelectedFcn = createCallbackFcn(app, @Menu_AboutSelected, true);
            app.Menu_About.Text = '关于';
            app.GridLayout_Main = uigridlayout(app.CellSegmentorUIFigure);
            app.GridLayout = uigridlayout(app.GridLayout_Main);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab_Morphology = uitab(app.TabGroup);
            app.Tab_Morphology.Title = '形态学';
            app.GridLayout2 = uigridlayout(app.Tab_Morphology);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '半径均值';
            app.Spinner_MeanRadius_Morphology = uispinner(app.GridLayout2);
            app.Spinner_MeanRadius_Morphology.Limits = [1 4];
            app.Spinner_MeanRadius_Morphology.ValueChangedFcn = createCallbackFcn(app, @Spinner_MeanRadius_MorphologyValueChanged, true);
            app.Spinner_MeanRadius_Morphology.Value = 2.2;
            app.Spinner_2Label = uilabel(app.GridLayout2);
            app.Spinner_2Label.Text = '半径标准差';
            app.Spinner_StdRadius_Morphology = uispinner(app.GridLayout2);
            app.Spinner_StdRadius_Morphology.Limits = [0 1];
            app.Spinner_StdRadius_Morphology.ValueChangedFcn = createCallbackFcn(app, @Spinner_StdRadius_MorphologyValueChanged, true);
            app.Spinner_StdRadius_Morphology.Value = 0.4;
            app.mumLabel = uilabel(app.GridLayout2);
            app.mumLabel.Interpreter = 'tex';
            app.mumLabel.Text = '\mum';
            app.mumLabel_2 = uilabel(app.GridLayout2);
            app.mumLabel_2.Interpreter = 'tex';
            app.mumLabel_2.Text = '\mum';
            app.CheckBox_AutoThreshold = uicheckbox(app.GridLayout2);
            app.CheckBox_AutoThreshold.ValueChangedFcn = createCallbackFcn(app, @CheckBox_AutoThresholdValueChanged, true);
            app.CheckBox_AutoThreshold.Text = '自动';
            app.CheckBox_AutoThreshold.Value = true;
            app.Label_7 = uilabel(app.GridLayout2);
            app.Label_7.Text = '强度阈值';
            app.EditField_IntensityThreshold = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_IntensityThreshold.Limits = [0 255];
            app.EditField_IntensityThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_IntensityThresholdValueChanged, true);
            app.EditField_IntensityThreshold.Enable = 'off';
            app.EditField_IntensityThreshold.Value = 50;
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = '球度阈值';
            app.Spinner_SphericityThreshold = uispinner(app.GridLayout2);
            app.Spinner_SphericityThreshold.Step = 0.1;
            app.Spinner_SphericityThreshold.Limits = [0 1];
            app.Spinner_SphericityThreshold.ValueDisplayFormat = '%.2f';
            app.Spinner_SphericityThreshold.ValueChangedFcn = createCallbackFcn(app, @Spinner_SphericityThresholdValueChanged, true);
            app.Spinner_SphericityThreshold.Value = 0.5;
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '离群方向';
            app.DropDown_Outlier = uidropdown(app.GridLayout2);
            app.DropDown_Outlier.Items = {'双侧', '左侧', '右侧'};
            app.DropDown_Outlier.ItemsData = {'both', 'left', 'right'};
            app.DropDown_Outlier.ValueChangedFcn = createCallbackFcn(app, @DropDown_OutlierValueChanged, true);
            app.DropDown_Outlier.Value = 'both';
            app.Label_13 = uilabel(app.GridLayout2);
            app.Label_13.Text = '分割算法';
            app.DropDown_SegmentAlgorithm = uidropdown(app.GridLayout2);
            app.DropDown_SegmentAlgorithm.Items = {'分水岭', '分水岭(旧)'};
            app.DropDown_SegmentAlgorithm.ItemsData = {'watershed', 'watershed_old'};
            app.DropDown_SegmentAlgorithm.ValueChangedFcn = createCallbackFcn(app, @DropDown_SegmentAlgorithmValueChanged, true);
            app.DropDown_SegmentAlgorithm.Value = 'watershed';
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = '目标数量';
            app.EditField_ObjectNumber = uieditfield(app.GridLayout2, 'text');
            app.EditField_ObjectNumber.CharacterLimits = [0 1023];
            app.EditField_ObjectNumber.ValueChangedFcn = createCallbackFcn(app, @EditField_ObjectNumberValueChanged, true);
            app.EditField_ObjectNumber.Placeholder = '#R1,#R2';
            app.Image_OutlierDirectionTooltip = uiimage(app.GridLayout2);
            app.Image_OutlierDirectionTooltip.ImageSource = 'info.png';
            app.Tab_MachineLearning = uitab(app.TabGroup);
            app.Tab_MachineLearning.Title = '机器学习';
            app.GridLayout4 = uigridlayout(app.Tab_MachineLearning);
            app.mumLabel_3 = uilabel(app.GridLayout4);
            app.mumLabel_3.Interpreter = 'tex';
            app.mumLabel_3.Text = '\mum';
            app.mumLabel_4 = uilabel(app.GridLayout4);
            app.mumLabel_4.Interpreter = 'tex';
            app.mumLabel_4.Text = '\mum';
            app.Spinner_5Label = uilabel(app.GridLayout4);
            app.Spinner_5Label.Text = '半径均值';
            app.Spinner_MeanRadius_Cellpose = uispinner(app.GridLayout4);
            app.Spinner_MeanRadius_Cellpose.Limits = [1 4];
            app.Spinner_MeanRadius_Cellpose.ValueChangedFcn = createCallbackFcn(app, @Spinner_MeanRadius_CellposeValueChanged, true);
            app.Spinner_MeanRadius_Cellpose.Value = 2.2;
            app.Label_14 = uilabel(app.GridLayout4);
            app.Label_14.Text = '半径下限';
            app.Spinner_MinRadius_Cellpose = uispinner(app.GridLayout4);
            app.Spinner_MinRadius_Cellpose.Step = 0.1;
            app.Spinner_MinRadius_Cellpose.Limits = [1 4];
            app.Spinner_MinRadius_Cellpose.ValueChangedFcn = createCallbackFcn(app, @Spinner_MinRadius_CellposeValueChanged, true);
            app.Spinner_MinRadius_Cellpose.Value = 1.4;
            app.Label_10 = uilabel(app.GridLayout4);
            app.Label_10.Text = '阈值';
            app.Spinner_Threshold = uispinner(app.GridLayout4);
            app.Spinner_Threshold.Limits = [-6 6];
            app.Spinner_Threshold.ValueChangedFcn = createCallbackFcn(app, @Spinner_ThresholdValueChanged, true);
            app.Spinner_Threshold.Value = -3;
            app.CheckBox_Enhance = uicheckbox(app.GridLayout4);
            app.CheckBox_Enhance.ValueChangedFcn = createCallbackFcn(app, @CheckBox_EnhanceValueChanged, true);
            app.CheckBox_Enhance.Text = '强化';
            app.Label_9 = uilabel(app.GridLayout4);
            app.Label_9.Text = '模型';
            app.DropDown_Model = uidropdown(app.GridLayout4);
            app.DropDown_Model.Items = {'cyto', 'CP', 'nuclei', 'KC'};
            app.DropDown_Model.ItemsData = {'cyto', 'CP', 'nuclei', 'KC'};
            app.DropDown_Model.ValueChangedFcn = createCallbackFcn(app, @DropDown_ModelValueChanged, true);
            app.DropDown_Model.Value = 'cyto';
            app.DropDown_3Label = uilabel(app.GridLayout4);
            app.DropDown_3Label.Text = '日龄';
            app.DropDown_Stage = uidropdown(app.GridLayout4);
            app.DropDown_Stage.Items = {'L1', 'L2', 'L3'};
            app.DropDown_Stage.ItemsData = {'L1', 'L2', 'L3'};
            app.DropDown_Stage.ValueChangedFcn = createCallbackFcn(app, @DropDown_StageValueChanged, true);
            app.DropDown_Stage.Value = 'L1';
            app.Label_11 = uilabel(app.GridLayout4);
            app.Label_11.Text = '批处理大小';
            app.Spinner_BatchSize = uispinner(app.GridLayout4);
            app.Spinner_BatchSize.Limits = [1 8];
            app.Spinner_BatchSize.ValueChangedFcn = createCallbackFcn(app, @Spinner_BatchSizeValueChanged, true);
            app.Spinner_BatchSize.Value = 4;
            app.Label_12 = uilabel(app.GridLayout4);
            app.Label_12.Text = '学习率';
            app.EditField_LearnRate = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_LearnRate.Limits = [0 1];
            app.EditField_LearnRate.ValueChangedFcn = createCallbackFcn(app, @EditField_LearnRateValueChanged, true);
            app.EditField_LearnRate.Value = 0.2;
            app.Label_15 = uilabel(app.GridLayout4);
            app.Label_15.Text = '权重衰减';
            app.EditField_WeightDecay = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_WeightDecay.Limits = [0 1];
            app.EditField_WeightDecay.ValueChangedFcn = createCallbackFcn(app, @EditField_WeightDecayValueChanged, true);
            app.EditField_WeightDecay.Value = 1e-05;
            app.Label_16 = uilabel(app.GridLayout4);
            app.Label_16.Text = '最大训练轮次';
            app.EditField_MaxEpoch = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_MaxEpoch.Limits = [1 Inf];
            app.EditField_MaxEpoch.ValueDisplayFormat = '%.0f';
            app.EditField_MaxEpoch.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxEpochValueChanged, true);
            app.EditField_MaxEpoch.Value = 500;
            app.Image_ThresholdTooltip = uiimage(app.GridLayout4);
            app.Image_ThresholdTooltip.ImageSource = 'info.png';
            app.Image_BatchSizeTooltip = uiimage(app.GridLayout4);
            app.Image_BatchSizeTooltip.ImageSource = 'info.png';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Button_Train = uibutton(app.GridLayout5, 'push');
            app.Button_Train.ButtonPushedFcn = createCallbackFcn(app, @Button_TrainPushed, true);
            app.Button_Train.Icon = 'training.png';
            app.Button_Train.Text = '训练';
            app.Button_Segment = uibutton(app.GridLayout5, 'push');
            app.Button_Segment.ButtonPushedFcn = createCallbackFcn(app, @Button_SegmentPushed, true);
            app.Button_Segment.Icon = 'segmentation.png';
            app.Button_Segment.Text = '分割';
            app.DropDown_View = uidropdown(app.GridLayout5);
            app.DropDown_View.Items = {'全局目标', '局部目标', '异常目标', '无'};
            app.DropDown_View.ItemsData = {'GLOBAL', 'LOCAL', 'EXCEPTION', 'NONE'};
            app.DropDown_View.ValueChangedFcn = createCallbackFcn(app, @DropDown_ViewValueChanged, true);
            app.DropDown_View.Value = 'GLOBAL';
            app.Panel_CommonParameters = uipanel(app.GridLayout);
            app.Panel_CommonParameters.Title = '共有参数';
            app.GridLayout3 = uigridlayout(app.Panel_CommonParameters);
            app.Label_6 = uilabel(app.GridLayout3);
            app.Label_6.Text = '相机背景';
            app.EditField_CameraBackground = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_CameraBackground.Limits = [0 65535];
            app.EditField_CameraBackground.ValueChangedFcn = createCallbackFcn(app, @EditField_CameraBackgroundValueChanged, true);
            app.EditField_CameraBackground.Value = 100;
            app.EditField_3Label = uilabel(app.GridLayout3);
            app.EditField_3Label.Text = '伽马';
            app.EditField_Gamma = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_Gamma.Limits = [0 2];
            app.EditField_Gamma.ValueDisplayFormat = '%.2f';
            app.EditField_Gamma.ValueChangedFcn = createCallbackFcn(app, @EditField_GammaValueChanged, true);
            app.EditField_Gamma.Value = 1;
            app.Label_5 = uilabel(app.GridLayout3);
            app.Label_5.Text = '子区域数量';
            app.Spinner_SubregionNumber = uispinner(app.GridLayout3);
            app.Spinner_SubregionNumber.Limits = [1 255];
            app.Spinner_SubregionNumber.ValueDisplayFormat = '%.0f';
            app.Spinner_SubregionNumber.ValueChangedFcn = createCallbackFcn(app, @Spinner_SubregionNumberValueChanged, true);
            app.Spinner_SubregionNumber.Value = 2;
            app.CheckBox_Split = uicheckbox(app.GridLayout3);
            app.CheckBox_Split.ValueChangedFcn = createCallbackFcn(app, @CheckBox_SplitValueChanged, true);
            app.CheckBox_Split.Text = '划定';
            app.GridLayout6 = uigridlayout(app.GridLayout_Main);
            app.UITable_Anomaly = uitable(app.GridLayout6);
            app.UITable_Anomaly.ColumnName = {'ID'; 'Comments'};
            app.UITable_Anomaly.RowName = {};
            app.UITable_Anomaly.SelectionType = 'row';
            app.UITable_Anomaly.SelectionChangedFcn = createCallbackFcn(app, @UITable_AnomalySelectionChanged, true);
            app.UITable_Anomaly.Multiselect = 'off';
            app.UITable_Anomaly.Enable = 'off';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.Label_Anomaly_Error = uilabel(app.GridLayout7);
            app.Label_Anomaly_Error.Text = 'Error(0)';
            app.Label_Anomaly_Warning = uilabel(app.GridLayout7);
            app.Label_Anomaly_Warning.Text = 'Warning(0)';
            app.Label_Anomaly_Info = uilabel(app.GridLayout7);
            app.Label_Anomaly_Info.Text = 'Info(0)';
            app.UITable_ObjectInfo = uitable(app.GridLayout6);
            app.UITable_ObjectInfo.ColumnName = {'质心'; '等效半径'};
            app.UITable_ObjectInfo.RowName = {};
            app.UITable_ObjectInfo.Enable = 'off';
            % Show the figure after all components are created
            app.CellSegmentorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellSegmentor(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CellSegmentorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CellSegmentorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CellSegmentorUIFigure)
        end
    end
end