classdef ChannelCorrector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelCorrectorUIFigure       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        GridLayout7                    matlab.ui.container.GridLayout
        Button_Bottom                  matlab.ui.control.Button
        GridLayout6                    matlab.ui.container.GridLayout
        Button_OK                      matlab.ui.control.Button
        Button_Right                   matlab.ui.control.Button
        Button_Left                    matlab.ui.control.Button
        GridLayout5                    matlab.ui.container.GridLayout
        Button_Top                     matlab.ui.control.Button
        GridLayout3                    matlab.ui.container.GridLayout
        EditField_DA                   matlab.ui.control.NumericEditField
        DeltathetaEditFieldLabel       matlab.ui.control.Label
        EditField_DY                   matlab.ui.control.NumericEditField
        DeltaYEditFieldLabel           matlab.ui.control.Label
        EditField_DX                   matlab.ui.control.NumericEditField
        DeltaXEditFieldLabel           matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        Button_ClockwiseRotate         matlab.ui.control.Button
        Button_CounterClockwiseRotate  matlab.ui.control.Button
    end

    properties (Constant)
        VER = "1.0.0"
    end
    
    properties (Constant, Hidden)
        DX = 0.2;               % usual as um
        DY = 0.2;               % usual as um
        DA = 0.25;              % degree
        DX_LIMIT = [-64, 64];   
        DY_LIMIT = [-64, 64];
        DA_LIMIT = [-90, 90];
    end

    properties (Access = private)
        caller;         % the parent app
        shift;          % the shift array, [dx, dy, da]
        langmap;        % mapping for language
    end

    methods(Access=?Register)

        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr >= 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout7.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                case "DARK"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout7.BackgroundColor = constdef.GRAYSCALE_DARK;
                otherwise
            end
        end

        function Refresh(app)
        end

        function RefreshTooltips(app)
        end

        function Close(app)
            delete(app);

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.shift = [0, 0, 0];
            app.caller = mainapp;
            
            [~, app.langmap] = app.caller.GetLangsInfo();

            % send the initialized signal
            app.caller.SetChannelAlignmentView([app.shift]);

            RefreshPanelStyle(app);

            RefreshTooltips(app);
        end

        % Button pushed function: Button_Top
        function Button_TopPushed(app, event)
            if app.shift(2) >= app.DY_LIMIT(1) +app.DY
                app.Button_Top.BackgroundColor = [0, 1, 0];
                app.shift(2) = app.shift(2) - app.DY;
                app.EditField_DY.Value = app.shift(2);
            else
                % self lock
                app.Button_Bottom.Enable = "off";
            end

            if app.shift(2) <= app.DY_LIMIT(2)-app.DY ...
                    && app.Button_Top.Enable == "off"
                app.Button_Top.Enable = "on";
            end

            % update view
            app.caller.SetChannelAlignmentView(app.shift);

            app.Button_Top.BackgroundColor = [0.96, 0.96, 0.96];
        end

        % Button pushed function: Button_Left
        function Button_LeftPushed(app, event)
            if app.shift(1) >= app.DX_LIMIT(1) + app.DX
                app.Button_Left.BackgroundColor = [0, 1, 0];
                app.shift(1) = app.shift(1) - app.DX;
                app.EditField_DX.Value = app.shift(1);
            else
                % self lock
                app.Button_Left.Enable = "off";
            end

            if app.shift(1) <= app.DX_LIMIT(2)-app.DX ...
                    && app.Button_Right.Enable == "off"
                app.Button_Right.Enable = "on";
            end

            % update view
            app.caller.SetChannelAlignmentView(app.shift);

            app.Button_Left.BackgroundColor = [0.96, 0.96, 0.96];
        end

        % Button pushed function: Button_Bottom
        function Button_BottomPushed(app, event)
            if app.shift(2) <= app.DY_LIMIT(2) - app.DY
                app.Button_Bottom.BackgroundColor = [0, 1, 0];
                app.shift(2) = app.shift(2) + app.DY;
                app.EditField_DY.Value = app.shift(2);
            else
                % self lock
                app.Button_Top.Enable = "off";
            end

            if app.shift(2) >= app.DY ...
                    && app.Button_Bottom.Enable == "off"
                app.Button_Bottom.Enable = "on";
            end

            % update view
            app.caller.SetChannelAlignmentView(app.shift);

            app.Button_Bottom.BackgroundColor = [0.96, 0.96, 0.96];
        end

        % Button pushed function: Button_Right
        function Button_RightPushed(app, event)
            if app.shift(1) <= app.DX_LIMIT(2) - app.DX
                app.Button_Right.BackgroundColor = [0, 1, 0];
                app.shift(1) = app.shift(1) + app.DX;
                app.EditField_DX.Value = app.shift(1);
            else
                % self lock
                app.Button_Right.Enable = "off";
            end

            if app.shift(1) >= app.DX ...
                    && app.Button_Left.Enable == "off"
                app.Button_Left.Enable = "on";
            end

            % update view
            app.caller.SetChannelAlignmentView(app.shift);

            app.Button_Right.BackgroundColor = [0.96, 0.96, 0.96];
        end

        % Button pushed function: Button_CounterClockwiseRotate
        function Button_CounterClockwiseRotatePushed(app, event)
            if app.shift(3) >= app.DA_LIMIT(1) + app.DA
                app.Button_CounterClockwiseRotate.BackgroundColor = [0, 1, 0];
                app.shift(3) = app.shift(3) - app.DA;
                app.EditField_DA.Value = app.shift(3);
            else
                % self lock
                app.Button_CounterClockwiseRotate.Enable = "off";
            end

            if app.shift(3) <= app.DA_LIMIT(2)-app.DA ...
                    && app.Button_ClockwiseRotate.Enable == "off"
                app.Button_ClockwiseRotate.Enable = "on";
            end

            % update view
            app.caller.SetChannelAlignmentView(app.shift);

            app.Button_CounterClockwiseRotate.BackgroundColor = [0.96, 0.96, 0.96];
        end

        % Button pushed function: Button_ClockwiseRotate
        function Button_ClockwiseRotatePushed(app, event)
            if app.shift(3) <= app.DA_LIMIT(2) - app.DA
                app.Button_ClockwiseRotate.BackgroundColor = [0, 1, 0];
                app.shift(3) = app.shift(3) + app.DA;
                app.EditField_DA.Value = app.shift(3);
            else
                % self lock
                app.Button_ClockwiseRotate.Enable = "off";
            end

            if app.shift(3) >= app.DA ...
                    && app.Button_CounterClockwiseRotate.Enable == "off"
                app.Button_CounterClockwiseRotate.Enable = "on";
            end

            % update view
            app.caller.SetChannelAlignmentView(app.shift);
            
            app.Button_ClockwiseRotate.BackgroundColor = [0.96, 0.96, 0.96];
        end

        % Button pushed function: Button_OK
        function Button_OKPushed(app, event)
            % push the OK for channel align confirmation and close this
            app.Button_OK.BackgroundColor = [0, 1, 1];
            app.caller.SetChannelAlignment(app.shift);

            Close(app);
        end

        % Close request function: ChannelCorrectorUIFigure
        function ChannelCorrectorUIFigureCloseRequest(app, event)
            
            Close(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelCorrectorUIFigure = uifigure('Visible', 'off');
            app.ChannelCorrectorUIFigure.Name = 'Channel Corrector';
            app.ChannelCorrectorUIFigure.Icon = 'channel_alignment.png';
            app.ChannelCorrectorUIFigure.CloseRequestFcn = createCallbackFcn(app, @ChannelCorrectorUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ChannelCorrectorUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Button_CounterClockwiseRotate = uibutton(app.GridLayout2, 'push');
            app.Button_CounterClockwiseRotate.ButtonPushedFcn = createCallbackFcn(app, @Button_CounterClockwiseRotatePushed, true);
            app.Button_CounterClockwiseRotate.Icon = 'left_rot.png';
            app.Button_CounterClockwiseRotate.Text = '';
            app.Button_ClockwiseRotate = uibutton(app.GridLayout2, 'push');
            app.Button_ClockwiseRotate.ButtonPushedFcn = createCallbackFcn(app, @Button_ClockwiseRotatePushed, true);
            app.Button_ClockwiseRotate.Icon = 'right_rot.png';
            app.Button_ClockwiseRotate.Text = '';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.DeltaXEditFieldLabel = uilabel(app.GridLayout3);
            app.DeltaXEditFieldLabel.Interpreter = 'tex';
            app.DeltaXEditFieldLabel.Text = '\DeltaX';
            app.EditField_DX = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_DX.Limits = [-64 64];
            app.EditField_DX.ValueDisplayFormat = '%.1f';
            app.EditField_DX.Editable = 'off';
            app.DeltaYEditFieldLabel = uilabel(app.GridLayout3);
            app.DeltaYEditFieldLabel.Interpreter = 'tex';
            app.DeltaYEditFieldLabel.Text = '\DeltaY';
            app.EditField_DY = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_DY.Limits = [-64 64];
            app.EditField_DY.ValueDisplayFormat = '%.1f';
            app.EditField_DY.Editable = 'off';
            app.DeltathetaEditFieldLabel = uilabel(app.GridLayout3);
            app.DeltathetaEditFieldLabel.Interpreter = 'tex';
            app.DeltathetaEditFieldLabel.Text = '\Delta\theta';
            app.EditField_DA = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_DA.Limits = [-90 90];
            app.EditField_DA.ValueDisplayFormat = '%.1f';
            app.EditField_DA.Editable = 'off';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.Button_Top = uibutton(app.GridLayout5, 'push');
            app.Button_Top.ButtonPushedFcn = createCallbackFcn(app, @Button_TopPushed, true);
            app.Button_Top.Icon = 'up_arrow.png';
            app.Button_Top.Text = '';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.Button_Left = uibutton(app.GridLayout6, 'push');
            app.Button_Left.ButtonPushedFcn = createCallbackFcn(app, @Button_LeftPushed, true);
            app.Button_Left.Icon = 'left_arrow.png';
            app.Button_Left.Text = '';
            app.Button_Right = uibutton(app.GridLayout6, 'push');
            app.Button_Right.ButtonPushedFcn = createCallbackFcn(app, @Button_RightPushed, true);
            app.Button_Right.Icon = 'right_arrow.png';
            app.Button_Right.Text = '';
            app.Button_OK = uibutton(app.GridLayout6, 'push');
            app.Button_OK.ButtonPushedFcn = createCallbackFcn(app, @Button_OKPushed, true);
            app.Button_OK.Icon = 'ok.png';
            app.Button_OK.Text = '';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.Button_Bottom = uibutton(app.GridLayout7, 'push');
            app.Button_Bottom.ButtonPushedFcn = createCallbackFcn(app, @Button_BottomPushed, true);
            app.Button_Bottom.Icon = 'down_arrow.png';
            app.Button_Bottom.Text = '';
            % Show the figure after all components are created
            app.ChannelCorrectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChannelCorrector(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChannelCorrectorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ChannelCorrectorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelCorrectorUIFigure)
        end
    end
end