classdef Evaluator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RegistrationEvaluatorUIFigure  matlab.ui.Figure
        Menu_File                      matlab.ui.container.Menu
        Menu_Export                    matlab.ui.container.Menu
        GridLayout_Main                matlab.ui.container.GridLayout
        GridLayout                     matlab.ui.container.GridLayout
        Button_RefreshRegEvaluate      matlab.ui.control.Button
        UIAxes_Metric                  matlab.ui.control.UIAxes
        TabGroup_GL                    matlab.ui.container.TabGroup
        Tab_Global                     matlab.ui.container.Tab
        TabGroup_SRS                   matlab.ui.container.TabGroup
        Tab_Shift                      matlab.ui.container.Tab
        Tab_Rotate                     matlab.ui.container.Tab
        Tab_Local                      matlab.ui.container.Tab
        GridLayout_Local               matlab.ui.container.GridLayout
        GridLayout_LocalInner          matlab.ui.container.GridLayout
        Panel_Local_Control            matlab.ui.container.Panel
        DropDown_Amap                  matlab.ui.control.DropDown
        DropDownLabel                  matlab.ui.control.Label
        CheckBox_Movie                 matlab.ui.control.CheckBox
        CheckBox_DF                    matlab.ui.control.CheckBox
        DropDown_ViewProjection        matlab.ui.control.DropDown
        Label_5                        matlab.ui.control.Label
        DropDown_Colormap              matlab.ui.control.DropDown
        Label_4                        matlab.ui.control.Label
        DropDown_RendererAcc           matlab.ui.control.DropDown
        Label_2                        matlab.ui.control.Label
        UIAxes_Alphamap                matlab.ui.control.UIAxes
        UIAxes_CS                      matlab.ui.control.UIAxes
        Slider_Frame                   matlab.ui.control.Slider
    end

    properties (Constant)
        VER = "1.0.0"
    end

    properties (Constant, Hidden)
        DIM_NAME = ["X","Y","Z"];
        LINE_SPEC = {'ro-','go-','bo-'};
        NEW_POINT_DIST_TH = 1e-2;
        FILENAME_SHIFT = "xyz_shift";
        FILENAME_ROTATE = "xyz_rotate";
        FILENAME_EVALUATE_RESULT = "Evaluation_Result";
        BITMAP = [".jpg",".png",".tif",".pdf"];
        VECMAP = [".emf",".eps"];
    end
    
    properties (Access = private)
        caller;             % the parent app

        shift;              % the rigid shift
        rotation;           % the rigid rotation
        nonrigid_tform;     % the nonrigid transformation

        rigid_mark;         % the logical vector mark which is rigid tform

        shift_xyz;          % the uiaxes handle cell, 1*3 for dx, dy, dz
        rotate_xyz;         % the uiaxes handle cell, 1*3 for ax, ay, az

        mov_r;              % the raw(source) movie, marker channel
        mov_a;              % the aligned movie, marker channel
        render_mov;         % the rendering volume(after downsampling)

        img_info;           % the image info, table
        render_opt;         % the rendering options, struct
        render_isp;         % renderer intelligent speed up

        frame_cur;          % the current frame

        amap_line;          % the handle of turing curve
        amap_sltp;          % the select point position (x order)
        amap_sltp_active;   % flag for recording the select point activity

        vf_obj;             % the vector field object
        ivs_obj;            % the image volume surface object for {x,y,z} surfaces
        cbar_obj;           % the colorbar object

        langmap;            % mapping for language
    end

    properties (Access = private, Hidden)
        AMAP = struct("linear", [0,     0; ...
                                 1,     1], ...
                      "mri",    [0,     0; ...
                                 0.02,  0; ...
                                 0.04,  0.15; ...
                                 0.12,  0.3; ...
                                 0.25,   0.4; ...
                                 1,     0.5], ...
                      "mri_mip",[0,     0; ...
                                 0.04,  0; ...
                                 0.15,  0.5; ...
                                 1,     1], ...
                      "ct_soft",[0,     0; ...
                                 0.21,  0; ...
                                 0.22,  0.75; ...
                                 0.3,   0.75; ...
                                 0.32,  0; ...
                                 1,     0], ...
                      "ct_mip", [0,     0; ...
                                 0.4,   0; ...
                                 0.6,   1; ...
                                 1,     1]);
    end

    methods (Access = ?Register)
        
        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr >= 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.GridLayout_Main.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_Local.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_LocalInner.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.UIAxes_Metric.Color = constdef.GRAYSCALE_AXES_LIGHT;
                    app.UIAxes_CS.Color = constdef.GRAYSCALE_AXES_LIGHT;
                    app.UIAxes_Alphamap.Color = constdef.GRAYSCALE_AXES_LIGHT;
                case "DARK"
                    app.GridLayout_Main.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_Local.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_LocalInner.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.UIAxes_Metric.Color = constdef.GRAYSCALE_AXES_DARK;
                    app.UIAxes_CS.Color = constdef.GRAYSCALE_AXES_DARK;
                    app.UIAxes_Alphamap.Color = constdef.GRAYSCALE_AXES_DARK;
                otherwise
            end
        end

        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            app.Menu_File.Text = app.langmap.FILE;
            app.Menu_Export.Text = app.langmap.EXPORT;
            app.Tab_Global.Title = app.langmap.GLOBAL_REGISTRATION;
            app.Tab_Shift.Title = app.langmap.TRANSLATION;
            app.Tab_Rotate.Title = app.langmap.ROTATE;
            app.Tab_Local.Title = app.langmap.LOCAL_REGISTRATION;
            app.Button_RefreshRegEvaluate.Text = ...
                app.langmap.REFRESH_REGISTRATION_GOODNESS;
            app.Panel_Local_Control.Title = app.langmap.RENDERER_PARAMETERS;
            app.Label_2.Text = app.langmap.RENDER_PRECISION;
            app.Label_4.Text = app.langmap.COLOR_MAPPING;
            app.Label_5.Text = app.langmap.PROJECTION;
            app.DropDownLabel.Text = app.langmap.TRANSPARENT_MAPPING;
            app.CheckBox_Movie.Text = app.langmap.REGISTRATION_IMAGE;
            app.CheckBox_DF.Text = app.langmap.DISPLACEMENT_FIELD;

            % xlabel, ylabel
            % ...
        end

        function Refresh(app)
        end

        function RefreshTooltips(app)

        end

        function Close(app)
            delete(app);
        end
    end

    methods (Access = private)
        
        function InitData(app, mainapp)
            app.caller = mainapp;

            % set the rendering options
            app.render_opt = struct("accuracy",     0.05, ...
                                    "cmap",         jet,...
                                    "amap",         "linear",...
                                    "camera_view",  [-37.5 30],...
                                    "view_image",   true,...
                                    "view_df",      false);

            % data_changed for data initialized: [] -> data
            % isp is reletive marker
            app.render_isp = struct("render_acc_changed",   [false,false], ...
                                    "data_changed",         [false,false], ...
                                    "alpha_changed",        false, ...
                                    "color_changed",        false, ...
                                    "scene_removed",        [false,false], ...
                                    "frame_changed",        [false,false]);

            UpdateData(app);

            UpdateRenderMovie(app);

            app.frame_cur = 1;

            app.amap_line = [];
            app.amap_sltp = [];
            app.amap_sltp_active = false;

            app.vf_obj = [];
            app.ivs_obj = {};
            app.cbar_obj = [];
        end

        function InitPanel(app)
            D = numel(app.DIM_NAME);

            % reshape global registration -> shift panel
            t = tiledlayout(app.Tab_Shift, D, 1,"Padding", "compact");
            xlabel(t,app.langmap.TIME_FRAME,"FontSize",12);    %#ok<>
            ylabel(t,app.langmap.DISPLACEMENT_VOXEL,"FontSize",12);
            app.shift_xyz{1} = nexttile(t,1);
            app.shift_xyz{2} = nexttile(t,2);
            app.shift_xyz{3} = nexttile(t,3);
            for k = 1:D
                ymin = min(app.shift(:,k));
                ymax = max(app.shift(:,k));
                if ymax - ymin < 10*eps(ymin) || any(isnan([ymin ymax]))
                    ylim(app.shift_xyz{k}, [-0.5, 0.5]);
                else
                    ylim(app.shift_xyz{k}, [ymin, ymax]);
                end
            end

            % reshape global registration -> rotate panel
            t = tiledlayout(app.Tab_Rotate, D, 1, "Padding", "compact");
            xlabel(t,app.langmap.TIME_FRAME,"FontSize",12);
            ylabel(t,app.langmap.ANGULAR_DISPLACEMENT_RADIAN,"FontSize",12);
            app.rotate_xyz{1} = nexttile(t,1);
            app.rotate_xyz{2} = nexttile(t,2);
            app.rotate_xyz{3} = nexttile(t,3);
            for k = 1:D
                ymin = min(app.rotation(:,k));
                ymax = max(app.rotation(:,k));
                if ymax - ymin < 10*eps(ymin) || any(isnan([ymin ymax]))
                    ylim(app.rotate_xyz{k}, [-0.5*pi, 0.5*pi]);
                else
                    ylim(app.rotate_xyz{k}, [ymin, ymax]);
                end
            end

            % initialize view direction
            view(app.UIAxes_CS, [-37.5 30]);

            % remove toolbar
            app.UIAxes_Alphamap.Toolbar = [];
            % disable interaction
            disableDefaultInteractivity(app.UIAxes_Alphamap);

            % remove toolbar
            app.UIAxes_Metric.Toolbar = [];
            % disable interaction
            disableDefaultInteractivity(app.UIAxes_Metric);

            % reshape the slider frame
            app.Slider_Frame.Limits = [1 app.img_info.frames];
            app.Slider_Frame.MajorTicks = round(linspace(1, app.img_info.frames, 10));
            app.Slider_Frame.MinorTicks = round(linspace(1, app.img_info.frames, 50));
        end

        function PreviousFramePtr(app)
            if app.frame_cur > app.Slider_Frame.Limits(1)
                app.frame_cur = app.frame_cur - 1;
                app.Slider_Frame.Value = app.frame_cur;
                app.render_isp.frame_changed = [true, true];
            end
        end

        function NextFramePtr(app)
            if app.frame_cur < app.Slider_Frame.Limits(end)
                app.frame_cur = app.frame_cur + 1;
                app.Slider_Frame.Value = app.frame_cur;
                app.render_isp.frame_changed = [true, true];
            end
        end

        function UpdateData(app)
            % get marker channel volume
            [movr, mova] = app.caller.GetMoviePair();
            if ~isequal(movr, app.mov_r) || ~isequal(mova, app.mov_a)
                app.mov_a = mova;
                app.mov_r = movr;
                app.render_isp.data_changed = [true, true];
            else
                app.render_isp.data_changed = [false, false];
            end

            % get the image info
            app.img_info = app.caller.GetImgInfo();

            % get registration transformation
            tform = app.caller.GetTransformation();

            % record the regid transformation flag
            app.rigid_mark = true(app.img_info.frames, 1);
            for k = 1:size(tform,1)
                if isempty(tform{k,1}) || ~tform{k,1}.isRigid()
                    app.rigid_mark(k) = false;
                end
            end

            % get shift and rotation
            app.GetRigidShift(tform(:, 1));
            app.GetRigidRotation(tform(:, 1));

            % get non-rigid transformation
            app.GetNonRigidTform(tform(:, 2));
        end

        function ComplexSceneRenderer(app)
            % get vol and info
            Vol = app.render_mov;
            Info = app.caller.GetImgInfo();

            % rendering the image scene
            VolumeRenderer(app, Vol, Info);

            % rendering the displacement field scene
            DisplacementFieldRenderer(app, Vol, Info);
        end

        function VolumeRenderer(app, vol, info)
            % current channel for pseudo color mapping
            c = app.caller.GetCurrentChannel();
            [~, c] = ismember(c,["r","g","b"]);

            % use render_isp for rendering speed up
            if app.render_opt.view_image == true
                if app.render_isp.render_acc_changed(1) || ...
                         app.render_isp.data_changed(1) || ...
                         app.render_isp.scene_removed(1)|| ...
                         app.render_isp.frame_changed(1)
                    % reset the scene first
                    for k = 1:numel(app.ivs_obj)
                        delete(app.ivs_obj{k});
                    end
                    app.ivs_obj = {};
                    % =========== quick data information update ==============
                    dr = 1/app.render_opt.accuracy;
                    xr = info.xRes*dr; yr = info.yRes*dr; zr = info.zRes;
                    w = size(vol, 2); h = size(vol, 1);
                    d = size(vol, 4);
                    % ========================================================

                    % generate the meshgrid
                    [X, Y, Z] = meshgrid(0.5*xr:xr:(w-0.5)*xr,...
                        0.5*yr:yr:(h-0.5)*yr,...
                        0.5*zr:zr:(d-0.5)*zr);

                    % generate the surfaces object
                    app.ivs_obj = {gobjects(1,size(X, 2));
                        gobjects(1,size(Y, 1));
                        gobjects(1,size(Z, 3))};

                    % get the alpha mapping on each surface as 3D data
                    amap = GetAlphaMap(app, squeeze(vol(:,:,c,:,app.frame_cur)));

                    % =================  Renderer Running ====================
                    hold(app.UIAxes_CS, "on");
                    % render z direction
                    for z = 1:size(Z, 3)
                        XX = squeeze(X(:,:,z)); YY = squeeze(Y(:,:,z));ZZ = squeeze(Z(:,:,z));
                        VV = squeeze(vol(:,:,:,z,app.frame_cur));
                        aa = squeeze(amap(:,:,z));
                        app.ivs_obj{3}(z) = surface(app.UIAxes_CS,...
                            XX, YY, ZZ, VV, ...
                            "EdgeColor","none","FaceAlpha","flat",...
                            "AlphaData",aa,"AlphaDataMapping","none");
                    end
                    % render x direction
                    for x = 1:size(X, 2)
                        XX = squeeze(X(:,x,:)); YY = squeeze(Y(:,x,:));ZZ = squeeze(Z(:,x,:));
                        VV = permute(squeeze(vol(:,x,:,:,app.frame_cur)),[1,3,2]);
                        aa = squeeze(amap(:,x,:));
                        app.ivs_obj{1}(x) = surface(app.UIAxes_CS,...
                            XX, YY, ZZ, VV, ...
                            "EdgeColor","none","FaceAlpha","flat",...
                            "AlphaData",aa,"AlphaDataMapping","none");
                    end
                    % render y direction
                    for y = 1:size(Y, 1)
                        XX = squeeze(X(y,:,:)); YY = squeeze(Y(y,:,:));ZZ = squeeze(Z(y,:,:));
                        VV = permute(squeeze(vol(y,:,:,:,app.frame_cur)),[1,3,2]);
                        aa = squeeze(amap(y,:,:));
                        app.ivs_obj{2}(y) = surface(app.UIAxes_CS,...
                            XX, YY, ZZ, VV, ...
                            "EdgeColor","none","FaceAlpha","flat",...
                            "AlphaData",aa,"AlphaDataMapping","none");
                    end
                    hold(app.UIAxes_CS, "off");

                    % set the volume position limit
                    xlim(app.UIAxes_CS,[0 info.xRes*info.width]);
                    ylim(app.UIAxes_CS,[0 info.yRes*info.height]);
                    zlim(app.UIAxes_CS,[0 info.zRes*info.slices]);
                    %========================================================
                    % recover the changed flag
                    app.render_isp.render_acc_changed(1) = false;
                    app.render_isp.data_changed(1) = false;
                    app.render_isp.scene_removed(1) = false;
                    app.render_isp.frame_changed(1) = false;
                elseif app.render_isp.alpha_changed
                    % in this case, only alpha map could be changed
                    if ~isempty(app.ivs_obj)
                        % update the alpha mapping
                        amap = GetAlphaMap(app, squeeze(vol(:,:,c,:,app.frame_cur)));

                        %============== Rerendering Running ================
                        hold(app.UIAxes_CS, "on");
                        for z = 1:numel(app.ivs_obj{3})
                            aa = squeeze(amap(:,:,z));
                            alpha(app.ivs_obj{3}(z), aa);
                        end
                        for x = 1:numel(app.ivs_obj{1})
                            aa = squeeze(amap(:,x,:));
                            alpha(app.ivs_obj{1}(x), aa);
                        end
                        for y = 1:numel(app.ivs_obj{2})
                            aa = squeeze(amap(y,:,:));
                            alpha(app.ivs_obj{2}(y), aa);
                        end
                        hold(app.UIAxes_CS, "off");
                        %===================================================
                    end
                    % recover the changed flag
                    app.render_isp.alpha_changed = false;
                end
            else
                % remove volume scene
                for k = 1:numel(app.ivs_obj)
                    delete(app.ivs_obj{k});
                end
                app.ivs_obj = {};
                app.render_isp.scene_removed(1) = true;
            end
        end

        function DisplacementFieldRenderer(app, vol, info)
            dr = 1/app.render_opt.accuracy;
            % get the displacement field vectors
            dfv = app.nonrigid_tform{app.frame_cur};
            if isempty(dfv)
                % warning: no displacement field data
                warning("DisplacementFieldRenderer:displacementFieldDataLost",...
                    app.langmap.DISPLACEMENT_NOT_EXIST);
                return;
            end

            dfv = DownsamplingDisplacement(dfv, dr);
            dfv = dfv(:,:,1:2:end,:);   % z-direction downsampling

            % use render_isp for rendering speed up
            if app.render_opt.view_df == true
                if app.render_isp.render_acc_changed(2) || ...
                        app.render_isp.data_changed(2)  || ...
                        app.render_isp.scene_removed(2) || ...
                        app.render_isp.frame_changed(2)
                    % reset the scene first
                    delete(app.vf_obj);
                    app.vf_obj = [];
                    delete(app.cbar_obj);
                    % =========== quick data information update ==============
                    xr = info.xRes*dr; yr = info.yRes*dr; zr = info.zRes;
                    w = size(vol, 2); h = size(vol, 1);
                    d = size(vol, 4);
                    % ========================================================
                    % generate the meshgrid
                    [U, V, W] = meshgrid(0.5*xr:xr:(w-0.5)*xr,...
                        0.5*yr:yr:(h-0.5)*yr,...
                        0.5*zr:2*zr:(d-0.5)*zr);

                    % get the color mapping data
                    cmap = app.render_opt.cmap;
                    colormap(app.UIAxes_CS, cmap);

                    if ~isempty(dfv) && ndims(dfv) == 4
                        % generate the vector field object
                        app.vf_obj = gobjects(size(dfv));

                        [vx, vy, vz, C] = V2C();
                        width = size(vx, 2);
                        height = size(vy, 1);
                        depth = size(vz, 3);
                        %================ Rendering Running ==================
                        hold(app.UIAxes_CS,"on");

                        bar = uiprogressdlg(app.RegistrationEvaluatorUIFigure,...
                            "Title",app.langmap.INFORMATION,...
                            "Message",app.langmap.RENDERING_DISPLACEMENT_FIELD,...
                            "ShowPercentage","on");
                        pause(0.5);
                        for i = 1:height
                            for j = 1:width
                                for k = 1:depth
                                    app.vf_obj(i,j,k) = quiver3(app.UIAxes_CS,...
                                        U(i,j,k), V(i,j,k), W(i,j,k),...
                                        vx(i,j,k),vy(i,j,k),vz(i,j,k),...
                                        'AutoScale','on','Color',cmap(C(i,j,k),:),...
                                        'MaxHeadSize',100, 'LineWidth',1);
                                    bar.Value = ((i-1)*width*depth + (j-1)*depth...
                                        + k)/(height*width*depth);
                                end
                            end
                        end
                        close(bar);

                        hold(app.UIAxes_CS,"off");
                        %=====================================================
                        % add color bar
                        app.cbar_obj = colorbar(app.UIAxes_CS,"east");
                        app.cbar_obj.Label.String = "Normalized Displacement Norm";
                    end
                    % recover the changed flag
                    app.render_isp.render_acc_changed(2) = false;
                    app.render_isp.data_changed(2) = false;
                    app.render_isp.scene_removed(2) = false;
                    app.render_isp.frame_changed(2) = false;
                elseif app.render_isp.color_changed
                    % in this case, only color map could be changed
                    cmap = app.render_opt.cmap;
                    colormap(app.UIAxes_CS, cmap);

                    [~, ~, ~, C] = V2C();
                    width = size(C, 2);
                    height = size(C, 1);
                    depth = size(C, 3);
                    %================ Rendering Running ==================
                    hold(app.UIAxes_CS,"on");

                    for i = 1:height
                        for j = 1:width
                            for k = 1:depth
                                app.vf_obj(i,j,k).Color = cmap(C(i,j,k),:);
                            end
                        end
                    end

                    hold(app.UIAxes_CS,"off");
                    %=====================================================
                    % recover the changed flag
                    app.render_isp.color_changed = false;
                end
            else
                % remove vector field scene
                delete(app.vf_obj);
                app.vf_obj = [];
                delete(app.cbar_obj);
                app.render_isp.scene_removed(2) = true;
            end

            function [vx, vy, vz, C] = V2C()
                % extract the vector field components
                vx = dfv(:,:,:,1); vy = dfv(:,:,:,2); vz = dfv(:,:,:,3);
                dv = sqrt(vx.^2+vy.^2+vz.^2);
                dv_min = min(dv,[],[1,2,3]); dv_max = max(dv,[],[1,2,3]);

                % normalized and map to color
                vx = vx./dv; vy = vy./dv; vz = vz./dv;
                C = 255*((dv - dv_min)./(dv_max - dv_min))+1;
                C = round(C);
            end
        end

        function UpdateMetricAxes(app)
            % This function calculates registration goodness and refresh  
            % metric axes
            % we use MSE and Correlation evaluate the registration goodness

            template = single(app.caller.GetTemplate());
            N = app.img_info.frames;
            DR = max(template,[],"all") - min(template,[],"all");

            rmsd = zeros(N, 2);
            ssim_arr = zeros(N, 2);

            bar = uiprogressdlg(app.RegistrationEvaluatorUIFigure, ...
                "Title",app.langmap.INFORMATION,...
                "Message",app.langmap.CALCULATING_GOODNESS,...
                "ShowPercentage","on");
            pause(0.5);

            for k = 1:N
                % 1. shift the mean to template (for brightness)
                movr = single(app.mov_r(:,:,:,k));
                movr = movr - mean(movr,"all") + mean(template,"all");
                mova = single(app.mov_a(:,:,:,k));
                mova = mova - mean(mova,"all") + mean(template,"all");

                % 2. shrink the standard devision to template (for contrast)
                movr = movr/std(movr,0,"all")*std(template,0,"all");
                mova = mova/std(mova,0,"all")*std(template,0,"all");

                % 3. calculate the structured mse
                rmsd(k,1) = sqrt(immse(movr, template));
                rmsd(k,2) = sqrt(immse(mova, template));

                % structure similarity information 
                ssim_arr(k,1) = ssim(movr, template,"DynamicRange",DR);
                ssim_arr(k,2) = ssim(mova, template,"DynamicRange",DR);
                bar.Value = k/N;
            end

            % update the viewer
            cla(app.UIAxes_Metric, "reset");

            yyaxis(app.UIAxes_Metric,"left");
            hold(app.UIAxes_Metric, "on");
            plot(app.UIAxes_Metric, rmsd(:,1), '*-');
            plot(app.UIAxes_Metric, rmsd(:,2), 'o-');
            ylabel(app.UIAxes_Metric,"Structured RMSD","FontSize",12);

            yyaxis(app.UIAxes_Metric,"right");
            plot(app.UIAxes_Metric, ssim_arr(:,1), '*-');
            plot(app.UIAxes_Metric, ssim_arr(:,2), 'o-');
            ylabel(app.UIAxes_Metric,"SSIM","FontSize",12);
            hold(app.UIAxes_Metric, "off");

            % reshape total registration metric
            xlim(app.UIAxes_Metric,[1 N]);

            legend(app.UIAxes_Metric,...
                {app.langmap.RAW, app.langmap.ALIGNED,...
                 app.langmap.RAW, app.langmap.ALIGNED},...
                "Box","on","FontSize",10);
            xlabel(app.UIAxes_Metric,app.langmap.TIME_FRAME,"FontSize",12);
            title(app.UIAxes_Metric, app.langmap.REGISTRATION_GOODNESS,"FontSize",12);

            close(bar);
        end

        function UpdateAmapAxes(app)
            ia_map = app.AMAP.(app.render_opt.amap);
            x = ia_map(:,1); v = ia_map(:,2);
            app.amap_line =  plot(app.UIAxes_Alphamap,x,v,...
                "Marker","o","MarkerEdgeColor","none",...
                "MarkerFaceColor","b","LineWidth",1,...
                "LineStyle","-","Color","b");
            app.amap_line.ButtonDownFcn = createCallbackFcn(app,@Line_ButtonDownFcn,true);

            xlabel(app.UIAxes_Alphamap,app.langmap.IMAGE_INTENSITY,"FontSize",11);
            ylabel(app.UIAxes_Alphamap,app.langmap.OPACITY,"FontSize",11);

            xticks(app.UIAxes_Alphamap, 0:0.2:1);
            xticklabels(app.UIAxes_Alphamap, []);
            yticks(app.UIAxes_Alphamap, 0:0.2:1);
            yticklabels(app.UIAxes_Alphamap, []);
            xlim(app.UIAxes_Alphamap, [0 1]);
            ylim(app.UIAxes_Alphamap, [0 1]);
        end

        function UpdateComplexSceneAxes(app)
            PanelWaiting(app);
            % if rendering accuracy changed or source changed, we need to
            % generate the new source, otherwise we only need to
            % rerendering the surface or arrow colormap

            %======================= Render the scene ====================
            if any(app.render_isp.render_acc_changed | ...
                    app.render_isp.data_changed)
                UpdateRenderMovie(app);
            end

            ComplexSceneRenderer(app);

            %================= Change The Camera View=====================
            view(app.UIAxes_CS, app.render_opt.camera_view);

            %==================== Set the Axes Labels ====================
            xlabel(app.UIAxes_CS,"X /\mum","FontSize",12);
            ylabel(app.UIAxes_CS,"Y /\mum","FontSize",12);
            zlabel(app.UIAxes_CS,"Z /\mum","FontSize",12);
            title(app.UIAxes_CS,app.langmap.DISPLACEMENT_FIELD,"FontSize",14);

            % modify the scene scale
            axis(app.UIAxes_CS, "equal");

            PanelRecovered(app);
        end

        function UpdateRenderMovie(app)

            dr = 1/app.render_opt.accuracy;
            c = app.caller.GetCurrentChannel();

            % downsampling (only on XY plane)
            app.render_mov = ...
                DownsamplingDisplacement(app.mov_a, dr);
            app.render_mov = uint16(app.render_mov);

            CMAX = max(app.render_mov,[],"all");
            CMIN = min(app.render_mov,[],"all");
            app.render_mov = imrescalei(app.render_mov,CMIN,CMAX,true);

            % smooth the dawnsampling data
            for k = app.img_info.frames
                V = app.render_mov(:,:,:,k);
                T = graythresh(V);
                V(V <= T*V) = 0;
                app.render_mov(:,:,:,k) = smooth3(V);
            end

            % transform to RGB mode, size from (m,n,p,q) -> (m,n,3,p,q)
            app.render_mov = imrescalei(app.render_mov,CMIN,CMAX,false);
            app.render_mov = single(app.render_mov)/single(CMAX);
            
            switch c
                case "r"
                    app.render_mov = cat(5,app.render_mov,zeros([size(app.render_mov),2]));
                case "g"
                    app.render_mov = cat(5,app.render_mov*0.4,...
                        app.render_mov,zeros(size(app.render_mov)));
                otherwise
            end

            app.render_mov = permute(app.render_mov,[1,2,5,3,4]);
        end

        function UpdateRigidAxes(app)
            % This function use shift and rotation update panel
            D = numel(app.DIM_NAME);
            N = app.img_info.frames;

            % update the shift panel
            for k = 1:D
                plot(app.shift_xyz{k}, app.shift(:,k), app.LINE_SPEC{k});
                xlim(app.shift_xyz{k}, [1 N]);
                legend(app.shift_xyz{k}, app.DIM_NAME(k)+" -shift", ...
                    "FontSize", 10);
            end

            % update the rotate panel
            for k = 1:D
                plot(app.rotate_xyz{k}, app.rotation(:,k), app.LINE_SPEC{k});
                xlim(app.rotate_xyz{k}, [1 N]);
                legend(app.rotate_xyz{k}, app.DIM_NAME(k)+" -rotate", ...
                    "FontSize", 10);
            end
        end

        function GetRigidShift(app, tform)
            N = app.img_info.frames;
            app.shift = zeros(N, 3); % [dx,dy,dz]
            for k = 1:N
                if app.rigid_mark(k) == true
                    tform_tmp = tform{k}.T;   % tform{k} is affine3d object
                    app.shift(k,:) = tform_tmp(4, 1:3);
                else
                    % how to estimate the shift?
                    app.shift(k,:) = missing;
                end
            end
        end

        function GetRigidRotation(app, tform)
            N = app.img_info.frames;
            app.rotation = zeros(N, 3); % [ax,ay,az]
            for k = 1:N
                if app.rigid_mark(k) == true
                    tform_tmp = tform{k}.T;   % tform{k} is affine3d object
                    app.rotation(k,:) = ...
                        rotationMatrixToVector(tform_tmp(1:3,1:3));
                else
                     % how to estimate the rotation?
                     app.rotation(k,:) = missing;
                end
            end
        end

        function GetNonRigidTform(app, tform)
            app.nonrigid_tform = tform;
        end

        function amap = GetAlphaMap(app, A)
            % This function generate the alpha map which keep size with
            % matrix A
            ia_map = app.AMAP.(app.render_opt.amap);
            x = ia_map(:,1); v = ia_map(:,2);
            amap = interp1(x,v,A,"linear");    % piecewise linear
        end

        function PanelWaiting(app)
            % set the pointer for better visual effort
            set(app.RegistrationEvaluatorUIFigure,'Pointer','watch');
            drawnow nocallbacks;
        end

        function PanelRecovered(app)
            % set the pointer back
            set(app.RegistrationEvaluatorUIFigure,'Pointer','arrow');
            drawnow nocallbacks;
        end

        function ExportEvaluateResults(app, filedir, ext)
            % save rigid results and evaluate results
            if ismember(ext, app.BITMAP)
                fname = fullfile(filedir, app.FILENAME_SHIFT+ext);
                exportgraphics(app.shift_xyz{1}.Parent, fname, ...
                    "Resolution",600,"ContentType","image");
                fname = fullfile(filedir, app.FILENAME_ROTATE+ext);
                exportgraphics(app.rotate_xyz{1}.Parent, fname, ...
                    "Resolution",600,"ContentType","image");
                fname = fullfile(filedir, app.FILENAME_EVALUATE_RESULT+ext);
                exportgraphics(app.UIAxes_Metric, fname, ...
                    "Resolution",600,"ContentType","image");
            elseif ismember(ext, app.VECMAP)
                fname = fullfile(filedir, app.FILENAME_SHIFT+ext);
                exportgraphics(app.shift_xyz{1}.Parent, fname, ...
                    "ContentType","vector");
                fname = fullfile(filedir, app.FILENAME_ROTATE+ext);
                exportgraphics(app.rotate_xyz{1}.Parent, fname, ...
                    "ContentType","vector");
                fname = fullfile(filedir, app.FILENAME_EVALUATE_RESULT+ext);
                exportgraphics(app.UIAxes_Metric, fname, ...
                    "ContentType","vector");
            else
                error("exportEvaluateResults:badFileFormat",...
                    app.langmap.UNSUPPORTED_FILE_FORMAT);
            end
        end

        %================== USER CALL BACK ===================
        function Line_ButtonDownFcn(app, event)
            % get the mouse position
            ip = event.IntersectionPoint(1:2);

            % if the mouse position is a new target
            points_old = app.AMAP.(app.render_opt.amap);
            d = sqrt(sum((points_old - ip).^2, 2));
            if min(d) > app.NEW_POINT_DIST_TH
                % compare the new x vs. points
                % new x drop in [points_old(p-1,1), points_old(p,1)]
                p = find(ip(1) < points_old(:,1), 1, "first");
                app.AMAP.selfdef = [points_old(1:p-1,:);...
                                    ip;                 ...
                                    points_old(p:end,:)];
                app.amap_sltp = p;
                app.amap_sltp_active = true;
                app.render_opt.amap = "selfdef";
                if ~ismember('Self-Definition', app.DropDown_Amap.Items)
                    app.DropDown_Amap.Items = [app.DropDown_Amap.Items, ...
                        {'Self-Definition'}];
                end
                app.DropDown_Amap.Value = "Self-Definition";
            else
                % two points is overlap, that means invalid points or 
                % new point need to be fixed
                app.amap_sltp_active = false;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            InitData(app, mainapp);

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);

            InitPanel(app);

            UpdateRigidAxes(app);

            UpdateMetricAxes(app);

            UpdateAmapAxes(app);

            UpdateComplexSceneAxes(app);    

            RefreshTooltips(app);
        end

        % Value changed function: DropDown_ViewProjection
        function DropDown_ViewProjectionValueChanged(app, event)
            value = app.DropDown_ViewProjection.Value;
            switch value
                case "None"
                    app.render_opt.camera_view = [-37.5 30];
                case "XY"
                    app.render_opt.camera_view = [0 90];
                case "XZ"
                    app.render_opt.camera_view = [0 0];
                case "YZ"
                    app.render_opt.camera_view = [90 0];
            end

            view(app.UIAxes_CS, app.render_opt.camera_view);
        end

        % Value changed function: Slider_Frame
        function Slider_FrameValueChanged(app, event)
            value = app.Slider_Frame.Value;
            value = round(value);

            app.Slider_Frame.Value = value;

            app.frame_cur = value;
            app.render_isp.frame_changed = [true, true];

            UpdateComplexSceneAxes(app);
        end

        % Value changed function: DropDown_RendererAcc
        function DropDown_RendererAccValueChanged(app, event)
            value = app.DropDown_RendererAcc.Value;
            switch value
                case "1:40"
                    app.render_opt.accuracy = 0.025;
                case "1:20"
                    app.render_opt.accuracy = 0.05;
                case "1:10"
                    app.render_opt.accuracy = 0.1;
                case "1:5"
                    app.render_opt.accuracy = 0.2;
            end
            app.render_isp.render_acc_changed = [true, true];

            UpdateComplexSceneAxes(app);
        end

        % Value changed function: DropDown_Colormap
        function DropDown_ColormapValueChanged(app, event)
            value = app.DropDown_Colormap.Value;
            switch value
                case "default"
                    app.render_opt.cmap = parula;   % default setting R2022a
                otherwise
                    app.render_opt.cmap = eval(value);
            end

            app.render_isp.color_changed = true;

            UpdateComplexSceneAxes(app);
        end

        % Button down function: Tab_Local
        function Tab_LocalButtonDown(app, event)
            
            UpdateData(app);

            UpdateComplexSceneAxes(app);
        end

        % Value changed function: DropDown_Amap
        function DropDown_AmapValueChanged(app, event)
            value = app.DropDown_Amap.Value;
            if value == "Self-Definition"
                app.render_opt.amap = "selfdef";
                return;
            end

            switch value
                case "Linear"
                    app.render_opt.amap = "linear";
                case "MRI"
                    app.render_opt.amap = "mri";
                case "MRI-MIP"
                    app.render_opt.amap = "mri_mip";
                case "CT-SOFT"
                    app.render_opt.amap = "ct_soft";
                case "CT-MIP"
                    app.render_opt.amap = "ct_mip";
            end
            if ismember('Self-Definition',app.DropDown_Amap.Items)
                app.DropDown_Amap.Items(end) = [];
                app.AMAP = rmfield(app.AMAP, "selfdef");
            end

            app.render_isp.alpha_changed = true;

            UpdateAmapAxes(app);

            UpdateComplexSceneAxes(app);
        end

        % Value changed function: CheckBox_Movie
        function CheckBox_MovieValueChanged(app, event)
            value = app.CheckBox_Movie.Value;
            app.render_opt.view_image = value;

            UpdateComplexSceneAxes(app);
        end

        % Value changed function: CheckBox_DF
        function CheckBox_DFValueChanged(app, event)
            value = app.CheckBox_DF.Value;
            app.render_opt.view_df = value;

            UpdateComplexSceneAxes(app);
        end

        % Window button motion function: RegistrationEvaluatorUIFigure
        function RegistrationEvaluatorUIFigureWindowButtonMotion(app, event)
            % This funciton for checking the mouse motion
            % check the AMAP status
            if app.render_opt.amap == "selfdef"
                if app.amap_sltp_active
                    % tracking and real time updating
                    mouse_cur = app.UIAxes_Alphamap.CurrentPoint(1,1:2);
                    if mouse_cur(1) <= app.AMAP.selfdef(app.amap_sltp-1, 1)
                        return;
                    end
                    app.AMAP.selfdef(app.amap_sltp,:) = ...
                        mouse_cur;

                    app.render_isp.alpha_changed = true;
                    
                    UpdateAmapAxes(app);

                    UpdateComplexSceneAxes(app);
                end
            end

            % reset the view
            app.render_opt.camera_view = app.UIAxes_CS.View;

        end

        % Menu selected function: Menu_Export
        function Menu_ExportSelected(app, event)
            try
                filter = {'*.jpg', '联合图像专家组 (*.jpg)'; ...
                    '*.png', '可移植网络图形 (*.png)'; ...
                    '*.tif', '标记图像文件格式 (*.tif)'; ...
                    '*.pdf', '可移植文档格式 (*.pdf)'; ...
                    '*.emf', '仅适用于 Windows® 系统的增强型图元文件 (*.emf)'; ...
                    '*.eps', '封装的 PostScript® (*.eps)'};

                % Get the file path for locating images
                pathToMLAPP = fileparts(mfilename('fullpath'));

                [file, path] = uiputfile(filter, "导出", pathToMLAPP);
                if ~isequal(path, 0)
                    [~, ~, ext] = fileparts(fullfile(path, file));

                    ExportEvaluateResults(app, path, string(ext));
                end

                app.caller.PushInfo("Evaluator", "Menu_ExportSelected");
            catch ME
                app.caller.PushInfo("Evaluator", "Menu_ExportSelected", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Button pushed function: Button_RefreshRegEvaluate
        function Button_RefreshRegEvaluatePushed(app, event)
            UpdateData(app);

            UpdateMetricAxes(app);
        end

        % Key press function: RegistrationEvaluatorUIFigure
        function RegistrationEvaluatorUIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case "leftarrow"
                    PreviousFramePtr(app);
                    UpdateComplexSceneAxes(app);
                case "rightarrow"
                    NextFramePtr(app);
                    UpdateComplexSceneAxes(app);
                otherwise
            end
        end

        % Close request function: RegistrationEvaluatorUIFigure
        function RegistrationEvaluatorUIFigureCloseRequest(app, event)
            Close(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RegistrationEvaluatorUIFigure = uifigure('Visible', 'off');
            app.RegistrationEvaluatorUIFigure.Name = 'Registration Evaluator';
            app.RegistrationEvaluatorUIFigure.Icon = 'evaluation.png';
            app.RegistrationEvaluatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @RegistrationEvaluatorUIFigureCloseRequest, true);
            app.RegistrationEvaluatorUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @RegistrationEvaluatorUIFigureWindowButtonMotion, true);
            app.RegistrationEvaluatorUIFigure.KeyPressFcn = createCallbackFcn(app, @RegistrationEvaluatorUIFigureKeyPress, true);
            app.Menu_File = uimenu(app.RegistrationEvaluatorUIFigure);
            app.Menu_File.Tooltip = {'导出结果'};
            app.Menu_File.Text = '文件';
            app.Menu_Export = uimenu(app.Menu_File);
            app.Menu_Export.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExportSelected, true);
            app.Menu_Export.Text = '导出';
            app.GridLayout_Main = uigridlayout(app.RegistrationEvaluatorUIFigure);
            app.TabGroup_GL = uitabgroup(app.GridLayout_Main);
            app.Tab_Global = uitab(app.TabGroup_GL);
            app.Tab_Global.Tooltip = {''};
            app.Tab_Global.Title = '全局配准';
            app.TabGroup_SRS = uitabgroup(app.Tab_Global);
            app.Tab_Shift = uitab(app.TabGroup_SRS);
            app.Tab_Shift.Title = '平移';
            app.Tab_Rotate = uitab(app.TabGroup_SRS);
            app.Tab_Rotate.Title = '旋转';
            app.Tab_Local = uitab(app.TabGroup_GL);
            app.Tab_Local.Tooltip = {''};
            app.Tab_Local.Title = '局部配准';
            app.Tab_Local.ButtonDownFcn = createCallbackFcn(app, @Tab_LocalButtonDown, true);
            app.GridLayout_Local = uigridlayout(app.Tab_Local);
            app.Slider_Frame = uislider(app.GridLayout_Local);
            app.Slider_Frame.ValueChangedFcn = createCallbackFcn(app, @Slider_FrameValueChanged, true);
            app.GridLayout_LocalInner = uigridlayout(app.GridLayout_Local);
            app.UIAxes_CS = uiaxes(app.GridLayout_LocalInner);
            title(app.UIAxes_CS, '位移场估计')
            xlabel(app.UIAxes_CS, 'X')
            ylabel(app.UIAxes_CS, 'Y')
            zlabel(app.UIAxes_CS, 'Z')
            app.UIAxes_CS.YDir = 'reverse';
            app.Panel_Local_Control = uipanel(app.GridLayout_LocalInner);
            app.Panel_Local_Control.Title = '渲染器参数';
            app.UIAxes_Alphamap = uiaxes(app.Panel_Local_Control);
            app.UIAxes_Alphamap.XTick = [];
            app.UIAxes_Alphamap.YTick = [];
            app.Label_2 = uilabel(app.Panel_Local_Control);
            app.Label_2.Text = '渲染精度';
            app.DropDown_RendererAcc = uidropdown(app.Panel_Local_Control);
            app.DropDown_RendererAcc.Items = {'1:40', '1:20', '1:10', '1:5'};
            app.DropDown_RendererAcc.ValueChangedFcn = createCallbackFcn(app, @DropDown_RendererAccValueChanged, true);
            app.DropDown_RendererAcc.Value = '1:20';
            app.Label_4 = uilabel(app.Panel_Local_Control);
            app.Label_4.Text = '颜色映射';
            app.DropDown_Colormap = uidropdown(app.Panel_Local_Control);
            app.DropDown_Colormap.Items = {'autumn', 'bone', 'colorcube', 'cool', 'copper', 'default', 'flag', 'gray', 'hot', 'hsv', 'jet', 'lines', 'parula', 'pink', 'prism', 'spring', 'summer', 'turbo', 'white', 'winter'};
            app.DropDown_Colormap.ValueChangedFcn = createCallbackFcn(app, @DropDown_ColormapValueChanged, true);
            app.DropDown_Colormap.Value = 'default';
            app.Label_5 = uilabel(app.Panel_Local_Control);
            app.Label_5.Text = '投影';
            app.DropDown_ViewProjection = uidropdown(app.Panel_Local_Control);
            app.DropDown_ViewProjection.Items = {'None', 'XY', 'XZ', 'YZ'};
            app.DropDown_ViewProjection.ValueChangedFcn = createCallbackFcn(app, @DropDown_ViewProjectionValueChanged, true);
            app.DropDown_ViewProjection.Value = 'None';
            app.CheckBox_DF = uicheckbox(app.Panel_Local_Control);
            app.CheckBox_DF.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DFValueChanged, true);
            app.CheckBox_DF.Text = '位移场';
            app.CheckBox_Movie = uicheckbox(app.Panel_Local_Control);
            app.CheckBox_Movie.ValueChangedFcn = createCallbackFcn(app, @CheckBox_MovieValueChanged, true);
            app.CheckBox_Movie.Text = '配准图像';
            app.CheckBox_Movie.Value = true;
            app.DropDownLabel = uilabel(app.Panel_Local_Control);
            app.DropDownLabel.Text = '透明度映射';
            app.DropDown_Amap = uidropdown(app.Panel_Local_Control);
            app.DropDown_Amap.Items = {'Linear', 'MRI', 'MRI-MIP', 'CT-SOFT', 'CT-MIP'};
            app.DropDown_Amap.ValueChangedFcn = createCallbackFcn(app, @DropDown_AmapValueChanged, true);
            app.DropDown_Amap.Value = 'Linear';
            app.GridLayout = uigridlayout(app.GridLayout_Main);
            app.UIAxes_Metric = uiaxes(app.GridLayout);
            app.Button_RefreshRegEvaluate = uibutton(app.GridLayout, 'push');
            app.Button_RefreshRegEvaluate.ButtonPushedFcn = createCallbackFcn(app, @Button_RefreshRegEvaluatePushed, true);
            app.Button_RefreshRegEvaluate.Text = '刷新配准优度';
            % Show the figure after all components are created
            app.RegistrationEvaluatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Evaluator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RegistrationEvaluatorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.RegistrationEvaluatorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RegistrationEvaluatorUIFigure)
        end
    end
end