classdef FrameSelecter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FramesSelecterUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout_Status       matlab.ui.container.GridLayout
        EditField_InputStatus   matlab.ui.control.EditField
        EditField_DropRatio     matlab.ui.control.EditField
        GridLayout_Control      matlab.ui.container.GridLayout
        Button_OK               matlab.ui.control.Button
        Button_Append           matlab.ui.control.Button
        Panel_View              matlab.ui.container.Panel
        UITable_Section         matlab.ui.control.Table
    end

    properties (Constant)
        VER = "1.0.0"
    end

    properties (Access = private)
        caller;    % the parent app

        frames_section; % array for recording frame indices
        frames_n;       % total frames number
        langmap;        % mapping for language
        fp;             % 1X2 array, frame pointer, [row, col]

        tbl_copy        % n-by-2 table, the uitable data copy for recovering if bad input
    end

    properties (SetAccess = private, GetAccess = ?Register)
        indices_;
    end

    methods (Access = ?Register)

        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr >= 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.Panel_View.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_Control.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_Status.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.UITable_Section.BackgroundColor = constdef.GRAYSCALE_AXES_LIGHT;
                case "DARK"
                    app.Panel_View.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_Control.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_Status.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.UITable_Section.BackgroundColor = constdef.GRAYSCALE_AXES_DARK;
                otherwise
            end
        end
        
        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            app.Panel_View.Title = app.langmap.CROP_FRAME;
            app.UITable_Section.ColumnName = [app.langmap.BEGIN, ...
                                              app.langmap.END];
            app.Button_Append.Text = app.langmap.APPEND;
            app.Button_OK.Text = app.langmap.OK;

            UpdateDiscardStats(app);
            UpdateInputStatus(app);
        end

        function AppendFrame(app, fidx)
            status = AppendFrame_Private(app, fidx);

            if status == -1
                % ERROR: display at anytime
                uialert(app.FramesSelecterUIFigure, ...
                    app.langmap.INVALID_FRAME_ORDER, ...
                    app.langmap.ERROR, "Icon","error");
            else
                UpdateDiscardStats(app);
                UpdateInputStatus(app);
            end
        end

        function Refresh(app)
        end

        function RefreshTooltips(app)
        end

        function Close(app)
            delete(app);
        end
    end
    
    methods (Access = private)
        
        function InitData(app, mainapp)
            app.caller = mainapp;
            app.frames_section = [];
            app.frames_n = app.caller.GetImgInfo.frames;
            app.fp = [1, 1];
            app.indices_ = {};

            app.UITable_Section.Data = table('Size',[0 2],...
                'VariableTypes',{'double','double'},...
                'VariableNames',{'BEGIN','END'});
        end

        function UpdateDiscardStats(app)
            % 1. calculate discard ratio
            if ~isempty(app.UITable_Section.Data)
                dr = (app.frames_n - sum(app.UITable_Section.Data.END - ...
                    app.UITable_Section.Data.BEGIN + 1))/app.frames_n;
            else
                dr = nan;
            end

            % 2. checking randomness


            % refresh to panel
            app.EditField_DropRatio.Value = ...
                sprintf("%s: %.3f %%", app.langmap.DISCARD_RATIO, 100*dr);
            app.EditField_DropRatio.HorizontalAlignment = 'left';


        end

        function UpdateInputStatus(app)
            switch app.fp(2)
                case 1
                    status = app.langmap.WAITING + ...
                             app.langmap.INPUT +  ...
                             app.langmap.BEGIN;
                case 2
                    status = app.langmap.WAITING + ...
                             app.langmap.INPUT +  ...
                             app.langmap.END;
            end
            
            % update status panel
            app.EditField_InputStatus.Value = status;
        end

        function status = AppendFrame_Private(app, frame)
            % validate frame
            if ~isempty(app.UITable_Section.Data)
                fp_old = [app.fp(1)+app.fp(2)-2, app.fp(2)-(-1)^mod(app.fp(2),2)];
                % app.langs_mapping.INVALID_FRAME,...
                if (frame < app.UITable_Section.Data{fp_old(1),fp_old(2)}) || ...
                        (frame == app.UITable_Section.Data{fp_old(1),fp_old(2)} && fp_old(2)==2)
                    status = -1;
                    return;
                end
            else
                if frame == app.frames_n
                    status = -1;
                    return;
                end
            end
            switch app.fp(2)
                case 1
                    % generate new table with 1 row
                    T = table('Size',[1 2],...
                        'VariableTypes',{'double','double'},...
                        'VariableNames',{'BEGIN','END'});
                    T.BEGIN(1) = frame;
                    T.END(1) = app.frames_n;

                    % expand the table
                    app.UITable_Section.Data = [app.UITable_Section.Data; T];
                    app.UITable_Section.RowName = [app.UITable_Section.RowName; ...
                        "Section "+num2str(app.fp(1))];
                case 2
                    % modify the end of table
                    app.UITable_Section.Data.END(app.fp(1)) = frame;

                    % move the pointer
                    app.fp(1) = app.fp(1) + 1;
            end

            % move the pointer
            app.fp(2) = mod(app.fp(2), 2) + 1;

            % copy the data
            app.tbl_copy = app.UITable_Section.Data;

            % scroll to bottom auto
            scroll(app.UITable_Section, "bottom");

            status = 0;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            InitData(app, mainapp);

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);

            UpdateDiscardStats(app);

            UpdateInputStatus(app);

            RefreshTooltips(app);
        end

        % Button pushed function: Button_Append
        function Button_AppendPushed(app, event)
            fidx = app.caller.GetCurrentFrameIndex();

            status = AppendFrame_Private(app, fidx);

            if status == -1
                % ERROR: display at anytime
                uialert(app.FramesSelecterUIFigure, ...
                    app.langmap.INVALID_FRAME_ORDER, ...
                    app.langmap.ERROR, "Icon","error");
            else
                UpdateDiscardStats(app);
                UpdateInputStatus(app);
            end
        end

        % Button pushed function: Button_OK
        function Button_OKPushed(app, event)
            if ~isempty(app.UITable_Section.Data)
                % translate table to indices vector
                app.indices_ = cell(1, size(app.UITable_Section.Data, 1));
                for r = 1:numel(app.indices_)
                    app.indices_{r} = app.UITable_Section.Data{r,1}:app.UITable_Section.Data{r,2};
                end

                app.indices_ = unique(cat(2, app.indices_{:}));

                app.caller.SetFrames(app.indices_);
            end

            Close(app);
        end

        % Close request function: FramesSelecterUIFigure
        function FramesSelecterUIFigureCloseRequest(app, event)
            Close(app);

        end

        % Cell edit callback: UITable_Section
        function UITable_SectionCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if newData < 1 || newData > app.frames_n
                % ERROR: display at anytime
                uialert(app.FramesSelecterUIFigure, ...
                    app.langmap.INVALID_FRAME_ORDER, ...
                    app.langmap.ERROR, "Icon","error");

                % recover data
                app.UITable_Section.Data = app.tbl_copy;
                return;
            end

            % auto change to integer value
            newData = round(newData);
            
            if indices(2) == 1
                if indices(1) > 1
                    % this cell must be gt last of pre row and
                    % leq last of this row
                    if newData <= app.tbl_copy{indices(1)-1, 2} || ...
                            newData > app.tbl_copy{indices(1),2}
                        % ERROR: display at anytime
                        uialert(app.FramesSelecterUIFigure, ...
                            app.langmap.INVALID_FRAME_ORDER, ...
                            app.langmap.ERROR, "Icon","error");

                        % recover data
                        app.UITable_Section.Data = app.tbl_copy;
                        return;
                    end
                end
            else
                if newData < app.tbl_copy{indices(1),1}
                    % ERROR: display at anytime
                    uialert(app.FramesSelecterUIFigure, ...
                        app.langmap.INVALID_FRAME_ORDER, ...
                        app.langmap.ERROR, "Icon","error");

                    % recover data
                    app.UITable_Section.Data = app.tbl_copy;
                    return;
                end
                if size(app.tbl_copy, 1) > indices(1)
                    if newData >= app.tbl_copy{indices(1)+1,1}
                        % ERROR: display at anytime
                        uialert(app.FramesSelecterUIFigure, ...
                            app.langmap.INVALID_FRAME_ORDER, ...
                            app.langmap.ERROR, "Icon","error");

                        % recover data
                        app.UITable_Section.Data = app.tbl_copy;
                        return;
                    end
                end
            end

            % modify the tbl_copy
            app.tbl_copy{indices(1), indices(2)} = newData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FramesSelecterUIFigure = uifigure('Visible', 'off');
            app.FramesSelecterUIFigure.Name = 'Frames Selecter';
            app.FramesSelecterUIFigure.Icon = 'crop.png';
            app.FramesSelecterUIFigure.CloseRequestFcn = createCallbackFcn(app, @FramesSelecterUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.FramesSelecterUIFigure);
            app.Panel_View = uipanel(app.GridLayout);
            app.Panel_View.Title = '帧裁剪';
            app.UITable_Section = uitable(app.Panel_View);
            app.UITable_Section.ColumnName = {'开始'; '结束'};
            app.UITable_Section.RowName = {};
            app.UITable_Section.ColumnEditable = true;
            app.UITable_Section.CellEditCallback = createCallbackFcn(app, @UITable_SectionCellEdit, true);
            app.GridLayout_Control = uigridlayout(app.GridLayout);
            app.Button_Append = uibutton(app.GridLayout_Control, 'push');
            app.Button_Append.ButtonPushedFcn = createCallbackFcn(app, @Button_AppendPushed, true);
            app.Button_Append.Tooltip = {'Ctrl+A'};
            app.Button_Append.Text = '添加';
            app.Button_OK = uibutton(app.GridLayout_Control, 'push');
            app.Button_OK.ButtonPushedFcn = createCallbackFcn(app, @Button_OKPushed, true);
            app.Button_OK.Tooltip = {''};
            app.Button_OK.Text = '确定';
            app.GridLayout_Status = uigridlayout(app.GridLayout);
            app.EditField_DropRatio = uieditfield(app.GridLayout_Status, 'text');
            app.EditField_DropRatio.Editable = 'off';
            app.EditField_DropRatio.Tooltip = {''};
            app.EditField_InputStatus = uieditfield(app.GridLayout_Status, 'text');
            app.EditField_InputStatus.CharacterLimits = [0 20];
            app.EditField_InputStatus.Editable = 'off';
            app.EditField_InputStatus.Tooltip = {''};
            % Show the figure after all components are created
            app.FramesSelecterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FrameSelecter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FramesSelecterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.FramesSelecterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FramesSelecterUIFigure)
        end
    end
end