classdef FrameSelecter_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FramesSelecterUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout_Status       matlab.ui.container.GridLayout
        EditField_InputStatus   matlab.ui.control.EditField
        EditField_DropRatio     matlab.ui.control.EditField
        GridLayout_Control      matlab.ui.container.GridLayout
        Button_OK               matlab.ui.control.Button
        Button_Append           matlab.ui.control.Button
        Panel_View              matlab.ui.container.Panel
        UITable_Section         matlab.ui.control.Table
    end

    
    properties (Access = private)
        calling_app;    % the parent app

        frames_section; % array for recording frame indices
        frames_n;       % total frames number
        langs_mapping;  % mapping for language
        fp;             % 1X2 array, frame pointer, [row, col]
    end

    properties (SetAccess = private, GetAccess = public)
        indices;
    end
    
    methods (Access = private)
        
        function InitData(app, mainapp)
            app.calling_app = mainapp;
            app.frames_section = [];
            app.frames_n = app.calling_app.GetImgInfo.frames;
            app.fp = [1, 1];
            app.indices = {};

            app.UITable_Section.Data = table('Size',[0 2],...
                'VariableTypes',{'double','double'},...
                'VariableNames',{'BEGIN','END'});
        end

        function UpdateDiscardStats(app)
            % 1. calculate discard ratio
            if ~isempty(app.UITable_Section.Data)
                dr = (app.frames_n - sum(app.UITable_Section.Data.END - ...
                    app.UITable_Section.Data.BEGIN + 1))/app.frames_n;
            else
                dr = nan;
            end

            % 2. checking randomness


            % refresh to panel
            app.EditField_DropRatio.Value = ...
                sprintf(app.langs_mapping.DISCARD_RATIO+": %.2f", dr);
            app.EditField_DropRatio.HorizontalAlignment = 'left';


        end

        function UpdateInputStatus(app)
            switch app.fp(2)
                case 1
                    status = app.langs_mapping.WAITING + ...
                             app.langs_mapping.INPUT +  ...
                             app.langs_mapping.BEGIN;
                case 2
                    status = app.langs_mapping.WAITING + ...
                             app.langs_mapping.INPUT +  ...
                             app.langs_mapping.END;
            end
            
            % update status panel
            app.EditField_InputStatus.Value = status;
        end

        function status = AppendFrame_Private(app, frame)
            % validate frame
            if ~isempty(app.UITable_Section.Data)
                fp_old = [app.fp(1)+app.fp(2)-2, app.fp(2)-(-1)^mod(app.fp(2),2)];
                % app.langs_mapping.INVALID_FRAME,...
                if frame < app.UITable_Section.Data{fp_old(1),fp_old(2)}
                    status = -1;
                    return;
                end
            else
                if frame == app.frames_n
                    status = -1;
                    return;
                end
            end
            switch app.fp(2)
                case 1
                    % generate new table with 1 row
                    T = table('Size',[1 2],...
                        'VariableTypes',{'double','double'},...
                        'VariableNames',{'BEGIN','END'});
                    T.BEGIN(1) = frame;
                    T.END(1) = app.frames_n;

                    % expand the table
                    app.UITable_Section.Data = [app.UITable_Section.Data; T];
                    app.UITable_Section.RowName = [app.UITable_Section.RowName; ...
                        "Section "+num2str(app.fp(1))];
                case 2
                    % modify the end of table
                    app.UITable_Section.Data.END(app.fp(1)) = frame;

                    % move the pointer
                    app.fp(1) = app.fp(1) + 1;
            end

            % move the pointer
            app.fp(2) = mod(app.fp(2), 2) + 1;

            status = 0;
        end

    end
    
    methods (Access = public)
        
        function RefreshPanelLanguage(app)
            [~, app.langs_mapping] = app.calling_app.GetLangsInfo();
            
            app.Panel_View.Title = app.langs_mapping.CROP_FRAME;
            app.UITable_Section.ColumnName = [app.langs_mapping.BEGIN, ...
                                              app.langs_mapping.END];
            app.Button_Append.Text = app.langs_mapping.APPEND;
            app.Button_OK.Text = app.langs_mapping.OK;

            UpdateDiscardStats(app);
            UpdateInputStatus(app);
        end

        function AppendFrame(app, fidx)
            status = AppendFrame_Private(app, fidx);

            if status == -1
                uialert(app.FramesSelecterUIFigure, ...
                    app.langs_mapping.INVALID_FRAME_ORDER, ...
                    app.langs_mapping.ERROR, "Icon","error");
            else
                UpdateDiscardStats(app);
                UpdateInputStatus(app);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            InitData(app, mainapp);

            RefreshPanelLanguage(app);

            UpdateDiscardStats(app);

            UpdateInputStatus(app);
        end

        % Button pushed function: Button_Append
        function Button_AppendPushed(app, event)
            fidx = app.calling_app.GetCurrentFrameIndex();

            status = AppendFrame_Private(app, fidx);

            if status == -1
                uialert(app.FramesSelecterUIFigure, ...
                    app.langs_mapping.INVALID_FRAME_ORDER, ...
                    app.langs_mapping.ERROR, "Icon","error");
            else
                UpdateDiscardStats(app);
                UpdateInputStatus(app);
            end
        end

        % Button pushed function: Button_OK
        function Button_OKPushed(app, event)
            if ~isempty(app.UITable_Section.Data)
                % translate table to indices vector
                app.indices = cell(1, size(app.UITable_Section.Data, 1));
                for r = 1:numel(app.indices)
                    app.indices{r} = app.UITable_Section.Data{r,1}:app.UITable_Section.Data{r,2};
                end

                app.indices = cat(2, app.indices{:});

                app.calling_app.SetFrames(app.indices);
            end

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FramesSelecterUIFigure = uifigure('Visible', 'off');
            app.FramesSelecterUIFigure.Name = 'Frames Selecter';
            app.FramesSelecterUIFigure.Icon = 'crop.png';
            app.GridLayout = uigridlayout(app.FramesSelecterUIFigure);
            app.Panel_View = uipanel(app.GridLayout);
            app.Panel_View.Title = '帧裁剪';
            app.UITable_Section = uitable(app.Panel_View);
            app.UITable_Section.ColumnName = {'开始'; '结束'};
            app.UITable_Section.RowName = {};
            app.UITable_Section.ColumnEditable = true;
            app.GridLayout_Control = uigridlayout(app.GridLayout);
            app.Button_Append = uibutton(app.GridLayout_Control, 'push');
            app.Button_Append.ButtonPushedFcn = createCallbackFcn(app, @Button_AppendPushed, true);
            app.Button_Append.Tooltip = {'Ctrl+A'};
            app.Button_Append.Text = '添加';
            app.Button_OK = uibutton(app.GridLayout_Control, 'push');
            app.Button_OK.ButtonPushedFcn = createCallbackFcn(app, @Button_OKPushed, true);
            app.Button_OK.Tooltip = {'Ctrl+A'};
            app.Button_OK.Text = '确定';
            app.GridLayout_Status = uigridlayout(app.GridLayout);
            app.EditField_DropRatio = uieditfield(app.GridLayout_Status, 'text');
            app.EditField_DropRatio.CharacterLimits = [0 20];
            app.EditField_DropRatio.Editable = 'off';
            app.EditField_DropRatio.Tooltip = {''};
            app.EditField_InputStatus = uieditfield(app.GridLayout_Status, 'text');
            app.EditField_InputStatus.CharacterLimits = [0 20];
            app.EditField_InputStatus.Editable = 'off';
            app.EditField_InputStatus.Tooltip = {''};
            % Show the figure after all components are created
            app.FramesSelecterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FrameSelecter_GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FramesSelecterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.FramesSelecterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FramesSelecterUIFigure)
        end
    end
end