classdef ImageImporter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImporterUIFigure        matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout4             matlab.ui.container.GridLayout
        GridLayout6             matlab.ui.container.GridLayout
        Image_Slogan            matlab.ui.control.Image
        GridLayout5             matlab.ui.container.GridLayout
        Button_Import           matlab.ui.control.Button
        Button_Skip             matlab.ui.control.Button
        GridLayout3             matlab.ui.container.GridLayout
        Button_SelectTimeStamp  matlab.ui.control.Button
        EditField_TimeStamp     matlab.ui.control.EditField
        Label_2                 matlab.ui.control.Label
        GridLayout2             matlab.ui.container.GridLayout
        Button_SelectImageFile  matlab.ui.control.Button
        EditField_ImageFile     matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        caller      % 1-by-1 Register object
        config      % 1-by-1 struct with project configuration
        pos_caller  % 1-by-4 double array with integer
        langmap     % 1-by-1 struct with language mapping
    end

    methods (Access = private)

        function InitData(app)
            app.config = app.caller.GetImporterPreset();

            app.pos_caller = app.caller.GetAppPosition();

            % apply
            app.EditField_ImageFile.Value = app.config.ImportFolder;
            app.EditField_TimeStamp.Enable = app.config.IndependentTime;
            app.Button_SelectTimeStamp.Enable = app.config.IndependentTime;
        end

        function RefreshPanelLanguage(app)
            % get language map
            [~, app.langmap] = app.caller.GetLangsInfo();

            % apply
            app.Label.Text = app.langmap.IMAGING_FILE;
            app.Label_2.Text = app.langmap.TIME_STAMP_FILE;
            app.Button_Skip.Text = app.langmap.SKIP;
            app.Button_Import.Text = app.langmap.IMPORT;
        end

        function SetupWindowPosition(app)
            x0 = app.pos_caller(1); y0 = app.pos_caller(2);
            w0 = app.pos_caller(3); h0 = app.pos_caller(4);
            w = app.ImporterUIFigure.Position(3);
            h = app.ImporterUIFigure.Position(4);
            x = ceil(x0 + 0.5*(w0 - w));
            y = ceil(y0 + 0.5*(h0 - h));
            app.ImporterUIFigure.Position(1) = x;
            app.ImporterUIFigure.Position(2) = y;
        end

        function Close(app)
            delete(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.caller = caller;

            InitData(app);

            SetupWindowPosition(app);

            RefreshPanelLanguage(app);
        end

        % Button pushed function: Button_SelectImageFile
        function Button_SelectImageFilePushed(app, event)
            % uigetfile selection
            [file, path] = uigetfile(...
                {'*.ims;*.nd2;*.tif;*.h5;*.rmv', ...
                [char(app.langmap.IMAGING_FILE),'(*.ims,*.tif,*.nd2,*.h5,*.rmv)']},...
                char(app.langmap.OPEN_IMAGING_FILE), ...
                app.config.ImportFolder);

            if ~isnumeric(file)
                img_file = string(fullfile(path, file));
                app.EditField_ImageFile.Value = img_file;

                [path, ~, ext] = fileparts(img_file);
                switch ext
                    case ".tif"
                        app.EditField_TimeStamp.Enable = "on";
                        app.Button_SelectTimeStamp.Enable = "on";
                        app.config.IndependentTime = true;
                    otherwise
                        app.EditField_TimeStamp.Enable = "off";
                        app.Button_SelectTimeStamp.Enable = "off";
                        app.config.IndependentTime = false;
                end
                
                app.config.ImportFolder = path;
                app.config.ImageFile = img_file;
                app.Button_Import.Enable = "on";
            end
        end

        % Button pushed function: Button_SelectTimeStamp
        function Button_SelectTimeStampPushed(app, event)
            % uigetfile selection
            [file, path] = uigetfile(...
                {['*', char(constdef.TIME_FILE_EXT)], ...
                [app.langmap.TIME_SERIES_FILE.char(), ...
                ' (*', char(constdef.TIME_FILE_EXT) ,')']},...
                app.langmap.LOAD_TIME, "camera_time.tim");

            if ~isnumeric(file)
                tim_file = string(fullfile(path, file));
                app.EditField_TimeStamp.Value = tim_file;

                app.config.TimeStampFile = tim_file;
            end
        end

        % Button pushed function: Button_Skip
        function Button_SkipPushed(app, event)
            % set and close
            app.config.SkipImport = true;
            app.caller.SetImporterPreset(app.config);

            Close(app);
        end

        % Button pushed function: Button_Import
        function Button_ImportPushed(app, event)
            % set and close
            app.config.SkipImport = false;
            app.caller.SetImporterPreset(app.config);

            Close(app);
        end

        % Close request function: ImporterUIFigure
        function ImporterUIFigureCloseRequest(app, event)
            app.config.SkipImport = true;
            app.caller.SetImporterPreset(app.config);

            Close(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImporterUIFigure = uifigure('Visible', 'off');
            app.ImporterUIFigure.Name = 'Importer';
            app.ImporterUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImporterUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ImporterUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '图像文件';
            app.EditField_ImageFile = uieditfield(app.GridLayout2, 'text');
            app.EditField_ImageFile.Editable = 'off';
            app.Button_SelectImageFile = uibutton(app.GridLayout2, 'push');
            app.Button_SelectImageFile.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectImageFilePushed, true);
            app.Button_SelectImageFile.Icon = 'folder.png';
            app.Button_SelectImageFile.Text = '';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '时间戳文件';
            app.EditField_TimeStamp = uieditfield(app.GridLayout3, 'text');
            app.EditField_TimeStamp.Editable = 'off';
            app.Button_SelectTimeStamp = uibutton(app.GridLayout3, 'push');
            app.Button_SelectTimeStamp.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectTimeStampPushed, true);
            app.Button_SelectTimeStamp.Icon = 'folder.png';
            app.Button_SelectTimeStamp.Text = '';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.Button_Skip = uibutton(app.GridLayout5, 'push');
            app.Button_Skip.ButtonPushedFcn = createCallbackFcn(app, @Button_SkipPushed, true);
            app.Button_Skip.Text = '跳过';
            app.Button_Import = uibutton(app.GridLayout5, 'push');
            app.Button_Import.ButtonPushedFcn = createCallbackFcn(app, @Button_ImportPushed, true);
            app.Button_Import.Enable = 'off';
            app.Button_Import.Text = '导入';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.Image_Slogan = uiimage(app.GridLayout6);
            app.Image_Slogan.ImageSource = 'import_slogan.png';
            % Show the figure after all components are created
            app.ImporterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageImporter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ImporterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ImporterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImporterUIFigure)
        end
    end
end