classdef LabNasLogin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LabNasLoginUIFigure  matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Panel_LabNas         matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout5          matlab.ui.container.GridLayout
        Button_Cancel        matlab.ui.control.Button
        Button_Login         matlab.ui.control.Button
        GridLayout4          matlab.ui.container.GridLayout
        Button_HidePassword  matlab.ui.control.Button
        EditField_Password   matlab.ui.control.EditField
        EditField_2Label     matlab.ui.control.Label
        GridLayout3          matlab.ui.container.GridLayout
        EditField_UserName   matlab.ui.control.EditField
        Label                matlab.ui.control.Label
    end

    
    properties (Access = private)
        calling_app    % the parent app

        pwd_pre        % the previous password
        pwd_status     % the password status
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.calling_app = mainapp;

            app.pwd_pre = [];
            app.pwd_status = "hide";
            app.Button_Login.Enable = "off";
        end

        % Button pushed function: Button_Login
        function Button_LoginPushed(app, event)
            try
                bar = uiprogressdlg(app.LabNasLoginUIFigure, ...
                    "Message","Login...","Indeterminate","on");

                % security warning: pass plain text as arguments
                app.calling_app.SetSFTPToLabNas(...
                    app.EditField_UserName.Value, ...
                    app.pwd_pre);

                delete(bar);
            catch exception
                switch exception.identifier
                    case 'MATLAB:io:ftp:ftp:BadLogin'
                        error("uipassword:invalidInputs", ...
                            "Invalid user name or password.");
                    case 'MATLAB:io:ftp:ftp:NoConnection'
                        error("uipassword:remoteNoResponse", ...
                            "Remote host no response, please try again.");
                    otherwise
                end
            end

            delete(app);
        end

        % Button pushed function: Button_Cancel
        function Button_CancelPushed(app, event)
            delete(app);
        end

        % Button pushed function: Button_HidePassword
        function Button_HidePasswordPushed(app, event)
            switch app.pwd_status
                case "hide"
                    app.pwd_status = "uncover";
                    app.Button_HidePassword.Icon ...
                        = "eye_open.png";
                    app.EditField_Password.Value = app.pwd_pre;
                case "uncover"
                    app.pwd_status = "hide";
                    app.Button_HidePassword.Icon ...
                        = "eye_close.png";
                    app.EditField_Password.Value = repmat('*',1,numel(app.pwd_pre));
            end
        end

        % Value changing function: EditField_Password
        function EditField_PasswordValueChanging(app, event)
            pwd_cur = event.Value;
            
            d = length(pwd_cur) - length(app.pwd_pre);
            if d > 0
                app.pwd_pre = [app.pwd_pre, pwd_cur(end-d+1:end)];
                if app.pwd_status == "hide"
                    app.EditField_Password.Value = repmat('*',1,numel(app.pwd_pre));
                end
            elseif d < 0
                app.pwd_pre(end+d+1:end) = [];
            end

            if ~isempty(pwd_cur) && ~isempty(app.EditField_UserName.Value)
                app.Button_Login.Enable = "on";
            else
                app.Button_Login.Enable = "off";
            end
        end

        % Value changing function: EditField_UserName
        function EditField_UserNameValueChanging(app, event)
            usr_cur = event.Value;
            if ~isempty(usr_cur) && ~isempty(app.EditField_Password.Value)
                app.Button_Login.Enable = "on";
            else
                app.Button_Login.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LabNasLoginUIFigure = uifigure('Visible', 'off');
            app.LabNasLoginUIFigure.Name = 'LabNasLogin';
            app.LabNasLoginUIFigure.Icon = 'lock.png';
            app.GridLayout = uigridlayout(app.LabNasLoginUIFigure);
            app.Panel_LabNas = uipanel(app.GridLayout);
            app.Panel_LabNas.Title = '登录 — LabNas';
            app.GridLayout2 = uigridlayout(app.Panel_LabNas);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '用户名';
            app.EditField_UserName = uieditfield(app.GridLayout3, 'text');
            app.EditField_UserName.ValueChangingFcn = createCallbackFcn(app, @EditField_UserNameValueChanging, true);
            app.EditField_UserName.Placeholder = 'user name';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.EditField_2Label = uilabel(app.GridLayout4);
            app.EditField_2Label.Text = '密码';
            app.EditField_Password = uieditfield(app.GridLayout4, 'text');
            app.EditField_Password.ValueChangingFcn = createCallbackFcn(app, @EditField_PasswordValueChanging, true);
            app.EditField_Password.Placeholder = 'password';
            app.Button_HidePassword = uibutton(app.GridLayout4, 'push');
            app.Button_HidePassword.ButtonPushedFcn = createCallbackFcn(app, @Button_HidePasswordPushed, true);
            app.Button_HidePassword.Icon = 'eye_close.png';
            app.Button_HidePassword.Text = '';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.Button_Login = uibutton(app.GridLayout5, 'push');
            app.Button_Login.ButtonPushedFcn = createCallbackFcn(app, @Button_LoginPushed, true);
            app.Button_Login.Text = '登录';
            app.Button_Cancel = uibutton(app.GridLayout5, 'push');
            app.Button_Cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_CancelPushed, true);
            app.Button_Cancel.Text = '取消';
            % Show the figure after all components are created
            app.LabNasLoginUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabNasLogin(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LabNasLoginUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.LabNasLoginUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LabNasLoginUIFigure)
        end
    end
end