classdef MaskGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaskManagerV100UIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout_Regionprop    matlab.ui.container.GridLayout
        GridLayout_Regionprop3D  matlab.ui.container.GridLayout
        Label_Title3D            matlab.ui.control.Label
        UITable_Regionprop3D     matlab.ui.control.Table
        GridLayout_Regionprop2D  matlab.ui.container.GridLayout
        Label_Title2D            matlab.ui.control.Label
        UITable_Regionprop2D     matlab.ui.control.Table
        GridLayout_Control       matlab.ui.container.GridLayout
        Button_Inverse           matlab.ui.control.StateButton
        Button_Export            matlab.ui.control.Button
        Button_Remove            matlab.ui.control.Button
        Button_Append            matlab.ui.control.Button
    end

    properties (Constant)
        VER = "1.0.0"
    end

    properties (Access = private)
        caller;    % the parent app

        mask;           % volume mask, true(full image) as default, m*n*p logical tensor
        slice_modified; % the modified slice record vector, p*1 logical vector
        rp_2d;          % the 2d region properties table, with [slice,area,perimeter,centroid]
        rp_3d;          % the 3d region properties table, with [volume,surface area,sphericity,centroid]

        langmap;  % mapping for language
    end

    methods(Access = ?Register)
        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr >= 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_Control.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_Regionprop.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_Regionprop2D.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_Regionprop3D.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.UITable_Regionprop2D.BackgroundColor = constdef.GRAYSCALE_AXES_LIGHT;
                    app.UITable_Regionprop3D.BackgroundColor = constdef.GRAYSCALE_AXES_LIGHT;
                case "DARK"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_Control.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_Regionprop.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_Regionprop2D.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_Regionprop3D.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.UITable_Regionprop2D.BackgroundColor = constdef.GRAYSCALE_AXES_DARK;
                    app.UITable_Regionprop3D.BackgroundColor = constdef.GRAYSCALE_AXES_DARK;
                otherwise
            end
        end

        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            app.Label_Title2D.Text = app.langmap.REGION_PROPERTY_SLICE;
            app.UITable_Regionprop2D.ColumnName = [app.langmap.SLICE, ...
                app.langmap.AREA, app.langmap.PERIMETER, ...
                app.langmap.CENTROID];
            app.Label_Title3D.Text = app.langmap.REGION_PROPERTY_VOLUME;
            app.UITable_Regionprop3D.ColumnName = [app.langmap.VOLUME, ...
                app.langmap.SURFACE_AREA, app.langmap.SPHERICITY, ...
                app.langmap.CENTROID];

            app.Button_Append.Text = app.langmap.APPEND;
            app.Button_Remove.Text = app.langmap.REMOVE;
            app.Button_Inverse.Text = app.langmap.INVERSE;
            app.Button_Export.Text = app.langmap.EXPORT;
        end

        function Close(app)
            delete(app);
        end
    end
    
    methods (Access = private)
        
        function InitData(app, mainapp)
            app.caller = mainapp;
            info = mainapp.GetImgInfo;
            app.mask = false(info.height, info.width, info.slices);
            app.slice_modified = false(info.slices, 1);

            app.rp_2d = MaskGenerator.CreateNewItem();

            app.rp_3d = MaskGenerator.CreateNewItem(struct, "3D");
            
        end

        function InitPanel(app)
            app.UITable_Regionprop2D.RowName = "numbered";
        end

        % refresh the uitable
        function UpdateTable2D(app)
            app.UITable_Regionprop2D.Data = app.rp_2d;
        end

        % refresh the uitable
        function UpdateTable3D(app)
            app.UITable_Regionprop3D.Data = app.rp_3d;
        end
    end

    methods(Static)
        function T = CreateNewItem(stats, type)
            arguments
                stats (1,1) struct = struct;
                type (1,1) string {mustBeMember(type,["2D","3D"])} = "2D";
            end

            switch type
                case "2D"
                    if isStructEqual(stats, struct)
                        T = table('Size',[0 4],...
                            'VariableTypes',{'double','double','double','double'},...
                            'VariableNames',{'Slice','Area','Perimeter','Centroid'});
                    else
                        assert(all(ismember(fieldnames(stats),["Slice","Area","Perimeter","Centroid"]))...
                            && all(ismember(["Slice","Area","Perimeter","Centroid"],fieldnames(stats))),...
                            "CreateNewItem:invalid2DVariables","Variables not match.");
                        T = struct2table(stats);
                    end
                case "3D"
                    if isStructEqual(stats, struct)
                        T = table('Size',[0 4],...
                            'VariableTypes',{'double','double','double','double'},...
                            'VariableNames',{'Volume','SurfaceArea','Sphericity','Centroid'});
                    else
                        assert(all(ismember(fieldnames(stats),["Volume","SurfaceArea","Sphericity","Centroid"]))...
                            && all(ismember(["Volume","SurfaceArea","Sphericity","Centroid"],fieldnames(stats))),...
                            "CreateNewItem:invalid3DVariables","Variables not match.");
                        T = struct2table(stats);
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            InitData(app, mainapp);

            InitPanel(app);

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);
        end

        % Button pushed function: Button_Append
        function Button_AppendPushed(app, event)
            % get the current roi in calling app
            roi = app.caller.GetROI();
            if ~isempty(roi)
                % generate 2d stats
                mask_s = createMask(roi);
                slice = app.caller.GetCurrentSliceIndex();

                stats = regionprops("struct",mask_s,{'Area','Perimeter','Centroid'});
                if numel(stats) > 1
                    warning("MaskGenerator:multiROI", app.langmap.TOO_MUCH_ROI);
                    return;
                end
                
                app.mask(:,:,slice) = mask_s;
                stats.Slice = slice;
                T = MaskGenerator.CreateNewItem(stats);

                % find the slice position
                [ism, slice_pos] = ismember(slice, app.rp_2d.Slice);
                if ~ism
                    app.slice_modified(slice) = true;
                    % append item at bottom
                    app.rp_2d = [app.rp_2d; T];
                else
                    % replace the item
                    T = T(:,["Slice","Area","Perimeter","Centroid"]);
                    app.rp_2d(slice_pos,:) = T;
                end

                % generate 3d stats
                stats = regionprops3(app.mask,{'Volume','Centroid','SurfaceArea'});
                stats = table2struct(stats);
                if numel(stats) > 1
                    stats = stats(1);   % extract the biggest connected component
                    warning("MaskGenerator:volumeSelection", app.langmap.MULTI_CONNECTION);
                end
                stats.Sphericity = (4*pi)^(1/3)*(3*stats.Volume)^(2/3)...
                    /stats.SurfaceArea;
                app.rp_3d = MaskGenerator.CreateNewItem(stats, "3D");
                app.rp_3d = app.rp_3d(:,["Volume","SurfaceArea","Sphericity","Centroid"]);
            else
                uialert(app.MaskManagerV100UIFigure,...
                    app.langmap.CONFIRM_ROI_RANGE,...
                    app.langmap.WARNING,"Icon","warning");
            end

            UpdateTable2D(app);
            UpdateTable3D(app);
        end

        % Button pushed function: Button_Remove
        function Button_RemovePushed(app, event)
            selection = app.UITable_Regionprop2D.Selection;
            if ~isempty(selection)
                app.rp_2d(selection,:) = [];
            end

            selection = app.UITable_Regionprop3D.Selection;
            if ~isempty(selection)
                % means remove all
                app.rp_2d = MaskGenerator.CreateNewItem();
                app.rp_3d(selection,:) = [];
            end

            UpdateTable2D(app);
            UpdateTable3D(app);
        end

        % Button pushed function: Button_Export
        function Button_ExportPushed(app, event)
            % output the mask and table

            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            % try to get the filename
            fname = app.caller.GetFileName();

            if isempty(fname)
                t = datetime('now','TimeZone','local','Format','yyMMddHHmm');
                fname = string(t);
            else
                [~,fname,~] = fileparts(fname);
            end

            [file, path] = uiputfile({'*.mat','MAT File(*.mat)'},app.langmap.EXPORT_MASK,...
                pathToMLAPP+"\"+fname+"_mask.mat");
            
            if path ~= 0
                file_mask = fullfile(path, file);
                if app.Button_Inverse.Value == true
                    mask_to_save = ~app.mask;
                else
                    mask_to_save = app.mask;
                end
                save(file_mask, "mask_to_save");
                file_rp2d = fullfile(path, fname+"_rp2d.csv");
                writetable(app.rp_2d, file_rp2d);
                file_rp3d = fullfile(path, fname+"_rp3d.csv");
                writetable(app.rp_3d, file_rp3d);

                app.caller.SendMessage(["EXPORT_MASK_SUCCESS",""], true);
            end
        end

        % Close request function: MaskManagerV100UIFigure
        function MaskManagerV100UIFigureCloseRequest(app, event)
            Close(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaskManagerV100UIFigure = uifigure('Visible', 'off');
            app.MaskManagerV100UIFigure.Name = 'Mask Manager V1.0.0';
            app.MaskManagerV100UIFigure.Icon = 'mask.png';
            app.MaskManagerV100UIFigure.CloseRequestFcn = createCallbackFcn(app, @MaskManagerV100UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.MaskManagerV100UIFigure);
            app.GridLayout_Control = uigridlayout(app.GridLayout);
            app.Button_Append = uibutton(app.GridLayout_Control, 'push');
            app.Button_Append.ButtonPushedFcn = createCallbackFcn(app, @Button_AppendPushed, true);
            app.Button_Append.Text = '添加';
            app.Button_Remove = uibutton(app.GridLayout_Control, 'push');
            app.Button_Remove.ButtonPushedFcn = createCallbackFcn(app, @Button_RemovePushed, true);
            app.Button_Remove.Text = '移除';
            app.Button_Export = uibutton(app.GridLayout_Control, 'push');
            app.Button_Export.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportPushed, true);
            app.Button_Export.Text = '导出';
            app.Button_Inverse = uibutton(app.GridLayout_Control, 'state');
            app.Button_Inverse.Text = '反相';
            app.GridLayout_Regionprop = uigridlayout(app.GridLayout);
            app.GridLayout_Regionprop2D = uigridlayout(app.GridLayout_Regionprop);
            app.UITable_Regionprop2D = uitable(app.GridLayout_Regionprop2D);
            app.UITable_Regionprop2D.ColumnName = {'片层'; '面积'; '周长'; '质心'};
            app.UITable_Regionprop2D.RowName = {};
            app.UITable_Regionprop2D.SelectionType = 'row';
            app.Label_Title2D = uilabel(app.GridLayout_Regionprop2D);
            app.Label_Title2D.Text = '区域属性(片层)';
            app.GridLayout_Regionprop3D = uigridlayout(app.GridLayout_Regionprop);
            app.UITable_Regionprop3D = uitable(app.GridLayout_Regionprop3D);
            app.UITable_Regionprop3D.ColumnName = {'体积'; '表面积'; '球度'; '质心'};
            app.UITable_Regionprop3D.RowName = {};
            app.UITable_Regionprop3D.SelectionType = 'row';
            app.UITable_Regionprop3D.Multiselect = 'off';
            app.Label_Title3D = uilabel(app.GridLayout_Regionprop3D);
            app.Label_Title3D.Text = '区域属性(体积)';
            % Show the figure after all components are created
            app.MaskManagerV100UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaskGenerator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MaskManagerV100UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MaskManagerV100UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaskManagerV100UIFigure)
        end
    end
end