classdef MovieExporter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VideoExporterUIFigure    matlab.ui.Figure
        Panel_Setting            matlab.ui.container.Panel
        Button_Recover           matlab.ui.control.Button
        Label_FPS                matlab.ui.control.Label
        EditField_Fps            matlab.ui.control.NumericEditField
        Label_8                  matlab.ui.control.Label
        EditField_TargetBitRate  matlab.ui.control.NumericEditField
        Label_7                  matlab.ui.control.Label
        Button_Cancel            matlab.ui.control.Button
        Button_LockWHRatio       matlab.ui.control.StateButton
        Button_Export            matlab.ui.control.Button
        DropDown_Interp          matlab.ui.control.DropDown
        Label_6                  matlab.ui.control.Label
        CheckBox_NoCompress      matlab.ui.control.CheckBox
        Label_Mbps               matlab.ui.control.Label
        Spinner_Quality          matlab.ui.control.Spinner
        Label_4                  matlab.ui.control.Label
        Image_Link               matlab.ui.control.Image
        EditField_Height         matlab.ui.control.NumericEditField
        Label_3                  matlab.ui.control.Label
        EditField_Width          matlab.ui.control.NumericEditField
        Label_2                  matlab.ui.control.Label
        DropDown_MovieType       matlab.ui.control.DropDown
        Label                    matlab.ui.control.Label
    end

    properties (Constant)
        VER = "1.0.0"
    end

    properties (Access = private)
        caller;             % the parent app

        movie_type;         % the movie type, could be motion JPEG AVI/2000, MPEG-4
        width;              % the export width
        width_source;       % the source width
        height;             % the export height
        height_source;      % the source height
        fps;                % the export fps
        quality;            % the movie quality, for AVI and MPEG-4
        compression_ratio;  % the compression ratio
        is_nocompression;   % the no compression flag
        is_rescale;         % the rescale flag
        interp_method;      % the interpolation algorithm
        movie_path;         % the export file path
        movie_name;         % the export file name
        movie_ext;          % the export file ext

        langmap;            % mapping for language
    end

    methods(Access = ?Register)

        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr > 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.Panel_Setting.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                case "DARK"
                    app.Panel_Setting.BackgroundColor = constdef.GRAYSCALE_DARK;
                otherwise
            end
        end

        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();

            app.Panel_Setting.Title = app.langmap.SETTING_PANEL;
            app.Label.Text = app.langmap.MOVIE_FORMAT;
            app.Label_2.Text = app.langmap.WIDTH;
            app.Label_3.Text = app.langmap.HEIGHT;
            app.Label_4.Text = app.langmap.RENDER_QUALITY;
            app.Label_6.Text = app.langmap.INTERPOLATION;
            app.Label_7.Text = app.langmap.TARGET_BITRATE;
            app.Label_8.Text = app.langmap.FRAME_RATE;
            app.Button_Recover.Text = app.langmap.RECOVERY;
            app.CheckBox_NoCompress.Text = app.langmap.LOSSLESS;
            app.Button_Export.Text = app.langmap.EXPORT;
            app.Button_Cancel.Text = app.langmap.CANCEL;

            app.DropDown_Interp.Items = [app.langmap.BILINEAR,...
                                         app.langmap.BICUBIC];

        end

        function RefreshTooltips(app)
        end

        function Close(app)
            delete(app);
        end

    end
    
    methods (Access = private)
        
        function InitData(app, mainapp)
            app.caller = mainapp;
            mov_info = app.caller.GetImgInfo();
            app.width_source = mov_info.width;
            app.height_source = mov_info.height;
            % load the setting
            movie_config = app.caller.GetMovieConfig();
            if isempty(movie_config)
                app.movie_type = "Motion JPEG AVI";
                app.width = app.width_source;
                app.height = app.height_source;
                app.fps = 25;
                app.quality = 75;
                app.is_nocompression = false;
                app.is_rescale = false;
                app.compression_ratio = 5;  % should be calculated by bit rate
                app.interp_method = "bilinear";
                filename = app.caller.GetFileName();
                [app.movie_path, app.movie_name,~] = fileparts(filename.char());
                app.movie_ext = '.avi';
            else
                app.movie_type = movie_config.movie_type;
                app.width = movie_config.width;
                app.height = movie_config.height;
                app.fps = movie_config.fps;
                app.quality = movie_config.quality;
                app.is_nocompression = movie_config.is_nocompression;
                app.is_rescale = movie_config.is_rescale;
                app.compression_ratio = movie_config.compression_ratio;
                app.interp_method = movie_config.interp_method;
                [app.movie_path, app.movie_name, app.movie_ext] = ...
                    fileparts(movie_config.movie_name);
            end
        end

        function InitPanel(app)
            app.DropDown_MovieType.Value = app.movie_type;
            app.EditField_Width.Value = app.width;
            app.EditField_Height.Value = app.height;
            app.EditField_Fps.Value = app.fps;
            app.Spinner_Quality.Value = app.quality;
            app.CheckBox_NoCompress.Value = app.is_nocompression;
            app.EditField_TargetBitRate.Value = round(...
                app.width*app.height*app.fps*24/1048576, 2);
            app.DropDown_Interp.Value = app.interp_method;
            app.CheckBox_NoCompress.Enable = "off";
            app.EditField_TargetBitRate.Enable = "off";
        end

        function SetCallingAppData(app, mode)
            switch mode
                case "submit"
                    if app.width~=app.width_source || app.height~=app.height_source
                        app.is_rescale = true;
                    end
                    fname = fullfile(app.movie_path, [app.movie_name, app.movie_ext]);

                    movie_config = struct("movie_type",         app.movie_type, ...
                                          "width",              app.width, ...
                                          "height",             app.height, ...
                                          "fps",                app.fps, ...
                                          "quality",            app.quality, ...
                                          "is_nocompression",   app.is_nocompression, ...
                                          "is_rescale",         app.is_rescale, ...
                                          "compression_ratio",  app.compression_ratio, ...
                                          "interp_method",      app.interp_method, ...
                                          "movie_name",         fname);

                case "cancel"
                    movie_config = struct([]);
            end

            app.caller.SetMovieConfig(movie_config);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            InitData(app, mainapp);

            InitPanel(app);

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);

            RefreshTooltips(app);
        end

        % Value changed function: Button_LockWHRatio
        function Button_LockWHRatioValueChanged(app, event)
            value = app.Button_LockWHRatio.Value;
            
            if value == true
                app.Button_LockWHRatio.Icon = "sources/lock.png";
            else
                app.Button_LockWHRatio.Icon = "sources/unlock.png";
            end
        end

        % Button pushed function: Button_Export
        function Button_ExportPushed(app, event)
            [file, path] = uiputfile({'*.avi','AVI 文件(*.avi)';...
                '*.mj2','Motion JPEG 2000 文件(*.mj2)';...
                '*.mp4;*.m4v','MPEG-4 文件(*.mp4,*.m4v)'},...
                "导出影片",fullfile(app.movie_path, [app.movie_name, app.movie_ext]));
            if path ~= 0
                [~,file,ext] = fileparts(file);
                app.movie_name = file;
                app.movie_path = path;
                app.movie_ext = ext;
                SetCallingAppData(app, "submit");
                delete(app);
            else
                return;
            end
        end

        % Button pushed function: Button_Cancel
        function Button_CancelPushed(app, event)
            SetCallingAppData(app, "cancel");
            Close(app);
        end

        % Value changed function: DropDown_MovieType
        function DropDown_MovieTypeValueChanged(app, event)
            value = app.DropDown_MovieType.Value;
            app.movie_type = value;

            switch value
                case "Motion JPEG 2000"
                    app.EditField_TargetBitRate.Enable = "on";
                    app.CheckBox_NoCompress.Enable = "on";
                    app.Spinner_Quality.Enable = "off";
                    app.movie_ext = '.mj2';
                otherwise
                    app.EditField_TargetBitRate.Enable = "off";
                    app.CheckBox_NoCompress.Enable = "off";
                    app.Spinner_Quality.Enable = "on";
                    if value == "Motion JPEG AVI"
                        app.movie_ext = '.avi';
                    elseif value == "MPEG-4"
                        app.movie_ext = '.mp4';
                    elseif value == "Uncompressed AVI"
                        app.movie_ext = '.avi';
                        app.Spinner_Quality.Enable = "off";
                    end
            end
        end

        % Value changed function: EditField_Width
        function EditField_WidthValueChanged(app, event)
            value = app.EditField_Width.Value;
            app.width = value;
            if app.Button_LockWHRatio.Value == true
                % keep the width heigth ratio
                app.height = round(app.width*app.height_source/app.width_source);
                app.EditField_Height.Value = app.height;
            end
        end

        % Value changed function: EditField_Height
        function EditField_HeightValueChanged(app, event)
            value = app.EditField_Height.Value;
            app.height = value;
            if app.Button_LockWHRatio.Value == true
                % keep the width heigth ratio
                app.width = round(app.height*app.width_source/app.height_source);
                app.EditField_Width.Value = app.width;
            end
        end

        % Value changed function: EditField_Fps
        function EditField_FpsValueChanged(app, event)
            value = app.EditField_Fps.Value;
            app.fps = value;
        end

        % Value changed function: Spinner_Quality
        function Spinner_QualityValueChanged(app, event)
            value = app.Spinner_Quality.Value;
            app.quality = value;
        end

        % Value changed function: CheckBox_NoCompress
        function CheckBox_NoCompressValueChanged(app, event)
            value = app.CheckBox_NoCompress.Value;
            app.is_nocompression = value;
            if value == true
                app.EditField_TargetBitRate.Enable = "off";
            else
                app.EditField_TargetBitRate.Enable = "on";
            end
        end

        % Value changed function: EditField_TargetBitRate
        function EditField_TargetBitRateValueChanged(app, event)
            value = app.EditField_TargetBitRate.Value;  % target
            % calculate the real bit rate
            bit_rate = app.width*app.height*24*app.fps/1048576;

            app.compression_ratio = bit_rate / value;
        end

        % Value changed function: DropDown_Interp
        function DropDown_InterpValueChanged(app, event)
            value = app.DropDown_Interp.Value;
            switch value
                case "最近邻"
                    app.interp_method = "nearest";
                case "双线性"
                    app.interp_method = "bilinear";
                case "双立方"
                    app.interp_method = "bicubic";
            end
        end

        % Button pushed function: Button_Recover
        function Button_RecoverPushed(app, event)
            app.EditField_Width.Value = app.width_source;
            app.EditField_Height.Value = app.height_source;
        end

        % Close request function: VideoExporterUIFigure
        function VideoExporterUIFigureCloseRequest(app, event)
            Close(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VideoExporterUIFigure = uifigure('Visible', 'off');
            app.VideoExporterUIFigure.Name = 'Video Exporter';
            app.VideoExporterUIFigure.Icon = 'exporter.png';
            app.VideoExporterUIFigure.CloseRequestFcn = createCallbackFcn(app, @VideoExporterUIFigureCloseRequest, true);
            app.Panel_Setting = uipanel(app.VideoExporterUIFigure);
            app.Panel_Setting.Title = '设置面板';
            app.Label = uilabel(app.Panel_Setting);
            app.Label.Text = '影片格式';
            app.DropDown_MovieType = uidropdown(app.Panel_Setting);
            app.DropDown_MovieType.Items = {'Motion JPEG AVI', 'Motion JPEG 2000', 'MPEG-4', 'Uncompressed AVI'};
            app.DropDown_MovieType.ValueChangedFcn = createCallbackFcn(app, @DropDown_MovieTypeValueChanged, true);
            app.DropDown_MovieType.Value = 'Motion JPEG AVI';
            app.Label_2 = uilabel(app.Panel_Setting);
            app.Label_2.Text = '宽';
            app.EditField_Width = uieditfield(app.Panel_Setting, 'numeric');
            app.EditField_Width.Limits = [1 8192];
            app.EditField_Width.ValueDisplayFormat = '%.0f';
            app.EditField_Width.ValueChangedFcn = createCallbackFcn(app, @EditField_WidthValueChanged, true);
            app.EditField_Width.Value = 1024;
            app.Label_3 = uilabel(app.Panel_Setting);
            app.Label_3.Text = '高';
            app.EditField_Height = uieditfield(app.Panel_Setting, 'numeric');
            app.EditField_Height.Limits = [1 8192];
            app.EditField_Height.ValueDisplayFormat = '%.0f';
            app.EditField_Height.ValueChangedFcn = createCallbackFcn(app, @EditField_HeightValueChanged, true);
            app.EditField_Height.Value = 1024;
            app.Image_Link = uiimage(app.Panel_Setting);
            app.Image_Link.ImageSource = 'linkicon.png';
            app.Label_4 = uilabel(app.Panel_Setting);
            app.Label_4.Text = '渲染质量';
            app.Spinner_Quality = uispinner(app.Panel_Setting);
            app.Spinner_Quality.Limits = [0 100];
            app.Spinner_Quality.ValueDisplayFormat = '%.0f';
            app.Spinner_Quality.ValueChangedFcn = createCallbackFcn(app, @Spinner_QualityValueChanged, true);
            app.Spinner_Quality.Value = 75;
            app.Label_Mbps = uilabel(app.Panel_Setting);
            app.Label_Mbps.Text = 'Mbps';
            app.CheckBox_NoCompress = uicheckbox(app.Panel_Setting);
            app.CheckBox_NoCompress.ValueChangedFcn = createCallbackFcn(app, @CheckBox_NoCompressValueChanged, true);
            app.CheckBox_NoCompress.Text = '无损';
            app.Label_6 = uilabel(app.Panel_Setting);
            app.Label_6.Text = '插值';
            app.DropDown_Interp = uidropdown(app.Panel_Setting);
            app.DropDown_Interp.Items = {'双线性', '双立方'};
            app.DropDown_Interp.ItemsData = {'bilinear', 'bicubic'};
            app.DropDown_Interp.ValueChangedFcn = createCallbackFcn(app, @DropDown_InterpValueChanged, true);
            app.DropDown_Interp.Value = 'bilinear';
            app.Button_Export = uibutton(app.Panel_Setting, 'push');
            app.Button_Export.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportPushed, true);
            app.Button_Export.Text = '导出';
            app.Button_LockWHRatio = uibutton(app.Panel_Setting, 'state');
            app.Button_LockWHRatio.ValueChangedFcn = createCallbackFcn(app, @Button_LockWHRatioValueChanged, true);
            app.Button_LockWHRatio.Icon = 'lock.png';
            app.Button_LockWHRatio.Text = '';
            app.Button_LockWHRatio.Value = true;
            app.Button_Cancel = uibutton(app.Panel_Setting, 'push');
            app.Button_Cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_CancelPushed, true);
            app.Button_Cancel.Text = '取消';
            app.Label_7 = uilabel(app.Panel_Setting);
            app.Label_7.Text = '目标码率';
            app.EditField_TargetBitRate = uieditfield(app.Panel_Setting, 'numeric');
            app.EditField_TargetBitRate.Limits = [0 Inf];
            app.EditField_TargetBitRate.RoundFractionalValues = 'on';
            app.EditField_TargetBitRate.ValueDisplayFormat = '%.2f';
            app.EditField_TargetBitRate.ValueChangedFcn = createCallbackFcn(app, @EditField_TargetBitRateValueChanged, true);
            app.EditField_TargetBitRate.Value = 10;
            app.Label_8 = uilabel(app.Panel_Setting);
            app.Label_8.Text = '帧率';
            app.EditField_Fps = uieditfield(app.Panel_Setting, 'numeric');
            app.EditField_Fps.Limits = [0 Inf];
            app.EditField_Fps.ValueChangedFcn = createCallbackFcn(app, @EditField_FpsValueChanged, true);
            app.EditField_Fps.Value = 25;
            app.Label_FPS = uilabel(app.Panel_Setting);
            app.Label_FPS.Text = 'FPS';
            app.Button_Recover = uibutton(app.Panel_Setting, 'push');
            app.Button_Recover.ButtonPushedFcn = createCallbackFcn(app, @Button_RecoverPushed, true);
            app.Button_Recover.Text = '还原';
            % Show the figure after all components are created
            app.VideoExporterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MovieExporter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.VideoExporterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.VideoExporterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VideoExporterUIFigure)
        end
    end
end