classdef NewProjectConfigurator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfiguratorUIFigure          matlab.ui.Figure
        GridLayout_Main               matlab.ui.container.GridLayout
        GridLayout6                   matlab.ui.container.GridLayout
        EditField_CacheFolderName     matlab.ui.control.EditField
        Label_4                       matlab.ui.control.Label
        CheckBox_NameAsProject        matlab.ui.control.CheckBox
        GridLayout11                  matlab.ui.container.GridLayout
        Image_DataProtectedTooltip    matlab.ui.control.Image
        Switch_DataProtected          matlab.ui.control.Switch
        Label_8                       matlab.ui.control.Label
        Image_CachePolicyTooltip      matlab.ui.control.Image
        DropDown_CachePolicy          matlab.ui.control.DropDown
        Label_6                       matlab.ui.control.Label
        GridLayout7                   matlab.ui.container.GridLayout
        Image_WaveLengthTooltip       matlab.ui.control.Image
        GridLayout10                  matlab.ui.container.GridLayout
        nmLabel_3                     matlab.ui.control.Label
        EditField_WaveLength_B        matlab.ui.control.NumericEditField
        bEditFieldLabel               matlab.ui.control.Label
        GridLayout9                   matlab.ui.container.GridLayout
        nmLabel_2                     matlab.ui.control.Label
        EditField_WaveLength_G        matlab.ui.control.NumericEditField
        gEditFieldLabel               matlab.ui.control.Label
        GridLayout8                   matlab.ui.container.GridLayout
        nmLabel                       matlab.ui.control.Label
        EditField_WaveLength_R        matlab.ui.control.NumericEditField
        rLabel                        matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        GridLayout5                   matlab.ui.container.GridLayout
        CheckBox_PostponeImport       matlab.ui.control.CheckBox
        Button_Cancel                 matlab.ui.control.Button
        Button_Create                 matlab.ui.control.Button
        Button_ProjectSetting         matlab.ui.control.StateButton
        GridLayout4                   matlab.ui.container.GridLayout
        Image_ProjectTemplateTooltip  matlab.ui.control.Image
        DropDown_ProjectTemplate      matlab.ui.control.DropDown
        Label_3                       matlab.ui.control.Label
        GridLayout3                   matlab.ui.container.GridLayout
        DropDown_ProjectLocation      matlab.ui.control.DropDown
        Label_2                       matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        EditField_ProjectName         matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
    end

    properties (Constant)
        VER = "1.0.0"
    end

    properties (Constant, Hidden)
        FIGURE_HEIGHT_EXPAND = 450
        FIGURE_HEIGHT_COLLAPSE = 250
    end

    properties (Access = private)
        caller      % 1-by-1 Register object
        pos_caller  % 1-by-4 double array with integer
        config      % 1-by-1 struct with project configuration (local setting)
        profile     % 1-by-1 struct with project profile (global setting)
        langmap     % 1-by-1 struct with language mapping
    end
    
    methods (Access = ?Register)
        
        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr > 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.GridLayout_Main.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout7.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout8.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout9.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout10.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout11.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                case "DARK"
                    app.GridLayout_Main.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout7.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout8.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout9.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout10.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout11.BackgroundColor = constdef.GRAYSCALE_DARK;
                otherwise
            end
        end

        function RefreshPanelLanguage(app)
            % get language map
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            % apply languages
            app.Label.Text = app.langmap.PROJECT_NAME;
            app.Label_2.Text = app.langmap.POSITION;
            app.Label_3.Text = app.langmap.TEMPLATE;
            app.Label_4.Text = app.langmap.CACHE_FOLDER;
            app.Label_5.Text = app.langmap.IMAGE_COLOR_CONFIGURATION;
            app.Label_6.Text = app.langmap.CACHE_POLICY;
            app.Label_8.Text = app.langmap.DATA_PROTECTED;
            app.CheckBox_NameAsProject.Text = app.langmap.USE_PROJECT_NAME;
            app.CheckBox_PostponeImport.Text = app.langmap.IMPORT_DATA_DELAY;
            app.Button_Create.Text = app.langmap.CREATION;
            app.Button_Cancel.Text = app.langmap.CANCEL;
            app.DropDown_CachePolicy.Items = [app.langmap.PERFORMANCE, ...
                app.langmap.RESOURCES, app.langmap.BALANCE];
            app.Switch_DataProtected.Items = [app.langmap.DISABLE, ...
                app.langmap.ENABLE];
        end

        function RefreshTooltips(app)
            tl = app.caller.GetAppTipsLevel();
            switch tl
                case constdef.TOOLTIP_SIMPLE
                    app.Image_ProjectTemplateTooltip.Tooltip ...
                        = app.langmap.TIP_PROJECT_TEMPLATE;
                    app.Image_CachePolicyTooltip.Tooltip ...
                        = app.langmap.TIP_CACHE_POLICY;
                    app.Image_DataProtectedTooltip.Tooltip ...
                        = app.langmap.TIP_DATA_PROTECTION;
                    app.Image_WaveLengthTooltip.Tooltip ...
                        = app.langmap.TIP_WAVELENGTH;
                case constdef.TOOLTIP_DETAIL
                    app.Image_ProjectTemplateTooltip.Tooltip ...
                        = app.langmap.TIP_PROJECT_TEMPLATE;
                    app.Image_CachePolicyTooltip.Tooltip ...
                        = app.langmap.TIP_CACHE_POLICY;
                    app.Image_DataProtectedTooltip.Tooltip ...
                        = app.langmap.TIP_DATA_PROTECTION;
                    app.Image_WaveLengthTooltip.Tooltip ...
                        = app.langmap.TIP_WAVELENGTH;
                case constdef.TOOLTIP_NONE
                    app.Image_ProjectTemplateTooltip.Tooltip = '';
                    app.Image_CachePolicyTooltip.Tooltip = '';
                    app.Image_DataProtectedTooltip.Tooltip = '';
                    app.Image_WaveLengthTooltip.Tooltip = '';
                otherwise
            end
        end
    end

    methods (Access = private)
        function InitData(app)
            % get current config from caller
            app.profile = app.caller.GetProjectProfile();
            app.config = app.caller.GetProjectConfig();

            app.pos_caller = app.caller.GetAppPosition();

            % apply
            app.EditField_ProjectName.Value = app.config.ProjectName;
            app.DropDown_ProjectLocation.Items = ...
                [app.profile.ProjectFolders; "Select Location"];
            n_items_exist = numel(app.DropDown_ProjectLocation.Items) - 1;
            app.DropDown_ProjectLocation.ItemsData = string([(1:n_items_exist)'; 0]);
            app.DropDown_ProjectTemplate.Value = app.config.Template;
            app.EditField_CacheFolderName.Value = app.config.ProjectName;
            app.DropDown_CachePolicy.Value = app.config.CachePolicy;
            app.Switch_DataProtected.Value = app.config.DataProtected;

            app.EditField_WaveLength_R.Value = app.config.WaveLength(1);
            app.EditField_WaveLength_G.Value = app.config.WaveLength(2);
            app.EditField_WaveLength_B.Value = app.config.WaveLength(3);
        end

        function InitAppearance(app)
            SetupWindowPosition(app);

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);

            RefreshTooltips(app);

            UpdatePanelView(app);
        end

        function SetupWindowPosition(app)
            x0 = app.pos_caller(1); y0 = app.pos_caller(2);
            w0 = app.pos_caller(3); h0 = app.pos_caller(4);
            w = app.ConfiguratorUIFigure.Position(3);
            h = app.ConfiguratorUIFigure.Position(4);
            x = ceil(x0 + 0.5*(w0 - w));
            y = ceil(y0 + 0.5*(h0 - h));
            app.ConfiguratorUIFigure.Position(1) = x;
            app.ConfiguratorUIFigure.Position(2) = y;
        end

        function UpdatePanelView(app)
            c = WaveLength2RGB(app.config.WaveLength(1));
            app.EditField_WaveLength_R.FontColor = c/255;
            app.rLabel.FontColor = c/255;

            c = WaveLength2RGB(app.config.WaveLength(2));
            app.EditField_WaveLength_G.FontColor = c/255;
            app.gEditFieldLabel.FontColor = c/255;

            c = WaveLength2RGB(app.config.WaveLength(3));
            app.EditField_WaveLength_B.FontColor = c/255;
            app.bEditFieldLabel.FontColor = c/255;
        end

        function Close(app)
            % ~
            delete(app)
        end
    end

    methods (Static)
        function r = ValidateFileName(s)
            arguments
                s   (1,1)   string
            end

            if ispc()
                r1 = all(~s.contains(["<", ">", ":", """", "/", "\", ...
                    "|", "?", "*"]));
                r2 = all(~ismember(s, ["CON", "PRN", "AUX", "NUL", "COM1", ...
                    "COM2", "COM3", "COM4", "COM5", "COM6", "COM7", "COM8", ...
                    "COM9", "LPT1", "LPT2", "LPT3", "LPT4", "LPT5", "LPT6", ...
                    "LPT7", "LPT8", "LPT9"]));
                r = r1 && r2;
            else
                % treat as unix
                r1 = all(~s.contains([" ", "/", "*", "?", "&", "|", """", ...
                    ":", ";", ",", "!", "(", ")", "'", "@", "#", "$", "%", ...
                    "^", "[", "]", "{", "}", "?", "\", "~", "`"]));
                r2 = all(~ismember(s, ["bin", "sbin", "etc"]));
                r = r1 && r2;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.caller = caller;

            InitData(app);

            InitAppearance(app);
        end

        % Value changed function: DropDown_ProjectLocation
        function DropDown_ProjectLocationValueChanged(app, event)
            value = app.DropDown_ProjectLocation.Value;
            
            switch value
                case "0"
                    % select and append new location
                    folder = uigetdir(userpath, app.langmap.PROJECT_FOLDER_SELECTION);
                    if ~isnumeric(folder)
                        folder = string(folder);
                        [p, loc] = ismember(folder, string(app.DropDown_ProjectLocation.Items));
                        if ~p
                            app.DropDown_ProjectLocation.Items = ...
                                [app.DropDown_ProjectLocation.Items(1:end-1), ...
                                folder, ...
                                "Select Location"];
                            n_items_exist = numel(app.DropDown_ProjectLocation.Items)-1;
                            app.DropDown_ProjectLocation.ItemsData = ...
                                string([(1:n_items_exist)'; 0]);
                            app.DropDown_ProjectLocation.Value = string(n_items_exist);
                        else
                            app.DropDown_ProjectLocation.Value = ...
                                string(loc);
                        end
                    end
                otherwise
                    % skip
            end
        end

        % Button pushed function: Button_Create
        function Button_CreatePushed(app, event)
            % send config back to Register
            try
                % validate the project name
                project_name = string(app.EditField_ProjectName.Value);
                if NewProjectConfigurator.ValidateFileName(project_name)
                    pf_loc = (app.DropDown_ProjectLocation.Value ...
                        == app.DropDown_ProjectLocation.ItemsData);
                    project_root_folder = string(app.DropDown_ProjectLocation.Items(pf_loc));

                    caller_profile = app.caller.GetProfile();
                    % avoid the same folder
                    if caller_profile.CacheRootFolder == project_root_folder
                        uialert(app.ConfiguratorUIFigure, app.langmap.INVALID_FOLDERS_PROFILE, ...
                            app.langmap.ERROR, "Icon","error");
                        return;
                    end

                    project_folder = project_root_folder + filesep + project_name;

                    if isfolder(project_folder)
                        slt = uiconfirm(app.ConfiguratorUIFigure, ...
                            app.langmap.REPLACE_EXIST_PROJECT, ...
                            app.langmap.WARNING, "Icon","warning", ...
                            "Options", [app.langmap.REPLACE, app.langmap.CANCEL], ...
                            "DefaultOption", app.langmap.CANCEL);

                        switch slt
                            case app.langmap.CANCEL
                                return;
                            otherwise
                                % go ahead
                        end
                    end

                    %
                    app.config.ProjectName = project_name;
                    app.config.ProjectFolder = project_folder;

                    cache_folder = app.EditField_CacheFolderName.Value;
                    if NewProjectConfigurator.ValidateFileName(cache_folder)
                        app.config.CacheFolder = string(cache_folder);
                    else
                        uialert(app.ConfiguratorUIFigure, app.langmap.INVALID_FOLDER_NAME, ...
                            app.langmap.ERROR, "Icon","error", "Modal",true);
                        return;
                    end
                    app.config.Template = string(app.DropDown_ProjectTemplate.Value);
                    app.config.CachePolicy = string(app.DropDown_CachePolicy.Value);
                    app.config.DataProtected = string(app.Switch_DataProtected.Value);
                    app.config.WaveLength = [app.EditField_WaveLength_R.Value, ...
                                             app.EditField_WaveLength_G.Value, ...
                                             app.EditField_WaveLength_B.Value];
                    post_import = app.CheckBox_PostponeImport.Value;

                    % set caller state
                    app.caller.SetProjectConfig(app.config, true, post_import);

                    % close this
                    Close(app);
                else
                    % ERROR: display at anytime
                    uialert(app.ConfiguratorUIFigure, app.langmap.INVALID_FILE_NAME, ...
                        app.langmap.ERROR, "Icon","error", "Modal",true);
                end
            catch ME
                app.caller.PushInfo("NewProjectConfigurator", "Button_CreatePushed", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Button pushed function: Button_Cancel
        function Button_CancelPushed(app, event)
            % set no update flag
            app.caller.SetProjectConfig(app.config, false);

            % and close
            Close(app);
        end

        % Close request function: ConfiguratorUIFigure
        function ConfiguratorUIFigureCloseRequest(app, event)
            % set no update flag
            app.caller.SetProjectConfig(app.config, false);

            % and close
            Close(app);
        end

        % Value changed function: Button_ProjectSetting
        function Button_ProjectSettingValueChanged(app, event)
            value = app.Button_ProjectSetting.Value;
            
            if value == true
                % expand the optional settings
                app.ConfiguratorUIFigure.Position(4) = app.FIGURE_HEIGHT_EXPAND;
                app.GridLayout_Main.RowHeight = {'1x','1x','1x','1x','1x','1x', 25};
            else
                % collapse the optional settings
                app.ConfiguratorUIFigure.Position(4) = app.FIGURE_HEIGHT_COLLAPSE;
                app.GridLayout_Main.RowHeight = {'1x','1x','1x', 0, 0, 0, 25};
            end

            drawnow
        end

        % Value changed function: CheckBox_NameAsProject
        function CheckBox_NameAsProjectValueChanged(app, event)
            value = app.CheckBox_NameAsProject.Value;
            
            if value == true
                app.EditField_CacheFolderName.Editable = false;
                app.EditField_CacheFolderName.Value = app.EditField_ProjectName.Value;
            else
                app.EditField_CacheFolderName.Editable = true;
            end
        end

        % Value changed function: EditField_WaveLength_R
        function EditField_WaveLength_RValueChanged(app, event)
            value = app.EditField_WaveLength_R.Value;
            c = WaveLength2RGB(value);

            app.EditField_WaveLength_R.FontColor = c/255;
            app.rLabel.FontColor = c/255;
        end

        % Value changed function: EditField_WaveLength_G
        function EditField_WaveLength_GValueChanged(app, event)
            value = app.EditField_WaveLength_G.Value;
            c = WaveLength2RGB(value);

            app.EditField_WaveLength_G.FontColor = c/255;
            app.gEditFieldLabel.FontColor = c/255;
        end

        % Value changed function: EditField_WaveLength_B
        function EditField_WaveLength_BValueChanged(app, event)
            value = app.EditField_WaveLength_B.Value;
            c = WaveLength2RGB(value);

            app.EditField_WaveLength_B.FontColor = c/255;
            app.bEditFieldLabel.FontColor = c/255;
        end

        % Value changing function: EditField_ProjectName
        function EditField_ProjectNameValueChanging(app, event)
            changingValue = event.Value;
            if app.CheckBox_NameAsProject.Value == true
                app.EditField_CacheFolderName.Value = changingValue;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfiguratorUIFigure = uifigure('Visible', 'off');
            app.ConfiguratorUIFigure.Name = 'Configurator';
            app.ConfiguratorUIFigure.Icon = 'new_project.png';
            app.ConfiguratorUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConfiguratorUIFigureCloseRequest, true);
            app.GridLayout_Main = uigridlayout(app.ConfiguratorUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout_Main);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '项目名';
            app.EditField_ProjectName = uieditfield(app.GridLayout2, 'text');
            app.EditField_ProjectName.CharacterLimits = [0 255];
            app.EditField_ProjectName.ValueChangingFcn = createCallbackFcn(app, @EditField_ProjectNameValueChanging, true);
            app.EditField_ProjectName.Value = 'Untitled';
            app.GridLayout3 = uigridlayout(app.GridLayout_Main);
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '位置';
            app.DropDown_ProjectLocation = uidropdown(app.GridLayout3);
            app.DropDown_ProjectLocation.Items = {'/home/Reg3D Projects', 'Select Position'};
            app.DropDown_ProjectLocation.ItemsData = {'1', '0'};
            app.DropDown_ProjectLocation.ValueChangedFcn = createCallbackFcn(app, @DropDown_ProjectLocationValueChanged, true);
            app.DropDown_ProjectLocation.Value = '1';
            app.GridLayout4 = uigridlayout(app.GridLayout_Main);
            app.Label_3 = uilabel(app.GridLayout4);
            app.Label_3.Text = '模板';
            app.DropDown_ProjectTemplate = uidropdown(app.GridLayout4);
            app.DropDown_ProjectTemplate.Items = {'None', 'Standard Registration Workflow.regproj', 'Media Workflow.regproj', 'Developer Workflow.regproj'};
            app.DropDown_ProjectTemplate.ItemsData = {'NONE', 'STDREG', 'MEDIA', 'DEV'};
            app.DropDown_ProjectTemplate.Value = 'NONE';
            app.Image_ProjectTemplateTooltip = uiimage(app.GridLayout4);
            app.Image_ProjectTemplateTooltip.ImageSource = 'info.png';
            app.GridLayout5 = uigridlayout(app.GridLayout_Main);
            app.Button_ProjectSetting = uibutton(app.GridLayout5, 'state');
            app.Button_ProjectSetting.ValueChangedFcn = createCallbackFcn(app, @Button_ProjectSettingValueChanged, true);
            app.Button_ProjectSetting.Icon = 'setting_single.png';
            app.Button_ProjectSetting.Text = '';
            app.Button_Create = uibutton(app.GridLayout5, 'push');
            app.Button_Create.ButtonPushedFcn = createCallbackFcn(app, @Button_CreatePushed, true);
            app.Button_Create.Text = '创建';
            app.Button_Cancel = uibutton(app.GridLayout5, 'push');
            app.Button_Cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_CancelPushed, true);
            app.Button_Cancel.Text = '取消';
            app.CheckBox_PostponeImport = uicheckbox(app.GridLayout5);
            app.CheckBox_PostponeImport.Text = '稍后导入数据';
            app.GridLayout7 = uigridlayout(app.GridLayout_Main);
            app.Label_5 = uilabel(app.GridLayout7);
            app.Label_5.Text = '图像颜色配置';
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.rLabel = uilabel(app.GridLayout8);
            app.rLabel.Text = 'r';
            app.EditField_WaveLength_R = uieditfield(app.GridLayout8, 'numeric');
            app.EditField_WaveLength_R.Limits = [380 780];
            app.EditField_WaveLength_R.ValueChangedFcn = createCallbackFcn(app, @EditField_WaveLength_RValueChanged, true);
            app.EditField_WaveLength_R.Value = 694;
            app.nmLabel = uilabel(app.GridLayout8);
            app.nmLabel.Text = 'nm';
            app.GridLayout9 = uigridlayout(app.GridLayout7);
            app.gEditFieldLabel = uilabel(app.GridLayout9);
            app.gEditFieldLabel.Text = 'g';
            app.EditField_WaveLength_G = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_WaveLength_G.Limits = [380 780];
            app.EditField_WaveLength_G.ValueChangedFcn = createCallbackFcn(app, @EditField_WaveLength_GValueChanged, true);
            app.EditField_WaveLength_G.Value = 525;
            app.nmLabel_2 = uilabel(app.GridLayout9);
            app.nmLabel_2.Text = 'nm';
            app.GridLayout10 = uigridlayout(app.GridLayout7);
            app.bEditFieldLabel = uilabel(app.GridLayout10);
            app.bEditFieldLabel.Text = 'b';
            app.EditField_WaveLength_B = uieditfield(app.GridLayout10, 'numeric');
            app.EditField_WaveLength_B.Limits = [380 780];
            app.EditField_WaveLength_B.ValueChangedFcn = createCallbackFcn(app, @EditField_WaveLength_BValueChanged, true);
            app.EditField_WaveLength_B.Value = 440;
            app.nmLabel_3 = uilabel(app.GridLayout10);
            app.nmLabel_3.Text = 'nm';
            app.Image_WaveLengthTooltip = uiimage(app.GridLayout7);
            app.Image_WaveLengthTooltip.ImageSource = 'info.png';
            app.GridLayout11 = uigridlayout(app.GridLayout_Main);
            app.Label_6 = uilabel(app.GridLayout11);
            app.Label_6.Text = '缓存策略';
            app.DropDown_CachePolicy = uidropdown(app.GridLayout11);
            app.DropDown_CachePolicy.Items = {'性能', '资源', '平衡'};
            app.DropDown_CachePolicy.ItemsData = {'PERFORMANCE', 'RESOURCE', 'BALANCE'};
            app.DropDown_CachePolicy.Value = 'PERFORMANCE';
            app.Image_CachePolicyTooltip = uiimage(app.GridLayout11);
            app.Image_CachePolicyTooltip.ImageSource = 'info.png';
            app.Label_8 = uilabel(app.GridLayout11);
            app.Label_8.Text = '数据保护';
            app.Switch_DataProtected = uiswitch(app.GridLayout11, 'slider');
            app.Switch_DataProtected.Items = {'禁用', '启用'};
            app.Switch_DataProtected.ItemsData = {'OFF', 'ON'};
            app.Switch_DataProtected.Enable = 'off';
            app.Switch_DataProtected.Value = 'OFF';
            app.Image_DataProtectedTooltip = uiimage(app.GridLayout11);
            app.Image_DataProtectedTooltip.ImageSource = 'info.png';
            app.GridLayout6 = uigridlayout(app.GridLayout_Main);
            app.CheckBox_NameAsProject = uicheckbox(app.GridLayout6);
            app.CheckBox_NameAsProject.ValueChangedFcn = createCallbackFcn(app, @CheckBox_NameAsProjectValueChanged, true);
            app.CheckBox_NameAsProject.Text = '使用项目名';
            app.CheckBox_NameAsProject.Value = true;
            app.Label_4 = uilabel(app.GridLayout6);
            app.Label_4.Text = '缓存文件夹';
            app.EditField_CacheFolderName = uieditfield(app.GridLayout6, 'text');
            app.EditField_CacheFolderName.Editable = 'off';
            app.EditField_CacheFolderName.Value = 'Untitled';
            % Show the figure after all components are created
            app.ConfiguratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewProjectConfigurator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConfiguratorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ConfiguratorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfiguratorUIFigure)
        end
    end
end