classdef OCRegSetter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OCRSUIFigure                   matlab.ui.Figure
        TabGroup_AlgorithmSelection    matlab.ui.container.TabGroup
        Tab_Global                     matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup_Global                matlab.ui.container.TabGroup
        Tab_NS_Global                  matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        Panel_NS_Reg_Global            matlab.ui.container.Panel
        GridLayout_NS_Global           matlab.ui.container.GridLayout
        GridLayout71                   matlab.ui.container.GridLayout
        DropDown                       matlab.ui.control.DropDown
        Label_68                       matlab.ui.control.Label
        GridLayout67                   matlab.ui.container.GridLayout
        Spinner_ErodeRadius_Global     matlab.ui.control.Spinner
        Label_67                       matlab.ui.control.Label
        EditField_VT_Global            matlab.ui.control.NumericEditField
        Label_66                       matlab.ui.control.Label
        GridLayout66                   matlab.ui.container.GridLayout
        DropDown_Detector_Global       matlab.ui.control.DropDown
        Label_65                       matlab.ui.control.Label
        DropDown_CoarseAlg_Global      matlab.ui.control.DropDown
        Label_64                       matlab.ui.control.Label
        GridLayout14                   matlab.ui.container.GridLayout
        Image_CPU                      matlab.ui.control.Image
        GridLayout15                   matlab.ui.container.GridLayout
        EditField_MaxStep_Global       matlab.ui.control.NumericEditField
        Label_6                        matlab.ui.control.Label
        EditField_MinStep_Global       matlab.ui.control.NumericEditField
        Label_5                        matlab.ui.control.Label
        GridLayout12                   matlab.ui.container.GridLayout
        DropDown_TformType_Global      matlab.ui.control.DropDown
        Label_2                        matlab.ui.control.Label
        DropDown_TemplateSampling_Global  matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        GridLayout11                   matlab.ui.container.GridLayout
        CheckBox_AutoUpdateTemplate_Global  matlab.ui.control.CheckBox
        EditField_Template_Global      matlab.ui.control.EditField
        Label_11                       matlab.ui.control.Label
        Tab_AS_Global                  matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        Panel_AS_Reg_Global            matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout8                    matlab.ui.container.GridLayout
        Label_VPL_Global               matlab.ui.control.Label
        Spinner_VPL_Global             matlab.ui.control.Spinner
        DropDown_Interp_Global         matlab.ui.control.DropDown
        Label_9                        matlab.ui.control.Label
        GridLayout7                    matlab.ui.container.GridLayout
        EditField_IterCoeff_Global     matlab.ui.control.NumericEditField
        Label_8                        matlab.ui.control.Label
        EditField_MaxIterN_Global      matlab.ui.control.NumericEditField
        Label_7                        matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        DropDown_RegModal              matlab.ui.control.DropDown
        Label_10                       matlab.ui.control.Label
        DropDown_DownSampling_Global   matlab.ui.control.DropDown
        Label_13                       matlab.ui.control.Label
        Panel_AS_PP_Global             matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        GridLayout70                   matlab.ui.container.GridLayout
        EditField_OptimalZ_Tol_Global  matlab.ui.control.NumericEditField
        Label_56                       matlab.ui.control.Label
        EditField_GaussianFilter_Global  matlab.ui.control.EditField
        Label_52                       matlab.ui.control.Label
        EditField_OptimalZ_MaxShift_Global  matlab.ui.control.NumericEditField
        Label_55                       matlab.ui.control.Label
        EditField_MedianFilter_Global  matlab.ui.control.EditField
        Label_51                       matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        Label_Helps_Global             matlab.ui.control.Label
        CheckBox_Lock_Global           matlab.ui.control.CheckBox
        Tab_MGlobal                    matlab.ui.container.Tab
        GridLayout33                   matlab.ui.container.GridLayout
        Panel_Reg_MGlobal              matlab.ui.container.Panel
        GridLayout35                   matlab.ui.container.GridLayout
        GridLayout45                   matlab.ui.container.GridLayout
        GridLayout69                   matlab.ui.container.GridLayout
        CheckBox_ApplyTransformation   matlab.ui.control.CheckBox
        Label_TransformMatrix          matlab.ui.control.Label
        GridLayout68                   matlab.ui.container.GridLayout
        EditField_TM44_MGlobal         matlab.ui.control.EditField
        EditField_TM43_MGlobal         matlab.ui.control.EditField
        EditField_TM42_MGlobal         matlab.ui.control.EditField
        EditField_TM41_MGlobal         matlab.ui.control.EditField
        EditField_TM34_MGlobal         matlab.ui.control.EditField
        EditField_TM33_MGlobal         matlab.ui.control.EditField
        EditField_TM32_MGlobal         matlab.ui.control.EditField
        EditField_TM31_MGlobal         matlab.ui.control.EditField
        EditField_TM24_MGlobal         matlab.ui.control.EditField
        EditField_TM23_MGlobal         matlab.ui.control.EditField
        EditField_TM22_MGlobal         matlab.ui.control.EditField
        EditField_TM21_MGlobal         matlab.ui.control.EditField
        EditField_TM14_MGlobal         matlab.ui.control.EditField
        EditField_TM13_MGlobal         matlab.ui.control.EditField
        EditField_TM12_MGlobal         matlab.ui.control.EditField
        EditField_TM11_MGlobal         matlab.ui.control.EditField
        GridLayout44                   matlab.ui.container.GridLayout
        CheckBox_ReSampleIsometric_MGlobal  matlab.ui.control.CheckBox
        EditField_ReSampleRatio_MGlobal  matlab.ui.control.EditField
        Label_61                       matlab.ui.control.Label
        GridLayout43                   matlab.ui.container.GridLayout
        Spinner_PolyOrder_MGlobal      matlab.ui.control.Spinner
        Label_24                       matlab.ui.control.Label
        DropDown_Interp_MGlobal        matlab.ui.control.DropDown
        Label_26                       matlab.ui.control.Label
        GridLayout42                   matlab.ui.container.GridLayout
        DropDown_Projection_MGlobal    matlab.ui.control.DropDown
        Label_60                       matlab.ui.control.Label
        DropDown_RegTform_MGlobal      matlab.ui.control.DropDown
        Label_22                       matlab.ui.control.Label
        GridLayout41                   matlab.ui.container.GridLayout
        DropDown_DView_MGlobal         matlab.ui.control.DropDown
        Label_59                       matlab.ui.control.Label
        EditField_CPNumber_MGlobal     matlab.ui.control.NumericEditField
        Label_23                       matlab.ui.control.Label
        GridLayout34                   matlab.ui.container.GridLayout
        Label_Helps_MANGlobal          matlab.ui.control.Label
        CheckBox_Lock_MGlobal          matlab.ui.control.CheckBox
    end

    properties (Constant)
        VER = "2.0.0"
    end
    
    properties (Access = private, Hidden)
        caller;         % the parent app

        fig_refvol;     % figure handle of reference volume

        volopts;        % 1-by-12 table, with volumes options
        regopts;        % 1-by-1 regopt object
        fixdef;         % struct with {"Sampling", "Channel"}, the fixed volume definition
        opt_locked      % 1-by-5 table, indicate the options locked status
        hw_info;        % the hardware information struct

        langmap;        % mapping for language
    end

    methods (Access = ?Register)

        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr >= 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.Panel_NS_Reg_Global.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_NS_Global.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout11.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout71.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout12.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout66.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout67.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout14.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout15.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout11.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_AS_PP_Global.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout70.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_AS_Reg_Global.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout7.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout8.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout33.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_Reg_MGlobal.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout35.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout41.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout42.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout43.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout44.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout45.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout69.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                case "DARK"
                    app.Panel_NS_Reg_Global.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_NS_Global.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout11.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout71.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout12.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout66.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout67.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout14.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout15.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout11.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_AS_PP_Global.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout70.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_AS_Reg_Global.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout7.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout8.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout33.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_Reg_MGlobal.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout35.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout41.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout42.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout43.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout44.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout45.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout69.BackgroundColor = constdef.GRAYSCALE_DARK;
                otherwise
            end
        end

        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            app.Tab_Global.Title = app.langmap.GLOBAL;
            app.Tab_Global.Tooltip = app.langmap.TIP_TAB_GLOBAL;
            app.Tab_MGlobal.Title = app.langmap.MGLOBAL;
            app.Tab_MGlobal.Tooltip = app.langmap.TIP_TAB_MGLOBAL;
            app.Tab_NS_Global.Title = app.langmap.USUAL;
            app.Tab_AS_Global.Title = app.langmap.ADVANCED;
            app.Panel_NS_Reg_Global.Title = app.langmap.ALIGNED;
            app.Panel_AS_PP_Global.Title = app.langmap.PREPROCESSING;
            app.Panel_AS_Reg_Global.Title = app.langmap.ALIGNED;
            app.Panel_Reg_MGlobal.Title = app.langmap.ALIGNED;
            app.Label.Text = app.langmap.SAMPLING;
            app.Label_2.Text = app.langmap.GEOMETRIC_TRANSFORMATION;
            app.Label_5.Text = app.langmap.MIN_ITERATION_STEP;
            app.Label_6.Text = app.langmap.MAX_ITERATION_STEP;
            app.Label_7.Text = app.langmap.MAX_ITERATION_N;
            app.Label_8.Text = app.langmap.ITERATION_COEFFICIENT;
            app.Label_9.Text = app.langmap.INTERPOLATION;
            app.Label_10.Text = app.langmap.MODAL;
            app.Label_VPL_Global.Text = app.langmap.PYRAMID_COEFFICIENT;
            app.Label_11.Text = app.langmap.TEMPLATE;
            app.Label_13.Text = app.langmap.DOWNSAMPLING;
            app.Label_22.Text = app.langmap.GEOMETRIC_TRANSFORMATION;
            app.Label_23.Text = app.langmap.CONTROL_VAL;
            app.Label_24.Text = app.langmap.POLYNOMIAL_ORDER;
            app.Label_26.Text = app.langmap.INTERPOLATION;
            app.Label_51.Text = app.langmap.MEDIAN_FILTER;
            app.Label_52.Text = app.langmap.GAUSSIAN_FILTER;
            app.Label_55.Text = app.langmap.MAX_Z_SHIFT;
            app.Label_56.Text = app.langmap.Z_OPTIMAL_TOL;
            app.Label_59.Text = app.langmap.DVIEW;
            app.Label_60.Text = app.langmap.PROJECTION;
            app.Label_61.Text = app.langmap.RESAMPLING_RATIO;
            app.Label_64.Text = app.langmap.COARSE_REG;
            app.Label_65.Text = app.langmap.FILTER;
            app.Label_TransformMatrix.Text = app.langmap.TRANSFORMATION_MATRIX;
            if app.regopts.Algorithm == "OCREG" ...
                    && app.regopts.Mode == "global"
                if app.regopts.Options.CoarseAlg == "mmt"
                    app.Label_66.Text = app.langmap.VOLUMETRIC_THRESHOLD;
                end

                app.Label_67.Text = app.langmap.ERODE_RADIUS;
            end
            
            app.DropDown_TemplateSampling_Global.Items = [app.langmap.MEAN,...
                app.langmap.MEDIAN, app.langmap.MIN, app.langmap.MAX];
            app.DropDown_TformType_Global.Items = [app.langmap.TRANSLATION,...
                app.langmap.RIGID, app.langmap.AFFINE];
            app.DropDown_RegModal.Items = [app.langmap.MONOMODAL, ...
                app.langmap.MULTIMODAL];
            app.DropDown_CoarseAlg_Global.Items = [app.langmap.MASS_CENTER_ESTIMATION, ...
                app.langmap.PHASE_CORRELATION, app.langmap.CLOSE];
            app.DropDown_DownSampling_Global.Items = [app.langmap.AUTO, ...
                "2X2", "3X3", app.langmap.CLOSE];
            app.DropDown_Interp_Global.Items = [app.langmap.LINEAR_FAST, ...
                app.langmap.CUBIC_HIGH_QUALITY];
            app.DropDown_DView_MGlobal.Items = [app.langmap.DVIEW_XY, ...
                app.langmap.DVIEW_ZX, app.langmap.DVIEW_YZ];
            app.DropDown_RegTform_MGlobal.Items = [app.langmap.TRANSLATION, ...
                app.langmap.RIGID, app.langmap.AFFINE, ...
                app.langmap.PIECEWISE_LINEAR, app.langmap.POLYNOMIAL];
            app.DropDown_Projection_MGlobal.Items = [app.langmap.MAX, ...
                app.langmap.MIN, app.langmap.MEDIAN, app.langmap.MEAN];
            app.DropDown_Interp_MGlobal.Items = [app.langmap.LINEAR_FAST, ...
                app.langmap.CUBIC_HIGH_QUALITY];

            app.CheckBox_Lock_Global.Text = app.langmap.LOCK;
            app.CheckBox_Lock_Global.Tooltip = app.langmap.TIP_CBX_LOCK;
            app.CheckBox_Lock_MGlobal.Text = app.langmap.LOCK;
            app.CheckBox_Lock_MGlobal.Tooltip = app.langmap.TIP_CBX_LOCK;
            app.CheckBox_AutoUpdateTemplate_Global.Text = app.langmap.TEMPLATE_AUTO_UPDATE;
            app.CheckBox_ApplyTransformation.Text = app.langmap.APPLY;
            app.CheckBox_ApplyTransformation.Tooltip = app.langmap.TIP_CBX_APPLY_TMAT;
        end
        
        function FontBlink(app,components,ts,c)
            % This function for app components font blink
            arguments
                app (1,1) TCRegSetter;
                components string {mustBeVector}; % target  source
                ts (:,2) double {mustBePositive} = [0.25,   0.25; ...
                    0.25,   0.25; ...
                    0.25,   0.25]   %time series, seconds
                % c is RGB array
                c (2,3) double {mustBeInRange(c,0,1)} = [1,0,0; ... % target
                    0,0,0];    % source
            end

            N = numel(components);

            for t = 1:size(ts,1)
                for k = 1:N
                    if isprop(app.(components(k)),"FontColor")
                        app.(components(k)).FontColor = c(1,:);
                    end
                end
                pause(ts(t,1));
                for k = 1:N
                    if isprop(app.(components(k)),"FontColor")
                        app.(components(k)).FontColor = c(2,:);
                    end
                end
                pause(ts(t,2));
            end
        end

        function Refresh(app, mainapp)
            InitData(app, mainapp);
            InitPanel(app);
        end

        function Close(app)
            OCRSUIFigureCloseRequest(app, []);
        end
    end
    
    methods (Access = private)
        function InitData(app, mainapp)
            app.caller = mainapp;
            app.fig_refvol = [];
            [app.regopts, app.fixdef, app.opt_locked] = ...
                app.caller.GetRegOptions();
            app.volopts = app.caller.GetImgInfo();
        end

        function InitPanel(app)
            % SELECT ONE SETTER
            switch app.regopts.Mode
                case "global"
                    switch app.regopts.Algorithm
                        case "OCREG"
                            SetGlobalPanel(app);
                        case "MANREG"
                            SetMGlobalPanel(app);
                        otherwise
                    end
                otherwise
            end
        end

        %==================== GLOBAL PANEL ======================
        % Global panel setting include: 
        % <SetGlobalPanel>: set the controller value
        % <SetGlobalPanelEnabling>: set the controller enable status
        % <LockGlobalPanel>: set the controllers disabled
        % <UnlockGloablPanel>: set the controllers enabled

        function SetGlobalPanel(app)
            % ================== SET THE USUAL ARGUMENTS ================
            % set registration template
            app.EditField_Template_Global.Value = app.fixdef.Sampling(2);
            % force enable the auto template generation
            app.CheckBox_AutoUpdateTemplate_Global.Enable = "off";
            app.DropDown_TemplateSampling_Global.Value = app.fixdef.Sampling(1);

            % set transformation
            app.DropDown_TformType_Global.Value = app.regopts.Options.TformType;

            % set the coarse registration algorithm
            app.DropDown_CoarseAlg_Global.Value = app.regopts.Options.CoarseAlg;

            switch app.regopts.Options.CoarseAlg
                case "mmt"
                    app.DropDown_Detector_Global.Visible = "on";
                    app.Label_65.Visible = "on";
                    app.Label_64.FontColor = [0, 0, 1];
                    app.GridLayout_NS_Global.RowHeight = ...
                        {25,25,25,25,25,60,'1x'};
                otherwise
                    app.DropDown_Detector_Global.Visible = "off";
                    app.Label_65.Visible = "off";
                    app.Label_64.FontColor = [0, 0, 0];
                    app.GridLayout_NS_Global.RowHeight = ...
                        {25,25,25,25,0,60,'1x'};
            end

            % set the coarse registration parameters
            app.DropDown_Detector_Global.Value = app.regopts.Options.CoarseArgs.Filter;
            app.EditField_VT_Global.Value = app.regopts.Options.CoarseArgs.VT;
            app.Spinner_ErodeRadius_Global.Value = app.regopts.Options.CoarseArgs.Radius;

            % set iteration options
            app.EditField_MinStep_Global.Value = app.regopts.Options.MinStep;
            app.EditField_MaxStep_Global.Value = app.regopts.Options.MaxStep;

            %================== SET THE ADVANCED ARGUMENTS ================
            % set the preprocessing arguments
            app.EditField_MedianFilter_Global.Value = ...
                string(app.regopts.Options.MedianFilter).join(",");
            app.EditField_GaussianFilter_Global.Value = ...
                string(app.regopts.Options.GaussianFilter).join(",");
            app.EditField_OptimalZ_MaxShift_Global.Value = app.regopts.Options.MaxZOptShift;
            app.EditField_OptimalZ_Tol_Global.Value = app.regopts.Options.TolZOpt;

            % set the modal
            app.DropDown_RegModal.Value = app.regopts.Options.RegModal;

            % set down sampling options
            switch app.regopts.Options.DS
                case "1X1"
                    app.DropDown_DownSampling_Global.Value = "close";
                otherwise
                    app.DropDown_DownSampling_Global.Value = app.regopts.Options.DS;
            end

            % set iteration options
            app.EditField_MaxIterN_Global.Value = app.regopts.Options.MaxIterN;
            app.EditField_IterCoeff_Global.Value = app.regopts.Options.IterCoeff;

            % set algorithm miscellaneous
            app.Spinner_VPL_Global.Limits = ...
                [1, floor(log(app.volopts.slices)/log(4))+1];
            app.Spinner_VPL_Global.Value = app.regopts.Options.VPL;
            app.DropDown_Interp_Global.Value = app.regopts.Options.Interp;

            % set the panel locked status
            app.CheckBox_Lock_Global.Value = app.opt_locked.Global;
            if app.opt_locked.Global == true
                LockGlobalPanel(app); 
            else
                UnlockGlobalPanel(app);
            end

            %==========================================================
            % focus this panel
            app.TabGroup_AlgorithmSelection.SelectedTab = app.Tab_Global;
        end

        function SetGlobalPanelEnabling(app, state)
            arguments
                app
                state (1,1) string {mustBeMember(state, ["on","off"])}
            end
            app.EditField_Template_Global.Enable = state;
            app.DropDown_TemplateSampling_Global.Enable = state;
            app.DropDown_TformType_Global.Enable = state;
            app.DropDown_CoarseAlg_Global.Enable = state;
            app.DropDown_Detector_Global.Enable = state;
            app.EditField_VT_Global.Enable = state;
            app.Spinner_ErodeRadius_Global.Enable = state;
            app.EditField_MinStep_Global.Enable = state;
            app.EditField_MaxStep_Global.Enable = state;
            app.EditField_MedianFilter_Global.Enable = state;
            app.EditField_GaussianFilter_Global.Enable = state;
            app.EditField_OptimalZ_MaxShift_Global.Enable = state;
            app.EditField_OptimalZ_Tol_Global.Enable = state;
            app.DropDown_RegModal.Enable = state;
            app.DropDown_DownSampling_Global.Enable = state;
            app.EditField_MaxIterN_Global.Enable = state;
            app.EditField_IterCoeff_Global.Enable = state;
            app.Spinner_VPL_Global.Enable = state;
            app.DropDown_Interp_Global.Enable = state;
        end

        function LockGlobalPanel(app)
            SetGlobalPanelEnabling(app, "off");
            app.Tab_Global.ForegroundColor = [1, 0, 0];
            app.CheckBox_Lock_Global.FontColor = [1, 0, 0];
        end

        function UnlockGlobalPanel(app)
            SetGlobalPanelEnabling(app, "on");
            app.Tab_Global.ForegroundColor = [0, 0, 0];
            app.CheckBox_Lock_Global.FontColor = [0, 0, 0];
        end

        %=========================================================

        %===================== MGLOBAL PANEL =====================
        % MGlobal panel setting include: 
        % <SetLocalPanel>: set the controller value
        % <SetLocalPanelEnabling>: set the controller enable status
        % <LockLocalPanel>: set the controllers disabled
        % <UnlockLocalPanel>: set the controllers enabled

        function SetMGlobalPanel(app)
            % =============== SET REGISTRATION PARAMETERS ================
            app.DropDown_DView_MGlobal.Value = app.regopts.Options.DView;
            app.DropDown_Projection_MGlobal.Value = app.regopts.Options.Projection;
            app.DropDown_RegTform_MGlobal.Value = app.regopts.Options.TformType;

            switch app.regopts.Options.TformType
                case "translation"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 1;
                case "rigid"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 2;
                case "affine"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 3;
                case "pwl"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 4;
                case "polynomial"
                    app.Spinner_PolyOrder_MGlobal.Value = app.regopts.Options.Degree;
                    app.EditField_CPNumber_MGlobal.Value = ...
                        nchoosek(app.regopts.Options.Degree+2, 2);
                    app.Label_24.Visible = "on";
                    app.Spinner_PolyOrder_MGlobal.Visible = "on";

            end

            app.DropDown_Interp_MGlobal.Value = app.regopts.Options.Interp;
            app.CheckBox_ReSampleIsometric_MGlobal.Value = ...
                app.regopts.Options.Isometric;

            if app.regopts.Options.Isometric == false
                app.EditField_ReSampleRatio_MGlobal.Editable = "on";
            else
                app.EditField_ReSampleRatio_MGlobal.Editable = "off";
            end
            app.EditField_ReSampleRatio_MGlobal.Value ...
                = string(app.regopts.Options.Resampling).join(",");

            app.CheckBox_ApplyTransformation.Value = ...
                app.regopts.Options.TFEnable;

            SetMGlobalTFMatrixView(app, app.regopts.Options.TFMatrix);

            ApplyConstrainOnMGlobalTF(app, app.regopts.Options.TformType);

            % set the panel locked status
            app.CheckBox_Lock_MGlobal.Value = app.opt_locked.MGlobal;
            if app.opt_locked.MGlobal == true
                LockMGlobalPanel(app); 
            else
                UnlockMGlobalPanel(app);
            end
            
            %============================================================
            % focus this panel
            app.TabGroup_AlgorithmSelection.SelectedTab = app.Tab_MGlobal;
        end

        function SetMGlobalPanelEnabling(app, state)
            arguments
                app
                state (1,1) string {mustBeMember(state, ["on","off"])}
            end
            app.DropDown_DView_MGlobal.Enable = state;
            app.DropDown_Projection_MGlobal.Enable = state;
            app.DropDown_RegTform_MGlobal.Enable = state;
            app.EditField_CPNumber_MGlobal.Enable = state;
            app.DropDown_Interp_MGlobal.Enable = state;
            app.CheckBox_ReSampleIsometric_MGlobal.Enable = state;
            app.EditField_ReSampleRatio_MGlobal.Enable = state;
        end

        function LockMGlobalPanel(app)
            SetMGlobalPanelEnabling(app, "off");
            app.Tab_MGlobal.ForegroundColor = [1, 0, 0];
            app.CheckBox_Lock_MGlobal.FontColor = [1, 0, 0];
        end

        function UnlockMGlobalPanel(app)
            SetMGlobalPanelEnabling(app, "on");
            app.Tab_MGlobal.ForegroundColor = [0, 0, 0];
            app.CheckBox_Lock_MGlobal.FontColor = [0, 0, 0];
        end

        %=========================================================

        % This function lock/unlock the target panel and synchronize others 
        % behavior
        function UpdateLockGroup(app, panel, state)
            arguments
                app
                panel   (1,1)   string {mustBeMember(panel, ["Global","MGlobal"])}
                state   (1,1)   logical
            end

            UnlockAllPanel(app);

            % clear flag table
            app.opt_locked{1,:} = false;
            app.opt_locked.(panel) = state;

            if state == true
                LockPanel(app, panel);
            end
        end

        function LockPanel(app, panel)
            arguments
                app %#ok<INUSA>
                panel   (1,1)   string {mustBeMember(panel, ["Global", "MGlobal"])}
            end

            eval("Lock"+panel+"Panel(app);");
            % add check box selection
            eval("app.CheckBox_Lock_"+panel+".Value = true;");
        end

        function UnlockAllPanel(app) %#ok<MANU>
            for panel = ["Global", "MGlobal"]
                eval("Unlock"+panel+"Panel(app);");
                % remove check box selection
                eval("app.CheckBox_Lock_"+panel+".Value = false;");
            end
        end

        function ApplyConstrainOnMGlobalTF(app, type)
            % only translation and affine are supported easily
            % disable 'rigid' and other transformation types
            switch type
                case "translation"
                    for m = 1:3
                        for n = 1:3
                            eval(sprintf("app.EditField_TM%d%d_MGlobal.Enable " + ...
                                "= 'off';", m,n));
                        end
                    end
                    app.EditField_TM14_MGlobal.Enable = "on";
                    app.EditField_TM24_MGlobal.Enable = "on";
                    app.EditField_TM34_MGlobal.Enable = "on";
                case "affine"
                    for m = 1:3
                        for n = 1:4
                            eval(sprintf("app.EditField_TM%d%d_MGlobal.Enable " + ...
                                "= 'on';", m,n));
                        end
                    end
                otherwise
                    for m = 1:3
                        for n = 1:4
                            eval(sprintf("app.EditField_TM%d%d_MGlobal.Enable " + ...
                                "= 'off';", m,n));
                        end
                    end
            end
        end

        function SetMGlobalTFMatrixView(app, mat)
            arguments
                app %#ok<INUSA>
                mat (4,4)   double
            end

            for m = 1:4
                for n = 1:4
                    eval(sprintf("app.EditField_TM%d%d_MGlobal.Value " + ...
                                "= '%s';", m,n, string(mat(m,n))));
                end
            end
        end


        function SaveMGlobalTFMatrixTo(app, row, col, val)
            tf = app.regopts.Options.TFMatrix;

            if isnan(val) || isinf(val) || ~isreal(val)
                eval(sprintf("app.EditField_TM%d%d_MGlobal.Value " + ...
                    "= string(tf(row,col));", row, col));
            else
                tf(row, col) = val;
                app.regopts = set(app.regopts, "TFMatrix", tf);
                app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
            end
        end

        function InitHardwareDetector(app)
            [hw_logo, app.hw_info] = GetHardwareInfo();
            app.Image_CPU.Visible = "on";

            switch hw_logo.cpu
                case "Intel"
                    app.Image_CPU.ImageSource = "sources/intel_logo.png";
                case "AMD"
                    app.Image_CPU.ImageSource = "sources/amd_logo.png";
                case "Unknown"
                    app.Image_CPU.ImageSource = "sources/unknown_logo.png";
                otherwise
                    app.Image_CPU.Visible = "off";
            end
        end

        function [v, opt] = CalcPreProcRefVol(app)
            refvol = app.caller.GetRefVol();
            v = refvol.RefVol;
            % do preprocess
            switch app.TabGroup_AlgorithmSelection.SelectedTab.Tag
                case "Global"
                    ds = app.regopts.Options.DS;
                    if ds == "auto", regds = inf;
                    else,            regds = 1/str2double(ds.extractBefore("X"));
                    end
                    [s, v] = Resample(v, regds);
                    v = preproc_oc(v, ...
                        app.regopts.Options.MedianFilter, ...
                        app.regopts.Options.GaussianFilter);
                    if nargout == 2
                        opt = app.volopts;
                        opt.channels = 1; opt.frames = 1; opt.cOrder = app.fixdef.Channel;
                        opt.images = 1;
                        opt.xRes = app.volopts.xRes / s;
                        opt.yRes = app.volopts.yRes / s;
                    end
                    % use binary mask for robust coarse estimation
                    v = isoutlier(single(v), app.regopts.Options.CoarseArgs.Filter);
                    % volume open operation to remove possible local little structure
                    v = bwareaopen(v, app.regopts.Options.CoarseArgs.VT, 26);
                    % erode for better true foreground estimation
                    v = imerode(v, strel("sphere", app.regopts.Options.CoarseArgs.Radius));
                otherwise
                    if nargout == 2
                        opt = app.volopts;
                    end
            end
        end
    end

    methods(Static)
        function r = calc_isometric(volopt)
            xr = volopt.xRes;
            yr = volopt.yRes;
            zr = volopt.zRes;

            rmin = min([xr, yr, zr]);
            r = round([xr, yr, zr]./rmin);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            InitData(app, mainapp);

            InitPanel(app);

            InitHardwareDetector(app);

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);
        end

        % Value changed function: EditField_Template_Global
        function EditField_Template_GlobalValueChanged(app, event)
            value = app.EditField_Template_Global.Value;
            value = string(value).replace("end",string(app.volopts.frames));
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(value <= 0)
                % blink the field
                FontBlink(app,"EditField_Template_Global");
                app.EditField_Template_Global.Value ...
                    = app.EditField_Template_Global.Placeholder;
                value = str2num(app.EditField_Template_Global.Placeholder); %#ok<ST2NM>
            end

            app.fixdef.Sampling(2) = string(value).join(",");

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_TemplateSampling_Global
        function DropDown_TemplateSampling_GlobalValueChanged(app, event)
            value = app.DropDown_TemplateSampling_Global.Value;

            app.fixdef.Sampling(1) = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_TformType_Global
        function DropDown_TformType_GlobalValueChanged(app, event)
            value = app.DropDown_TformType_Global.Value;

            switch value
                case "translation"
                    app.DropDown_TformType_Global.Tooltip = ...
                        app.langmap.TRANSLATION_TOOLTIP;
                case "rigid"
                    app.DropDown_TformType_Global.Tooltip = ...
                        app.langmap.RIGID_TOOLTIP;
                case "affine"
                    app.DropDown_TformType_Global.Tooltip = ...
                        app.langmap.AFFINE_TOOLTIP;
                otherwise
            end

            app.regopts = set(app.regopts, "TformType", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_RegModal
        function DropDown_RegModalValueChanged(app, event)
            value = app.DropDown_RegModal.Value;
            
            app.regopts = set(app.regopts, "RegModal", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MinStep_Global
        function EditField_MinStep_GlobalValueChanged(app, event)
            value = app.EditField_MinStep_Global.Value;

            app.regopts = set(app.regopts, "MinStep", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MaxStep_Global
        function EditField_MaxStep_GlobalValueChanged(app, event)
            value = app.EditField_MaxStep_Global.Value;
            
            app.regopts = set(app.regopts, "MaxStep", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Image clicked function: Image_CPU
        function Image_CPUClicked(app, event)
            uialert(app.OCRSUIFigure,app.hw_info.cpu, ...
                app.langmap.INFORMATION, "Icon","info");
        end

        % Value changed function: EditField_MaxIterN_Global
        function EditField_MaxIterN_GlobalValueChanged(app, event)
            value = app.EditField_MaxIterN_Global.Value;
            
            app.regopts = set(app.regopts, "MaxIterN", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_IterCoeff_Global
        function EditField_IterCoeff_GlobalValueChanged(app, event)
            value = app.EditField_IterCoeff_Global.Value;
            
            app.regopts = set(app.regopts, "IterCoeff", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_DownSampling_Global
        function DropDown_DownSampling_GlobalValueChanged(app, event)
            value = app.DropDown_DownSampling_Global.Value;
            
            switch value
                case "close"
                    app.regopts = set(app.regopts, "DS", "1X1");
                otherwise
                    app.regopts = set(app.regopts, "DS", string(value));
            end

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Interp_Global
        function DropDown_Interp_GlobalValueChanged(app, event)
            value = app.DropDown_Interp_Global.Value;
            
            app.regopts = set(app.regopts, "Interp", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_VPL_Global
        function Spinner_VPL_GlobalValueChanged(app, event)
            value = app.Spinner_VPL_Global.Value;
            
            app.regopts = set(app.regopts, "glVPL", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_Lock_Global
        function CheckBox_Lock_GlobalValueChanged(app, event)
            value = app.CheckBox_Lock_Global.Value;

            UpdateLockGroup(app, "Global", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);

            if value == true
                app.caller.SendMessage(["REGMODE_CURRENT_GLOBAL",""]);
            end
        end

        % Value changed function: DropDown_RegTform_MGlobal
        function DropDown_RegTform_MGlobalValueChanged(app, event)
            value = app.DropDown_RegTform_MGlobal.Value;
            
            switch value
                case "translation"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 1;
                case "rigid"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 2;
                case "affine"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 3;
                case "pwl"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 4;
                case "poly"
                    app.Spinner_PolyOrder_MGlobal.Visible = "on";
                    app.Label_24.Visible = "on";
                    app.EditField_CPNumber_MGlobal.Value ...
                        = nchoosek(app.Spinner_PolyOrder_MGlobal.Value+2,2);
                otherwise
            end

            ApplyConstrainOnMGlobalTF(app, value);

            app.regopts = set(app.regopts, "TformType", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_PolyOrder_MGlobal
        function Spinner_PolyOrder_MGlobalValueChanged(app, event)
            value = app.Spinner_PolyOrder_MGlobal.Value;

            % Synchronize minimum control point pairs number
            app.EditField_CPNumber_MGlobal.Value = nchoosek(value+2, 2);

            app.regopts = set(app.regopts, "Degree", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Interp_MGlobal
        function DropDown_Interp_MGlobalValueChanged(app, event)
            value = app.DropDown_Interp_MGlobal.Value;

            app.regopts = set(app.regopts, "Interp", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Close request function: OCRSUIFigure
        function OCRSUIFigureCloseRequest(app, event)
            % Set the registration options
            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);

            delete(app);
        end

        % Selection change function: TabGroup_AlgorithmSelection
        function TabGroup_AlgorithmSelectionSelectionChanged(app, event)
            selectedTab = app.TabGroup_AlgorithmSelection.SelectedTab;
            
            switch selectedTab.Tag
                case "Global"
                    app.regopts.Algorithm = "OCREG";
                    app.regopts.Mode = "global";
                    SetGlobalPanel(app);
                case "MGlobal"
                    app.regopts.Algorithm = "MANREG";
                    app.regopts.Mode = "global";
                    SetMGlobalPanel(app);
                otherwise
                    throw(MException("OCRegSetter:innerError", ...
                        "Undefined Tab tag."));
            end

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MedianFilter_Global
        function EditField_MedianFilter_GlobalValueChanged(app, event)
            value = app.EditField_MedianFilter_Global.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value) ~= [1,3]) ...
                    || (all(value ~= 0) && any(mod(round(value),2)==0))
                app.EditField_MedianFilter_Global.Value ...
                    = app.EditField_MedianFilter_Global.Placeholder;
                value = str2num(app.EditField_MedianFilter_Global.Placeholder); %#ok<ST2NM>
            end
            app.regopts = set(app.regopts, "MedianFilter", round(value));

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_GaussianFilter_Global
        function EditField_GaussianFilter_GlobalValueChanged(app, event)
            value = app.EditField_GaussianFilter_Global.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value) ~= [1,3]) ...
                    || (all(value ~= 0) && any(mod(round(value),2)==0))
                app.EditField_GaussianFilter_Global.Value ...
                    = app.EditField_GaussianFilter_Global.Placeholder;
                value = str2num(app.EditField_GaussianFilter_Global.Placeholder); %#ok<ST2NM>   
            end

            app.regopts = set(app.regopts, "GaussianFilter", round(value));

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_OptimalZ_MaxShift_Global
        function EditField_OptimalZ_MaxShift_GlobalValueChanged(app, event)
            value = app.EditField_OptimalZ_MaxShift_Global.Value;
            
            app.regopts = set(app.regopts, "MaxZOptShift", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_OptimalZ_Tol_Global
        function EditField_OptimalZ_Tol_GlobalValueChanged(app, event)
            value = app.EditField_OptimalZ_Tol_Global.Value;
            
            app.regopts = set(app.regopts, "TolZOpt", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_Lock_MGlobal
        function CheckBox_Lock_MGlobalValueChanged(app, event)
            value = app.CheckBox_Lock_MGlobal.Value;
            
            UpdateLockGroup(app, "MGlobal", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);

            if value == true
                app.caller.SendMessage(["REGMODE_CURRENT_MGLOBAL",""]);
            end
        end

        % Value changed function: DropDown_DView_MGlobal
        function DropDown_DView_MGlobalValueChanged(app, event)
            value = app.DropDown_DView_MGlobal.Value;

            app.regopts = set(app.regopts, "DView", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Projection_MGlobal
        function DropDown_Projection_MGlobalValueChanged(app, event)
            value = app.DropDown_Projection_MGlobal.Value;

            app.regopts = set(app.regopts, "Projection", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_ReSampleRatio_MGlobal
        function EditField_ReSampleRatio_MGlobalValueChanged(app, event)
            value = app.EditField_ReSampleRatio_MGlobal.Value;
            
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value) ~= [1,3])
                app.EditField_ReSampleRatio_MGlobal.Value ...
                    = app.EditField_ReSampleRatio_MGlobal.Placeholder;
                value = str2num(app.EditField_ReSampleRatio_MGlobal.Placeholder); %#ok<ST2NM>
            end

            app.regopts = set(app.regopts, "Resampling", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_ReSampleIsometric_MGlobal
        function CheckBox_ReSampleIsometric_MGlobalValueChanged(app, event)
            value = app.CheckBox_ReSampleIsometric_MGlobal.Value;
            
            if value == true
                r = TCRegSetter.calc_isometric(app.volopts);
                app.EditField_ReSampleRatio_MGlobal.Editable = "off";
                app.EditField_ReSampleRatio_MGlobal.Value = string(r).join(",");
                app.regopts = set(app.regopts, "Resampling", r);
            else
                app.EditField_ReSampleRatio_MGlobal.Editable = "on";
            end

            app.regopts = set(app.regopts, "Isometric", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Key press function: OCRSUIFigure
        function OCRSUIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case {'v', 'V'}
                    % 'V' for preview the processed volume
                    vol = CalcPreProcRefVol(app);
                    if isempty(app.fig_refvol) || ~isvalid(app.fig_refvol)
                        app.fig_refvol = figure("Name", "Ref-Mask Preview");
                    else
                        clf(app.fig_refvol, "reset");
                    end
                    h_sv = sliceViewer(vol, "Colormap", jet(256), ...
                        "Parent", app.fig_refvol);
                    ax_sv = getAxesHandle(h_sv);
                    colorbar(ax_sv, "eastoutside");
                otherwise
            end
        end

        % Value changed function: DropDown_CoarseAlg_Global
        function DropDown_CoarseAlg_GlobalValueChanged(app, event)
            value = app.DropDown_CoarseAlg_Global.Value;

            switch value
                case "mmt"
                    app.DropDown_Detector_Global.Visible = "on";
                    app.Label_65.Visible = "on";
                    app.Label_64.FontColor = [0, 0, 1];
                    app.GridLayout_NS_Global.RowHeight = ...
                        {25,25,25,25,25,60,'1x'};
                case "pcorr"
                    app.DropDown_Detector_Global.Visible = "off";
                    app.Label_65.Visible = "off";
                    app.Label_64.FontColor = [0, 0, 0];
                    app.GridLayout_NS_Global.RowHeight = ...
                        {25,25,25,25,0,60,'1x'};
                otherwise
            end
            
            app.regopts = set(app.regopts, "CoarseAlg", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Detector_Global
        function DropDown_Detector_GlobalValueChanged(app, event)
            value = app.DropDown_Detector_Global.Value;
            
            cargs = app.regopts.Options.CoarseArgs;
            cargs.Filter = value;

            app.regopts = set(app.regopts, "CoarseArgs", cargs);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_TM11_MGlobal
        function EditField_TM11_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM11_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 1, 1, value);
        end

        % Value changed function: EditField_TM12_MGlobal
        function EditField_TM12_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM12_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 1, 2, value);
        end

        % Value changed function: EditField_TM13_MGlobal
        function EditField_TM13_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM13_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 1, 3, value);
        end

        % Value changed function: EditField_TM14_MGlobal
        function EditField_TM14_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM14_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 1, 4, value);
        end

        % Value changed function: EditField_TM21_MGlobal
        function EditField_TM21_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM21_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 2, 1, value);
        end

        % Value changed function: EditField_TM22_MGlobal
        function EditField_TM22_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM22_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 2, 2, value);
        end

        % Value changed function: EditField_TM23_MGlobal
        function EditField_TM23_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM23_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 2, 3, value);
        end

        % Value changed function: EditField_TM24_MGlobal
        function EditField_TM24_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM24_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 2, 4, value);
        end

        % Value changed function: EditField_TM31_MGlobal
        function EditField_TM31_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM31_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 3, 1, value);
        end

        % Value changed function: EditField_TM32_MGlobal
        function EditField_TM32_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM32_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 3, 2, value);
        end

        % Value changed function: EditField_TM33_MGlobal
        function EditField_TM33_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM33_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 3, 3, value);
        end

        % Value changed function: EditField_TM34_MGlobal
        function EditField_TM34_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM34_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 3, 4, value);
        end

        % Value changed function: CheckBox_ApplyTransformation
        function CheckBox_ApplyTransformationValueChanged(app, event)
            value = app.CheckBox_ApplyTransformation.Value;
            
            app.regopts = set(app.regopts, "TFEnable", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_VT_Global
        function EditField_VT_GlobalValueChanged(app, event)
            value = app.EditField_VT_Global.Value;
            
            cargs = app.regopts.Options.CoarseArgs;
            cargs.VT = value;

            app.regopts = set(app.regopts, "CoarseArgs", cargs);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_ErodeRadius_Global
        function Spinner_ErodeRadius_GlobalValueChanged(app, event)
            value = app.Spinner_ErodeRadius_Global.Value;
            
            cargs = app.regopts.Options.CoarseArgs;
            cargs.Radius = value;

            app.regopts = set(app.regopts, "CoarseArgs", cargs);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OCRSUIFigure = uifigure('Visible', 'off');
            app.OCRSUIFigure.Name = 'OCRS';
            app.OCRSUIFigure.Icon = 'ocrs.png';
            app.OCRSUIFigure.CloseRequestFcn = createCallbackFcn(app, @OCRSUIFigureCloseRequest, true);
            app.OCRSUIFigure.KeyPressFcn = createCallbackFcn(app, @OCRSUIFigureKeyPress, true);
            app.TabGroup_AlgorithmSelection = uitabgroup(app.OCRSUIFigure);
            app.TabGroup_AlgorithmSelection.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_AlgorithmSelectionSelectionChanged, true);
            app.Tab_Global = uitab(app.TabGroup_AlgorithmSelection);
            app.Tab_Global.Tooltip = {'基于强度、相位和特征的自动全局几何变换'};
            app.Tab_Global.Title = '全局';
            app.Tab_Global.Tag = 'Global';
            app.GridLayout = uigridlayout(app.Tab_Global);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CheckBox_Lock_Global = uicheckbox(app.GridLayout2);
            app.CheckBox_Lock_Global.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Lock_GlobalValueChanged, true);
            app.CheckBox_Lock_Global.Tooltip = {'启用全局配准'};
            app.CheckBox_Lock_Global.Text = '锁定';
            app.Label_Helps_Global = uilabel(app.GridLayout2);
            app.Label_Helps_Global.Text = '';
            app.TabGroup_Global = uitabgroup(app.GridLayout);
            app.Tab_NS_Global = uitab(app.TabGroup_Global);
            app.Tab_NS_Global.Tooltip = {''};
            app.Tab_NS_Global.Title = '常用';
            app.GridLayout10 = uigridlayout(app.Tab_NS_Global);
            app.Panel_NS_Reg_Global = uipanel(app.GridLayout10);
            app.Panel_NS_Reg_Global.Title = '配准';
            app.GridLayout_NS_Global = uigridlayout(app.Panel_NS_Reg_Global);
            app.GridLayout11 = uigridlayout(app.GridLayout_NS_Global);
            app.Label_11 = uilabel(app.GridLayout11);
            app.Label_11.Text = '模板';
            app.EditField_Template_Global = uieditfield(app.GridLayout11, 'text');
            app.EditField_Template_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_Template_GlobalValueChanged, true);
            app.EditField_Template_Global.Placeholder = '1';
            app.CheckBox_AutoUpdateTemplate_Global = uicheckbox(app.GridLayout11);
            app.CheckBox_AutoUpdateTemplate_Global.Enable = 'off';
            app.CheckBox_AutoUpdateTemplate_Global.Text = '自动更新';
            app.CheckBox_AutoUpdateTemplate_Global.Value = true;
            app.GridLayout12 = uigridlayout(app.GridLayout_NS_Global);
            app.Label = uilabel(app.GridLayout12);
            app.Label.Text = '采样';
            app.DropDown_TemplateSampling_Global = uidropdown(app.GridLayout12);
            app.DropDown_TemplateSampling_Global.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_TemplateSampling_Global.ItemsData = {'mean', 'median', 'min', 'max'};
            app.DropDown_TemplateSampling_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_TemplateSampling_GlobalValueChanged, true);
            app.DropDown_TemplateSampling_Global.Tooltip = {''};
            app.DropDown_TemplateSampling_Global.Value = 'mean';
            app.Label_2 = uilabel(app.GridLayout12);
            app.Label_2.Text = '几何变换';
            app.DropDown_TformType_Global = uidropdown(app.GridLayout12);
            app.DropDown_TformType_Global.Items = {'平移', '刚体', '仿射'};
            app.DropDown_TformType_Global.ItemsData = {'translation', 'rigid', 'affine'};
            app.DropDown_TformType_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_TformType_GlobalValueChanged, true);
            app.DropDown_TformType_Global.Tooltip = {''};
            app.DropDown_TformType_Global.Value = 'translation';
            app.GridLayout14 = uigridlayout(app.GridLayout_NS_Global);
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.Label_5 = uilabel(app.GridLayout15);
            app.Label_5.Text = '最小迭代步长';
            app.EditField_MinStep_Global = uieditfield(app.GridLayout15, 'numeric');
            app.EditField_MinStep_Global.Limits = [0 1];
            app.EditField_MinStep_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_MinStep_GlobalValueChanged, true);
            app.EditField_MinStep_Global.Tooltip = {''};
            app.EditField_MinStep_Global.Value = 0.0001;
            app.Label_6 = uilabel(app.GridLayout15);
            app.Label_6.Text = '最大迭代步长';
            app.EditField_MaxStep_Global = uieditfield(app.GridLayout15, 'numeric');
            app.EditField_MaxStep_Global.Limits = [0 1];
            app.EditField_MaxStep_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxStep_GlobalValueChanged, true);
            app.EditField_MaxStep_Global.Tooltip = {''};
            app.EditField_MaxStep_Global.Value = 0.1;
            app.Image_CPU = uiimage(app.GridLayout14);
            app.Image_CPU.ImageClickedFcn = createCallbackFcn(app, @Image_CPUClicked, true);
            app.Image_CPU.Visible = 'off';
            app.Image_CPU.Tooltip = {'CPU状态'};
            app.GridLayout66 = uigridlayout(app.GridLayout_NS_Global);
            app.Label_64 = uilabel(app.GridLayout66);
            app.Label_64.Text = '粗配准';
            app.DropDown_CoarseAlg_Global = uidropdown(app.GridLayout66);
            app.DropDown_CoarseAlg_Global.Items = {'质心估计', '相位相关', '关闭'};
            app.DropDown_CoarseAlg_Global.ItemsData = {'mmt', 'pcorr', 'none'};
            app.DropDown_CoarseAlg_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_CoarseAlg_GlobalValueChanged, true);
            app.DropDown_CoarseAlg_Global.Value = 'mmt';
            app.Label_65 = uilabel(app.GridLayout66);
            app.Label_65.Text = '滤子';
            app.DropDown_Detector_Global = uidropdown(app.GridLayout66);
            app.DropDown_Detector_Global.Items = {'median', 'quartiles', 'grubbs', 'gesd', 'mean'};
            app.DropDown_Detector_Global.ItemsData = {'median', 'quartiles', 'grubbs', 'gesd', 'mean'};
            app.DropDown_Detector_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_Detector_GlobalValueChanged, true);
            app.DropDown_Detector_Global.Value = 'median';
            app.GridLayout67 = uigridlayout(app.GridLayout_NS_Global);
            app.Label_66 = uilabel(app.GridLayout67);
            app.Label_66.Text = '体积阈值';
            app.EditField_VT_Global = uieditfield(app.GridLayout67, 'numeric');
            app.EditField_VT_Global.Limits = [0 Inf];
            app.EditField_VT_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_VT_GlobalValueChanged, true);
            app.EditField_VT_Global.Value = 1000;
            app.Label_67 = uilabel(app.GridLayout67);
            app.Label_67.Text = '腐蚀半径';
            app.Spinner_ErodeRadius_Global = uispinner(app.GridLayout67);
            app.Spinner_ErodeRadius_Global.Limits = [0 10];
            app.Spinner_ErodeRadius_Global.ValueDisplayFormat = '%.0f';
            app.Spinner_ErodeRadius_Global.ValueChangedFcn = createCallbackFcn(app, @Spinner_ErodeRadius_GlobalValueChanged, true);
            app.Spinner_ErodeRadius_Global.Value = 3;
            app.GridLayout71 = uigridlayout(app.GridLayout_NS_Global);
            app.Label_68 = uilabel(app.GridLayout71);
            app.Label_68.Text = '前景特征';
            app.DropDown = uidropdown(app.GridLayout71);
            app.DropDown.Items = {'广泛自荧光', '离散标记', '自动检测'};
            app.DropDown.ItemsData = {'GAF', 'DM', 'AD'};
            app.DropDown.Value = 'GAF';
            app.Tab_AS_Global = uitab(app.TabGroup_Global);
            app.Tab_AS_Global.Title = '高级';
            app.GridLayout3 = uigridlayout(app.Tab_AS_Global);
            app.Panel_AS_PP_Global = uipanel(app.GridLayout3);
            app.Panel_AS_PP_Global.Title = '预处理';
            app.GridLayout4 = uigridlayout(app.Panel_AS_PP_Global);
            app.GridLayout70 = uigridlayout(app.GridLayout4);
            app.Label_51 = uilabel(app.GridLayout70);
            app.Label_51.Text = '中值滤波器';
            app.EditField_MedianFilter_Global = uieditfield(app.GridLayout70, 'text');
            app.EditField_MedianFilter_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_MedianFilter_GlobalValueChanged, true);
            app.EditField_MedianFilter_Global.Placeholder = '3,3,3';
            app.Label_55 = uilabel(app.GridLayout70);
            app.Label_55.Text = '最大Z漂移';
            app.EditField_OptimalZ_MaxShift_Global = uieditfield(app.GridLayout70, 'numeric');
            app.EditField_OptimalZ_MaxShift_Global.Limits = [0 Inf];
            app.EditField_OptimalZ_MaxShift_Global.ValueDisplayFormat = '%.1f';
            app.EditField_OptimalZ_MaxShift_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_OptimalZ_MaxShift_GlobalValueChanged, true);
            app.EditField_OptimalZ_MaxShift_Global.Value = 2;
            app.Label_52 = uilabel(app.GridLayout70);
            app.Label_52.Text = '高斯滤波器';
            app.EditField_GaussianFilter_Global = uieditfield(app.GridLayout70, 'text');
            app.EditField_GaussianFilter_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_GaussianFilter_GlobalValueChanged, true);
            app.EditField_GaussianFilter_Global.Placeholder = '3,3,3';
            app.Label_56 = uilabel(app.GridLayout70);
            app.Label_56.Text = 'Z优化公差';
            app.EditField_OptimalZ_Tol_Global = uieditfield(app.GridLayout70, 'numeric');
            app.EditField_OptimalZ_Tol_Global.Limits = [0 1];
            app.EditField_OptimalZ_Tol_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_OptimalZ_Tol_GlobalValueChanged, true);
            app.EditField_OptimalZ_Tol_Global.Value = 0.001;
            app.Panel_AS_Reg_Global = uipanel(app.GridLayout3);
            app.Panel_AS_Reg_Global.Title = '配准';
            app.GridLayout5 = uigridlayout(app.Panel_AS_Reg_Global);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Label_13 = uilabel(app.GridLayout6);
            app.Label_13.Text = '降采样';
            app.DropDown_DownSampling_Global = uidropdown(app.GridLayout6);
            app.DropDown_DownSampling_Global.Items = {'自动', '2X2', '3X3', '关闭'};
            app.DropDown_DownSampling_Global.ItemsData = {'auto', '2X2', '3X3', 'close'};
            app.DropDown_DownSampling_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_DownSampling_GlobalValueChanged, true);
            app.DropDown_DownSampling_Global.Tooltip = {''};
            app.DropDown_DownSampling_Global.Value = 'auto';
            app.Label_10 = uilabel(app.GridLayout6);
            app.Label_10.Text = '模态';
            app.DropDown_RegModal = uidropdown(app.GridLayout6);
            app.DropDown_RegModal.Items = {'单模态', '多模态'};
            app.DropDown_RegModal.ItemsData = {'monomodal', 'multimodal'};
            app.DropDown_RegModal.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegModalValueChanged, true);
            app.DropDown_RegModal.Tooltip = {''};
            app.DropDown_RegModal.Value = 'monomodal';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.Label_7 = uilabel(app.GridLayout7);
            app.Label_7.Text = '迭代数上限';
            app.EditField_MaxIterN_Global = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_MaxIterN_Global.Limits = [1 Inf];
            app.EditField_MaxIterN_Global.ValueDisplayFormat = '%.0f';
            app.EditField_MaxIterN_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_GlobalValueChanged, true);
            app.EditField_MaxIterN_Global.Tooltip = {''};
            app.EditField_MaxIterN_Global.Value = 50;
            app.Label_8 = uilabel(app.GridLayout7);
            app.Label_8.Text = '迭代因子';
            app.EditField_IterCoeff_Global = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_IterCoeff_Global.Limits = [0 1];
            app.EditField_IterCoeff_Global.ValueDisplayFormat = '%.3f';
            app.EditField_IterCoeff_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_IterCoeff_GlobalValueChanged, true);
            app.EditField_IterCoeff_Global.Tooltip = {''};
            app.EditField_IterCoeff_Global.Value = 0.5;
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.Label_9 = uilabel(app.GridLayout8);
            app.Label_9.Text = '插值';
            app.DropDown_Interp_Global = uidropdown(app.GridLayout8);
            app.DropDown_Interp_Global.Items = {'线性(快速)', '立方(高质量)'};
            app.DropDown_Interp_Global.ItemsData = {'linear', 'cubic'};
            app.DropDown_Interp_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_Interp_GlobalValueChanged, true);
            app.DropDown_Interp_Global.Tooltip = {''};
            app.DropDown_Interp_Global.Value = 'linear';
            app.Spinner_VPL_Global = uispinner(app.GridLayout8);
            app.Spinner_VPL_Global.Limits = [1 4];
            app.Spinner_VPL_Global.ValueDisplayFormat = '%.0f';
            app.Spinner_VPL_Global.ValueChangedFcn = createCallbackFcn(app, @Spinner_VPL_GlobalValueChanged, true);
            app.Spinner_VPL_Global.Tooltip = {''};
            app.Spinner_VPL_Global.Value = 3;
            app.Label_VPL_Global = uilabel(app.GridLayout8);
            app.Label_VPL_Global.Text = '金字塔因子';
            app.Tab_MGlobal = uitab(app.TabGroup_AlgorithmSelection);
            app.Tab_MGlobal.Tooltip = {'基于控制点的区域变形'};
            app.Tab_MGlobal.Title = '全局(手动)';
            app.Tab_MGlobal.Tag = 'MGlobal';
            app.GridLayout33 = uigridlayout(app.Tab_MGlobal);
            app.GridLayout34 = uigridlayout(app.GridLayout33);
            app.CheckBox_Lock_MGlobal = uicheckbox(app.GridLayout34);
            app.CheckBox_Lock_MGlobal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Lock_MGlobalValueChanged, true);
            app.CheckBox_Lock_MGlobal.Tooltip = {'启用手动配准'};
            app.CheckBox_Lock_MGlobal.Text = '锁定';
            app.Label_Helps_MANGlobal = uilabel(app.GridLayout34);
            app.Label_Helps_MANGlobal.Text = '';
            app.Panel_Reg_MGlobal = uipanel(app.GridLayout33);
            app.Panel_Reg_MGlobal.Title = '配准';
            app.GridLayout35 = uigridlayout(app.Panel_Reg_MGlobal);
            app.GridLayout41 = uigridlayout(app.GridLayout35);
            app.Label_23 = uilabel(app.GridLayout41);
            app.Label_23.Text = '最小控制';
            app.EditField_CPNumber_MGlobal = uieditfield(app.GridLayout41, 'numeric');
            app.EditField_CPNumber_MGlobal.Limits = [1 Inf];
            app.EditField_CPNumber_MGlobal.ValueDisplayFormat = '%.0f';
            app.EditField_CPNumber_MGlobal.Editable = 'off';
            app.EditField_CPNumber_MGlobal.Tooltip = {''};
            app.EditField_CPNumber_MGlobal.Value = 1;
            app.Label_59 = uilabel(app.GridLayout41);
            app.Label_59.Text = '视角(法向)';
            app.DropDown_DView_MGlobal = uidropdown(app.GridLayout41);
            app.DropDown_DView_MGlobal.Items = {'XY平面(Z)', 'ZX平面(Y)', 'YZ平面(X)'};
            app.DropDown_DView_MGlobal.ItemsData = {'XY', 'ZX', 'YZ'};
            app.DropDown_DView_MGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_DView_MGlobalValueChanged, true);
            app.DropDown_DView_MGlobal.Value = 'XY';
            app.GridLayout42 = uigridlayout(app.GridLayout35);
            app.Label_22 = uilabel(app.GridLayout42);
            app.Label_22.Text = '几何变换';
            app.DropDown_RegTform_MGlobal = uidropdown(app.GridLayout42);
            app.DropDown_RegTform_MGlobal.Items = {'平移', '刚体', '仿射', '分段线性', '多项式'};
            app.DropDown_RegTform_MGlobal.ItemsData = {'translation', 'rigid', 'affine', 'pwl', 'poly'};
            app.DropDown_RegTform_MGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegTform_MGlobalValueChanged, true);
            app.DropDown_RegTform_MGlobal.Tooltip = {''};
            app.DropDown_RegTform_MGlobal.Value = 'translation';
            app.Label_60 = uilabel(app.GridLayout42);
            app.Label_60.Text = '投影';
            app.DropDown_Projection_MGlobal = uidropdown(app.GridLayout42);
            app.DropDown_Projection_MGlobal.Items = {'最大值', '最小值', '中位值', '平均值'};
            app.DropDown_Projection_MGlobal.ItemsData = {'max', 'min', 'median', 'mean'};
            app.DropDown_Projection_MGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_Projection_MGlobalValueChanged, true);
            app.DropDown_Projection_MGlobal.Value = 'max';
            app.GridLayout43 = uigridlayout(app.GridLayout35);
            app.Label_26 = uilabel(app.GridLayout43);
            app.Label_26.Text = '插值';
            app.DropDown_Interp_MGlobal = uidropdown(app.GridLayout43);
            app.DropDown_Interp_MGlobal.Items = {'线性(快速)', '立方(高质量)'};
            app.DropDown_Interp_MGlobal.ItemsData = {'linear', 'cubic'};
            app.DropDown_Interp_MGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_Interp_MGlobalValueChanged, true);
            app.DropDown_Interp_MGlobal.Tooltip = {''};
            app.DropDown_Interp_MGlobal.Value = 'linear';
            app.Label_24 = uilabel(app.GridLayout43);
            app.Label_24.Text = '多项式次数';
            app.Spinner_PolyOrder_MGlobal = uispinner(app.GridLayout43);
            app.Spinner_PolyOrder_MGlobal.Limits = [2 4];
            app.Spinner_PolyOrder_MGlobal.ValueDisplayFormat = '%.0f';
            app.Spinner_PolyOrder_MGlobal.ValueChangedFcn = createCallbackFcn(app, @Spinner_PolyOrder_MGlobalValueChanged, true);
            app.Spinner_PolyOrder_MGlobal.Tooltip = {''};
            app.Spinner_PolyOrder_MGlobal.Value = 2;
            app.GridLayout44 = uigridlayout(app.GridLayout35);
            app.Label_61 = uilabel(app.GridLayout44);
            app.Label_61.Text = '重采样比率';
            app.EditField_ReSampleRatio_MGlobal = uieditfield(app.GridLayout44, 'text');
            app.EditField_ReSampleRatio_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_ReSampleRatio_MGlobalValueChanged, true);
            app.EditField_ReSampleRatio_MGlobal.Placeholder = '1,1,1';
            app.CheckBox_ReSampleIsometric_MGlobal = uicheckbox(app.GridLayout44);
            app.CheckBox_ReSampleIsometric_MGlobal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ReSampleIsometric_MGlobalValueChanged, true);
            app.CheckBox_ReSampleIsometric_MGlobal.Tooltip = {'等距重采样将体积单元重构为立方体'};
            app.CheckBox_ReSampleIsometric_MGlobal.Text = '等距';
            app.GridLayout45 = uigridlayout(app.GridLayout35);
            app.GridLayout68 = uigridlayout(app.GridLayout45);
            app.EditField_TM11_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM11_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM11_MGlobalValueChanged, true);
            app.EditField_TM11_MGlobal.Placeholder = 'sxx';
            app.EditField_TM11_MGlobal.Value = '1';
            app.EditField_TM12_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM12_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM12_MGlobalValueChanged, true);
            app.EditField_TM12_MGlobal.Placeholder = 'sxy';
            app.EditField_TM12_MGlobal.Value = '0';
            app.EditField_TM13_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM13_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM13_MGlobalValueChanged, true);
            app.EditField_TM13_MGlobal.Placeholder = 'sxz';
            app.EditField_TM13_MGlobal.Value = '0';
            app.EditField_TM14_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM14_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM14_MGlobalValueChanged, true);
            app.EditField_TM14_MGlobal.Placeholder = 'tx';
            app.EditField_TM14_MGlobal.Value = '0';
            app.EditField_TM21_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM21_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM21_MGlobalValueChanged, true);
            app.EditField_TM21_MGlobal.Placeholder = 'syx';
            app.EditField_TM21_MGlobal.Value = '0';
            app.EditField_TM22_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM22_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM22_MGlobalValueChanged, true);
            app.EditField_TM22_MGlobal.Placeholder = 'syy';
            app.EditField_TM22_MGlobal.Value = '1';
            app.EditField_TM23_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM23_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM23_MGlobalValueChanged, true);
            app.EditField_TM23_MGlobal.Placeholder = 'syz';
            app.EditField_TM23_MGlobal.Value = '0';
            app.EditField_TM24_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM24_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM24_MGlobalValueChanged, true);
            app.EditField_TM24_MGlobal.Placeholder = 'ty';
            app.EditField_TM24_MGlobal.Value = '0';
            app.EditField_TM31_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM31_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM31_MGlobalValueChanged, true);
            app.EditField_TM31_MGlobal.Placeholder = 'szx';
            app.EditField_TM31_MGlobal.Value = '0';
            app.EditField_TM32_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM32_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM32_MGlobalValueChanged, true);
            app.EditField_TM32_MGlobal.Placeholder = 'szy';
            app.EditField_TM32_MGlobal.Value = '0';
            app.EditField_TM33_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM33_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM33_MGlobalValueChanged, true);
            app.EditField_TM33_MGlobal.Placeholder = 'szz';
            app.EditField_TM33_MGlobal.Value = '1';
            app.EditField_TM34_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM34_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM34_MGlobalValueChanged, true);
            app.EditField_TM34_MGlobal.Placeholder = 'tz';
            app.EditField_TM34_MGlobal.Value = '0';
            app.EditField_TM41_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM41_MGlobal.Editable = 'off';
            app.EditField_TM41_MGlobal.Enable = 'off';
            app.EditField_TM41_MGlobal.Placeholder = '0';
            app.EditField_TM42_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM42_MGlobal.Editable = 'off';
            app.EditField_TM42_MGlobal.Enable = 'off';
            app.EditField_TM42_MGlobal.Placeholder = '0';
            app.EditField_TM43_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM43_MGlobal.Editable = 'off';
            app.EditField_TM43_MGlobal.Enable = 'off';
            app.EditField_TM43_MGlobal.Placeholder = '0';
            app.EditField_TM44_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM44_MGlobal.Editable = 'off';
            app.EditField_TM44_MGlobal.Enable = 'off';
            app.EditField_TM44_MGlobal.Placeholder = '1';
            app.Label_TransformMatrix = uilabel(app.GridLayout45);
            app.Label_TransformMatrix.Text = '变换矩阵';
            app.GridLayout69 = uigridlayout(app.GridLayout45);
            app.CheckBox_ApplyTransformation = uicheckbox(app.GridLayout69);
            app.CheckBox_ApplyTransformation.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ApplyTransformationValueChanged, true);
            app.CheckBox_ApplyTransformation.Tooltip = {'指定变换并跳过选定控制点'};
            app.CheckBox_ApplyTransformation.Text = '应用';
            % Show the figure after all components are created
            app.OCRSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OCRegSetter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OCRSUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OCRSUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OCRSUIFigure)
        end
    end
end