classdef ProjectConfiguratorSetting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SettingUIFigure           matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout5               matlab.ui.container.GridLayout
        Button_Cancel             matlab.ui.control.Button
        Button_OK                 matlab.ui.control.Button
        GridLayout3               matlab.ui.container.GridLayout
        GridLayout4_3             matlab.ui.container.GridLayout
        nmLabel_3                 matlab.ui.control.Label
        bEditField_WaveLength_B   matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        GridLayout4_2             matlab.ui.container.GridLayout
        nmLabel_2                 matlab.ui.control.Label
        EditField_WaveLength_G    matlab.ui.control.NumericEditField
        gEditFieldLabel           matlab.ui.control.Label
        GridLayout4               matlab.ui.container.GridLayout
        nmLabel                   matlab.ui.control.Label
        EditField_WaveLenghth_R   matlab.ui.control.NumericEditField
        rLabel                    matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        GridLayout2               matlab.ui.container.GridLayout
        EditField_DataFolderName  matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        CheckBox_NameAsProject    matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        caller      % 1-by-1 Register object
        pos_caller  % 1-by-4 double array with integer
        config      % 1-by-1 struct with project configuration
        langmap     % 1-by-1 struct with language mapping
    end

    methods (Access = private)

        function InitData(app)
            
        end

        function InitAppearance(app)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.caller = caller;

            InitData(app);

            InitAppearance(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SettingUIFigure = uifigure('Visible', 'off');
            app.SettingUIFigure.Name = 'Setting';
            app.SettingUIFigure.Icon = 'setting_single.png';
            app.GridLayout = uigridlayout(app.SettingUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CheckBox_NameAsProject = uicheckbox(app.GridLayout2);
            app.CheckBox_NameAsProject.Text = '使用项目名';
            app.CheckBox_NameAsProject.Value = true;
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '数据文件夹';
            app.EditField_DataFolderName = uieditfield(app.GridLayout2, 'text');
            app.EditField_DataFolderName.Value = 'Untitled';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '图像颜色配置';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.rLabel = uilabel(app.GridLayout4);
            app.rLabel.Text = 'r';
            app.EditField_WaveLenghth_R = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_WaveLenghth_R.Limits = [380 780];
            app.EditField_WaveLenghth_R.Value = 694;
            app.nmLabel = uilabel(app.GridLayout4);
            app.nmLabel.Text = 'nm';
            app.GridLayout4_2 = uigridlayout(app.GridLayout3);
            app.gEditFieldLabel = uilabel(app.GridLayout4_2);
            app.gEditFieldLabel.Text = 'g';
            app.EditField_WaveLength_G = uieditfield(app.GridLayout4_2, 'numeric');
            app.EditField_WaveLength_G.Limits = [380 780];
            app.EditField_WaveLength_G.Value = 525;
            app.nmLabel_2 = uilabel(app.GridLayout4_2);
            app.nmLabel_2.Text = 'nm';
            app.GridLayout4_3 = uigridlayout(app.GridLayout3);
            app.bEditFieldLabel = uilabel(app.GridLayout4_3);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField_WaveLength_B = uieditfield(app.GridLayout4_3, 'numeric');
            app.bEditField_WaveLength_B.Limits = [380 780];
            app.bEditField_WaveLength_B.Value = 440;
            app.nmLabel_3 = uilabel(app.GridLayout4_3);
            app.nmLabel_3.Text = 'nm';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Button_OK = uibutton(app.GridLayout5, 'push');
            app.Button_OK.Text = '确定';
            app.Button_Cancel = uibutton(app.GridLayout5, 'push');
            app.Button_Cancel.Text = '取消';
            % Show the figure after all components are created
            app.SettingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectConfiguratorSetting(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SettingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SettingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SettingUIFigure)
        end
    end
end