classdef RegSetting_OC_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SettingUIFigure                 matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_AutoReg_Global              matlab.ui.container.Tab
        Spinner_Gamma                   matlab.ui.control.Spinner
        Label_14                        matlab.ui.control.Label
        EditField_ScaleThreshold        matlab.ui.control.NumericEditField
        Label_13                        matlab.ui.control.Label
        Spinner_IntensityThreshold      matlab.ui.control.Spinner
        Label_12                        matlab.ui.control.Label
        DropDown_RegionLabel            matlab.ui.control.DropDown
        Label_11                        matlab.ui.control.Label
        EditField_RegTemplate_Global    matlab.ui.control.EditField
        Label_9                         matlab.ui.control.Label
        DropDown_RegModal               matlab.ui.control.DropDown
        Label_8                         matlab.ui.control.Label
        DropDown_InterpAlg_Auto_Global  matlab.ui.control.DropDown
        Label_7                         matlab.ui.control.Label
        Label_VPL_Global                matlab.ui.control.Label
        Spinner_VPL_Global              matlab.ui.control.Spinner
        EditField_IterCoeff             matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        EditField_MaxIterN_Global       matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        EditField_InitStep              matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        EditField_MinStep               matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        DropDown_RegTform_Auto          matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        DropDown_RegSampling_Global     matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        CheckBox_GlobalOn               matlab.ui.control.CheckBox
        Image_CPU                       matlab.ui.control.Image
        Tab_AutoReg_Local               matlab.ui.container.Tab
        DropDown_GridUnitSize           matlab.ui.control.DropDown
        Label_23                        matlab.ui.control.Label
        EditField_RegTemplate_Local     matlab.ui.control.EditField
        Label_22                        matlab.ui.control.Label
        EditField_MaxIterN_Local        matlab.ui.control.EditField
        Label_21                        matlab.ui.control.Label
        EditField_AFS                   matlab.ui.control.NumericEditField
        Label_18                        matlab.ui.control.Label
        DropDown_InterpAlg_Auto_Local   matlab.ui.control.DropDown
        Label_17                        matlab.ui.control.Label
        DropDown_RegSampling_Local      matlab.ui.control.DropDown
        Label_15                        matlab.ui.control.Label
        Label_VPL_Local                 matlab.ui.control.Label
        Spinner_VPL_Local               matlab.ui.control.Spinner
        Image_GPU                       matlab.ui.control.Image
        CheckBox_LocalOn                matlab.ui.control.CheckBox
        Tab_ManualReg                   matlab.ui.container.Tab
        DropDown_TemplateSync           matlab.ui.control.DropDown
        Label_29                        matlab.ui.control.Label
        DropDown_InterpAlg_Manual       matlab.ui.control.DropDown
        Label_28                        matlab.ui.control.Label
        Spinner_WPN                     matlab.ui.control.Spinner
        Label_27                        matlab.ui.control.Label
        Spinner_PolyOrder               matlab.ui.control.Spinner
        Label_26                        matlab.ui.control.Label
        EditField_CPNumber              matlab.ui.control.NumericEditField
        Label_25                        matlab.ui.control.Label
        DropDown_RegTform_Manual        matlab.ui.control.DropDown
        Label_24                        matlab.ui.control.Label
        ButtonGroup_Smooth              matlab.ui.container.ButtonGroup
        Button_Smooth_Manual_N          matlab.ui.control.RadioButton
        Button_Smooth_Manual_Y          matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        calling_app;    % the parent app
        
        reg_opts;       % the registration options
        template_sync;  % the template synchronize mode, "global"/"local"
        hw_info;        % the hardware information struct

        langs_mapping;  % mapping for language
    end
    
    methods (Access = private)
        
        function InitData(app, mainapp)
            app.calling_app = mainapp;
            [app.reg_opts, app.template_sync] = ...
                app.calling_app.GetRegParams();
        end

        function InitPanel(app)
            SetAutoRegGlobalPanel(app);
            SetAutoRegLocalPanel(app);
            SetManualRegPanel(app);

            SetCrossOpts(app);
        end

        function SetAutoRegGlobalPanel(app)
            % set registration template
            app.DropDown_RegSampling_Global.Value = ...
                upper(app.reg_opts.volfixed.G(1));
            
            app.EditField_RegTemplate_Global.Value = ...
                app.reg_opts.volfixed.G(2);

            % set transformation
            app.DropDown_RegTform_Auto.Value = upper(app.reg_opts.AutoTform);

            % set the modal
            app.DropDown_RegModal.Value = upper(app.reg_opts.RegModal);

            % set pre-processing objects
            app.DropDown_RegionLabel.Value = app.reg_opts.RL;
            app.Spinner_Gamma.Value = app.reg_opts.GA;
            app.Spinner_IntensityThreshold.Value = app.reg_opts.InsTh;
            app.EditField_ScaleThreshold.Value = app.reg_opts.ScaleTh;

            % set iteration options
            app.EditField_MinStep.Value = app.reg_opts.MinStep;
            app.EditField_InitStep.Value = app.reg_opts.InitStep;
            app.EditField_MaxIterN_Global.Value = app.reg_opts.MaxIterN_Rigid;
            app.EditField_IterCoeff.Value = app.reg_opts.IterCoeff;

            % set algorithm options
            opts = app.calling_app.GetImgInfo();
            app.Spinner_VPL_Global.Limits = [1, ...
                floor(log(opts.slices)/log(4))+1];
            app.Spinner_VPL_Global.Value = app.reg_opts.VPL_Rigid;

            app.DropDown_InterpAlg_Auto_Global.Value = ...
                upper(app.reg_opts.Interp_Rigid);
        end

        function SetAutoRegLocalPanel(app)
            % set registration template
            app.DropDown_RegSampling_Local.Value = ...
                upper(app.reg_opts.volfixed.L(1));

            app.EditField_RegTemplate_Local.Value = ...
                app.reg_opts.volfixed.L(2);
            
            % set grid
            switch app.reg_opts.GridUnit
                case "auto"
                    app.DropDown_GridUnitSize.Value = "AUTO";
                case "3 3 1"
                    app.DropDown_GridUnitSize.Value = "3X3X1";
                case "5 5 1"
                    app.DropDown_GridUnitSize.Value = "5X5X1";
                otherwise
                    app.DropDown_GridUnitSize.Value = "CLOSE";
            end

            % set iteration options
            app.EditField_MaxIterN_Local.Value = ...
                num2str(app.reg_opts.MaxIterN_NonRigid);

            % set algorithm options
            app.EditField_AFS.Value = app.reg_opts.AFS;
            app.Spinner_VPL_Local.Limits = app.Spinner_VPL_Global.Limits;
            app.Spinner_VPL_Local.Value = app.Spinner_VPL_Global.Value;

            app.DropDown_InterpAlg_Auto_Local.Value = ...
                upper(app.reg_opts.Interp_NonRigid);
        end

        function SetManualRegPanel(app)
            % set the manual registration parameters
            switch app.reg_opts.ManualTform
                case "polynomial"
                    app.DropDown_RegTform_Manual.Value = "POLYNOMIAL";
                    app.Spinner_PolyOrder.Enable = "on";
                    app.Spinner_WPN.Enable = "off";
                case "lwm"
                    app.DropDown_RegTform_Manual.Value = "LWM";
                    app.Spinner_WPN.Enable = "on";
                    app.Spinner_PolyOrder.Enable = "off";
                    AppendTextArea_Log(app,"提示：局部加权平均推荐控制量12");
                case "nonreflectivesimilarity"
                    app.DropDown_RegTform_Manual.Value = "SIMILARITY";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
                case "affine"
                    app.DropDown_RegTform_Manual.Value = "AFFINE";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
                case "projective"
                    app.DropDown_RegTform_Manual.Value = "PROJECTION";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
                case "pwl"
                    app.DropDown_RegTform_Manual.Value = "PWL";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
            end
            app.Spinner_PolyOrder.Value = app.reg_opts.Degree;
            app.Spinner_WPN.Value = app.reg_opts.Nlwm;
            UpdateMinCPN(app);

            % set algorithm options
            app.DropDown_InterpAlg_Manual.Value = ...
                upper(app.reg_opts.ManualInterp);

            if app.reg_opts.ManualEdgeSmooth == true
                app.ButtonGroup_Smooth.SelectedObject ...
                    = app.Button_Smooth_Manual_Y;
            else
                app.ButtonGroup_Smooth.SelectedObject ...
                    = app.Button_Smooth_Manual_N;
            end

            app.DropDown_TemplateSync.Value = upper(app.template_sync);
        end

        function SetCrossOpts(app)
            % set registration mode
            switch app.reg_opts.RegMode
                case "global-only"
                    app.CheckBox_GlobalOn.Value = true;
                    app.CheckBox_LocalOn.Value = false;
                case "local-only"
                    app.CheckBox_GlobalOn.Value = false;
                    app.CheckBox_LocalOn.Value = true;
                case "mix"
                    app.CheckBox_GlobalOn.Value = true;
                    app.CheckBox_LocalOn.Value = true;
                otherwise   % some times may be none
                    app.CheckBox_GlobalOn.Value = false;
                    app.CheckBox_LocalOn.Value = false;
            end

        end

        function UpdateMinCPN(app)
            switch app.DropDown_RegTform_Manual.Value
                case "SIMILARITY"
                    app.EditField_CPNumber.Value = 2;
                case "AFFINE"
                    app.EditField_CPNumber.Value = 3;
                case "PROJECTION"
                    app.EditField_CPNumber.Value = 4;
                case "PWL"
                    app.EditField_CPNumber.Value = 4;
                case "POLYNOMIAL"
                    switch app.Spinner_PolyOrder.Value
                        case 2
                            app.EditField_CPNumber.Value = 6;
                        case 3
                            app.EditField_CPNumber.Value = 10;
                        case 4
                            app.EditField_CPNumber.Value = 15;
                        otherwise
                    end
                case "LWM"
                    app.EditField_CPNumber.Value = 6;
                otherwise
            end
        end

        function UpdateRegProgram(app)
            if app.CheckBox_GlobalOn.Value == true && ...
                    app.CheckBox_LocalOn.Value == true
                app.reg_opts.RegMode = "mix";
            elseif app.CheckBox_GlobalOn.Value == true && ...
                    app.CheckBox_LocalOn.Value == false
                app.reg_opts.RegMode = "global-only";
            elseif app.CheckBox_GlobalOn.Value == false && ...
                    app.CheckBox_LocalOn.Value == true
                app.reg_opts.RegMode = "local-only";
            else
                app.reg_opts.RegMode = "none"; 
            end
        end


        function InitHardwareDetector(app)
            [hw_logo, app.hw_info] = GetHardwareInfo();
            app.Image_CPU.Visible = "on";
            app.Image_GPU.Visible = "on";

            switch hw_logo.cpu
                case "Intel"
                    app.Image_CPU.ImageSource = "sources/intel_logo.png";
                case "AMD"
                    app.Image_CPU.ImageSource = "sources/amd_logo.png";
                case "Unknown"
                    app.Image_CPU.ImageSource = "sources/unknown_logo.png";
                otherwise
                    app.Image_CPU.Visible = "off";
            end
            switch hw_logo.gpu
                case "Intel"
                    app.Image_GPU.ImageSource = "sources/intel_logo.png";
                case "AMD"
                    app.Image_GPU.ImageSource = "sources/amd_logo.png";
                case "Nvidia"
                    app.Image_GPU.ImageSource = "sources/nvidia_logo.png";
                case "Unknown"
                    app.Image_GPU.ImageSource = "sources/unknown_logo.png";
                otherwise
                    app.Image_GPU.Visible = "off";
            end
        end
    end

    methods (Access = public)
        
        function FontBlink(app,components,ts,c)
            % This function for app components font blink
            arguments
                app (1,1) RegSetting_OC_GUI;
                components string {mustBeVector}; % target  source
                ts (:,2) double {mustBePositive} = [0.25,   0.25; ...
                    0.25,   0.25; ...
                    0.25,   0.25]   %time series, seconds
                % c is RGB array
                c (2,3) double {mustBeInRange(c,0,1)} = [1,0,0; ... % target
                    0,0,0];    % source
            end

            N = numel(components);

            for t = 1:size(ts,1)
                for k = 1:N
                    if isprop(app.(components(k)),"FontColor")
                        app.(components(k)).FontColor = c(1,:);
                    end
                end
                pause(ts(t,1));
                for k = 1:N
                    if isprop(app.(components(k)),"FontColor")
                        app.(components(k)).FontColor = c(2,:);
                    end
                end
                pause(ts(t,2));
            end
        end

        function LockPanel(app)
            % lock the auto registration (global) panel
            app.EditField_RegTemplate_Global.Enable = "off";
            app.DropDown_RegSampling_Global.Enable = "off";
            app.DropDown_RegTform_Auto.Enable = "off";
            app.DropDown_RegModal.Enable = "off";
            app.DropDown_RegionLabel.Enable = "off";
            app.Spinner_Gamma.Enable = "off";
            app.Spinner_IntensityThreshold.Enable = "off";
            app.EditField_ScaleThreshold.Enable = "off";
            app.EditField_MinStep.Enable = "off";
            app.EditField_InitStep.Enable = "off";
            app.EditField_MaxIterN_Global.Enable = "off";
            app.EditField_IterCoeff.Enable = "off";
            app.DropDown_InterpAlg_Auto_Global.Enable = "off";
            app.Spinner_VPL_Global.Enable = "off";
            app.CheckBox_GlobalOn.Enable = "off";

            % lock the auto registration (local) panel
            app.EditField_RegTemplate_Local.Enable = "off";
            app.DropDown_RegSampling_Local.Enable = "off";
            app.DropDown_GridUnitSize.Enable = "off";
            app.EditField_MaxIterN_Local.Enable = "off";
            app.EditField_AFS.Enable = "off";
            app.DropDown_InterpAlg_Auto_Local.Enable = "off";
            app.Spinner_VPL_Local.Enable = "off";
            app.CheckBox_LocalOn.Enable = "off";

            % lock the manual registration panel
            app.DropDown_RegTform_Manual.Enable = "off";
            app.EditField_CPNumber.Enable = "off";
            app.Spinner_PolyOrder.Enable = "off";
            app.Spinner_WPN.Enable = "off";
            app.ButtonGroup_Smooth.Enable = "off";
            app.DropDown_InterpAlg_Manual.Enable = "off";
            app.DropDown_TemplateSync.Enable = "off";
        end

        function UnlockPanel(app)
            % unlock the auto registration (global) panel
            app.EditField_RegTemplate_Global.Enable = "on";
            app.DropDown_RegSampling_Global.Enable = "on";
            app.DropDown_RegTform_Auto.Enable = "on";
            app.DropDown_RegModal.Enable = "on";
            app.DropDown_RegionLabel.Enable = "on";
            app.Spinner_Gamma.Enable = "on";
            app.Spinner_IntensityThreshold.Enable = "on";
            app.EditField_ScaleThreshold.Enable = "on";
            app.EditField_MinStep.Enable = "on";
            app.EditField_InitStep.Enable = "on";
            app.EditField_MaxIterN_Global.Enable = "on";
            app.EditField_IterCoeff.Enable = "on";
            app.DropDown_InterpAlg_Auto_Global.Enable = "on";
            app.Spinner_VPL_Global.Enable = "on";
            app.CheckBox_GlobalOn.Enable = "on";

            % unlock the auto registration (local) panel
            app.EditField_RegTemplate_Local.Enable = "on";
            app.DropDown_RegSampling_Local.Enable = "on";
            app.DropDown_GridUnitSize.Enable = "on";
            app.EditField_MaxIterN_Local.Enable = "on";
            app.EditField_AFS.Enable = "on";
            app.DropDown_InterpAlg_Auto_Local.Enable = "on";
            app.Spinner_VPL_Local.Enable = "on";
            app.CheckBox_LocalOn.Enable = "on";

            % unlock the manual registration panel
            app.DropDown_RegTform_Manual.Enable = "on";
            app.ButtonGroup_Smooth.Enable = "on";
            app.DropDown_InterpAlg_Manual.Enable = "on";
            app.DropDown_TemplateSync.Enable = "on"; 
        end

        function Refresh(app)
            InitData(app);
            InitPanel(app);
        end

        function RefreshPanelLanguage(app)
            [~, app.langs_mapping] = app.calling_app.GetLangsInfo();
            
            app.Tab_AutoReg_Global.Title = app.langs_mapping.GLOBAL_REGISTRATION_AUTO;
            app.Tab_AutoReg_Local.Title = app.langs_mapping.LOCAL_REGISTRATION_AUTO;
            app.Tab_ManualReg.Title = app.langs_mapping.GLOBAL_REGISTRATION_MANUAL;
            app.Label.Text = app.langs_mapping.SAMPLING;
            app.Label_2.Text = app.langs_mapping.GEOMETRIC_TRANSFORMATION;
            app.Label_3.Text = app.langs_mapping.MIN_ITERATION_STEP;
            app.Label_4.Text = app.langs_mapping.INITIAL_ITERATION_STEP;
            app.Label_5.Text = app.langs_mapping.MAX_ITERATION_N;
            app.Label_6.Text = app.langs_mapping.ITERATION_COEFFICIENT;
            app.Label_7.Text = app.langs_mapping.INTERPOLATION;
            app.Label_8.Text = app.langs_mapping.MODAL;
            app.Label_9.Text = app.langs_mapping.REGISTRATION_TEMPLATE;
            app.Label_VPL_Global.Text = app.langs_mapping.PYRAMID_COEFFICIENT;
            app.Label_11.Text = app.langs_mapping.REGION_LABEL;
            app.Label_12.Text = app.langs_mapping.INTENSITY_THRESHOLD;
            app.Label_13.Text = app.langs_mapping.SCALE_THRESHOLD;
            app.Label_14.Text = app.langs_mapping.GAMMA;
            app.Label_15.Text = app.langs_mapping.SAMPLING;
            app.Label_17.Text = app.langs_mapping.INTERPOLATION;
            app.Label_18.Text = app.langs_mapping.ACCUMULATIVE_FIELD_SMOOTHING;
            app.Label_21.Text = app.langs_mapping.MAX_ITERATION_N;
            app.Label_22.Text = app.langs_mapping.REGISTRATION_TEMPLATE;
            app.Label_23.Text = app.langs_mapping.GRID_UNIT_SIZE;
            app.Label_VPL_Local.Text = app.langs_mapping.PYRAMID_COEFFICIENT;
            app.Label_24.Text = app.langs_mapping.GEOMETRIC_TRANSFORMATION;
            app.Label_25.Text = app.langs_mapping.MIN_CONTROL_VAL;
            app.Label_26.Text = app.langs_mapping.POLYNOMIAL_ORDER;
            app.Label_27.Text = app.langs_mapping.WEIGHTED_POINTS_NUMBER;
            app.Label_28.Text = app.langs_mapping.INTERPOLATION;
            app.Label_29.Text = app.langs_mapping.TEMPLATE_SYNCHRONISATION;
            app.DropDown_RegSampling_Global.Items = [app.langs_mapping.MEAN,...
                app.langs_mapping.MEDIAN, app.langs_mapping.MIN, app.langs_mapping.MAX];
            app.DropDown_RegTform_Auto.Items = [app.langs_mapping.TRANSLATION,...
                app.langs_mapping.RIGID, app.langs_mapping.SIMILARITY, ...
                app.langs_mapping.AFFINE];
            app.DropDown_RegModal.Items = [app.langs_mapping.MONOMODAL, ...
                app.langs_mapping.MULTIMODAL];
            app.DropDown_InterpAlg_Auto_Global.Items = [app.langs_mapping.NEAREST_FAST,...
                app.langs_mapping.LINEARITY, app.langs_mapping.CUBIC_HIGH_QUALITY];
            app.DropDown_RegSampling_Local.Items = [app.langs_mapping.MEAN,...
                app.langs_mapping.MEDIAN, app.langs_mapping.MIN, app.langs_mapping.MAX];
            app.DropDown_GridUnitSize.Items = [app.langs_mapping.AUTO, "2X2X1", ...
                "3X3X1","4X4X1",app.langs_mapping.CLOSE];
            app.DropDown_InterpAlg_Auto_Local.Items = [app.langs_mapping.NEAREST_FAST,...
                app.langs_mapping.LINEARITY, app.langs_mapping.CUBIC_HIGH_QUALITY];
            app.DropDown_RegTform_Manual.Items = [app.langs_mapping.SIMILARITY,...
                app.langs_mapping.AFFINE, app.langs_mapping.PROJECTION, ...
                app.langs_mapping.PIECEWISE_LINEAR, app.langs_mapping.POLYNOMIAL,...
                app.langs_mapping.LOCAL_WEIGHTED_MEAN];
            app.DropDown_InterpAlg_Manual.Items = [app.langs_mapping.NEAREST_FAST,...
                app.langs_mapping.LINEARITY, app.langs_mapping.CUBIC_HIGH_QUALITY];
            app.DropDown_TemplateSync.Items = [app.langs_mapping.GLOBAL_REGISTRATION_AUTO, ...
                app.langs_mapping.LOCAL_REGISTRATION_AUTO];
            app.CheckBox_GlobalOn.Text = app.langs_mapping.ENABLE;
            app.CheckBox_LocalOn.Text = app.langs_mapping.ENABLE;
            app.ButtonGroup_Smooth.Title = app.langs_mapping.EDGE_SMOOTHING;
            app.Button_Smooth_Manual_Y.Text = app.langs_mapping.YES;
            app.Button_Smooth_Manual_N.Text = app.langs_mapping.NO;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            InitData(app, mainapp);

            InitPanel(app);

            InitHardwareDetector(app);

            RefreshPanelLanguage(app);
        end

        % Value changed function: EditField_RegTemplate_Global
        function EditField_RegTemplate_GlobalValueChanged(app, event)
            value = app.EditField_RegTemplate_Global.Value;
            opts = app.calling_app.GetImgInfo();
            value = string(value).replace("end",num2str(opts.frames));
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || numel(value) > opts.frames
                % blink the field
                FontBlink(app,"EditField_RegTemplate_Global");
                app.EditField_RegTemplate_Global.Value = ...
                    app.reg_opts.volfixed.G(2).join(",");
            else
                app.reg_opts.volfixed.G(2) = value;
            end

            app.calling_app.SetRegParams(app.reg_opts);
            
        end

        % Value changed function: DropDown_RegSampling_Global
        function DropDown_RegSampling_GlobalValueChanged(app, event)
            value = app.DropDown_RegSampling_Global.Value;

            app.reg_opts.volfixed.G(1) = lower(value);
            
            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_RegTform_Auto
        function DropDown_RegTform_AutoValueChanged(app, event)
            value = app.DropDown_RegTform_Auto.Value;

            app.reg_opts.AutoTform = lower(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_RegModal
        function DropDown_RegModalValueChanged(app, event)
            value = app.DropDown_RegModal.Value;
            
            app.reg_opts.RegModel = lower(value);
            switch value
                case "MONOMODAL"
                    app.EditField_InitStep.Value = 0.1;
                    app.reg_opts.InitStep = 0.1;
                    app.EditField_IterCoeff.Value = 0.5;
                    app.reg_opts.IterCoeff = 0.5;
                case "MULTIMODAL"
                    app.EditField_InitStep.Value = 1e-2;
                    app.reg_opts.InitStep = 1e-2;
                    app.EditField_IterCoeff.Value = 1.05;
                    app.reg_opts.IterCoeff = 1.05;
                otherwise
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_RegionLabel
        function DropDown_RegionLabelValueChanged(app, event)
            value = app.DropDown_RegionLabel.Value;
            
            app.reg_opts.RL = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: Spinner_Gamma
        function Spinner_GammaValueChanged(app, event)
            value = app.Spinner_Gamma.Value;
            
            app.reg_opts.GA = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: Spinner_IntensityThreshold
        function Spinner_IntensityThresholdValueChanged(app, event)
            value = app.Spinner_IntensityThreshold.Value;
            
            app.reg_opts.InsTh = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_ScaleThreshold
        function EditField_ScaleThresholdValueChanged(app, event)
            value = app.EditField_ScaleThreshold.Value;
            
            app.reg_opts.ScaleTh = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_MinStep
        function EditField_MinStepValueChanged(app, event)
            value = app.EditField_MinStep.Value;
            
            app.reg_opts.MinStep = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_InitStep
        function EditField_InitStepValueChanged(app, event)
            value = app.EditField_InitStep.Value;
            
            app.reg_opts.InitStep = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_MaxIterN_Global
        function EditField_MaxIterN_GlobalValueChanged(app, event)
            value = app.EditField_MaxIterN_Global.Value;
            
            app.reg_opts.MaxIterN_Rigid = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_IterCoeff
        function EditField_IterCoeffValueChanged(app, event)
            value = app.EditField_IterCoeff.Value;
            
            app.reg_opts.IterCoeff = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_InterpAlg_Auto_Global
        function DropDown_InterpAlg_Auto_GlobalValueChanged(app, event)
            value = app.DropDown_InterpAlg_Auto_Global.Value;
            
            app.reg_opts.Interp_Rigid = lower(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: Spinner_VPL_Global
        function Spinner_VPL_GlobalValueChanged(app, event)
            value = app.Spinner_VPL_Global.Value;
            
            app.reg_opts.VPL_Rigid = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Image clicked function: Image_CPU
        function Image_CPUClicked(app, event)
            uialert(app.SettingUIFigure,app.hw_info.cpu,"通知","Icon","info");
        end

        % Value changed function: CheckBox_GlobalOn
        function CheckBox_GlobalOnValueChanged(app, event)
            UpdateRegProgram(app);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_RegTemplate_Local
        function EditField_RegTemplate_LocalValueChanged(app, event)
            value = app.EditField_RegTemplate_Local.Value;
            opts = app.calling_app.GetImgInfo();
            value = string(value).replace("end",num2str(opts.frames));
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || numel(value) > opts.frames
                % blink the field
                FontBlink(app,"EditField_RegTemplate_Local");
                app.EditField_RegTemplate_Local.Value = ...
                    app.reg_opts.volfixed.L(2).join(",");
            else
                app.reg_opts.volfixed.L(2) = value;
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_RegSampling_Local
        function DropDown_RegSampling_LocalValueChanged(app, event)
            value = app.DropDown_RegSampling_Local.Value;

            app.reg_opts.volfixed.L(1) = lower(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_GridUnitSize
        function DropDown_GridUnitSizeValueChanged(app, event)
            value = app.DropDown_GridUnitSize.Value;
            
            switch value
                case "AUTO"
                    app.reg_opts.GridUnit = "auto";
                case "3X3X1"
                    app.reg_opts.GridUnit = "3 3 1";
                case "5X5X1"
                    app.reg_opts.GridUnit = "5 5 1";
                otherwise
                    app.reg_opts.GridUnit = "1 1 1";
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_MaxIterN_Local
        function EditField_MaxIterN_LocalValueChanged(app, event)
            value = app.EditField_MaxIterN_Local.Value;
            
            value = str2num(value); %#ok<ST2NM>
            if isempty(value) || numel(value)>app.reg_opts.VPL_NonRigid
                % blink the field
                FontBlink(app,"EditField_MaxIterN_Local");
                app.EditField_MaxIterN_Local.Value = ...
                    string(app.reg_opts.MaxIterN_NonRigid).join(",");
            else
                app.reg_opts.MaxIterN_NonRigid = value;
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_AFS
        function EditField_AFSValueChanged(app, event)
            value = app.EditField_AFS.Value;
            
            app.reg_opts.AFS = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_InterpAlg_Auto_Local
        function DropDown_InterpAlg_Auto_LocalValueChanged(app, event)
            value = app.DropDown_InterpAlg_Auto_Local.Value;
            
            app.reg_opts.Interp_NonRigid = lower(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: Spinner_VPL_Local
        function Spinner_VPL_LocalValueChanged(app, event)
            value = app.Spinner_VPL_Local.Value;
            
            app.reg_opts.VPL_NonRigid = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Image clicked function: Image_GPU
        function Image_GPUClicked(app, event)
            uialert(app.SettingUIFigure,app.hw_info.gpu,"通知","Icon","info");
        end

        % Value changed function: CheckBox_LocalOn
        function CheckBox_LocalOnValueChanged(app, event)
            UpdateRegProgram(app);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_RegTform_Manual
        function DropDown_RegTform_ManualValueChanged(app, event)
            value = app.DropDown_RegTform_Manual.Value;
            
            switch value
                case "POLYNOMIAL"
                    app.reg_opts.ManualTform = "polynomial";
                    app.Spinner_PolyOrder.Enable = "on";
                    app.Spinner_WPN.Enable = "off";
                case "LWM"
                    app.reg_opts.ManualTform = "lwm";
                    app.Spinner_WPN.Enable = "on";
                    app.Spinner_PolyOrder.Enable = "off";
                    AppendTextArea_Log(app,"提示：局部加权平均推荐控制量12");
                otherwise
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
                    switch value
                        case "SIMILARITY"
                            app.reg_opts.ManualTform = "nonreflectivesimilarity";
                        case "AFFINE"
                            app.reg_opts.ManualTform = "affine";
                        case "PROJECTION"
                            app.reg_opts.ManualTform = "projective";
                        case "PWL"
                            app.reg_opts.ManualTform = "pwl";
                        otherwise
                    end
            end

            UpdateMinCPN(app);
            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: Spinner_PolyOrder
        function Spinner_PolyOrderValueChanged(app, event)
            value = app.Spinner_PolyOrder.Value;
            app.reg_opts.Degree = value;

            UpdateMinCPN(app);
            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: Spinner_WPN
        function Spinner_WPNValueChanged(app, event)
            value = app.Spinner_WPN.Value;
            app.reg_opts.Nlwm = value;

            UpdateMinCPN(app);
            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Selection changed function: ButtonGroup_Smooth
        function ButtonGroup_SmoothSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_Smooth.SelectedObject;
            
            switch selectedButton
                case app.Button_Smooth_Manual_Y
                    app.reg_opts.ManualEdgeSmooth = true;
                case app.Button_Smooth_Manual_N
                    app.reg_opts.ManualEdgeSmooth = false;
                otherwise
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_InterpAlg_Manual
        function DropDown_InterpAlg_ManualValueChanged(app, event)
            value = app.DropDown_InterpAlg_Manual.Value;
            
            app.reg_opts.ManualInterp = lower(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_TemplateSync
        function DropDown_TemplateSyncValueChanged(app, event)
            value = app.DropDown_TemplateSync.Value;
            
            app.template_sync = lower(value);

            app.calling_app.SetRegParams(app.reg_opts, app.template_sync);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            switch selectedTab
                case app.Tab_ManualReg
                    if app.reg_opts.RegType == "auto"
                        app.calling_app.GenSysMsg(["REGMODE_CURRENT_MANUAL",""]);
                    end
                    app.reg_opts.RegType = "manual";
                    app.calling_app.SetRegParams(app.reg_opts);
                otherwise
                    if app.reg_opts.RegType == "manual"
                        app.calling_app.GenSysMsg(["REGMODE_CURRENT_AUTO",""]);
                    end
                    app.reg_opts.RegType = "auto";
                    app.calling_app.SetRegParams(app.reg_opts);
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SettingUIFigure = uifigure('Visible', 'off');
            app.SettingUIFigure.Name = 'Setting';
            app.SettingUIFigure.Icon = 'setting.png';
            app.TabGroup = uitabgroup(app.SettingUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab_AutoReg_Global = uitab(app.TabGroup);
            app.Tab_AutoReg_Global.Title = '自动配准(全局)';
            app.Image_CPU = uiimage(app.Tab_AutoReg_Global);
            app.Image_CPU.ImageClickedFcn = createCallbackFcn(app, @Image_CPUClicked, true);
            app.Image_CPU.Visible = 'off';
            app.Image_CPU.Tooltip = {'CPU状态'};
            app.CheckBox_GlobalOn = uicheckbox(app.Tab_AutoReg_Global);
            app.CheckBox_GlobalOn.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GlobalOnValueChanged, true);
            app.CheckBox_GlobalOn.Tooltip = {'启用刚体配准'};
            app.CheckBox_GlobalOn.Text = '启用';
            app.Label = uilabel(app.Tab_AutoReg_Global);
            app.Label.Text = '采样';
            app.DropDown_RegSampling_Global = uidropdown(app.Tab_AutoReg_Global);
            app.DropDown_RegSampling_Global.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_RegSampling_Global.ItemsData = {'MEAN', 'MEDIAN', 'MIN', 'MAX'};
            app.DropDown_RegSampling_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegSampling_GlobalValueChanged, true);
            app.DropDown_RegSampling_Global.Tooltip = {'帧运算'};
            app.DropDown_RegSampling_Global.Value = 'MEAN';
            app.Label_2 = uilabel(app.Tab_AutoReg_Global);
            app.Label_2.Text = '几何变换';
            app.DropDown_RegTform_Auto = uidropdown(app.Tab_AutoReg_Global);
            app.DropDown_RegTform_Auto.Items = {'平移', '刚体', '相似', '仿射'};
            app.DropDown_RegTform_Auto.ItemsData = {'TRANSLATION', 'RIGID', 'SIMILARITY', 'AFFINE'};
            app.DropDown_RegTform_Auto.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegTform_AutoValueChanged, true);
            app.DropDown_RegTform_Auto.Tooltip = {'几何变换类型'};
            app.DropDown_RegTform_Auto.Value = 'TRANSLATION';
            app.Label_3 = uilabel(app.Tab_AutoReg_Global);
            app.Label_3.Text = '最小迭代步长';
            app.EditField_MinStep = uieditfield(app.Tab_AutoReg_Global, 'numeric');
            app.EditField_MinStep.Limits = [0 Inf];
            app.EditField_MinStep.ValueDisplayFormat = '%e';
            app.EditField_MinStep.ValueChangedFcn = createCallbackFcn(app, @EditField_MinStepValueChanged, true);
            app.EditField_MinStep.Tooltip = {'收敛判别步长'};
            app.EditField_MinStep.Value = 0.0001;
            app.Label_4 = uilabel(app.Tab_AutoReg_Global);
            app.Label_4.Text = '初始迭代步长';
            app.EditField_InitStep = uieditfield(app.Tab_AutoReg_Global, 'numeric');
            app.EditField_InitStep.Limits = [0 Inf];
            app.EditField_InitStep.ValueDisplayFormat = '%e';
            app.EditField_InitStep.ValueChangedFcn = createCallbackFcn(app, @EditField_InitStepValueChanged, true);
            app.EditField_InitStep.Tooltip = {'起始步长'};
            app.EditField_InitStep.Value = 0.1;
            app.Label_5 = uilabel(app.Tab_AutoReg_Global);
            app.Label_5.Text = '迭代数上限';
            app.EditField_MaxIterN_Global = uieditfield(app.Tab_AutoReg_Global, 'numeric');
            app.EditField_MaxIterN_Global.Limits = [1 Inf];
            app.EditField_MaxIterN_Global.ValueDisplayFormat = '%.0f';
            app.EditField_MaxIterN_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_GlobalValueChanged, true);
            app.EditField_MaxIterN_Global.Tooltip = {'最大迭代次数'};
            app.EditField_MaxIterN_Global.Value = 50;
            app.Label_6 = uilabel(app.Tab_AutoReg_Global);
            app.Label_6.Text = '迭代因子';
            app.EditField_IterCoeff = uieditfield(app.Tab_AutoReg_Global, 'numeric');
            app.EditField_IterCoeff.Limits = [0 1];
            app.EditField_IterCoeff.ValueDisplayFormat = '%.3f';
            app.EditField_IterCoeff.ValueChangedFcn = createCallbackFcn(app, @EditField_IterCoeffValueChanged, true);
            app.EditField_IterCoeff.Tooltip = {''};
            app.EditField_IterCoeff.Value = 0.5;
            app.Spinner_VPL_Global = uispinner(app.Tab_AutoReg_Global);
            app.Spinner_VPL_Global.Limits = [2 4];
            app.Spinner_VPL_Global.ValueDisplayFormat = '%.0f';
            app.Spinner_VPL_Global.ValueChangedFcn = createCallbackFcn(app, @Spinner_VPL_GlobalValueChanged, true);
            app.Spinner_VPL_Global.Tooltip = {'一个体积内拥有的层数越多，需要越大的金字塔因子提高配准精度，通常为3'};
            app.Spinner_VPL_Global.Value = 3;
            app.Label_VPL_Global = uilabel(app.Tab_AutoReg_Global);
            app.Label_VPL_Global.Text = '金字塔因子';
            app.Label_7 = uilabel(app.Tab_AutoReg_Global);
            app.Label_7.Text = '插值';
            app.DropDown_InterpAlg_Auto_Global = uidropdown(app.Tab_AutoReg_Global);
            app.DropDown_InterpAlg_Auto_Global.Items = {'最近邻(快速)', '线性', '立方(高质量)'};
            app.DropDown_InterpAlg_Auto_Global.ItemsData = {'NEAREST', 'LINEAR', 'CUBIC'};
            app.DropDown_InterpAlg_Auto_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_InterpAlg_Auto_GlobalValueChanged, true);
            app.DropDown_InterpAlg_Auto_Global.Tooltip = {'图像插值算法'};
            app.DropDown_InterpAlg_Auto_Global.Value = 'CUBIC';
            app.Label_8 = uilabel(app.Tab_AutoReg_Global);
            app.Label_8.Text = '模态';
            app.DropDown_RegModal = uidropdown(app.Tab_AutoReg_Global);
            app.DropDown_RegModal.Items = {'单模态', '多模态'};
            app.DropDown_RegModal.ItemsData = {'MONOMODAL', 'MULTIMODAL'};
            app.DropDown_RegModal.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegModalValueChanged, true);
            app.DropDown_RegModal.Tooltip = {'采集设备属性'};
            app.DropDown_RegModal.Value = 'MONOMODAL';
            app.Label_9 = uilabel(app.Tab_AutoReg_Global);
            app.Label_9.Text = '配准模板';
            app.EditField_RegTemplate_Global = uieditfield(app.Tab_AutoReg_Global, 'text');
            app.EditField_RegTemplate_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_RegTemplate_GlobalValueChanged, true);
            app.EditField_RegTemplate_Global.Value = '1';
            app.Label_11 = uilabel(app.Tab_AutoReg_Global);
            app.Label_11.Text = '区域标签';
            app.DropDown_RegionLabel = uidropdown(app.Tab_AutoReg_Global);
            app.DropDown_RegionLabel.Items = {'ORN', 'PN', 'LN', 'KC', 'MBON'};
            app.DropDown_RegionLabel.ItemsData = {'ORN', 'PN', 'LN', 'KC', 'MBON'};
            app.DropDown_RegionLabel.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegionLabelValueChanged, true);
            app.DropDown_RegionLabel.Value = 'ORN';
            app.Label_12 = uilabel(app.Tab_AutoReg_Global);
            app.Label_12.Text = '强度阈值';
            app.Spinner_IntensityThreshold = uispinner(app.Tab_AutoReg_Global);
            app.Spinner_IntensityThreshold.Step = 0.5;
            app.Spinner_IntensityThreshold.Limits = [-5 5];
            app.Spinner_IntensityThreshold.ValueDisplayFormat = '%.1f';
            app.Spinner_IntensityThreshold.ValueChangedFcn = createCallbackFcn(app, @Spinner_IntensityThresholdValueChanged, true);
            app.Spinner_IntensityThreshold.Value = 2;
            app.Label_13 = uilabel(app.Tab_AutoReg_Global);
            app.Label_13.Text = '尺度阈值(μm)';
            app.EditField_ScaleThreshold = uieditfield(app.Tab_AutoReg_Global, 'numeric');
            app.EditField_ScaleThreshold.Limits = [0.1 1000];
            app.EditField_ScaleThreshold.ValueDisplayFormat = '%.1f';
            app.EditField_ScaleThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_ScaleThresholdValueChanged, true);
            app.EditField_ScaleThreshold.Value = 2;
            app.Label_14 = uilabel(app.Tab_AutoReg_Global);
            app.Label_14.Text = '伽马';
            app.Spinner_Gamma = uispinner(app.Tab_AutoReg_Global);
            app.Spinner_Gamma.Step = 0.5;
            app.Spinner_Gamma.Limits = [0 4];
            app.Spinner_Gamma.ValueChangedFcn = createCallbackFcn(app, @Spinner_GammaValueChanged, true);
            app.Spinner_Gamma.Value = 2;
            app.Tab_AutoReg_Local = uitab(app.TabGroup);
            app.Tab_AutoReg_Local.Title = '自动配准(局部)';
            app.CheckBox_LocalOn = uicheckbox(app.Tab_AutoReg_Local);
            app.CheckBox_LocalOn.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LocalOnValueChanged, true);
            app.CheckBox_LocalOn.Tooltip = {'启用非刚体配准'};
            app.CheckBox_LocalOn.Text = '启用';
            app.Image_GPU = uiimage(app.Tab_AutoReg_Local);
            app.Image_GPU.ImageClickedFcn = createCallbackFcn(app, @Image_GPUClicked, true);
            app.Image_GPU.Visible = 'off';
            app.Image_GPU.Tooltip = {'GPU状态'};
            app.Spinner_VPL_Local = uispinner(app.Tab_AutoReg_Local);
            app.Spinner_VPL_Local.Limits = [2 4];
            app.Spinner_VPL_Local.ValueDisplayFormat = '%.0f';
            app.Spinner_VPL_Local.ValueChangedFcn = createCallbackFcn(app, @Spinner_VPL_LocalValueChanged, true);
            app.Spinner_VPL_Local.Tooltip = {'一个体积内拥有的层数越多，需要更大的金字塔因子，通常为3'};
            app.Spinner_VPL_Local.Value = 3;
            app.Label_VPL_Local = uilabel(app.Tab_AutoReg_Local);
            app.Label_VPL_Local.Text = '金字塔因子';
            app.Label_15 = uilabel(app.Tab_AutoReg_Local);
            app.Label_15.Text = '采样';
            app.DropDown_RegSampling_Local = uidropdown(app.Tab_AutoReg_Local);
            app.DropDown_RegSampling_Local.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_RegSampling_Local.ItemsData = {'MEAN', 'MEDIAN', 'MIN', 'MAX'};
            app.DropDown_RegSampling_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegSampling_LocalValueChanged, true);
            app.DropDown_RegSampling_Local.Tooltip = {'帧运算'};
            app.DropDown_RegSampling_Local.Value = 'MEAN';
            app.Label_17 = uilabel(app.Tab_AutoReg_Local);
            app.Label_17.Text = '插值';
            app.DropDown_InterpAlg_Auto_Local = uidropdown(app.Tab_AutoReg_Local);
            app.DropDown_InterpAlg_Auto_Local.Items = {'最近邻(快速)', '线性', '立方(高质量)'};
            app.DropDown_InterpAlg_Auto_Local.ItemsData = {'NEAREST', 'LINEAR', 'CUBIC'};
            app.DropDown_InterpAlg_Auto_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_InterpAlg_Auto_LocalValueChanged, true);
            app.DropDown_InterpAlg_Auto_Local.Tooltip = {'图像插值算法'};
            app.DropDown_InterpAlg_Auto_Local.Value = 'CUBIC';
            app.Label_18 = uilabel(app.Tab_AutoReg_Local);
            app.Label_18.Text = '累积场平滑';
            app.EditField_AFS = uieditfield(app.Tab_AutoReg_Local, 'numeric');
            app.EditField_AFS.Limits = [0.5 3];
            app.EditField_AFS.ValueDisplayFormat = '%.2f';
            app.EditField_AFS.ValueChangedFcn = createCallbackFcn(app, @EditField_AFSValueChanged, true);
            app.EditField_AFS.Tooltip = {'数值越小，局部非刚性形变越强'};
            app.EditField_AFS.Value = 1;
            app.Label_21 = uilabel(app.Tab_AutoReg_Local);
            app.Label_21.Text = '迭代数上限';
            app.EditField_MaxIterN_Local = uieditfield(app.Tab_AutoReg_Local, 'text');
            app.EditField_MaxIterN_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_LocalValueChanged, true);
            app.EditField_MaxIterN_Local.Tooltip = {'最大迭代次数，可以设置不同金字塔层的迭代上限'};
            app.EditField_MaxIterN_Local.Placeholder = '标量或数组';
            app.EditField_MaxIterN_Local.Value = '100';
            app.Label_22 = uilabel(app.Tab_AutoReg_Local);
            app.Label_22.Text = '配准模板';
            app.EditField_RegTemplate_Local = uieditfield(app.Tab_AutoReg_Local, 'text');
            app.EditField_RegTemplate_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_RegTemplate_LocalValueChanged, true);
            app.EditField_RegTemplate_Local.Value = '1';
            app.Label_23 = uilabel(app.Tab_AutoReg_Local);
            app.Label_23.Text = '网格单元体积';
            app.DropDown_GridUnitSize = uidropdown(app.Tab_AutoReg_Local);
            app.DropDown_GridUnitSize.Items = {'自动', '3X3X1', '5X5X1', '关闭'};
            app.DropDown_GridUnitSize.ItemsData = {'AUTO', '3X3X1', '5X5X1', '1X1X1'};
            app.DropDown_GridUnitSize.ValueChangedFcn = createCallbackFcn(app, @DropDown_GridUnitSizeValueChanged, true);
            app.DropDown_GridUnitSize.Value = 'AUTO';
            app.Tab_ManualReg = uitab(app.TabGroup);
            app.Tab_ManualReg.Title = '手动配准(全局)';
            app.ButtonGroup_Smooth = uibuttongroup(app.Tab_ManualReg);
            app.ButtonGroup_Smooth.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_SmoothSelectionChanged, true);
            app.ButtonGroup_Smooth.Tooltip = {'几何变换边界平滑，默认开启'};
            app.ButtonGroup_Smooth.Title = '边界平滑';
            app.Button_Smooth_Manual_Y = uiradiobutton(app.ButtonGroup_Smooth);
            app.Button_Smooth_Manual_Y.Text = '是';
            app.Button_Smooth_Manual_Y.Value = true;
            app.Button_Smooth_Manual_N = uiradiobutton(app.ButtonGroup_Smooth);
            app.Button_Smooth_Manual_N.Text = '否';
            app.Label_24 = uilabel(app.Tab_ManualReg);
            app.Label_24.Text = '几何变换';
            app.DropDown_RegTform_Manual = uidropdown(app.Tab_ManualReg);
            app.DropDown_RegTform_Manual.Items = {'非反射相似', '仿射', '投影', '分段线性', '多项式', '局部加权平均'};
            app.DropDown_RegTform_Manual.ItemsData = {'SIMILARITY', 'AFFINE', 'PROJECTION', 'PWL', 'POLYNOMIAL', 'LWM'};
            app.DropDown_RegTform_Manual.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegTform_ManualValueChanged, true);
            app.DropDown_RegTform_Manual.Tooltip = {'几何变换类型'};
            app.DropDown_RegTform_Manual.Value = 'SIMILARITY';
            app.Label_25 = uilabel(app.Tab_ManualReg);
            app.Label_25.Text = '最小控制量';
            app.EditField_CPNumber = uieditfield(app.Tab_ManualReg, 'numeric');
            app.EditField_CPNumber.Limits = [2 Inf];
            app.EditField_CPNumber.ValueDisplayFormat = '%.0f';
            app.EditField_CPNumber.Editable = 'off';
            app.EditField_CPNumber.Tooltip = {'控制点对数量的下限'};
            app.EditField_CPNumber.Value = 2;
            app.Label_26 = uilabel(app.Tab_ManualReg);
            app.Label_26.Text = '多项式次数';
            app.Spinner_PolyOrder = uispinner(app.Tab_ManualReg);
            app.Spinner_PolyOrder.Limits = [2 4];
            app.Spinner_PolyOrder.ValueDisplayFormat = '%.0f';
            app.Spinner_PolyOrder.ValueChangedFcn = createCallbackFcn(app, @Spinner_PolyOrderValueChanged, true);
            app.Spinner_PolyOrder.Tooltip = {'多项式变换参数'};
            app.Spinner_PolyOrder.Value = 3;
            app.Label_27 = uilabel(app.Tab_ManualReg);
            app.Label_27.Text = '加权点数';
            app.Spinner_WPN = uispinner(app.Tab_ManualReg);
            app.Spinner_WPN.Limits = [6 18];
            app.Spinner_WPN.ValueDisplayFormat = '%.0f';
            app.Spinner_WPN.ValueChangedFcn = createCallbackFcn(app, @Spinner_WPNValueChanged, true);
            app.Spinner_WPN.Tooltip = {'局部加权平均参数'};
            app.Spinner_WPN.Value = 12;
            app.Label_28 = uilabel(app.Tab_ManualReg);
            app.Label_28.Text = '插值';
            app.DropDown_InterpAlg_Manual = uidropdown(app.Tab_ManualReg);
            app.DropDown_InterpAlg_Manual.Items = {'最近邻(快速)', '线性', '立方(高质量)'};
            app.DropDown_InterpAlg_Manual.ItemsData = {'NEAREST', 'LINEAR', 'CUBIC'};
            app.DropDown_InterpAlg_Manual.ValueChangedFcn = createCallbackFcn(app, @DropDown_InterpAlg_ManualValueChanged, true);
            app.DropDown_InterpAlg_Manual.Tooltip = {'图像插值算法'};
            app.DropDown_InterpAlg_Manual.Value = 'CUBIC';
            app.Label_29 = uilabel(app.Tab_ManualReg);
            app.Label_29.Text = '模板同步';
            app.DropDown_TemplateSync = uidropdown(app.Tab_ManualReg);
            app.DropDown_TemplateSync.Items = {'自动配准(全局)', '自动配准(局部)'};
            app.DropDown_TemplateSync.ItemsData = {'GLOBAL', 'LOCAL'};
            app.DropDown_TemplateSync.ValueChangedFcn = createCallbackFcn(app, @DropDown_TemplateSyncValueChanged, true);
            app.DropDown_TemplateSync.Value = 'GLOBAL';
            % Show the figure after all components are created
            app.SettingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RegSetting_OC_GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SettingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SettingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SettingUIFigure)
        end
    end
end