classdef RegSetting_TC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RSTCUIFigure                    matlab.ui.Figure
        TabGroup_AlgorithmSelection     matlab.ui.container.TabGroup
        Tab_Global                      matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup_Global                 matlab.ui.container.TabGroup
        Tab_NS_Global                   matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        Panel_NS_Reg_Global             matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        GridLayout14                    matlab.ui.container.GridLayout
        Image_CPU                       matlab.ui.control.Image
        GridLayout15                    matlab.ui.container.GridLayout
        EditField_MaxStep_Local         matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        EditField_MinStep_Global        matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        GridLayout13                    matlab.ui.container.GridLayout
        DropDown_Functional_Global      matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        DropDown_Structured_Global      matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        GridLayout12                    matlab.ui.container.GridLayout
        DropDown_TformType_Global       matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        DropDown_TemplateSampling_Global  matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        GridLayout11                    matlab.ui.container.GridLayout
        CheckBox_AutoUpdateTemplate_Global  matlab.ui.control.CheckBox
        EditField_Template_Global       matlab.ui.control.EditField
        Label_11                        matlab.ui.control.Label
        Tab_AS_Global                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Panel_AS_Reg_Global             matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        Label_VPL_Global                matlab.ui.control.Label
        Spinner_VPL_Global              matlab.ui.control.Spinner
        DropDown_Interp_Global          matlab.ui.control.DropDown
        Label_9                         matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        EditField_IterCoeff             matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        EditField_MaxIterN_Global       matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        DropDown_RegModal               matlab.ui.control.DropDown
        Label_10                        matlab.ui.control.Label
        DropDown_DownSampling           matlab.ui.control.DropDown
        Label_13                        matlab.ui.control.Label
        Panel_NS_PP_Global              matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        EditField_GaussianFilter        matlab.ui.control.EditField
        Label_52                        matlab.ui.control.Label
        EditField_OptimalZ_Tol          matlab.ui.control.NumericEditField
        Label_56                        matlab.ui.control.Label
        EditField_OptimalZ_MaxIterN     matlab.ui.control.NumericEditField
        Label_55                        matlab.ui.control.Label
        EditField_MedianFilter          matlab.ui.control.EditField
        Label_51                        matlab.ui.control.Label
        EditField_OpenOperator          matlab.ui.control.EditField
        Label_53                        matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        Label_Helps_Global              matlab.ui.control.Label
        CheckBox_Lock_Global            matlab.ui.control.CheckBox
        Tab_Local                       matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout17                    matlab.ui.container.GridLayout
        Label_Helps_Local               matlab.ui.control.Label
        CheckBox_Lock_Local             matlab.ui.control.CheckBox
        GridLayout32                    matlab.ui.container.GridLayout
        TabGroup_Local                  matlab.ui.container.TabGroup
        Tab_NS_Local                    matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        Panel_NS_Local                  matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        Label_VPL_Global_2              matlab.ui.control.Label
        Spinner_NS_VPL_Local            matlab.ui.control.Spinner
        EditField_GridSpacing_Local     matlab.ui.control.EditField
        Label_57                        matlab.ui.control.Label
        EditField_GridRegularization_Local  matlab.ui.control.NumericEditField
        Label_58                        matlab.ui.control.Label
        Tab_AS_Local                    matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        GridLayout26                    matlab.ui.container.GridLayout
        Panel_AS_Local                  matlab.ui.container.Panel
        GridLayout27                    matlab.ui.container.GridLayout
        GridLayout31                    matlab.ui.container.GridLayout
        Label_VPL_Global_3              matlab.ui.control.Label
        Spinner_AS_VPL_Local            matlab.ui.control.Spinner
        DropDown_Interp_Local           matlab.ui.control.DropDown
        Label_16                        matlab.ui.control.Label
        GridLayout30                    matlab.ui.container.GridLayout
        GridLayout28                    matlab.ui.container.GridLayout
        GridLayout29                    matlab.ui.container.GridLayout
        EditField_AFS_Local             matlab.ui.control.NumericEditField
        Label_17                        matlab.ui.control.Label
        EditField_MaxIterN_Local        matlab.ui.control.EditField
        Label_20                        matlab.ui.control.Label
        Image_GPU                       matlab.ui.control.Image
        Panel_Common_Local              matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        GridLayout24                    matlab.ui.container.GridLayout
        CheckBox_RepAccOn_Local         matlab.ui.control.CheckBox
        DropDown_RepAcc_Local           matlab.ui.control.DropDown
        Label_19                        matlab.ui.control.Label
        GridLayout23                    matlab.ui.container.GridLayout
        DropDown_Functional_Local       matlab.ui.control.DropDown
        Label_18                        matlab.ui.control.Label
        DropDown_Structured_Local       matlab.ui.control.DropDown
        Label_15                        matlab.ui.control.Label
        GridLayout22                    matlab.ui.container.GridLayout
        DropDown_TemplateSampling_Local  matlab.ui.control.DropDown
        Label_14                        matlab.ui.control.Label
        EditField_Template_Local        matlab.ui.control.EditField
        Label_21                        matlab.ui.control.Label
        Tab_MGlobal                     matlab.ui.container.Tab
        GridLayout33                    matlab.ui.container.GridLayout
        Panel_Reg_MGlobal               matlab.ui.container.Panel
        GridLayout35                    matlab.ui.container.GridLayout
        GridLayout45                    matlab.ui.container.GridLayout
        GridLayout44                    matlab.ui.container.GridLayout
        CheckBox_ReSampleIsometric_MGlobal  matlab.ui.control.CheckBox
        EditField_ReSampleRatio_MGlobal  matlab.ui.control.EditField
        Label_61                        matlab.ui.control.Label
        GridLayout43                    matlab.ui.container.GridLayout
        Spinner_PolyOrder_MGlobal       matlab.ui.control.Spinner
        Label_24                        matlab.ui.control.Label
        DropDown_Interp_MGlobal         matlab.ui.control.DropDown
        Label_26                        matlab.ui.control.Label
        GridLayout42                    matlab.ui.container.GridLayout
        DropDown_Projection_MGlobal     matlab.ui.control.DropDown
        Label_60                        matlab.ui.control.Label
        DropDown_RegTform_MGlobal       matlab.ui.control.DropDown
        Label_22                        matlab.ui.control.Label
        GridLayout41                    matlab.ui.container.GridLayout
        DropDown_DView_MGlobal          matlab.ui.control.DropDown
        Label_59                        matlab.ui.control.Label
        EditField_CPNumber_MGlobal      matlab.ui.control.NumericEditField
        Label_23                        matlab.ui.control.Label
        GridLayout34                    matlab.ui.container.GridLayout
        Label_Helps_MANGlobal           matlab.ui.control.Label
        CheckBox_Lock_MGlobal           matlab.ui.control.CheckBox
        Tab_LTGlobal                    matlab.ui.container.Tab
        TabGroup_LongTerm_Params        matlab.ui.container.TabGroup
        Tab_PointCloud                  matlab.ui.container.Tab
        CheckBox_LongTerm_Review        matlab.ui.control.CheckBox
        EditField_ErrorThreshold        matlab.ui.control.NumericEditField
        Label_41                        matlab.ui.control.Label
        EditField_MaxIterN_LongTerm_PC  matlab.ui.control.NumericEditField
        Label_40                        matlab.ui.control.Label
        EditField_OutlierRatio          matlab.ui.control.NumericEditField
        Label_39                        matlab.ui.control.Label
        EditField_DownSampling_LongTerm_Param  matlab.ui.control.NumericEditField
        Label_38                        matlab.ui.control.Label
        DropDown_DownSampling_LongTerm_PC  matlab.ui.control.DropDown
        Label_37                        matlab.ui.control.Label
        EditField_ScaleThreshold_LongTerm  matlab.ui.control.NumericEditField
        Label_36                        matlab.ui.control.Label
        EditField_IntensityThreshold_LongTerm  matlab.ui.control.NumericEditField
        Label_34                        matlab.ui.control.Label
        Tab_Voxels                      matlab.ui.container.Tab
        EditField_MaxIterN_LongTerm_Voxel  matlab.ui.control.EditField
        Label_50                        matlab.ui.control.Label
        DropDown_RepAcc_LTLocal         matlab.ui.control.DropDown
        Label_49                        matlab.ui.control.Label
        EditField_AFS_LTLocal           matlab.ui.control.NumericEditField
        Label_48                        matlab.ui.control.Label
        CheckBox_RepAccOn_LTLocal       matlab.ui.control.CheckBox
        DropDown_DownSampling_LTGlobal  matlab.ui.control.DropDown
        Label_47                        matlab.ui.control.Label
        EditField_IterCoeff_LTGlobal    matlab.ui.control.NumericEditField
        Label_45                        matlab.ui.control.Label
        EditField_MaxStep_LTGlobal      matlab.ui.control.NumericEditField
        Label_43                        matlab.ui.control.Label
        EditField_MinStep_LTGlobal      matlab.ui.control.NumericEditField
        Label_42                        matlab.ui.control.Label
        DropDown_Signal_LongTerm        matlab.ui.control.DropDown
        Label_33                        matlab.ui.control.Label
        DropDown_Marker_LongTerm        matlab.ui.control.DropDown
        Label_32                        matlab.ui.control.Label
        CheckBox_AutoKeyFrame           matlab.ui.control.CheckBox
        EditField_RegTemplate_LongTerm  matlab.ui.control.EditField
        Label_31                        matlab.ui.control.Label
        DropDown_RegSampling_LongTerm   matlab.ui.control.DropDown
        Label_30                        matlab.ui.control.Label
        EditField_KeyFrame              matlab.ui.control.EditField
        Label_29                        matlab.ui.control.Label
        CheckBox_Lock_LTGlobal          matlab.ui.control.CheckBox
        Tab_LTLocal                     matlab.ui.container.Tab
        GridLayout36                    matlab.ui.container.GridLayout
        GridLayout38                    matlab.ui.container.GridLayout
        Panel_Common_LTLocal            matlab.ui.container.Panel
        TabGroup_LTLocal                matlab.ui.container.TabGroup
        Tab_NS_LTLocal                  matlab.ui.container.Tab
        GridLayout39                    matlab.ui.container.GridLayout
        Panel_NS_LTLocal                matlab.ui.container.Panel
        Tab_AS_LTLocal                  matlab.ui.container.Tab
        GridLayout40                    matlab.ui.container.GridLayout
        Panel_AS_LTLocal                matlab.ui.container.Panel
        GridLayout37                    matlab.ui.container.GridLayout
        Label_Helps_LTLocal             matlab.ui.control.Label
        CheckBox_Lock_LTLocal           matlab.ui.control.CheckBox
    end

    properties (Access = ?BugReporter, Constant)
        VER = "2.0.0"
    end
    
    properties (Access = private)
        caller;         % the parent app
        
        volopts;        % 1-by-12 table, with volumes options
        regopts;        % 1-by-1 regopt object
        fixdef;         % struct with {"Sampling", "Channel"}, the fixed volume defination
        opt_locked      % 1-by-5 table, indicate the options locked status
        hw_info;        % the hardware information struct

        langmap;        % mapping for language
    end

    methods (Access = ?Register)
        
        function FontBlink(app,components,ts,c)
            % This function for app components font blink
            arguments
                app (1,1) RegSetting_TC;
                components string {mustBeVector}; % target  source
                ts (:,2) double {mustBePositive} = [0.25,   0.25; ...
                    0.25,   0.25; ...
                    0.25,   0.25]   %time series, seconds
                % c is RGB array
                c (2,3) double {mustBeInRange(c,0,1)} = [1,0,0; ... % target
                    0,0,0];    % source
            end

            N = numel(components);

            for t = 1:size(ts,1)
                for k = 1:N
                    if isprop(app.(components(k)),"FontColor")
                        app.(components(k)).FontColor = c(1,:);
                    end
                end
                pause(ts(t,1));
                for k = 1:N
                    if isprop(app.(components(k)),"FontColor")
                        app.(components(k)).FontColor = c(2,:);
                    end
                end
                pause(ts(t,2));
            end
        end

        % function LockPanel(app)
        %     % lock the auto registration (local) panel
        %     app.EditField_Template_Local.Enable = "off";
        %     app.DropDown_TemplateSampling_Local.Enable = "off";
        %     app.DropDown_Structured_Local.Enable = "off";
        %     app.DropDown_Functional_Local.Enable = "off";
        %     app.EditField_MaxIterN_Local.Enable = "off";
        %     app.EditField_AFS_Local.Enable = "off";
        %     app.CheckBox_RepAccOn_Local.Enable = "off";
        %     app.DropDown_RepAcc_Local.Enable = "off";
        %     app.DropDown_Interp_Local.Enable = "off";
        %     app.Spinner_VPL_Local_1.Enable = "off";
        %     app.CheckBox_Lock_Local.Enable = "off";
        % 
        %     % lock the longterm panel
        %     app.EditField_RegTemplate_LongTerm.Enable = "off";
        %     app.DropDown_RegSampling_LongTerm.Enable = "off";
        %     app.CheckBox_AutoKeyFrame.Enable = "off";
        %     app.EditField_KeyFrame.Enable = "off";
        %     app.DropDown_Marker_LongTerm.Enable = "off";
        %     app.DropDown_Signal_LongTerm.Enable = "off";
        %     app.EditField_IntensityThreshold_LongTerm.Enable = "off";
        %     app.EditField_ScaleThreshold_LongTerm.Enable = "off";
        %     app.DropDown_DownSampling_LongTerm_PC.Enable = "off";
        %     app.EditField_DownSampling_LongTerm_Param.Enable = "off";
        %     app.EditField_OutlierRatio.Enable = "off";
        %     app.EditField_MaxIterN_LongTerm_PC.Enable = "off";
        %     app.EditField_ErrorThreshold.Enable = "off";
        %     app.CheckBox_LongTerm_Review.Enable = "off";
        %     app.EditField_MinStep_LTGlobal.Enable = "off";
        %     app.DropDown_DownSampling_LTGlobal.Enable = "off";
        %     app.EditField_MaxStep_LTGlobal.Enable = "off";
        %     app.EditField_IterCoeff_LTGlobal.Enable = "off";
        %     app.EditField_MaxIterN_LongTerm_Voxel.Enable = "off";
        %     app.CheckBox_RepAccOn_LTLocal.Enable = "off";
        %     app.EditField_AFS_LTLocal.Enable = "off";
        %     app.DropDown_RepAcc_LTLocal.Enable = "off";
        %     app.CheckBox_Lock_LTGlobal.Enable = "off";
        % 
        %     % lock the manual registration panel
        %     app.DropDown_RegTform_MGlobal.Enable = "off";
        %     app.EditField_CPNumber_MGlobal.Enable = "off";
        %     app.Spinner_PolyOrder_MGlobal.Enable = "off";
        %     app.ButtonGroup_Smooth.Enable = "off";
        %     app.DropDown_Interp_MGlobal.Enable = "off";
        %     app.DropDown_TemplateSync_MGlobal.Enable = "off";
        % end
        % 
        % function UnlockPanel(app)
        %     % unlock the auto registration (global) panel
        %     app.EditField_Template_Global.Enable = "on";
        %     app.DropDown_TemplateSampling_Global.Enable = "on";
        %     app.DropDown_TformType_Global.Enable = "on";
        %     app.DropDown_RegModal.Enable = "on";
        %     app.DropDown_Structured_Global.Enable = "on";
        %     app.DropDown_Functional_Global.Enable = "on";
        %     app.EditField_MinStep_Global.Enable = "on";
        %     app.EditField_MaxStep_Local.Enable = "on";
        %     app.EditField_MaxIterN_Global.Enable = "on";
        %     app.EditField_IterCoeff.Enable = "on";
        %     app.EditField_RobustCoeff.Enable = "on";
        %     app.DropDown_DownSampling.Enable = "on";
        %     app.DropDown_Interp_Global.Enable = "on";
        %     app.Spinner_VPL_Global.Enable = "on";
        %     app.CheckBox_Lock_Global.Enable = "on";
        % 
        %     % unlock the auto registration (local) panel
        %     app.EditField_Template_Local.Enable = "on";
        %     app.DropDown_TemplateSampling_Local.Enable = "on";
        %     app.DropDown_Structured_Local.Enable = "on";
        %     app.DropDown_Functional_Local.Enable = "on";
        %     app.EditField_MaxIterN_Local.Enable = "on";
        %     app.EditField_AFS_Local.Enable = "on";
        %     app.CheckBox_RepAccOn_Local.Enable = "on";
        %     if app.CheckBox_RepAccOn_Local.Value == true
        %         app.DropDown_RepAcc_Local.Enable = "on";
        %     end
        %     app.DropDown_Interp_Local.Enable = "on";
        %     app.Spinner_VPL_Local_1.Enable = "on";
        %     app.CheckBox_Lock_Local.Enable = "on";
        % 
        %     % unlock the longterm panel
        %     app.EditField_RegTemplate_LongTerm.Enable = "on";
        %     app.DropDown_RegSampling_LongTerm.Enable = "on";
        %     app.CheckBox_AutoKeyFrame.Enable = "on";
        %     if app.CheckBox_AutoKeyFrame.Value == false
        %         app.EditField_KeyFrame.Enable = "on";
        %     end
        %     app.DropDown_Marker_LongTerm.Enable = "on";
        %     app.DropDown_Signal_LongTerm.Enable = "on";
        %     app.EditField_IntensityThreshold_LongTerm.Enable = "on";
        %     app.EditField_ScaleThreshold_LongTerm.Enable = "on";
        %     app.DropDown_DownSampling_LongTerm_PC.Enable = "on";
        %     app.EditField_DownSampling_LongTerm_Param.Enable = "on";
        %     app.EditField_OutlierRatio.Enable = "on";
        %     app.EditField_MaxIterN_LongTerm_PC.Enable = "on";
        %     app.EditField_ErrorThreshold.Enable = "on";
        %     app.CheckBox_LongTerm_Review.Enable = "on";
        %     app.EditField_MinStep_LTGlobal.Enable = "on";
        %     app.DropDown_DownSampling_LTGlobal.Enable = "on";
        %     app.EditField_MaxStep_LTGlobal.Enable = "on";
        %     app.EditField_IterCoeff_LTGlobal.Enable = "on";
        %     app.EditField_MaxIterN_LongTerm_Voxel.Enable = "on";
        %     app.CheckBox_RepAccOn_LTLocal.Enable = "on";
        %     app.EditField_AFS_LTLocal.Enable = "on";
        %     if app.CheckBox_RepAccOn_LTLocal.Value == true
        %         app.DropDown_RepAcc_LTLocal.Enable = "on";
        %     end
        %     app.CheckBox_Lock_LTGlobal.Enable = "on";
        % 
        %     % unlock the manual registration panel
        %     app.DropDown_RegTform_MGlobal.Enable = "on";
        %     app.ButtonGroup_Smooth.Enable = "on";
        %     app.DropDown_Interp_MGlobal.Enable = "on";
        %     app.DropDown_TemplateSync_MGlobal.Enable = "on"; 
        % end

        function Refresh(app, mainapp)
            InitData(app, mainapp);
            InitPanel(app);
        end

        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            app.Tab_Global.Title = app.langmap.GLOBAL;
            app.Tab_Local.Title = app.langmap.LOCAL;
            app.Tab_MGlobal.Title = app.langmap.MGLOBAL;
            app.Tab_LTGlobal.Title = app.langmap.LTGLOBAL;
            app.Tab_LTLocal.Title = app.langmap.LTLOCAL;
            app.Tab_NS_Global.Title = app.langmap.USUAL;
            app.Tab_AS_Global.Title = app.langmap.ADVANCED;
            app.Tab_NS_Local.Title = app.langmap.USUAL;
            app.Tab_AS_Local.Title = app.langmap.ADVANCED;
            % app.Tab_NS_LTGlobal.Title = app.langmap.USUAL;
            % app.Tab_AS_LTGlobal.Title = app.langmap.ADVANCED;
            app.Tab_NS_LTLocal.Title = app.langmap.USUAL;
            app.Tab_AS_LTLocal.Title = app.langmap.ADVANCED;
            app.Panel_NS_Reg_Global.Title = app.langmap.ALIGNED;
            app.Panel_NS_PP_Global.Title = app.langmap.PREPROCESSING;
            app.Panel_AS_Reg_Global.Title = app.langmap.ALIGNED;
            app.Panel_Common_Local.Title = app.langmap.COMMON;
            app.Panel_Reg_MGlobal.Title = app.langmap.ALIGNED;
            app.Panel_Common_LTLocal.Title = app.langmap.COMMON;
            app.Tab_PointCloud.Title = app.langmap.POINT_CLOUD;
            app.Tab_Voxels.Title = app.langmap.VOXEL;
            app.Label.Text = app.langmap.SAMPLING;
            app.Label_2.Text = app.langmap.GEOMETRIC_TRANSFORMATION;
            app.Label_3.Text = app.langmap.LOCATION;
            app.Label_4.Text = app.langmap.SIGNAL;
            app.Label_5.Text = app.langmap.MIN_ITERATION_STEP;
            app.Label_6.Text = app.langmap.MAX_ITERATION_STEP;
            app.Label_7.Text = app.langmap.MAX_ITERATION_N;
            app.Label_8.Text = app.langmap.ITERATION_COEFFICIENT;
            app.Label_9.Text = app.langmap.INTERPOLATION;
            app.Label_10.Text = app.langmap.MODAL;
            app.Label_VPL_Global.Text = app.langmap.PYRAMID_COEFFICIENT;
            app.Label_VPL_Global_2.Text = app.langmap.PYRAMID_COEFFICIENT;
            app.Label_VPL_Global_3.Text = app.langmap.PYRAMID_COEFFICIENT;
            app.Label_11.Text = app.langmap.REGISTRATION_TEMPLATE;
            app.Label_13.Text = app.langmap.DOWNSAMPLING;
            app.Label_14.Text = app.langmap.SAMPLING;
            app.Label_15.Text = app.langmap.LOCATION;
            app.Label_16.Text = app.langmap.INTERPOLATION;
            app.Label_17.Text = app.langmap.ACCUMULATIVE_FIELD_SMOOTHING;
            app.Label_18.Text = app.langmap.SIGNAL;
            app.Label_19.Text = app.langmap.PRECISION;
            app.Label_20.Text = app.langmap.MAX_ITERATION_N;
            app.Label_21.Text = app.langmap.REGISTRATION_TEMPLATE;
            app.Label_22.Text = app.langmap.GEOMETRIC_TRANSFORMATION;
            app.Label_23.Text = app.langmap.MIN_CONTROL_VAL;
            app.Label_24.Text = app.langmap.POLYNOMIAL_ORDER;
            app.Label_26.Text = app.langmap.INTERPOLATION;
            app.Label_29.Text = app.langmap.KEY_FRAME;
            app.Label_30.Text = app.langmap.SAMPLING;
            app.Label_31.Text = app.langmap.REGISTRATION_TEMPLATE;
            app.Label_32.Text = app.langmap.LOCATION;
            app.Label_33.Text = app.langmap.SIGNAL;
            app.Label_34.Text = app.langmap.INTENSITY_THRESHOLD;
            app.Label_36.Text = app.langmap.SCALE_THRESHOLD;
            app.Label_37.Text = app.langmap.DOWNSAMPLING;
            app.Label_38.Text = app.langmap.GRID_STEP;
            app.Label_39.Text = app.langmap.OUTLIER_RATIO;
            app.Label_40.Text = app.langmap.MAX_ITERATION_N;
            app.Label_41.Text = app.langmap.GLOBAL_ERROR_LIMIT;
            app.Label_42.Text = app.langmap.MIN_ITERATION_STEP;
            app.Label_43.Text = app.langmap.MAX_ITERATION_STEP;
            app.Label_45.Text = app.langmap.ITERATION_COEFFICIENT;
            app.Label_47.Text = app.langmap.DOWNSAMPLING;
            app.Label_48.Text = app.langmap.ACCUMULATIVE_FIELD_SMOOTHING;
            app.Label_49.Text = app.langmap.PRECISION;
            app.Label_50.Text = app.langmap.MAX_ITERATION_N;
            app.Label_51.Text = app.langmap.MEDIAN_FILTER;
            app.Label_52.Text = app.langmap.GAUSSIAN_FILTER;
            app.Label_53.Text = app.langmap.OPEN_OPERATOR;
            app.Label_55.Text = app.langmap.MAX_ITERATION_N;
            app.Label_56.Text = app.langmap.Z_OPTIMAL_TOL;
            app.Label_57.Text = app.langmap.GRID_SPACING;
            app.Label_58.Text = app.langmap.DISPLACEMENT_REGULATION;
            app.Label_59.Text = app.langmap.DVIEW;
            app.Label_60.Text = app.langmap.PROJECTION;
            app.Label_61.Text = app.langmap.RESAMPLING_RATIO;
            
            app.DropDown_TemplateSampling_Global.Items = [app.langmap.MEAN,...
                app.langmap.MEDIAN, app.langmap.MIN, app.langmap.MAX];

            app.DropDown_TformType_Global.Items = [app.langmap.TRANSLATION,...
                app.langmap.RIGID, app.langmap.AFFINE];

            app.DropDown_RegModal.Items = [app.langmap.MONOMODAL, ...
                app.langmap.MULTIMODAL];

            app.DropDown_Structured_Global.Items = [app.langmap.RED, ...
                app.langmap.GREEN];

            app.DropDown_Functional_Global.Items = [app.langmap.RED, ...
                app.langmap.GREEN];

            app.DropDown_DownSampling.Items = [app.langmap.AUTO, ...
                "2X2", "3X3", app.langmap.CLOSE];

            app.DropDown_Interp_Global.Items = [app.langmap.LINEAR_FAST, ...
                app.langmap.CUBIC_HIGH_QUALITY];

            app.DropDown_TemplateSampling_Local.Items = [app.langmap.MEAN,...
                app.langmap.MEDIAN, app.langmap.MIN, app.langmap.MAX];

            app.DropDown_Structured_Local.Items = [app.langmap.RED, ...
                app.langmap.GREEN];

            app.DropDown_Functional_Local.Items = [app.langmap.RED, ...
                app.langmap.GREEN];

            app.DropDown_RepAcc_Local.Items = [app.langmap.LOW, ...
                app.langmap.MEDIUM, app.langmap.HIGH];

            app.DropDown_Interp_Local.Items = [app.langmap.LINEAR_FAST, ...
                app.langmap.CUBIC_HIGH_QUALITY];

            app.DropDown_DView_MGlobal.Items = [app.langmap.DVIEW_XY, ...
                app.langmap.DVIEW_ZX, app.langmap.DVIEW_YZ];

            app.DropDown_RegTform_MGlobal.Items = [app.langmap.TRANSLATION, ...
                app.langmap.RIGID, app.langmap.PIECEWISE_LINEAR, app.langmap.POLYNOMIAL];

            app.DropDown_Projection_MGlobal.Items = [app.langmap.MAX, ...
                app.langmap.MIN, app.langmap.MEDIAN, app.langmap.MEAN];

            app.DropDown_Interp_MGlobal.Items = [app.langmap.LINEAR_FAST, ...
                app.langmap.CUBIC_HIGH_QUALITY];
            
            app.DropDown_RegSampling_LongTerm.Items = [app.langmap.MEAN,...
                app.langmap.MEDIAN, app.langmap.MIN, app.langmap.MAX];

            app.DropDown_Marker_LongTerm.Items = [app.langmap.RED, ...
                app.langmap.GREEN];

            app.DropDown_Signal_LongTerm.Items = [app.langmap.RED, ...
                app.langmap.GREEN];

            app.DropDown_DownSampling_LongTerm_PC.Items = [app.langmap.GRID_AVERAGE, ...
                app.langmap.RANDOM_UNIFORM, app.langmap.NONUNIFORM];

            app.CheckBox_Lock_Global.Text = app.langmap.LOCK;

            app.CheckBox_Lock_Local.Text = app.langmap.LOCK;

            app.CheckBox_Lock_MGlobal.Text = app.langmap.LOCK;

            app.CheckBox_Lock_LTGlobal.Text = app.langmap.LOCK;

            app.CheckBox_Lock_LTLocal.Text = app.langmap.LOCK;

            app.CheckBox_AutoUpdateTemplate_Global.Text = app.langmap.TEMPLATE_AUTO_UPDATE;

            app.CheckBox_ReSampleIsometric_MGlobal.Text = app.langmap.ISOMETRIC;

            app.CheckBox_AutoKeyFrame.Text = app.langmap.AUTO;

            app.CheckBox_LongTerm_Review.Text = app.langmap.PREVIEW;

            app.CheckBox_RepAccOn_Local.Text = app.langmap.REPLENISH_PHOTOBLEACHING;

            app.CheckBox_RepAccOn_LTLocal.Text = app.langmap.REPLENISH_PHOTOBLEACHING;
        end

        function Close(app)
            RSTCUIFigureCloseRequest(app, []);
        end
    end
    
    methods (Access = private)
        function InitData(app, mainapp)
            app.caller = mainapp;
            [app.regopts, app.fixdef, app.opt_locked] = ...
                app.caller.GetRegOptions();
            app.volopts = app.caller.GetImgInfo();
        end

        function InitPanel(app)
            % SELECT ONE SETTER
            switch app.regopts.Mode
                case "global"
                    switch app.regopts.Algorithm
                        case "TCREG"
                            SetGlobalPanel(app);
                            app.caller.SendMessage(["REGMODE_CURRENT_GLOBAL",""]);
                        case "LTREG"
                            SetLTGlobalPanel(app);
                            app.caller.SendMessage(["REGMODE_CURRENT_LTGLOBAL",""]);
                        case "MANREG"
                            SetMGlobalPanel(app);
                            app.caller.SendMessage(["REGMODE_CURRENT_MGLOBAL",""]);
                        otherwise
                    end
                case "local"
                    switch app.regopts.Algorithm
                        case "TCREG"
                            SetLocalPanel(app);
                            app.caller.SendMessage(["REGMODE_CURRENT_LOCAL",""]);
                        case "LTREG"
                            SetLTLocalPanel(app);
                            app.caller.SendMessage(["REGMODE_CURRENT_LTLOCAL",""]);
                        otherwise
                    end
                otherwise
            end
        end

        %==================== GLOBAL PANEL ======================
        % Global panel setting include: 
        % <SetGlobalPanel>: set the controller value
        % <SetGlobalPanelEnabling>: set the controller enable status
        % <LockGlobalPanel>: set the controllers disabled
        % <UnlockGloablPanel>: set the controllers enabled

        function SetGlobalPanel(app)
            % ================== SET THE USUAL ARGUMENTS ================
            % set registration template
            app.EditField_Template_Global.Value = app.fixdef.Sampling(2);
            % TODO: enable the auto template generation
            app.CheckBox_AutoUpdateTemplate_Global.Enable = "off";
            app.DropDown_TemplateSampling_Global.Value = app.fixdef.Sampling(1);

            % set transformation
            app.DropDown_TformType_Global.Value = app.regopts.Options.TformType;

            % set the structured and functional channels
            app.DropDown_Structured_Global.Value = app.regopts.Options.SC;
            app.DropDown_Functional_Global.Value = app.regopts.Options.FC;

            % set iteration options
            app.EditField_MinStep_Global.Value = app.regopts.Options.MinStep;
            app.EditField_MaxStep_Local.Value = app.regopts.Options.MaxStep;


            %================== SET THE ADVANCED ARGUMENTS ================
            % set the preprocessing arguments
            app.EditField_MedianFilter.Value = ...
                string(app.regopts.Options.MedianFilter).join(",");
            app.EditField_OpenOperator.Value = ...
                string(app.regopts.Options.OpenOperator).join(",");
            app.EditField_GaussianFilter.Value = ...
                string(app.regopts.Options.GaussianFilter).join(",");
            app.EditField_OptimalZ_MaxIterN.Value = app.regopts.Options.MaxZOptIterN;
            app.EditField_OptimalZ_Tol.Value = app.regopts.Options.TolZOpt;

            % set the modal
            app.DropDown_RegModal.Value = app.regopts.Options.RegModal;

            % set downsampling options
            switch app.regopts.Options.DS
                case "1X1"
                    app.DropDown_DownSampling.Value = "close";
                otherwise
                    app.DropDown_DownSampling.Value = app.regopts.Options.DS;
            end

            % set iteration options
            app.EditField_MaxIterN_Global.Value = app.regopts.Options.MaxIterN;
            app.EditField_IterCoeff.Value = app.regopts.Options.IterCoeff;

            % set algorithm miscellaneous
            app.Spinner_VPL_Global.Limits = ...
                [1, floor(log(app.volopts.slices)/log(4))+1];
            app.Spinner_VPL_Global.Value = app.regopts.Options.VPL;
            app.DropDown_Interp_Global.Value = app.regopts.Options.Interp;

            % set the panel locked status
            app.CheckBox_Lock_Global.Value = app.opt_locked.Global;
            if app.opt_locked.Global == true
                LockGlobalPanel(app); 
            else
                UnlockGlobalPanel(app);
            end

            %==========================================================
            % focus this panel
            app.TabGroup_AlgorithmSelection.SelectedTab = app.Tab_Global;
        end

        function SetGlobalPanelEnabling(app, state)
            arguments
                app
                state (1,1) string {mustBeMember(state, ["on","off"])}
            end

            app.EditField_Template_Global.Enable = state;
            app.DropDown_TemplateSampling_Global.Enable = state;
            app.DropDown_TformType_Global.Enable = state;
            app.DropDown_Structured_Global.Enable = state;
            app.DropDown_Functional_Global.Enable = state;
            app.EditField_MinStep_Global.Enable = state;
            app.EditField_MaxStep_Local.Enable = state;
            app.EditField_MedianFilter.Enable = state;
            app.EditField_OpenOperator.Enable = state;
            app.EditField_GaussianFilter.Enable = state;
            app.EditField_OptimalZ_MaxIterN.Enable = state;
            app.EditField_OptimalZ_Tol.Enable = state;
            app.DropDown_RegModal.Enable = state;
            app.DropDown_DownSampling.Enable = state;
            app.EditField_MaxIterN_Global.Enable = state;
            app.EditField_IterCoeff.Enable = state;
            app.Spinner_VPL_Global.Enable = state;
            app.DropDown_Interp_Global.Enable = state;
        end

        function LockGlobalPanel(app)
            SetGlobalPanelEnabling(app, "off");
            app.Tab_Global.ForegroundColor = [1, 0, 0];
            app.CheckBox_Lock_Global.FontColor = [1, 0, 0];
        end

        function UnlockGlobalPanel(app)
            SetGlobalPanelEnabling(app, "on");
            app.Tab_Global.ForegroundColor = [0, 0, 0];
            app.CheckBox_Lock_Global.FontColor = [0, 0, 0];
        end

        %=========================================================

        %==================== LOCAL PANEL ========================
        % Local panel setting include: 
        % <SetLocalPanel>: set the controller value
        % <SetLocalPanelEnabling>: set the controller enable status
        % <LockLocalPanel>: set the controllers disabled
        % <UnlockLocalPanel>: set the controllers enabled

        function SetLocalPanel(app)
            % ================= SET THE COMMON ARGUMENTS =================
            % set registration template
            app.EditField_Template_Local.Value = app.fixdef.Sampling(2);
            app.DropDown_TemplateSampling_Local.Value = app.fixdef.Sampling(1);
            
            % set the structured and functional channels
            app.DropDown_Structured_Local.Value = app.regopts.Options.SC;
            app.DropDown_Functional_Local.Value = app.regopts.Options.FC;

            % set the fluoscrence replenishment arguments
            app.CheckBox_RepAccOn_Local.Value = app.regopts.Options.ImageRehist;
            
            app.DropDown_RepAcc_Local.Value = string(app.regopts.Options.RepAcc);
            app.DropDown_RepAcc_Local.Enable = app.regopts.Options.ImageRehist;


            % ================= SET THE USUAL ARGUMENTS =================
            app.EditField_GridRegularization_Local.Value = app.regopts.Options.GR;
            app.EditField_GridSpacing_Local.Value = ...
                string(app.regopts.Options.GS).join(",");
            app.Spinner_NS_VPL_Local.Limits = ...
                [1, floor(log(app.volopts.slices)/log(4))+1];
            app.Spinner_NS_VPL_Local.Value = app.regopts.Options.VPL;
            
            
            % ================ SET THE ADVANCED ARGUMENTS ===============
            % set iteration options
            app.EditField_MaxIterN_Local.Value = ...
                string(app.regopts.Options.MaxIterN).join(",");

            % set algorithm options
            app.EditField_AFS_Local.Value = app.regopts.Options.AFS;
            app.Spinner_AS_VPL_Local.Limits = ...
                [1, floor(log(app.volopts.slices)/log(4))+1];
            app.Spinner_AS_VPL_Local.Value = app.regopts.Options.VPL;
            app.DropDown_Interp_Local.Value = app.regopts.Options.Interp;

            % set the panel locked status
            app.CheckBox_Lock_Local.Value = app.opt_locked.Local;
            if app.opt_locked.Local == true
                LockLocalPanel(app);
            else
                UnlockLocalPanel(app);
            end

            %============================================================
            % focus this panel
            app.TabGroup_AlgorithmSelection.SelectedTab = app.Tab_Local;
        end

        function SetLocalPanelEnabling(app, state)
            arguments
                app
                state (1,1) string {mustBeMember(state, ["on","off"])}
            end

            app.EditField_Template_Local.Enable = state;
            app.DropDown_TemplateSampling_Local.Enable = state;
            app.DropDown_Structured_Local.Enable = state;
            app.DropDown_Functional_Local.Enable = state;
            app.CheckBox_RepAccOn_Local.Enable = state;
            if state == "on"
                if app.CheckBox_RepAccOn_Local.Value == true
                    app.DropDown_RepAcc_Local.Enable = state;
                end
            else
                app.DropDown_RepAcc_Local.Enable = state;
            end
            app.EditField_GridRegularization_Local.Enable = state;
            app.EditField_GridSpacing_Local.Enable = state;
            app.Spinner_NS_VPL_Local.Enable = state;
            app.EditField_MaxIterN_Local.Enable = state;
            app.EditField_AFS_Local.Enable = state;
            app.Spinner_AS_VPL_Local.Enable = state;
            app.DropDown_Interp_Local.Enable = state;
        end

        function LockLocalPanel(app)
            SetLocalPanelEnabling(app, "off");
            app.Tab_Local.ForegroundColor = [1, 0, 0];
            app.CheckBox_Lock_Local.FontColor = [1, 0, 0];
        end

        function UnlockLocalPanel(app)
            SetLocalPanelEnabling(app, "on");
            app.Tab_Local.ForegroundColor = [0, 0, 0];
            app.CheckBox_Lock_Local.FontColor = [0, 0, 0];
        end

        %=========================================================

        %===================== MGLOBAL PANEL =====================
        % MGlobal panel setting include: 
        % <SetLocalPanel>: set the controller value
        % <SetLocalPanelEnabling>: set the controller enable status
        % <LockLocalPanel>: set the controllers disabled
        % <UnlockLocalPanel>: set the controllers enabled

        function SetMGlobalPanel(app)
            % =============== SET REGISTRATION PARAMETERS ================
            app.DropDown_DView_MGlobal.Value = app.regopts.Options.DView;
            app.DropDown_Projection_MGlobal.Value = app.regopts.Options.Projection;
            app.DropDown_RegTform_MGlobal.Value = app.regopts.Options.TformType;

            switch app.regopts.Options.TformType
                case "translation"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 1;
                case "rigid"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 2;
                case "pwl"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 4;
                case "polynomial"
                    app.Spinner_PolyOrder_MGlobal.Value = app.regopts.Options.Degree;
                    app.EditField_CPNumber_MGlobal.Value = ...
                        nchoosek(app.regopts.Options.Degree+2, 2);
                    app.Label_24.Visible = "on";
                    app.Spinner_PolyOrder_MGlobal.Visible = "on";

            end

            app.DropDown_Interp_MGlobal.Value = app.regopts.Options.Interp;
            app.CheckBox_ReSampleIsometric_MGlobal.Value = ...
                app.regopts.Options.Isometric;

            if app.regopts.Options.Isometric == false
                app.EditField_ReSampleRatio_MGlobal.Editable = "on";
            else
                app.EditField_ReSampleRatio_MGlobal.Editable = "off";
            end
            app.EditField_ReSampleRatio_MGlobal.Value ...
                = string(app.regopts.Options.Resampling).join(",");

            % set the panel locked status
            app.CheckBox_Lock_MGlobal.Value = app.opt_locked.MGlobal;
            if app.opt_locked.MGlobal == true
                LockMGlobalPanel(app); 
            else
                UnlockMGlobalPanel(app);
            end
            
            %============================================================
            % focus this panel
            app.TabGroup_AlgorithmSelection.SelectedTab = app.Tab_MGlobal;
        end

        function SetMGlobalPanelEnabling(app, state)
            arguments
                app
                state (1,1) string {mustBeMember(state, ["on","off"])}
            end
            app.DropDown_DView_MGlobal.Enable = state;
            app.DropDown_Projection_MGlobal.Enable = state;
            app.DropDown_RegTform_MGlobal.Enable = state;
            app.EditField_CPNumber_MGlobal.Enable = state;
            app.DropDown_Interp_MGlobal.Enable = state;
            app.CheckBox_ReSampleIsometric_MGlobal.Enable = state;
            app.EditField_ReSampleRatio_MGlobal.Enable = state;
        end

        function LockMGlobalPanel(app)
            SetMGlobalPanelEnabling(app, "off");
            app.Tab_MGlobal.ForegroundColor = [1, 0, 0];
            app.CheckBox_Lock_MGlobal.FontColor = [1, 0, 0];
        end

        function UnlockMGlobalPanel(app)
            SetMGlobalPanelEnabling(app, "on");
            app.Tab_MGlobal.ForegroundColor = [0, 0, 0];
            app.CheckBox_Lock_MGlobal.FontColor = [0, 0, 0];
        end

        %=========================================================

        %===================== LTGLOBAL PANEL =====================
        % LTGlobal panel setting include: 
        % <SetLocalPanel>: set the controller value
        % <SetLocalPanelEnabling>: set the controller enable status
        % <LockLocalPanel>: set the controllers disabled
        % <UnlockLocalPanel>: set the controllers enabled
        function SetLTGlobalPanel(app)
            app.EditField_RegTemplate_LongTerm.Value ...
                = app.regopts.Options.volfixed.G(2);
            app.DropDown_RegSampling_LongTerm.Value ...
                = upper(app.regopts.Options.volfixed.G(1));

            if ~isempty(str2num(app.regopts.Options.KeyFrames)) %#ok<ST2NM>
                app.EditField_KeyFrame.Value = app.regopts.Options.KeyFrames;
                app.CheckBox_AutoKeyFrame.Value = false;
            else
                app.EditField_KeyFrame.Enable = "off";
                app.CheckBox_AutoKeyFrame.Value = true;
            end

            app.EditField_IntensityThreshold_LongTerm.Value ...
                = app.regopts.Options.IntensityThreshold;
            app.EditField_ScaleThreshold_LongTerm.Value = ...
                app.regopts.Options.ScaleThreshold;
            app.DropDown_DownSampling_LongTerm_PC.Value = ...
                app.regopts.Options.DS_PointCloud;
            app.EditField_DownSampling_LongTerm_Param.Value = ...
                app.regopts.Options.DS_PointCloud_Param;
            app.EditField_OutlierRatio.Value = ...
                app.regopts.Options.OutlierRatio;
            app.EditField_MaxIterN_LongTerm_PC.Value = ...
                app.regopts.Options.MaxIterN_PointCloud;
            app.EditField_ErrorThreshold.Value = ...
                app.regopts.Options.ErrorLimit;
            app.EditField_MinStep_LTGlobal.Value = ...
                app.regopts.Options.MinStep_LongTerm;
            switch app.regopts.Options.DS_Voxel
                case "1x1"
                    app.DropDown_DownSampling_LTGlobal.Value = "OFF";
                otherwise
                    app.DropDown_DownSampling_LTGlobal.Value ...
                        = app.regopts.Options.DS_Voxel;
            end
            app.EditField_MaxStep_LTGlobal.Value = ...
                app.regopts.Options.InitStep_LongTerm;
            app.EditField_IterCoeff_LTGlobal.Value = ...
                app.regopts.Options.IterCoeff_LongTerm;
            app.EditField_MaxIterN_LongTerm_Voxel.Value = ...
                string(app.regopts.Options.MaxIterN_Voxel).join(",");
            app.CheckBox_RepAccOn_LTLocal.Value = ...
                app.regopts.Options.AutoContrast_LongTerm;
            app.EditField_AFS_LTLocal.Value = ...
                app.regopts.Options.AFS_LongTerm;
            app.DropDown_RepAcc_LTLocal.Value = ...
                string(app.regopts.Options.RepAcc_LongTerm);

            % set the panel locked status
            app.CheckBox_Lock_LTGlobal.Value = app.opt_locked.LTGlobal;
            if app.opt_locked.LTGlobal == true
                LockLTGlobalPanel(app); 
            else
                UnlockLTGlobalPanel(app);
            end

            %============================================================
            % focus this panel
            app.TabGroup_AlgorithmSelection.SelectedTab = app.Tab_LTGlobal;
        end

        function SetLTGlobalPanelEnabling(app, state)
            
        end

        function LockLTGlobalPanel(app)
            SetLTGlobalPanelEnabling(app, "off");
            app.Tab_LTGlobal.ForegroundColor = [1, 0, 0];
            app.CheckBox_Lock_LTGlobal.FontColor = [1, 0, 0];
        end

        function UnlockLTGlobalPanel(app)
            SetLTGlobalPanelEnabling(app, "on");
            app.Tab_LTGlobal.ForegroundColor = [0, 0, 0];
            app.CheckBox_Lock_LTGlobal.FontColor = [0, 0, 0];
        end

        %=========================================================

        %====================== LTLOCAL PANEL ==================== 
        % LTLocal panel setting include: 
        % <SetLocalPanel>: set the controller value
        % <SetLocalPanelEnabling>: set the controller enable status
        % <LockLocalPanel>: set the controllers disabled
        % <UnlockLocalPanel>: set the controllers enabled

        function SetLTLocalPanel(app)

            % set the panel locked status
            app.CheckBox_Lock_LTLocal.Value = app.opt_locked.LTLocal;
            if app.opt_locked.LTLocal == true
                LockLTLocalPanel(app); 
            else
                UnlockLTLocalPanel(app);
            end

            %============================================================
            % focus this panel
            app.TabGroup_AlgorithmSelection.SelectedTab = app.Tab_LTLocal;
        end

        function SetLTLocalPanelEnabling(app, state)

        end

        function LockLTLocalPanel(app)
            SetLTLocalPanelEnabling(app, "off");
            app.Tab_LTLocal.ForegroundColor = [1, 0, 0];
            app.CheckBox_Lock_LTLocal.FontColor = [1, 0, 0];
        end

        function UnlockLTLocalPanel(app)
            SetLTLocalPanelEnabling(app, "on");
            app.Tab_LTLocal.ForegroundColor = [0, 0, 0];
            app.CheckBox_Lock_LTLocal.FontColor = [0, 0, 0];
        end

        %=========================================================

        % This function lock/unlock the target panel and synchronize others 
        % behaviour
        function UpdateLockGroup(app, panel, state)
            arguments
                app
                panel   (1,1)   string {mustBeMember(panel, ["Global","Local", ...
                    "MGlobal", "LTGlobal", "LTLocal"])}
                state   (1,1)   logical
            end

            UnlockAllPanel(app);

            % clear flag table
            app.opt_locked{1,:} = false;
            app.opt_locked.(panel) = state;

            if state == true
                LockPanel(app, panel);
            end
        end

        function LockPanel(app, panel)
            arguments
                app %#ok<INUSA>
                panel   (1,1)   string {mustBeMember(panel, ["Global","Local", ...
                    "MGlobal", "LTGlobal", "LTLocal"])}
            end

            eval("Lock"+panel+"Panel(app);");
            % add check box selection
            eval("app.CheckBox_Lock_"+panel+".Value = true;");
        end

        function UnlockAllPanel(app) %#ok<MANU>
            for panel = ["Global","Local","MGlobal", "LTGlobal", "LTLocal"]
                eval("Unlock"+panel+"Panel(app);");
                % remove check box selection
                eval("app.CheckBox_Lock_"+panel+".Value = false;");
            end
        end

        function InitHardwareDetector(app)
            [hw_logo, app.hw_info] = GetHardwareInfo();
            app.Image_CPU.Visible = "on";
            app.Image_GPU.Visible = "on";

            switch hw_logo.cpu
                case "Intel"
                    app.Image_CPU.ImageSource = "sources/intel_logo.png";
                case "AMD"
                    app.Image_CPU.ImageSource = "sources/amd_logo.png";
                case "Unknown"
                    app.Image_CPU.ImageSource = "sources/unknown_logo.png";
                otherwise
                    app.Image_CPU.Visible = "off";
            end
            switch hw_logo.gpu
                case "Intel"
                    app.Image_GPU.ImageSource = "sources/intel_logo.png";
                case "AMD"
                    app.Image_GPU.ImageSource = "sources/amd_logo.png";
                case "Nvidia"
                    app.Image_GPU.ImageSource = "sources/nvidia_logo.png";
                case "Unknown"
                    app.Image_GPU.ImageSource = "sources/unknown_logo.png";
                otherwise
                    app.Image_GPU.Visible = "off";
            end
        end
    end

    methods(Static)
        function r = calc_isometric(volopt)
            xr = volopt.xRes;
            yr = volopt.yRes;
            zr = volopt.zRes;

            rmin = min([xr, yr, zr]);
            r = round([xr, yr, zr]./rmin);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            InitData(app, mainapp);

            InitPanel(app);

            InitHardwareDetector(app);

            RefreshPanelLanguage(app);
        end

        % Value changed function: EditField_Template_Global
        function EditField_Template_GlobalValueChanged(app, event)
            value = app.EditField_Template_Global.Value;
            value = string(value).replace("end",string(app.volopts.frames));
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(value <= 0)
                % blink the field
                FontBlink(app,"EditField_Template_Global");
                app.EditField_Template_Global.Value ...
                    = app.EditField_Template_Global.Placeholder;
                value = str2num(app.EditField_Template_Global.Placeholder); %#ok<ST2NM>
            end

            app.fixdef.Sampling(2) = string(value).join(",");

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_TemplateSampling_Global
        function DropDown_TemplateSampling_GlobalValueChanged(app, event)
            value = app.DropDown_TemplateSampling_Global.Value;

            app.fixdef.Sampling(1) = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_TformType_Global
        function DropDown_TformType_GlobalValueChanged(app, event)
            value = app.DropDown_TformType_Global.Value;

            switch value
                case "TRANSLATION"
                    app.DropDown_TformType_Global.Tooltip = ...
                        app.langmap.TRANSLATION_TOOLTIP;
                case "RIGID"
                    app.DropDown_TformType_Global.Tooltip = ...
                        app.langmap.RIGID_TOOLTIP;
                case "AFFINE"
                    app.DropDown_TformType_Global.Tooltip = ...
                        app.langmap.AFFINE_TOOLTIP;
                otherwise
            end

            app.regopts = set(app.regopts, "TformType", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_RegModal
        function DropDown_RegModalValueChanged(app, event)
            value = app.DropDown_RegModal.Value;
            
            app.regopts = set(app.regopts, "RegModal", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Structured_Global
        function DropDown_Structured_GlobalValueChanged(app, event)
            value = app.DropDown_Structured_Global.Value;

            app.regopts = set(app.regopts, "SC", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Functional_Global
        function DropDown_Functional_GlobalValueChanged(app, event)
            value = app.DropDown_Functional_Global.Value;
            
            app.regopts = set(app.regopts, "FC", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MinStep_Global
        function EditField_MinStep_GlobalValueChanged(app, event)
            value = app.EditField_MinStep_Global.Value;

            app.regopts = set(app.regopts, "MinStep", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MaxStep_Local
        function EditField_MaxStep_LocalValueChanged(app, event)
            value = app.EditField_MaxStep_Local.Value;
            
            app.regopts = set(app.regopts, "MaxStep", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Image clicked function: Image_CPU
        function Image_CPUClicked(app, event)
            uialert(app.RSTCUIFigure,app.hw_info.cpu, ...
                app.langmap.INFORMATION, "Icon","info");
        end

        % Value changed function: EditField_MaxIterN_Global
        function EditField_MaxIterN_GlobalValueChanged(app, event)
            value = app.EditField_MaxIterN_Global.Value;
            
            app.regopts = set(app.regopts, "MaxIterN", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_IterCoeff
        function EditField_IterCoeffValueChanged(app, event)
            value = app.EditField_IterCoeff.Value;
            
            app.regopts = set(app.regopts, "IterCoeff", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_DownSampling
        function DropDown_DownSamplingValueChanged(app, event)
            value = app.DropDown_DownSampling.Value;
            
            switch value
                case "close"
                    app.regopts = set(app.regopts, "DS", "1X1");
                otherwise
                    app.regopts = set(app.regopts, "DS", string(value));
            end

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Interp_Global
        function DropDown_Interp_GlobalValueChanged(app, event)
            value = app.DropDown_Interp_Global.Value;
            
            app.regopts = set(app.regopts, "Interp", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_VPL_Global
        function Spinner_VPL_GlobalValueChanged(app, event)
            value = app.Spinner_VPL_Global.Value;
            
            app.regopts = set(app.regopts, "VPL", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_Lock_Global
        function CheckBox_Lock_GlobalValueChanged(app, event)
            value = app.CheckBox_Lock_Global.Value;

            UpdateLockGroup(app, "Global", value);

            if value == true
                app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
            end
        end

        % Value changed function: EditField_Template_Local
        function EditField_Template_LocalValueChanged(app, event)
            value = app.EditField_Template_Local.Value;
            value = string(value).replace("end",string(app.volopts.frames));
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(value <= 0)
                % blink the field
                FontBlink(app, "EditField_Template_Local");
                app.EditField_Template_Local.Value ...
                    = app.EditField_Template_Local.Placeholder;
                value = str2num(app.EditField_Template_Local.Placeholder); %#ok<ST2NM>
            end

            app.fixdef.Sampling(2) = string(value).join(",");

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_TemplateSampling_Local
        function DropDown_TemplateSampling_LocalValueChanged(app, event)
            value = app.DropDown_TemplateSampling_Local.Value;

            app.fixdef.Sampling(1) = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Structured_Local
        function DropDown_Structured_LocalValueChanged(app, event)
            value = app.DropDown_Structured_Local.Value;
            
            app.regopts = set(app.regopts, "SC", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Functional_Local
        function DropDown_Functional_LocalValueChanged(app, event)
            value = app.DropDown_Functional_Local.Value;
            
            app.regopts = set(app.regopts, "FC", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MaxIterN_Local
        function EditField_MaxIterN_LocalValueChanged(app, event)
            value = app.EditField_MaxIterN_Local.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || numel(value)>app.regopts.Options.VPL
                % blink the field
                FontBlink(app,"EditField_MaxIterN_Local");
                app.EditField_MaxIterN_Local.Value = ...
                    app.EditField_MaxIterN_Local.Placeholder;
                value = str2num(app.EditField_MaxIterN_Local.Placeholder); %#ok<ST2NM>
            end

            app.regopts = set(app.regopts, "MaxIterN", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_AFS_Local
        function EditField_AFS_LocalValueChanged(app, event)
            value = app.EditField_AFS_Local.Value;

            app.regopts = set(app.regopts, "AFS", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Image clicked function: Image_GPU
        function Image_GPUClicked(app, event)
            uialert(app.RSTCUIFigure,app.hw_info.gpu, ...
                app.langmap.INFORMATION, "Icon","info");
        end

        % Value changed function: CheckBox_RepAccOn_Local
        function CheckBox_RepAccOn_LocalValueChanged(app, event)
            value = app.CheckBox_RepAccOn_Local.Value;

            app.DropDown_RepAcc_Local.Enable = value;

            app.regopts = set(app.regopts, "ImageRehist", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_RepAcc_Local
        function DropDown_RepAcc_LocalValueChanged(app, event)
            value = app.DropDown_RepAcc_Local.Value;
            
            app.regopts = set(app.regopts, "RepAcc", str2double(value));

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Interp_Local
        function DropDown_Interp_LocalValueChanged(app, event)
            value = app.DropDown_Interp_Local.Value;
            
            app.regopts = set(app.regopts, "Interp", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_Lock_Local
        function CheckBox_Lock_LocalValueChanged(app, event)
            value = app.CheckBox_Lock_Local.Value;

            if (value == true) && isMATLABReleaseOlderThan("R2022b")
                switch app.TabGroup_Local.SelectedTab.Tag
                    case "Usual"
                        warning("RegSetting_TC:invalidLocalRegistrationOptions",...
                            "Can not use usual algorithm because MATLAB version < R2022b.");
                        app.CheckBox_Lock_Local.Value = false;
                        return;
                    otherwise
                end
            end

            UpdateLockGroup(app, "Local", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_RegTform_MGlobal
        function DropDown_RegTform_MGlobalValueChanged(app, event)
            value = app.DropDown_RegTform_MGlobal.Value;
            
            switch value
                case "translation"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 1;
                case "rigid"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 2;
                case "pwl"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 4;
                case "poly"
                    app.Spinner_PolyOrder_MGlobal.Visible = "on";
                    app.Label_24.Visible = "on";
                    app.EditField_CPNumber_MGlobal.Value ...
                        = nchoosek(app.Spinner_PolyOrder_MGlobal.Value+2,2);
                otherwise
            end

            app.regopts = set(app.regopts, "TformType", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_PolyOrder_MGlobal
        function Spinner_PolyOrder_MGlobalValueChanged(app, event)
            value = app.Spinner_PolyOrder_MGlobal.Value;

            % Synchronize minimum control point pairs number
            app.EditField_CPNumber_MGlobal.Value = nchoosek(value+2, 2);

            app.regopts = set(app.regopts, "Degree", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Interp_MGlobal
        function DropDown_Interp_MGlobalValueChanged(app, event)
            value = app.DropDown_Interp_MGlobal.Value;

            app.regopts = set(app.regopts, "Interp", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Close request function: RSTCUIFigure
        function RSTCUIFigureCloseRequest(app, event)
            % Set the registration options
            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);

            delete(app);
        end

        % Selection change function: TabGroup_AlgorithmSelection
        function TabGroup_AlgorithmSelectionSelectionChanged(app, event)
            selectedTab = app.TabGroup_AlgorithmSelection.SelectedTab;
            
            switch selectedTab.Tag
                case "Global"
                    app.regopts.Algorithm = "TCREG";
                    app.regopts.Mode = "global";
                    SetGlobalPanel(app);
                    app.caller.SendMessage(["REGMODE_CURRENT_GLOBAL",""]);
                case "Local"
                    app.regopts.Algorithm = "TCREG";
                    app.regopts.Mode = "local";
                    SetLocalPanel(app);
                    app.caller.SendMessage(["REGMODE_CURRENT_LOCAL",""]);
                case "MGlobal"
                    app.regopts.Algorithm = "MANREG";
                    app.regopts.Mode = "global";
                    SetMGlobalPanel(app);
                    app.caller.SendMessage(["REGMODE_CURRENT_MGLOBAL",""]);
                case "LTGlobal"
                    app.regopts.Algorithm = "LTREG";
                    app.regopts.Mode = "global";
                    SetLTGlobalPanel(app);
                    app.caller.SendMessage(["REGMODE_CURRENT_LTGLOBAL",""]);
                case "LTLocal"
                    app.regopts.Algorithm = "LTREG";
                    app.regopts.Mode = "local";
                    SetLTLocalPanel(app);
                    app.caller.SendMessage(["REGMODE_CURRENT_LTLOCAL",""]);
                otherwise
                    throw(MException("RegSetting_TC:innerError", ...
                        "Undefined Tab tag."));
            end

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_RegTemplate_LongTerm
        function EditField_RegTemplate_LongTermValueChanged(app, event)
            value = app.EditField_RegTemplate_LongTerm.Value;
            
            app.regopts.volfixed.G(2) = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_RegSampling_LongTerm
        function DropDown_RegSampling_LongTermValueChanged(app, event)
            value = app.DropDown_RegSampling_LongTerm.Value;
            
            app.regopts.volfixed.G(1) = lower(value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_KeyFrame
        function EditField_KeyFrameValueChanged(app, event)
            value = app.EditField_KeyFrame.Value;
            
            app.regopts.Options.KeyFrames = string(value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_AutoKeyFrame
        function CheckBox_AutoKeyFrameValueChanged(app, event)
            value = app.CheckBox_AutoKeyFrame.Value;
            
            if value == true
                app.EditField_KeyFrame.Enable = "off";
                app.regopts.Options.KeyFrames = "auto";
            else
                app.EditField_KeyFrame.Enable = "on";
                app.regopts.Options.KeyFrames = string(app.EditField_KeyFrame.Value);
            end

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Marker_LongTerm
        function DropDown_Marker_LongTermValueChanged(app, event)
            value = app.DropDown_Marker_LongTerm.Value;
            
            switch value
                case "RED"
                    app.regopts.Options.Chla = "r";
                case "GREEN"
                    app.regopts.Options.Chla = "g";
                otherwise
            end

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Signal_LongTerm
        function DropDown_Signal_LongTermValueChanged(app, event)
            value = app.DropDown_Signal_LongTerm.Value;
            
            switch value
                case "RED"
                    app.regopts.Options.Chls = "r";
                case "GREEN"
                    app.regopts.Options.Chls = "g";
                otherwise
            end

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_IntensityThreshold_LongTerm
        function EditField_IntensityThreshold_LongTermValueChanged(app, event)
            value = app.EditField_IntensityThreshold_LongTerm.Value;
            
            app.regopts.Options.IntensityThreshold = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_ScaleThreshold_LongTerm
        function EditField_ScaleThreshold_LongTermValueChanged(app, event)
            value = app.EditField_ScaleThreshold_LongTerm.Value;
            
            app.regopts.Options.ScaleThreshold = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_DownSampling_LongTerm_PC
        function DropDown_DownSampling_LongTerm_PCValueChanged(app, event)
            value = app.DropDown_DownSampling_LongTerm_PC.Value;
            
            switch value
                case "GA"
                    app.Label_38.Text = "网格步长";
                case "RAND"
                    app.Label_38.Text = "百分比";
                case "NU"
                    app.Label_38.Text = "最大数量";
                otherwise
                    app.Label_38.Text = "参数值";
            end

            app.regopts.Options.DS_PointCloud = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_DownSampling_LongTerm_Param
        function EditField_DownSampling_LongTerm_ParamValueChanged(app, event)
            value = app.EditField_DownSampling_LongTerm_Param.Value;
            
            app.regopts.Options.DS_PointCloud_Param = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_OutlierRatio
        function EditField_OutlierRatioValueChanged(app, event)
            value = app.EditField_OutlierRatio.Value;
            
            app.regopts.Options.OutlierRatio = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MaxIterN_LongTerm_PC
        function EditField_MaxIterN_LongTerm_PCValueChanged(app, event)
            value = app.EditField_MaxIterN_LongTerm_PC.Value;
            
            app.regopts.Options.MaxIterN_PointCloud = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_ErrorThreshold
        function EditField_ErrorThresholdValueChanged(app, event)
            value = app.EditField_ErrorThreshold.Value;
            app.regopts.Options.ErrorLimit = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_LongTerm_Review
        function CheckBox_LongTerm_ReviewValueChanged(app, event)
            value = app.CheckBox_LongTerm_Review.Value;
            
            if value == true
                % append points cloud registration preview tab

            else
                % remove points cloud registration preview tab

            end
        end

        % Value changed function: EditField_MinStep_LTGlobal
        function EditField_MinStep_LTGlobalValueChanged(app, event)
            value = app.EditField_MinStep_LTGlobal.Value;
            app.regopts.Options.MinStep_LongTerm = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_DownSampling_LTGlobal
        function DropDown_DownSampling_LTGlobalValueChanged(app, event)
            value = app.DropDown_DownSampling_LTGlobal.Value;
            switch value
                case "OFF"
                    app.regopts.Options.DS_Voxel = "1x1";
                otherwise
                    app.regopts.Options.DS_Voxel = value;
            end

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MaxStep_LTGlobal
        function EditField_MaxStep_LTGlobalValueChanged(app, event)
            value = app.EditField_MaxStep_LTGlobal.Value;
            app.regopts.Options.InitStep_LongTerm = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_IterCoeff_LTGlobal
        function EditField_IterCoeff_LTGlobalValueChanged(app, event)
            value = app.EditField_IterCoeff_LTGlobal.Value;
            app.regopts.Options.IterCoeff_LongTerm = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MaxIterN_LongTerm_Voxel
        function EditField_MaxIterN_LongTerm_VoxelValueChanged(app, event)
            value = app.EditField_MaxIterN_LongTerm_Voxel.Value;
            app.regopts.Options.MaxIterN_Voxel = str2num(value); %#ok<ST2NM>

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_RepAccOn_LTLocal
        function CheckBox_RepAccOn_LTLocalValueChanged(app, event)
            value = app.CheckBox_RepAccOn_LTLocal.Value;
            
            if value == true
                app.DropDown_RepAcc_LTLocal.Enable = "on";
            else
                app.DropDown_RepAcc_LTLocal.Enable = "off";
            end
        end

        % Value changed function: EditField_AFS_LTLocal
        function EditField_AFS_LTLocalValueChanged(app, event)
            value = app.EditField_AFS_LTLocal.Value;
            app.regopts.Options.AFS_LongTerm = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_RepAcc_LTLocal
        function DropDown_RepAcc_LTLocalValueChanged(app, event)
            value = app.DropDown_RepAcc_LTLocal.Value;
            app.regopts.Options.RepAcc_LongTerm = str2double(value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_Lock_LTGlobal
        function CheckBox_Lock_LTGlobalValueChanged(app, event)
            value = app.CheckBox_Lock_LTGlobal.Value;
            
            if value == true
                if app.CheckBox_Lock_Global.Value || app.CheckBox_Lock_Local.Value
                    slt = uiconfirm(app.RSTCUIFigure, ...
                        app.langmap.LONG_TERM_NORMAL_CONFLICT, ...
                        app.langmap.WARNING, ...
                        "Icon","warning");
                    if slt == "OK"
                        app.CheckBox_Lock_Global.Value = false;
                        app.CheckBox_Lock_Local.Value = false;
                    else
                        app.CheckBox_Lock_LTGlobal.Value = false;
                    end
                end
            end

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_AutoUpdateTemplate_Global
        function CheckBox_AutoUpdateTemplate_GlobalValueChanged(app, event)
            value = app.CheckBox_AutoUpdateTemplate_Global.Value;
            
            if value == true
                % turn off the template window
                app.EditField_Template_Global.Editable = "off";
                
                % calculate the best template arguments
                % ... TODO
                app.EditField_Template_Global.Value = "1";
            else
                app.EditField_Template_Global.Editable = "on";
            end
        end

        % Value changed function: EditField_MedianFilter
        function EditField_MedianFilterValueChanged(app, event)
            value = app.EditField_MedianFilter.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value) ~= [1,3]) ...
                    || any(value == 0)
                app.EditField_MedianFilter.Value ...
                    = app.EditField_MedianFilter.Placeholder;
                value = str2num(app.EditField_MedianFilter.Placeholder); %#ok<ST2NM>
            end
            app.regopts = set(app.regopts, "MedianFilter", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_OpenOperator
        function EditField_OpenOperatorValueChanged(app, event)
            value = app.EditField_OpenOperator.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value) ~= [1,3]) ...
                    || any(value == 0)
                app.EditField_OpenOperator.Value ...
                    = app.EditField_OpenOperator.Placeholder;
                value = str2num(app.EditField_OpenOperator.Placeholder); %#ok<ST2NM>
            end

            app.regopts = set(app.regopts, "OpenOperator", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_GaussianFilter
        function EditField_GaussianFilterValueChanged(app, event)
            value = app.EditField_GaussianFilter.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value) ~= [1,3]) ...
                    || any(value == 0)
                app.EditField_GaussianFilter.Value ...
                    = app.EditField_GaussianFilter.Placeholder;
                value = str2num(app.EditField_GaussianFilter.Placeholder); %#ok<ST2NM>   
            end

            app.regopts = set(app.regopts, "GaussianFilter", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_OptimalZ_MaxIterN
        function EditField_OptimalZ_MaxIterNValueChanged(app, event)
            value = app.EditField_OptimalZ_MaxIterN.Value;
            
            app.regopts = set(app.regopts, "MaxZOptIterN", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_OptimalZ_Tol
        function EditField_OptimalZ_TolValueChanged(app, event)
            value = app.EditField_OptimalZ_Tol.Value;
            
            app.regopts = set(app.regopts, "TolZOpt", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_NS_VPL_Local
        function Spinner_NS_VPL_LocalValueChanged(app, event)
            value = app.Spinner_NS_VPL_Local.Value;

            app.regopts = set(app.regopts, "VPL", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_Lock_MGlobal
        function CheckBox_Lock_MGlobalValueChanged(app, event)
            value = app.CheckBox_Lock_MGlobal.Value;
            
            UpdateLockGroup(app, "MGlobal", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_Lock_LTLocal
        function CheckBox_Lock_LTLocalValueChanged(app, event)
            value = app.CheckBox_Lock_LTLocal.Value;

            UpdateLockGroup(app, "LTLocal", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_GridRegularization_Local
        function EditField_GridRegularization_LocalValueChanged(app, event)
            value = app.EditField_GridRegularization_Local.Value;

            app.regopts = set(app.regopts, "GR", value);
            
            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_GridSpacing_Local
        function EditField_GridSpacing_LocalValueChanged(app, event)
            value = app.EditField_GridSpacing_Local.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value) ~= [1,3]) ...
                    || any(value == 0)
                app.EditField_GridSpacing_Local.Value ...
                    = app.EditField_GridSpacing_Local.Placeholder;
                value = str2num(app.EditField_GridSpacing_Local.Placeholder); %#ok<ST2NM>
            end
            
            app.regopts = set(app.regopts, "GS", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_AS_VPL_Local
        function Spinner_AS_VPL_LocalValueChanged(app, event)
            value = app.Spinner_AS_VPL_Local.Value;
            
            app.regopts = set(app.regopts, "VPL", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_DView_MGlobal
        function DropDown_DView_MGlobalValueChanged(app, event)
            value = app.DropDown_DView_MGlobal.Value;

            app.regopts = set(app.regopts, "DView", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Projection_MGlobal
        function DropDown_Projection_MGlobalValueChanged(app, event)
            value = app.DropDown_Projection_MGlobal.Value;

            app.regopts = set(app.regopts, "Projection", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_ReSampleRatio_MGlobal
        function EditField_ReSampleRatio_MGlobalValueChanged(app, event)
            value = app.EditField_ReSampleRatio_MGlobal.Value;
            
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value) ~= [1,3])
                app.EditField_ReSampleRatio_MGlobal.Value ...
                    = app.EditField_ReSampleRatio_MGlobal.Placeholder;
                value = str2num(app.EditField_ReSampleRatio_MGlobal.Placeholder); %#ok<ST2NM>
            end

            app.regopts = set(app.regopts, "Resampling", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_ReSampleIsometric_MGlobal
        function CheckBox_ReSampleIsometric_MGlobalValueChanged(app, event)
            value = app.CheckBox_ReSampleIsometric_MGlobal.Value;
            
            if value == true
                r = RegSetting_TC.calc_isometric(app.volopts);
                app.EditField_ReSampleRatio_MGlobal.Editable = "off";
                app.EditField_ReSampleRatio_MGlobal.Value = string(r).join(",");
                app.regopts = set(app.regopts, "Resampling", r);
            else
                app.EditField_ReSampleRatio_MGlobal.Editable = "on";
            end

            app.regopts = set(app.regopts, "Isometric", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Selection change function: TabGroup_Local
        function TabGroup_LocalSelectionChanged(app, event)
            selectedTab = app.TabGroup_Local.SelectedTab;
            
            switch selectedTab.Tag
                case "Usual"
                    app.regopts = set(app.regopts, "SubAlgorithm", "usual");
                case "Advanced"
                    app.regopts = set(app.regopts, "SubAlgorithm", "advanced");
                otherwise
            end

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RSTCUIFigure = uifigure('Visible', 'off');
            app.RSTCUIFigure.Name = 'RSTC';
            app.RSTCUIFigure.Icon = 'rstc.png';
            app.RSTCUIFigure.CloseRequestFcn = createCallbackFcn(app, @RSTCUIFigureCloseRequest, true);
            app.TabGroup_AlgorithmSelection = uitabgroup(app.RSTCUIFigure);
            app.TabGroup_AlgorithmSelection.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_AlgorithmSelectionSelectionChanged, true);
            app.Tab_Global = uitab(app.TabGroup_AlgorithmSelection);
            app.Tab_Global.Tooltip = {'基于相位及强度的全自动刚体配准方案'};
            app.Tab_Global.Title = '全局';
            app.Tab_Global.Tag = 'Global';
            app.GridLayout = uigridlayout(app.Tab_Global);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CheckBox_Lock_Global = uicheckbox(app.GridLayout2);
            app.CheckBox_Lock_Global.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Lock_GlobalValueChanged, true);
            app.CheckBox_Lock_Global.Tooltip = {'启用刚体配准'};
            app.CheckBox_Lock_Global.Text = '锁定';
            app.Label_Helps_Global = uilabel(app.GridLayout2);
            app.Label_Helps_Global.Text = '';
            app.TabGroup_Global = uitabgroup(app.GridLayout);
            app.Tab_NS_Global = uitab(app.TabGroup_Global);
            app.Tab_NS_Global.Title = '常用';
            app.GridLayout10 = uigridlayout(app.Tab_NS_Global);
            app.Panel_NS_Reg_Global = uipanel(app.GridLayout10);
            app.Panel_NS_Reg_Global.Title = '配准';
            app.GridLayout9 = uigridlayout(app.Panel_NS_Reg_Global);
            app.GridLayout11 = uigridlayout(app.GridLayout9);
            app.Label_11 = uilabel(app.GridLayout11);
            app.Label_11.Text = '模板';
            app.EditField_Template_Global = uieditfield(app.GridLayout11, 'text');
            app.EditField_Template_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_Template_GlobalValueChanged, true);
            app.EditField_Template_Global.Placeholder = '1';
            app.CheckBox_AutoUpdateTemplate_Global = uicheckbox(app.GridLayout11);
            app.CheckBox_AutoUpdateTemplate_Global.ValueChangedFcn = createCallbackFcn(app, @CheckBox_AutoUpdateTemplate_GlobalValueChanged, true);
            app.CheckBox_AutoUpdateTemplate_Global.Enable = 'off';
            app.CheckBox_AutoUpdateTemplate_Global.Text = '自动更新';
            app.CheckBox_AutoUpdateTemplate_Global.Value = true;
            app.GridLayout12 = uigridlayout(app.GridLayout9);
            app.Label = uilabel(app.GridLayout12);
            app.Label.Text = '采样';
            app.DropDown_TemplateSampling_Global = uidropdown(app.GridLayout12);
            app.DropDown_TemplateSampling_Global.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_TemplateSampling_Global.ItemsData = {'mean', 'median', 'min', 'max'};
            app.DropDown_TemplateSampling_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_TemplateSampling_GlobalValueChanged, true);
            app.DropDown_TemplateSampling_Global.Tooltip = {'帧运算'};
            app.DropDown_TemplateSampling_Global.Value = 'mean';
            app.Label_2 = uilabel(app.GridLayout12);
            app.Label_2.Text = '几何变换';
            app.DropDown_TformType_Global = uidropdown(app.GridLayout12);
            app.DropDown_TformType_Global.Items = {'平移', '刚体', '仿射'};
            app.DropDown_TformType_Global.ItemsData = {'translation', 'rigid', 'affine'};
            app.DropDown_TformType_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_TformType_GlobalValueChanged, true);
            app.DropDown_TformType_Global.Tooltip = {'几何变换类型'};
            app.DropDown_TformType_Global.Value = 'translation';
            app.GridLayout13 = uigridlayout(app.GridLayout9);
            app.Label_3 = uilabel(app.GridLayout13);
            app.Label_3.Text = '定位';
            app.DropDown_Structured_Global = uidropdown(app.GridLayout13);
            app.DropDown_Structured_Global.Items = {'红', '绿'};
            app.DropDown_Structured_Global.ItemsData = {'r', 'g'};
            app.DropDown_Structured_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_Structured_GlobalValueChanged, true);
            app.DropDown_Structured_Global.Tooltip = {''};
            app.DropDown_Structured_Global.Value = 'r';
            app.Label_4 = uilabel(app.GridLayout13);
            app.Label_4.Text = '信号';
            app.DropDown_Functional_Global = uidropdown(app.GridLayout13);
            app.DropDown_Functional_Global.Items = {'红', '绿'};
            app.DropDown_Functional_Global.ItemsData = {'r', 'g'};
            app.DropDown_Functional_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_Functional_GlobalValueChanged, true);
            app.DropDown_Functional_Global.Tooltip = {''};
            app.DropDown_Functional_Global.Value = 'g';
            app.GridLayout14 = uigridlayout(app.GridLayout9);
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.Label_5 = uilabel(app.GridLayout15);
            app.Label_5.Text = '最小迭代步长';
            app.EditField_MinStep_Global = uieditfield(app.GridLayout15, 'numeric');
            app.EditField_MinStep_Global.Limits = [0 1];
            app.EditField_MinStep_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_MinStep_GlobalValueChanged, true);
            app.EditField_MinStep_Global.Tooltip = {'收敛判别步长'};
            app.EditField_MinStep_Global.Value = 0.0001;
            app.Label_6 = uilabel(app.GridLayout15);
            app.Label_6.Text = '最大迭代步长';
            app.EditField_MaxStep_Local = uieditfield(app.GridLayout15, 'numeric');
            app.EditField_MaxStep_Local.Limits = [0 1];
            app.EditField_MaxStep_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxStep_LocalValueChanged, true);
            app.EditField_MaxStep_Local.Tooltip = {'起始步长'};
            app.EditField_MaxStep_Local.Value = 0.1;
            app.Image_CPU = uiimage(app.GridLayout14);
            app.Image_CPU.ImageClickedFcn = createCallbackFcn(app, @Image_CPUClicked, true);
            app.Image_CPU.Visible = 'off';
            app.Image_CPU.Tooltip = {'CPU状态'};
            app.Tab_AS_Global = uitab(app.TabGroup_Global);
            app.Tab_AS_Global.Title = '高级';
            app.GridLayout3 = uigridlayout(app.Tab_AS_Global);
            app.Panel_NS_PP_Global = uipanel(app.GridLayout3);
            app.Panel_NS_PP_Global.Title = '预处理';
            app.GridLayout4 = uigridlayout(app.Panel_NS_PP_Global);
            app.Label_53 = uilabel(app.GridLayout4);
            app.Label_53.Text = '开运算';
            app.EditField_OpenOperator = uieditfield(app.GridLayout4, 'text');
            app.EditField_OpenOperator.ValueChangedFcn = createCallbackFcn(app, @EditField_OpenOperatorValueChanged, true);
            app.EditField_OpenOperator.Placeholder = '5,5,2';
            app.Label_51 = uilabel(app.GridLayout4);
            app.Label_51.Text = '中值滤波器';
            app.EditField_MedianFilter = uieditfield(app.GridLayout4, 'text');
            app.EditField_MedianFilter.ValueChangedFcn = createCallbackFcn(app, @EditField_MedianFilterValueChanged, true);
            app.EditField_MedianFilter.Placeholder = '3,3,3';
            app.Label_55 = uilabel(app.GridLayout4);
            app.Label_55.Text = '迭代数上限';
            app.EditField_OptimalZ_MaxIterN = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_OptimalZ_MaxIterN.Limits = [0 Inf];
            app.EditField_OptimalZ_MaxIterN.ValueDisplayFormat = '%.0f';
            app.EditField_OptimalZ_MaxIterN.ValueChangedFcn = createCallbackFcn(app, @EditField_OptimalZ_MaxIterNValueChanged, true);
            app.EditField_OptimalZ_MaxIterN.Value = 100;
            app.Label_56 = uilabel(app.GridLayout4);
            app.Label_56.Text = 'Z优化公差';
            app.EditField_OptimalZ_Tol = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_OptimalZ_Tol.Limits = [0 1];
            app.EditField_OptimalZ_Tol.ValueChangedFcn = createCallbackFcn(app, @EditField_OptimalZ_TolValueChanged, true);
            app.EditField_OptimalZ_Tol.Value = 0.001;
            app.Label_52 = uilabel(app.GridLayout4);
            app.Label_52.Text = '高斯滤波器';
            app.EditField_GaussianFilter = uieditfield(app.GridLayout4, 'text');
            app.EditField_GaussianFilter.ValueChangedFcn = createCallbackFcn(app, @EditField_GaussianFilterValueChanged, true);
            app.EditField_GaussianFilter.Placeholder = '3,3,1';
            app.Panel_AS_Reg_Global = uipanel(app.GridLayout3);
            app.Panel_AS_Reg_Global.Title = '配准';
            app.GridLayout5 = uigridlayout(app.Panel_AS_Reg_Global);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Label_13 = uilabel(app.GridLayout6);
            app.Label_13.Text = '降采样';
            app.DropDown_DownSampling = uidropdown(app.GridLayout6);
            app.DropDown_DownSampling.Items = {'自动', '2X2', '3X3', '关闭'};
            app.DropDown_DownSampling.ItemsData = {'auto', '2X2', '3X3', 'close'};
            app.DropDown_DownSampling.ValueChangedFcn = createCallbackFcn(app, @DropDown_DownSamplingValueChanged, true);
            app.DropDown_DownSampling.Tooltip = {'加速配准并降低资源占用，但会降低配准精度'};
            app.DropDown_DownSampling.Value = 'auto';
            app.Label_10 = uilabel(app.GridLayout6);
            app.Label_10.Text = '模态';
            app.DropDown_RegModal = uidropdown(app.GridLayout6);
            app.DropDown_RegModal.Items = {'单模态', '多模态'};
            app.DropDown_RegModal.ItemsData = {'monomodal', 'multimodal'};
            app.DropDown_RegModal.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegModalValueChanged, true);
            app.DropDown_RegModal.Tooltip = {'采集设备属性'};
            app.DropDown_RegModal.Value = 'monomodal';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.Label_7 = uilabel(app.GridLayout7);
            app.Label_7.Text = '迭代数上限';
            app.EditField_MaxIterN_Global = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_MaxIterN_Global.Limits = [1 Inf];
            app.EditField_MaxIterN_Global.ValueDisplayFormat = '%.0f';
            app.EditField_MaxIterN_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_GlobalValueChanged, true);
            app.EditField_MaxIterN_Global.Tooltip = {'最大迭代次数'};
            app.EditField_MaxIterN_Global.Value = 50;
            app.Label_8 = uilabel(app.GridLayout7);
            app.Label_8.Text = '迭代因子';
            app.EditField_IterCoeff = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_IterCoeff.Limits = [0 1];
            app.EditField_IterCoeff.ValueDisplayFormat = '%.3f';
            app.EditField_IterCoeff.ValueChangedFcn = createCallbackFcn(app, @EditField_IterCoeffValueChanged, true);
            app.EditField_IterCoeff.Tooltip = {''};
            app.EditField_IterCoeff.Value = 0.5;
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.Label_9 = uilabel(app.GridLayout8);
            app.Label_9.Text = '插值';
            app.DropDown_Interp_Global = uidropdown(app.GridLayout8);
            app.DropDown_Interp_Global.Items = {'线性(快速)', '立方(高质量)'};
            app.DropDown_Interp_Global.ItemsData = {'linear', 'cubic'};
            app.DropDown_Interp_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_Interp_GlobalValueChanged, true);
            app.DropDown_Interp_Global.Tooltip = {'图像插值算法'};
            app.DropDown_Interp_Global.Value = 'linear';
            app.Spinner_VPL_Global = uispinner(app.GridLayout8);
            app.Spinner_VPL_Global.Limits = [1 4];
            app.Spinner_VPL_Global.ValueDisplayFormat = '%.0f';
            app.Spinner_VPL_Global.ValueChangedFcn = createCallbackFcn(app, @Spinner_VPL_GlobalValueChanged, true);
            app.Spinner_VPL_Global.Tooltip = {'一个体积内拥有的层数越多，需要越大的金字塔因子提高配准精度，通常为3'};
            app.Spinner_VPL_Global.Value = 3;
            app.Label_VPL_Global = uilabel(app.GridLayout8);
            app.Label_VPL_Global.Text = '金字塔因子';
            app.Tab_Local = uitab(app.TabGroup_AlgorithmSelection);
            app.Tab_Local.Tooltip = {'基于位移场估计的全自动非刚体配准方案'};
            app.Tab_Local.Title = '局部';
            app.Tab_Local.Tag = 'Local';
            app.GridLayout16 = uigridlayout(app.Tab_Local);
            app.GridLayout32 = uigridlayout(app.GridLayout16);
            app.Panel_Common_Local = uipanel(app.GridLayout32);
            app.Panel_Common_Local.Title = '共有';
            app.GridLayout21 = uigridlayout(app.Panel_Common_Local);
            app.GridLayout22 = uigridlayout(app.GridLayout21);
            app.Label_21 = uilabel(app.GridLayout22);
            app.Label_21.Text = '模板';
            app.EditField_Template_Local = uieditfield(app.GridLayout22, 'text');
            app.EditField_Template_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_Template_LocalValueChanged, true);
            app.EditField_Template_Local.Value = '1';
            app.Label_14 = uilabel(app.GridLayout22);
            app.Label_14.Text = '采样';
            app.DropDown_TemplateSampling_Local = uidropdown(app.GridLayout22);
            app.DropDown_TemplateSampling_Local.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_TemplateSampling_Local.ItemsData = {'mean', 'median', 'min', 'max'};
            app.DropDown_TemplateSampling_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_TemplateSampling_LocalValueChanged, true);
            app.DropDown_TemplateSampling_Local.Tooltip = {'帧运算'};
            app.DropDown_TemplateSampling_Local.Value = 'mean';
            app.GridLayout23 = uigridlayout(app.GridLayout21);
            app.Label_15 = uilabel(app.GridLayout23);
            app.Label_15.Text = '定位';
            app.DropDown_Structured_Local = uidropdown(app.GridLayout23);
            app.DropDown_Structured_Local.Items = {'红', '绿'};
            app.DropDown_Structured_Local.ItemsData = {'r', 'g'};
            app.DropDown_Structured_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_Structured_LocalValueChanged, true);
            app.DropDown_Structured_Local.Value = 'r';
            app.Label_18 = uilabel(app.GridLayout23);
            app.Label_18.Text = '信号';
            app.DropDown_Functional_Local = uidropdown(app.GridLayout23);
            app.DropDown_Functional_Local.Items = {'红', '绿'};
            app.DropDown_Functional_Local.ItemsData = {'r', 'g'};
            app.DropDown_Functional_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_Functional_LocalValueChanged, true);
            app.DropDown_Functional_Local.Value = 'g';
            app.GridLayout24 = uigridlayout(app.GridLayout21);
            app.Label_19 = uilabel(app.GridLayout24);
            app.Label_19.Enable = 'off';
            app.Label_19.Text = '精度';
            app.DropDown_RepAcc_Local = uidropdown(app.GridLayout24);
            app.DropDown_RepAcc_Local.Items = {'低', '一般', '高'};
            app.DropDown_RepAcc_Local.ItemsData = {'2048', '4096', '8192'};
            app.DropDown_RepAcc_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_RepAcc_LocalValueChanged, true);
            app.DropDown_RepAcc_Local.Enable = 'off';
            app.DropDown_RepAcc_Local.Tooltip = {'淬灭补偿精度, 更高的精度代价是内存的消耗'};
            app.DropDown_RepAcc_Local.Value = '2048';
            app.CheckBox_RepAccOn_Local = uicheckbox(app.GridLayout24);
            app.CheckBox_RepAccOn_Local.ValueChangedFcn = createCallbackFcn(app, @CheckBox_RepAccOn_LocalValueChanged, true);
            app.CheckBox_RepAccOn_Local.Tooltip = {'如果定位通道亮度变化较明显，建议开启淬灭补偿'};
            app.CheckBox_RepAccOn_Local.Text = '补偿光漂白';
            app.TabGroup_Local = uitabgroup(app.GridLayout32);
            app.TabGroup_Local.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_LocalSelectionChanged, true);
            app.Tab_NS_Local = uitab(app.TabGroup_Local);
            app.Tab_NS_Local.Title = '常用';
            app.Tab_NS_Local.Tag = 'Usual';
            app.GridLayout20 = uigridlayout(app.Tab_NS_Local);
            app.Panel_NS_Local = uipanel(app.GridLayout20);
            app.Panel_NS_Local.Title = 'Imregdeform (Medical Imaging Toolbox)';
            app.GridLayout25 = uigridlayout(app.Panel_NS_Local);
            app.Label_58 = uilabel(app.GridLayout25);
            app.Label_58.Text = '位移正则化';
            app.EditField_GridRegularization_Local = uieditfield(app.GridLayout25, 'numeric');
            app.EditField_GridRegularization_Local.Limits = [0 5];
            app.EditField_GridRegularization_Local.ValueDisplayFormat = '%.2f';
            app.EditField_GridRegularization_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_GridRegularization_LocalValueChanged, true);
            app.EditField_GridRegularization_Local.Tooltip = {'数值越小，局部非刚性形变越强'};
            app.EditField_GridRegularization_Local.Value = 0.11;
            app.Label_57 = uilabel(app.GridLayout25);
            app.Label_57.Text = '网格间距';
            app.EditField_GridSpacing_Local = uieditfield(app.GridLayout25, 'text');
            app.EditField_GridSpacing_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_GridSpacing_LocalValueChanged, true);
            app.EditField_GridSpacing_Local.Placeholder = '4,4,4';
            app.Spinner_NS_VPL_Local = uispinner(app.GridLayout25);
            app.Spinner_NS_VPL_Local.Limits = [1 4];
            app.Spinner_NS_VPL_Local.ValueDisplayFormat = '%.0f';
            app.Spinner_NS_VPL_Local.ValueChangedFcn = createCallbackFcn(app, @Spinner_NS_VPL_LocalValueChanged, true);
            app.Spinner_NS_VPL_Local.Tooltip = {'一个体积内拥有的层数越多，需要越大的金字塔因子提高配准精度，通常为3'};
            app.Spinner_NS_VPL_Local.Value = 3;
            app.Label_VPL_Global_2 = uilabel(app.GridLayout25);
            app.Label_VPL_Global_2.Text = '金字塔因子';
            app.Tab_AS_Local = uitab(app.TabGroup_Local);
            app.Tab_AS_Local.Title = '高级';
            app.Tab_AS_Local.Tag = 'Advanced';
            app.GridLayout19 = uigridlayout(app.Tab_AS_Local);
            app.GridLayout26 = uigridlayout(app.GridLayout19);
            app.Panel_AS_Local = uipanel(app.GridLayout26);
            app.Panel_AS_Local.Title = 'Imregdemons (Image Processing Toolbox)';
            app.GridLayout27 = uigridlayout(app.Panel_AS_Local);
            app.GridLayout28 = uigridlayout(app.GridLayout27);
            app.Image_GPU = uiimage(app.GridLayout28);
            app.Image_GPU.ImageClickedFcn = createCallbackFcn(app, @Image_GPUClicked, true);
            app.Image_GPU.Visible = 'off';
            app.Image_GPU.Tooltip = {'GPU状态'};
            app.GridLayout29 = uigridlayout(app.GridLayout28);
            app.Label_20 = uilabel(app.GridLayout29);
            app.Label_20.Text = '迭代数上限';
            app.EditField_MaxIterN_Local = uieditfield(app.GridLayout29, 'text');
            app.EditField_MaxIterN_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_LocalValueChanged, true);
            app.EditField_MaxIterN_Local.Tooltip = {'最大迭代次数，可以设置不同金字塔层的迭代上限'};
            app.EditField_MaxIterN_Local.Placeholder = '100';
            app.Label_17 = uilabel(app.GridLayout29);
            app.Label_17.Text = '累积场平滑';
            app.EditField_AFS_Local = uieditfield(app.GridLayout29, 'numeric');
            app.EditField_AFS_Local.Limits = [0.5 3];
            app.EditField_AFS_Local.ValueDisplayFormat = '%.2f';
            app.EditField_AFS_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_AFS_LocalValueChanged, true);
            app.EditField_AFS_Local.Tooltip = {'数值越小，局部非刚性形变越强'};
            app.EditField_AFS_Local.Value = 1;
            app.GridLayout30 = uigridlayout(app.GridLayout27);
            app.GridLayout31 = uigridlayout(app.GridLayout27);
            app.Label_16 = uilabel(app.GridLayout31);
            app.Label_16.Text = '插值';
            app.DropDown_Interp_Local = uidropdown(app.GridLayout31);
            app.DropDown_Interp_Local.Items = {'线性(快速)', '立方(高质量)'};
            app.DropDown_Interp_Local.ItemsData = {'linear', 'cubic'};
            app.DropDown_Interp_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_Interp_LocalValueChanged, true);
            app.DropDown_Interp_Local.Tooltip = {'图像插值算法'};
            app.DropDown_Interp_Local.Value = 'linear';
            app.Spinner_AS_VPL_Local = uispinner(app.GridLayout31);
            app.Spinner_AS_VPL_Local.Limits = [1 4];
            app.Spinner_AS_VPL_Local.ValueDisplayFormat = '%.0f';
            app.Spinner_AS_VPL_Local.ValueChangedFcn = createCallbackFcn(app, @Spinner_AS_VPL_LocalValueChanged, true);
            app.Spinner_AS_VPL_Local.Tooltip = {'一个体积内拥有的层数越多，需要越大的金字塔因子提高配准精度，通常为3'};
            app.Spinner_AS_VPL_Local.Value = 3;
            app.Label_VPL_Global_3 = uilabel(app.GridLayout31);
            app.Label_VPL_Global_3.Text = '金字塔因子';
            app.GridLayout17 = uigridlayout(app.GridLayout16);
            app.CheckBox_Lock_Local = uicheckbox(app.GridLayout17);
            app.CheckBox_Lock_Local.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Lock_LocalValueChanged, true);
            app.CheckBox_Lock_Local.Tooltip = {'启用非刚体配准'};
            app.CheckBox_Lock_Local.Text = '锁定';
            app.Label_Helps_Local = uilabel(app.GridLayout17);
            app.Label_Helps_Local.Text = '';
            app.Tab_MGlobal = uitab(app.TabGroup_AlgorithmSelection);
            app.Tab_MGlobal.Tooltip = {'基于控制点方法的手动配准方案'};
            app.Tab_MGlobal.Title = '全局(手动)';
            app.Tab_MGlobal.Tag = 'MGlobal';
            app.GridLayout33 = uigridlayout(app.Tab_MGlobal);
            app.GridLayout34 = uigridlayout(app.GridLayout33);
            app.CheckBox_Lock_MGlobal = uicheckbox(app.GridLayout34);
            app.CheckBox_Lock_MGlobal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Lock_MGlobalValueChanged, true);
            app.CheckBox_Lock_MGlobal.Tooltip = {'启用刚体配准'};
            app.CheckBox_Lock_MGlobal.Text = '锁定';
            app.Label_Helps_MANGlobal = uilabel(app.GridLayout34);
            app.Label_Helps_MANGlobal.Text = '';
            app.Panel_Reg_MGlobal = uipanel(app.GridLayout33);
            app.Panel_Reg_MGlobal.Title = '配准';
            app.GridLayout35 = uigridlayout(app.Panel_Reg_MGlobal);
            app.GridLayout41 = uigridlayout(app.GridLayout35);
            app.Label_23 = uilabel(app.GridLayout41);
            app.Label_23.Text = '最小控制';
            app.EditField_CPNumber_MGlobal = uieditfield(app.GridLayout41, 'numeric');
            app.EditField_CPNumber_MGlobal.Limits = [1 Inf];
            app.EditField_CPNumber_MGlobal.ValueDisplayFormat = '%.0f';
            app.EditField_CPNumber_MGlobal.Editable = 'off';
            app.EditField_CPNumber_MGlobal.Tooltip = {'控制点对数量的下限'};
            app.EditField_CPNumber_MGlobal.Value = 1;
            app.Label_59 = uilabel(app.GridLayout41);
            app.Label_59.Text = '视角(法向)';
            app.DropDown_DView_MGlobal = uidropdown(app.GridLayout41);
            app.DropDown_DView_MGlobal.Items = {'XY平面(Z)', 'ZX平面(Y)', 'YZ平面(X)'};
            app.DropDown_DView_MGlobal.ItemsData = {'XY', 'ZX', 'YZ'};
            app.DropDown_DView_MGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_DView_MGlobalValueChanged, true);
            app.DropDown_DView_MGlobal.Value = 'XY';
            app.GridLayout42 = uigridlayout(app.GridLayout35);
            app.Label_22 = uilabel(app.GridLayout42);
            app.Label_22.Text = '几何变换';
            app.DropDown_RegTform_MGlobal = uidropdown(app.GridLayout42);
            app.DropDown_RegTform_MGlobal.Items = {'平移', '刚体', '分段线性', '多项式'};
            app.DropDown_RegTform_MGlobal.ItemsData = {'translation', 'rigid', 'pwl', 'poly'};
            app.DropDown_RegTform_MGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegTform_MGlobalValueChanged, true);
            app.DropDown_RegTform_MGlobal.Tooltip = {'几何变换类型'};
            app.DropDown_RegTform_MGlobal.Value = 'translation';
            app.Label_60 = uilabel(app.GridLayout42);
            app.Label_60.Text = '投影';
            app.DropDown_Projection_MGlobal = uidropdown(app.GridLayout42);
            app.DropDown_Projection_MGlobal.Items = {'最大值', '最小值', '中位值', '平均值'};
            app.DropDown_Projection_MGlobal.ItemsData = {'max', 'min', 'median', 'mean'};
            app.DropDown_Projection_MGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_Projection_MGlobalValueChanged, true);
            app.DropDown_Projection_MGlobal.Value = 'max';
            app.GridLayout43 = uigridlayout(app.GridLayout35);
            app.Label_26 = uilabel(app.GridLayout43);
            app.Label_26.Text = '插值';
            app.DropDown_Interp_MGlobal = uidropdown(app.GridLayout43);
            app.DropDown_Interp_MGlobal.Items = {'线性(快速)', '立方(高质量)'};
            app.DropDown_Interp_MGlobal.ItemsData = {'linear', 'cubic'};
            app.DropDown_Interp_MGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_Interp_MGlobalValueChanged, true);
            app.DropDown_Interp_MGlobal.Tooltip = {'图像插值算法'};
            app.DropDown_Interp_MGlobal.Value = 'linear';
            app.Label_24 = uilabel(app.GridLayout43);
            app.Label_24.Text = '多项式次数';
            app.Spinner_PolyOrder_MGlobal = uispinner(app.GridLayout43);
            app.Spinner_PolyOrder_MGlobal.Limits = [2 4];
            app.Spinner_PolyOrder_MGlobal.ValueDisplayFormat = '%.0f';
            app.Spinner_PolyOrder_MGlobal.ValueChangedFcn = createCallbackFcn(app, @Spinner_PolyOrder_MGlobalValueChanged, true);
            app.Spinner_PolyOrder_MGlobal.Tooltip = {'多项式变换参数'};
            app.Spinner_PolyOrder_MGlobal.Value = 2;
            app.GridLayout44 = uigridlayout(app.GridLayout35);
            app.Label_61 = uilabel(app.GridLayout44);
            app.Label_61.Text = '重采样比率';
            app.EditField_ReSampleRatio_MGlobal = uieditfield(app.GridLayout44, 'text');
            app.EditField_ReSampleRatio_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_ReSampleRatio_MGlobalValueChanged, true);
            app.EditField_ReSampleRatio_MGlobal.Placeholder = '1,1,1';
            app.CheckBox_ReSampleIsometric_MGlobal = uicheckbox(app.GridLayout44);
            app.CheckBox_ReSampleIsometric_MGlobal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ReSampleIsometric_MGlobalValueChanged, true);
            app.CheckBox_ReSampleIsometric_MGlobal.Text = '等距';
            app.GridLayout45 = uigridlayout(app.GridLayout35);
            app.Tab_LTGlobal = uitab(app.TabGroup_AlgorithmSelection);
            app.Tab_LTGlobal.Title = '全局(长时程)';
            app.Tab_LTGlobal.Tag = 'LTGlobal';
            app.CheckBox_Lock_LTGlobal = uicheckbox(app.Tab_LTGlobal);
            app.CheckBox_Lock_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Lock_LTGlobalValueChanged, true);
            app.CheckBox_Lock_LTGlobal.Tooltip = {'启用刚体配准'};
            app.CheckBox_Lock_LTGlobal.Text = '锁定';
            app.Label_29 = uilabel(app.Tab_LTGlobal);
            app.Label_29.Text = '关键帧';
            app.EditField_KeyFrame = uieditfield(app.Tab_LTGlobal, 'text');
            app.EditField_KeyFrame.ValueChangedFcn = createCallbackFcn(app, @EditField_KeyFrameValueChanged, true);
            app.EditField_KeyFrame.Placeholder = '1,101,201,301,401,...';
            app.Label_30 = uilabel(app.Tab_LTGlobal);
            app.Label_30.Text = '采样';
            app.DropDown_RegSampling_LongTerm = uidropdown(app.Tab_LTGlobal);
            app.DropDown_RegSampling_LongTerm.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_RegSampling_LongTerm.ItemsData = {'MEAN', 'MEDIAN', 'NIN', 'MAX'};
            app.DropDown_RegSampling_LongTerm.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegSampling_LongTermValueChanged, true);
            app.DropDown_RegSampling_LongTerm.Tooltip = {'帧运算'};
            app.DropDown_RegSampling_LongTerm.Value = 'MEAN';
            app.Label_31 = uilabel(app.Tab_LTGlobal);
            app.Label_31.Text = '配准模板';
            app.EditField_RegTemplate_LongTerm = uieditfield(app.Tab_LTGlobal, 'text');
            app.EditField_RegTemplate_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_RegTemplate_LongTermValueChanged, true);
            app.EditField_RegTemplate_LongTerm.Value = '1';
            app.CheckBox_AutoKeyFrame = uicheckbox(app.Tab_LTGlobal);
            app.CheckBox_AutoKeyFrame.ValueChangedFcn = createCallbackFcn(app, @CheckBox_AutoKeyFrameValueChanged, true);
            app.CheckBox_AutoKeyFrame.Text = '自动';
            app.Label_32 = uilabel(app.Tab_LTGlobal);
            app.Label_32.Text = '定位';
            app.DropDown_Marker_LongTerm = uidropdown(app.Tab_LTGlobal);
            app.DropDown_Marker_LongTerm.Items = {'红', '绿'};
            app.DropDown_Marker_LongTerm.ItemsData = {'r', 'g'};
            app.DropDown_Marker_LongTerm.ValueChangedFcn = createCallbackFcn(app, @DropDown_Marker_LongTermValueChanged, true);
            app.DropDown_Marker_LongTerm.Tooltip = {''};
            app.DropDown_Marker_LongTerm.Value = 'r';
            app.Label_33 = uilabel(app.Tab_LTGlobal);
            app.Label_33.Text = '信号';
            app.DropDown_Signal_LongTerm = uidropdown(app.Tab_LTGlobal);
            app.DropDown_Signal_LongTerm.Items = {'红', '绿'};
            app.DropDown_Signal_LongTerm.ItemsData = {'r', 'g'};
            app.DropDown_Signal_LongTerm.ValueChangedFcn = createCallbackFcn(app, @DropDown_Signal_LongTermValueChanged, true);
            app.DropDown_Signal_LongTerm.Tooltip = {''};
            app.DropDown_Signal_LongTerm.Value = 'r';
            app.TabGroup_LongTerm_Params = uitabgroup(app.Tab_LTGlobal);
            app.Tab_PointCloud = uitab(app.TabGroup_LongTerm_Params);
            app.Tab_PointCloud.Title = '点云';
            app.Label_34 = uilabel(app.Tab_PointCloud);
            app.Label_34.Text = '强度下阈值';
            app.EditField_IntensityThreshold_LongTerm = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_IntensityThreshold_LongTerm.Limits = [0 100];
            app.EditField_IntensityThreshold_LongTerm.ValueDisplayFormat = '%.2f';
            app.EditField_IntensityThreshold_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_IntensityThreshold_LongTermValueChanged, true);
            app.EditField_IntensityThreshold_LongTerm.Value = 97;
            app.Label_36 = uilabel(app.Tab_PointCloud);
            app.Label_36.Text = '尺度阈值(μm)';
            app.EditField_ScaleThreshold_LongTerm = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_ScaleThreshold_LongTerm.Limits = [0.1 1000];
            app.EditField_ScaleThreshold_LongTerm.ValueDisplayFormat = '%.1f';
            app.EditField_ScaleThreshold_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_ScaleThreshold_LongTermValueChanged, true);
            app.EditField_ScaleThreshold_LongTerm.Value = 3;
            app.Label_37 = uilabel(app.Tab_PointCloud);
            app.Label_37.Text = '降采样';
            app.DropDown_DownSampling_LongTerm_PC = uidropdown(app.Tab_PointCloud);
            app.DropDown_DownSampling_LongTerm_PC.Items = {'网格平均', '随机', '非均匀'};
            app.DropDown_DownSampling_LongTerm_PC.ItemsData = {'GA', 'RAND', 'NU'};
            app.DropDown_DownSampling_LongTerm_PC.ValueChangedFcn = createCallbackFcn(app, @DropDown_DownSampling_LongTerm_PCValueChanged, true);
            app.DropDown_DownSampling_LongTerm_PC.Value = 'GA';
            app.Label_38 = uilabel(app.Tab_PointCloud);
            app.Label_38.Text = '网格步长';
            app.EditField_DownSampling_LongTerm_Param = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_DownSampling_LongTerm_Param.Limits = [0 Inf];
            app.EditField_DownSampling_LongTerm_Param.ValueChangedFcn = createCallbackFcn(app, @EditField_DownSampling_LongTerm_ParamValueChanged, true);
            app.EditField_DownSampling_LongTerm_Param.Value = 2;
            app.Label_39 = uilabel(app.Tab_PointCloud);
            app.Label_39.Text = '异常点比例';
            app.EditField_OutlierRatio = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_OutlierRatio.Limits = [0 1];
            app.EditField_OutlierRatio.ValueDisplayFormat = '%.2f';
            app.EditField_OutlierRatio.ValueChangedFcn = createCallbackFcn(app, @EditField_OutlierRatioValueChanged, true);
            app.EditField_OutlierRatio.Value = 0.1;
            app.Label_40 = uilabel(app.Tab_PointCloud);
            app.Label_40.Text = '迭代数上限';
            app.EditField_MaxIterN_LongTerm_PC = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_MaxIterN_LongTerm_PC.Limits = [1 Inf];
            app.EditField_MaxIterN_LongTerm_PC.ValueDisplayFormat = '%.0f';
            app.EditField_MaxIterN_LongTerm_PC.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_LongTerm_PCValueChanged, true);
            app.EditField_MaxIterN_LongTerm_PC.Tooltip = {'最大迭代次数'};
            app.EditField_MaxIterN_LongTerm_PC.Value = 50;
            app.Label_41 = uilabel(app.Tab_PointCloud);
            app.Label_41.Text = '全局误差限';
            app.EditField_ErrorThreshold = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_ErrorThreshold.Limits = [0 Inf];
            app.EditField_ErrorThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_ErrorThresholdValueChanged, true);
            app.EditField_ErrorThreshold.Tooltip = {'收敛判别步长'};
            app.EditField_ErrorThreshold.Value = 1e-05;
            app.CheckBox_LongTerm_Review = uicheckbox(app.Tab_PointCloud);
            app.CheckBox_LongTerm_Review.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LongTerm_ReviewValueChanged, true);
            app.CheckBox_LongTerm_Review.Text = '预览';
            app.Tab_Voxels = uitab(app.TabGroup_LongTerm_Params);
            app.Tab_Voxels.Title = '体素';
            app.Label_42 = uilabel(app.Tab_Voxels);
            app.Label_42.Text = '最小迭代步长';
            app.EditField_MinStep_LTGlobal = uieditfield(app.Tab_Voxels, 'numeric');
            app.EditField_MinStep_LTGlobal.Limits = [0 Inf];
            app.EditField_MinStep_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_MinStep_LTGlobalValueChanged, true);
            app.EditField_MinStep_LTGlobal.Tooltip = {'收敛判别步长'};
            app.EditField_MinStep_LTGlobal.Value = 1e-07;
            app.Label_43 = uilabel(app.Tab_Voxels);
            app.Label_43.Text = '最大迭代步长';
            app.EditField_MaxStep_LTGlobal = uieditfield(app.Tab_Voxels, 'numeric');
            app.EditField_MaxStep_LTGlobal.Limits = [0 Inf];
            app.EditField_MaxStep_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxStep_LTGlobalValueChanged, true);
            app.EditField_MaxStep_LTGlobal.Tooltip = {'起始步长'};
            app.EditField_MaxStep_LTGlobal.Value = 0.0625;
            app.Label_45 = uilabel(app.Tab_Voxels);
            app.Label_45.Text = '迭代因子';
            app.EditField_IterCoeff_LTGlobal = uieditfield(app.Tab_Voxels, 'numeric');
            app.EditField_IterCoeff_LTGlobal.Limits = [0 1];
            app.EditField_IterCoeff_LTGlobal.ValueDisplayFormat = '%.3f';
            app.EditField_IterCoeff_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_IterCoeff_LTGlobalValueChanged, true);
            app.EditField_IterCoeff_LTGlobal.Tooltip = {''};
            app.EditField_IterCoeff_LTGlobal.Value = 0.5;
            app.Label_47 = uilabel(app.Tab_Voxels);
            app.Label_47.Text = '降采样';
            app.DropDown_DownSampling_LTGlobal = uidropdown(app.Tab_Voxels);
            app.DropDown_DownSampling_LTGlobal.Items = {'2X2', '3X3', '关闭'};
            app.DropDown_DownSampling_LTGlobal.ItemsData = {'2X2', '3X3', 'OFF'};
            app.DropDown_DownSampling_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_DownSampling_LTGlobalValueChanged, true);
            app.DropDown_DownSampling_LTGlobal.Tooltip = {'加速配准并降低资源占用，但会降低配准精度'};
            app.DropDown_DownSampling_LTGlobal.Value = '2X2';
            app.CheckBox_RepAccOn_LTLocal = uicheckbox(app.Tab_Voxels);
            app.CheckBox_RepAccOn_LTLocal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_RepAccOn_LTLocalValueChanged, true);
            app.CheckBox_RepAccOn_LTLocal.Tooltip = {'如果定位通道亮度变化较明显，建议开启淬灭补偿'};
            app.CheckBox_RepAccOn_LTLocal.Text = '补偿光漂白';
            app.Label_48 = uilabel(app.Tab_Voxels);
            app.Label_48.Text = '累积场平滑';
            app.EditField_AFS_LTLocal = uieditfield(app.Tab_Voxels, 'numeric');
            app.EditField_AFS_LTLocal.Limits = [0.5 3];
            app.EditField_AFS_LTLocal.ValueDisplayFormat = '%.2f';
            app.EditField_AFS_LTLocal.ValueChangedFcn = createCallbackFcn(app, @EditField_AFS_LTLocalValueChanged, true);
            app.EditField_AFS_LTLocal.Tooltip = {'数值越小，局部非刚性形变越强'};
            app.EditField_AFS_LTLocal.Value = 1;
            app.Label_49 = uilabel(app.Tab_Voxels);
            app.Label_49.Text = '精度';
            app.DropDown_RepAcc_LTLocal = uidropdown(app.Tab_Voxels);
            app.DropDown_RepAcc_LTLocal.Items = {'低', '一般', '高'};
            app.DropDown_RepAcc_LTLocal.ItemsData = {'2048', '4096', '8192'};
            app.DropDown_RepAcc_LTLocal.ValueChangedFcn = createCallbackFcn(app, @DropDown_RepAcc_LTLocalValueChanged, true);
            app.DropDown_RepAcc_LTLocal.Tooltip = {'淬灭补偿精度, 更高的精度代价是内存的消耗'};
            app.DropDown_RepAcc_LTLocal.Value = '2048';
            app.Label_50 = uilabel(app.Tab_Voxels);
            app.Label_50.Text = '迭代数上限';
            app.EditField_MaxIterN_LongTerm_Voxel = uieditfield(app.Tab_Voxels, 'text');
            app.EditField_MaxIterN_LongTerm_Voxel.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_LongTerm_VoxelValueChanged, true);
            app.EditField_MaxIterN_LongTerm_Voxel.Tooltip = {'最大迭代次数，可以设置不同金字塔层的迭代上限'};
            app.EditField_MaxIterN_LongTerm_Voxel.Placeholder = '标量或数组';
            app.EditField_MaxIterN_LongTerm_Voxel.Value = '100,50,25';
            app.Tab_LTLocal = uitab(app.TabGroup_AlgorithmSelection);
            app.Tab_LTLocal.Title = '局部(长时程)';
            app.Tab_LTLocal.Tag = 'LTLocal';
            app.GridLayout36 = uigridlayout(app.Tab_LTLocal);
            app.GridLayout37 = uigridlayout(app.GridLayout36);
            app.CheckBox_Lock_LTLocal = uicheckbox(app.GridLayout37);
            app.CheckBox_Lock_LTLocal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Lock_LTLocalValueChanged, true);
            app.CheckBox_Lock_LTLocal.Tooltip = {'启用刚体配准'};
            app.CheckBox_Lock_LTLocal.Text = '锁定';
            app.Label_Helps_LTLocal = uilabel(app.GridLayout37);
            app.Label_Helps_LTLocal.Text = '';
            app.GridLayout38 = uigridlayout(app.GridLayout36);
            app.TabGroup_LTLocal = uitabgroup(app.GridLayout38);
            app.Tab_NS_LTLocal = uitab(app.TabGroup_LTLocal);
            app.Tab_NS_LTLocal.Title = '常用';
            app.GridLayout39 = uigridlayout(app.Tab_NS_LTLocal);
            app.Panel_NS_LTLocal = uipanel(app.GridLayout39);
            app.Panel_NS_LTLocal.Title = 'Algorithm 1';
            app.Tab_AS_LTLocal = uitab(app.TabGroup_LTLocal);
            app.Tab_AS_LTLocal.Title = '高级';
            app.GridLayout40 = uigridlayout(app.Tab_AS_LTLocal);
            app.Panel_AS_LTLocal = uipanel(app.GridLayout40);
            app.Panel_AS_LTLocal.Title = 'Algorithm 2';
            app.Panel_Common_LTLocal = uipanel(app.GridLayout38);
            app.Panel_Common_LTLocal.Title = '共有';
            % Show the figure after all components are created
            app.RSTCUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RegSetting_TC(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RSTCUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.RSTCUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RSTCUIFigure)
        end
    end
end