classdef RegSetting_TC_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SettingUIFigure                 matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_AutoReg_Global              matlab.ui.container.Tab
        DropDown_DownSampling           matlab.ui.control.DropDown
        Label_13                        matlab.ui.control.Label
        EditField_RobustCoeff           matlab.ui.control.NumericEditField
        Label_12                        matlab.ui.control.Label
        EditField_RegTemplate_Global    matlab.ui.control.EditField
        Label_11                        matlab.ui.control.Label
        DropDown_RegModal               matlab.ui.control.DropDown
        Label_10                        matlab.ui.control.Label
        CheckBox_GlobalOn               matlab.ui.control.CheckBox
        Image_CPU                       matlab.ui.control.Image
        DropDown_InterpAlg_Auto_Global  matlab.ui.control.DropDown
        Label_9                         matlab.ui.control.Label
        Label_VPL_Global                matlab.ui.control.Label
        Spinner_VPL_Global              matlab.ui.control.Spinner
        EditField_IterCoeff             matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        EditField_MaxIterN_Global       matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        EditField_InitStep              matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        EditField_MinStep               matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        DropDown_Signal_Global          matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        DropDown_Marker_Global          matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        DropDown_RegTform_Auto          matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        DropDown_RegSampling_Global     matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        Tab_AutoReg_Local               matlab.ui.container.Tab
        EditField_RegTemplate_Local     matlab.ui.control.EditField
        Label_21                        matlab.ui.control.Label
        EditField_MaxIterN_Local        matlab.ui.control.EditField
        Label_20                        matlab.ui.control.Label
        CheckBox_PhotonCompOn           matlab.ui.control.CheckBox
        DropDown_PhotonCompAcc          matlab.ui.control.DropDown
        Label_19                        matlab.ui.control.Label
        DropDown_Signal_Local           matlab.ui.control.DropDown
        Label_18                        matlab.ui.control.Label
        EditField_AFS                   matlab.ui.control.NumericEditField
        Label_17                        matlab.ui.control.Label
        Label_VPL_Local                 matlab.ui.control.Label
        Spinner_VPL_Local               matlab.ui.control.Spinner
        DropDown_InterpAlg_Auto_Local   matlab.ui.control.DropDown
        Label_16                        matlab.ui.control.Label
        Image_GPU                       matlab.ui.control.Image
        CheckBox_LocalOn                matlab.ui.control.CheckBox
        DropDown_Marker_Local           matlab.ui.control.DropDown
        Label_15                        matlab.ui.control.Label
        DropDown_RegSampling_Local      matlab.ui.control.DropDown
        Label_14                        matlab.ui.control.Label
        Tab_AutoReg_LongTerm            matlab.ui.container.Tab
        TabGroup_LongTerm_Params        matlab.ui.container.TabGroup
        Tab_PointCloud                  matlab.ui.container.Tab
        CheckBox_LongTerm_Review        matlab.ui.control.CheckBox
        EditField_ErrorThreshold        matlab.ui.control.NumericEditField
        Label_41                        matlab.ui.control.Label
        EditField_MaxIterN_LongTerm_PC  matlab.ui.control.NumericEditField
        Label_40                        matlab.ui.control.Label
        EditField_OutlierRatio          matlab.ui.control.NumericEditField
        Label_39                        matlab.ui.control.Label
        EditField_DownSampling_LongTerm_Param  matlab.ui.control.NumericEditField
        Label_38                        matlab.ui.control.Label
        DropDown_DownSampling_LongTerm_PC  matlab.ui.control.DropDown
        Label_37                        matlab.ui.control.Label
        EditField_ScaleThreshold_LongTerm  matlab.ui.control.NumericEditField
        Label_36                        matlab.ui.control.Label
        EditField_IntensityThreshold_LongTerm  matlab.ui.control.NumericEditField
        Label_34                        matlab.ui.control.Label
        Tab_Voxels                      matlab.ui.container.Tab
        EditField_MaxIterN_LongTerm_Voxel  matlab.ui.control.EditField
        Label_50                        matlab.ui.control.Label
        DropDown_PhotonCompAcc_LongTerm  matlab.ui.control.DropDown
        Label_49                        matlab.ui.control.Label
        EditField_AFS_LongTerm          matlab.ui.control.NumericEditField
        Label_48                        matlab.ui.control.Label
        CheckBox_PhotonCompOn_LongTerm  matlab.ui.control.CheckBox
        DropDown_DownSampling_LongTerm_Voxel  matlab.ui.control.DropDown
        Label_47                        matlab.ui.control.Label
        EditField_IterCoeff_LongTerm    matlab.ui.control.NumericEditField
        Label_45                        matlab.ui.control.Label
        EditField_InitStep_LongTerm     matlab.ui.control.NumericEditField
        Label_43                        matlab.ui.control.Label
        EditField_MinStep_LongTerm      matlab.ui.control.NumericEditField
        Label_42                        matlab.ui.control.Label
        DropDown_Signal_LongTerm        matlab.ui.control.DropDown
        Label_33                        matlab.ui.control.Label
        DropDown_Marker_LongTerm        matlab.ui.control.DropDown
        Label_32                        matlab.ui.control.Label
        CheckBox_AutoKeyFrame           matlab.ui.control.CheckBox
        EditField_RegTemplate_LongTerm  matlab.ui.control.EditField
        Label_31                        matlab.ui.control.Label
        DropDown_RegSampling_LongTerm   matlab.ui.control.DropDown
        Label_30                        matlab.ui.control.Label
        EditField_KeyFrame              matlab.ui.control.EditField
        Label_29                        matlab.ui.control.Label
        CheckBox_LongTermOn             matlab.ui.control.CheckBox
        Tab_ManualReg                   matlab.ui.container.Tab
        DropDown_TemplateSync           matlab.ui.control.DropDown
        Label_27                        matlab.ui.control.Label
        ButtonGroup_Smooth              matlab.ui.container.ButtonGroup
        Button_Smooth_Manual_N          matlab.ui.control.RadioButton
        Button_Smooth_Manual_Y          matlab.ui.control.RadioButton
        DropDown_InterpAlg_Manual       matlab.ui.control.DropDown
        Label_26                        matlab.ui.control.Label
        Spinner_WPN                     matlab.ui.control.Spinner
        Label_25                        matlab.ui.control.Label
        Spinner_PolyOrder               matlab.ui.control.Spinner
        Label_24                        matlab.ui.control.Label
        EditField_CPNumber              matlab.ui.control.NumericEditField
        Label_23                        matlab.ui.control.Label
        DropDown_RegTform_Manual        matlab.ui.control.DropDown
        Label_22                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        calling_app;    % the parent app
        
        reg_opts;       % the registration options
        template_sync;  % the template synchronize mode, "global"/"local"
        hw_info;        % the hardware information struct

        langs_mapping;  % % mapping for language
    end
    
    methods (Access = private)
        function InitData(app, mainapp)
            app.calling_app = mainapp;
            [app.reg_opts, app.template_sync] = ...
                app.calling_app.GetRegParams();
        end

        function InitPanel(app)
            SetAutoRegGlobalPanel(app);
            SetAutoRegLocalPanel(app);
            SetAutoRegLongTermPanel(app);
            SetManualRegPanel(app);

            SetCrossOpts(app);
        end

        function SetAutoRegGlobalPanel(app)
            % set registration template
            app.DropDown_RegSampling_Global.Value = ...
                upper(app.reg_opts.volfixed.G(1));

            app.EditField_RegTemplate_Global.Value = ...
                app.reg_opts.volfixed.G(2);

            % set transformation
            app.DropDown_RegTform_Auto.Value = upper(app.reg_opts.AutoTform);

            % set the modal
            app.DropDown_RegModal.Value = upper(app.reg_opts.RegModal);

            % set iteration options
            app.EditField_MinStep.Value = app.reg_opts.MinStep;
            app.EditField_InitStep.Value = app.reg_opts.InitStep;
            app.EditField_MaxIterN_Global.Value = app.reg_opts.MaxIterN_Rigid;
            app.EditField_IterCoeff.Value = app.reg_opts.IterCoeff;

             % set downsampling options
            switch app.reg_opts.DS
                case "auto"
                    app.DropDown_DownSampling.Value = "AUTO";
                case "1X1"
                    app.DropDown_DownSampling.Value = "CLOSE";
                otherwise
                    app.DropDown_DownSampling.Value = app.reg_opts.DS;
            end

            % set algorithm options
            opts = app.calling_app.GetImgInfo();
            app.Spinner_VPL_Global.Limits = [1, ...
                floor(log(opts.slices)/log(4))+1];
            app.Spinner_VPL_Global.Value = app.reg_opts.VPL_Rigid;

            app.EditField_RobustCoeff.Value = (1-app.reg_opts.CoRegC/100)^2;
            app.DropDown_InterpAlg_Auto_Global.Value = ...
                upper(app.reg_opts.Interp_Rigid);

        end

        function SetAutoRegLocalPanel(app)
            % set registration template
            app.DropDown_RegSampling_Local.Value = ...
                upper(app.reg_opts.volfixed.L(1));
            
            app.EditField_RegTemplate_Local.Value = ...
                app.reg_opts.volfixed.L(2);

            % set iteration options
            app.EditField_MaxIterN_Local.Value = ...
                num2str(app.reg_opts.MaxIterN_NonRigid);

            % set algorithm options
            app.EditField_AFS.Value = app.reg_opts.AFS;
            app.Spinner_VPL_Local.Limits = app.Spinner_VPL_Global.Limits;
            app.Spinner_VPL_Local.Value = app.Spinner_VPL_Global.Value;
            app.CheckBox_PhotonCompOn.Value = app.reg_opts.AutoContrast;
            app.DropDown_PhotonCompAcc.Value = num2str(app.reg_opts.CompAcc);
            app.DropDown_PhotonCompAcc.Enable = app.reg_opts.AutoContrast;

            app.DropDown_InterpAlg_Auto_Local.Value = ...
                upper(app.reg_opts.Interp_NonRigid);

        end

        function SetAutoRegLongTermPanel(app)
            app.EditField_RegTemplate_LongTerm.Value ...
                = app.reg_opts.volfixed.G(2);
            app.DropDown_RegSampling_LongTerm.Value ...
                = upper(app.reg_opts.volfixed.G(1));

            if ~isempty(str2num(app.reg_opts.KeyFrames)) %#ok<ST2NM>
                app.EditField_KeyFrame.Value = app.reg_opts.KeyFrames;
                app.CheckBox_AutoKeyFrame.Value = false;
            else
                app.EditField_KeyFrame.Enable = "off";
                app.CheckBox_AutoKeyFrame.Value = true;
            end

            app.EditField_IntensityThreshold_LongTerm.Value ...
                = app.reg_opts.IntensityThreshold;
            app.EditField_ScaleThreshold_LongTerm.Value = ...
                app.reg_opts.ScaleThreshold;
            app.DropDown_DownSampling_LongTerm_PC.Value = ...
                app.reg_opts.DS_PointCloud;
            app.EditField_DownSampling_LongTerm_Param.Value = ...
                app.reg_opts.DS_PointCloud_Param;
            app.EditField_OutlierRatio.Value = ...
                app.reg_opts.OutlierRatio;
            app.EditField_MaxIterN_LongTerm_PC.Value = ...
                app.reg_opts.MaxIterN_PointCloud;
            app.EditField_ErrorThreshold.Value = ...
                app.reg_opts.ErrorLimit;
            app.EditField_MinStep_LongTerm.Value = ...
                app.reg_opts.MinStep_LongTerm;
            switch app.reg_opts.DS_Voxel
                case "1x1"
                    app.DropDown_DownSampling_LongTerm_Voxel.Value = "OFF";
                otherwise
                    app.DropDown_DownSampling_LongTerm_Voxel.Value ...
                        = app.reg_opts.DS_Voxel;
            end
            app.EditField_InitStep_LongTerm.Value = ...
                app.reg_opts.InitStep_LongTerm;
            app.EditField_IterCoeff_LongTerm.Value = ...
                app.reg_opts.IterCoeff_LongTerm;
            app.EditField_MaxIterN_LongTerm_Voxel.Value = ...
                string(app.reg_opts.MaxIterN_Voxel).join(",");
            app.CheckBox_PhotonCompOn_LongTerm.Value = ...
                app.reg_opts.AutoContrast_LongTerm;
            app.EditField_AFS_LongTerm.Value = ...
                app.reg_opts.AFS_LongTerm;
            app.DropDown_PhotonCompAcc_LongTerm.Value = ...
                string(app.reg_opts.CompAcc_LongTerm);
        end

        function SetManualRegPanel(app)
            % set the manual registration parameters
            switch app.reg_opts.ManualTform
                case "polynomial"
                    app.DropDown_RegTform_Manual.Value = "POLYNOMIAL";
                    app.Spinner_PolyOrder.Enable = "on";
                    app.Spinner_WPN.Enable = "off";
                case "lwm"
                    app.DropDown_RegTform_Manual.Value = "LWM";
                    app.Spinner_WPN.Enable = "on";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.calling_app.GenSysMsg(["LOCAL_WEIGHTED_CONTROL",""]);
                case "nonreflectivesimilarity"
                    app.DropDown_RegTform_Manual.Value = "SIMILARITY";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
                case "affine"
                    app.DropDown_RegTform_Manual.Value = "AFFINE";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
                case "projective"
                    app.DropDown_RegTform_Manual.Value = "PROJECTION";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
                case "pwl"
                    app.DropDown_RegTform_Manual.Value = "PWL";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
            end
            app.Spinner_PolyOrder.Value = app.reg_opts.Degree;
            app.Spinner_WPN.Value = app.reg_opts.Nlwm;
            UpdateMinCPN(app);

            % set algorithm options
            app.DropDown_InterpAlg_Manual.Value = ...
                upper(app.reg_opts.ManualInterp);

            if app.reg_opts.ManualEdgeSmooth == true
                app.ButtonGroup_Smooth.SelectedObject ...
                    = app.Button_Smooth_Manual_Y;
            else
                app.ButtonGroup_Smooth.SelectedObject ...
                    = app.Button_Smooth_Manual_N;
            end

            app.DropDown_TemplateSync.Value = upper(app.template_sync);
        end

        function SetCrossOpts(app)
            % set registration mode
            switch app.reg_opts.RegMode
                case "global-only"
                    app.CheckBox_GlobalOn.Value = true;
                    app.CheckBox_LocalOn.Value = false;
                case "local-only"
                    app.CheckBox_GlobalOn.Value = false;
                    app.CheckBox_LocalOn.Value = true;
                case "mix"
                    app.CheckBox_GlobalOn.Value = true;
                    app.CheckBox_LocalOn.Value = true;
                otherwise   % some times may be none
                    app.CheckBox_GlobalOn.Value = false;
                    app.CheckBox_LocalOn.Value = false;
            end

            % set marker/signal channel
            switch app.reg_opts.Chla
                case "r"
                    app.DropDown_Marker_Global.Value = "RED";
                    app.DropDown_Marker_Local.Value = "RED";
                case "g"
                    app.DropDown_Marker_Global.Value = "GREEN";
                    app.DropDown_Marker_Local.Value = "GREEN";
            end
            switch app.reg_opts.Chls
                case "r"
                    app.DropDown_Signal_Global.Value = "RED";
                    app.DropDown_Signal_Local.Value = "RED";
                case "g"
                    app.DropDown_Signal_Global.Value = "GREEN";
                    app.DropDown_Signal_Local.Value = "GREEN";
            end
        end

        function UpdateMinCPN(app)
            switch app.DropDown_RegTform_Manual.Value
                case "SIMILARITY"
                    app.EditField_CPNumber.Value = 2;
                case "AFFINE"
                    app.EditField_CPNumber.Value = 3;
                case "PROJECTION"
                    app.EditField_CPNumber.Value = 4;
                case "PWL"
                    app.EditField_CPNumber.Value = 4;
                case "POLYNOMIAL"
                    switch app.Spinner_PolyOrder.Value
                        case 2
                            app.EditField_CPNumber.Value = 6;
                        case 3
                            app.EditField_CPNumber.Value = 10;
                        case 4
                            app.EditField_CPNumber.Value = 15;
                        otherwise
                    end
                case "LWM"
                    app.EditField_CPNumber.Value = 6;
                otherwise
            end
        end

        function UpdateChannelState(app)
            if app.CheckBox_GlobalOn.Value == true && ...
                    app.CheckBox_LocalOn.Value == true
                app.DropDown_Marker_Local.Enable = "off";
                app.DropDown_Signal_Local.Enable = "off";
                % align the setting
                app.DropDown_Marker_Local.Value = ...
                    app.DropDown_Marker_Global.Value;
                app.DropDown_Signal_Local.Value = ...
                    app.DropDown_Signal_Global.Value;
            else
                app.DropDown_Marker_Local.Enable = "on";
                app.DropDown_Signal_Local.Enable = "on";
            end
        end

        function UpdateRegProgram(app)
            if app.CheckBox_LongTermOn.Value == false
                if app.CheckBox_GlobalOn.Value == true && ...
                        app.CheckBox_LocalOn.Value == true
                    app.reg_opts.RegMode = "mix";
                elseif app.CheckBox_GlobalOn.Value == true && ...
                        app.CheckBox_LocalOn.Value == false
                    app.reg_opts.RegMode = "global-only";
                elseif app.CheckBox_GlobalOn.Value == false && ...
                        app.CheckBox_LocalOn.Value == true
                    app.reg_opts.RegMode = "local-only";
                else
                    app.reg_opts.RegMode = "none";
                end
            else
                app.reg_opts.RegMode = "longterm";
            end
        end

        function InitHardwareDetector(app)
            [hw_logo, app.hw_info] = GetHardwareInfo();
            app.Image_CPU.Visible = "on";
            app.Image_GPU.Visible = "on";

            switch hw_logo.cpu
                case "Intel"
                    app.Image_CPU.ImageSource = "sources/intel_logo.png";
                case "AMD"
                    app.Image_CPU.ImageSource = "sources/amd_logo.png";
                case "Unknown"
                    app.Image_CPU.ImageSource = "sources/unknown_logo.png";
                otherwise
                    app.Image_CPU.Visible = "off";
            end
            switch hw_logo.gpu
                case "Intel"
                    app.Image_GPU.ImageSource = "sources/intel_logo.png";
                case "AMD"
                    app.Image_GPU.ImageSource = "sources/amd_logo.png";
                case "Nvidia"
                    app.Image_GPU.ImageSource = "sources/nvidia_logo.png";
                case "Unknown"
                    app.Image_GPU.ImageSource = "sources/unknown_logo.png";
                otherwise
                    app.Image_GPU.Visible = "off";
            end
        end
    end
    
    methods (Access = public)
        
        function FontBlink(app,components,ts,c)
            % This function for app components font blink
            arguments
                app (1,1) RegSetting_TC_GUI;
                components string {mustBeVector}; % target  source
                ts (:,2) double {mustBePositive} = [0.25,   0.25; ...
                    0.25,   0.25; ...
                    0.25,   0.25]   %time series, seconds
                % c is RGB array
                c (2,3) double {mustBeInRange(c,0,1)} = [1,0,0; ... % target
                    0,0,0];    % source
            end

            N = numel(components);

            for t = 1:size(ts,1)
                for k = 1:N
                    if isprop(app.(components(k)),"FontColor")
                        app.(components(k)).FontColor = c(1,:);
                    end
                end
                pause(ts(t,1));
                for k = 1:N
                    if isprop(app.(components(k)),"FontColor")
                        app.(components(k)).FontColor = c(2,:);
                    end
                end
                pause(ts(t,2));
            end
        end

        function LockPanel(app)
            % lock the auto registration (global) panel
            app.EditField_RegTemplate_Global.Enable = "off";
            app.DropDown_RegSampling_Global.Enable = "off";
            app.DropDown_RegTform_Auto.Enable = "off";
            app.DropDown_RegModal.Enable = "off";
            app.DropDown_Marker_Global.Enable = "off";
            app.DropDown_Signal_Global.Enable = "off";
            app.EditField_MinStep.Enable = "off";
            app.EditField_InitStep.Enable = "off";
            app.EditField_MaxIterN_Global.Enable = "off";
            app.EditField_IterCoeff.Enable = "off";
            app.EditField_RobustCoeff.Enable = "off";
            app.DropDown_DownSampling.Enable = "off";
            app.DropDown_InterpAlg_Auto_Global.Enable = "off";
            app.Spinner_VPL_Global.Enable = "off";
            app.CheckBox_GlobalOn.Enable = "off";

            % lock the auto registration (local) panel
            app.EditField_RegTemplate_Local.Enable = "off";
            app.DropDown_RegSampling_Local.Enable = "off";
            app.DropDown_Marker_Local.Enable = "off";
            app.DropDown_Signal_Local.Enable = "off";
            app.EditField_MaxIterN_Local.Enable = "off";
            app.EditField_AFS.Enable = "off";
            app.CheckBox_PhotonCompOn.Enable = "off";
            app.DropDown_PhotonCompAcc.Enable = "off";
            app.DropDown_InterpAlg_Auto_Local.Enable = "off";
            app.Spinner_VPL_Local.Enable = "off";
            app.CheckBox_LocalOn.Enable = "off";
            
            % lock the longterm panel
            app.EditField_RegTemplate_LongTerm.Enable = "off";
            app.DropDown_RegSampling_LongTerm.Enable = "off";
            app.CheckBox_AutoKeyFrame.Enable = "off";
            app.EditField_KeyFrame.Enable = "off";
            app.DropDown_Marker_LongTerm.Enable = "off";
            app.DropDown_Signal_LongTerm.Enable = "off";
            app.EditField_IntensityThreshold_LongTerm.Enable = "off";
            app.EditField_ScaleThreshold_LongTerm.Enable = "off";
            app.DropDown_DownSampling_LongTerm_PC.Enable = "off";
            app.EditField_DownSampling_LongTerm_Param.Enable = "off";
            app.EditField_OutlierRatio.Enable = "off";
            app.EditField_MaxIterN_LongTerm_PC.Enable = "off";
            app.EditField_ErrorThreshold.Enable = "off";
            app.CheckBox_LongTerm_Review.Enable = "off";
            app.EditField_MinStep_LongTerm.Enable = "off";
            app.DropDown_DownSampling_LongTerm_Voxel.Enable = "off";
            app.EditField_InitStep_LongTerm.Enable = "off";
            app.EditField_IterCoeff_LongTerm.Enable = "off";
            app.EditField_MaxIterN_LongTerm_Voxel.Enable = "off";
            app.CheckBox_PhotonCompOn_LongTerm.Enable = "off";
            app.EditField_AFS_LongTerm.Enable = "off";
            app.DropDown_PhotonCompAcc_LongTerm.Enable = "off";
            app.CheckBox_LongTermOn.Enable = "off";

            % lock the manual registration panel
            app.DropDown_RegTform_Manual.Enable = "off";
            app.EditField_CPNumber.Enable = "off";
            app.Spinner_PolyOrder.Enable = "off";
            app.Spinner_WPN.Enable = "off";
            app.ButtonGroup_Smooth.Enable = "off";
            app.DropDown_InterpAlg_Manual.Enable = "off";
            app.DropDown_TemplateSync.Enable = "off";
        end

        function UnlockPanel(app)
            % unlock the auto registration (global) panel
            app.EditField_RegTemplate_Global.Enable = "on";
            app.DropDown_RegSampling_Global.Enable = "on";
            app.DropDown_RegTform_Auto.Enable = "on";
            app.DropDown_RegModal.Enable = "on";
            app.DropDown_Marker_Global.Enable = "on";
            app.DropDown_Signal_Global.Enable = "on";
            app.EditField_MinStep.Enable = "on";
            app.EditField_InitStep.Enable = "on";
            app.EditField_MaxIterN_Global.Enable = "on";
            app.EditField_IterCoeff.Enable = "on";
            app.EditField_RobustCoeff.Enable = "on";
            app.DropDown_DownSampling.Enable = "on";
            app.DropDown_InterpAlg_Auto_Global.Enable = "on";
            app.Spinner_VPL_Global.Enable = "on";
            app.CheckBox_GlobalOn.Enable = "on";

            % unlock the auto registration (local) panel
            app.EditField_RegTemplate_Local.Enable = "on";
            app.DropDown_RegSampling_Local.Enable = "on";
            app.DropDown_Marker_Local.Enable = "on";
            app.DropDown_Signal_Local.Enable = "on";
            app.EditField_MaxIterN_Local.Enable = "on";
            app.EditField_AFS.Enable = "on";
            app.CheckBox_PhotonCompOn.Enable = "on";
            if app.CheckBox_PhotonCompOn.Value == true
                app.DropDown_PhotonCompAcc.Enable = "on";
            end
            app.DropDown_InterpAlg_Auto_Local.Enable = "on";
            app.Spinner_VPL_Local.Enable = "on";
            app.CheckBox_LocalOn.Enable = "on";

            % unlock the longterm panel
            app.EditField_RegTemplate_LongTerm.Enable = "on";
            app.DropDown_RegSampling_LongTerm.Enable = "on";
            app.CheckBox_AutoKeyFrame.Enable = "on";
            if app.CheckBox_AutoKeyFrame.Value == false
                app.EditField_KeyFrame.Enable = "on";
            end
            app.DropDown_Marker_LongTerm.Enable = "on";
            app.DropDown_Signal_LongTerm.Enable = "on";
            app.EditField_IntensityThreshold_LongTerm.Enable = "on";
            app.EditField_ScaleThreshold_LongTerm.Enable = "on";
            app.DropDown_DownSampling_LongTerm_PC.Enable = "on";
            app.EditField_DownSampling_LongTerm_Param.Enable = "on";
            app.EditField_OutlierRatio.Enable = "on";
            app.EditField_MaxIterN_LongTerm_PC.Enable = "on";
            app.EditField_ErrorThreshold.Enable = "on";
            app.CheckBox_LongTerm_Review.Enable = "on";
            app.EditField_MinStep_LongTerm.Enable = "on";
            app.DropDown_DownSampling_LongTerm_Voxel.Enable = "on";
            app.EditField_InitStep_LongTerm.Enable = "on";
            app.EditField_IterCoeff_LongTerm.Enable = "on";
            app.EditField_MaxIterN_LongTerm_Voxel.Enable = "on";
            app.CheckBox_PhotonCompOn_LongTerm.Enable = "on";
            app.EditField_AFS_LongTerm.Enable = "on";
            if app.CheckBox_PhotonCompOn_LongTerm.Value == true
                app.DropDown_PhotonCompAcc_LongTerm.Enable = "on";
            end
            app.CheckBox_LongTermOn.Enable = "on";

            % unlock the manual registration panel
            app.DropDown_RegTform_Manual.Enable = "on";
            app.ButtonGroup_Smooth.Enable = "on";
            app.DropDown_InterpAlg_Manual.Enable = "on";
            app.DropDown_TemplateSync.Enable = "on"; 
        end

        function Refresh(app)
            InitData(app);
            InitPanel(app);
        end

        function RefreshPanelLanguage(app)
            [~, app.langs_mapping] = app.calling_app.GetLangsInfo();
            
            app.Tab_AutoReg_Global.Title = app.langs_mapping.GLOBAL_REGISTRATION_AUTO;
            app.Tab_AutoReg_Local.Title = app.langs_mapping.LOCAL_REGISTRATION_AUTO;
            app.Tab_AutoReg_LongTerm.Title = app.langs_mapping.LONGTERM_REGISTRATION_AUTO;
            app.Tab_ManualReg.Title = app.langs_mapping.GLOBAL_REGISTRATION_MANUAL;
            app.Tab_PointCloud.Title = app.langs_mapping.POINT_CLOUD;
            app.Tab_Voxels.Title = app.langs_mapping.VOXEL;
            app.Label.Text = app.langs_mapping.SAMPLING;
            app.Label_2.Text = app.langs_mapping.GEOMETRIC_TRANSFORMATION;
            app.Label_3.Text = app.langs_mapping.LOCATION;
            app.Label_4.Text = app.langs_mapping.SIGNAL;
            app.Label_5.Text = app.langs_mapping.MIN_ITERATION_STEP;
            app.Label_6.Text = app.langs_mapping.INITIAL_ITERATION_STEP;
            app.Label_7.Text = app.langs_mapping.MAX_ITERATION_N;
            app.Label_8.Text = app.langs_mapping.ITERATION_COEFFICIENT;
            app.Label_9.Text = app.langs_mapping.INTERPOLATION;
            app.Label_10.Text = app.langs_mapping.MODAL;
            app.Label_VPL_Global.Text = app.langs_mapping.PYRAMID_COEFFICIENT;
            app.Label_11.Text = app.langs_mapping.REGISTRATION_TEMPLATE;
            app.Label_12.Text = app.langs_mapping.ROBUSTNESS_COEFFICIENT;
            app.Label_13.Text = app.langs_mapping.DOWNSAMPLING;
            app.Label_14.Text = app.langs_mapping.SAMPLING;
            app.Label_15.Text = app.langs_mapping.LOCATION;
            app.Label_16.Text = app.langs_mapping.INTERPOLATION;
            app.Label_17.Text = app.langs_mapping.ACCUMULATIVE_FIELD_SMOOTHING;
            app.Label_18.Text = app.langs_mapping.SIGNAL;
            app.Label_19.Text = app.langs_mapping.PRECISION;
            app.Label_20.Text = app.langs_mapping.MAX_ITERATION_N;
            app.Label_21.Text = app.langs_mapping.REGISTRATION_TEMPLATE;
            app.Label_22.Text = app.langs_mapping.GEOMETRIC_TRANSFORMATION;
            app.Label_23.Text = app.langs_mapping.MIN_CONTROL_VAL;
            app.Label_VPL_Local.Text = app.langs_mapping.PYRAMID_COEFFICIENT;
            app.Label_24.Text = app.langs_mapping.POLYNOMIAL_ORDER;
            app.Label_25.Text = app.langs_mapping.WEIGHTED_POINTS_NUMBER;
            app.Label_26.Text = app.langs_mapping.INTERPOLATION;
            app.Label_27.Text = app.langs_mapping.TEMPLATE_SYNCHRONISATION;
            app.Label_29.Text = app.langs_mapping.KEY_FRAME;
            app.Label_30.Text = app.langs_mapping.SAMPLING;
            app.Label_31.Text = app.langs_mapping.REGISTRATION_TEMPLATE;
            app.Label_32.Text = app.langs_mapping.LOCATION;
            app.Label_33.Text = app.langs_mapping.SIGNAL;
            app.Label_34.Text = app.langs_mapping.INTENSITY_THRESHOLD;
            app.Label_36.Text = app.langs_mapping.SCALE_THRESHOLD;
            app.Label_37.Text = app.langs_mapping.DOWNSAMPLING;
            app.Label_38.Text = app.langs_mapping.GRID_STEP;
            app.Label_39.Text = app.langs_mapping.OUTLIER_RATIO;
            app.Label_40.Text = app.langs_mapping.MAX_ITERATION_N;
            app.Label_41.Text = app.langs_mapping.GLOBAL_ERROR_LIMIT;
            app.Label_42.Text = app.langs_mapping.MIN_ITERATION_STEP;
            app.Label_43.Text = app.langs_mapping.INITIAL_ITERATION_STEP;
            app.Label_45.Text = app.langs_mapping.ITERATION_COEFFICIENT;
            app.Label_47.Text = app.langs_mapping.DOWNSAMPLING;
            app.Label_48.Text = app.langs_mapping.ACCUMULATIVE_FIELD_SMOOTHING;
            app.Label_49.Text = app.langs_mapping.PRECISION;
            app.Label_50.Text = app.langs_mapping.MAX_ITERATION_N;
            
            app.DropDown_RegSampling_Global.Items = [app.langs_mapping.MEAN,...
                app.langs_mapping.MEDIAN, app.langs_mapping.MIN, app.langs_mapping.MAX];
            app.DropDown_RegTform_Auto.Items = [app.langs_mapping.TRANSLATION,...
                app.langs_mapping.RIGID, app.langs_mapping.SIMILARITY, ...
                app.langs_mapping.AFFINE];
            app.DropDown_RegModal.Items = [app.langs_mapping.MONOMODAL, ...
                app.langs_mapping.MULTIMODAL];
            app.DropDown_Marker_Global.Items = [app.langs_mapping.RED, ...
                app.langs_mapping.GREEN];
            app.DropDown_Signal_Global.Items = [app.langs_mapping.RED, ...
                app.langs_mapping.GREEN];
            app.DropDown_DownSampling.Items = [app.langs_mapping.AUTO, ...
                "2X2", "3X3", app.langs_mapping.CLOSE];
            app.DropDown_InterpAlg_Auto_Global.Items = [app.langs_mapping.NEAREST_FAST,...
                app.langs_mapping.LINEARITY, app.langs_mapping.CUBIC_HIGH_QUALITY];
            app.DropDown_RegSampling_Local.Items = [app.langs_mapping.MEAN,...
                app.langs_mapping.MEDIAN, app.langs_mapping.MIN, app.langs_mapping.MAX];
            app.DropDown_RegSampling_LongTerm.Items = [app.langs_mapping.MEAN,...
                app.langs_mapping.MEDIAN, app.langs_mapping.MIN, app.langs_mapping.MAX];
            app.DropDown_Marker_Local.Items = [app.langs_mapping.RED, ...
                app.langs_mapping.GREEN];
            app.DropDown_Signal_Local.Items = [app.langs_mapping.RED, ...
                app.langs_mapping.GREEN];
            app.DropDown_Marker_LongTerm.Items = [app.langs_mapping.RED, ...
                app.langs_mapping.GREEN];
            app.DropDown_Signal_LongTerm.Items = [app.langs_mapping.RED, ...
                app.langs_mapping.GREEN];
            app.DropDown_DownSampling_LongTerm_PC.Items = [app.langs_mapping.GRID_AVERAGE, ...
                app.langs_mapping.RANDOM_UNIFORM, app.langs_mapping.NONUNIFORM];
            app.DropDown_InterpAlg_Auto_Local.Items = [app.langs_mapping.NEAREST_FAST,...
                app.langs_mapping.LINEARITY, app.langs_mapping.CUBIC_HIGH_QUALITY];
            app.DropDown_RegTform_Manual.Items = [app.langs_mapping.SIMILARITY,...
                app.langs_mapping.AFFINE, app.langs_mapping.PROJECTION, ...
                app.langs_mapping.PIECEWISE_LINEAR, app.langs_mapping.POLYNOMIAL,...
                app.langs_mapping.LOCAL_WEIGHTED_MEAN];
            app.DropDown_InterpAlg_Manual.Items = [app.langs_mapping.NEAREST_FAST,...
                app.langs_mapping.LINEARITY, app.langs_mapping.CUBIC_HIGH_QUALITY];
            app.DropDown_TemplateSync.Items = [app.langs_mapping.GLOBAL_REGISTRATION_AUTO, ...
                app.langs_mapping.LOCAL_REGISTRATION_AUTO];
            app.CheckBox_GlobalOn.Text = app.langs_mapping.ENABLE;
            app.CheckBox_LocalOn.Text = app.langs_mapping.ENABLE;
            app.CheckBox_AutoKeyFrame.Text = app.langs_mapping.AUTO;
            app.CheckBox_LongTerm_Review.Text = app.langs_mapping.PREVIEW;
            app.CheckBox_LongTermOn.Text = app.langs_mapping.ENABLE;
            app.CheckBox_PhotonCompOn.Text = app.langs_mapping.COMPENSATION_PHOTOBLEACHING;
            app.CheckBox_PhotonCompOn_LongTerm.Text = app.langs_mapping.COMPENSATION_PHOTOBLEACHING;
            app.ButtonGroup_Smooth.Title = app.langs_mapping.EDGE_SMOOTHING;
            app.Button_Smooth_Manual_Y.Text = app.langs_mapping.YES;
            app.Button_Smooth_Manual_N.Text = app.langs_mapping.NO;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            InitData(app, mainapp);

            InitPanel(app);

            InitHardwareDetector(app);

            RefreshPanelLanguage(app);
        end

        % Value changed function: EditField_RegTemplate_Global
        function EditField_RegTemplate_GlobalValueChanged(app, event)
            value = app.EditField_RegTemplate_Global.Value;
            opts = app.calling_app.GetImgInfo();
            value = string(value).replace("end",num2str(opts.frames));
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || numel(value) > opts.frames
                % blink the field
                FontBlink(app,"EditField_RegTemplate_Global");
                app.EditField_RegTemplate_Global.Value = ...
                    app.reg_opts.volfixed.G(2).join(",");
            else
                app.reg_opts.volfixed.G(2) = value;
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_RegSampling_Global
        function DropDown_RegSampling_GlobalValueChanged(app, event)
            value = app.DropDown_RegSampling_Global.Value;

            app.reg_opts.volfixed.G(1) = lower(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_RegTform_Auto
        function DropDown_RegTform_AutoValueChanged(app, event)
            value = app.DropDown_RegTform_Auto.Value;

            switch value
                case "TRANSLATION"
                    app.DropDown_RegTform_Auto.Tooltip = ...
                        app.langs_mapping.TRANSLATION_TOOLTIP;
                case "RIGID"
                    app.DropDown_RegTform_Auto.Tooltip = ...
                        app.langs_mapping.RIGID_TOOLTIP;
                case "SIMILARITY"
                    app.DropDown_RegTform_Auto.Tooltip = ...
                        app.langs_mapping.SIMILARITY_TOOLTIP;
                case "AFFINE"
                    app.DropDown_RegTform_Auto.Tooltip = ...
                        app.langs_mapping.AFFINE_TOOLTIP;
                otherwise
            end
            
            app.reg_opts.AutoTform = lower(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_RegModal
        function DropDown_RegModalValueChanged(app, event)
            value = app.DropDown_RegModal.Value;
            
            app.reg_opts.RegModal = lower(value);

            switch value
                case "MONOMODAL"
                    app.EditField_InitStep.Value = 0.1;
                    app.reg_opts.InitStep = 0.1;
                    app.EditField_IterCoeff.Value = 0.5;
                    app.reg_opts.IterCoeff = 0.5;
                case "MULTIMODAL"
                    app.EditField_InitStep.Value = 1e-2;
                    app.reg_opts.InitStep = 1e-2;
                    app.EditField_IterCoeff.Value = 1.05;
                    app.reg_opts.IterCoeff = 1.05;
                otherwise
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_Marker_Global
        function DropDown_Marker_GlobalValueChanged(app, event)
            value = app.DropDown_Marker_Global.Value;

            switch value
                case "RED"
                    app.reg_opts.Chla = "r";
                case "GREEN"
                    app.reg_opts.Chla = "g";
                otherwise
            end

            UpdateChannelState(app);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_Signal_Global
        function DropDown_Signal_GlobalValueChanged(app, event)
            value = app.DropDown_Signal_Global.Value;
            
            switch value
                case "RED"
                    app.reg_opts.Chls = "r";
                case "GREEN"
                    app.reg_opts.Chls = "g";
                otherwise
            end

            UpdateChannelState(app);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_MinStep
        function EditField_MinStepValueChanged(app, event)
            value = app.EditField_MinStep.Value;
            
            app.reg_opts.MinStep = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_InitStep
        function EditField_InitStepValueChanged(app, event)
            value = app.EditField_InitStep.Value;
            
            app.reg_opts.InitStep = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Image clicked function: Image_CPU
        function Image_CPUClicked(app, event)
            uialert(app.SettingUIFigure,app.hw_info.cpu, ...
                app.langs_mapping.INFORMATION, "Icon","info");
        end

        % Value changed function: EditField_MaxIterN_Global
        function EditField_MaxIterN_GlobalValueChanged(app, event)
            value = app.EditField_MaxIterN_Global.Value;
            
            app.reg_opts.MaxIterN_Rigid = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_IterCoeff
        function EditField_IterCoeffValueChanged(app, event)
            value = app.EditField_IterCoeff.Value;
            
            app.reg_opts.IterCoeff = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_RobustCoeff
        function EditField_RobustCoeffValueChanged(app, event)
            value = app.EditField_RobustCoeff.Value;
            
            app.reg_opts.CoRegC = (1 - sqrt(value)) * 100;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_DownSampling
        function DropDown_DownSamplingValueChanged(app, event)
            value = app.DropDown_DownSampling.Value;
            
            switch value
                case "AUTO"
                    app.reg_opts.DS = "auto";
                case "CLOSE"
                    app.reg_opts.DS = "1X1";
                otherwise
                    app.reg_opts.DS = string(value);
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_InterpAlg_Auto_Global
        function DropDown_InterpAlg_Auto_GlobalValueChanged(app, event)
            value = app.DropDown_InterpAlg_Auto_Global.Value;
            
            app.reg_opts.Interp_Rigid = lower(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: Spinner_VPL_Global
        function Spinner_VPL_GlobalValueChanged(app, event)
            value = app.Spinner_VPL_Global.Value;
            
            app.reg_opts.VPL_Rigid = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: CheckBox_GlobalOn
        function CheckBox_GlobalOnValueChanged(app, event)
            value = app.CheckBox_GlobalOn.Value;

            if value == true
                if app.CheckBox_LongTermOn.Value == true
                    slt = uiconfirm(app.SettingUIFigure, ...
                        app.langs_mapping.NORMAL_LONG_TERM_CONFLICT, ...
                        app.langs_mapping.WARNING, ...
                        "Icon","warning");
                    if slt == "OK"
                        app.CheckBox_LongTermOn.Value = false;
                    else
                        app.CheckBox_GlobalOn.Value = false;
                    end
                end
            end
            
            UpdateRegProgram(app);

            UpdateChannelState(app);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_RegTemplate_Local
        function EditField_RegTemplate_LocalValueChanged(app, event)
            value = app.EditField_RegTemplate_Local.Value;
            opts = app.calling_app.GetImgInfo();
            value = string(value).replace("end",num2str(opts.frames));
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || numel(value) > opts.frames
                % blink the field
                FontBlink(app,"EditField_RegTemplate_Local");
                app.EditField_RegTemplate_Local.Value = ...
                    app.reg_opts.volfixed.L(2).join(",");
            else
                app.reg_opts.volfixed.L(2) = value;
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_RegSampling_Local
        function DropDown_RegSampling_LocalValueChanged(app, event)
            value = app.DropDown_RegSampling_Local.Value;
            
            app.reg_opts.volfixed.L(1) = lower(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_Marker_Local
        function DropDown_Marker_LocalValueChanged(app, event)
            value = app.DropDown_Marker_Local.Value;
            
            switch value
                case "RED"
                    app.reg_opts.Chla = "r";
                case "GREEN"
                    app.reg_opts.Chla = "g";
                otherwise
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_Signal_Local
        function DropDown_Signal_LocalValueChanged(app, event)
            value = app.DropDown_Signal_Local.Value;
            
            switch value
                case "RED"
                    app.reg_opts.Chls = "r";
                case "GREEN"
                    app.reg_opts.Chls = "g";
                otherwise
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_MaxIterN_Local
        function EditField_MaxIterN_LocalValueChanged(app, event)
            value = app.EditField_MaxIterN_Local.Value;
            
            value = str2num(value); %#ok<ST2NM>
            if isempty(value) || numel(value)>app.reg_opts.VPL_NonRigid
                % blink the field
                FontBlink(app,"EditField_MaxIterN_Local");
                app.EditField_MaxIterN_Local.Value = ...
                    string(app.reg_opts.MaxIterN_NonRigid).join(",");
            else
                app.reg_opts.MaxIterN_NonRigid = value;
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_AFS
        function EditField_AFSValueChanged(app, event)
            value = app.EditField_AFS.Value;
            
            app.reg_opts.AFS = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Image clicked function: Image_GPU
        function Image_GPUClicked(app, event)
            uialert(app.SettingUIFigure,app.hw_info.gpu, ...
                app.langs_mapping.INFORMATION, "Icon","info");
        end

        % Value changed function: CheckBox_PhotonCompOn
        function CheckBox_PhotonCompOnValueChanged(app, event)
            value = app.CheckBox_PhotonCompOn.Value;
            
            if value == true
                app.DropDown_PhotonCompAcc.Enable = true;
                app.reg_opts.AutoContrast = true;
            else
                app.DropDown_PhotonCompAcc.Enable = false;
                app.reg_opts.AutoContrast = false;
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_PhotonCompAcc
        function DropDown_PhotonCompAccValueChanged(app, event)
            value = app.DropDown_PhotonCompAcc.Value;
            
            app.reg_opts.CompAcc = str2double(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_InterpAlg_Auto_Local
        function DropDown_InterpAlg_Auto_LocalValueChanged(app, event)
            value = app.DropDown_InterpAlg_Auto_Local.Value;
            
            app.reg_opts.Interp_NonRigid = lower(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: Spinner_VPL_Local
        function Spinner_VPL_LocalValueChanged(app, event)
            value = app.Spinner_VPL_Local.Value;
            
            app.reg_opts.VPL_NonRigid = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: CheckBox_LocalOn
        function CheckBox_LocalOnValueChanged(app, event)
            value = app.CheckBox_LocalOn.Value;

            if value == true
                if app.CheckBox_LongTermOn.Value == true
                    slt = uiconfirm(app.SettingUIFigure, ...
                        app.langs_mapping.NORMAL_LONG_TERM_CONFLICT, ...
                        app.langs_mapping.WARNING, ...
                        "Icon","warning");
                    if slt == "OK"
                        app.CheckBox_LongTermOn.Value = false;
                    else
                        app.CheckBox_LocalOn.Value = false;
                    end
                end
            end

            UpdateRegProgram(app);

            UpdateChannelState(app);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_RegTform_Manual
        function DropDown_RegTform_ManualValueChanged(app, event)
            value = app.DropDown_RegTform_Manual.Value;
            
            switch value
                case "POLYNOMIAL"
                    app.reg_opts.ManualTform = "polynomial";
                    app.Spinner_PolyOrder.Enable = "on";
                    app.Spinner_WPN.Enable = "off";
                case "LWM"
                    app.reg_opts.ManualTform = "lwm";
                    app.Spinner_WPN.Enable = "on";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.calling_app.GenSysMsg(["LOCAL_WEIGHTED_CONTROL",""]);
                otherwise
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
                    switch value
                        case "SIMILARITY"
                            app.reg_opts.ManualTform = "nonreflectivesimilarity";
                        case "AFFINE"
                            app.reg_opts.ManualTform = "affine";
                        case "PROJECTION"
                            app.reg_opts.ManualTform = "projective";
                        case "PWL"
                            app.reg_opts.ManualTform = "pwl";
                        otherwise
                    end
            end

            UpdateMinCPN(app);
            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: Spinner_PolyOrder
        function Spinner_PolyOrderValueChanged(app, event)
            value = app.Spinner_PolyOrder.Value;
            app.reg_opts.Degree = value;

            UpdateMinCPN(app);
            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: Spinner_WPN
        function Spinner_WPNValueChanged(app, event)
            value = app.Spinner_WPN.Value;
            app.reg_opts.Nlwm = value;

            UpdateMinCPN(app);
            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Selection changed function: ButtonGroup_Smooth
        function ButtonGroup_SmoothSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_Smooth.SelectedObject;
            
            switch selectedButton
                case app.Button_Smooth_Manual_Y
                    app.reg_opts.ManualEdgeSmooth = true;
                case app.Button_Smooth_Manual_N
                    app.reg_opts.ManualEdgeSmooth = false;
                otherwise
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_InterpAlg_Manual
        function DropDown_InterpAlg_ManualValueChanged(app, event)
            value = app.DropDown_InterpAlg_Manual.Value;
            
            app.reg_opts.ManualInterp = lower(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_TemplateSync
        function DropDown_TemplateSyncValueChanged(app, event)
            value = app.DropDown_TemplateSync.Value;
            
            app.template_sync = lower(value);

            app.calling_app.SetRegParams(app.reg_opts, app.template_sync);
        end

        % Close request function: SettingUIFigure
        function SettingUIFigureCloseRequest(app, event)
            % Set the registration options
            app.calling_app.SetRegParams(app.reg_opts, app.template_sync);

            delete(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            switch selectedTab
                case app.Tab_ManualReg
                    if app.reg_opts.RegType ~= "manual"
                       app.calling_app.GenSysMsg(["REGMODE_CURRENT_MANUAL",""]);
                    end
                    app.reg_opts.RegType = "manual";
                    app.calling_app.SetRegParams(app.reg_opts);
                case app.Tab_AutoReg_LongTerm
                    if app.reg_opts.RegType ~= "longterm"
                       app.calling_app.GenSysMsg(["REGMODE_CURRENT_LONGTERM",""]);
                    end
                    app.reg_opts.RegType = "longterm";
                    app.calling_app.SetRegParams(app.reg_opts);
                otherwise
                    if app.reg_opts.RegType ~= "auto"
                        app.calling_app.GenSysMsg(["REGMODE_CURRENT_AUTO",""]);
                    end
                    app.reg_opts.RegType = "auto";
                    app.calling_app.SetRegParams(app.reg_opts);
            end 
        end

        % Value changed function: EditField_RegTemplate_LongTerm
        function EditField_RegTemplate_LongTermValueChanged(app, event)
            value = app.EditField_RegTemplate_LongTerm.Value;
            
            app.reg_opts.volfixed.G(2) = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_RegSampling_LongTerm
        function DropDown_RegSampling_LongTermValueChanged(app, event)
            value = app.DropDown_RegSampling_LongTerm.Value;
            
            app.reg_opts.volfixed.G(1) = lower(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_KeyFrame
        function EditField_KeyFrameValueChanged(app, event)
            value = app.EditField_KeyFrame.Value;
            
            app.reg_opts.KeyFrames = string(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: CheckBox_AutoKeyFrame
        function CheckBox_AutoKeyFrameValueChanged(app, event)
            value = app.CheckBox_AutoKeyFrame.Value;
            
            if value == true
                app.EditField_KeyFrame.Enable = "off";
                app.reg_opts.KeyFrames = "auto";
            else
                app.EditField_KeyFrame.Enable = "on";
                app.reg_opts.KeyFrames = string(app.EditField_KeyFrame.Value);
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_Marker_LongTerm
        function DropDown_Marker_LongTermValueChanged(app, event)
            value = app.DropDown_Marker_LongTerm.Value;
            
            switch value
                case "RED"
                    app.reg_opts.Chla = "r";
                case "GREEN"
                    app.reg_opts.Chla = "g";
                otherwise
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_Signal_LongTerm
        function DropDown_Signal_LongTermValueChanged(app, event)
            value = app.DropDown_Signal_LongTerm.Value;
            
            switch value
                case "RED"
                    app.reg_opts.Chls = "r";
                case "GREEN"
                    app.reg_opts.Chls = "g";
                otherwise
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_IntensityThreshold_LongTerm
        function EditField_IntensityThreshold_LongTermValueChanged(app, event)
            value = app.EditField_IntensityThreshold_LongTerm.Value;
            
            app.reg_opts.IntensityThreshold = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_ScaleThreshold_LongTerm
        function EditField_ScaleThreshold_LongTermValueChanged(app, event)
            value = app.EditField_ScaleThreshold_LongTerm.Value;
            
            app.reg_opts.ScaleThreshold = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_DownSampling_LongTerm_PC
        function DropDown_DownSampling_LongTerm_PCValueChanged(app, event)
            value = app.DropDown_DownSampling_LongTerm_PC.Value;
            
            switch value
                case "GA"
                    app.Label_38.Text = "网格步长";
                case "RAND"
                    app.Label_38.Text = "百分比";
                case "NU"
                    app.Label_38.Text = "最大数量";
                otherwise
                    app.Label_38.Text = "参数值";
            end

            app.reg_opts.DS_PointCloud = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_DownSampling_LongTerm_Param
        function EditField_DownSampling_LongTerm_ParamValueChanged(app, event)
            value = app.EditField_DownSampling_LongTerm_Param.Value;
            
            app.reg_opts.DS_PointCloud_Param = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_OutlierRatio
        function EditField_OutlierRatioValueChanged(app, event)
            value = app.EditField_OutlierRatio.Value;
            
            app.reg_opts.OutlierRatio = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_MaxIterN_LongTerm_PC
        function EditField_MaxIterN_LongTerm_PCValueChanged(app, event)
            value = app.EditField_MaxIterN_LongTerm_PC.Value;
            
            app.reg_opts.MaxIterN_PointCloud = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_ErrorThreshold
        function EditField_ErrorThresholdValueChanged(app, event)
            value = app.EditField_ErrorThreshold.Value;
            app.reg_opts.ErrorLimit = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: CheckBox_LongTerm_Review
        function CheckBox_LongTerm_ReviewValueChanged(app, event)
            value = app.CheckBox_LongTerm_Review.Value;
            
            if value == true
                % append points cloud registration preview tab

            else
                % remove points cloud registration preview tab

            end
        end

        % Value changed function: EditField_MinStep_LongTerm
        function EditField_MinStep_LongTermValueChanged(app, event)
            value = app.EditField_MinStep_LongTerm.Value;
            app.reg_opts.app.reg_opts.MinStep_LongTerm = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_DownSampling_LongTerm_Voxel
        function DropDown_DownSampling_LongTerm_VoxelValueChanged(app, event)
            value = app.DropDown_DownSampling_LongTerm_Voxel.Value;
            switch value
                case "OFF"
                    app.reg_opts.DS_Voxel = "1x1";
                otherwise
                    app.reg_opts.DS_Voxel = value;
            end

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_InitStep_LongTerm
        function EditField_InitStep_LongTermValueChanged(app, event)
            value = app.EditField_InitStep_LongTerm.Value;
            app.reg_opts.InitStep_LongTerm = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_IterCoeff_LongTerm
        function EditField_IterCoeff_LongTermValueChanged(app, event)
            value = app.EditField_IterCoeff_LongTerm.Value;
            app.reg_opts.IterCoeff_LongTerm = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: EditField_MaxIterN_LongTerm_Voxel
        function EditField_MaxIterN_LongTerm_VoxelValueChanged(app, event)
            value = app.EditField_MaxIterN_LongTerm_Voxel.Value;
            app.reg_opts.MaxIterN_Voxel = str2num(value); %#ok<ST2NM>

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: CheckBox_PhotonCompOn_LongTerm
        function CheckBox_PhotonCompOn_LongTermValueChanged(app, event)
            value = app.CheckBox_PhotonCompOn_LongTerm.Value;
            
            if value == true
                app.DropDown_PhotonCompAcc_LongTerm.Enable = "on";
            else
                app.DropDown_PhotonCompAcc_LongTerm.Enable = "off";
            end
        end

        % Value changed function: EditField_AFS_LongTerm
        function EditField_AFS_LongTermValueChanged(app, event)
            value = app.EditField_AFS_LongTerm.Value;
            app.reg_opts.AFS_LongTerm = value;

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: DropDown_PhotonCompAcc_LongTerm
        function DropDown_PhotonCompAcc_LongTermValueChanged(app, event)
            value = app.DropDown_PhotonCompAcc_LongTerm.Value;
            app.reg_opts.CompAcc_LongTerm = str2double(value);

            app.calling_app.SetRegParams(app.reg_opts);
        end

        % Value changed function: CheckBox_LongTermOn
        function CheckBox_LongTermOnValueChanged(app, event)
            value = app.CheckBox_LongTermOn.Value;
            
            if value == true
                if app.CheckBox_GlobalOn.Value || app.CheckBox_LocalOn.Value
                    slt = uiconfirm(app.SettingUIFigure, ...
                        app.langs_mapping.LONG_TERM_NORMAL_CONFLICT, ...
                        app.langs_mapping.WARNING, ...
                        "Icon","warning");
                    if slt == "OK"
                        app.CheckBox_GlobalOn.Value = false;
                        app.CheckBox_LocalOn.Value = false;
                    else
                        app.CheckBox_LongTermOn.Value = false;
                    end
                end
            end

            UpdateRegProgram(app);
            app.calling_app.SetRegParams(app.reg_opts);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SettingUIFigure = uifigure('Visible', 'off');
            app.SettingUIFigure.Name = 'Setting';
            app.SettingUIFigure.Icon = 'setting.png';
            app.SettingUIFigure.CloseRequestFcn = createCallbackFcn(app, @SettingUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.SettingUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab_AutoReg_Global = uitab(app.TabGroup);
            app.Tab_AutoReg_Global.Tooltip = {'基于相位及强度的全自动刚体配准方案'};
            app.Tab_AutoReg_Global.Title = '自动配准(全局)';
            app.Label = uilabel(app.Tab_AutoReg_Global);
            app.Label.Text = '采样';
            app.DropDown_RegSampling_Global = uidropdown(app.Tab_AutoReg_Global);
            app.DropDown_RegSampling_Global.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_RegSampling_Global.ItemsData = {'MEAN', 'MEDIAN', 'MIN', 'MAX'};
            app.DropDown_RegSampling_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegSampling_GlobalValueChanged, true);
            app.DropDown_RegSampling_Global.Tooltip = {'帧运算'};
            app.DropDown_RegSampling_Global.Value = 'MEAN';
            app.Label_2 = uilabel(app.Tab_AutoReg_Global);
            app.Label_2.Text = '几何变换';
            app.DropDown_RegTform_Auto = uidropdown(app.Tab_AutoReg_Global);
            app.DropDown_RegTform_Auto.Items = {'平移', '刚体', '相似', '仿射'};
            app.DropDown_RegTform_Auto.ItemsData = {'TRANSLATION', 'RIGID', 'SIMILARITY', 'AFFINE'};
            app.DropDown_RegTform_Auto.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegTform_AutoValueChanged, true);
            app.DropDown_RegTform_Auto.Tooltip = {'几何变换类型'};
            app.DropDown_RegTform_Auto.Value = 'TRANSLATION';
            app.Label_3 = uilabel(app.Tab_AutoReg_Global);
            app.Label_3.Text = '定位';
            app.DropDown_Marker_Global = uidropdown(app.Tab_AutoReg_Global);
            app.DropDown_Marker_Global.Items = {'红', '绿'};
            app.DropDown_Marker_Global.ItemsData = {'RED', 'GREEN'};
            app.DropDown_Marker_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_Marker_GlobalValueChanged, true);
            app.DropDown_Marker_Global.Tooltip = {''};
            app.DropDown_Marker_Global.Value = 'RED';
            app.Label_4 = uilabel(app.Tab_AutoReg_Global);
            app.Label_4.Text = '信号';
            app.DropDown_Signal_Global = uidropdown(app.Tab_AutoReg_Global);
            app.DropDown_Signal_Global.Items = {'红', '绿'};
            app.DropDown_Signal_Global.ItemsData = {'RED', 'GREEN'};
            app.DropDown_Signal_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_Signal_GlobalValueChanged, true);
            app.DropDown_Signal_Global.Tooltip = {''};
            app.DropDown_Signal_Global.Value = 'GREEN';
            app.Label_5 = uilabel(app.Tab_AutoReg_Global);
            app.Label_5.Text = '最小迭代步长';
            app.EditField_MinStep = uieditfield(app.Tab_AutoReg_Global, 'numeric');
            app.EditField_MinStep.Limits = [0 Inf];
            app.EditField_MinStep.ValueDisplayFormat = '%e';
            app.EditField_MinStep.ValueChangedFcn = createCallbackFcn(app, @EditField_MinStepValueChanged, true);
            app.EditField_MinStep.Tooltip = {'收敛判别步长'};
            app.EditField_MinStep.Value = 0.0001;
            app.Label_6 = uilabel(app.Tab_AutoReg_Global);
            app.Label_6.Text = '初始迭代步长';
            app.EditField_InitStep = uieditfield(app.Tab_AutoReg_Global, 'numeric');
            app.EditField_InitStep.Limits = [0 Inf];
            app.EditField_InitStep.ValueDisplayFormat = '%e';
            app.EditField_InitStep.ValueChangedFcn = createCallbackFcn(app, @EditField_InitStepValueChanged, true);
            app.EditField_InitStep.Tooltip = {'起始步长'};
            app.EditField_InitStep.Value = 0.1;
            app.Label_7 = uilabel(app.Tab_AutoReg_Global);
            app.Label_7.Text = '迭代数上限';
            app.EditField_MaxIterN_Global = uieditfield(app.Tab_AutoReg_Global, 'numeric');
            app.EditField_MaxIterN_Global.Limits = [1 Inf];
            app.EditField_MaxIterN_Global.ValueDisplayFormat = '%.0f';
            app.EditField_MaxIterN_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_GlobalValueChanged, true);
            app.EditField_MaxIterN_Global.Tooltip = {'最大迭代次数'};
            app.EditField_MaxIterN_Global.Value = 50;
            app.Label_8 = uilabel(app.Tab_AutoReg_Global);
            app.Label_8.Text = '迭代因子';
            app.EditField_IterCoeff = uieditfield(app.Tab_AutoReg_Global, 'numeric');
            app.EditField_IterCoeff.Limits = [0 1];
            app.EditField_IterCoeff.ValueDisplayFormat = '%.3f';
            app.EditField_IterCoeff.ValueChangedFcn = createCallbackFcn(app, @EditField_IterCoeffValueChanged, true);
            app.EditField_IterCoeff.Tooltip = {''};
            app.EditField_IterCoeff.Value = 0.5;
            app.Spinner_VPL_Global = uispinner(app.Tab_AutoReg_Global);
            app.Spinner_VPL_Global.Limits = [2 4];
            app.Spinner_VPL_Global.ValueDisplayFormat = '%.0f';
            app.Spinner_VPL_Global.ValueChangedFcn = createCallbackFcn(app, @Spinner_VPL_GlobalValueChanged, true);
            app.Spinner_VPL_Global.Tooltip = {'一个体积内拥有的层数越多，需要越大的金字塔因子提高配准精度，通常为3'};
            app.Spinner_VPL_Global.Value = 3;
            app.Label_VPL_Global = uilabel(app.Tab_AutoReg_Global);
            app.Label_VPL_Global.Text = '金字塔因子';
            app.Label_9 = uilabel(app.Tab_AutoReg_Global);
            app.Label_9.Text = '插值';
            app.DropDown_InterpAlg_Auto_Global = uidropdown(app.Tab_AutoReg_Global);
            app.DropDown_InterpAlg_Auto_Global.Items = {'最近邻(快速)', '线性', '立方(高质量)'};
            app.DropDown_InterpAlg_Auto_Global.ItemsData = {'NEAREST', 'LINEAR', 'CUBIC'};
            app.DropDown_InterpAlg_Auto_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_InterpAlg_Auto_GlobalValueChanged, true);
            app.DropDown_InterpAlg_Auto_Global.Tooltip = {'图像插值算法'};
            app.DropDown_InterpAlg_Auto_Global.Value = 'CUBIC';
            app.Image_CPU = uiimage(app.Tab_AutoReg_Global);
            app.Image_CPU.ImageClickedFcn = createCallbackFcn(app, @Image_CPUClicked, true);
            app.Image_CPU.Visible = 'off';
            app.Image_CPU.Tooltip = {'CPU状态'};
            app.CheckBox_GlobalOn = uicheckbox(app.Tab_AutoReg_Global);
            app.CheckBox_GlobalOn.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GlobalOnValueChanged, true);
            app.CheckBox_GlobalOn.Tooltip = {'启用刚体配准'};
            app.CheckBox_GlobalOn.Text = '启用';
            app.Label_10 = uilabel(app.Tab_AutoReg_Global);
            app.Label_10.Text = '模态';
            app.DropDown_RegModal = uidropdown(app.Tab_AutoReg_Global);
            app.DropDown_RegModal.Items = {'单模态', '多模态'};
            app.DropDown_RegModal.ItemsData = {'MONOMODAL', 'MULTIMODAL'};
            app.DropDown_RegModal.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegModalValueChanged, true);
            app.DropDown_RegModal.Tooltip = {'采集设备属性'};
            app.DropDown_RegModal.Value = 'MONOMODAL';
            app.Label_11 = uilabel(app.Tab_AutoReg_Global);
            app.Label_11.Text = '配准模板';
            app.EditField_RegTemplate_Global = uieditfield(app.Tab_AutoReg_Global, 'text');
            app.EditField_RegTemplate_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_RegTemplate_GlobalValueChanged, true);
            app.EditField_RegTemplate_Global.Value = '1';
            app.Label_12 = uilabel(app.Tab_AutoReg_Global);
            app.Label_12.Text = '鲁棒因子';
            app.EditField_RobustCoeff = uieditfield(app.Tab_AutoReg_Global, 'numeric');
            app.EditField_RobustCoeff.Limits = [0 1];
            app.EditField_RobustCoeff.ValueDisplayFormat = '%.2f';
            app.EditField_RobustCoeff.ValueChangedFcn = createCallbackFcn(app, @EditField_RobustCoeffValueChanged, true);
            app.EditField_RobustCoeff.Tooltip = {'配准的抗噪水平'};
            app.EditField_RobustCoeff.Value = 0.5;
            app.Label_13 = uilabel(app.Tab_AutoReg_Global);
            app.Label_13.Text = '降采样';
            app.DropDown_DownSampling = uidropdown(app.Tab_AutoReg_Global);
            app.DropDown_DownSampling.Items = {'自动', '2X2', '3X3', '关闭'};
            app.DropDown_DownSampling.ItemsData = {'AUTO', '2X2', '3X3', 'CLOSE'};
            app.DropDown_DownSampling.ValueChangedFcn = createCallbackFcn(app, @DropDown_DownSamplingValueChanged, true);
            app.DropDown_DownSampling.Tooltip = {'加速配准并降低资源占用，但会降低配准精度'};
            app.DropDown_DownSampling.Value = 'AUTO';
            app.Tab_AutoReg_Local = uitab(app.TabGroup);
            app.Tab_AutoReg_Local.Tooltip = {'基于位移场估计的全自动非刚体配准方案'};
            app.Tab_AutoReg_Local.Title = '自动配准(局部)';
            app.Label_14 = uilabel(app.Tab_AutoReg_Local);
            app.Label_14.Text = '采样';
            app.DropDown_RegSampling_Local = uidropdown(app.Tab_AutoReg_Local);
            app.DropDown_RegSampling_Local.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_RegSampling_Local.ItemsData = {'MEAN', 'MEDIAN', 'NIN', 'MAX'};
            app.DropDown_RegSampling_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegSampling_LocalValueChanged, true);
            app.DropDown_RegSampling_Local.Tooltip = {'帧运算'};
            app.DropDown_RegSampling_Local.Value = 'MEAN';
            app.Label_15 = uilabel(app.Tab_AutoReg_Local);
            app.Label_15.Text = '定位';
            app.DropDown_Marker_Local = uidropdown(app.Tab_AutoReg_Local);
            app.DropDown_Marker_Local.Items = {'红', '绿'};
            app.DropDown_Marker_Local.ItemsData = {'RED', 'GREEN'};
            app.DropDown_Marker_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_Marker_LocalValueChanged, true);
            app.DropDown_Marker_Local.Value = 'RED';
            app.CheckBox_LocalOn = uicheckbox(app.Tab_AutoReg_Local);
            app.CheckBox_LocalOn.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LocalOnValueChanged, true);
            app.CheckBox_LocalOn.Tooltip = {'启用非刚体配准'};
            app.CheckBox_LocalOn.Text = '启用';
            app.Image_GPU = uiimage(app.Tab_AutoReg_Local);
            app.Image_GPU.ImageClickedFcn = createCallbackFcn(app, @Image_GPUClicked, true);
            app.Image_GPU.Visible = 'off';
            app.Image_GPU.Tooltip = {'GPU状态'};
            app.Label_16 = uilabel(app.Tab_AutoReg_Local);
            app.Label_16.Text = '插值';
            app.DropDown_InterpAlg_Auto_Local = uidropdown(app.Tab_AutoReg_Local);
            app.DropDown_InterpAlg_Auto_Local.Items = {'最近邻(快速)', '线性', '立方(高质量)'};
            app.DropDown_InterpAlg_Auto_Local.ItemsData = {'NEAREST', 'LINEAR', 'CUBIC'};
            app.DropDown_InterpAlg_Auto_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_InterpAlg_Auto_LocalValueChanged, true);
            app.DropDown_InterpAlg_Auto_Local.Tooltip = {'图像插值算法'};
            app.DropDown_InterpAlg_Auto_Local.Value = 'CUBIC';
            app.Spinner_VPL_Local = uispinner(app.Tab_AutoReg_Local);
            app.Spinner_VPL_Local.Limits = [2 4];
            app.Spinner_VPL_Local.ValueDisplayFormat = '%.0f';
            app.Spinner_VPL_Local.ValueChangedFcn = createCallbackFcn(app, @Spinner_VPL_LocalValueChanged, true);
            app.Spinner_VPL_Local.Tooltip = {'一个体积内拥有的层数越多，需要更大的金字塔因子，通常为3'};
            app.Spinner_VPL_Local.Value = 3;
            app.Label_VPL_Local = uilabel(app.Tab_AutoReg_Local);
            app.Label_VPL_Local.Text = '金字塔因子';
            app.Label_17 = uilabel(app.Tab_AutoReg_Local);
            app.Label_17.Text = '累积场平滑';
            app.EditField_AFS = uieditfield(app.Tab_AutoReg_Local, 'numeric');
            app.EditField_AFS.Limits = [0.5 3];
            app.EditField_AFS.ValueDisplayFormat = '%.2f';
            app.EditField_AFS.ValueChangedFcn = createCallbackFcn(app, @EditField_AFSValueChanged, true);
            app.EditField_AFS.Tooltip = {'数值越小，局部非刚性形变越强'};
            app.EditField_AFS.Value = 1;
            app.Label_18 = uilabel(app.Tab_AutoReg_Local);
            app.Label_18.Text = '信号';
            app.DropDown_Signal_Local = uidropdown(app.Tab_AutoReg_Local);
            app.DropDown_Signal_Local.Items = {'红', '绿'};
            app.DropDown_Signal_Local.ItemsData = {'RED', 'GREEN'};
            app.DropDown_Signal_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_Signal_LocalValueChanged, true);
            app.DropDown_Signal_Local.Value = 'GREEN';
            app.Label_19 = uilabel(app.Tab_AutoReg_Local);
            app.Label_19.Text = '精度';
            app.DropDown_PhotonCompAcc = uidropdown(app.Tab_AutoReg_Local);
            app.DropDown_PhotonCompAcc.Items = {'512', '1024', '2048', '4096'};
            app.DropDown_PhotonCompAcc.ItemsData = {'512', '1024', '2048', '4096'};
            app.DropDown_PhotonCompAcc.ValueChangedFcn = createCallbackFcn(app, @DropDown_PhotonCompAccValueChanged, true);
            app.DropDown_PhotonCompAcc.Tooltip = {'淬灭补偿精度, 更高的精度代价是内存的消耗'};
            app.DropDown_PhotonCompAcc.Value = '1024';
            app.CheckBox_PhotonCompOn = uicheckbox(app.Tab_AutoReg_Local);
            app.CheckBox_PhotonCompOn.ValueChangedFcn = createCallbackFcn(app, @CheckBox_PhotonCompOnValueChanged, true);
            app.CheckBox_PhotonCompOn.Tooltip = {'如果定位通道亮度变化较明显，建议开启淬灭补偿'};
            app.CheckBox_PhotonCompOn.Text = '补偿光漂白';
            app.Label_20 = uilabel(app.Tab_AutoReg_Local);
            app.Label_20.Text = '迭代数上限';
            app.EditField_MaxIterN_Local = uieditfield(app.Tab_AutoReg_Local, 'text');
            app.EditField_MaxIterN_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_LocalValueChanged, true);
            app.EditField_MaxIterN_Local.Tooltip = {'最大迭代次数，可以设置不同金字塔层的迭代上限'};
            app.EditField_MaxIterN_Local.Placeholder = '标量或数组';
            app.EditField_MaxIterN_Local.Value = '100';
            app.Label_21 = uilabel(app.Tab_AutoReg_Local);
            app.Label_21.Text = '配准模板';
            app.EditField_RegTemplate_Local = uieditfield(app.Tab_AutoReg_Local, 'text');
            app.EditField_RegTemplate_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_RegTemplate_LocalValueChanged, true);
            app.EditField_RegTemplate_Local.Value = '1';
            app.Tab_AutoReg_LongTerm = uitab(app.TabGroup);
            app.Tab_AutoReg_LongTerm.Title = '自动配准(长时程)';
            app.CheckBox_LongTermOn = uicheckbox(app.Tab_AutoReg_LongTerm);
            app.CheckBox_LongTermOn.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LongTermOnValueChanged, true);
            app.CheckBox_LongTermOn.Tooltip = {'启用刚体配准'};
            app.CheckBox_LongTermOn.Text = '启用';
            app.Label_29 = uilabel(app.Tab_AutoReg_LongTerm);
            app.Label_29.Text = '关键帧';
            app.EditField_KeyFrame = uieditfield(app.Tab_AutoReg_LongTerm, 'text');
            app.EditField_KeyFrame.ValueChangedFcn = createCallbackFcn(app, @EditField_KeyFrameValueChanged, true);
            app.EditField_KeyFrame.Placeholder = '1,101,201,301,401,...';
            app.Label_30 = uilabel(app.Tab_AutoReg_LongTerm);
            app.Label_30.Text = '采样';
            app.DropDown_RegSampling_LongTerm = uidropdown(app.Tab_AutoReg_LongTerm);
            app.DropDown_RegSampling_LongTerm.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_RegSampling_LongTerm.ItemsData = {'MEAN', 'MEDIAN', 'NIN', 'MAX'};
            app.DropDown_RegSampling_LongTerm.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegSampling_LongTermValueChanged, true);
            app.DropDown_RegSampling_LongTerm.Tooltip = {'帧运算'};
            app.DropDown_RegSampling_LongTerm.Value = 'MEAN';
            app.Label_31 = uilabel(app.Tab_AutoReg_LongTerm);
            app.Label_31.Text = '配准模板';
            app.EditField_RegTemplate_LongTerm = uieditfield(app.Tab_AutoReg_LongTerm, 'text');
            app.EditField_RegTemplate_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_RegTemplate_LongTermValueChanged, true);
            app.EditField_RegTemplate_LongTerm.Value = '1';
            app.CheckBox_AutoKeyFrame = uicheckbox(app.Tab_AutoReg_LongTerm);
            app.CheckBox_AutoKeyFrame.ValueChangedFcn = createCallbackFcn(app, @CheckBox_AutoKeyFrameValueChanged, true);
            app.CheckBox_AutoKeyFrame.Text = '自动';
            app.Label_32 = uilabel(app.Tab_AutoReg_LongTerm);
            app.Label_32.Text = '定位';
            app.DropDown_Marker_LongTerm = uidropdown(app.Tab_AutoReg_LongTerm);
            app.DropDown_Marker_LongTerm.Items = {'红', '绿'};
            app.DropDown_Marker_LongTerm.ItemsData = {'RED', 'GREEN'};
            app.DropDown_Marker_LongTerm.ValueChangedFcn = createCallbackFcn(app, @DropDown_Marker_LongTermValueChanged, true);
            app.DropDown_Marker_LongTerm.Tooltip = {''};
            app.DropDown_Marker_LongTerm.Value = 'RED';
            app.Label_33 = uilabel(app.Tab_AutoReg_LongTerm);
            app.Label_33.Text = '信号';
            app.DropDown_Signal_LongTerm = uidropdown(app.Tab_AutoReg_LongTerm);
            app.DropDown_Signal_LongTerm.Items = {'红', '绿'};
            app.DropDown_Signal_LongTerm.ItemsData = {'RED', 'GREEN'};
            app.DropDown_Signal_LongTerm.ValueChangedFcn = createCallbackFcn(app, @DropDown_Signal_LongTermValueChanged, true);
            app.DropDown_Signal_LongTerm.Tooltip = {''};
            app.DropDown_Signal_LongTerm.Value = 'GREEN';
            app.TabGroup_LongTerm_Params = uitabgroup(app.Tab_AutoReg_LongTerm);
            app.Tab_PointCloud = uitab(app.TabGroup_LongTerm_Params);
            app.Tab_PointCloud.Title = '点云';
            app.Label_34 = uilabel(app.Tab_PointCloud);
            app.Label_34.Text = '强度下阈值';
            app.EditField_IntensityThreshold_LongTerm = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_IntensityThreshold_LongTerm.Limits = [0 100];
            app.EditField_IntensityThreshold_LongTerm.ValueDisplayFormat = '%.2f';
            app.EditField_IntensityThreshold_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_IntensityThreshold_LongTermValueChanged, true);
            app.EditField_IntensityThreshold_LongTerm.Value = 97;
            app.Label_36 = uilabel(app.Tab_PointCloud);
            app.Label_36.Text = '尺度阈值(μm)';
            app.EditField_ScaleThreshold_LongTerm = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_ScaleThreshold_LongTerm.Limits = [0.1 1000];
            app.EditField_ScaleThreshold_LongTerm.ValueDisplayFormat = '%.1f';
            app.EditField_ScaleThreshold_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_ScaleThreshold_LongTermValueChanged, true);
            app.EditField_ScaleThreshold_LongTerm.Value = 3;
            app.Label_37 = uilabel(app.Tab_PointCloud);
            app.Label_37.Text = '降采样';
            app.DropDown_DownSampling_LongTerm_PC = uidropdown(app.Tab_PointCloud);
            app.DropDown_DownSampling_LongTerm_PC.Items = {'网格平均', '随机', '非均匀'};
            app.DropDown_DownSampling_LongTerm_PC.ItemsData = {'GA', 'RAND', 'NU'};
            app.DropDown_DownSampling_LongTerm_PC.ValueChangedFcn = createCallbackFcn(app, @DropDown_DownSampling_LongTerm_PCValueChanged, true);
            app.DropDown_DownSampling_LongTerm_PC.Value = 'GA';
            app.Label_38 = uilabel(app.Tab_PointCloud);
            app.Label_38.Text = '网格步长';
            app.EditField_DownSampling_LongTerm_Param = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_DownSampling_LongTerm_Param.Limits = [0 Inf];
            app.EditField_DownSampling_LongTerm_Param.ValueChangedFcn = createCallbackFcn(app, @EditField_DownSampling_LongTerm_ParamValueChanged, true);
            app.EditField_DownSampling_LongTerm_Param.Value = 2;
            app.Label_39 = uilabel(app.Tab_PointCloud);
            app.Label_39.Text = '异常点比例';
            app.EditField_OutlierRatio = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_OutlierRatio.Limits = [0 1];
            app.EditField_OutlierRatio.ValueDisplayFormat = '%.2f';
            app.EditField_OutlierRatio.ValueChangedFcn = createCallbackFcn(app, @EditField_OutlierRatioValueChanged, true);
            app.EditField_OutlierRatio.Value = 0.1;
            app.Label_40 = uilabel(app.Tab_PointCloud);
            app.Label_40.Text = '迭代数上限';
            app.EditField_MaxIterN_LongTerm_PC = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_MaxIterN_LongTerm_PC.Limits = [1 Inf];
            app.EditField_MaxIterN_LongTerm_PC.ValueDisplayFormat = '%.0f';
            app.EditField_MaxIterN_LongTerm_PC.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_LongTerm_PCValueChanged, true);
            app.EditField_MaxIterN_LongTerm_PC.Tooltip = {'最大迭代次数'};
            app.EditField_MaxIterN_LongTerm_PC.Value = 50;
            app.Label_41 = uilabel(app.Tab_PointCloud);
            app.Label_41.Text = '全局误差限';
            app.EditField_ErrorThreshold = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_ErrorThreshold.Limits = [0 Inf];
            app.EditField_ErrorThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_ErrorThresholdValueChanged, true);
            app.EditField_ErrorThreshold.Tooltip = {'收敛判别步长'};
            app.EditField_ErrorThreshold.Value = 1e-05;
            app.CheckBox_LongTerm_Review = uicheckbox(app.Tab_PointCloud);
            app.CheckBox_LongTerm_Review.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LongTerm_ReviewValueChanged, true);
            app.CheckBox_LongTerm_Review.Text = '预览';
            app.Tab_Voxels = uitab(app.TabGroup_LongTerm_Params);
            app.Tab_Voxels.Title = '体素';
            app.Label_42 = uilabel(app.Tab_Voxels);
            app.Label_42.Text = '最小迭代步长';
            app.EditField_MinStep_LongTerm = uieditfield(app.Tab_Voxels, 'numeric');
            app.EditField_MinStep_LongTerm.Limits = [0 Inf];
            app.EditField_MinStep_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_MinStep_LongTermValueChanged, true);
            app.EditField_MinStep_LongTerm.Tooltip = {'收敛判别步长'};
            app.EditField_MinStep_LongTerm.Value = 1e-07;
            app.Label_43 = uilabel(app.Tab_Voxels);
            app.Label_43.Text = '初始迭代步长';
            app.EditField_InitStep_LongTerm = uieditfield(app.Tab_Voxels, 'numeric');
            app.EditField_InitStep_LongTerm.Limits = [0 Inf];
            app.EditField_InitStep_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_InitStep_LongTermValueChanged, true);
            app.EditField_InitStep_LongTerm.Tooltip = {'起始步长'};
            app.EditField_InitStep_LongTerm.Value = 0.0625;
            app.Label_45 = uilabel(app.Tab_Voxels);
            app.Label_45.Text = '迭代因子';
            app.EditField_IterCoeff_LongTerm = uieditfield(app.Tab_Voxels, 'numeric');
            app.EditField_IterCoeff_LongTerm.Limits = [0 1];
            app.EditField_IterCoeff_LongTerm.ValueDisplayFormat = '%.3f';
            app.EditField_IterCoeff_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_IterCoeff_LongTermValueChanged, true);
            app.EditField_IterCoeff_LongTerm.Tooltip = {''};
            app.EditField_IterCoeff_LongTerm.Value = 0.5;
            app.Label_47 = uilabel(app.Tab_Voxels);
            app.Label_47.Text = '降采样';
            app.DropDown_DownSampling_LongTerm_Voxel = uidropdown(app.Tab_Voxels);
            app.DropDown_DownSampling_LongTerm_Voxel.Items = {'2X2', '3X3', '关闭'};
            app.DropDown_DownSampling_LongTerm_Voxel.ItemsData = {'2X2', '3X3', 'OFF'};
            app.DropDown_DownSampling_LongTerm_Voxel.ValueChangedFcn = createCallbackFcn(app, @DropDown_DownSampling_LongTerm_VoxelValueChanged, true);
            app.DropDown_DownSampling_LongTerm_Voxel.Tooltip = {'加速配准并降低资源占用，但会降低配准精度'};
            app.DropDown_DownSampling_LongTerm_Voxel.Value = '2X2';
            app.CheckBox_PhotonCompOn_LongTerm = uicheckbox(app.Tab_Voxels);
            app.CheckBox_PhotonCompOn_LongTerm.ValueChangedFcn = createCallbackFcn(app, @CheckBox_PhotonCompOn_LongTermValueChanged, true);
            app.CheckBox_PhotonCompOn_LongTerm.Tooltip = {'如果定位通道亮度变化较明显，建议开启淬灭补偿'};
            app.CheckBox_PhotonCompOn_LongTerm.Text = '补偿光漂白';
            app.Label_48 = uilabel(app.Tab_Voxels);
            app.Label_48.Text = '累积场平滑';
            app.EditField_AFS_LongTerm = uieditfield(app.Tab_Voxels, 'numeric');
            app.EditField_AFS_LongTerm.Limits = [0.5 3];
            app.EditField_AFS_LongTerm.ValueDisplayFormat = '%.2f';
            app.EditField_AFS_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_AFS_LongTermValueChanged, true);
            app.EditField_AFS_LongTerm.Tooltip = {'数值越小，局部非刚性形变越强'};
            app.EditField_AFS_LongTerm.Value = 1;
            app.Label_49 = uilabel(app.Tab_Voxels);
            app.Label_49.Text = '精度';
            app.DropDown_PhotonCompAcc_LongTerm = uidropdown(app.Tab_Voxels);
            app.DropDown_PhotonCompAcc_LongTerm.Items = {'512', '1024', '2048', '4096'};
            app.DropDown_PhotonCompAcc_LongTerm.ItemsData = {'512', '1024', '2048', '4096'};
            app.DropDown_PhotonCompAcc_LongTerm.ValueChangedFcn = createCallbackFcn(app, @DropDown_PhotonCompAcc_LongTermValueChanged, true);
            app.DropDown_PhotonCompAcc_LongTerm.Tooltip = {'淬灭补偿精度, 更高的精度代价是内存的消耗'};
            app.DropDown_PhotonCompAcc_LongTerm.Value = '1024';
            app.Label_50 = uilabel(app.Tab_Voxels);
            app.Label_50.Text = '迭代数上限';
            app.EditField_MaxIterN_LongTerm_Voxel = uieditfield(app.Tab_Voxels, 'text');
            app.EditField_MaxIterN_LongTerm_Voxel.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_LongTerm_VoxelValueChanged, true);
            app.EditField_MaxIterN_LongTerm_Voxel.Tooltip = {'最大迭代次数，可以设置不同金字塔层的迭代上限'};
            app.EditField_MaxIterN_LongTerm_Voxel.Placeholder = '标量或数组';
            app.EditField_MaxIterN_LongTerm_Voxel.Value = '100,50,25';
            app.Tab_ManualReg = uitab(app.TabGroup);
            app.Tab_ManualReg.Tooltip = {'基于控制点方法的手动配准方案'};
            app.Tab_ManualReg.Title = '手动配准(全局)';
            app.Label_22 = uilabel(app.Tab_ManualReg);
            app.Label_22.Text = '几何变换';
            app.DropDown_RegTform_Manual = uidropdown(app.Tab_ManualReg);
            app.DropDown_RegTform_Manual.Items = {'非反射相似', '仿射', '投影', '分段线性', '多项式', '局部加权平均'};
            app.DropDown_RegTform_Manual.ItemsData = {'SIMILARITY', 'AFFINE', 'PROJECTION', 'PWL', 'POLYNOMIAL', 'LWM'};
            app.DropDown_RegTform_Manual.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegTform_ManualValueChanged, true);
            app.DropDown_RegTform_Manual.Tooltip = {'几何变换类型'};
            app.DropDown_RegTform_Manual.Value = 'SIMILARITY';
            app.Label_23 = uilabel(app.Tab_ManualReg);
            app.Label_23.Text = '最小控制量';
            app.EditField_CPNumber = uieditfield(app.Tab_ManualReg, 'numeric');
            app.EditField_CPNumber.Limits = [2 Inf];
            app.EditField_CPNumber.ValueDisplayFormat = '%.0f';
            app.EditField_CPNumber.Editable = 'off';
            app.EditField_CPNumber.Tooltip = {'控制点对数量的下限'};
            app.EditField_CPNumber.Value = 2;
            app.Label_24 = uilabel(app.Tab_ManualReg);
            app.Label_24.Text = '多项式次数';
            app.Spinner_PolyOrder = uispinner(app.Tab_ManualReg);
            app.Spinner_PolyOrder.Limits = [2 4];
            app.Spinner_PolyOrder.ValueDisplayFormat = '%.0f';
            app.Spinner_PolyOrder.ValueChangedFcn = createCallbackFcn(app, @Spinner_PolyOrderValueChanged, true);
            app.Spinner_PolyOrder.Tooltip = {'多项式变换参数'};
            app.Spinner_PolyOrder.Value = 3;
            app.Label_25 = uilabel(app.Tab_ManualReg);
            app.Label_25.Text = '加权点数';
            app.Spinner_WPN = uispinner(app.Tab_ManualReg);
            app.Spinner_WPN.Limits = [6 18];
            app.Spinner_WPN.ValueDisplayFormat = '%.0f';
            app.Spinner_WPN.ValueChangedFcn = createCallbackFcn(app, @Spinner_WPNValueChanged, true);
            app.Spinner_WPN.Tooltip = {'局部加权平均参数'};
            app.Spinner_WPN.Value = 12;
            app.Label_26 = uilabel(app.Tab_ManualReg);
            app.Label_26.Text = '插值';
            app.DropDown_InterpAlg_Manual = uidropdown(app.Tab_ManualReg);
            app.DropDown_InterpAlg_Manual.Items = {'最近邻(快速)', '线性', '立方(高质量)'};
            app.DropDown_InterpAlg_Manual.ItemsData = {'NEAREST', 'LINEAR', 'CUBIC'};
            app.DropDown_InterpAlg_Manual.ValueChangedFcn = createCallbackFcn(app, @DropDown_InterpAlg_ManualValueChanged, true);
            app.DropDown_InterpAlg_Manual.Tooltip = {'图像插值算法'};
            app.DropDown_InterpAlg_Manual.Value = 'CUBIC';
            app.ButtonGroup_Smooth = uibuttongroup(app.Tab_ManualReg);
            app.ButtonGroup_Smooth.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_SmoothSelectionChanged, true);
            app.ButtonGroup_Smooth.Tooltip = {'几何变换边界平滑，默认开启'};
            app.ButtonGroup_Smooth.Title = '边界平滑';
            app.Button_Smooth_Manual_Y = uiradiobutton(app.ButtonGroup_Smooth);
            app.Button_Smooth_Manual_Y.Text = '是';
            app.Button_Smooth_Manual_Y.Value = true;
            app.Button_Smooth_Manual_N = uiradiobutton(app.ButtonGroup_Smooth);
            app.Button_Smooth_Manual_N.Text = '否';
            app.Label_27 = uilabel(app.Tab_ManualReg);
            app.Label_27.Text = '模板同步';
            app.DropDown_TemplateSync = uidropdown(app.Tab_ManualReg);
            app.DropDown_TemplateSync.Items = {'自动配准(全局)', '自动配准(局部)'};
            app.DropDown_TemplateSync.ItemsData = {'GLOBAL', 'LOCAL'};
            app.DropDown_TemplateSync.ValueChangedFcn = createCallbackFcn(app, @DropDown_TemplateSyncValueChanged, true);
            app.DropDown_TemplateSync.Value = 'GLOBAL';
            % Show the figure after all components are created
            app.SettingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RegSetting_TC_GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SettingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SettingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SettingUIFigure)
        end
    end
end