classdef ResourceManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ResourceManagerUIFigure       matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        Tab_Preference                matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        GridLayout10                  matlab.ui.container.GridLayout
        Button_Cancel                 matlab.ui.control.Button
        Button_OK                     matlab.ui.control.Button
        Button_Apply                  matlab.ui.control.Button
        GridLayout6                   matlab.ui.container.GridLayout
        Panel_Options                 matlab.ui.container.Panel
        GridLayout_Options            matlab.ui.container.GridLayout
        DropDown_ImportSourcePreset   matlab.ui.control.DropDown
        Label_32                      matlab.ui.control.Label
        Label_30                      matlab.ui.control.Label
        GridLayout14                  matlab.ui.container.GridLayout
        Spinner_AutoSaveInterval      matlab.ui.control.Spinner
        Label_31                      matlab.ui.control.Label
        Switch_AutoSave               matlab.ui.control.Switch
        Label_29                      matlab.ui.control.Label
        Image_DataRootTooltip         matlab.ui.control.Image
        Image_DeveloperModeTooltip    matlab.ui.control.Image
        Switch_DeveloperMode          matlab.ui.control.Switch
        Label_28                      matlab.ui.control.Label
        Image_InformationCollectionTooltip  matlab.ui.control.Image
        Switch_InformationCollection  matlab.ui.control.Switch
        Label_27                      matlab.ui.control.Label
        GridLayout13                  matlab.ui.container.GridLayout
        Button_SelectTemporaryFileRootFolder  matlab.ui.control.Button
        EditField_CacheRootFolder     matlab.ui.control.EditField
        Label_26                      matlab.ui.control.Label
        Image_ExperimentalFeaturesTooltip  matlab.ui.control.Image
        Image_DataProtectionTooltip   matlab.ui.control.Image
        DropDown_TooltipsLevel        matlab.ui.control.DropDown
        Label_25                      matlab.ui.control.Label
        Image_CachePolicyTooltip      matlab.ui.control.Image
        Switch_PreprocessEvaluation   matlab.ui.control.Switch
        Label_24                      matlab.ui.control.Label
        GridLayout12                  matlab.ui.container.GridLayout
        Button_RequireCapacityMEM     matlab.ui.control.Button
        EditField_MemoryCacheSize     matlab.ui.control.NumericEditField
        Label_23                      matlab.ui.control.Label
        DropDown_StorageViewStyle     matlab.ui.control.DropDown
        Label_22                      matlab.ui.control.Label
        DropDown_CachePolicy          matlab.ui.control.DropDown
        Label_20                      matlab.ui.control.Label
        Switch_AutoTemplate           matlab.ui.control.Switch
        Label_19                      matlab.ui.control.Label
        Switch_SimpleStatistics       matlab.ui.control.Switch
        Label_18                      matlab.ui.control.Label
        DropDown_Language             matlab.ui.control.DropDown
        Label_17                      matlab.ui.control.Label
        PythonLabel                   matlab.ui.control.Label
        GridLayout11                  matlab.ui.container.GridLayout
        Button_SelectPython           matlab.ui.control.Button
        EditField_PyPath              matlab.ui.control.EditField
        Switch_DataProtected          matlab.ui.control.Switch
        Label_16                      matlab.ui.control.Label
        Switch_ExperimentalFeatures   matlab.ui.control.Switch
        Label_15                      matlab.ui.control.Label
        DropDown_UpdateChannel        matlab.ui.control.DropDown
        Label_13                      matlab.ui.control.Label
        DropDown_AutoUpdate           matlab.ui.control.DropDown
        Label_12                      matlab.ui.control.Label
        DropDown_MessageLevel         matlab.ui.control.DropDown
        Label_10                      matlab.ui.control.Label
        DropDown_Style                matlab.ui.control.DropDown
        Label_9                       matlab.ui.control.Label
        Spinner_GPU_Protected         matlab.ui.control.Spinner
        GPULabel                      matlab.ui.control.Label
        Spinner_CPU_Protected         matlab.ui.control.Spinner
        CPULabel                      matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        GridLayout9                   matlab.ui.container.GridLayout
        Button_RequireCapacityHDD     matlab.ui.control.Button
        EditField_HardDriveCacheSize  matlab.ui.control.NumericEditField
        GridLayout8                   matlab.ui.container.GridLayout
        DropDown_ProgressBarColor     matlab.ui.control.DropDown
        EditField_ProgressBarColor    matlab.ui.control.EditField
        Label_4                       matlab.ui.control.Label
        Tree_OptionGroups             matlab.ui.container.Tree
        Node_Appearance               matlab.ui.container.TreeNode
        Node_Style                    matlab.ui.container.TreeNode
        Node_ImageToolbar             matlab.ui.container.TreeNode
        Node_DataManagement           matlab.ui.container.TreeNode
        Node_ProjectManagement        matlab.ui.container.TreeNode
        Node_TemporaryFile            matlab.ui.container.TreeNode
        Node_ParallelComputing        matlab.ui.container.TreeNode
        Node_Others                   matlab.ui.container.TreeNode
        Node_Notification             matlab.ui.container.TreeNode
        Node_DataSecurity             matlab.ui.container.TreeNode
        Node_ExternalLibraryPath      matlab.ui.container.TreeNode
        Node_SystemAndUpdate          matlab.ui.container.TreeNode
        Tab_TaskNetwork               matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        Panel_TaskNetworkTopology     matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        UIAxes_TaskNetwork            matlab.ui.control.UIAxes
        Panel_TaskTable               matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        UITable_TaskView              matlab.ui.control.Table
        Tab_ProjectManagement         matlab.ui.container.Tab
        GridLayout15                  matlab.ui.container.GridLayout
        Panel_Project                 matlab.ui.container.Panel
        GridLayout17                  matlab.ui.container.GridLayout
        UITable_Project               matlab.ui.control.Table
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu_DeleteProject            matlab.ui.container.Menu
    end

    properties (Constant)
        VER = "1.0.0"
    end

    properties (Constant, Hidden)
        GRID_ROW_HEIGHT = 25
        FEATURES_NUMBER = 25
        NRSCPHN = 4              % non-resource place holders number
    end

    properties (Access = private)
        caller      (1,1)                       % the caller
        aopts       (1,1)   struct              % app profile options
        popts       (1,1)   struct              % project options
        nwdaemon    (1,1)                       % the task network (appearance) refresh daemon timer
        rsctb       (:,:)   table               % the resource table
        nwview      (1,1)   logical = false     % the network view flag
        tmpfolders  (:,1)   string  = []        % the temporary files folder
        langmap     (1,1)   struct              % mapping for language
    end

    methods(Access=?Register)
        
        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr > 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout8.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout12.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout9.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout10.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout11.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout13.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout14.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout15.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Tree_OptionGroups.BackgroundColor = constdef.GRAYSCALE_AXES_LIGHT;
                    app.Panel_Options.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_Options.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.UITable_TaskView.BackgroundColor = constdef.GRAYSCALE_AXES_LIGHT;
                    app.UIAxes_TaskNetwork.Color = constdef.GRAYSCALE_AXES_LIGHT;
                case "DARK"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout8.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout12.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout9.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout10.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout11.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout13.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout14.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout15.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Tree_OptionGroups.BackgroundColor = constdef.GRAYSCALE_AXES_DARK;
                    app.Panel_Options.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_Options.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.UITable_TaskView.BackgroundColor = constdef.GRAYSCALE_AXES_DARK;
                    app.UIAxes_TaskNetwork.Color = constdef.GRAYSCALE_AXES_DARK;
                otherwise
            end
        end

        function RefreshPanelLanguage(app)
            % get language map
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            % apply languages
            app.Tab_Preference.Title = app.langmap.PREFERENCE;
            app.Tab_TaskNetwork.Title = app.langmap.TASK_NETWORK;
            app.Tab_ProjectManagement.Title = app.langmap.PROJECT_MANAGEMENT;
            app.Panel_Options.Title = app.langmap.OPTION;
            app.Panel_TaskTable.Title = app.langmap.TASK_TABLE;
            app.Panel_TaskNetworkTopology.Title = app.langmap.NETWORK_TOPOLOGY;
            app.Panel_Project.Title = app.langmap.MY_PROJECT;
            app.UITable_TaskView.ColumnName = [app.langmap.USER, app.langmap.STATUS, ...
                app.langmap.PROGRESS, app.langmap.TIME_COST, "#CPU", "#GPU"];
            app.UITable_Project.ColumnName = [app.langmap.PROJECT_NAME, ...
                app.langmap.PROJECT_PATH, app.langmap.MODIFIED_DATE, ...
                app.langmap.PROJECT_SIZE];
            app.Node_Appearance.Text = app.langmap.APPEARANCE;
            app.Node_Style.Text = app.langmap.STYLE;
            app.Node_ImageToolbar.Text = app.langmap.IMAGE_TOOLBAR;
            app.Node_DataManagement.Text = app.langmap.DATA_MANAGEMENT;
            app.Node_TemporaryFile.Text = app.langmap.TEMPORARY_FILE;
            app.Node_ParallelComputing.Text = app.langmap.PARALLEL_COMPUTING;
            app.Node_Others.Text = app.langmap.OTHER_SETTINGS;
            app.Node_Notification.Text = app.langmap.NOTIFICATION;
            app.Node_DataSecurity.Text = app.langmap.DATA_SECURITY;
            app.Node_ExternalLibraryPath.Text = app.langmap.EXTERNAL_PATH;
            app.Node_SystemAndUpdate.Text = app.langmap.SYS_UPDATE;

            app.Label_17.Text = app.langmap.LANGUAGE;
            app.Label_9.Text = app.langmap.STYLE;
            app.Label_4.Text = app.langmap.PROGRESSBAR_COLOR;
            app.Label_18.Text = app.langmap.SIMPLE_STAT;
            app.Label_19.Text = app.langmap.AUTO_TEMPLATE;
            app.Label_6.Text = app.langmap.CAPACITY_HDD;
            app.Label_23.Text = app.langmap.CAPACITY_MEM;
            app.CPULabel.Text = app.langmap.PROTECTED_NUMBER_CPU;
            app.GPULabel.Text = app.langmap.PROTECTED_NUMBER_GPU;
            app.Label_10.Text = app.langmap.MESSAGE_LEVEL;
            app.Label_16.Text = app.langmap.DATA_PROTECTED;
            app.PythonLabel.Text = app.langmap.PYTHON_INTERPRETER;
            app.Label_12.Text = app.langmap.AUTO_UPDATE;
            app.Label_13.Text = app.langmap.CHANNEL_UPDATE;
            app.Label_15.Text = app.langmap.EXPERIMENTAL_FEATURE;
            app.Label_20.Text = app.langmap.CACHE_POLICY;
            app.Label_22.Text = app.langmap.STORAGE_VIEW;
            app.Label_24.Text = app.langmap.PREPROCESSING_EVALUATION;
            app.Label_25.Text = app.langmap.TOOLTIP;
            app.Label_26.Text = app.langmap.CACHE_ROOT_FOLDER;
            app.Label_27.Text = app.langmap.APP_INFO_COLLECTION;
            app.Label_28.Text = app.langmap.DEVELOPER_MODE;
            app.Label_29.Text = app.langmap.AUTO_SAVE;
            app.Label_30.Text = app.langmap.AUTO_SAVE_INTERVAL;
            app.Label_31.Text = app.langmap.MINUTES;
            app.Label_32.Text = app.langmap.IMPORT_SOURCE_PRESETS;

            app.DropDown_Language.Items = ["中文", "English", app.langmap.FOLLOW_SYSTEM];
            app.DropDown_Style.Items = [app.langmap.STYLE_LIGHT, ...
                app.langmap.STYLE_DARK, app.langmap.FOLLOW_SYSTEM];
            app.DropDown_StorageViewStyle.Items = [app.langmap.CLASSICAL, ...
                app.langmap.MODERN];
            app.DropDown_ImportSourcePreset.Items = [app.langmap.REPEAT_LAST_TIME, ...
                app.langmap.MOST_FREQUENTLY_USED, app.langmap.HOME];
            app.DropDown_CachePolicy.Items = [app.langmap.PERFORMANCE, ...
                app.langmap.RESOURCES, app.langmap.BALANCE];
            app.DropDown_MessageLevel.Items = [app.langmap.ERROR, app.langmap.WARNING, ...
                app.langmap.INFORMATION];
            app.DropDown_TooltipsLevel.Items = [app.langmap.SIMPLE, app.langmap.DETAIL, ...
                app.langmap.NONE];
            app.DropDown_AutoUpdate.Items = [app.langmap.STARTUP, app.langmap.REAL_TIME, ...
                app.langmap.EVERYDAY, app.langmap.OFF];
            app.DropDown_UpdateChannel.Items = [app.langmap.RELEASE_CHANNEL, ...
                app.langmap.PREVIEW_CHANNEL, app.langmap.ALL_CHANNEL];

            app.Switch_SimpleStatistics.Items = [app.langmap.DISABLE, app.langmap.ENABLE];
            app.Switch_AutoTemplate.Items = [app.langmap.DISABLE, app.langmap.ENABLE];
            app.Switch_PreprocessEvaluation.Items = [app.langmap.DISABLE, app.langmap.ENABLE];
            app.Switch_AutoSave.Items = [app.langmap.DISABLE, app.langmap.ENABLE];
            app.Switch_DataProtected.Items = [app.langmap.DISABLE, app.langmap.ENABLE];
            app.Switch_InformationCollection.Items = [app.langmap.DISABLE, app.langmap.ENABLE];
            app.Switch_DeveloperMode.Items = [app.langmap.DISABLE, app.langmap.ENABLE];
            app.Switch_ExperimentalFeatures.Items = [app.langmap.DISABLE, app.langmap.ENABLE];

            app.Button_Apply.Text = app.langmap.APPLY;
            app.Button_OK.Text = app.langmap.OK;
            app.Button_Cancel.Text = app.langmap.CANCEL;
        end

        function RefreshTooltips(app)
            %% normal tooltips
            tl = app.caller.GetAppTipsLevel();
            switch tl
                case constdef.TOOLTIP_SIMPLE
                    app.Image_DataRootTooltip.Tooltip ...
                        = app.langmap.TIP_CACHE_ROOT_FOLDER;
                    app.Image_CachePolicyTooltip.Tooltip ...
                        = app.langmap.TIP_CACHE_POLICY;
                    app.Image_DataProtectionTooltip.Tooltip ...
                        = app.langmap.TIP_DATA_PROTECTION;
                    app.Image_InformationCollectionTooltip.Tooltip ...
                        = app.langmap.TIP_APP_INFO_COLLECTION_SIMPLE;
                    app.Image_DeveloperModeTooltip.Tooltip = ...
                        app.langmap.TIP_DEVELOPER_MODE;
                    app.Image_ExperimentalFeaturesTooltip.Tooltip ...
                        = app.langmap.TIP_EXPERIMENTAL_FEATURES;
                case constdef.TOOLTIP_DETAIL
                    app.Image_DataRootTooltip.Tooltip ...
                        = app.langmap.TIP_CACHE_ROOT_FOLDER;
                    app.Image_CachePolicyTooltip.Tooltip ...
                        = app.langmap.TIP_CACHE_POLICY;
                    app.Image_DataProtectionTooltip.Tooltip ...
                        = app.langmap.TIP_DATA_PROTECTION;
                    app.Image_InformationCollectionTooltip.Tooltip ...
                        = app.langmap.TIP_APP_INFO_COLLECTION_DETAIL;
                    app.Image_DeveloperModeTooltip.Tooltip = ...
                        app.langmap.TIP_DEVELOPER_MODE;
                    app.Image_ExperimentalFeaturesTooltip.Tooltip ...
                        = app.langmap.TIP_EXPERIMENTAL_FEATURES;
                case constdef.TOOLTIP_NONE
                    app.Image_DataRootTooltip.Tooltip = '';
                    app.Image_CachePolicyTooltip.Tooltip = '';
                    app.Image_DataProtectionTooltip.Tooltip = '';
                    app.Image_InformationCollectionTooltip.Tooltip = '';
                    app.Image_DeveloperModeTooltip.Tooltip = '';
                    app.Image_ExperimentalFeaturesTooltip.Tooltip = '';
                otherwise
            end

            %% folder/file path tips
            app.EditField_CacheRootFolder.Tooltip = app.aopts.CacheRootFolder;
            app.EditField_PyPath.Tooltip = app.aopts.PythonPath;
        end

        function LockPreferencePanel(app)
            app.Tree_OptionGroups.Enable = "off";
            app.Panel_Options.Enable = "off";
            app.Button_Apply.Enable = "off";
            app.Button_OK.Enable = "off";
            app.Button_Cancel.Enable = "off";
        end

        function UnlockPreferencePanel(app)
            app.Tree_OptionGroups.Enable = "on";
            app.Panel_Options.Enable = "on";
            app.Button_Apply.Enable = "on";
            app.Button_OK.Enable = "on";
            app.Button_Cancel.Enable = "on";
        end

        function Refresh(app, mainapp)
            app.caller = mainapp;
            InitData(app);
            InitPanel(app);
        end

        function Close(app)
            % stop daemon
            stop(app.nwdaemon);
            delete(app.nwdaemon);

            % ~
            delete(app)
        end
    end
    
    methods (Access = private)
        function InitData(app)
            app.aopts = app.caller.GetProfile();
            app.popts = app.caller.GetProjectConfig();

            % timer for task net work refresh
            app.nwdaemon = timer("Period",1, "BusyMode","queue", ...
                "ExecutionMode","fixedRate", "TasksToExecute",inf,...
                "Name","Timer_TaskNetDaemon", "TimerFcn",@app.UpdateTaskNetView);

            CPU_MAX_N = GetCPUWorkersMaxN([],[]);
            GPU_MAX_N = GetGPUWorkersMaxN();
            app.Spinner_CPU_Protected.Limits = [0, CPU_MAX_N];
            app.Spinner_GPU_Protected.Limits = [0, GPU_MAX_N];

            [MEM_CACHE_MAX, HDD_BUFFER_MAX] = mpimg.GetBufferSizeMax();
            app.EditField_MemoryCacheSize.Limits = [1, MEM_CACHE_MAX];
            app.EditField_HardDriveCacheSize.Limits = [1, HDD_BUFFER_MAX];

            r = app.caller.GetEngineInfo();
            nwp = r.NWP;
            app.Spinner_CPU_Protected.Value = nwp(1);
            app.Spinner_GPU_Protected.Value = nwp(2);
        end

        function InitPanel(app)
            %% Init Appearance Group
            app.DropDown_Language.Value = app.aopts.Language;
            app.DropDown_Style.Value = app.aopts.Style;
            app.DropDown_StorageViewStyle.Value = app.aopts.StorageViewStyle;
            app.EditField_ProgressBarColor.Value = string(app.aopts.ProgressBarColor).join(",");
            app.DropDown_ProgressBarColor.BackgroundColor = app.aopts.ProgressBarColor;
            app.Switch_SimpleStatistics.Value = app.aopts.SimpleStatistics;
            app.Switch_AutoTemplate.Value = app.aopts.AutoTemplate;
            app.Switch_PreprocessEvaluation.Value = app.aopts.PreprocessEvaluation;

            %% Init Data Management Group
            app.EditField_CacheRootFolder.Value = app.aopts.CacheRootFolder;
            app.Switch_AutoSave.Value = app.aopts.AutoSave;
            app.Spinner_AutoSaveInterval.Value = app.aopts.AutoSaveInterval;
            app.DropDown_ImportSourcePreset.Value = app.aopts.ImportSourcePreset;
            app.DropDown_CachePolicy.Value = app.popts.CachePolicy;
            app.EditField_MemoryCacheSize.Value = app.aopts.MemoryCapacity;
            app.EditField_HardDriveCacheSize.Value = app.aopts.HardDriveCapacity;

            %% Init Parallel Computing Group
            app.Spinner_CPU_Protected.Value = app.aopts.NumProtectedCPU;
            app.Spinner_GPU_Protected.Value = app.aopts.NumProtectedGPU;

            %% Init Others Group
            app.DropDown_MessageLevel.Value = app.aopts.MessageLevel;
            app.DropDown_TooltipsLevel.Value = app.aopts.TooltipsLevel;
            app.Switch_DataProtected.Value = app.popts.DataProtected;
            app.Switch_InformationCollection.Value = app.aopts.InformationCollection;
            app.EditField_PyPath.Value = app.aopts.PythonPath;
            app.Switch_DeveloperMode.Value = app.aopts.DeveloperMode;
            app.DropDown_AutoUpdate.Value = app.aopts.AutoUpdate;
            app.DropDown_UpdateChannel.Value = app.aopts.UpdateChannel;
            app.Switch_ExperimentalFeatures.Value = app.aopts.ExperimentalFeature;
        end

        function SaveProfile(app)
            %% save app profile
            prf_file = app.caller.GetProfileFullPath();
            [fpath, ~, ~] = fileparts(prf_file);
            if ~isfolder(fpath)
                try mkdir(fpath); catch ME, throw(ME); end
            end

            try
                writestruct(app.aopts, prf_file, "FileType","xml");
            catch ME
                throw(MException("ResourceManager:permissionDenied", ...
                    app.langmap.PERMISSION_DENIED));
            end
        end

        function InitAppearance(app)
            InitPanel(app);

            % refresh Options
            app.Tree_OptionGroups.SelectedNodes = app.Node_Appearance;
            app.Node_Appearance.expand();
            DisplayOptionsSwitchTo(app, "APPEARANCE");

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);

            RefreshTooltips(app);
        end
        
        function HiddenAllOptions(app)
            app.GridLayout_Options.RowHeight = zeros(1, app.FEATURES_NUMBER);
        end

        function DisplayOptionsSwitchTo(app, Tag)
            switch Tag
                case "APPEARANCE"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [1, 1, 1, 1, 1, 1, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                case "STYLE"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [1, 1, 1, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                case "IMAGE_TOOLBAR"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 1, 1, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                case "DATA_MANAGEMENT"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 1, 1, 1, 1, 1, 1, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                case "PROJECT_MANAGEMENT"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 1, 1, 1, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                case "TEMPORARY_FILE"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 1, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                case "PARALLEL_COMPUTING"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                case "OTHERS"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 1, 1, 1, 1, 1, 1, 1, 1];
                case "INFO_LEVEL"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 1, 0, 0, 0, 0, 0, 0, 0];
                case "DATA_SECURITY"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 1, 0, 0, 0, 0, 0];
                case "EXTERNAL_PATH"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0];
                case "SYSTEM_UPDATE"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 1, 1, 1];
                otherwise
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        zeros(1, app.FEATURES_NUMBER);
            end
        end

        % be invoked by nwdaemon: inf loops
        function UpdateTaskNetView(app, ~, ~)
            % task table view
            UpdateTaskTableView(app);

            % task nework view
            UpdateTaskTopologyView(app);

            drawnow nocallbacks
        end

        function UpdateTaskTableView(app)
            r = app.caller.GetEngineInfo();
            app.rsctb = r.T;    % update resource table
            app.UITable_TaskView.Data = r.T;
            pawait = find(r.T.status == "Await");
            pawait = [pawait, repmat(2, numel(pawait), 1)];
            pready = find(r.T.status == "Ready");
            pready = [pready, repmat(2, numel(pready), 1)];
            prun = find(r.T.status == "Run");
            prun = [prun, repmat(2, numel(prun), 1)];

            sawait = uistyle("Icon","error","IconAlignment","rightmargin");
            sready = uistyle("Icon","warning","IconAlignment","rightmargin");
            srun = uistyle("Icon","success","IconAlignment","rightmargin");

            % refresh the table style
            removeStyle(app.UITable_TaskView);
            if ~isempty(pawait)
                addStyle(app.UITable_TaskView, sawait, "cell", pawait);
            end
            if ~isempty(pready)
                addStyle(app.UITable_TaskView, sready, "cell", pready);
            end
            if ~isempty(prun)
                addStyle(app.UITable_TaskView, srun, "cell", prun);
            end
        end

        function UpdateTaskTopologyView(app)
            % generate adjencient matrix:
            % [row, column] -> [resource, user]
            % weight for resource count
            netm = table2array(app.rsctb(:, app.NRSCPHN+1:end))';

            if ~isempty(netm)
                % create resource bipartite graph
                bnetm = zeros(sum(size(netm)), sum(size(netm)));
                bnetm(1:size(netm,1), end-size(netm,2)+1:end) = netm;

                % generate node name
                nodes = string(app.rsctb.Properties.VariableNames(app.NRSCPHN+1:end));
                nodes = nodes.erase("n_").upper();
                nodec = hsv(numel(nodes));
                nodes = [nodes'; app.rsctb.user];
                nodec = [nodec; zeros(numel(app.rsctb.user), 3)];

                rG = digraph(bnetm, nodes);

                % mark line by user status
                line_stl = strings(size(rG.Edges, 1), 1);
                for k = 1:size(rG.Edges, 1)
                    % validate user status
                    status = app.rsctb.status(app.rsctb.user==string(rG.Edges.EndNodes(k, 2)));
                    switch status
                        case "Run"
                            line_stl(k) = "-";
                        case "Await"
                            line_stl(k) = "--";
                        otherwise
                            line_stl(k) = "none";
                    end
                end

                plot(app.UIAxes_TaskNetwork, rG, "Layout","layered", ...
                    "EdgeLabel",rG.Edges.Weight, "NodeColor",nodec, ...
                    "LineWidth", 5*rG.Edges.Weight/app.Spinner_CPU_Protected.Limits(2), ...
                    "MarkerSize",10, "NodeFontSize",14, "EdgeFontSize",12, ...
                    "ArrowSize",18,"ArrowPosition",1, "EdgeFontWeight","bold", ...
                    "LineStyle", line_stl, "Interpreter","none");
            else
                cla(app.UIAxes_TaskNetwork);
            end
        end

        function UpdateProjectManagement(app)
            pmtable = table('Size',[0,4], 'VariableTypes',{'string','string','datetime', 'double'}, ...
                'VariableNames',[app.langmap.PROJECT_NAME, app.langmap.PROJECT_PATH, ...
                app.langmap.MODIFIED_DATE, app.langmap.PROJECT_SIZE]);
            app.tmpfolders = [];

            ud = uiprogressdlg(app.ResourceManagerUIFigure, "Indeterminate","on");

            % searching for all project from generated history
            ppfile = app.caller.GetProjectProfileFullPath();
            prof = readstruct(ppfile, "FileType","xml");

            if ~isempty(prof.ProjectFolders)
                for k = 1:numel(prof.ProjectFolders)
                    if isfolder(prof.ProjectFolders(k))
                        % list all folders under project folder k
                        folders = struct2table(dir(prof.ProjectFolders(k)));
                        folders(1:2,:) = [];    % remove . and ..
                        if ~isempty(folders)
                            pfnames = string(folders.name);
                            ppfolders = string(folders.folder);
                            dtimes = datetime(folders.date);
                            
                            % load DS_ and get the temporary location
                            for n = 1:numel(ppfolders)
                                pfname_full = ppfolders(n) + filesep + pfnames(n) ...
                                    + filesep + pfnames(n) + constdef.PROJECT_FILE_EXT;
                                load(pfname_full, "-mat", "DS_");
                                if isobject(DS_.img_cur) && isvalid(DS_.img_cur)
                                    [tmpfolder, ~, ~] = fileparts(DS_.img_cur.Location);
                                    app.tmpfolders = [app.tmpfolders; string(tmpfolder)];
                                    fsize = struct2table(dir(tmpfolder));
                                    fsize = sum(fsize.bytes)/constdef.GB2B;
                                else
                                    fsize = 0;
                                end

                                dpmtable = cell2table({pfnames(n), ppfolders(n), dtimes(n), fsize}, ...
                                    "VariableNames",[app.langmap.PROJECT_NAME, app.langmap.PROJECT_PATH, ...
                                    app.langmap.MODIFIED_DATE, app.langmap.PROJECT_SIZE]);

                                pmtable = [pmtable; dpmtable]; %#ok<AGROW>
                            end
                        end
                    end
                end
            end

            app.tmpfolders = unique(app.tmpfolders);

            % apply
            app.UITable_Project.Data = pmtable;

            delete(ud);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            HiddenAllOptions(app);

            app.caller = mainapp;

            InitData(app);

            InitAppearance(app);
        end

        % Selection changed function: Tree_OptionGroups
        function Tree_OptionGroupsSelectionChanged(app, event)
            selectedNodes = app.Tree_OptionGroups.SelectedNodes;
            
            app.DisplayOptionsSwitchTo(selectedNodes.Tag);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            switch selectedTab
                case app.Tab_TaskNetwork
                    stop(app.nwdaemon);     % security trigger
                    start(app.nwdaemon);
                case app.Tab_Preference
                    % stop refresh to save kernel visiting
                    stop(app.nwdaemon);
                case app.Tab_ProjectManagement
                    % stop refresh to save kernel visiting
                    stop(app.nwdaemon);
                    % refresh projects information
                    UpdateProjectManagement(app);
                otherwise
            end
        end

        % Close request function: ResourceManagerUIFigure
        function ResourceManagerUIFigureCloseRequest(app, event)

            Close(app);            
        end

        % Value changed function: DropDown_Style
        function DropDown_StyleValueChanged(app, event)
            value = app.DropDown_Style.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.Style = string(value);
        end

        % Value changed function: EditField_ProgressBarColor
        function EditField_ProgressBarColorValueChanged(app, event)
            value = app.EditField_ProgressBarColor.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value)~=[1,3]) || any(value<0 | value>1)
                app.EditField_ProgressBarColor.Value = string(app.aopts).join(",");
            else
                app.Button_Apply.Enable = "on";
                app.DropDown_ProgressBarColor.BackgroundColor = value;
                app.aopts.ProgressBarColor = value;
            end
        end

        % Drop down opening function: DropDown_ProgressBarColor
        function DropDown_ProgressBarColorOpening(app, event)
            rgb = uisetcolor(app.aopts.ProgressBarColor);
            rgb = round(rgb, 2);
            app.Button_Apply.Enable = "on";

            app.EditField_ProgressBarColor.Value = ...
                sprintf("%1.2f,%1.2f,%1.2f", rgb(1), rgb(2), rgb(3));
            app.DropDown_ProgressBarColor.BackgroundColor = rgb;

            app.aopts.ProgressBarColor = rgb;
        end

        % Value changed function: EditField_HardDriveCacheSize
        function EditField_HardDriveCacheSizeValueChanged(app, event)
            value = app.EditField_HardDriveCacheSize.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.HardDriveCapacity = value;
        end

        % Value changed function: Spinner_CPU_Protected
        function Spinner_CPU_ProtectedValueChanged(app, event)
            value = app.Spinner_CPU_Protected.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.NumProtectedCPU = value;
        end

        % Value changed function: Spinner_GPU_Protected
        function Spinner_GPU_ProtectedValueChanged(app, event)
            value = app.Spinner_GPU_Protected.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.NumProtectedGPU = value;
        end

        % Value changed function: DropDown_MessageLevel
        function DropDown_MessageLevelValueChanged(app, event)
            value = app.DropDown_MessageLevel.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.MessageLevel = string(value);
        end

        % Value changed function: DropDown_AutoUpdate
        function DropDown_AutoUpdateValueChanged(app, event)
            value = app.DropDown_AutoUpdate.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.AutoUpdate = string(value);
        end

        % Value changed function: DropDown_UpdateChannel
        function DropDown_UpdateChannelValueChanged(app, event)
            value = app.DropDown_UpdateChannel.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.UpdateChannel = string(value);
        end

        % Button pushed function: Button_RequireCapacityHDD, 
        % ...and 1 other component
        function Button_RequireCapacityHDDPushed(app, event)
            uialert(app.ResourceManagerUIFigure, app.langmap.REQUIRE_CAPACITY, ...
                app.langmap.NOTIFICATION, "Icon","info");
            
        end

        % Button pushed function: Button_Apply
        function Button_ApplyPushed(app, event)
            % set and apply, save configuration, do not close
            app.ResourceManagerUIFigure.Pointer = "watch";
            drawnow;

            app.caller.SetProfile(app.aopts);
            app.caller.SetProjectConfig(app.popts);
            app.SaveProfile();
            app.Button_Apply.Enable = "off";

            app.ResourceManagerUIFigure.Pointer = "arrow";
            drawnow;
        end

        % Button pushed function: Button_OK
        function Button_OKPushed(app, event)
            % set and apply, save configuration, close
            if app.Button_Apply.Enable == "on"  % changed some items but didn't save
                app.ResourceManagerUIFigure.Pointer = "watch";
                drawnow;

                app.caller.SetProfile(app.aopts);
                app.caller.SetProjectConfig(app.popts);
                SaveProfile(app);

                app.ResourceManagerUIFigure.Pointer = "arrow";
                drawnow;
            end

            Close(app);
        end

        % Button pushed function: Button_Cancel
        function Button_CancelPushed(app, event)
            % close only
            Close(app);
        end

        % Value changed function: Switch_SimpleStatistics
        function Switch_SimpleStatisticsValueChanged(app, event)
            value = app.Switch_SimpleStatistics.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.SimpleStatistics = string(value);
        end

        % Value changed function: Switch_AutoTemplate
        function Switch_AutoTemplateValueChanged(app, event)
            value = app.Switch_AutoTemplate.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.AutoTemplate = string(value);
        end

        % Value changed function: Switch_DataProtected
        function Switch_DataProtectedValueChanged(app, event)
            value = app.Switch_DataProtected.Value;
            app.Button_Apply.Enable = "on";
            
            app.popts.DataProtected = string(value);
        end

        % Value changed function: Switch_ExperimentalFeatures
        function Switch_ExperimentalFeaturesValueChanged(app, event)
            value = app.Switch_ExperimentalFeatures.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.ExperimentalFeature = string(value);
        end

        % Value changed function: DropDown_Language
        function DropDown_LanguageValueChanged(app, event)
            value = app.DropDown_Language.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.Language = string(value);
        end

        % Button pushed function: Button_SelectPython
        function Button_SelectPythonPushed(app, event)
            if app.aopts.PythonPath == ""
                if ispc()
                    defname = "python.exe";
                elseif isunix()
                    defname = "python";
                end
                [file, path] = uigetfile(defname, ...
                    app.langmap.PYTHON_INTERPRETER_SELECTION);
            else
                [file, path] = uigetfile(app.aopts.PythonPath, ...
                    app.langmap.PYTHON_INTERPRETER_SELECTION);
            end

            if ~isnumeric(file)
                file = fullfile(path, file);
                try
                    % reload python process
                    terminate(pyenv);
                    pyenv(Version=file, ExecutionMode="OutOfProcess");

                    % update file path
                    app.EditField_PyPath.Value = file;
                    app.aopts.PythonPath = string(file);
                    
                    app.EditField_PyPath.Tooltip = app.aopts.PythonPath;
                catch ME
                    throwAsCaller(ME);
                end
            else
                app.aopts.PythonPath = "";
                app.EditField_PyPath.Tooltip = app.aopts.PythonPath;
            end
        end

        % Value changed function: DropDown_StorageViewStyle
        function DropDown_StorageViewStyleValueChanged(app, event)
            value = app.DropDown_StorageViewStyle.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.StorageViewStyle = string(value);
        end

        % Value changed function: EditField_MemoryCacheSize
        function EditField_MemoryCacheSizeValueChanged(app, event)
            value = app.EditField_MemoryCacheSize.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.MemoryCapacity = value;
        end

        % Value changed function: Switch_PreprocessEvaluation
        function Switch_PreprocessEvaluationValueChanged(app, event)
            value = app.Switch_PreprocessEvaluation.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.PreprocessEvaluation = string(value);
        end

        % Value changed function: DropDown_TooltipsLevel
        function DropDown_TooltipsLevelValueChanged(app, event)
            value = app.DropDown_TooltipsLevel.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.TooltipsLevel = string(value);
        end

        % Button pushed function: Button_SelectTemporaryFileRootFolder
        function Button_SelectTemporaryFileRootFolderPushed(app, event)
            folder = uigetdir(userpath, app.langmap.TEMPORARY_FOLDER_SELECTION);
            app.Button_Apply.Enable = "on";

            if ~isnumeric(folder)
                app.aopts.CacheRootFolder = string(folder);
            else
                app.aopts.CacheRootFolder = "";
            end

            app.EditField_CacheRootFolder.Value = app.aopts.CacheRootFolder;
            app.EditField_CacheRootFolder.Tooltip = app.aopts.CacheRootFolder;
        end

        % Value changed function: Switch_InformationCollection
        function Switch_InformationCollectionValueChanged(app, event)
            value = app.Switch_InformationCollection.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.InformationCollection = string(value);
        end

        % Value changed function: Switch_DeveloperMode
        function Switch_DeveloperModeValueChanged(app, event)
            value = app.Switch_DeveloperMode.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.DeveloperMode = string(value);
        end

        % Value changed function: Switch_AutoSave
        function Switch_AutoSaveValueChanged(app, event)
            value = app.Switch_AutoSave.Value;
            app.Spinner_AutoSaveInterval.Enable = lower(value);
            app.Button_Apply.Enable = "on";
            
            app.aopts.AutoSave = string(value);
        end

        % Value changed function: Spinner_AutoSaveInterval
        function Spinner_AutoSaveIntervalValueChanged(app, event)
            value = app.Spinner_AutoSaveInterval.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.AutoSaveInterval = value;
        end

        % Value changed function: DropDown_ImportSourcePreset
        function DropDown_ImportSourcePresetValueChanged(app, event)
            value = app.DropDown_ImportSourcePreset.Value;
            app.Button_Apply.Enable = "on";

            app.aopts.ImportSourcePreset = string(value);
        end

        % Value changed function: DropDown_CachePolicy
        function DropDown_CachePolicyValueChanged(app, event)
            value = app.DropDown_CachePolicy.Value;
            app.Button_Apply.Enable = "on";

            app.popts.CachePolicy = string(value);
        end

        % Menu selected function: Menu_DeleteProject
        function Menu_DeleteProjectSelected(app, event)
            if ~isempty(app.UITable_Project.Selection)
                slt = uiconfirm(app.ResourceManagerUIFigure, ...
                    app.langmap.DELETE_CONFIRMATION, app.langmap.WARNING, ...
                    "Icon","question");
                switch slt
                    case "OK"
                        for k = app.UITable_Project.Selection
                            % delete selected project
                            pfolder = app.UITable_Project.Data{k, app.langmap.PROJECT_PATH} ...
                                + filesep + app.UITable_Project.Data{k, app.langmap.PROJECT_NAME};
                            pfile = pfolder + filesep + app.UITable_Project.Data{k, app.langmap.PROJECT_NAME} ...
                                + constdef.PROJECT_FILE_EXT;
                            load(pfile, "-mat", "occupied");
                            if occupied == false
                                try
                                    % remove project folder
                                    rmdir(pfolder, "s");
                                    % remove temporary folder
                                    rmdir(app.tmpfolders(k), "s");
                                catch
                                    % continue
                                end
                            else
                                uiconfirm(app.ResourceManagerUIFigure, ...
                                    sprintf("%s - [%s]", app.langmap.DELETE_ABORTION, ...
                                    pfile), app.langmap.ERROR, "Icon","error");
                            end
                        end

                        UpdateProjectManagement(app);
                    otherwise
                        return;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ResourceManagerUIFigure = uifigure('Visible', 'off');
            app.ResourceManagerUIFigure.Name = 'Resource Manager';
            app.ResourceManagerUIFigure.Icon = 'resource_manager.png';
            app.ResourceManagerUIFigure.CloseRequestFcn = createCallbackFcn(app, @ResourceManagerUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ResourceManagerUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab_Preference = uitab(app.TabGroup);
            app.Tab_Preference.Title = '首选项';
            app.GridLayout5 = uigridlayout(app.Tab_Preference);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Tree_OptionGroups = uitree(app.GridLayout6);
            app.Tree_OptionGroups.SelectionChangedFcn = createCallbackFcn(app, @Tree_OptionGroupsSelectionChanged, true);
            app.Node_Appearance = uitreenode(app.Tree_OptionGroups);
            app.Node_Appearance.Tag = 'APPEARANCE';
            app.Node_Appearance.Text = '外观';
            app.Node_Style = uitreenode(app.Node_Appearance);
            app.Node_Style.Tag = 'STYLE';
            app.Node_Style.Text = '风格';
            app.Node_ImageToolbar = uitreenode(app.Node_Appearance);
            app.Node_ImageToolbar.Tag = 'IMAGE_TOOLBAR';
            app.Node_ImageToolbar.Text = '图像工具栏';
            app.Node_DataManagement = uitreenode(app.Tree_OptionGroups);
            app.Node_DataManagement.Tag = 'DATA_MANAGEMENT';
            app.Node_DataManagement.Text = '数据管理';
            app.Node_ProjectManagement = uitreenode(app.Node_DataManagement);
            app.Node_ProjectManagement.Tag = 'PROJECT_MANAGEMENT';
            app.Node_ProjectManagement.Text = '工程管理';
            app.Node_TemporaryFile = uitreenode(app.Node_DataManagement);
            app.Node_TemporaryFile.Tag = 'TEMPORARY_FILE';
            app.Node_TemporaryFile.Text = '临时文件';
            app.Node_ParallelComputing = uitreenode(app.Tree_OptionGroups);
            app.Node_ParallelComputing.Tag = 'PARALLEL_COMPUTING';
            app.Node_ParallelComputing.Text = '并行计算';
            app.Node_Others = uitreenode(app.Tree_OptionGroups);
            app.Node_Others.Tag = 'OTHERS';
            app.Node_Others.Text = '其他设置';
            app.Node_Notification = uitreenode(app.Node_Others);
            app.Node_Notification.Tag = 'INFO_LEVEL';
            app.Node_Notification.Text = '通知';
            app.Node_DataSecurity = uitreenode(app.Node_Others);
            app.Node_DataSecurity.Tag = 'DATA_SECURITY';
            app.Node_DataSecurity.Text = '数据安全';
            app.Node_ExternalLibraryPath = uitreenode(app.Node_Others);
            app.Node_ExternalLibraryPath.Tag = 'EXTERNAL_PATH';
            app.Node_ExternalLibraryPath.Text = '外部路径';
            app.Node_SystemAndUpdate = uitreenode(app.Node_Others);
            app.Node_SystemAndUpdate.Tag = 'SYSTEM_UPDATE';
            app.Node_SystemAndUpdate.Text = '系统和更新';
            app.Panel_Options = uipanel(app.GridLayout6);
            app.Panel_Options.Title = '选项';
            app.GridLayout_Options = uigridlayout(app.Panel_Options);
            app.Label_4 = uilabel(app.GridLayout_Options);
            app.Label_4.Text = '进度条颜色';
            app.GridLayout8 = uigridlayout(app.GridLayout_Options);
            app.EditField_ProgressBarColor = uieditfield(app.GridLayout8, 'text');
            app.EditField_ProgressBarColor.CharacterLimits = [5 16];
            app.EditField_ProgressBarColor.ValueChangedFcn = createCallbackFcn(app, @EditField_ProgressBarColorValueChanged, true);
            app.EditField_ProgressBarColor.Value = '0.30,0.75,0.93';
            app.DropDown_ProgressBarColor = uidropdown(app.GridLayout8);
            app.DropDown_ProgressBarColor.Items = {};
            app.DropDown_ProgressBarColor.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_ProgressBarColorOpening, true);
            app.DropDown_ProgressBarColor.Value = {};
            app.GridLayout9 = uigridlayout(app.GridLayout_Options);
            app.EditField_HardDriveCacheSize = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_HardDriveCacheSize.Limits = [1 256];
            app.EditField_HardDriveCacheSize.RoundFractionalValues = 'on';
            app.EditField_HardDriveCacheSize.ValueDisplayFormat = '%.0f';
            app.EditField_HardDriveCacheSize.ValueChangedFcn = createCallbackFcn(app, @EditField_HardDriveCacheSizeValueChanged, true);
            app.EditField_HardDriveCacheSize.Value = 128;
            app.Button_RequireCapacityHDD = uibutton(app.GridLayout9, 'push');
            app.Button_RequireCapacityHDD.ButtonPushedFcn = createCallbackFcn(app, @Button_RequireCapacityHDDPushed, true);
            app.Button_RequireCapacityHDD.Text = 'GB';
            app.Label_6 = uilabel(app.GridLayout_Options);
            app.Label_6.Text = '硬盘容量';
            app.CPULabel = uilabel(app.GridLayout_Options);
            app.CPULabel.Text = '保留数(CPU)';
            app.Spinner_CPU_Protected = uispinner(app.GridLayout_Options);
            app.Spinner_CPU_Protected.Limits = [0 128];
            app.Spinner_CPU_Protected.RoundFractionalValues = 'on';
            app.Spinner_CPU_Protected.ValueDisplayFormat = '%.0f';
            app.Spinner_CPU_Protected.ValueChangedFcn = createCallbackFcn(app, @Spinner_CPU_ProtectedValueChanged, true);
            app.GPULabel = uilabel(app.GridLayout_Options);
            app.GPULabel.Text = '保留数(GPU)';
            app.Spinner_GPU_Protected = uispinner(app.GridLayout_Options);
            app.Spinner_GPU_Protected.Limits = [0 128];
            app.Spinner_GPU_Protected.RoundFractionalValues = 'on';
            app.Spinner_GPU_Protected.ValueDisplayFormat = '%.0f';
            app.Spinner_GPU_Protected.ValueChangedFcn = createCallbackFcn(app, @Spinner_GPU_ProtectedValueChanged, true);
            app.Label_9 = uilabel(app.GridLayout_Options);
            app.Label_9.Text = '风格';
            app.DropDown_Style = uidropdown(app.GridLayout_Options);
            app.DropDown_Style.Items = {'浅色', '深色', '跟随系统'};
            app.DropDown_Style.ItemsData = {'LIGHT', 'DARK', 'FOLLOW'};
            app.DropDown_Style.ValueChangedFcn = createCallbackFcn(app, @DropDown_StyleValueChanged, true);
            app.DropDown_Style.Value = 'FOLLOW';
            app.Label_10 = uilabel(app.GridLayout_Options);
            app.Label_10.Text = '消息级别';
            app.DropDown_MessageLevel = uidropdown(app.GridLayout_Options);
            app.DropDown_MessageLevel.Items = {'错误', '警告', '信息'};
            app.DropDown_MessageLevel.ItemsData = {'ERROR', 'WARNING', 'INFO'};
            app.DropDown_MessageLevel.ValueChangedFcn = createCallbackFcn(app, @DropDown_MessageLevelValueChanged, true);
            app.DropDown_MessageLevel.Value = 'WARNING';
            app.Label_12 = uilabel(app.GridLayout_Options);
            app.Label_12.Text = '自动更新';
            app.DropDown_AutoUpdate = uidropdown(app.GridLayout_Options);
            app.DropDown_AutoUpdate.Items = {'启动时', '实时', '每天', '关闭'};
            app.DropDown_AutoUpdate.ItemsData = {'STARTUP', 'RT', 'EVERYDAY', 'OFF'};
            app.DropDown_AutoUpdate.ValueChangedFcn = createCallbackFcn(app, @DropDown_AutoUpdateValueChanged, true);
            app.DropDown_AutoUpdate.Value = 'STARTUP';
            app.Label_13 = uilabel(app.GridLayout_Options);
            app.Label_13.Text = '更新频道';
            app.DropDown_UpdateChannel = uidropdown(app.GridLayout_Options);
            app.DropDown_UpdateChannel.Items = {'正式版', '预览版', '所有'};
            app.DropDown_UpdateChannel.ItemsData = {'REL', 'PRE', 'ALL'};
            app.DropDown_UpdateChannel.ValueChangedFcn = createCallbackFcn(app, @DropDown_UpdateChannelValueChanged, true);
            app.DropDown_UpdateChannel.Value = 'REL';
            app.Label_15 = uilabel(app.GridLayout_Options);
            app.Label_15.Text = '实验功能';
            app.Switch_ExperimentalFeatures = uiswitch(app.GridLayout_Options, 'slider');
            app.Switch_ExperimentalFeatures.Items = {'禁用', '启用'};
            app.Switch_ExperimentalFeatures.ItemsData = {'OFF', 'ON'};
            app.Switch_ExperimentalFeatures.ValueChangedFcn = createCallbackFcn(app, @Switch_ExperimentalFeaturesValueChanged, true);
            app.Switch_ExperimentalFeatures.Enable = 'off';
            app.Switch_ExperimentalFeatures.Value = 'OFF';
            app.Label_16 = uilabel(app.GridLayout_Options);
            app.Label_16.Text = '数据保护';
            app.Switch_DataProtected = uiswitch(app.GridLayout_Options, 'slider');
            app.Switch_DataProtected.Items = {'禁用', '启用'};
            app.Switch_DataProtected.ItemsData = {'OFF', 'ON'};
            app.Switch_DataProtected.ValueChangedFcn = createCallbackFcn(app, @Switch_DataProtectedValueChanged, true);
            app.Switch_DataProtected.Enable = 'off';
            app.Switch_DataProtected.Value = 'OFF';
            app.GridLayout11 = uigridlayout(app.GridLayout_Options);
            app.EditField_PyPath = uieditfield(app.GridLayout11, 'text');
            app.EditField_PyPath.Editable = 'off';
            app.Button_SelectPython = uibutton(app.GridLayout11, 'push');
            app.Button_SelectPython.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectPythonPushed, true);
            app.Button_SelectPython.Icon = 'folder.png';
            app.Button_SelectPython.Text = '';
            app.PythonLabel = uilabel(app.GridLayout_Options);
            app.PythonLabel.Text = 'Python解释器';
            app.Label_17 = uilabel(app.GridLayout_Options);
            app.Label_17.Text = '语言';
            app.DropDown_Language = uidropdown(app.GridLayout_Options);
            app.DropDown_Language.Items = {'中文', 'English', '跟随系统'};
            app.DropDown_Language.ItemsData = {'CN', 'US', 'FOLLOW'};
            app.DropDown_Language.ValueChangedFcn = createCallbackFcn(app, @DropDown_LanguageValueChanged, true);
            app.DropDown_Language.Value = 'FOLLOW';
            app.Label_18 = uilabel(app.GridLayout_Options);
            app.Label_18.Text = '简单统计';
            app.Switch_SimpleStatistics = uiswitch(app.GridLayout_Options, 'slider');
            app.Switch_SimpleStatistics.Items = {'禁用', '启用'};
            app.Switch_SimpleStatistics.ItemsData = {'OFF', 'ON'};
            app.Switch_SimpleStatistics.ValueChangedFcn = createCallbackFcn(app, @Switch_SimpleStatisticsValueChanged, true);
            app.Switch_SimpleStatistics.Value = 'ON';
            app.Label_19 = uilabel(app.GridLayout_Options);
            app.Label_19.Text = '自动模板';
            app.Switch_AutoTemplate = uiswitch(app.GridLayout_Options, 'slider');
            app.Switch_AutoTemplate.Items = {'禁用', '启用'};
            app.Switch_AutoTemplate.ItemsData = {'OFF', 'ON'};
            app.Switch_AutoTemplate.ValueChangedFcn = createCallbackFcn(app, @Switch_AutoTemplateValueChanged, true);
            app.Switch_AutoTemplate.Value = 'ON';
            app.Label_20 = uilabel(app.GridLayout_Options);
            app.Label_20.Text = '缓存策略';
            app.DropDown_CachePolicy = uidropdown(app.GridLayout_Options);
            app.DropDown_CachePolicy.Items = {'性能', '资源', '平衡'};
            app.DropDown_CachePolicy.ItemsData = {'PERFORMANCE', 'RESOURCE', 'BALANCE'};
            app.DropDown_CachePolicy.ValueChangedFcn = createCallbackFcn(app, @DropDown_CachePolicyValueChanged, true);
            app.DropDown_CachePolicy.Value = 'PERFORMANCE';
            app.Label_22 = uilabel(app.GridLayout_Options);
            app.Label_22.Text = '存储视图';
            app.DropDown_StorageViewStyle = uidropdown(app.GridLayout_Options);
            app.DropDown_StorageViewStyle.Items = {'经典', '现代'};
            app.DropDown_StorageViewStyle.ItemsData = {'CLASSICAL', 'MODERN'};
            app.DropDown_StorageViewStyle.ValueChangedFcn = createCallbackFcn(app, @DropDown_StorageViewStyleValueChanged, true);
            app.DropDown_StorageViewStyle.Value = 'MODERN';
            app.Label_23 = uilabel(app.GridLayout_Options);
            app.Label_23.Text = '内存容量';
            app.GridLayout12 = uigridlayout(app.GridLayout_Options);
            app.EditField_MemoryCacheSize = uieditfield(app.GridLayout12, 'numeric');
            app.EditField_MemoryCacheSize.Limits = [1 96];
            app.EditField_MemoryCacheSize.RoundFractionalValues = 'on';
            app.EditField_MemoryCacheSize.ValueDisplayFormat = '%.0f';
            app.EditField_MemoryCacheSize.ValueChangedFcn = createCallbackFcn(app, @EditField_MemoryCacheSizeValueChanged, true);
            app.EditField_MemoryCacheSize.Value = 64;
            app.Button_RequireCapacityMEM = uibutton(app.GridLayout12, 'push');
            app.Button_RequireCapacityMEM.ButtonPushedFcn = createCallbackFcn(app, @Button_RequireCapacityHDDPushed, true);
            app.Button_RequireCapacityMEM.Text = 'GB';
            app.Label_24 = uilabel(app.GridLayout_Options);
            app.Label_24.Text = '预处理评估';
            app.Switch_PreprocessEvaluation = uiswitch(app.GridLayout_Options, 'slider');
            app.Switch_PreprocessEvaluation.Items = {'禁用', '启用'};
            app.Switch_PreprocessEvaluation.ItemsData = {'OFF', 'ON'};
            app.Switch_PreprocessEvaluation.ValueChangedFcn = createCallbackFcn(app, @Switch_PreprocessEvaluationValueChanged, true);
            app.Switch_PreprocessEvaluation.Value = 'ON';
            app.Image_CachePolicyTooltip = uiimage(app.GridLayout_Options);
            app.Image_CachePolicyTooltip.ImageSource = 'info.png';
            app.Label_25 = uilabel(app.GridLayout_Options);
            app.Label_25.Text = '工具提示';
            app.DropDown_TooltipsLevel = uidropdown(app.GridLayout_Options);
            app.DropDown_TooltipsLevel.Items = {'简略', '详细', '无'};
            app.DropDown_TooltipsLevel.ItemsData = {'SIMPLE', 'DETAIL', 'NONE'};
            app.DropDown_TooltipsLevel.ValueChangedFcn = createCallbackFcn(app, @DropDown_TooltipsLevelValueChanged, true);
            app.DropDown_TooltipsLevel.Value = 'SIMPLE';
            app.Image_DataProtectionTooltip = uiimage(app.GridLayout_Options);
            app.Image_DataProtectionTooltip.ImageSource = 'info.png';
            app.Image_ExperimentalFeaturesTooltip = uiimage(app.GridLayout_Options);
            app.Image_ExperimentalFeaturesTooltip.ImageSource = 'info.png';
            app.Label_26 = uilabel(app.GridLayout_Options);
            app.Label_26.Text = '缓存根目录';
            app.GridLayout13 = uigridlayout(app.GridLayout_Options);
            app.EditField_CacheRootFolder = uieditfield(app.GridLayout13, 'text');
            app.EditField_CacheRootFolder.Editable = 'off';
            app.Button_SelectTemporaryFileRootFolder = uibutton(app.GridLayout13, 'push');
            app.Button_SelectTemporaryFileRootFolder.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectTemporaryFileRootFolderPushed, true);
            app.Button_SelectTemporaryFileRootFolder.Icon = 'folder.png';
            app.Button_SelectTemporaryFileRootFolder.Text = '';
            app.Label_27 = uilabel(app.GridLayout_Options);
            app.Label_27.Text = '收集应用信息';
            app.Switch_InformationCollection = uiswitch(app.GridLayout_Options, 'slider');
            app.Switch_InformationCollection.Items = {'拒绝', '同意'};
            app.Switch_InformationCollection.ItemsData = {'OFF', 'ON'};
            app.Switch_InformationCollection.ValueChangedFcn = createCallbackFcn(app, @Switch_InformationCollectionValueChanged, true);
            app.Switch_InformationCollection.Value = 'ON';
            app.Image_InformationCollectionTooltip = uiimage(app.GridLayout_Options);
            app.Image_InformationCollectionTooltip.ImageSource = 'info.png';
            app.Label_28 = uilabel(app.GridLayout_Options);
            app.Label_28.Text = '开发者模式';
            app.Switch_DeveloperMode = uiswitch(app.GridLayout_Options, 'slider');
            app.Switch_DeveloperMode.Items = {'禁用', '启用'};
            app.Switch_DeveloperMode.ItemsData = {'OFF', 'ON'};
            app.Switch_DeveloperMode.ValueChangedFcn = createCallbackFcn(app, @Switch_DeveloperModeValueChanged, true);
            app.Switch_DeveloperMode.Value = 'OFF';
            app.Image_DeveloperModeTooltip = uiimage(app.GridLayout_Options);
            app.Image_DeveloperModeTooltip.ImageSource = 'info.png';
            app.Image_DataRootTooltip = uiimage(app.GridLayout_Options);
            app.Image_DataRootTooltip.ImageSource = 'info.png';
            app.Label_29 = uilabel(app.GridLayout_Options);
            app.Label_29.Text = '自动保存';
            app.Switch_AutoSave = uiswitch(app.GridLayout_Options, 'slider');
            app.Switch_AutoSave.Items = {'禁用', '启用'};
            app.Switch_AutoSave.ItemsData = {'OFF', 'ON'};
            app.Switch_AutoSave.ValueChangedFcn = createCallbackFcn(app, @Switch_AutoSaveValueChanged, true);
            app.Switch_AutoSave.Value = 'ON';
            app.GridLayout14 = uigridlayout(app.GridLayout_Options);
            app.Label_31 = uilabel(app.GridLayout14);
            app.Label_31.Text = '分钟';
            app.Spinner_AutoSaveInterval = uispinner(app.GridLayout14);
            app.Spinner_AutoSaveInterval.Limits = [1 120];
            app.Spinner_AutoSaveInterval.ValueDisplayFormat = '%.0f';
            app.Spinner_AutoSaveInterval.ValueChangedFcn = createCallbackFcn(app, @Spinner_AutoSaveIntervalValueChanged, true);
            app.Spinner_AutoSaveInterval.Value = 10;
            app.Label_30 = uilabel(app.GridLayout_Options);
            app.Label_30.Text = '自动保存间隔';
            app.Label_32 = uilabel(app.GridLayout_Options);
            app.Label_32.Text = '导入源预设';
            app.DropDown_ImportSourcePreset = uidropdown(app.GridLayout_Options);
            app.DropDown_ImportSourcePreset.Items = {'重复上次', '最常用', '主文件夹'};
            app.DropDown_ImportSourcePreset.ItemsData = {'LAST', 'MODE', 'HOME'};
            app.DropDown_ImportSourcePreset.ValueChangedFcn = createCallbackFcn(app, @DropDown_ImportSourcePresetValueChanged, true);
            app.DropDown_ImportSourcePreset.Value = 'HOME';
            app.GridLayout10 = uigridlayout(app.GridLayout5);
            app.Button_Apply = uibutton(app.GridLayout10, 'push');
            app.Button_Apply.ButtonPushedFcn = createCallbackFcn(app, @Button_ApplyPushed, true);
            app.Button_Apply.Text = '应用';
            app.Button_OK = uibutton(app.GridLayout10, 'push');
            app.Button_OK.ButtonPushedFcn = createCallbackFcn(app, @Button_OKPushed, true);
            app.Button_OK.Text = '确定';
            app.Button_Cancel = uibutton(app.GridLayout10, 'push');
            app.Button_Cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_CancelPushed, true);
            app.Button_Cancel.Text = '取消';
            app.Tab_TaskNetwork = uitab(app.TabGroup);
            app.Tab_TaskNetwork.Title = '任务网络';
            app.GridLayout2 = uigridlayout(app.Tab_TaskNetwork);
            app.Panel_TaskTable = uipanel(app.GridLayout2);
            app.Panel_TaskTable.Title = '任务表';
            app.GridLayout3 = uigridlayout(app.Panel_TaskTable);
            app.UITable_TaskView = uitable(app.GridLayout3);
            app.UITable_TaskView.ColumnName = {'用户'; '状态'; '进度'; '用时'; '#CPU'; '#GPU'};
            app.UITable_TaskView.RowName = {};
            app.UITable_TaskView.SelectionType = 'row';
            app.Panel_TaskNetworkTopology = uipanel(app.GridLayout2);
            app.Panel_TaskNetworkTopology.Title = '网络拓扑';
            app.GridLayout4 = uigridlayout(app.Panel_TaskNetworkTopology);
            app.UIAxes_TaskNetwork = uiaxes(app.GridLayout4);
            zlabel(app.UIAxes_TaskNetwork, 'Z')
            app.UIAxes_TaskNetwork.XTick = [];
            app.UIAxes_TaskNetwork.YTick = [];
            app.Tab_ProjectManagement = uitab(app.TabGroup);
            app.Tab_ProjectManagement.Title = '项目管理';
            app.GridLayout15 = uigridlayout(app.Tab_ProjectManagement);
            app.Panel_Project = uipanel(app.GridLayout15);
            app.Panel_Project.Title = '我的项目';
            app.GridLayout17 = uigridlayout(app.Panel_Project);
            app.UITable_Project = uitable(app.GridLayout17);
            app.UITable_Project.ColumnName = {'项目名'; '项目路径'; '修改日期'; '大小(GB)'};
            app.UITable_Project.RowName = {};
            app.UITable_Project.ColumnSortable = true;
            app.UITable_Project.SelectionType = 'row';
            app.ContextMenu = uicontextmenu(app.ResourceManagerUIFigure);
            app.Menu_DeleteProject = uimenu(app.ContextMenu);
            app.Menu_DeleteProject.MenuSelectedFcn = createCallbackFcn(app, @Menu_DeleteProjectSelected, true);
            app.Menu_DeleteProject.Text = '删除';
            % Assign app.ContextMenu
            app.UITable_Project.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.ResourceManagerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResourceManager(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ResourceManagerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ResourceManagerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResourceManagerUIFigure)
        end
    end
end