classdef ScaleBar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScaleBarUIFigure      matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Panel_Scale           matlab.ui.container.Panel
        Label_TxtSpace        matlab.ui.control.Label
        Spinner_TxtSpace      matlab.ui.control.Spinner
        Label_6               matlab.ui.control.Label
        Label_LineHatUnit     matlab.ui.control.Label
        CheckBox_FillLineHat  matlab.ui.control.CheckBox
        Spinner_LineHat       matlab.ui.control.Spinner
        Label_5               matlab.ui.control.Label
        DropDown_LineHat      matlab.ui.control.DropDown
        Label_4               matlab.ui.control.Label
        Button_Font           matlab.ui.control.Button
        DropDown_Color        matlab.ui.control.DropDown
        Label_3               matlab.ui.control.Label
        Label_LineWidthUnit   matlab.ui.control.Label
        Spinner_LineWidth     matlab.ui.control.Spinner
        Label_2               matlab.ui.control.Label
        CheckBox_Vertical     matlab.ui.control.CheckBox
        CheckBox_Horizontal   matlab.ui.control.CheckBox
        EditField_YLen        matlab.ui.control.NumericEditField
        YEditField_2Label     matlab.ui.control.Label
        Label_YLen            matlab.ui.control.Label
        Label_XLen            matlab.ui.control.Label
        EditField_XLen        matlab.ui.control.NumericEditField
        XEditField_2Label     matlab.ui.control.Label
        Panel_Ratio           matlab.ui.container.Panel
        Label_ZRes            matlab.ui.control.Label
        EditField_ZRes        matlab.ui.control.NumericEditField
        ZEditFieldLabel       matlab.ui.control.Label
        Button_ResLock        matlab.ui.control.StateButton
        DropDown_LengthUnit   matlab.ui.control.DropDown
        Label                 matlab.ui.control.Label
        Label_YRes            matlab.ui.control.Label
        Label_XRes            matlab.ui.control.Label
        EditField_YRes        matlab.ui.control.NumericEditField
        YEditFieldLabel       matlab.ui.control.Label
        EditField_XRes        matlab.ui.control.NumericEditField
        XEditFieldLabel       matlab.ui.control.Label
    end

    properties (Constant)
        VER = "1.2.0"
    end

    properties (Constant, Hidden)
        COLOR_MAPPING = ["r","RED";...
                         "g","GREEN";...
                         "b","BLUE";...
                         "c","CYAN";...
                         "m","MAGENTA";...
                         "y","YELLOW";...
                         "k","BLACK";...
                         "w","WHITE"];
    end

    properties (Access = private, Hidden)
        caller;         % the parent app
        scale;          %
        bar;            %
        font;
        langmap;        % mapping for language
    end


    methods (Access = ?Register)

        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr >= 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_Ratio.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_Scale.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                case "DARK"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_Ratio.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_Scale.BackgroundColor = constdef.GRAYSCALE_DARK;
                otherwise
            end
        end

        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            app.Panel_Ratio.Title = app.langmap.RATIO;
            app.Panel_Scale.Title = app.langmap.SCALE;
            app.Label.Text = app.langmap.SCALE_UNIT;
            app.Label_2.Text = app.langmap.LINE_WIDTH;
            app.Label_3.Text = app.langmap.COLOR;
            app.Label_4.Text = app.langmap.LINE_HAT;
            app.Label_5.Text = app.langmap.HAT_SIZE;
            app.Label_6.Text = app.langmap.SPACE;
            app.XEditFieldLabel.Text = app.langmap.X_DIRECTION_RESOLUTION;
            app.YEditFieldLabel.Text = app.langmap.Y_DIRECTION_RESOLUTION;
            app.ZEditFieldLabel.Text = app.langmap.Z_DIRECTION_RESOLUTION;
            app.XEditField_2Label.Text = app.langmap.X_DIRECTION_LENGTH;
            app.YEditField_2Label.Text = app.langmap.Y_DIRECTION_LENGTH;
            app.CheckBox_Horizontal.Text = app.langmap.HORIZONTAL;
            app.CheckBox_Vertical.Text = app.langmap.VERTICAL;
            app.CheckBox_FillLineHat.Text = app.langmap.SOLID;
            app.DropDown_Color.Items = [app.langmap.RED, ...
                                        app.langmap.GREEN, ...
                                        app.langmap.BLUE, ...
                                        app.langmap.CYAN, ...
                                        app.langmap.MAGENTA, ...
                                        app.langmap.YELLOW, ...
                                        app.langmap.BLACK, ...
                                        app.langmap.WHITE, ...
                                        app.langmap.SELF_DEFINITION];
            app.DropDown_LineHat.Items = [app.langmap.NONE, ...
                                          app.langmap.FLAT_HAT, ...
                                          app.langmap.CIRCLE_HAT, ...
                                          app.langmap.SQUARE_HAT, ...
                                          app.langmap.DIAMOND_HAT];
            app.Button_Font.Text = app.langmap.FONT;
        end

        function RefreshTooltips(app)
        end

        function Close(app)
            delete(app);
        end
    end
    
    methods (Access = private)
        
        function status = InitPanel(app)
            info = app.caller.GetImgInfo();
            
            UpdateScaleBarParams(app);

            % setting scale panel data
            app.EditField_XRes.Value = info.xRes;
            app.EditField_YRes.Value = info.yRes;
            app.EditField_ZRes.Value = info.zRes;
            app.DropDown_LengthUnit.Value = app.scale.unit;
            app.EditField_XRes.Enable = "off";
            app.EditField_YRes.Enable = "off";
            app.EditField_ZRes.Enable = "off";
            app.DropDown_LengthUnit.Enable = "off";

            % setting bar panel data
            app.EditField_XLen.Value = app.bar.xlen;
            app.CheckBox_Horizontal.Value = app.bar.ishor;
            app.EditField_YLen.Value = app.bar.ylen;
            app.CheckBox_Vertical.Value = app.bar.isver;
            app.Spinner_LineWidth.Value = app.bar.linw;
            app.Spinner_LineHat.Value = app.bar.mk_size;
            app.Spinner_TxtSpace.Value = app.bar.txt_space;
            app.CheckBox_FillLineHat.Value = app.bar.mk_fill;

            if isnumeric(app.bar.color)
                app.DropDown_Color.Value = "SELF_DEFINITION";
            elseif isstring(app.bar.color)
                [~, p] = ismember(app.bar.color, app.COLOR_MAPPING(:,1));
                app.DropDown_Color.Value = app.COLOR_MAPPING(p,2);
            else
                error(app.langmap.ERROR);
            end
            
            switch app.bar.mk
                case "o"
                    app.DropDown_LineHat.Value = "CIRCLE";
                otherwise
                    app.DropDown_LineHat.Value = upper(app.bar.mk);
            end

            status = 0;
        end

        function UpdateScaleBarParams(app)
            sbar = app.caller.GetScaleBar();
            app.scale = sbar.Scale;
            app.bar = sbar.Bar;
            app.font = sbar.Font;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.caller = mainapp;

            InitPanel(app);

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);

            RefreshTooltips(app);
        end

        % Value changed function: EditField_XRes
        function EditField_XResValueChanged(app, event)
            value = app.EditField_XRes.Value;
            
            app.scale.xr = value;
            app.caller.SetScaleBar(app.scale, app.bar, app.font);
        end

        % Value changed function: EditField_YRes
        function EditField_YResValueChanged(app, event)
            value = app.EditField_YRes.Value;
            
            app.scale.yr = value;
            app.caller.SetScaleBar(app.scale, app.bar, app.font);
        end

        % Value changed function: DropDown_LengthUnit
        function DropDown_LengthUnitValueChanged(app, event)
            value = string(app.DropDown_LengthUnit.Value);

            app.Label_XRes.Text = value + "/pixel";
            app.Label_YRes.Text = value + "/pixel";

            app.scale.unit = value;
            app.caller.SetScaleBar(app.scale, app.bar, app.font);
        end

        % Value changed function: EditField_XLen
        function EditField_XLenValueChanged(app, event)
            value = app.EditField_XLen.Value;
            
            app.bar.xlen = value;
            app.caller.SetScaleBar(app.scale, app.bar, app.font);
        end

        % Value changed function: CheckBox_Horizontal
        function CheckBox_HorizontalValueChanged(app, event)
            value = app.CheckBox_Horizontal.Value;
            
            app.bar.ishor = value;
            app.caller.SetScaleBar(app.scale, app.bar, app.font);
        end

        % Value changed function: EditField_YLen
        function EditField_YLenValueChanged(app, event)
            value = app.EditField_YLen.Value;
            
            app.bar.ylen = value;
            app.caller.SetScaleBar(app.scale, app.bar, app.font);
        end

        % Value changed function: CheckBox_Vertical
        function CheckBox_VerticalValueChanged(app, event)
            value = app.CheckBox_Vertical.Value;
            
            app.bar.isver = value;
            app.caller.SetScaleBar(app.scale, app.bar, app.font);
        end

        % Value changed function: Spinner_LineWidth
        function Spinner_LineWidthValueChanged(app, event)
            value = app.Spinner_LineWidth.Value;
            
            app.bar.linw = value;
            app.caller.SetScaleBar(app.scale, app.bar, app.font);
        end

        % Value changed function: DropDown_Color
        function DropDown_ColorValueChanged(app, event)
            value = app.DropDown_Color.Value;

            [~, p] = ismember(value, app.COLOR_MAPPING(:,2));

            if p ~= 0
                value = app.COLOR_MAPPING(p,1);
            else
                value = uisetcolor([1,1,1]);
            end
            
            app.bar.color = value;
            app.caller.SetScaleBar(app.scale, app.bar, app.font);
        end

        % Button pushed function: Button_Font
        function Button_FontPushed(app, event)
            bar_old = app.caller.GetScaleBar();
            font_ = uisetfont(bar_old.Font);

            if isstruct(font_)
                app.font = font_;
                app.caller.SetScaleBar(app.scale, app.bar, app.font);
            end
        end

        % Value changed function: Button_ResLock
        function Button_ResLockValueChanged(app, event)
            value = app.Button_ResLock.Value;
            
            if value == true
                % unlock the resolution writable
                app.EditField_XRes.Enable = "on";
                app.EditField_YRes.Enable = "on";
                app.EditField_ZRes.Enable = "on";
                app.DropDown_LengthUnit.Enable = "on";

                app.Button_ResLock.Icon = "sources/unlock.png";
            else
                % % % lock the resolution writable
                app.EditField_XRes.Enable = "off";
                app.EditField_YRes.Enable = "off";
                app.EditField_ZRes.Enable = "off";
                app.DropDown_LengthUnit.Enable = "off";

                app.Button_ResLock.Icon = "sources/lock.png";
            end
        end

        % Value changed function: DropDown_LineHat
        function DropDown_LineHatValueChanged(app, event)
            value = app.DropDown_LineHat.Value;
            
            switch value
                case "CIRCLE"
                    app.bar.mk = "o";
                otherwise
                    app.bar.mk = lower(value);
            end

            app.caller.SetScaleBar(app.scale, app.bar, app.font);
        end

        % Value changed function: CheckBox_FillLineHat
        function CheckBox_FillLineHatValueChanged(app, event)
            value = app.CheckBox_FillLineHat.Value;
            
            app.bar.mk_fill = value;
            app.caller.SetScaleBar(app.scale, app.bar, app.font);
        end

        % Value changed function: Spinner_LineHat
        function Spinner_LineHatValueChanged(app, event)
            value = app.Spinner_LineHat.Value;
            
            app.bar.mk_size = value;
            app.caller.SetScaleBar(app.scale, app.bar, app.font);
        end

        % Value changed function: Spinner_TxtSpace
        function Spinner_TxtSpaceValueChanged(app, event)
            value = app.Spinner_TxtSpace.Value;
            
            app.bar.txt_space = value;
            app.caller.SetScaleBar(app.scale, app.bar, app.font);
        end

        % Close request function: ScaleBarUIFigure
        function ScaleBarUIFigureCloseRequest(app, event)
            Close(app);
            
        end

        % Value changed function: EditField_ZRes
        function EditField_ZResValueChanged(app, event)
            value = app.EditField_ZRes.Value;
            
            app.scale.zr = value;
            app.caller.SetScaleBar(app.scale, app.bar, app.font);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScaleBarUIFigure = uifigure('Visible', 'off');
            app.ScaleBarUIFigure.Name = 'Scale Bar';
            app.ScaleBarUIFigure.Icon = 'ruler.png';
            app.ScaleBarUIFigure.CloseRequestFcn = createCallbackFcn(app, @ScaleBarUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ScaleBarUIFigure);
            app.Panel_Ratio = uipanel(app.GridLayout);
            app.Panel_Ratio.Title = '比例';
            app.XEditFieldLabel = uilabel(app.Panel_Ratio);
            app.XEditFieldLabel.Text = 'X 方向分辨率';
            app.EditField_XRes = uieditfield(app.Panel_Ratio, 'numeric');
            app.EditField_XRes.Limits = [0 1];
            app.EditField_XRes.ValueDisplayFormat = '%.3f';
            app.EditField_XRes.ValueChangedFcn = createCallbackFcn(app, @EditField_XResValueChanged, true);
            app.EditField_XRes.Value = 0.1;
            app.YEditFieldLabel = uilabel(app.Panel_Ratio);
            app.YEditFieldLabel.Text = 'Y 方向分辨率';
            app.EditField_YRes = uieditfield(app.Panel_Ratio, 'numeric');
            app.EditField_YRes.Limits = [0 1];
            app.EditField_YRes.ValueDisplayFormat = '%.3f';
            app.EditField_YRes.ValueChangedFcn = createCallbackFcn(app, @EditField_YResValueChanged, true);
            app.EditField_YRes.Value = 0.1;
            app.Label_XRes = uilabel(app.Panel_Ratio);
            app.Label_XRes.Text = 'um/pixel';
            app.Label_YRes = uilabel(app.Panel_Ratio);
            app.Label_YRes.Text = 'um/pixel';
            app.Label = uilabel(app.Panel_Ratio);
            app.Label.Text = '长度单位';
            app.DropDown_LengthUnit = uidropdown(app.Panel_Ratio);
            app.DropDown_LengthUnit.Items = {'um', 'nm'};
            app.DropDown_LengthUnit.ItemsData = {'um', 'nm'};
            app.DropDown_LengthUnit.ValueChangedFcn = createCallbackFcn(app, @DropDown_LengthUnitValueChanged, true);
            app.DropDown_LengthUnit.Value = 'um';
            app.Button_ResLock = uibutton(app.Panel_Ratio, 'state');
            app.Button_ResLock.ValueChangedFcn = createCallbackFcn(app, @Button_ResLockValueChanged, true);
            app.Button_ResLock.Icon = 'lock.png';
            app.Button_ResLock.Text = '';
            app.ZEditFieldLabel = uilabel(app.Panel_Ratio);
            app.ZEditFieldLabel.Text = 'Z 方向分辨率';
            app.EditField_ZRes = uieditfield(app.Panel_Ratio, 'numeric');
            app.EditField_ZRes.Limits = [0 Inf];
            app.EditField_ZRes.ValueDisplayFormat = '%.3f';
            app.EditField_ZRes.ValueChangedFcn = createCallbackFcn(app, @EditField_ZResValueChanged, true);
            app.EditField_ZRes.Value = 0.1;
            app.Label_ZRes = uilabel(app.Panel_Ratio);
            app.Label_ZRes.Text = 'um/pixel';
            app.Panel_Scale = uipanel(app.GridLayout);
            app.Panel_Scale.Title = '尺';
            app.XEditField_2Label = uilabel(app.Panel_Scale);
            app.XEditField_2Label.Text = 'X方向长度';
            app.EditField_XLen = uieditfield(app.Panel_Scale, 'numeric');
            app.EditField_XLen.Limits = [0 Inf];
            app.EditField_XLen.ValueDisplayFormat = '%.1f';
            app.EditField_XLen.ValueChangedFcn = createCallbackFcn(app, @EditField_XLenValueChanged, true);
            app.EditField_XLen.Value = 10;
            app.Label_XLen = uilabel(app.Panel_Scale);
            app.Label_XLen.Text = 'um';
            app.Label_YLen = uilabel(app.Panel_Scale);
            app.Label_YLen.Text = 'um';
            app.YEditField_2Label = uilabel(app.Panel_Scale);
            app.YEditField_2Label.Text = 'Y方向长度';
            app.EditField_YLen = uieditfield(app.Panel_Scale, 'numeric');
            app.EditField_YLen.Limits = [0 Inf];
            app.EditField_YLen.ValueDisplayFormat = '%.1f';
            app.EditField_YLen.ValueChangedFcn = createCallbackFcn(app, @EditField_YLenValueChanged, true);
            app.EditField_YLen.Value = 10;
            app.CheckBox_Horizontal = uicheckbox(app.Panel_Scale);
            app.CheckBox_Horizontal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_HorizontalValueChanged, true);
            app.CheckBox_Horizontal.Text = '水平';
            app.CheckBox_Vertical = uicheckbox(app.Panel_Scale);
            app.CheckBox_Vertical.ValueChangedFcn = createCallbackFcn(app, @CheckBox_VerticalValueChanged, true);
            app.CheckBox_Vertical.Text = '垂直';
            app.Label_2 = uilabel(app.Panel_Scale);
            app.Label_2.Text = '线宽';
            app.Spinner_LineWidth = uispinner(app.Panel_Scale);
            app.Spinner_LineWidth.Limits = [1 5];
            app.Spinner_LineWidth.ValueDisplayFormat = '%.0f';
            app.Spinner_LineWidth.ValueChangedFcn = createCallbackFcn(app, @Spinner_LineWidthValueChanged, true);
            app.Spinner_LineWidth.Value = 1;
            app.Label_LineWidthUnit = uilabel(app.Panel_Scale);
            app.Label_LineWidthUnit.Text = 'pt';
            app.Label_3 = uilabel(app.Panel_Scale);
            app.Label_3.Text = '颜色';
            app.DropDown_Color = uidropdown(app.Panel_Scale);
            app.DropDown_Color.Items = {'红', '绿', '蓝', '靛青', '洋红', '黄', '黑', '白', '自定义'};
            app.DropDown_Color.ItemsData = {'RED', 'GREEN', 'BLUE', 'CYAN', 'MAGENTA', 'YELLOW', 'BLACK', 'WHITE', 'SELF_DEFINITION'};
            app.DropDown_Color.ValueChangedFcn = createCallbackFcn(app, @DropDown_ColorValueChanged, true);
            app.DropDown_Color.Value = 'WHITE';
            app.Button_Font = uibutton(app.Panel_Scale, 'push');
            app.Button_Font.ButtonPushedFcn = createCallbackFcn(app, @Button_FontPushed, true);
            app.Button_Font.Text = '字体';
            app.Label_4 = uilabel(app.Panel_Scale);
            app.Label_4.Text = '线帽';
            app.DropDown_LineHat = uidropdown(app.Panel_Scale);
            app.DropDown_LineHat.Items = {'无', '平帽', '圆帽', '方帽', '菱帽'};
            app.DropDown_LineHat.ItemsData = {'NONE', 'FLAT', 'CIRCLE', 'SQUARE', 'DIAMOND'};
            app.DropDown_LineHat.ValueChangedFcn = createCallbackFcn(app, @DropDown_LineHatValueChanged, true);
            app.DropDown_LineHat.Value = 'NONE';
            app.Label_5 = uilabel(app.Panel_Scale);
            app.Label_5.Text = '线帽大小';
            app.Spinner_LineHat = uispinner(app.Panel_Scale);
            app.Spinner_LineHat.Limits = [1 Inf];
            app.Spinner_LineHat.ValueDisplayFormat = '%.0f';
            app.Spinner_LineHat.ValueChangedFcn = createCallbackFcn(app, @Spinner_LineHatValueChanged, true);
            app.Spinner_LineHat.Value = 1;
            app.CheckBox_FillLineHat = uicheckbox(app.Panel_Scale);
            app.CheckBox_FillLineHat.ValueChangedFcn = createCallbackFcn(app, @CheckBox_FillLineHatValueChanged, true);
            app.CheckBox_FillLineHat.Text = '实心';
            app.Label_LineHatUnit = uilabel(app.Panel_Scale);
            app.Label_LineHatUnit.Text = 'pt';
            app.Label_6 = uilabel(app.Panel_Scale);
            app.Label_6.Text = '间距';
            app.Spinner_TxtSpace = uispinner(app.Panel_Scale);
            app.Spinner_TxtSpace.Limits = [1 Inf];
            app.Spinner_TxtSpace.ValueChangedFcn = createCallbackFcn(app, @Spinner_TxtSpaceValueChanged, true);
            app.Spinner_TxtSpace.Value = 1;
            app.Label_TxtSpace = uilabel(app.Panel_Scale);
            app.Label_TxtSpace.Text = 'pt';
            % Show the figure after all components are created
            app.ScaleBarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScaleBar(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ScaleBarUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ScaleBarUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScaleBarUIFigure)
        end
    end
end