classdef SignalEstimator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignaEstimatorV100UIFigure  matlab.ui.Figure
        Menu_Options                matlab.ui.container.Menu
        Menu_CaImAnInitValEst       matlab.ui.container.Menu
        Menu_LocalTracking          matlab.ui.container.Menu
        Menu_Help                   matlab.ui.container.Menu
        Menu_About                  matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        UITable_ObjectInfo          matlab.ui.control.Table
        GridLayout12                matlab.ui.container.GridLayout
        Button_Save                 matlab.ui.control.Button
        Button_Preview              matlab.ui.control.Button
        Button_Fit                  matlab.ui.control.Button
        GridLayout2                 matlab.ui.container.GridLayout
        Panel_ModelParameters       matlab.ui.container.Panel
        GridLayout16                matlab.ui.container.GridLayout
        Panel_FluorescenceModel     matlab.ui.container.Panel
        GridLayout10                matlab.ui.container.GridLayout
        GridLayout5                 matlab.ui.container.GridLayout
        EditField_FixedBackground   matlab.ui.control.NumericEditField
        Label_6                     matlab.ui.control.Label
        CheckBox_FluorescenceOnly   matlab.ui.control.CheckBox
        GridLayout4                 matlab.ui.container.GridLayout
        Button_AutoKernel           matlab.ui.control.StateButton
        DropDown_Kernel             matlab.ui.control.DropDown
        Label_5                     matlab.ui.control.Label
        Panel_BaselineModel         matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        TabGroup_BaselineModel      matlab.ui.container.TabGroup
        Tab_MovingQuantile          matlab.ui.container.Tab
        GridLayout7                 matlab.ui.container.GridLayout
        EditField_Window_MovingQuantile  matlab.ui.control.NumericEditField
        EditField_2Label            matlab.ui.control.Label
        Button_AutoQuantile_MovingQuantile  matlab.ui.control.StateButton
        EditField_MinValue_MovingQuantile  matlab.ui.control.NumericEditField
        QuantileminLabel            matlab.ui.control.Label
        Tab_MixedExponential        matlab.ui.container.Tab
        GridLayout18                matlab.ui.container.GridLayout
        GridLayout20                matlab.ui.container.GridLayout
        Button_AutoOrder_MixedExponential  matlab.ui.control.StateButton
        Spinner_RegressionOrder_MixedExponential  matlab.ui.control.Spinner
        Label_4                     matlab.ui.control.Label
        GridLayout19                matlab.ui.container.GridLayout
        EditField_Grid_MixedExponential  matlab.ui.control.NumericEditField
        Label_9                     matlab.ui.control.Label
        EditField_MinValue_MixedExponential  matlab.ui.control.NumericEditField
        QuantileminLabel_2          matlab.ui.control.Label
        Panel_NoiseModel            matlab.ui.container.Panel
        GridLayout14                matlab.ui.container.GridLayout
        DropDown_PSNR_Th            matlab.ui.control.DropDown
        Label_8                     matlab.ui.control.Label
        DropDown_NoiseDistribution  matlab.ui.control.DropDown
        Label_7                     matlab.ui.control.Label
        Panel_Components            matlab.ui.container.Panel
        GridLayout15                matlab.ui.container.GridLayout
        GridLayout17                matlab.ui.container.GridLayout
        Button_CheckInverse         matlab.ui.control.Button
        Button_CheckAll             matlab.ui.control.Button
        Tree_Components             matlab.ui.container.CheckBoxTree
    end

    properties (Constant)
        VER = "1.0.1"
    end
    
    properties (Access = private, Hidden)
        caller          % 1-by-1 Register, the parent app

        mask
        comp_nodes      % 1-by-s cell, items are uitreenode
        comps_slt       % 1-by-k positive integer array, labels in mask, processing indices
        activities      % s-by-t double array, only comps_slt indicated rows are not nan
        img_ext         % 1-by-1 regmov object, the extracted image
        sig_channel     % 1-by-1 string, "r","g" or "b", indicate the signal channel
        message_level   % 1-by-1 string control message display level
        opt_sig         % 1-by-1 sigopt object, signal extraction options
        fit_flag        % s-by-1 logical array, indicate the fitted components
        caiman_flag     % 1-by-1 logical, the caiman data fitting flag
        prev_fig        % 1-by-1 figure object, preview figure
        estimator       % 1-by-1 SignalExtractor object
        langmap         % 1-by-1 struct, mapping for language
    end
    
    methods (Access = public)

        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr >= 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.UITable_ObjectInfo.BackgroundColor = constdef.GRAYSCALE_AXES_LIGHT;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_Components.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout15.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout17.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_ModelParameters.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout16.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_FluorescenceModel.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout10.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout7.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout14.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout18.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout19.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout20.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Tree_Components.BackgroundColor = constdef.GRAYSCALE_AXES_LIGHT;
                case "DARK"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.UITable_ObjectInfo.BackgroundColor = constdef.GRAYSCALE_AXES_DARK;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_Components.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout15.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout17.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_ModelParameters.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout16.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_FluorescenceModel.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout10.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout7.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout14.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout18.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout19.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout20.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Tree_Components.BackgroundColor = constdef.GRAYSCALE_AXES_DARK;
                otherwise
            end
        end
        
        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            app.Panel_Components.Title = app.langmap.MASK_COMPONENTS;
            app.Panel_ModelParameters.Title = app.langmap.MODEL_PARAMETERS;
            app.Panel_BaselineModel.Title = app.langmap.BASELINE_MODEL;
            app.Panel_FluorescenceModel.Title = app.langmap.FLUORESCENCE_MODEL;
            app.Panel_NoiseModel.Title = app.langmap.NOISE_MODEL;
            app.Menu_Options.Text = app.langmap.OPTION;
            app.Menu_Help.Text = app.langmap.HELP;
            app.Menu_CaImAnInitValEst.Text = app.langmap.WITH_CAIMAN;
            app.Menu_About.Text = app.langmap.ABOUT;
            app.Tab_MovingQuantile.Title = app.langmap.MOVING_QUANTILE;
            app.Tab_MixedExponential.Title = app.langmap.MIXED_EXPONENTIAL;
            app.QuantileminLabel.Text = app.langmap.QUANTILE;
            app.Button_AutoQuantile_MovingQuantile.Text = app.langmap.AUTO;
            app.EditField_2Label.Text = app.langmap.WINDOW;
            app.QuantileminLabel_2.Text = app.langmap.QUANTILE;
            app.Label_4.Text = app.langmap.REGRESSION_ORDER;
            app.Button_AutoOrder_MixedExponential.Text = app.langmap.AUTO;
            app.Label_5.Text = app.langmap.KERNEL;
            app.DropDown_Kernel.Items = [app.langmap.KERNEL_UNIFORM, ...
                app.langmap.KERNEL_GAUSSIAN, app.langmap.KERNEL_LAPLACIAN_GAUSSIAN];
            app.Label_6.Text = app.langmap.FIXED_BACKGROUND;
            app.Button_AutoKernel.Text = app.langmap.AUTO;
            app.CheckBox_FluorescenceOnly.Text = app.langmap.FLUORESCENCE_ONLY;
            app.DropDown_NoiseDistribution.Items = [app.langmap.NORMAL_DISTRIBUTION, ...
                app.langmap.NONE];
            app.Label_9.Text = app.langmap.GRID_SPACING;
            app.Label_7.Text = app.langmap.DISTRIBUTION;
            app.UITable_ObjectInfo.ColumnName = [app.langmap.CENTROID, ...
                app.langmap.EQUIVALENT_RADIUS, app.langmap.SURFACE_AREA, ...
                app.langmap.VOLUME];
            app.Button_CheckAll.Text = app.langmap.CHECK_ALL;
            app.Button_CheckInverse.Text = app.langmap.INVERSE_CHECK;
            app.Button_Fit.Text = app.langmap.FIT;
            app.Button_Preview.Text = app.langmap.PREVIEW;
            app.Button_Save.Text = app.langmap.SAVE;
        end

        function RefreshTooltips(app)
        end

        function RefreshMessageLevel(app)
            app.message_level = app.caller.GetAppMessageLevel();
        end

        function Refresh(app, mainapp)
            InitData(app, mainapp);
            InitPanel(app);
        end

        function Close(app)
            % clear labels
            app.caller.HighlightROI([]);

            delete(app)
        end
    end

    methods (Access = private)

        function InitData(app, mainapp)
            app.caller = mainapp;
            app.mask = app.caller.GetMask();
            app.comp_nodes = {};
            app.comps_slt = [];
            app.img_ext = app.caller.GetCurrentSource();
            app.opt_sig = app.caller.GetSigOptions();
            app.sig_channel = app.caller.GetSignalChannel();
            app.fit_flag = false(app.mask.NumComps, 1);
            app.caiman_flag = false;
            app.activities = nan(app.mask.NumComps, app.img_ext.MetaData.frames);
            app.prev_fig = [];

            app.estimator = Estimator(app.img_ext, app.mask, app.caller);
        end

        function InitPanel(app)
            InitMaskComponents(app);
            InitModelParametersPanel(app);
            InitControlButtons(app);
        end

        function InitMaskComponents(app)
            % clear components tree
            for k = 1:numel(app.comp_nodes)
                delete(app.comp_nodes{k});
            end
            app.comp_nodes = {};

            % generate new nodes
            app.comp_nodes = cell(1, app.mask.NumComps);

            % assume labels number is equal to components number
            mask_idx = unique(app.mask.MaskVol);
            mask_idx(mask_idx==0) = [];     % remove background

            for k = 1:numel(app.comp_nodes)
                tmpnode = uitreenode(app.Tree_Components);
                tmpnode.Text = app.mask.MaskLabel(k);
                tmpnode.NodeData = mask_idx(k);

                app.comp_nodes{k} = tmpnode;
            end

            % add unplot style to all nodes
            img_style = uistyle("Icon", "warning");

            addStyle(app.Tree_Components, img_style, "level", 1);
        end

        function InitModelParametersPanel(app)
            % Init Fluorescence Model Parameters
            app.DropDown_Kernel.Value = ...
                app.opt_sig.Options.Kernel;
            app.Button_AutoKernel.Value = ...
                app.opt_sig.Options.AutoKernel;
            if app.opt_sig.Options.AutoKernel == true
                app.Button_AutoKernel.BackgroundColor = [0, 1, 0];
                app.DropDown_Kernel.Enable = "off";
            else
                app.Button_AutoKernel.BackgroundColor = [0.96, 0.96, 0.96];
                app.DropDown_Kernel.Enable = "on";
            end
            app.EditField_FixedBackground.Value = ...
                app.opt_sig.Options.Background;
            app.CheckBox_FluorescenceOnly.Value = ...
                app.opt_sig.Options.FluorescenceOnly;

            % Init Baseline Model Parameters
            switch app.opt_sig.BaselineModel
                case "MovingQuantile"
                    % moving quantile min value
                    app.EditField_MinValue_MovingQuantile.Value = ...
                        app.opt_sig.Options.MinQuantileValue;
                    app.Button_AutoQuantile_MovingQuantile.Value = ...
                        app.opt_sig.Options.AutoQuantile;
                    if app.opt_sig.Options.AutoQuantile == true
                        app.Button_AutoQuantile_MovingQuantile.BackgroundColor = [0, 1, 0];
                        app.EditField_MinValue_MovingQuantile.Editable = "off";
                    else
                        app.Button_AutoQuantile_MovingQuantile.BackgroundColor = [0.96, 0.96, 0.96];
                        app.EditField_MinValue_MovingQuantile.Editable = "on";
                    end

                    % window size
                    app.EditField_Window_MovingQuantile.Value = ...
                        app.opt_sig.Options.WindowSize;

                    % focus on tab
                    app.TabGroup_BaselineModel.SelectedTab = ...
                        app.Tab_MovingQuantile;
                case "MixedExponential"
                    % moving quantile min value
                    app.EditField_MinValue_MixedExponential.Value = ...
                        app.opt_sig.Options.MinQuantileValue;

                    % regression order
                    app.Spinner_RegressionOrder_MixedExponential.Value = ...
                        app.opt_sig.Options.RegressOrder;
                    app.Button_AutoOrder_MixedExponential.Value = ...
                        app.opt_sig.Options.AutoRegress;
                    if app.opt_sig.Options.AutoRegress == true
                        app.Button_AutoOrder_MixedExponential.BackgroundColor = [0, 1, 0];
                        app.Spinner_RegressionOrder_MixedExponential.Editable = "off";
                    else
                        app.Button_AutoOrder_MixedExponential.BackgroundColor = [0.96, 0.96, 0.96];
                        app.Spinner_RegressionOrder_MixedExponential.Editable = "on";
                    end

                    % focus on tab
                    app.TabGroup_BaselineModel.SelectedTab = ...
                        app.Tab_MixedExponential;
                otherwise
            end

            % Init Noise Model
            app.DropDown_NoiseDistribution.Value = app.opt_sig.Options.NoiseModel;
            app.DropDown_PSNR_Th.Value = app.opt_sig.Options.PSNR_Threshold;
        end

        function InitControlButtons(app)
            app.Button_Fit.Enable = "off";
            app.Button_Preview.Enable = "off";
            app.Button_Save.Enable = "off";
        end

        function UpdateObjInfo(app, comp)
           comp_mask = (app.mask.MaskVol == comp);
           stats = regionprops3(comp_mask, "Centroid", "EquivDiameter", ...
               "SurfaceArea", "Volume");
           C = sprintf("(%d, %d, %d)", round(stats.Centroid));
           R = sprintf("%.1f",  round(stats.EquivDiameter/2, 1));
           S = sprintf("%.1f",  round(stats.SurfaceArea, 1));
           V = sprintf("%d",    stats.Volume);

           app.UITable_ObjectInfo.Data = table(C, R, S, V);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            InitData(app, mainapp);

            InitPanel(app);

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);

            RefreshTooltips(app);

            RefreshMessageLevel(app);
        end

        % Close request function: SignaEstimatorV100UIFigure
        function SignaEstimatorV100UIFigureCloseRequest(app, event)
            Close(app);

        end

        % Callback function: Tree_Components
        function Tree_ComponentsCheckedNodesChanged(app, event)
            chkNodes = app.Tree_Components.CheckedNodes;
            
            app.comps_slt = nan(1, numel(chkNodes));

            for k = 1:numel(app.comps_slt)
                app.comps_slt(k) = chkNodes(k).NodeData;
            end

            % Highlight the ROIs
            app.caller.HighlightROI(app.comps_slt);

            if ~isempty(app.comps_slt)
                % enable fit button
                app.Button_Fit.Enable = "on";
                if any(app.fit_flag)
                    app.Button_Preview.Enable = "on";
                    app.Button_Save.Enable = "on";
                end
            else
                % disable all button
                app.Button_Fit.Enable = "off";
                app.Button_Preview.Enable = "off";
                app.Button_Save.Enable = "off";
            end
        end

        % Selection changed function: Tree_Components
        function Tree_ComponentsSelectionChanged(app, event)
            sltNode = app.Tree_Components.SelectedNodes;
            compidx = sltNode.NodeData;

            UpdateObjInfo(app, compidx);
        end

        % Node text changed function: Tree_Components
        function Tree_ComponentsNodeTextChanged(app, event)
            node = event.Node;

            mask_idx = unique(app.mask.MaskVol);
            mask_idx(mask_idx==0) = [];     % remove background
            ridx = find(mask_idx == node.NodeData);

            app.mask.MaskLabel(ridx) = node.Text;
            app.caller.RenameROI(ridx, string(node.Text));
        end

        % Value changed function: EditField_MinValue_MovingQuantile
        function EditField_MinValue_MovingQuantileValueChanged(app, event)
            value = app.EditField_MinValue_MovingQuantile.Value;
            
            app.opt_sig = app.opt_sig.set("MinQuantileValue", value);

            app.caller.SetSigOptions(app.opt_sig);
        end

        % Value changed function: Button_AutoQuantile_MovingQuantile
        function Button_AutoQuantile_MovingQuantileValueChanged(app, event)
            value = app.Button_AutoQuantile_MovingQuantile.Value;

            if value == true
                app.EditField_MinValue_MovingQuantile.Editable = "off";
                app.Button_AutoQuantile_MovingQuantile.BackgroundColor = [0, 1, 0];
            else
                app.EditField_MinValue_MovingQuantile.Editable = "on";
                app.Button_AutoQuantile_MovingQuantile.BackgroundColor = [0.96, 0.96, 0.96];
            end

            app.opt_sig = app.opt_sig.set("AutoQuantile", value);

            app.caller.SetSigOptions(app.opt_sig);
        end

        % Value changed function: EditField_Window_MovingQuantile
        function EditField_Window_MovingQuantileValueChanged(app, event)
            value = app.EditField_Window_MovingQuantile.Value;
            
            app.opt_sig = app.opt_sig.set("WindowSize", value);

            app.caller.SetSigOptions(app.opt_sig);
        end

        % Value changed function: EditField_MinValue_MixedExponential
        function EditField_MinValue_MixedExponentialValueChanged(app, event)
            value = app.EditField_MinValue_MixedExponential.Value;

            app.opt_sig = app.opt_sig.set("MinQuantileValue", value);

            app.caller.SetSigOptions(app.opt_sig);
        end

        % Value changed function: Spinner_RegressionOrder_MixedExponential
        function Spinner_RegressionOrder_MixedExponentialValueChanged(app, event)
            value = app.Spinner_RegressionOrder_MixedExponential.Value;
            
            app.opt_sig = app.opt_sig.set("RegressOrder", value);

            app.caller.SetSigOptions(app.opt_sig);
        end

        % Value changed function: Button_AutoOrder_MixedExponential
        function Button_AutoOrder_MixedExponentialValueChanged(app, event)
            value = app.Button_AutoOrder_MixedExponential.Value;
            
            if value == true
                app.Spinner_RegressionOrder_MixedExponential.Editable = "off";
                app.Button_AutoOrder_MixedExponential.BackgroundColor = [0, 1, 0];
            else
                app.Spinner_RegressionOrder_MixedExponential.Editable = "on";
                app.Button_AutoOrder_MixedExponential.BackgroundColor = [0.96, 0.96, 0.96];
            end

            app.opt_sig = app.opt_sig.set("AutoRegress", value);

            app.caller.SetSigOptions(app.opt_sig);
        end

        % Selection change function: TabGroup_BaselineModel
        function TabGroup_BaselineModelSelectionChanged(app, event)
            selectedTab = app.TabGroup_BaselineModel.SelectedTab;
            
            switch selectedTab
                case app.Tab_MovingQuantile
                    app.opt_sig.BaselineModel = "MovingQuantile";
                case app.Tab_MixedExponential
                    app.opt_sig.BaselineModel = "MixedExponential";
                otherwise
            end
        end

        % Value changed function: Button_AutoKernel
        function Button_AutoKernelValueChanged(app, event)
            value = app.Button_AutoKernel.Value;

            if value == true
                app.Button_AutoKernel.BackgroundColor = [0, 1, 0];
                app.DropDown_Kernel.Enable = "off";
            else
                app.DropDown_Kernel.Enable = "on";
                app.Button_AutoKernel.BackgroundColor = [0.96, 0.96, 0.96];
            end
            
            app.opt_sig = app.opt_sig.set("AutoKernel", value);

            app.caller.SetSigOptions(app.opt_sig);
        end

        % Value changed function: CheckBox_FluorescenceOnly
        function CheckBox_FluorescenceOnlyValueChanged(app, event)
            value = app.CheckBox_FluorescenceOnly.Value;
            
            app.opt_sig = app.opt_sig.set("FluorescenceOnly", value);

            app.caller.SetSigOptions(app.opt_sig);
        end

        % Value changed function: DropDown_NoiseDistribution
        function DropDown_NoiseDistributionValueChanged(app, event)
            value = app.DropDown_NoiseDistribution.Value;

            switch value
                case {'normal', 'none'}
                    app.opt_sig = app.opt_sig.set("NoiseModel", value);
                otherwise
                    switch app.message_level
                        case constdef.MESSAGE_INFO
                            uialert(app.SignaEstimatorV100UIFigure, ...
                                app.langmap.DEVELOPING, app.langmap.INFORMATION, ...
                                "Icon","info");
                        otherwise
                    end
                    app.DropDown_NoiseDistribution.Value = "normal";
                    return;
            end

            app.caller.SetSigOptions(app.opt_sig);
        end

        % Button pushed function: Button_Fit
        function Button_FitPushed(app, event)
            % fit the calcium fluorescence model
            try
            uidlg = uiprogressdlg(app.SignaEstimatorV100UIFigure, ...
                "Indeterminate","on", "Message",app.langmap.FITTING_ACTIVITY, ...
                "Icon","info");

            app.estimator.fit(app.opt_sig, app.sig_channel, ...
                app.comps_slt, app.caiman_flag);
            
            % set the extracted data
            comps_raw_labels = ...
                cellfun(@(x)x.NodeData, app.comp_nodes, "UniformOutput", true);
            [~, indices] = ismember(app.comps_slt, comps_raw_labels);

            app.activities = app.estimator.Activities;
            sig = app.estimator.Significant;

            app.fit_flag = false(size(app.fit_flag));
            app.fit_flag(indices) = true;

            % remark the components status
            warning_style = uistyle("Icon","warning");
            success_style = uistyle("Icon","success");
            question_style = uistyle("Icon","question");

            removeStyle(app.Tree_Components);
            addStyle(app.Tree_Components, warning_style, "level", 1);

            for k = indices
                if sig(k) == true
                    addStyle(app.Tree_Components, success_style, ...
                        "node", app.comp_nodes{k});
                else
                    addStyle(app.Tree_Components, question_style, ...
                        "node", app.comp_nodes{k});
                end
            end

            if ~isempty(indices)
                app.Button_Preview.Enable = "on";
                app.Button_Save.Enable = "on";
            else
                app.Button_Preview.Enable = "off";
                app.Button_Save.Enable = "off";
            end

            delete(uidlg);

            app.caller.PushInfo("SignalEstimator", "Button_FitPushed");
            catch ME
                app.caller.PushInfo("SignalEstimator", "Button_FitPushed", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Button pushed function: Button_Preview
        function Button_PreviewPushed(app, event)
            try
                % generate a view figure
                comps_raw_labels = ...
                    cellfun(@(x)x.NodeData, app.comp_nodes, "UniformOutput", true);
                [~, indices] = ismember(app.comps_slt, comps_raw_labels);

                if ~all(ismember(indices, find(app.fit_flag)))
                    switch app.message_level
                        case [constdef.MESSAGE_WARNING, constdef.MESSAGE_INFO]
                            uialert(app.SignaEstimatorV100UIFigure, ...
                                app.langmap.IGNORE_UNFIT_ITEMS, app.langmap.WARNING, ...
                                "Icon","warning");
                        otherwise
                    end
                end

                % only view the intersect of two set
                indices = intersect(indices, find(app.fit_flag));
                indices = reshape(indices, 1, []);

                if ~isempty(app.prev_fig) && isvalid(app.prev_fig)
                    close(app.prev_fig);
                end
                app.prev_fig = figure("Name","Activity Preview");

                setFigure(app.prev_fig, [0,0, numel(app.img_ext.Time)/2400*1.5+0.5, 0.4]);
                ax = axes(app.prev_fig);
                color = jet(numel(app.fit_flag));
                for k = indices
                    plot(ax, app.img_ext.Time, app.activities(k, :)', ...
                        "Color", color(k, :), "LineWidth",1, "LineStyle","-");
                    hold(ax, "on");
                end

                xlim(ax, [min(app.img_ext.Time)-1, max(app.img_ext.Time)+1]);
                set(ax, "FontSize", 12);

                legend(ax, app.mask.MaskLabel(indices), "FontSize", 14, ...
                    "Location","eastoutside","NumColumns",ceil(numel(indices)/16));
                hold(ax, "off");

                xlabel("Time /s", "FontSize",14);
                if app.opt_sig.Options.FluorescenceOnly == true
                    ylabel("Mag. F", "FontSize", 14);
                else
                    ylabel("\DeltaF/F", "FontSize",14);
                end
            catch ME
                app.caller.PushInfo("SignalEstimator", "Button_PreviewPushed", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Button pushed function: Button_Save
        function Button_SavePushed(app, event)
            try
                path = uigetdir(userpath, app.langmap.SELECTION_SAVING_FOLDER);

                if ~isnumeric(path)
                    file = app.caller.GetFileName();
                    app.estimator.save(app.comps_slt, path, file, app.caiman_flag);
                end
            catch ME
                app.caller.PushInfo("SignalEstimator", "Button_SavePushed", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Button pushed function: Button_CheckAll
        function Button_CheckAllPushed(app, event)
            % check all nodes
            app.Tree_Components.CheckedNodes = [];

            app.comps_slt = nan(1, numel(app.comp_nodes));

            for k = 1:numel(app.comp_nodes)
                app.Tree_Components.CheckedNodes = ...
                    [app.Tree_Components.CheckedNodes; app.comp_nodes{k}];
                app.comps_slt(k) = app.comp_nodes{k}.NodeData;
            end

            % enable fit
            app.Button_Fit.Enable = "on";
            if any(app.fit_flag)
                app.Button_Preview.Enable = "on";
                app.Button_Save.Enable = "on";
            end

            % send Highlight signal
            app.caller.HighlightROI(app.comps_slt);

        end

        % Button pushed function: Button_CheckInverse
        function Button_CheckInversePushed(app, event)
            % inverse checked status
            if isempty(app.comps_slt)
                % disable inverse function
                return;
            end

            inv_comp_slt = [];
            app.comps_slt = [];
            for k = 1:numel(app.comp_nodes)
                if ~ismember(app.comp_nodes{k}, app.Tree_Components.CheckedNodes)
                    inv_comp_slt = [inv_comp_slt; app.comp_nodes{k}]; %#ok<AGROW>
                    app.comps_slt = [app.comps_slt, app.comp_nodes{k}.NodeData];
                end
            end

            app.Tree_Components.CheckedNodes = inv_comp_slt;

            if isempty(inv_comp_slt)
                app.Button_Fit.Enable = "off";
                app.Button_Preview.Enable = "off";
                app.Button_Save.Enable = "off";
            else
                app.Button_Fit.Enable = "on";
                if any(app.fit_flag)
                    app.Button_Preview.Enable = "on";
                    app.Button_Save.Enable = "on";
                end
            end

            % send Highlight signal
            app.caller.HighlightROI(app.comps_slt);

        end

        % Value changed function: DropDown_Kernel
        function DropDown_KernelValueChanged(app, event)
            value = app.DropDown_Kernel.Value;
            
            app.opt_sig = app.opt_sig.set("Kernel", value);

            app.caller.SetSigOptions(app.opt_sig);
        end

        % Value changed function: EditField_FixedBackground
        function EditField_FixedBackgroundValueChanged(app, event)
            value = app.EditField_FixedBackground.Value;
            
            app.opt_sig = app.opt_sig.set("Background", value);

            app.caller.SetSigOptions(app.opt_sig);
        end

        % Value changed function: DropDown_PSNR_Th
        function DropDown_PSNR_ThValueChanged(app, event)
            value = app.DropDown_PSNR_Th.Value;
            
            app.opt_sig = app.opt_sig.set("PSNR_Threshold", value);

            app.caller.SetSigOptions(app.opt_sig);
        end

        % Menu selected function: Menu_CaImAnInitValEst
        function Menu_CaImAnInitValEstSelected(app, event)
            app.caiman_flag = ~app.caiman_flag;

            if app.caiman_flag == true
                app.Menu_CaImAnInitValEst.Checked = "on";
            else
                app.Menu_CaImAnInitValEst.Checked = "off";
            end
        end

        % Menu selected function: Menu_About
        function Menu_AboutSelected(app, event)
            msg = sprintf(['Signal Estimator for Register\n' ...
                'Version: 1.0.0 \n' ...
                'Author: Weihan Li \n' ...
                'E-mail: liweihan20@mails.ucas.ac.cn']);
            uialert(app.SignaEstimatorV100UIFigure, msg, ...
                app.langmap.INFORMATION, "Icon","info");
        end

        % Value changed function: EditField_Grid_MixedExponential
        function EditField_Grid_MixedExponentialValueChanged(app, event)
            value = app.EditField_Grid_MixedExponential.Value;
            
            app.opt_sig = app.opt_sig.set("GridStep", value);

            app.caller.SetSigOptions(app.opt_sig);
        end

        % Menu selected function: Menu_LocalTracking
        function Menu_LocalTrackingSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignaEstimatorV100UIFigure = uifigure('Visible', 'off');
            app.SignaEstimatorV100UIFigure.Name = 'Signa Estimator V1.0.0';
            app.SignaEstimatorV100UIFigure.Icon = 'estimator.png';
            app.SignaEstimatorV100UIFigure.CloseRequestFcn = createCallbackFcn(app, @SignaEstimatorV100UIFigureCloseRequest, true);
            app.Menu_Options = uimenu(app.SignaEstimatorV100UIFigure);
            app.Menu_Options.Text = '选项';
            app.Menu_CaImAnInitValEst = uimenu(app.Menu_Options);
            app.Menu_CaImAnInitValEst.MenuSelectedFcn = createCallbackFcn(app, @Menu_CaImAnInitValEstSelected, true);
            app.Menu_CaImAnInitValEst.Text = 'CaImAn 初值估计';
            app.Menu_LocalTracking = uimenu(app.Menu_Options);
            app.Menu_LocalTracking.MenuSelectedFcn = createCallbackFcn(app, @Menu_LocalTrackingSelected, true);
            app.Menu_LocalTracking.Enable = 'off';
            app.Menu_LocalTracking.Text = '局部追踪';
            app.Menu_Help = uimenu(app.SignaEstimatorV100UIFigure);
            app.Menu_Help.Text = '帮助';
            app.Menu_About = uimenu(app.Menu_Help);
            app.Menu_About.MenuSelectedFcn = createCallbackFcn(app, @Menu_AboutSelected, true);
            app.Menu_About.Text = '关于';
            app.GridLayout = uigridlayout(app.SignaEstimatorV100UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Panel_Components = uipanel(app.GridLayout2);
            app.Panel_Components.Title = '蒙版组分';
            app.GridLayout15 = uigridlayout(app.Panel_Components);
            app.Tree_Components = uitree(app.GridLayout15, 'checkbox');
            app.Tree_Components.SelectionChangedFcn = createCallbackFcn(app, @Tree_ComponentsSelectionChanged, true);
            app.Tree_Components.NodeTextChangedFcn = createCallbackFcn(app, @Tree_ComponentsNodeTextChanged, true);
            app.Tree_Components.Editable = 'on';
            % Assign Checked Nodes
            app.Tree_Components.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_ComponentsCheckedNodesChanged, true);
            app.GridLayout17 = uigridlayout(app.GridLayout15);
            app.Button_CheckAll = uibutton(app.GridLayout17, 'push');
            app.Button_CheckAll.ButtonPushedFcn = createCallbackFcn(app, @Button_CheckAllPushed, true);
            app.Button_CheckAll.Text = '全选';
            app.Button_CheckInverse = uibutton(app.GridLayout17, 'push');
            app.Button_CheckInverse.ButtonPushedFcn = createCallbackFcn(app, @Button_CheckInversePushed, true);
            app.Button_CheckInverse.Text = '反选';
            app.Panel_ModelParameters = uipanel(app.GridLayout2);
            app.Panel_ModelParameters.Title = '模型参数';
            app.GridLayout16 = uigridlayout(app.Panel_ModelParameters);
            app.Panel_NoiseModel = uipanel(app.GridLayout16);
            app.Panel_NoiseModel.Title = '噪声模型';
            app.GridLayout14 = uigridlayout(app.Panel_NoiseModel);
            app.Label_7 = uilabel(app.GridLayout14);
            app.Label_7.Text = '分布';
            app.DropDown_NoiseDistribution = uidropdown(app.GridLayout14);
            app.DropDown_NoiseDistribution.Items = {'正态', '无'};
            app.DropDown_NoiseDistribution.ItemsData = {'normal', 'none'};
            app.DropDown_NoiseDistribution.ValueChangedFcn = createCallbackFcn(app, @DropDown_NoiseDistributionValueChanged, true);
            app.DropDown_NoiseDistribution.Value = 'none';
            app.Label_8 = uilabel(app.GridLayout14);
            app.Label_8.Text = 'PSNR';
            app.DropDown_PSNR_Th = uidropdown(app.GridLayout14);
            app.DropDown_PSNR_Th.Items = {'10', '20', '30'};
            app.DropDown_PSNR_Th.ItemsData = {'10', '20', '30'};
            app.DropDown_PSNR_Th.ValueChangedFcn = createCallbackFcn(app, @DropDown_PSNR_ThValueChanged, true);
            app.DropDown_PSNR_Th.Value = '10';
            app.Panel_BaselineModel = uipanel(app.GridLayout16);
            app.Panel_BaselineModel.Title = '基线模型';
            app.GridLayout3 = uigridlayout(app.Panel_BaselineModel);
            app.TabGroup_BaselineModel = uitabgroup(app.GridLayout3);
            app.TabGroup_BaselineModel.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_BaselineModelSelectionChanged, true);
            app.Tab_MovingQuantile = uitab(app.TabGroup_BaselineModel);
            app.Tab_MovingQuantile.Title = '移动分位数';
            app.GridLayout7 = uigridlayout(app.Tab_MovingQuantile);
            app.QuantileminLabel = uilabel(app.GridLayout7);
            app.QuantileminLabel.Text = '分位值';
            app.EditField_MinValue_MovingQuantile = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_MinValue_MovingQuantile.Limits = [0 100];
            app.EditField_MinValue_MovingQuantile.RoundFractionalValues = 'on';
            app.EditField_MinValue_MovingQuantile.ValueDisplayFormat = '%.1f';
            app.EditField_MinValue_MovingQuantile.ValueChangedFcn = createCallbackFcn(app, @EditField_MinValue_MovingQuantileValueChanged, true);
            app.EditField_MinValue_MovingQuantile.Value = 10;
            app.Button_AutoQuantile_MovingQuantile = uibutton(app.GridLayout7, 'state');
            app.Button_AutoQuantile_MovingQuantile.ValueChangedFcn = createCallbackFcn(app, @Button_AutoQuantile_MovingQuantileValueChanged, true);
            app.Button_AutoQuantile_MovingQuantile.Text = '自动';
            app.EditField_2Label = uilabel(app.GridLayout7);
            app.EditField_2Label.Text = '窗口';
            app.EditField_Window_MovingQuantile = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_Window_MovingQuantile.Limits = [0 Inf];
            app.EditField_Window_MovingQuantile.RoundFractionalValues = 'on';
            app.EditField_Window_MovingQuantile.ValueChangedFcn = createCallbackFcn(app, @EditField_Window_MovingQuantileValueChanged, true);
            app.EditField_Window_MovingQuantile.Value = 100;
            app.Tab_MixedExponential = uitab(app.TabGroup_BaselineModel);
            app.Tab_MixedExponential.Title = '混合指数';
            app.GridLayout18 = uigridlayout(app.Tab_MixedExponential);
            app.GridLayout19 = uigridlayout(app.GridLayout18);
            app.QuantileminLabel_2 = uilabel(app.GridLayout19);
            app.QuantileminLabel_2.Text = '分位值';
            app.EditField_MinValue_MixedExponential = uieditfield(app.GridLayout19, 'numeric');
            app.EditField_MinValue_MixedExponential.Limits = [0 100];
            app.EditField_MinValue_MixedExponential.RoundFractionalValues = 'on';
            app.EditField_MinValue_MixedExponential.ValueDisplayFormat = '%.1f';
            app.EditField_MinValue_MixedExponential.ValueChangedFcn = createCallbackFcn(app, @EditField_MinValue_MixedExponentialValueChanged, true);
            app.EditField_MinValue_MixedExponential.Value = 10;
            app.Label_9 = uilabel(app.GridLayout19);
            app.Label_9.Text = '网格间距';
            app.EditField_Grid_MixedExponential = uieditfield(app.GridLayout19, 'numeric');
            app.EditField_Grid_MixedExponential.Limits = [1 Inf];
            app.EditField_Grid_MixedExponential.ValueDisplayFormat = '%.0f';
            app.EditField_Grid_MixedExponential.ValueChangedFcn = createCallbackFcn(app, @EditField_Grid_MixedExponentialValueChanged, true);
            app.EditField_Grid_MixedExponential.Value = 25;
            app.GridLayout20 = uigridlayout(app.GridLayout18);
            app.Label_4 = uilabel(app.GridLayout20);
            app.Label_4.Text = '回归阶';
            app.Spinner_RegressionOrder_MixedExponential = uispinner(app.GridLayout20);
            app.Spinner_RegressionOrder_MixedExponential.Limits = [1 5];
            app.Spinner_RegressionOrder_MixedExponential.ValueDisplayFormat = '%.0f';
            app.Spinner_RegressionOrder_MixedExponential.ValueChangedFcn = createCallbackFcn(app, @Spinner_RegressionOrder_MixedExponentialValueChanged, true);
            app.Spinner_RegressionOrder_MixedExponential.Value = 1;
            app.Button_AutoOrder_MixedExponential = uibutton(app.GridLayout20, 'state');
            app.Button_AutoOrder_MixedExponential.ValueChangedFcn = createCallbackFcn(app, @Button_AutoOrder_MixedExponentialValueChanged, true);
            app.Button_AutoOrder_MixedExponential.Text = '自动';
            app.Panel_FluorescenceModel = uipanel(app.GridLayout16);
            app.Panel_FluorescenceModel.Title = '发光模型';
            app.GridLayout10 = uigridlayout(app.Panel_FluorescenceModel);
            app.GridLayout4 = uigridlayout(app.GridLayout10);
            app.Label_5 = uilabel(app.GridLayout4);
            app.Label_5.Text = '内核';
            app.DropDown_Kernel = uidropdown(app.GridLayout4);
            app.DropDown_Kernel.Items = {'均匀核', '高斯核', '拉-高核'};
            app.DropDown_Kernel.ItemsData = {'uniform', 'gaussian', 'log'};
            app.DropDown_Kernel.ValueChangedFcn = createCallbackFcn(app, @DropDown_KernelValueChanged, true);
            app.DropDown_Kernel.Value = 'uniform';
            app.Button_AutoKernel = uibutton(app.GridLayout4, 'state');
            app.Button_AutoKernel.ValueChangedFcn = createCallbackFcn(app, @Button_AutoKernelValueChanged, true);
            app.Button_AutoKernel.Text = '自动';
            app.GridLayout5 = uigridlayout(app.GridLayout10);
            app.CheckBox_FluorescenceOnly = uicheckbox(app.GridLayout5);
            app.CheckBox_FluorescenceOnly.ValueChangedFcn = createCallbackFcn(app, @CheckBox_FluorescenceOnlyValueChanged, true);
            app.CheckBox_FluorescenceOnly.Text = '仅荧光强度';
            app.Label_6 = uilabel(app.GridLayout5);
            app.Label_6.Text = '固有背景';
            app.EditField_FixedBackground = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_FixedBackground.Limits = [0 65535];
            app.EditField_FixedBackground.ValueDisplayFormat = '%.1f';
            app.EditField_FixedBackground.ValueChangedFcn = createCallbackFcn(app, @EditField_FixedBackgroundValueChanged, true);
            app.EditField_FixedBackground.Value = 100;
            app.GridLayout12 = uigridlayout(app.GridLayout);
            app.Button_Fit = uibutton(app.GridLayout12, 'push');
            app.Button_Fit.ButtonPushedFcn = createCallbackFcn(app, @Button_FitPushed, true);
            app.Button_Fit.Text = '拟合';
            app.Button_Preview = uibutton(app.GridLayout12, 'push');
            app.Button_Preview.ButtonPushedFcn = createCallbackFcn(app, @Button_PreviewPushed, true);
            app.Button_Preview.Text = '预览';
            app.Button_Save = uibutton(app.GridLayout12, 'push');
            app.Button_Save.ButtonPushedFcn = createCallbackFcn(app, @Button_SavePushed, true);
            app.Button_Save.Text = '保存';
            app.UITable_ObjectInfo = uitable(app.GridLayout);
            app.UITable_ObjectInfo.ColumnName = {'质心'; '等效半径'; '表面积'; '体积'};
            app.UITable_ObjectInfo.RowName = {};
            app.UITable_ObjectInfo.Multiselect = 'off';
            % Show the figure after all components are created
            app.SignaEstimatorV100UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalEstimator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SignaEstimatorV100UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SignaEstimatorV100UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignaEstimatorV100UIFigure)
        end
    end
end