classdef TCRegSetter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TCRSUIFigure                    matlab.ui.Figure
        TabGroup_AlgorithmSelection     matlab.ui.container.TabGroup
        Tab_Global                      matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup_Global                 matlab.ui.container.TabGroup
        Tab_NS_Global                   matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        Panel_NS_Reg_Global             matlab.ui.container.Panel
        GridLayout_NS_Global            matlab.ui.container.GridLayout
        GridLayout67                    matlab.ui.container.GridLayout
        Spinner_Octave_Global           matlab.ui.control.Spinner
        Label_67                        matlab.ui.control.Label
        EditField_QT_Global             matlab.ui.control.NumericEditField
        Label_66                        matlab.ui.control.Label
        GridLayout66                    matlab.ui.container.GridLayout
        DropDown_Detector_Global        matlab.ui.control.DropDown
        Label_65                        matlab.ui.control.Label
        DropDown_CoarseAlg_Global       matlab.ui.control.DropDown
        Label_64                        matlab.ui.control.Label
        GridLayout14                    matlab.ui.container.GridLayout
        Image_CPU                       matlab.ui.control.Image
        GridLayout15                    matlab.ui.container.GridLayout
        EditField_MaxStep_Global        matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        EditField_MinStep_Global        matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        GridLayout13                    matlab.ui.container.GridLayout
        DropDown_Functional_Global      matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        DropDown_Structured_Global      matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        GridLayout12                    matlab.ui.container.GridLayout
        DropDown_TformType_Global       matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        DropDown_TemplateSampling_Global  matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        GridLayout11                    matlab.ui.container.GridLayout
        CheckBox_AutoUpdateTemplate_Global  matlab.ui.control.CheckBox
        EditField_Template_Global       matlab.ui.control.EditField
        Label_11                        matlab.ui.control.Label
        Tab_AS_Global                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Panel_AS_Reg_Global             matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        Label_VPL_Global                matlab.ui.control.Label
        Spinner_VPL_Global              matlab.ui.control.Spinner
        DropDown_Interp_Global          matlab.ui.control.DropDown
        Label_9                         matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        EditField_IterCoeff_Global      matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        EditField_MaxIterN_Global       matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        DropDown_RegModal               matlab.ui.control.DropDown
        Label_10                        matlab.ui.control.Label
        DropDown_DownSampling_Global    matlab.ui.control.DropDown
        Label_13                        matlab.ui.control.Label
        Panel_AS_PP_Global              matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout71                    matlab.ui.container.GridLayout
        CheckBox_DilateFilterEnhancer_Global  matlab.ui.control.CheckBox
        EditField_Gamma_Global          matlab.ui.control.NumericEditField
        Label_63                        matlab.ui.control.Label
        EditField_DilateFilter_Global   matlab.ui.control.EditField
        Label_53                        matlab.ui.control.Label
        GridLayout70                    matlab.ui.container.GridLayout
        EditField_OptimalZ_Tol_Global   matlab.ui.control.NumericEditField
        Label_56                        matlab.ui.control.Label
        EditField_GaussianFilter_Global  matlab.ui.control.EditField
        Label_52                        matlab.ui.control.Label
        EditField_OptimalZ_MaxShift_Global  matlab.ui.control.NumericEditField
        Label_55                        matlab.ui.control.Label
        EditField_MedianFilter_Global   matlab.ui.control.EditField
        Label_51                        matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        Label_Helps_Global              matlab.ui.control.Label
        CheckBox_Lock_Global            matlab.ui.control.CheckBox
        Tab_Local                       matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout17                    matlab.ui.container.GridLayout
        Label_Helps_Local               matlab.ui.control.Label
        CheckBox_Lock_Local             matlab.ui.control.CheckBox
        GridLayout32                    matlab.ui.container.GridLayout
        TabGroup_Local                  matlab.ui.container.TabGroup
        Tab_NS_Local                    matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        Panel_NS_Local                  matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        Label_VPL_Global_2              matlab.ui.control.Label
        Spinner_NS_VPL_Local            matlab.ui.control.Spinner
        EditField_GridSpacing_Local     matlab.ui.control.EditField
        Label_57                        matlab.ui.control.Label
        EditField_GridRegularization_Local  matlab.ui.control.NumericEditField
        Label_58                        matlab.ui.control.Label
        Tab_GS_Local                    matlab.ui.container.Tab
        GridLayout72                    matlab.ui.container.GridLayout
        Panel_CPD_Local                 matlab.ui.container.Panel
        GridLayout73                    matlab.ui.container.GridLayout
        EditField_MismatchTol_CPD       matlab.ui.control.NumericEditField
        Label_75                        matlab.ui.control.Label
        EditField_Flatness_CPD          matlab.ui.control.NumericEditField
        Label_74                        matlab.ui.control.Label
        Spinner_GridStep_CPD            matlab.ui.control.Spinner
        Label_73                        matlab.ui.control.Label
        EditField_IterNumMax_CPD        matlab.ui.control.NumericEditField
        Label_72                        matlab.ui.control.Label
        Tab_AS_Local                    matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        GridLayout26                    matlab.ui.container.GridLayout
        Panel_AS_Local                  matlab.ui.container.Panel
        GridLayout27                    matlab.ui.container.GridLayout
        GridLayout31                    matlab.ui.container.GridLayout
        Label_VPL_Global_3              matlab.ui.control.Label
        Spinner_AS_VPL_Local            matlab.ui.control.Spinner
        DropDown_Interp_Local           matlab.ui.control.DropDown
        Label_16                        matlab.ui.control.Label
        GridLayout30                    matlab.ui.container.GridLayout
        GridLayout28                    matlab.ui.container.GridLayout
        GridLayout29                    matlab.ui.container.GridLayout
        EditField_AFS_Local             matlab.ui.control.NumericEditField
        Label_17                        matlab.ui.control.Label
        EditField_MaxIterN_Local        matlab.ui.control.EditField
        Label_20                        matlab.ui.control.Label
        Image_GPU                       matlab.ui.control.Image
        Panel_Common_Local              matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        GridLayout24                    matlab.ui.container.GridLayout
        CheckBox_RepAccOn_Local         matlab.ui.control.CheckBox
        DropDown_RepAcc_Local           matlab.ui.control.DropDown
        Label_19                        matlab.ui.control.Label
        GridLayout23                    matlab.ui.container.GridLayout
        DropDown_Functional_Local       matlab.ui.control.DropDown
        Label_18                        matlab.ui.control.Label
        DropDown_Structured_Local       matlab.ui.control.DropDown
        Label_15                        matlab.ui.control.Label
        GridLayout22                    matlab.ui.container.GridLayout
        DropDown_TemplateSampling_Local  matlab.ui.control.DropDown
        Label_14                        matlab.ui.control.Label
        EditField_Template_Local        matlab.ui.control.EditField
        Label_21                        matlab.ui.control.Label
        Tab_MGlobal                     matlab.ui.container.Tab
        GridLayout33                    matlab.ui.container.GridLayout
        Panel_Reg_MGlobal               matlab.ui.container.Panel
        GridLayout35                    matlab.ui.container.GridLayout
        GridLayout45                    matlab.ui.container.GridLayout
        GridLayout69                    matlab.ui.container.GridLayout
        CheckBox_ApplyTransformation    matlab.ui.control.CheckBox
        Label_TransformMatrix           matlab.ui.control.Label
        GridLayout68                    matlab.ui.container.GridLayout
        EditField_TM44_MGlobal          matlab.ui.control.EditField
        EditField_TM43_MGlobal          matlab.ui.control.EditField
        EditField_TM42_MGlobal          matlab.ui.control.EditField
        EditField_TM41_MGlobal          matlab.ui.control.EditField
        EditField_TM34_MGlobal          matlab.ui.control.EditField
        EditField_TM33_MGlobal          matlab.ui.control.EditField
        EditField_TM32_MGlobal          matlab.ui.control.EditField
        EditField_TM31_MGlobal          matlab.ui.control.EditField
        EditField_TM24_MGlobal          matlab.ui.control.EditField
        EditField_TM23_MGlobal          matlab.ui.control.EditField
        EditField_TM22_MGlobal          matlab.ui.control.EditField
        EditField_TM21_MGlobal          matlab.ui.control.EditField
        EditField_TM14_MGlobal          matlab.ui.control.EditField
        EditField_TM13_MGlobal          matlab.ui.control.EditField
        EditField_TM12_MGlobal          matlab.ui.control.EditField
        EditField_TM11_MGlobal          matlab.ui.control.EditField
        GridLayout44                    matlab.ui.container.GridLayout
        CheckBox_ReSampleIsometric_MGlobal  matlab.ui.control.CheckBox
        EditField_ReSampleRatio_MGlobal  matlab.ui.control.EditField
        Label_61                        matlab.ui.control.Label
        GridLayout43                    matlab.ui.container.GridLayout
        Spinner_PolyOrder_MGlobal       matlab.ui.control.Spinner
        Label_24                        matlab.ui.control.Label
        DropDown_Interp_MGlobal         matlab.ui.control.DropDown
        Label_26                        matlab.ui.control.Label
        GridLayout42                    matlab.ui.container.GridLayout
        DropDown_Projection_MGlobal     matlab.ui.control.DropDown
        Label_60                        matlab.ui.control.Label
        DropDown_RegTform_MGlobal       matlab.ui.control.DropDown
        Label_22                        matlab.ui.control.Label
        GridLayout41                    matlab.ui.container.GridLayout
        DropDown_DView_MGlobal          matlab.ui.control.DropDown
        Label_59                        matlab.ui.control.Label
        EditField_CPNumber_MGlobal      matlab.ui.control.NumericEditField
        Label_23                        matlab.ui.control.Label
        GridLayout34                    matlab.ui.container.GridLayout
        Label_Helps_MANGlobal           matlab.ui.control.Label
        CheckBox_Lock_MGlobal           matlab.ui.control.CheckBox
        Tab_LTGlobal                    matlab.ui.container.Tab
        GridLayout46                    matlab.ui.container.GridLayout
        TabGroup_LTGlobal               matlab.ui.container.TabGroup
        Tab_NS_LTGlobal                 matlab.ui.container.Tab
        GridLayout49                    matlab.ui.container.GridLayout
        Panel_NS_Reg_LTGlobal           matlab.ui.container.Panel
        GridLayout50                    matlab.ui.container.GridLayout
        GridLayout63                    matlab.ui.container.GridLayout
        GridLayout65                    matlab.ui.container.GridLayout
        Image_Memory                    matlab.ui.control.Image
        GridLayout64                    matlab.ui.container.GridLayout
        EditField_MaxIterN_LTGlobal     matlab.ui.control.NumericEditField
        Label_40                        matlab.ui.control.Label
        EditField_IterCoeff_LTGlobal    matlab.ui.control.NumericEditField
        Label_45                        matlab.ui.control.Label
        EditField_MaxStep_LTGlobal      matlab.ui.control.NumericEditField
        Label_43                        matlab.ui.control.Label
        EditField_MinStep_LTGlobal      matlab.ui.control.NumericEditField
        Label_42                        matlab.ui.control.Label
        GridLayout53                    matlab.ui.container.GridLayout
        DropDown_Structured_LTGlobal    matlab.ui.control.DropDown
        Label_32                        matlab.ui.control.Label
        DropDown_Functional_LTGlobal    matlab.ui.control.DropDown
        Label_33                        matlab.ui.control.Label
        GridLayout52                    matlab.ui.container.GridLayout
        DropDown_TemplateSampling_LTGlobal  matlab.ui.control.DropDown
        Label_30                        matlab.ui.control.Label
        DropDown_TformType_LTGlobal     matlab.ui.control.DropDown
        Label_62                        matlab.ui.control.Label
        GridLayout51                    matlab.ui.container.GridLayout
        EditField_Template_LTGlobal     matlab.ui.control.EditField
        Label_31                        matlab.ui.control.Label
        CheckBox_AutoTemplate_LTGlobal  matlab.ui.control.CheckBox
        Tab_AS_LTGlobal                 matlab.ui.container.Tab
        GridLayout48                    matlab.ui.container.GridLayout
        Panel_AS_Reg_LTGlobal           matlab.ui.container.Panel
        GridLayout58                    matlab.ui.container.GridLayout
        GridLayout61                    matlab.ui.container.GridLayout
        Label_69                        matlab.ui.control.Label
        Spinner_TGridMin_LTGlobal       matlab.ui.control.Spinner
        Spinner_TGridMax_LTGlobal       matlab.ui.control.Spinner
        Label_68                        matlab.ui.control.Label
        GridLayout60                    matlab.ui.container.GridLayout
        DropDown_Interp_LTGlobal        matlab.ui.control.DropDown
        Label_70                        matlab.ui.control.Label
        DropDown_DownSampling_LTGlobal  matlab.ui.control.DropDown
        Label_37                        matlab.ui.control.Label
        GridLayout59                    matlab.ui.container.GridLayout
        CheckBox_AutoKeyframe_LTGlobal  matlab.ui.control.CheckBox
        EditField_Keyframes_LTGlobal    matlab.ui.control.EditField
        Label_29                        matlab.ui.control.Label
        Panel_AS_PP_LTGlobal            matlab.ui.container.Panel
        GridLayout56                    matlab.ui.container.GridLayout
        GridLayout57                    matlab.ui.container.GridLayout
        EditField_DilateFilter_LTGlobal  matlab.ui.control.EditField
        Label_71                        matlab.ui.control.Label
        EditField_OptimalZ_MaxShift_LTGlobal  matlab.ui.control.NumericEditField
        Label_34                        matlab.ui.control.Label
        GridLayout62                    matlab.ui.container.GridLayout
        GridLayout47                    matlab.ui.container.GridLayout
        Label_Helps_LTGlobal            matlab.ui.control.Label
        CheckBox_Lock_LTGlobal          matlab.ui.control.CheckBox
    end

    properties (Constant)
        VER = "2.2.0"
    end

    properties (Access = public)
        UIAxes_MotionViewer             matlab.ui.control.UIAxes
    end
    
    properties (Access = private, Hidden)
        caller;         % the parent app

        fig_refvol;     % figure handle of reference volume
        fig_fpp;        % figure handle of feature points pairs results

        volopts;        % 1-by-12 table, with volumes options
        regopts;        % 1-by-1 regopt object
        fixdef;         % struct with {"Sampling", "Channel"}, the fixed volume defination
        opt_locked      % 1-by-5 table, indicate the options locked status
        hw_info;        % the hardware information struct

        langmap;        % mapping for language
    end

    methods (Access = ?Register)

        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr >= 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout10.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_NS_Reg_Global.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_NS_Global.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout11.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout12.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout13.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout66.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout67.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout14.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout15.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_AS_PP_Global.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout70.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout71.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_CPD_Local.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout72.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout73.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_AS_Reg_Global.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout7.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout8.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout16.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout17.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout20.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_Common_Local.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout21.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout22.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout23.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout24.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_NS_Local.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout25.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout32.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_AS_Local.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout27.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout28.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout29.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout31.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout33.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_Reg_MGlobal.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout34.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout35.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout41.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout42.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout43.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout44.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout45.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout69.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout46.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout47.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_NS_Reg_LTGlobal.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout50.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout51.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout52.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout53.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout63.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout65.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout48.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_AS_PP_LTGlobal.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout59.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_AS_Reg_LTGlobal.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout58.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout60.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout61.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout56.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout57.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout62.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout64.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                case "DARK"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout10.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_NS_Reg_Global.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_NS_Global.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout11.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout12.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout13.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout66.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout67.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout14.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout15.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_AS_PP_Global.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout70.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout71.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_CPD_Local.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout72.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout73.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_AS_Reg_Global.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout7.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout8.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout16.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout17.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout20.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_Common_Local.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout21.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout22.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout23.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout24.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_NS_Local.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout25.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout32.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_AS_Local.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout27.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout28.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout29.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout31.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout33.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_Reg_MGlobal.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout34.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout35.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout41.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout42.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout43.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout44.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout45.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout69.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout46.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout47.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_NS_Reg_LTGlobal.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout50.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout51.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout52.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout53.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout63.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout65.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout48.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_AS_PP_LTGlobal.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout59.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_AS_Reg_LTGlobal.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout58.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout60.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout61.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout56.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout57.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout62.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout64.BackgroundColor = constdef.GRAYSCALE_DARK;
                otherwise
            end
        end

        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            app.Tab_Global.Title = app.langmap.GLOBAL;
            app.Tab_Global.Tooltip = app.langmap.TIP_TAB_GLOBAL;
            app.Tab_Local.Title = app.langmap.LOCAL;
            app.Tab_Local.Tooltip = app.langmap.TIP_TAB_LOCAL;
            app.Tab_MGlobal.Title = app.langmap.MGLOBAL;
            app.Tab_MGlobal.Tooltip = app.langmap.TIP_TAB_MGLOBAL;
            app.Tab_LTGlobal.Title = app.langmap.LTGLOBAL;
            app.Tab_LTGlobal.Tooltip = app.langmap.TIP_TAB_LTGLOBAL;
            app.Tab_NS_Global.Title = app.langmap.USUAL;
            app.Tab_AS_Global.Title = app.langmap.ADVANCED;
            app.Tab_NS_Local.Title = app.langmap.REG_LOCAL_DEFORM;
            app.Tab_AS_Local.Title = app.langmap.REG_LOCAL_DEMONS;
            app.Tab_GS_Local.Title = app.langmap.REG_LOCAL_CPD;
            app.Tab_NS_LTGlobal.Title = app.langmap.USUAL;
            app.Tab_AS_LTGlobal.Title = app.langmap.ADVANCED;
            app.Panel_NS_Reg_Global.Title = app.langmap.ALIGNED;
            app.Panel_AS_PP_Global.Title = app.langmap.PREPROCESSING;
            app.Panel_AS_Reg_Global.Title = app.langmap.ALIGNED;
            app.Panel_Common_Local.Title = app.langmap.COMMON;
            app.Panel_Reg_MGlobal.Title = app.langmap.ALIGNED;
            app.Panel_NS_Reg_LTGlobal.Title = app.langmap.ALIGNED;
            app.Panel_AS_PP_LTGlobal.Title = app.langmap.PREPROCESSING;
            app.Panel_AS_Reg_LTGlobal.Title = app.langmap.ALIGNED;
            app.Label.Text = app.langmap.SAMPLING;
            app.Label_2.Text = app.langmap.GEOMETRIC_TRANSFORMATION;
            app.Label_3.Text = app.langmap.LOCATION;
            app.Label_4.Text = app.langmap.SIGNAL;
            app.Label_7.Text = app.langmap.MAX_ITERATION_N;
            app.Label_9.Text = app.langmap.INTERPOLATION;
            app.Label_10.Text = app.langmap.MODAL;
            app.Label_VPL_Global.Text = app.langmap.PYRAMID_COEFFICIENT;
            app.Label_VPL_Global_2.Text = app.langmap.PYRAMID_COEFFICIENT;
            app.Label_VPL_Global_3.Text = app.langmap.PYRAMID_COEFFICIENT;
            app.Label_11.Text = app.langmap.TEMPLATE;
            app.Label_13.Text = app.langmap.DOWNSAMPLING;
            app.Label_14.Text = app.langmap.SAMPLING;
            app.Label_15.Text = app.langmap.LOCATION;
            app.Label_16.Text = app.langmap.INTERPOLATION;
            app.Label_17.Text = app.langmap.ACCUMULATIVE_FIELD_SMOOTHING;
            app.Label_18.Text = app.langmap.SIGNAL;
            app.Label_19.Text = app.langmap.PRECISION;
            app.Label_20.Text = app.langmap.MAX_ITERATION_N;
            app.Label_21.Text = app.langmap.TEMPLATE;
            app.Label_22.Text = app.langmap.GEOMETRIC_TRANSFORMATION;
            app.Label_23.Text = app.langmap.CONTROL_VAL;
            app.Label_24.Text = app.langmap.POLYNOMIAL_ORDER;
            app.Label_26.Text = app.langmap.INTERPOLATION;
            app.Label_29.Text = app.langmap.KEY_FRAME;
            app.Label_30.Text = app.langmap.SAMPLING;
            app.Label_31.Text = app.langmap.TEMPLATE;
            app.Label_32.Text = app.langmap.LOCATION;
            app.Label_33.Text = app.langmap.SIGNAL;
            app.Label_34.Text = app.langmap.MAX_Z_SHIFT;
            app.Label_37.Text = app.langmap.DOWNSAMPLING;
            app.Label_40.Text = app.langmap.MAX_ITERATION_N;
            app.Label_42.Text = app.langmap.MIN_ITERATION_STEP;
            app.Label_43.Text = app.langmap.MAX_ITERATION_STEP;
            app.Label_45.Text = app.langmap.ITERATION_COEFFICIENT;
            app.Label_51.Text = app.langmap.MEDIAN_FILTER;
            app.Label_52.Text = app.langmap.GAUSSIAN_FILTER;
            app.Label_53.Text = app.langmap.DILATE_FILTER;
            app.Label_55.Text = app.langmap.MAX_Z_SHIFT;
            app.Label_56.Text = app.langmap.Z_OPTIMAL_TOL;
            app.Label_57.Text = app.langmap.GRID_SPACING;
            app.Label_58.Text = app.langmap.DISPLACEMENT_REGULATION;
            app.Label_59.Text = app.langmap.DVIEW;
            app.Label_60.Text = app.langmap.PROJECTION;
            app.Label_61.Text = app.langmap.RESAMPLING_RATIO;
            app.Label_62.Text = app.langmap.GEOMETRIC_TRANSFORMATION;
            app.Label_63.Text = app.langmap.GAMMA;
            app.Label_64.Text = app.langmap.COARSE_REG;
            app.Label_65.Text = app.langmap.OPERATOR;
            app.Label_71.Text = app.langmap.DILATE_FILTER;
            app.Label_72.Text = app.langmap.MAX_ITERATION_N;
            app.Label_73.Text = app.langmap.GRID_STEP;
            app.Label_74.Text = app.langmap.FLATNESS;
            app.Label_75.Text = app.langmap.MISMATCH_TOLERANCE;

            app.Label_TransformMatrix.Text = app.langmap.TRANSFORMATION_MATRIX;
            if app.regopts.Algorithm == "TCREG" ...
                    && app.regopts.Mode == "global"
                if app.regopts.Options.CoarseAlg == "fpp"
                    switch app.regopts.Options.CoarseArgs.Operator
                        case {'KAZE','SIFT','SURF'}
                            app.Label_66.Text = app.langmap.THRESHOLD;
                        case {'BRISK', 'Harris'}
                            app.Label_66.Text = app.langmap.QUALITY;
                        otherwise
                    end
                end

                app.Label_67.Text = app.langmap.OCTAVE;

                switch app.regopts.Options.RegModal
                    case "monomodal"
                        app.Label_5.Text = app.langmap.MIN_ITERATION_STEP;
                        app.Label_6.Text = app.langmap.MAX_ITERATION_STEP;
                        app.Label_8.Text = app.langmap.ITERATION_COEFFICIENT;
                    case "multimodal"
                        app.Label_5.Text = app.langmap.MIN_SEARCHING_RADIUS;
                        app.Label_6.Text = app.langmap.MAX_SEARCHING_RADIUS;
                        app.Label_8.Text = app.langmap.GROWTH_FACTOR;
                    otherwise
                end
            end
            app.Label_68.Text = app.langmap.MIN_DIVISION;
            app.Label_69.Text = app.langmap.MAX_DIVISION;
            
            app.DropDown_TemplateSampling_Global.Items = [app.langmap.MEAN,...
                app.langmap.MEDIAN, app.langmap.MIN, app.langmap.MAX];
            app.DropDown_TformType_Global.Items = [app.langmap.TRANSLATION,...
                app.langmap.RIGID, app.langmap.AFFINE];
            app.DropDown_RegModal.Items = [app.langmap.MONOMODAL, ...
                app.langmap.MULTIMODAL];
            app.DropDown_Structured_Global.Items = [app.langmap.RED, ...
                app.langmap.GREEN];
            app.DropDown_Functional_Global.Items = [app.langmap.RED, ...
                app.langmap.GREEN];
            app.DropDown_CoarseAlg_Global.Items = [app.langmap.MASS_CENTER_ESTIMATION, ...
                app.langmap.PHASE_CORRELATION, app.langmap.FEATURE_POINT_MATCHING, ...
                app.langmap.CLOSE];
            app.DropDown_DownSampling_Global.Items = [app.langmap.AUTO, ...
                "2X2", "3X3", app.langmap.CLOSE];
            app.DropDown_Interp_Global.Items = [app.langmap.LINEAR_FAST, ...
                app.langmap.CUBIC_HIGH_QUALITY];
            app.DropDown_TemplateSampling_Local.Items = [app.langmap.MEAN,...
                app.langmap.MEDIAN, app.langmap.MIN, app.langmap.MAX];
            app.DropDown_Structured_Local.Items = [app.langmap.RED, ...
                app.langmap.GREEN];
            app.DropDown_Functional_Local.Items = [app.langmap.RED, ...
                app.langmap.GREEN];
            app.DropDown_RepAcc_Local.Items = [app.langmap.LOW, ...
                app.langmap.MEDIUM, app.langmap.HIGH];
            app.DropDown_Interp_Local.Items = [app.langmap.LINEAR_FAST, ...
                app.langmap.CUBIC_HIGH_QUALITY];
            app.DropDown_DView_MGlobal.Items = [app.langmap.DVIEW_XY, ...
                app.langmap.DVIEW_ZX, app.langmap.DVIEW_YZ];
            app.DropDown_RegTform_MGlobal.Items = [app.langmap.TRANSLATION, ...
                app.langmap.RIGID, app.langmap.AFFINE, ...
                app.langmap.PIECEWISE_LINEAR, app.langmap.POLYNOMIAL];
            app.DropDown_Projection_MGlobal.Items = [app.langmap.MAX, ...
                app.langmap.MIN, app.langmap.MEDIAN, app.langmap.MEAN];
            app.DropDown_Interp_MGlobal.Items = [app.langmap.LINEAR_FAST, ...
                app.langmap.CUBIC_HIGH_QUALITY];
            app.DropDown_TemplateSampling_LTGlobal.Items = [app.langmap.MEAN,...
                app.langmap.MEDIAN, app.langmap.MIN, app.langmap.MAX];
            app.DropDown_TformType_LTGlobal.Items = [app.langmap.AFFINE];
            app.DropDown_Structured_LTGlobal.Items = [app.langmap.RED, ...
                app.langmap.GREEN];
            app.DropDown_Functional_LTGlobal.Items = [app.langmap.RED, ...
                app.langmap.GREEN];
            app.DropDown_DownSampling_LTGlobal.Items = [app.langmap.AUTO, ...
                "2X2", "3X3", app.langmap.CLOSE];

            app.CheckBox_Lock_Global.Text = app.langmap.LOCK;
            app.CheckBox_Lock_Global.Tooltip = app.langmap.TIP_CBX_LOCK;
            app.CheckBox_Lock_Local.Text = app.langmap.LOCK;
            app.CheckBox_Lock_Local.Tooltip = app.langmap.TIP_CBX_LOCK;
            app.CheckBox_Lock_MGlobal.Text = app.langmap.LOCK;
            app.CheckBox_Lock_MGlobal.Tooltip = app.langmap.TIP_CBX_LOCK;
            app.CheckBox_Lock_LTGlobal.Text = app.langmap.LOCK;
            app.CheckBox_Lock_LTGlobal.Tooltip = app.langmap.TIP_CBX_LOCK;
            app.CheckBox_AutoUpdateTemplate_Global.Text = app.langmap.TEMPLATE_AUTO_UPDATE;
            app.CheckBox_AutoTemplate_LTGlobal.Text = app.langmap.AUTO;
            app.CheckBox_ReSampleIsometric_MGlobal.Text = app.langmap.ISOMETRIC;
            app.CheckBox_ReSampleIsometric_MGlobal.Tooltip = app.langmap.TIP_CBX_ISOMETRIC;
            app.CheckBox_AutoKeyframe_LTGlobal.Text = app.langmap.AUTO;
            app.CheckBox_AutoKeyframe_LTGlobal.Tooltip = app.langmap.TIP_CBX_AUTO_KEYFRAME;
            app.CheckBox_RepAccOn_Local.Text = app.langmap.REPLENISH_PHOTOBLEACHING;
            app.CheckBox_RepAccOn_Local.Tooltip = app.langmap.TIP_CBX_PHOTOBLEACHING;
            app.CheckBox_DilateFilterEnhancer_Global.Text = app.langmap.ENHANCEMENT;
            app.CheckBox_DilateFilterEnhancer_Global.Tooltip = app.langmap.TIP_CBX_ENHANCE;
            app.CheckBox_ApplyTransformation.Text = app.langmap.APPLY;
            app.CheckBox_ApplyTransformation.Tooltip = app.langmap.TIP_CBX_APPLY_TMAT;
        end
        
        function FontBlink(app,components,ts,c)
            % This function for app components font blink
            arguments
                app (1,1) TCRegSetter;
                components string {mustBeVector}; % target  source
                ts (:,2) double {mustBePositive} = [0.25,   0.25; ...
                    0.25,   0.25; ...
                    0.25,   0.25]   %time series, seconds
                % c is RGB array
                c (2,3) double {mustBeInRange(c,0,1)} = [1,0,0; ... % target
                    0,0,0];    % source
            end

            N = numel(components);

            for t = 1:size(ts,1)
                for k = 1:N
                    if isprop(app.(components(k)),"FontColor")
                        app.(components(k)).FontColor = c(1,:);
                    end
                end
                pause(ts(t,1));
                for k = 1:N
                    if isprop(app.(components(k)),"FontColor")
                        app.(components(k)).FontColor = c(2,:);
                    end
                end
                pause(ts(t,2));
            end
        end

        function Refresh(app, mainapp)
            InitData(app, mainapp);
            InitPanel(app);
        end

        function Close(app)
            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);

            delete(app);
        end
    end
    
    methods (Access = private)
        function InitData(app, mainapp)
            app.caller = mainapp;
            app.fig_refvol = [];
            app.fig_fpp = [];
            [app.regopts, app.fixdef, app.opt_locked] = ...
                app.caller.GetRegOptions();
            app.volopts = app.caller.GetImgInfo();
        end

        function InitPanel(app)
            % SELECT ONE SETTER
            switch app.regopts.Mode
                case "global"
                    switch app.regopts.Algorithm
                        case "TCREG"
                            SetGlobalPanel(app);
                        case "LTREG"
                            SetLTGlobalPanel(app);
                        case "MANREG"
                            SetMGlobalPanel(app);
                        otherwise
                    end
                case "local"
                    switch app.regopts.Algorithm
                        case "TCREG"
                            SetLocalPanel(app);
                        otherwise
                    end
                otherwise
            end
        end

        %==================== GLOBAL PANEL ======================
        % Global panel setting include: 
        % <SetGlobalPanel>: set the controller value
        % <SetGlobalPanelEnabling>: set the controller enable status
        % <LockGlobalPanel>: set the controllers disabled
        % <UnlockGloablPanel>: set the controllers enabled

        function SetGlobalPanel(app)
            % ================== SET THE USUAL ARGUMENTS ================
            % set registration template
            app.EditField_Template_Global.Value = app.fixdef.Sampling(2);
            % force enable the auto template generation
            app.CheckBox_AutoUpdateTemplate_Global.Enable = "off";
            app.DropDown_TemplateSampling_Global.Value = app.fixdef.Sampling(1);

            % set transformation
            app.DropDown_TformType_Global.Value = app.regopts.Options.TformType;

            % set the structured and functional channels
            app.DropDown_Structured_Global.Value = app.regopts.Options.SC;
            app.DropDown_Functional_Global.Value = app.regopts.Options.FC;

            % set the coarse registration algorithm
            app.DropDown_CoarseAlg_Global.Value = app.regopts.Options.CoarseAlg;

            switch app.regopts.Options.CoarseAlg
                case "fpp"
                    app.DropDown_Detector_Global.Visible = "on";
                    app.Label_65.Visible = "on";
                    app.Label_64.FontColor = [0, 0, 1];
                    app.GridLayout_NS_Global.RowHeight = ...
                        {25,25,25,25,25,60,'1x'};
                    app.EditField_OptimalZ_MaxShift_Global.Enable = "on";
                    app.EditField_OptimalZ_Tol_Global.Enable = "on";
                otherwise
                    app.DropDown_Detector_Global.Visible = "off";
                    app.Label_65.Visible = "off";
                    app.Label_64.FontColor = [0, 0, 0];
                    app.GridLayout_NS_Global.RowHeight = ...
                        {25,25,25,25,0,60,'1x'};
                    if isequal(app.regopts.Options.CoarseAlg, "none")
                        app.EditField_OptimalZ_MaxShift_Global.Enable = "off";
                        app.EditField_OptimalZ_Tol_Global.Enable = "off";
                    else
                        app.EditField_OptimalZ_MaxShift_Global.Enable = "on";
                        app.EditField_OptimalZ_Tol_Global.Enable = "on";
                    end
            end

            % set the coarse registration parameters
            app.DropDown_Detector_Global.Value = app.regopts.Options.CoarseArgs.Operator;
            app.EditField_QT_Global.Value = app.regopts.Options.CoarseArgs.QT;
            app.Spinner_Octave_Global.Value = app.regopts.Options.CoarseArgs.NumOctave;

            % set iteration options
            app.EditField_MinStep_Global.Value = app.regopts.Options.MinStep;
            app.EditField_MaxStep_Global.Value = app.regopts.Options.MaxStep;

            %================== SET THE ADVANCED ARGUMENTS ================
            % set the preprocessing arguments
            app.EditField_MedianFilter_Global.Value = ...
                string(app.regopts.Options.MedianFilter).join(",");
            app.EditField_DilateFilter_Global.Value = ...
                string(app.regopts.Options.DilateFilter).join(",");
            app.CheckBox_DilateFilterEnhancer_Global.Value = ...
                app.regopts.Options.DilateFilterEnh;
            app.EditField_GaussianFilter_Global.Value = ...
                string(app.regopts.Options.GaussianFilter).join(",");
            app.EditField_OptimalZ_MaxShift_Global.Value = app.regopts.Options.MaxZOptShift;
            app.EditField_OptimalZ_Tol_Global.Value = app.regopts.Options.TolZOpt;
            app.EditField_Gamma_Global.Value = app.regopts.Options.Gamma;

            % set the modal
            app.DropDown_RegModal.Value = app.regopts.Options.RegModal;

            % set downsampling options
            switch app.regopts.Options.DS
                case "1X1"
                    app.DropDown_DownSampling_Global.Value = "close";
                otherwise
                    app.DropDown_DownSampling_Global.Value = app.regopts.Options.DS;
            end

            % set iteration options
            app.EditField_MaxIterN_Global.Value = app.regopts.Options.MaxIterN;
            app.EditField_IterCoeff_Global.Value = app.regopts.Options.IterCoeff;

            % set algorithm miscellaneous
            app.Spinner_VPL_Global.Limits = ...
                [1, floor(log(app.volopts.slices)/log(4))+1];
            app.Spinner_VPL_Global.Value = min(app.regopts.Options.glVPL, ...
                                               app.Spinner_VPL_Global.Limits(2));
            app.DropDown_Interp_Global.Value = app.regopts.Options.Interp;

            % set the panel locked status
            app.CheckBox_Lock_Global.Value = app.opt_locked.Global;
            if app.opt_locked.Global == true
                LockGlobalPanel(app); 
            else
                UnlockGlobalPanel(app);
            end

            %==========================================================
            % focus this panel
            app.TabGroup_AlgorithmSelection.SelectedTab = app.Tab_Global;
        end

        function SetGlobalPanelEnabling(app, state)
            arguments
                app
                state (1,1) string {mustBeMember(state, ["on","off"])}
            end
            app.EditField_Template_Global.Enable = state;
            app.DropDown_TemplateSampling_Global.Enable = state;
            app.DropDown_TformType_Global.Enable = state;
            app.DropDown_Structured_Global.Enable = state;
            app.DropDown_Functional_Global.Enable = state;
            app.DropDown_CoarseAlg_Global.Enable = state;
            app.DropDown_Detector_Global.Enable = state;
            app.EditField_QT_Global.Enable = state;
            app.Spinner_Octave_Global.Enable = state;
            app.EditField_MinStep_Global.Enable = state;
            app.EditField_MaxStep_Global.Enable = state;
            app.EditField_MedianFilter_Global.Enable = state;
            app.EditField_DilateFilter_Global.Enable = state;
            switch state
                case "off"
                    app.CheckBox_DilateFilterEnhancer_Global.Enable = state;
                case "on"
                    if  (app.regopts.Algorithm == "TCREG") && ...
                            (app.regopts.Mode == "global")
                        % refresh only 'TCREG' & 'global'
                        if app.regopts.Options.DilateFilter(1) ~= 0
                            app.CheckBox_DilateFilterEnhancer_Global.Enable = state;
                        end
                    end
            end
            if  (app.regopts.Algorithm == "TCREG") && ...
                    (app.regopts.Mode == "global")
                if isequal(app.regopts.Options.CoarseAlg, "none")
                    app.EditField_OptimalZ_MaxShift_Global.Enable = "off";
                    app.EditField_OptimalZ_Tol_Global.Enable = "off";
                else
                    app.EditField_OptimalZ_MaxShift_Global.Enable = state;
                    app.EditField_OptimalZ_Tol_Global.Enable = state;
                end
            end
            app.EditField_GaussianFilter_Global.Enable = state;
            app.EditField_Gamma_Global.Enable = state;
            app.DropDown_RegModal.Enable = state;
            app.DropDown_DownSampling_Global.Enable = state;
            app.EditField_MaxIterN_Global.Enable = state;
            app.EditField_IterCoeff_Global.Enable = state;
            app.Spinner_VPL_Global.Enable = state;
            app.DropDown_Interp_Global.Enable = state;
        end

        function LockGlobalPanel(app)
            SetGlobalPanelEnabling(app, "off");
            app.Tab_Global.ForegroundColor = [1, 0, 0];
            app.CheckBox_Lock_Global.FontColor = [1, 0, 0];
        end

        function UnlockGlobalPanel(app)
            SetGlobalPanelEnabling(app, "on");
            app.Tab_Global.ForegroundColor = [0, 0, 0];
            app.CheckBox_Lock_Global.FontColor = [0, 0, 0];
        end

        %=========================================================

        %==================== LOCAL PANEL ========================
        % Local panel setting include: 
        % <SetLocalPanel>: set the controller value
        % <SetLocalPanelEnabling>: set the controller enable status
        % <LockLocalPanel>: set the controllers disabled
        % <UnlockLocalPanel>: set the controllers enabled

        function SetLocalPanel(app)
            % ================= SET THE COMMON ARGUMENTS =================
            % set registration template
            app.EditField_Template_Local.Value = app.fixdef.Sampling(2);
            app.DropDown_TemplateSampling_Local.Value = app.fixdef.Sampling(1);
            
            % set the structured and functional channels
            app.DropDown_Structured_Local.Value = app.regopts.Options.SC;
            app.DropDown_Functional_Local.Value = app.regopts.Options.FC;

            % set the fluoscrence replenishment arguments
            app.CheckBox_RepAccOn_Local.Value = app.regopts.Options.ImageRehist;
            
            app.DropDown_RepAcc_Local.Value = string(app.regopts.Options.RepAcc);
            app.DropDown_RepAcc_Local.Enable = app.regopts.Options.ImageRehist;


            % ================= SET THE DEFORM ARGUMENTS =================
            app.EditField_GridRegularization_Local.Value = app.regopts.Options.GR;
            app.EditField_GridSpacing_Local.Value = ...
                string(app.regopts.Options.GS).join(",");
            app.Spinner_NS_VPL_Local.Limits = ...
                [1, floor(log2(app.volopts.slices/0.7))];
            app.Spinner_NS_VPL_Local.Value = min(app.regopts.Options.dfVPL, ...
                                                 app.Spinner_NS_VPL_Local.Limits(2));

            % ================ SET THE CPD ARGUMENTS ===================
            % set iteration options
            app.EditField_IterNumMax_CPD.Value = app.regopts.Options.MaxIterN(1);
            
            % set algorithm options
            app.Spinner_GridStep_CPD.Value = app.regopts.Options.gStep;
            app.EditField_Flatness_CPD.Value = app.regopts.Options.flnss;
            app.EditField_MismatchTol_CPD.Value = app.regopts.Options.mmtol;
            
            % ================ SET THE DEMONS ARGUMENTS ===============
            % set iteration options
            app.EditField_MaxIterN_Local.Value = ...
                string(app.regopts.Options.MaxIterN).join(",");

            % set algorithm options
            app.EditField_AFS_Local.Value = app.regopts.Options.AFS;
            app.Spinner_AS_VPL_Local.Limits = [1, floor(app.volopts.slices/2)];
            app.Spinner_AS_VPL_Local.Value = min(app.regopts.Options.dmVPL, ...
                                                 app.Spinner_AS_VPL_Local.Limits(2));
            app.DropDown_Interp_Local.Value = app.regopts.Options.Interp;

            % set the panel locked status
            app.CheckBox_Lock_Local.Value = app.opt_locked.Local;
            if app.opt_locked.Local == true
                LockLocalPanel(app);
            else
                UnlockLocalPanel(app);
            end

            %============================================================
            % focus this panel
            app.TabGroup_AlgorithmSelection.SelectedTab = app.Tab_Local;
        end

        function SetLocalPanelEnabling(app, state)
            arguments
                app
                state (1,1) string {mustBeMember(state, ["on","off"])}
            end

            app.EditField_Template_Local.Enable = state;
            app.DropDown_TemplateSampling_Local.Enable = state;
            app.DropDown_Structured_Local.Enable = state;
            app.DropDown_Functional_Local.Enable = state;
            app.CheckBox_RepAccOn_Local.Enable = state;
            if state == "on"
                if app.CheckBox_RepAccOn_Local.Value == true
                    app.DropDown_RepAcc_Local.Enable = state;
                end
            else
                app.DropDown_RepAcc_Local.Enable = state;
            end
            app.EditField_GridRegularization_Local.Enable = state;
            app.EditField_GridSpacing_Local.Enable = state;
            app.Spinner_NS_VPL_Local.Enable = state;
            app.EditField_MaxIterN_Local.Enable = state;
            app.EditField_AFS_Local.Enable = state;
            app.Spinner_AS_VPL_Local.Enable = state;
            app.DropDown_Interp_Local.Enable = state;
        end

        function LockLocalPanel(app)
            SetLocalPanelEnabling(app, "off");
            app.Tab_Local.ForegroundColor = [1, 0, 0];
            app.CheckBox_Lock_Local.FontColor = [1, 0, 0];
        end

        function UnlockLocalPanel(app)
            SetLocalPanelEnabling(app, "on");
            app.Tab_Local.ForegroundColor = [0, 0, 0];
            app.CheckBox_Lock_Local.FontColor = [0, 0, 0];
        end

        %=========================================================

        %===================== MGLOBAL PANEL =====================
        % MGlobal panel setting include: 
        % <SetLocalPanel>: set the controller value
        % <SetLocalPanelEnabling>: set the controller enable status
        % <LockLocalPanel>: set the controllers disabled
        % <UnlockLocalPanel>: set the controllers enabled

        function SetMGlobalPanel(app)
            % =============== SET REGISTRATION PARAMETERS ================
            app.DropDown_DView_MGlobal.Value = app.regopts.Options.DView;
            app.DropDown_Projection_MGlobal.Value = app.regopts.Options.Projection;
            app.DropDown_RegTform_MGlobal.Value = app.regopts.Options.TformType;

            switch app.regopts.Options.TformType
                case "translation"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 1;
                case "rigid"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 2;
                case "affine"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 3;
                case "pwl"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 4;
                case "polynomial"
                    app.Spinner_PolyOrder_MGlobal.Value = app.regopts.Options.Degree;
                    app.EditField_CPNumber_MGlobal.Value = ...
                        nchoosek(app.regopts.Options.Degree+2, 2);
                    app.Label_24.Visible = "on";
                    app.Spinner_PolyOrder_MGlobal.Visible = "on";

            end

            app.DropDown_Interp_MGlobal.Value = app.regopts.Options.Interp;
            app.CheckBox_ReSampleIsometric_MGlobal.Value = ...
                app.regopts.Options.Isometric;

            if app.regopts.Options.Isometric == false
                app.EditField_ReSampleRatio_MGlobal.Editable = "on";
            else
                app.EditField_ReSampleRatio_MGlobal.Editable = "off";
            end
            app.EditField_ReSampleRatio_MGlobal.Value ...
                = string(app.regopts.Options.Resampling).join(",");

            app.CheckBox_ApplyTransformation.Value = ...
                app.regopts.Options.TFEnable;

            SetMGlobalTFMatrixView(app, app.regopts.Options.TFMatrix);

            ApplyConstrainOnMGlobalTF(app, app.regopts.Options.TformType);

            % set the panel locked status
            app.CheckBox_Lock_MGlobal.Value = app.opt_locked.MGlobal;
            if app.opt_locked.MGlobal == true
                LockMGlobalPanel(app); 
            else
                UnlockMGlobalPanel(app);
            end
            
            %============================================================
            % focus this panel
            app.TabGroup_AlgorithmSelection.SelectedTab = app.Tab_MGlobal;
        end

        function SetMGlobalPanelEnabling(app, state)
            arguments
                app
                state (1,1) string {mustBeMember(state, ["on","off"])}
            end
            app.DropDown_DView_MGlobal.Enable = state;
            app.DropDown_Projection_MGlobal.Enable = state;
            app.DropDown_RegTform_MGlobal.Enable = state;
            app.EditField_CPNumber_MGlobal.Enable = state;
            app.DropDown_Interp_MGlobal.Enable = state;
            app.CheckBox_ReSampleIsometric_MGlobal.Enable = state;
            app.EditField_ReSampleRatio_MGlobal.Enable = state;
        end

        function LockMGlobalPanel(app)
            SetMGlobalPanelEnabling(app, "off");
            app.Tab_MGlobal.ForegroundColor = [1, 0, 0];
            app.CheckBox_Lock_MGlobal.FontColor = [1, 0, 0];
        end

        function UnlockMGlobalPanel(app)
            SetMGlobalPanelEnabling(app, "on");
            app.Tab_MGlobal.ForegroundColor = [0, 0, 0];
            app.CheckBox_Lock_MGlobal.FontColor = [0, 0, 0];
        end

        %=========================================================

        %===================== LTGLOBAL PANEL =====================
        % LTGlobal panel setting include: 
        % <SetLocalPanel>: set the controller value
        % <SetLocalPanelEnabling>: set the controller enable status
        % <LockLocalPanel>: set the controllers disabled
        % <UnlockLocalPanel>: set the controllers enabled
        function SetLTGlobalPanel(app)
            % ================== SET THE USUAL ARGUMENTS ================
            % set registration template
            app.EditField_Template_LTGlobal.Value = app.fixdef.Sampling(2);
            app.CheckBox_AutoTemplate_LTGlobal.Value = ...
                app.regopts.Options.AutoTemplate;
            app.DropDown_TemplateSampling_LTGlobal.Value = app.fixdef.Sampling(1);
            
            % force enable the auto template generation
            app.EditField_Template_LTGlobal.Enable = ...
                ~app.regopts.Options.AutoTemplate;
            app.DropDown_TemplateSampling_LTGlobal.Enable = ...
                ~app.regopts.Options.AutoTemplate;

            % set the structured and functional channels
            app.DropDown_Structured_LTGlobal.Value = app.regopts.Options.SC;
            app.DropDown_Functional_LTGlobal.Value = app.regopts.Options.FC;

            % set iteration options
            app.EditField_MinStep_LTGlobal.Value = app.regopts.Options.MinStep;
            app.EditField_MaxStep_LTGlobal.Value = app.regopts.Options.MaxStep;
            app.EditField_MaxIterN_LTGlobal.Value = app.regopts.Options.MaxIterN;
            app.EditField_IterCoeff_LTGlobal.Value = app.regopts.Options.IterCoeff;

            %================== SET THE ADVANCED ARGUMENTS ================
            % set the preprocessing arguments
            app.EditField_OptimalZ_MaxShift_LTGlobal.Value ...
                = app.regopts.Options.MaxZOptShift;
            app.EditField_DilateFilter_LTGlobal.Value ...
                = string(app.regopts.Options.DilateFilter).join(",");

            % set the registration chain key frames
            app.EditField_Keyframes_LTGlobal.Value = ...
                string(app.regopts.Options.Keyframes).join(",");
            app.CheckBox_AutoKeyframe_LTGlobal.Value = ...
                app.regopts.Options.AutoKeyframe;
            app.EditField_Keyframes_LTGlobal.Enable = ...
                ~app.regopts.Options.AutoKeyframe;
            
            % set other fine registration parameters
            % set downsampling options
            switch app.regopts.Options.DS
                case "1X1"
                    app.DropDown_DownSampling_LTGlobal.Value = "close";
                otherwise
                    app.DropDown_DownSampling_LTGlobal.Value = ...
                        app.regopts.Options.DS;
            end
            
            % set partition
            app.Spinner_TGridMin_LTGlobal.Value = app.regopts.Options.TGridMinMax(1);
            app.Spinner_TGridMax_LTGlobal.Value = app.regopts.Options.TGridMinMax(2);

            % update motion viewer
            UpdateMotionViewer(app);

            % set the panel locked status
            app.CheckBox_Lock_LTGlobal.Value = app.opt_locked.LTGlobal;
            if app.opt_locked.LTGlobal == true
                LockLTGlobalPanel(app); 
            else
                UnlockLTGlobalPanel(app);
            end

            %============================================================
            % focus this panel
            app.TabGroup_AlgorithmSelection.SelectedTab = app.Tab_LTGlobal;
        end

        function SetLTGlobalPanelEnabling(app, state)
            arguments
                app
                state (1,1) string {mustBeMember(state, ["on","off"])}
            end
            if state == "on"
                if app.CheckBox_AutoTemplate_LTGlobal.Value == false
                    app.EditField_Template_LTGlobal.Enable = state;
                    app.DropDown_TemplateSampling_LTGlobal.Enable = state;
                end
                if app.CheckBox_AutoKeyframe_LTGlobal.Value == false
                    app.EditField_Keyframes_LTGlobal.Enable = state;
                end
            else
                app.EditField_Template_LTGlobal.Enable = state;
                app.DropDown_TemplateSampling_LTGlobal.Enable = state;
                app.EditField_Keyframes_LTGlobal.Enable = state;
            end
            app.CheckBox_AutoTemplate_LTGlobal.Enable = state;
            app.DropDown_Structured_LTGlobal.Enable = state;
            app.DropDown_Functional_LTGlobal.Enable = state;
            app.EditField_MinStep_LTGlobal.Enable = state;
            app.EditField_MaxStep_LTGlobal.Enable = state;
            app.EditField_MaxIterN_LTGlobal.Enable = state;
            app.EditField_IterCoeff_LTGlobal.Enable = state;
            app.EditField_OptimalZ_MaxShift_LTGlobal.Enable = state;
            app.EditField_DilateFilter_LTGlobal.Enable = state;
            app.CheckBox_AutoKeyframe_LTGlobal.Enable = state;
            app.DropDown_DownSampling_LTGlobal.Enable = state;
            app.DropDown_Interp_LTGlobal.Enable = state;
            app.Spinner_TGridMin_LTGlobal.Enable = state;
            app.Spinner_TGridMax_LTGlobal.Enable = state;
        end

        function LockLTGlobalPanel(app)
            SetLTGlobalPanelEnabling(app, "off");
            app.Tab_LTGlobal.ForegroundColor = [1, 0, 0];
            app.CheckBox_Lock_LTGlobal.FontColor = [1, 0, 0];
        end

        function UnlockLTGlobalPanel(app)
            SetLTGlobalPanelEnabling(app, "on");
            app.Tab_LTGlobal.ForegroundColor = [0, 0, 0];
            app.CheckBox_Lock_LTGlobal.FontColor = [0, 0, 0];
        end

        %=========================================================

        % This function lock/unlock the target panel and synchronize others 
        % behaviour
        function UpdateLockGroup(app, panel, state)
            arguments
                app
                panel   (1,1)   string {mustBeMember(panel, ["Global","Local", ...
                    "MGlobal", "LTGlobal"])}
                state   (1,1)   logical
            end

            UnlockAllPanel(app);

            % clear flag table
            app.opt_locked{1,:} = false;
            app.opt_locked.(panel) = state;

            if state == true
                LockPanel(app, panel);
            end
        end

        function LockPanel(app, panel)
            arguments
                app %#ok<INUSA>
                panel   (1,1)   string {mustBeMember(panel, ["Global","Local", ...
                    "MGlobal", "LTGlobal"])}
            end

            eval("Lock"+panel+"Panel(app);");
            % add check box selection
            eval("app.CheckBox_Lock_"+panel+".Value = true;");
        end

        function UnlockAllPanel(app) %#ok<MANU>
            for panel = ["Global","Local","MGlobal", "LTGlobal"]
                eval("Unlock"+panel+"Panel(app);");
                % remove check box selection
                eval("app.CheckBox_Lock_"+panel+".Value = false;");
            end
        end

        function ApplyConstrainOnMGlobalTF(app, type)
            % only translation and affine are supported easily
            % disable 'rigid' and other transformation types
            switch type
                case "translation"
                    for m = 1:3
                        for n = 1:3
                            eval(sprintf("app.EditField_TM%d%d_MGlobal.Enable " + ...
                                "= 'off';", m,n));
                        end
                    end
                    app.EditField_TM14_MGlobal.Enable = "on";
                    app.EditField_TM24_MGlobal.Enable = "on";
                    app.EditField_TM34_MGlobal.Enable = "on";
                case "affine"
                    for m = 1:3
                        for n = 1:4
                            eval(sprintf("app.EditField_TM%d%d_MGlobal.Enable " + ...
                                "= 'on';", m,n));
                        end
                    end
                otherwise
                    for m = 1:3
                        for n = 1:4
                            eval(sprintf("app.EditField_TM%d%d_MGlobal.Enable " + ...
                                "= 'off';", m,n));
                        end
                    end
            end
        end

        function SetMGlobalTFMatrixView(app, mat)
            arguments
                app %#ok<INUSA>
                mat (4,4)   double
            end

            for m = 1:4
                for n = 1:4
                    eval(sprintf("app.EditField_TM%d%d_MGlobal.Value " + ...
                                "= '%s';", m,n, string(mat(m,n))));
                end
            end
        end

        function UpdateMotionViewer(app)
            if isempty(app.UIAxes_MotionViewer) ...
                    || ~isvalid(app.UIAxes_MotionViewer)
                app.UIAxes_MotionViewer = uiaxes(app.GridLayout62);
            end
            
            rc = app.regopts.Options.RegChain;

            if isempty(rc)
                cla(app.UIAxes_MotionViewer, "reset");
                app.UIAxes_MotionViewer.Toolbar = [];
                disableDefaultInteractivity(app.UIAxes_MotionViewer);
                app.UIAxes_MotionViewer.Visible = "off";
            else
                cinfo = rc.ChainInfo;

                % app.UIAxes_MotionViewer
                tblc = cinfo.data;
                rtpl = cinfo.info;

                frameidx = rtpl{1}; masshift = rtpl{5};
                
                % plot shift curve
                plot(app.UIAxes_MotionViewer, 1:numel(masshift), masshift, ...
                    "k-","LineWidth",1);
                hold(app.UIAxes_MotionViewer, "on");

                % scatters on the axis
                scatter(app.UIAxes_MotionViewer, tblc.kfidx, masshift(tblc.kfidx), ...
                    20, "black");
                scatter(app.UIAxes_MotionViewer, frameidx, masshift(frameidx), ...
                    25, "red", "filled", "s");

                % patch on the axis: visualize partial scope
                yl = ylim(app.UIAxes_MotionViewer);
                c = jet(size(tblc,1));
                for k = 1:size(tblc,1)
                    xx = [tblc.fscope(k,:)-0.5, fliplr(tblc.fscope(k,:))+0.5];
                    yy = [yl(1),yl(1),yl(2),yl(2)];
                    fill(app.UIAxes_MotionViewer, xx, yy, c(k,:), ...
                        "FaceAlpha",0.4,"EdgeColor","none");
                end
                hold(app.UIAxes_MotionViewer, "off");

                xlim(app.UIAxes_MotionViewer, [0.5, numel(masshift)+0.5]);

                app.UIAxes_MotionViewer.Toolbar = [];
                disableDefaultInteractivity(app.UIAxes_MotionViewer);

                app.UIAxes_MotionViewer.Visible = "on";
            end

            drawnow
        end

        function SaveMGlobalTFMatrixTo(app, row, col, val)
            tf = app.regopts.Options.TFMatrix;

            if isnan(val) || isinf(val) || ~isreal(val)
                eval(sprintf("app.EditField_TM%d%d_MGlobal.Value " + ...
                    "= string(tf(row,col));", row, col));
            else
                tf(row, col) = val;
                app.regopts = set(app.regopts, "TFMatrix", tf);
                app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
            end
        end

        function InitHardwareDetector(app)
            [hw_logo, app.hw_info] = GetHardwareInfo();
            app.Image_CPU.Visible = "on";
            app.Image_GPU.Visible = "on";

            switch hw_logo.cpu
                case "Intel"
                    app.Image_CPU.ImageSource = "sources/intel_logo.png";
                case "AMD"
                    app.Image_CPU.ImageSource = "sources/amd_logo.png";
                case "Unknown"
                    app.Image_CPU.ImageSource = "sources/unknown_logo.png";
                otherwise
                    app.Image_CPU.Visible = "off";
            end

            switch hw_logo.gpu
                case "Intel"
                    app.Image_GPU.ImageSource = "sources/intel_logo.png";
                case "AMD"
                    app.Image_GPU.ImageSource = "sources/amd_logo.png";
                case "Nvidia"
                    app.Image_GPU.ImageSource = "sources/nvidia_logo.png";
                case "Unknown"
                    app.Image_GPU.ImageSource = "sources/unknown_logo.png";
                otherwise
                    app.Image_GPU.Visible = "off";
            end

            app.Image_Memory.ImageSource = "sources/memory.png";
        end

        function [v, opt] = CalcPreProcRefVol(app)
            refvol = app.caller.GetRefVol();
            v = refvol.RefVol;
            % do preprocess
            switch app.TabGroup_AlgorithmSelection.SelectedTab.Tag
                case {'Global','LTGlobal'}
                    ds = app.regopts.Options.DS;
                    if ds == "auto", regds = inf;
                    else,            regds = 1/str2double(ds.extractBefore("X"));
                    end
                    [s, v] = Resample(v, regds);
                    if app.TabGroup_AlgorithmSelection.SelectedTab.Tag ...
                            == "Global"
                        v = preproc_tc(v, ...
                            app.regopts.Options.DilateFilter, ...
                            app.regopts.Options.MedianFilter, ...
                            app.regopts.Options.GaussianFilter, ...
                            app.regopts.Options.Gamma);
                    else
                        v = preproc_tc(v, ...
                            app.regopts.Options.DilateFilter);
                    end
                    if nargout == 2
                        opt = app.volopts;
                        opt.channels = 1; opt.frames = 1; opt.cOrder = app.fixdef.Channel;
                        opt.images = 1;
                        opt.xRes = app.volopts.xRes / s;
                        opt.yRes = app.volopts.yRes / s;
                    end
                otherwise
            end
        end

        function [pts, img] = CalcPreProcRefPtsPairs(app, v)
            img = max(v, [], 3);
            pts = imregfpp(img, img, app.regopts.Options.CoarseArgs);
        end
    end

    methods(Static)
        function r = calc_isometric(volopt)
            xr = volopt.xRes;
            yr = volopt.yRes;
            zr = volopt.zRes;

            rmin = min([xr, yr, zr]);
            r = round([xr, yr, zr]./rmin);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            InitData(app, mainapp);

            InitPanel(app);

            InitHardwareDetector(app);

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);
        end

        % Value changed function: EditField_Template_Global
        function EditField_Template_GlobalValueChanged(app, event)
            value = app.EditField_Template_Global.Value;
            value = string(value).replace("end",string(app.volopts.frames));
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(value <= 0)
                % blink the field
                FontBlink(app,"EditField_Template_Global");
                app.EditField_Template_Global.Value ...
                    = app.EditField_Template_Global.Placeholder;
                value = str2num(app.EditField_Template_Global.Placeholder); %#ok<ST2NM>
            end

            app.fixdef.Sampling(2) = string(value).join(",");

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_TemplateSampling_Global
        function DropDown_TemplateSampling_GlobalValueChanged(app, event)
            value = app.DropDown_TemplateSampling_Global.Value;

            app.fixdef.Sampling(1) = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_TformType_Global
        function DropDown_TformType_GlobalValueChanged(app, event)
            value = app.DropDown_TformType_Global.Value;

            switch value
                case "translation"
                    app.DropDown_TformType_Global.Tooltip = ...
                        app.langmap.TRANSLATION_TOOLTIP;
                case "rigid"
                    app.DropDown_TformType_Global.Tooltip = ...
                        app.langmap.RIGID_TOOLTIP;
                case "affine"
                    app.DropDown_TformType_Global.Tooltip = ...
                        app.langmap.AFFINE_TOOLTIP;
                otherwise
            end

            app.regopts = set(app.regopts, "TformType", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_RegModal
        function DropDown_RegModalValueChanged(app, event)
            value = app.DropDown_RegModal.Value;

            switch value
                case "monomodal"
                    app.Label_5.Text = app.langmap.MIN_ITERATION_STEP;
                    app.Label_6.Text = app.langmap.MAX_ITERATION_STEP;
                    app.Label_8.Text = app.langmap.ITERATION_COEFFICIENT;
                    app.EditField_IterCoeff_Global.Limits = [0, 1];
                case "multimodal"
                    app.Label_5.Text = app.langmap.MIN_SEARCHING_RADIUS;
                    app.Label_6.Text = app.langmap.MAX_SEARCHING_RADIUS;
                    app.Label_8.Text = app.langmap.GROWTH_FACTOR;
                    app.EditField_IterCoeff_Global.Limits = [1, inf];
                otherwise
            end
            
            app.regopts = set(app.regopts, "RegModal", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Structured_Global
        function DropDown_Structured_GlobalValueChanged(app, event)
            value = app.DropDown_Structured_Global.Value;

            app.fixdef.Channel = value;

            app.regopts = set(app.regopts, "SC", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Functional_Global
        function DropDown_Functional_GlobalValueChanged(app, event)
            value = app.DropDown_Functional_Global.Value;
            
            app.regopts = set(app.regopts, "FC", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MinStep_Global
        function EditField_MinStep_GlobalValueChanged(app, event)
            value = app.EditField_MinStep_Global.Value;

            app.regopts = set(app.regopts, "MinStep", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MaxStep_Global
        function EditField_MaxStep_GlobalValueChanged(app, event)
            value = app.EditField_MaxStep_Global.Value;
            
            app.regopts = set(app.regopts, "MaxStep", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Image clicked function: Image_CPU
        function Image_CPUClicked(app, event)
            uialert(app.TCRSUIFigure,app.hw_info.cpu, ...
                app.langmap.INFORMATION, "Icon","info");
        end

        % Value changed function: EditField_MaxIterN_Global
        function EditField_MaxIterN_GlobalValueChanged(app, event)
            value = app.EditField_MaxIterN_Global.Value;
            
            app.regopts = set(app.regopts, "MaxIterN", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_IterCoeff_Global
        function EditField_IterCoeff_GlobalValueChanged(app, event)
            value = app.EditField_IterCoeff_Global.Value;
            
            app.regopts = set(app.regopts, "IterCoeff", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_DownSampling_Global
        function DropDown_DownSampling_GlobalValueChanged(app, event)
            value = app.DropDown_DownSampling_Global.Value;
            
            switch value
                case "close"
                    app.regopts = set(app.regopts, "DS", "1X1");
                otherwise
                    app.regopts = set(app.regopts, "DS", string(value));
            end

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Interp_Global
        function DropDown_Interp_GlobalValueChanged(app, event)
            value = app.DropDown_Interp_Global.Value;
            
            app.regopts = set(app.regopts, "Interp", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_VPL_Global
        function Spinner_VPL_GlobalValueChanged(app, event)
            value = app.Spinner_VPL_Global.Value;
            
            app.regopts = set(app.regopts, "glVPL", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_Lock_Global
        function CheckBox_Lock_GlobalValueChanged(app, event)
            value = app.CheckBox_Lock_Global.Value;

            UpdateLockGroup(app, "Global", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);

            if value == true
                app.caller.SendMessage(["REGMODE_CURRENT_GLOBAL",""]);
            end
        end

        % Value changed function: EditField_Template_Local
        function EditField_Template_LocalValueChanged(app, event)
            value = app.EditField_Template_Local.Value;
            value = string(value).replace("end",string(app.volopts.frames));
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(value <= 0)
                % blink the field
                FontBlink(app, "EditField_Template_Local");
                app.EditField_Template_Local.Value ...
                    = app.EditField_Template_Local.Placeholder;
                value = str2num(app.EditField_Template_Local.Placeholder); %#ok<ST2NM>
            end

            app.fixdef.Sampling(2) = string(value).join(",");

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_TemplateSampling_Local
        function DropDown_TemplateSampling_LocalValueChanged(app, event)
            value = app.DropDown_TemplateSampling_Local.Value;

            app.fixdef.Sampling(1) = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Structured_Local
        function DropDown_Structured_LocalValueChanged(app, event)
            value = app.DropDown_Structured_Local.Value;

            app.fixdef.Channel = value;
            
            app.regopts = set(app.regopts, "SC", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Functional_Local
        function DropDown_Functional_LocalValueChanged(app, event)
            value = app.DropDown_Functional_Local.Value;
            
            app.regopts = set(app.regopts, "FC", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MaxIterN_Local
        function EditField_MaxIterN_LocalValueChanged(app, event)
            value = app.EditField_MaxIterN_Local.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || numel(value)>app.regopts.Options.VPL
                % blink the field
                FontBlink(app,"EditField_MaxIterN_Local");
                app.EditField_MaxIterN_Local.Value = ...
                    app.EditField_MaxIterN_Local.Placeholder;
                value = str2num(app.EditField_MaxIterN_Local.Placeholder); %#ok<ST2NM>
            end

            app.regopts = set(app.regopts, "MaxIterN", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_AFS_Local
        function EditField_AFS_LocalValueChanged(app, event)
            value = app.EditField_AFS_Local.Value;

            app.regopts = set(app.regopts, "AFS", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Image clicked function: Image_GPU
        function Image_GPUClicked(app, event)
            uialert(app.TCRSUIFigure,app.hw_info.gpu, ...
                app.langmap.INFORMATION, "Icon","info");
        end

        % Value changed function: CheckBox_RepAccOn_Local
        function CheckBox_RepAccOn_LocalValueChanged(app, event)
            value = app.CheckBox_RepAccOn_Local.Value;

            app.DropDown_RepAcc_Local.Enable = value;

            app.regopts = set(app.regopts, "ImageRehist", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_RepAcc_Local
        function DropDown_RepAcc_LocalValueChanged(app, event)
            value = app.DropDown_RepAcc_Local.Value;
            
            app.regopts = set(app.regopts, "RepAcc", str2double(value));

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Interp_Local
        function DropDown_Interp_LocalValueChanged(app, event)
            value = app.DropDown_Interp_Local.Value;
            
            app.regopts = set(app.regopts, "Interp", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_Lock_Local
        function CheckBox_Lock_LocalValueChanged(app, event)
            value = app.CheckBox_Lock_Local.Value;

            if (value == true) && isMATLABReleaseOlderThan("R2022b")
                switch app.TabGroup_Local.SelectedTab.Tag
                    case "Usual"
                        warning("TCRegSetter:invalidLocalRegistrationOptions",...
                            "Can not use usual algorithm because MATLAB version < R2022b.");
                        app.CheckBox_Lock_Local.Value = false;
                        return;
                    otherwise
                end
            end

            UpdateLockGroup(app, "Local", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);

            if value == true
                app.caller.SendMessage(["REGMODE_CURRENT_LOCAL",""]);
            end
        end

        % Value changed function: DropDown_RegTform_MGlobal
        function DropDown_RegTform_MGlobalValueChanged(app, event)
            value = app.DropDown_RegTform_MGlobal.Value;
            
            switch value
                case "translation"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 1;
                case "rigid"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 2;
                case "affine"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 3;
                case "pwl"
                    app.Spinner_PolyOrder_MGlobal.Visible = "off";
                    app.Label_24.Visible = "off";
                    app.EditField_CPNumber_MGlobal.Value = 4;
                case "poly"
                    app.Spinner_PolyOrder_MGlobal.Visible = "on";
                    app.Label_24.Visible = "on";
                    app.EditField_CPNumber_MGlobal.Value ...
                        = nchoosek(app.Spinner_PolyOrder_MGlobal.Value+2,2);
                otherwise
            end

            ApplyConstrainOnMGlobalTF(app, value);

            app.regopts = set(app.regopts, "TformType", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_PolyOrder_MGlobal
        function Spinner_PolyOrder_MGlobalValueChanged(app, event)
            value = app.Spinner_PolyOrder_MGlobal.Value;

            % Synchronize minimum control point pairs number
            app.EditField_CPNumber_MGlobal.Value = nchoosek(value+2, 2);

            app.regopts = set(app.regopts, "Degree", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Interp_MGlobal
        function DropDown_Interp_MGlobalValueChanged(app, event)
            value = app.DropDown_Interp_MGlobal.Value;

            app.regopts = set(app.regopts, "Interp", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Close request function: TCRSUIFigure
        function TCRSUIFigureCloseRequest(app, event)
            % Set the registration options
            Close(app);
            
        end

        % Selection change function: TabGroup_AlgorithmSelection
        function TabGroup_AlgorithmSelectionSelectionChanged(app, event)
            selectedTab = app.TabGroup_AlgorithmSelection.SelectedTab;
            
            % refresh data before panel setting
            app.volopts = app.caller.GetImgInfo();

            switch selectedTab.Tag
                case "Global"
                    app.regopts.Algorithm = "TCREG";
                    app.regopts.Mode = "global";
                    SetGlobalPanel(app);
                case "Local"
                    app.regopts.Algorithm = "TCREG";
                    app.regopts.Mode = "local";
                    SetLocalPanel(app);
                case "MGlobal"
                    app.regopts.Algorithm = "MANREG";
                    app.regopts.Mode = "global";
                    SetMGlobalPanel(app);
                case "LTGlobal"
                    app.regopts.Algorithm = "LTREG";
                    app.regopts.Mode = "global";
                    SetLTGlobalPanel(app);
                otherwise
                    throw(MException("TCRegSetter:innerError", ...
                        "Undefined Tab tag."));
            end

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_Template_LTGlobal
        function EditField_Template_LTGlobalValueChanged(app, event)
            value = app.EditField_Template_LTGlobal.Value;
            value = string(value).replace("end",string(app.volopts.frames));
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(value <= 0)
                % blink the field
                FontBlink(app,"EditField_Template_LTGlobal");
                app.EditField_Template_LTGlobal.Value ...
                    = app.EditField_Template_LTGlobal.Placeholder;
                value = str2num(app.EditField_Template_LTGlobal.Placeholder); %#ok<ST2NM>
            end

            app.fixdef.Sampling(2) = string(value).join(",");

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_TemplateSampling_LTGlobal
        function DropDown_TemplateSampling_LTGlobalValueChanged(app, event)
            value = app.DropDown_TemplateSampling_LTGlobal.Value;
            
            app.fixdef.Sampling(1) = value;

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_Keyframes_LTGlobal
        function EditField_Keyframes_LTGlobalValueChanged(app, event)
            value = app.EditField_Keyframes_LTGlobal.Value;
            value = string(value).replace("end",string(app.volopts.frames));
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(value <= 0)
                % blink the field
                FontBlink(app,"EditField_KeyFrame_LTGlobal");
                app.EditField_Keyframes_LTGlobal.Value ...
                    = app.EditField_Keyframes_LTGlobal.Placeholder;
                value = [];
                app.EditField_Keyframes_LTGlobal.Editable = "off";
                app.CheckBox_AutoKeyframe_LTGlobal.Value = true;
                app.regopts = set(app.regopts, "AutoKeyframe", true);
            end

            app.regopts = set(app.regopts, "KeyFrames", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_AutoKeyframe_LTGlobal
        function CheckBox_AutoKeyframe_LTGlobalValueChanged(app, event)
            value = app.CheckBox_AutoKeyframe_LTGlobal.Value;
            
            app.EditField_Keyframes_LTGlobal.Enable = ~value;

            app.regopts = set(app.regopts, "AutoKeyframe", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Structured_LTGlobal
        function DropDown_Structured_LTGlobalValueChanged(app, event)
            value = app.DropDown_Structured_LTGlobal.Value;

            app.fixdef.Channel = value;

            app.regopts = set(app.regopts, "SC", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Functional_LTGlobal
        function DropDown_Functional_LTGlobalValueChanged(app, event)
            value = app.DropDown_Structured_LTGlobal.Value;

            app.regopts = set(app.regopts, "FC", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_OptimalZ_MaxShift_LTGlobal
        function EditField_OptimalZ_MaxShift_LTGlobalValueChanged(app, event)
            value = app.EditField_OptimalZ_MaxShift_LTGlobal.Value;
            
            app.regopts = set(app.regopts, "MaxZOptShift", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_DownSampling_LTGlobal
        function DropDown_DownSampling_LTGlobalValueChanged(app, event)
            value = app.DropDown_DownSampling_LTGlobal.Value;
            
            switch value
                case "close"
                    app.regopts = set(app.regopts, "DS", "1X1");
                otherwise
                    app.regopts = set(app.regopts, "DS", string(value));
            end

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MaxIterN_LTGlobal
        function EditField_MaxIterN_LTGlobalValueChanged(app, event)
            value = app.EditField_MaxIterN_LTGlobal.Value;

            app.regopts = set(app.regopts, "MaxIterN", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MinStep_LTGlobal
        function EditField_MinStep_LTGlobalValueChanged(app, event)
            value = app.EditField_MinStep_LTGlobal.Value;

            app.regopts = set(app.regopts, "MinStep", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MaxStep_LTGlobal
        function EditField_MaxStep_LTGlobalValueChanged(app, event)
            value = app.EditField_MaxStep_LTGlobal.Value;
            
            app.regopts = set(app.regopts, "MaxStep", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_IterCoeff_LTGlobal
        function EditField_IterCoeff_LTGlobalValueChanged(app, event)
            value = app.EditField_IterCoeff_LTGlobal.Value;

            app.regopts = set(app.regopts, "IterCoeff", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_Lock_LTGlobal
        function CheckBox_Lock_LTGlobalValueChanged(app, event)
            value = app.CheckBox_Lock_LTGlobal.Value;

            UpdateLockGroup(app, "LTGlobal", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);

            if value == true
                app.caller.SendMessage(["REGMODE_CURRENT_LTGLOBAL",""]);
            end
        end

        % Value changed function: EditField_MedianFilter_Global
        function EditField_MedianFilter_GlobalValueChanged(app, event)
            value = app.EditField_MedianFilter_Global.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value) ~= [1,3]) ...
                    || (all(value ~= 0) && any(mod(round(value),2)==0))
                app.EditField_MedianFilter_Global.Value ...
                    = app.EditField_MedianFilter_Global.Placeholder;
                value = str2num(app.EditField_MedianFilter_Global.Placeholder); %#ok<ST2NM>
            end
            app.regopts = set(app.regopts, "MedianFilter", round(value));

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_DilateFilter_Global
        function EditField_DilateFilter_GlobalValueChanged(app, event)
            value = app.EditField_DilateFilter_Global.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value) ~= [1,4]) ...
                    || any(isnan(value)) || value(2) >= value(3)
                app.EditField_DilateFilter_Global.Value ...
                    = app.EditField_DilateFilter_Global.Placeholder;
                value = str2num(app.EditField_DilateFilter_Global.Placeholder); %#ok<ST2NM>
            end

            if round(value(1)) == 0    % disable the dilatefilter
                app.CheckBox_DilateFilterEnhancer_Global.Enable = "off";
                app.CheckBox_DilateFilterEnhancer_Global.Value = false;
                app.regopts = set(app.regopts, "DilateFilterEnh", false);
            else
                app.CheckBox_DilateFilterEnhancer_Global.Enable = "on";
            end

            app.regopts = set(app.regopts, "DilateFilter", round(value));

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_GaussianFilter_Global
        function EditField_GaussianFilter_GlobalValueChanged(app, event)
            value = app.EditField_GaussianFilter_Global.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value) ~= [1,3]) ...
                    || (all(value ~= 0) && any(mod(round(value),2)==0)) ...
                    || (all(value ~= 0) && any(value < 3))
                app.EditField_GaussianFilter_Global.Value ...
                    = app.EditField_GaussianFilter_Global.Placeholder;
                value = str2num(app.EditField_GaussianFilter_Global.Placeholder); %#ok<ST2NM>   
            end

            app.regopts = set(app.regopts, "GaussianFilter", round(value));

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_OptimalZ_MaxShift_Global
        function EditField_OptimalZ_MaxShift_GlobalValueChanged(app, event)
            value = app.EditField_OptimalZ_MaxShift_Global.Value;
            
            app.regopts = set(app.regopts, "MaxZOptShift", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_OptimalZ_Tol_Global
        function EditField_OptimalZ_Tol_GlobalValueChanged(app, event)
            value = app.EditField_OptimalZ_Tol_Global.Value;
            
            app.regopts = set(app.regopts, "TolZOpt", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_NS_VPL_Local
        function Spinner_NS_VPL_LocalValueChanged(app, event)
            value = app.Spinner_NS_VPL_Local.Value;

            app.regopts = set(app.regopts, "dfVPL", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_Lock_MGlobal
        function CheckBox_Lock_MGlobalValueChanged(app, event)
            value = app.CheckBox_Lock_MGlobal.Value;
            
            UpdateLockGroup(app, "MGlobal", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);

            if value == true
                app.caller.SendMessage(["REGMODE_CURRENT_MGLOBAL",""]);
            end
        end

        % Value changed function: EditField_GridRegularization_Local
        function EditField_GridRegularization_LocalValueChanged(app, event)
            value = app.EditField_GridRegularization_Local.Value;

            app.regopts = set(app.regopts, "GR", value);
            
            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_GridSpacing_Local
        function EditField_GridSpacing_LocalValueChanged(app, event)
            value = app.EditField_GridSpacing_Local.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value) ~= [1,3]) ...
                    || any(value == 0)
                app.EditField_GridSpacing_Local.Value ...
                    = app.EditField_GridSpacing_Local.Placeholder;
                value = str2num(app.EditField_GridSpacing_Local.Placeholder); %#ok<ST2NM>
            end
            
            app.regopts = set(app.regopts, "GS", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_AS_VPL_Local
        function Spinner_AS_VPL_LocalValueChanged(app, event)
            value = app.Spinner_AS_VPL_Local.Value;
            
            app.regopts = set(app.regopts, "dmVPL", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_DView_MGlobal
        function DropDown_DView_MGlobalValueChanged(app, event)
            value = app.DropDown_DView_MGlobal.Value;

            app.regopts = set(app.regopts, "DView", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Projection_MGlobal
        function DropDown_Projection_MGlobalValueChanged(app, event)
            value = app.DropDown_Projection_MGlobal.Value;

            app.regopts = set(app.regopts, "Projection", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_ReSampleRatio_MGlobal
        function EditField_ReSampleRatio_MGlobalValueChanged(app, event)
            value = app.EditField_ReSampleRatio_MGlobal.Value;
            
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value) ~= [1,3])
                app.EditField_ReSampleRatio_MGlobal.Value ...
                    = app.EditField_ReSampleRatio_MGlobal.Placeholder;
                value = str2num(app.EditField_ReSampleRatio_MGlobal.Placeholder); %#ok<ST2NM>
            end

            app.regopts = set(app.regopts, "Resampling", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_ReSampleIsometric_MGlobal
        function CheckBox_ReSampleIsometric_MGlobalValueChanged(app, event)
            value = app.CheckBox_ReSampleIsometric_MGlobal.Value;
            
            if value == true
                r = TCRegSetter.calc_isometric(app.volopts);
                app.EditField_ReSampleRatio_MGlobal.Editable = "off";
                app.EditField_ReSampleRatio_MGlobal.Value = string(r).join(",");
                app.regopts = set(app.regopts, "Resampling", r);
            else
                app.EditField_ReSampleRatio_MGlobal.Editable = "on";
            end

            app.regopts = set(app.regopts, "Isometric", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Selection change function: TabGroup_Local
        function TabGroup_LocalSelectionChanged(app, event)
            selectedTab = app.TabGroup_Local.SelectedTab;
            
            switch selectedTab.Tag
                case "DEFORM"
                    app.regopts.SubAlgorithm = "deform";
                case "CPD"
                    app.regopts.SubAlgorithm = "cpd";
                case "DEMONS"
                    app.regopts.SubAlgorithm = "demons";
                otherwise
            end

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Image clicked function: Image_Memory
        function Image_MemoryClicked(app, event)
            % this callback will display the memory using estimation and
            % memory review
            uialert(app.TCRSUIFigure,app.hw_info.mem, ...
                app.langmap.INFORMATION, "Icon","info");
        end

        % Value changed function: EditField_Gamma_Global
        function EditField_Gamma_GlobalValueChanged(app, event)
            value = app.EditField_Gamma_Global.Value;
            
            app.regopts = set(app.regopts, "Gamma", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Key press function: TCRSUIFigure
        function TCRSUIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case {'v', 'V'}
                    % 'V' for preview the processed volume
                    vol = CalcPreProcRefVol(app);
                    if isempty(app.fig_refvol) || ~isvalid(app.fig_refvol)
                        app.fig_refvol = figure("Name", "Ref-Vol Preview");
                    else
                        clf(app.fig_refvol, "reset");
                    end
                    h_sv = sliceViewer(vol, "Colormap", jet(256), ...
                        "Parent", app.fig_refvol);
                    ax_sv = getAxesHandle(h_sv);
                    colorbar(ax_sv, "eastoutside");
                    
                    if app.regopts.Algorithm == "TCREG" && ...
                            app.regopts.Options.CoarseAlg == "fpp"
                        % if is feature points matching, also view the
                        % points matching results
                        [pts, img] = CalcPreProcRefPtsPairs(app, vol);
                        if isempty(app.fig_fpp) || ~isvalid(app.fig_fpp)
                            app.fig_fpp = figure("Name", "FPP Preview");
                        else
                            clf(app.fig_fpp, "reset");
                        end
                        ax_mf = axes(app.fig_fpp);
                        showMatchedFeatures(img, img, pts{1}, pts{2}, ...
                            'montage','Parent',ax_mf);
                    end
                case {'e','E'}
                    % 'E' for export the template volume
                    [vol, opts] = CalcPreProcRefVol(app);
                    [f,p] = uiputfile(".mat", app.langmap.EXPORT_TEMPLATE, "template.mat");
                    f = fullfile(p, f);
                    save(f, "vol", "opts");
                otherwise
            end
        end

        % Value changed function: DropDown_CoarseAlg_Global
        function DropDown_CoarseAlg_GlobalValueChanged(app, event)
            value = app.DropDown_CoarseAlg_Global.Value;

            switch value
                case "fpp"
                    app.DropDown_Detector_Global.Visible = "on";
                    app.Label_65.Visible = "on";
                    app.Label_64.FontColor = [0, 0, 1];
                    app.GridLayout_NS_Global.RowHeight = ...
                        {25,25,25,25,25,60,'1x'};
                    app.EditField_OptimalZ_MaxShift_Global.Enable = "on";
                    app.EditField_OptimalZ_Tol_Global.Enable = "on";
                otherwise
                    app.DropDown_Detector_Global.Visible = "off";
                    app.Label_65.Visible = "off";
                    app.Label_64.FontColor = [0, 0, 0];
                    app.GridLayout_NS_Global.RowHeight = ...
                        {25,25,25,25,0,60,'1x'};
                    if isequal(value, "none")
                        app.EditField_OptimalZ_MaxShift_Global.Enable = "off";
                        app.EditField_OptimalZ_Tol_Global.Enable = "off";
                    else
                        app.EditField_OptimalZ_MaxShift_Global.Enable = "on";
                        app.EditField_OptimalZ_Tol_Global.Enable = "on";
                    end
            end
            
            app.regopts = set(app.regopts, "CoarseAlg", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Detector_Global
        function DropDown_Detector_GlobalValueChanged(app, event)
            value = app.DropDown_Detector_Global.Value;

            switch value
                case {'KAZE','SIFT','SURF'}
                    app.Label_66.Text = app.langmap.THRESHOLD;
                case {'BRISK', 'Harris'}
                    app.Label_66.Text = app.langmap.QUALITY;
                otherwise
            end
            
            cargs = app.regopts.Options.CoarseArgs;
            cargs.Operator = value;

            app.regopts = set(app.regopts, "CoarseArgs", cargs);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_QT_Global
        function EditField_QT_GlobalValueChanged(app, event)
            value = app.EditField_QT_Global.Value;
            
            cargs = app.regopts.Options.CoarseArgs;
            cargs.QT = value;

            app.regopts = set(app.regopts, "CoarseArgs", cargs);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_Octave_Global
        function Spinner_Octave_GlobalValueChanged(app, event)
            value = app.Spinner_Octave_Global.Value;
            
            cargs = app.regopts.Options.CoarseArgs;
            cargs.NumOctave = value;

            app.regopts = set(app.regopts, "CoarseArgs", cargs);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_TM11_MGlobal
        function EditField_TM11_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM11_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 1, 1, value);
        end

        % Value changed function: EditField_TM12_MGlobal
        function EditField_TM12_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM12_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 1, 2, value);
        end

        % Value changed function: EditField_TM13_MGlobal
        function EditField_TM13_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM13_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 1, 3, value);
        end

        % Value changed function: EditField_TM14_MGlobal
        function EditField_TM14_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM14_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 1, 4, value);
        end

        % Value changed function: EditField_TM21_MGlobal
        function EditField_TM21_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM21_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 2, 1, value);
        end

        % Value changed function: EditField_TM22_MGlobal
        function EditField_TM22_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM22_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 2, 2, value);
        end

        % Value changed function: EditField_TM23_MGlobal
        function EditField_TM23_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM23_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 2, 3, value);
        end

        % Value changed function: EditField_TM24_MGlobal
        function EditField_TM24_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM24_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 2, 4, value);
        end

        % Value changed function: EditField_TM31_MGlobal
        function EditField_TM31_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM31_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 3, 1, value);
        end

        % Value changed function: EditField_TM32_MGlobal
        function EditField_TM32_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM32_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 3, 2, value);
        end

        % Value changed function: EditField_TM33_MGlobal
        function EditField_TM33_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM33_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 3, 3, value);
        end

        % Value changed function: EditField_TM34_MGlobal
        function EditField_TM34_MGlobalValueChanged(app, event)
            value = str2double(app.EditField_TM34_MGlobal.Value);
            SaveMGlobalTFMatrixTo(app, 3, 4, value);
        end

        % Value changed function: CheckBox_ApplyTransformation
        function CheckBox_ApplyTransformationValueChanged(app, event)
            value = app.CheckBox_ApplyTransformation.Value;
            
            app.regopts = set(app.regopts, "TFEnable", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_DilateFilterEnhancer_Global
        function CheckBox_DilateFilterEnhancer_GlobalValueChanged(app, event)
            value = app.CheckBox_DilateFilterEnhancer_Global.Value;
            
            app.regopts = set(app.regopts, "DilateFilterEnh", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: CheckBox_AutoTemplate_LTGlobal
        function CheckBox_AutoTemplate_LTGlobalValueChanged(app, event)
            value = app.CheckBox_AutoTemplate_LTGlobal.Value;

            app.EditField_Template_LTGlobal.Enable = ~value;
            app.DropDown_TemplateSampling_LTGlobal.Enable = ~value;
            
            app.regopts = set(app.regopts, "AutoTemplate", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_TGridMin_LTGlobal
        function Spinner_TGridMin_LTGlobalValueChanged(app, event)
            value = app.Spinner_TGridMin_LTGlobal.Value;
            tgmm = app.regopts.Options.TGridMinMax;
            tgmm(1) = value;

            app.regopts = set(app.regopts, "TGridMinMax", tgmm);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_TGridMax_LTGlobal
        function Spinner_TGridMax_LTGlobalValueChanged(app, event)
            value = app.Spinner_TGridMax_LTGlobal.Value;
            tgmm = app.regopts.Options.TGridMinMax;
            tgmm(2) = value;
            
            app.regopts = set(app.regopts, "TGridMinMax", tgmm);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: DropDown_Interp_LTGlobal
        function DropDown_Interp_LTGlobalValueChanged(app, event)
            value = app.DropDown_Interp_LTGlobal.Value;
            
            app.regopts = set(app.regopts, "Interp", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_DilateFilter_LTGlobal
        function EditField_DilateFilter_LTGlobalValueChanged(app, event)
            value = app.EditField_DilateFilter_LTGlobal.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value) ~= [1,4]) ...
                    || any(isnan(value)) || value(2) >= value(3)
                app.EditField_DilateFilter_LTGlobal.Value ...
                    = app.EditField_DilateFilter_LTGlobal.Placeholder;
                value = str2num(app.EditField_DilateFilter_LTGlobal.Placeholder); %#ok<ST2NM>
            end

            app.regopts = set(app.regopts, "DilateFilter", round(value));

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_IterNumMax_CPD
        function EditField_IterNumMax_CPDValueChanged(app, event)
            value = app.EditField_IterNumMax_CPD.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value)
                % blink the field
                FontBlink(app,"EditField_MaxIterN_Local");
                app.EditField_IterNumMax_CPD.Value = ...
                    app.EditField_IterNumMax_CPD.Placeholder;
                value = str2num(app.EditField_IterNumMax_CPD.Placeholder); %#ok<ST2NM>
            end

            app.regopts = set(app.regopts, "MaxIterN", value);

            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: Spinner_GridStep_CPD
        function Spinner_GridStep_CPDValueChanged(app, event)
            value = app.Spinner_GridStep_CPD.Value;

            app.regopts = set(app.regopts, "gStep", value);
            
            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_Flatness_CPD
        function EditField_Flatness_CPDValueChanged(app, event)
            value = app.EditField_Flatness_CPD.Value;
            
            app.regopts = set(app.regopts, "flnss", value);
            
            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end

        % Value changed function: EditField_MismatchTol_CPD
        function EditField_MismatchTol_CPDValueChanged(app, event)
            value = app.EditField_MismatchTol_CPD.Value;
            
            app.regopts = set(app.regopts, "mmtol", value);
            
            app.caller.SetRegOptions(app.regopts, app.fixdef, app.opt_locked);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TCRSUIFigure = uifigure('Visible', 'off');
            app.TCRSUIFigure.Name = 'TCRS';
            app.TCRSUIFigure.Icon = 'tcrs.png';
            app.TCRSUIFigure.CloseRequestFcn = createCallbackFcn(app, @TCRSUIFigureCloseRequest, true);
            app.TCRSUIFigure.KeyPressFcn = createCallbackFcn(app, @TCRSUIFigureKeyPress, true);
            app.TabGroup_AlgorithmSelection = uitabgroup(app.TCRSUIFigure);
            app.TabGroup_AlgorithmSelection.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_AlgorithmSelectionSelectionChanged, true);
            app.Tab_Global = uitab(app.TabGroup_AlgorithmSelection);
            app.Tab_Global.Tooltip = {'基于强度、相位和特征的自动全局几何变换'};
            app.Tab_Global.Title = '全局';
            app.Tab_Global.Tag = 'Global';
            app.GridLayout = uigridlayout(app.Tab_Global);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CheckBox_Lock_Global = uicheckbox(app.GridLayout2);
            app.CheckBox_Lock_Global.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Lock_GlobalValueChanged, true);
            app.CheckBox_Lock_Global.Tooltip = {'启用全局配准'};
            app.CheckBox_Lock_Global.Text = '锁定';
            app.Label_Helps_Global = uilabel(app.GridLayout2);
            app.Label_Helps_Global.Text = '';
            app.TabGroup_Global = uitabgroup(app.GridLayout);
            app.Tab_NS_Global = uitab(app.TabGroup_Global);
            app.Tab_NS_Global.Tooltip = {''};
            app.Tab_NS_Global.Title = '常用';
            app.GridLayout10 = uigridlayout(app.Tab_NS_Global);
            app.Panel_NS_Reg_Global = uipanel(app.GridLayout10);
            app.Panel_NS_Reg_Global.Title = '配准';
            app.GridLayout_NS_Global = uigridlayout(app.Panel_NS_Reg_Global);
            app.GridLayout11 = uigridlayout(app.GridLayout_NS_Global);
            app.Label_11 = uilabel(app.GridLayout11);
            app.Label_11.Text = '模板';
            app.EditField_Template_Global = uieditfield(app.GridLayout11, 'text');
            app.EditField_Template_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_Template_GlobalValueChanged, true);
            app.EditField_Template_Global.Placeholder = '1';
            app.CheckBox_AutoUpdateTemplate_Global = uicheckbox(app.GridLayout11);
            app.CheckBox_AutoUpdateTemplate_Global.Enable = 'off';
            app.CheckBox_AutoUpdateTemplate_Global.Text = '自动更新';
            app.CheckBox_AutoUpdateTemplate_Global.Value = true;
            app.GridLayout12 = uigridlayout(app.GridLayout_NS_Global);
            app.Label = uilabel(app.GridLayout12);
            app.Label.Text = '采样';
            app.DropDown_TemplateSampling_Global = uidropdown(app.GridLayout12);
            app.DropDown_TemplateSampling_Global.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_TemplateSampling_Global.ItemsData = {'mean', 'median', 'min', 'max'};
            app.DropDown_TemplateSampling_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_TemplateSampling_GlobalValueChanged, true);
            app.DropDown_TemplateSampling_Global.Tooltip = {''};
            app.DropDown_TemplateSampling_Global.Value = 'mean';
            app.Label_2 = uilabel(app.GridLayout12);
            app.Label_2.Text = '几何变换';
            app.DropDown_TformType_Global = uidropdown(app.GridLayout12);
            app.DropDown_TformType_Global.Items = {'平移', '刚体', '仿射'};
            app.DropDown_TformType_Global.ItemsData = {'translation', 'rigid', 'affine'};
            app.DropDown_TformType_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_TformType_GlobalValueChanged, true);
            app.DropDown_TformType_Global.Tooltip = {''};
            app.DropDown_TformType_Global.Value = 'translation';
            app.GridLayout13 = uigridlayout(app.GridLayout_NS_Global);
            app.Label_3 = uilabel(app.GridLayout13);
            app.Label_3.Text = '定位';
            app.DropDown_Structured_Global = uidropdown(app.GridLayout13);
            app.DropDown_Structured_Global.Items = {'红', '绿'};
            app.DropDown_Structured_Global.ItemsData = {'r', 'g'};
            app.DropDown_Structured_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_Structured_GlobalValueChanged, true);
            app.DropDown_Structured_Global.Tooltip = {''};
            app.DropDown_Structured_Global.Value = 'r';
            app.Label_4 = uilabel(app.GridLayout13);
            app.Label_4.Text = '信号';
            app.DropDown_Functional_Global = uidropdown(app.GridLayout13);
            app.DropDown_Functional_Global.Items = {'红', '绿'};
            app.DropDown_Functional_Global.ItemsData = {'r', 'g'};
            app.DropDown_Functional_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_Functional_GlobalValueChanged, true);
            app.DropDown_Functional_Global.Tooltip = {''};
            app.DropDown_Functional_Global.Value = 'g';
            app.GridLayout14 = uigridlayout(app.GridLayout_NS_Global);
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.Label_5 = uilabel(app.GridLayout15);
            app.Label_5.Text = '最小迭代步长';
            app.EditField_MinStep_Global = uieditfield(app.GridLayout15, 'numeric');
            app.EditField_MinStep_Global.Limits = [0 1];
            app.EditField_MinStep_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_MinStep_GlobalValueChanged, true);
            app.EditField_MinStep_Global.Tooltip = {''};
            app.EditField_MinStep_Global.Value = 0.0001;
            app.Label_6 = uilabel(app.GridLayout15);
            app.Label_6.Text = '最大迭代步长';
            app.EditField_MaxStep_Global = uieditfield(app.GridLayout15, 'numeric');
            app.EditField_MaxStep_Global.Limits = [0 1];
            app.EditField_MaxStep_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxStep_GlobalValueChanged, true);
            app.EditField_MaxStep_Global.Tooltip = {''};
            app.EditField_MaxStep_Global.Value = 0.1;
            app.Image_CPU = uiimage(app.GridLayout14);
            app.Image_CPU.ImageClickedFcn = createCallbackFcn(app, @Image_CPUClicked, true);
            app.Image_CPU.Visible = 'off';
            app.Image_CPU.Tooltip = {'CPU状态'};
            app.GridLayout66 = uigridlayout(app.GridLayout_NS_Global);
            app.Label_64 = uilabel(app.GridLayout66);
            app.Label_64.Text = '粗配准';
            app.DropDown_CoarseAlg_Global = uidropdown(app.GridLayout66);
            app.DropDown_CoarseAlg_Global.Items = {'质心估计', '相位相关', '特征点匹配', '关闭'};
            app.DropDown_CoarseAlg_Global.ItemsData = {'mmt', 'pcorr', 'fpp', 'none'};
            app.DropDown_CoarseAlg_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_CoarseAlg_GlobalValueChanged, true);
            app.DropDown_CoarseAlg_Global.Value = 'mmt';
            app.Label_65 = uilabel(app.GridLayout66);
            app.Label_65.Text = '算子';
            app.DropDown_Detector_Global = uidropdown(app.GridLayout66);
            app.DropDown_Detector_Global.Items = {'KAZE', 'SIFT', 'SURF', 'BRISK', 'Harris'};
            app.DropDown_Detector_Global.ItemsData = {'KAZE', 'SIFT', 'SURF', 'BRISK', 'Harris'};
            app.DropDown_Detector_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_Detector_GlobalValueChanged, true);
            app.DropDown_Detector_Global.Value = 'SIFT';
            app.GridLayout67 = uigridlayout(app.GridLayout_NS_Global);
            app.Label_66 = uilabel(app.GridLayout67);
            app.Label_66.Text = '阈值';
            app.EditField_QT_Global = uieditfield(app.GridLayout67, 'numeric');
            app.EditField_QT_Global.Limits = [0 1];
            app.EditField_QT_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_QT_GlobalValueChanged, true);
            app.EditField_QT_Global.Value = 0.0133;
            app.Label_67 = uilabel(app.GridLayout67);
            app.Label_67.Text = '倍率';
            app.Spinner_Octave_Global = uispinner(app.GridLayout67);
            app.Spinner_Octave_Global.Limits = [1 4];
            app.Spinner_Octave_Global.ValueDisplayFormat = '%.0f';
            app.Spinner_Octave_Global.ValueChangedFcn = createCallbackFcn(app, @Spinner_Octave_GlobalValueChanged, true);
            app.Spinner_Octave_Global.Value = 3;
            app.Tab_AS_Global = uitab(app.TabGroup_Global);
            app.Tab_AS_Global.Title = '高级';
            app.GridLayout3 = uigridlayout(app.Tab_AS_Global);
            app.Panel_AS_PP_Global = uipanel(app.GridLayout3);
            app.Panel_AS_PP_Global.Title = '预处理';
            app.GridLayout4 = uigridlayout(app.Panel_AS_PP_Global);
            app.GridLayout70 = uigridlayout(app.GridLayout4);
            app.Label_51 = uilabel(app.GridLayout70);
            app.Label_51.Text = '中值滤波器';
            app.EditField_MedianFilter_Global = uieditfield(app.GridLayout70, 'text');
            app.EditField_MedianFilter_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_MedianFilter_GlobalValueChanged, true);
            app.EditField_MedianFilter_Global.Placeholder = '3,3,3';
            app.Label_55 = uilabel(app.GridLayout70);
            app.Label_55.Text = '最大Z漂移';
            app.EditField_OptimalZ_MaxShift_Global = uieditfield(app.GridLayout70, 'numeric');
            app.EditField_OptimalZ_MaxShift_Global.Limits = [0 Inf];
            app.EditField_OptimalZ_MaxShift_Global.ValueDisplayFormat = '%.1f';
            app.EditField_OptimalZ_MaxShift_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_OptimalZ_MaxShift_GlobalValueChanged, true);
            app.EditField_OptimalZ_MaxShift_Global.Value = 2;
            app.Label_52 = uilabel(app.GridLayout70);
            app.Label_52.Text = '高斯滤波器';
            app.EditField_GaussianFilter_Global = uieditfield(app.GridLayout70, 'text');
            app.EditField_GaussianFilter_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_GaussianFilter_GlobalValueChanged, true);
            app.EditField_GaussianFilter_Global.Placeholder = '3,3,3';
            app.Label_56 = uilabel(app.GridLayout70);
            app.Label_56.Text = 'Z优化公差';
            app.EditField_OptimalZ_Tol_Global = uieditfield(app.GridLayout70, 'numeric');
            app.EditField_OptimalZ_Tol_Global.Limits = [0 1];
            app.EditField_OptimalZ_Tol_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_OptimalZ_Tol_GlobalValueChanged, true);
            app.EditField_OptimalZ_Tol_Global.Value = 0.001;
            app.GridLayout71 = uigridlayout(app.GridLayout4);
            app.Label_53 = uilabel(app.GridLayout71);
            app.Label_53.Text = '膨胀滤波器';
            app.EditField_DilateFilter_Global = uieditfield(app.GridLayout71, 'text');
            app.EditField_DilateFilter_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_DilateFilter_GlobalValueChanged, true);
            app.EditField_DilateFilter_Global.Placeholder = '3,115,65535,1000';
            app.Label_63 = uilabel(app.GridLayout71);
            app.Label_63.Text = '伽马';
            app.EditField_Gamma_Global = uieditfield(app.GridLayout71, 'numeric');
            app.EditField_Gamma_Global.Limits = [0 2];
            app.EditField_Gamma_Global.ValueDisplayFormat = '%.2f';
            app.EditField_Gamma_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_Gamma_GlobalValueChanged, true);
            app.EditField_Gamma_Global.Value = 1;
            app.CheckBox_DilateFilterEnhancer_Global = uicheckbox(app.GridLayout71);
            app.CheckBox_DilateFilterEnhancer_Global.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DilateFilterEnhancer_GlobalValueChanged, true);
            app.CheckBox_DilateFilterEnhancer_Global.Tooltip = {'强化算法自动调整滤波器参数以同步模板帧前景'};
            app.CheckBox_DilateFilterEnhancer_Global.Text = '强化';
            app.Panel_AS_Reg_Global = uipanel(app.GridLayout3);
            app.Panel_AS_Reg_Global.Title = '配准';
            app.GridLayout5 = uigridlayout(app.Panel_AS_Reg_Global);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Label_13 = uilabel(app.GridLayout6);
            app.Label_13.Text = '降采样';
            app.DropDown_DownSampling_Global = uidropdown(app.GridLayout6);
            app.DropDown_DownSampling_Global.Items = {'自动', '2X2', '3X3', '关闭'};
            app.DropDown_DownSampling_Global.ItemsData = {'auto', '2X2', '3X3', 'close'};
            app.DropDown_DownSampling_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_DownSampling_GlobalValueChanged, true);
            app.DropDown_DownSampling_Global.Tooltip = {''};
            app.DropDown_DownSampling_Global.Value = 'auto';
            app.Label_10 = uilabel(app.GridLayout6);
            app.Label_10.Text = '模态';
            app.DropDown_RegModal = uidropdown(app.GridLayout6);
            app.DropDown_RegModal.Items = {'单模态', '多模态'};
            app.DropDown_RegModal.ItemsData = {'monomodal', 'multimodal'};
            app.DropDown_RegModal.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegModalValueChanged, true);
            app.DropDown_RegModal.Tooltip = {''};
            app.DropDown_RegModal.Value = 'monomodal';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.Label_7 = uilabel(app.GridLayout7);
            app.Label_7.Text = '迭代数上限';
            app.EditField_MaxIterN_Global = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_MaxIterN_Global.Limits = [1 Inf];
            app.EditField_MaxIterN_Global.ValueDisplayFormat = '%.0f';
            app.EditField_MaxIterN_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_GlobalValueChanged, true);
            app.EditField_MaxIterN_Global.Tooltip = {''};
            app.EditField_MaxIterN_Global.Value = 50;
            app.Label_8 = uilabel(app.GridLayout7);
            app.Label_8.Text = '迭代因子';
            app.EditField_IterCoeff_Global = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_IterCoeff_Global.Limits = [0 1];
            app.EditField_IterCoeff_Global.ValueDisplayFormat = '%.3f';
            app.EditField_IterCoeff_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_IterCoeff_GlobalValueChanged, true);
            app.EditField_IterCoeff_Global.Tooltip = {''};
            app.EditField_IterCoeff_Global.Value = 0.5;
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.Label_9 = uilabel(app.GridLayout8);
            app.Label_9.Text = '插值';
            app.DropDown_Interp_Global = uidropdown(app.GridLayout8);
            app.DropDown_Interp_Global.Items = {'线性(快速)', '立方(高质量)'};
            app.DropDown_Interp_Global.ItemsData = {'linear', 'cubic'};
            app.DropDown_Interp_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_Interp_GlobalValueChanged, true);
            app.DropDown_Interp_Global.Tooltip = {''};
            app.DropDown_Interp_Global.Value = 'linear';
            app.Spinner_VPL_Global = uispinner(app.GridLayout8);
            app.Spinner_VPL_Global.Limits = [1 4];
            app.Spinner_VPL_Global.ValueDisplayFormat = '%.0f';
            app.Spinner_VPL_Global.ValueChangedFcn = createCallbackFcn(app, @Spinner_VPL_GlobalValueChanged, true);
            app.Spinner_VPL_Global.Tooltip = {''};
            app.Spinner_VPL_Global.Value = 3;
            app.Label_VPL_Global = uilabel(app.GridLayout8);
            app.Label_VPL_Global.Text = '金字塔因子';
            app.Tab_Local = uitab(app.TabGroup_AlgorithmSelection);
            app.Tab_Local.Tooltip = {'基于位移场估计的自动局部变形'};
            app.Tab_Local.Title = '局部';
            app.Tab_Local.Tag = 'Local';
            app.GridLayout16 = uigridlayout(app.Tab_Local);
            app.GridLayout32 = uigridlayout(app.GridLayout16);
            app.Panel_Common_Local = uipanel(app.GridLayout32);
            app.Panel_Common_Local.Title = '共有';
            app.GridLayout21 = uigridlayout(app.Panel_Common_Local);
            app.GridLayout22 = uigridlayout(app.GridLayout21);
            app.Label_21 = uilabel(app.GridLayout22);
            app.Label_21.Text = '模板';
            app.EditField_Template_Local = uieditfield(app.GridLayout22, 'text');
            app.EditField_Template_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_Template_LocalValueChanged, true);
            app.EditField_Template_Local.Value = '1';
            app.Label_14 = uilabel(app.GridLayout22);
            app.Label_14.Text = '采样';
            app.DropDown_TemplateSampling_Local = uidropdown(app.GridLayout22);
            app.DropDown_TemplateSampling_Local.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_TemplateSampling_Local.ItemsData = {'mean', 'median', 'min', 'max'};
            app.DropDown_TemplateSampling_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_TemplateSampling_LocalValueChanged, true);
            app.DropDown_TemplateSampling_Local.Tooltip = {''};
            app.DropDown_TemplateSampling_Local.Value = 'mean';
            app.GridLayout23 = uigridlayout(app.GridLayout21);
            app.Label_15 = uilabel(app.GridLayout23);
            app.Label_15.Text = '定位';
            app.DropDown_Structured_Local = uidropdown(app.GridLayout23);
            app.DropDown_Structured_Local.Items = {'红', '绿'};
            app.DropDown_Structured_Local.ItemsData = {'r', 'g'};
            app.DropDown_Structured_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_Structured_LocalValueChanged, true);
            app.DropDown_Structured_Local.Value = 'r';
            app.Label_18 = uilabel(app.GridLayout23);
            app.Label_18.Text = '信号';
            app.DropDown_Functional_Local = uidropdown(app.GridLayout23);
            app.DropDown_Functional_Local.Items = {'红', '绿'};
            app.DropDown_Functional_Local.ItemsData = {'r', 'g'};
            app.DropDown_Functional_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_Functional_LocalValueChanged, true);
            app.DropDown_Functional_Local.Value = 'g';
            app.GridLayout24 = uigridlayout(app.GridLayout21);
            app.Label_19 = uilabel(app.GridLayout24);
            app.Label_19.Enable = 'off';
            app.Label_19.Text = '精度';
            app.DropDown_RepAcc_Local = uidropdown(app.GridLayout24);
            app.DropDown_RepAcc_Local.Items = {'低', '一般', '高'};
            app.DropDown_RepAcc_Local.ItemsData = {'64', '128', '256'};
            app.DropDown_RepAcc_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_RepAcc_LocalValueChanged, true);
            app.DropDown_RepAcc_Local.Enable = 'off';
            app.DropDown_RepAcc_Local.Tooltip = {'淬灭补偿精度, 更高的精度代价是内存的消耗'};
            app.DropDown_RepAcc_Local.Value = '64';
            app.CheckBox_RepAccOn_Local = uicheckbox(app.GridLayout24);
            app.CheckBox_RepAccOn_Local.ValueChangedFcn = createCallbackFcn(app, @CheckBox_RepAccOn_LocalValueChanged, true);
            app.CheckBox_RepAccOn_Local.Tooltip = {'补偿恒定背景下的前景光漂白'};
            app.CheckBox_RepAccOn_Local.Text = '补偿光漂白';
            app.TabGroup_Local = uitabgroup(app.GridLayout32);
            app.TabGroup_Local.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_LocalSelectionChanged, true);
            app.Tab_NS_Local = uitab(app.TabGroup_Local);
            app.Tab_NS_Local.Title = '全变分';
            app.Tab_NS_Local.Tag = 'DEFORM';
            app.GridLayout20 = uigridlayout(app.Tab_NS_Local);
            app.Panel_NS_Local = uipanel(app.GridLayout20);
            app.Panel_NS_Local.Title = 'Imregdeform (Medical Imaging Toolbox)';
            app.GridLayout25 = uigridlayout(app.Panel_NS_Local);
            app.Label_58 = uilabel(app.GridLayout25);
            app.Label_58.Text = '位移正则化';
            app.EditField_GridRegularization_Local = uieditfield(app.GridLayout25, 'numeric');
            app.EditField_GridRegularization_Local.Limits = [0 5];
            app.EditField_GridRegularization_Local.ValueDisplayFormat = '%.2f';
            app.EditField_GridRegularization_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_GridRegularization_LocalValueChanged, true);
            app.EditField_GridRegularization_Local.Tooltip = {''};
            app.EditField_GridRegularization_Local.Value = 0.11;
            app.Label_57 = uilabel(app.GridLayout25);
            app.Label_57.Text = '网格间距';
            app.EditField_GridSpacing_Local = uieditfield(app.GridLayout25, 'text');
            app.EditField_GridSpacing_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_GridSpacing_LocalValueChanged, true);
            app.EditField_GridSpacing_Local.Placeholder = '4,4,4';
            app.Spinner_NS_VPL_Local = uispinner(app.GridLayout25);
            app.Spinner_NS_VPL_Local.Limits = [1 4];
            app.Spinner_NS_VPL_Local.ValueDisplayFormat = '%.0f';
            app.Spinner_NS_VPL_Local.ValueChangedFcn = createCallbackFcn(app, @Spinner_NS_VPL_LocalValueChanged, true);
            app.Spinner_NS_VPL_Local.Tooltip = {''};
            app.Spinner_NS_VPL_Local.Value = 3;
            app.Label_VPL_Global_2 = uilabel(app.GridLayout25);
            app.Label_VPL_Global_2.Text = '金字塔因子';
            app.Tab_GS_Local = uitab(app.TabGroup_Local);
            app.Tab_GS_Local.Title = '相干点漂移';
            app.Tab_GS_Local.Tag = 'CPD';
            app.GridLayout72 = uigridlayout(app.Tab_GS_Local);
            app.Panel_CPD_Local = uipanel(app.GridLayout72);
            app.Panel_CPD_Local.Title = 'pcregistercpd (Computer Vision Toolbox)';
            app.GridLayout73 = uigridlayout(app.Panel_CPD_Local);
            app.Label_72 = uilabel(app.GridLayout73);
            app.Label_72.Text = '迭代数上限';
            app.EditField_IterNumMax_CPD = uieditfield(app.GridLayout73, 'numeric');
            app.EditField_IterNumMax_CPD.Limits = [0 Inf];
            app.EditField_IterNumMax_CPD.ValueDisplayFormat = '%.0f';
            app.EditField_IterNumMax_CPD.ValueChangedFcn = createCallbackFcn(app, @EditField_IterNumMax_CPDValueChanged, true);
            app.EditField_IterNumMax_CPD.Placeholder = '20';
            app.EditField_IterNumMax_CPD.Value = 20;
            app.Label_73 = uilabel(app.GridLayout73);
            app.Label_73.Text = '网格步长';
            app.Spinner_GridStep_CPD = uispinner(app.GridLayout73);
            app.Spinner_GridStep_CPD.Step = 0.1;
            app.Spinner_GridStep_CPD.Limits = [0 5];
            app.Spinner_GridStep_CPD.ValueDisplayFormat = '%.1f';
            app.Spinner_GridStep_CPD.ValueChangedFcn = createCallbackFcn(app, @Spinner_GridStep_CPDValueChanged, true);
            app.Spinner_GridStep_CPD.Value = 2;
            app.Label_74 = uilabel(app.GridLayout73);
            app.Label_74.Text = '平整度';
            app.EditField_Flatness_CPD = uieditfield(app.GridLayout73, 'numeric');
            app.EditField_Flatness_CPD.Limits = [0 5];
            app.EditField_Flatness_CPD.ValueChangedFcn = createCallbackFcn(app, @EditField_Flatness_CPDValueChanged, true);
            app.EditField_Flatness_CPD.Value = 2;
            app.Label_75 = uilabel(app.GridLayout73);
            app.Label_75.Text = '失配容差';
            app.EditField_MismatchTol_CPD = uieditfield(app.GridLayout73, 'numeric');
            app.EditField_MismatchTol_CPD.Limits = [0 1];
            app.EditField_MismatchTol_CPD.ValueDisplayFormat = '%.2f';
            app.EditField_MismatchTol_CPD.ValueChangedFcn = createCallbackFcn(app, @EditField_MismatchTol_CPDValueChanged, true);
            app.EditField_MismatchTol_CPD.Value = 0.1;
            app.Tab_AS_Local = uitab(app.TabGroup_Local);
            app.Tab_AS_Local.Title = '光流场';
            app.Tab_AS_Local.Tag = 'DEMONS';
            app.GridLayout19 = uigridlayout(app.Tab_AS_Local);
            app.GridLayout26 = uigridlayout(app.GridLayout19);
            app.Panel_AS_Local = uipanel(app.GridLayout26);
            app.Panel_AS_Local.Title = 'Imregdemons (Image Processing Toolbox)';
            app.GridLayout27 = uigridlayout(app.Panel_AS_Local);
            app.GridLayout28 = uigridlayout(app.GridLayout27);
            app.Image_GPU = uiimage(app.GridLayout28);
            app.Image_GPU.ImageClickedFcn = createCallbackFcn(app, @Image_GPUClicked, true);
            app.Image_GPU.Visible = 'off';
            app.Image_GPU.Tooltip = {'GPU状态'};
            app.GridLayout29 = uigridlayout(app.GridLayout28);
            app.Label_20 = uilabel(app.GridLayout29);
            app.Label_20.Text = '迭代数上限';
            app.EditField_MaxIterN_Local = uieditfield(app.GridLayout29, 'text');
            app.EditField_MaxIterN_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_LocalValueChanged, true);
            app.EditField_MaxIterN_Local.Tooltip = {''};
            app.EditField_MaxIterN_Local.Placeholder = '100';
            app.Label_17 = uilabel(app.GridLayout29);
            app.Label_17.Text = '累积场平滑';
            app.EditField_AFS_Local = uieditfield(app.GridLayout29, 'numeric');
            app.EditField_AFS_Local.Limits = [0.5 3];
            app.EditField_AFS_Local.ValueDisplayFormat = '%.2f';
            app.EditField_AFS_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_AFS_LocalValueChanged, true);
            app.EditField_AFS_Local.Tooltip = {''};
            app.EditField_AFS_Local.Value = 1;
            app.GridLayout30 = uigridlayout(app.GridLayout27);
            app.GridLayout31 = uigridlayout(app.GridLayout27);
            app.Label_16 = uilabel(app.GridLayout31);
            app.Label_16.Text = '插值';
            app.DropDown_Interp_Local = uidropdown(app.GridLayout31);
            app.DropDown_Interp_Local.Items = {'线性(快速)', '立方(高质量)'};
            app.DropDown_Interp_Local.ItemsData = {'linear', 'cubic'};
            app.DropDown_Interp_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_Interp_LocalValueChanged, true);
            app.DropDown_Interp_Local.Tooltip = {''};
            app.DropDown_Interp_Local.Value = 'linear';
            app.Spinner_AS_VPL_Local = uispinner(app.GridLayout31);
            app.Spinner_AS_VPL_Local.Limits = [1 4];
            app.Spinner_AS_VPL_Local.ValueDisplayFormat = '%.0f';
            app.Spinner_AS_VPL_Local.ValueChangedFcn = createCallbackFcn(app, @Spinner_AS_VPL_LocalValueChanged, true);
            app.Spinner_AS_VPL_Local.Tooltip = {''};
            app.Spinner_AS_VPL_Local.Value = 3;
            app.Label_VPL_Global_3 = uilabel(app.GridLayout31);
            app.Label_VPL_Global_3.Text = '金字塔因子';
            app.GridLayout17 = uigridlayout(app.GridLayout16);
            app.CheckBox_Lock_Local = uicheckbox(app.GridLayout17);
            app.CheckBox_Lock_Local.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Lock_LocalValueChanged, true);
            app.CheckBox_Lock_Local.Tooltip = {'启用局部配准'};
            app.CheckBox_Lock_Local.Text = '锁定';
            app.Label_Helps_Local = uilabel(app.GridLayout17);
            app.Label_Helps_Local.Text = '';
            app.Tab_MGlobal = uitab(app.TabGroup_AlgorithmSelection);
            app.Tab_MGlobal.Tooltip = {'基于控制点的区域变形'};
            app.Tab_MGlobal.Title = '全局(手动)';
            app.Tab_MGlobal.Tag = 'MGlobal';
            app.GridLayout33 = uigridlayout(app.Tab_MGlobal);
            app.GridLayout34 = uigridlayout(app.GridLayout33);
            app.CheckBox_Lock_MGlobal = uicheckbox(app.GridLayout34);
            app.CheckBox_Lock_MGlobal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Lock_MGlobalValueChanged, true);
            app.CheckBox_Lock_MGlobal.Tooltip = {'启用手动配准'};
            app.CheckBox_Lock_MGlobal.Text = '锁定';
            app.Label_Helps_MANGlobal = uilabel(app.GridLayout34);
            app.Label_Helps_MANGlobal.Text = '';
            app.Panel_Reg_MGlobal = uipanel(app.GridLayout33);
            app.Panel_Reg_MGlobal.Title = '配准';
            app.GridLayout35 = uigridlayout(app.Panel_Reg_MGlobal);
            app.GridLayout41 = uigridlayout(app.GridLayout35);
            app.Label_23 = uilabel(app.GridLayout41);
            app.Label_23.Text = '最小控制';
            app.EditField_CPNumber_MGlobal = uieditfield(app.GridLayout41, 'numeric');
            app.EditField_CPNumber_MGlobal.Limits = [1 Inf];
            app.EditField_CPNumber_MGlobal.ValueDisplayFormat = '%.0f';
            app.EditField_CPNumber_MGlobal.Editable = 'off';
            app.EditField_CPNumber_MGlobal.Tooltip = {''};
            app.EditField_CPNumber_MGlobal.Value = 1;
            app.Label_59 = uilabel(app.GridLayout41);
            app.Label_59.Text = '视角(法向)';
            app.DropDown_DView_MGlobal = uidropdown(app.GridLayout41);
            app.DropDown_DView_MGlobal.Items = {'XY平面(Z)', 'ZX平面(Y)', 'YZ平面(X)'};
            app.DropDown_DView_MGlobal.ItemsData = {'XY', 'ZX', 'YZ'};
            app.DropDown_DView_MGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_DView_MGlobalValueChanged, true);
            app.DropDown_DView_MGlobal.Value = 'XY';
            app.GridLayout42 = uigridlayout(app.GridLayout35);
            app.Label_22 = uilabel(app.GridLayout42);
            app.Label_22.Text = '几何变换';
            app.DropDown_RegTform_MGlobal = uidropdown(app.GridLayout42);
            app.DropDown_RegTform_MGlobal.Items = {'平移', '刚体', '仿射', '分段线性', '多项式'};
            app.DropDown_RegTform_MGlobal.ItemsData = {'translation', 'rigid', 'affine', 'pwl', 'poly'};
            app.DropDown_RegTform_MGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegTform_MGlobalValueChanged, true);
            app.DropDown_RegTform_MGlobal.Tooltip = {''};
            app.DropDown_RegTform_MGlobal.Value = 'translation';
            app.Label_60 = uilabel(app.GridLayout42);
            app.Label_60.Text = '投影';
            app.DropDown_Projection_MGlobal = uidropdown(app.GridLayout42);
            app.DropDown_Projection_MGlobal.Items = {'最大值', '最小值', '中位值', '平均值'};
            app.DropDown_Projection_MGlobal.ItemsData = {'max', 'min', 'median', 'mean'};
            app.DropDown_Projection_MGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_Projection_MGlobalValueChanged, true);
            app.DropDown_Projection_MGlobal.Value = 'max';
            app.GridLayout43 = uigridlayout(app.GridLayout35);
            app.Label_26 = uilabel(app.GridLayout43);
            app.Label_26.Text = '插值';
            app.DropDown_Interp_MGlobal = uidropdown(app.GridLayout43);
            app.DropDown_Interp_MGlobal.Items = {'线性(快速)', '立方(高质量)'};
            app.DropDown_Interp_MGlobal.ItemsData = {'linear', 'cubic'};
            app.DropDown_Interp_MGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_Interp_MGlobalValueChanged, true);
            app.DropDown_Interp_MGlobal.Tooltip = {''};
            app.DropDown_Interp_MGlobal.Value = 'linear';
            app.Label_24 = uilabel(app.GridLayout43);
            app.Label_24.Text = '多项式次数';
            app.Spinner_PolyOrder_MGlobal = uispinner(app.GridLayout43);
            app.Spinner_PolyOrder_MGlobal.Limits = [2 4];
            app.Spinner_PolyOrder_MGlobal.ValueDisplayFormat = '%.0f';
            app.Spinner_PolyOrder_MGlobal.ValueChangedFcn = createCallbackFcn(app, @Spinner_PolyOrder_MGlobalValueChanged, true);
            app.Spinner_PolyOrder_MGlobal.Tooltip = {''};
            app.Spinner_PolyOrder_MGlobal.Value = 2;
            app.GridLayout44 = uigridlayout(app.GridLayout35);
            app.Label_61 = uilabel(app.GridLayout44);
            app.Label_61.Text = '重采样比率';
            app.EditField_ReSampleRatio_MGlobal = uieditfield(app.GridLayout44, 'text');
            app.EditField_ReSampleRatio_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_ReSampleRatio_MGlobalValueChanged, true);
            app.EditField_ReSampleRatio_MGlobal.Placeholder = '1,1,1';
            app.CheckBox_ReSampleIsometric_MGlobal = uicheckbox(app.GridLayout44);
            app.CheckBox_ReSampleIsometric_MGlobal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ReSampleIsometric_MGlobalValueChanged, true);
            app.CheckBox_ReSampleIsometric_MGlobal.Tooltip = {'等距重采样将体积单元重构为立方体'};
            app.CheckBox_ReSampleIsometric_MGlobal.Text = '等距';
            app.GridLayout45 = uigridlayout(app.GridLayout35);
            app.GridLayout68 = uigridlayout(app.GridLayout45);
            app.EditField_TM11_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM11_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM11_MGlobalValueChanged, true);
            app.EditField_TM11_MGlobal.Placeholder = 'sxx';
            app.EditField_TM11_MGlobal.Value = '1';
            app.EditField_TM12_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM12_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM12_MGlobalValueChanged, true);
            app.EditField_TM12_MGlobal.Placeholder = 'sxy';
            app.EditField_TM12_MGlobal.Value = '0';
            app.EditField_TM13_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM13_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM13_MGlobalValueChanged, true);
            app.EditField_TM13_MGlobal.Placeholder = 'sxz';
            app.EditField_TM13_MGlobal.Value = '0';
            app.EditField_TM14_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM14_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM14_MGlobalValueChanged, true);
            app.EditField_TM14_MGlobal.Placeholder = 'tx';
            app.EditField_TM14_MGlobal.Value = '0';
            app.EditField_TM21_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM21_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM21_MGlobalValueChanged, true);
            app.EditField_TM21_MGlobal.Placeholder = 'syx';
            app.EditField_TM21_MGlobal.Value = '0';
            app.EditField_TM22_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM22_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM22_MGlobalValueChanged, true);
            app.EditField_TM22_MGlobal.Placeholder = 'syy';
            app.EditField_TM22_MGlobal.Value = '1';
            app.EditField_TM23_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM23_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM23_MGlobalValueChanged, true);
            app.EditField_TM23_MGlobal.Placeholder = 'syz';
            app.EditField_TM23_MGlobal.Value = '0';
            app.EditField_TM24_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM24_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM24_MGlobalValueChanged, true);
            app.EditField_TM24_MGlobal.Placeholder = 'ty';
            app.EditField_TM24_MGlobal.Value = '0';
            app.EditField_TM31_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM31_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM31_MGlobalValueChanged, true);
            app.EditField_TM31_MGlobal.Placeholder = 'szx';
            app.EditField_TM31_MGlobal.Value = '0';
            app.EditField_TM32_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM32_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM32_MGlobalValueChanged, true);
            app.EditField_TM32_MGlobal.Placeholder = 'szy';
            app.EditField_TM32_MGlobal.Value = '0';
            app.EditField_TM33_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM33_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM33_MGlobalValueChanged, true);
            app.EditField_TM33_MGlobal.Placeholder = 'szz';
            app.EditField_TM33_MGlobal.Value = '1';
            app.EditField_TM34_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM34_MGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_TM34_MGlobalValueChanged, true);
            app.EditField_TM34_MGlobal.Placeholder = 'tz';
            app.EditField_TM34_MGlobal.Value = '0';
            app.EditField_TM41_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM41_MGlobal.Editable = 'off';
            app.EditField_TM41_MGlobal.Enable = 'off';
            app.EditField_TM41_MGlobal.Placeholder = '0';
            app.EditField_TM42_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM42_MGlobal.Editable = 'off';
            app.EditField_TM42_MGlobal.Enable = 'off';
            app.EditField_TM42_MGlobal.Placeholder = '0';
            app.EditField_TM43_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM43_MGlobal.Editable = 'off';
            app.EditField_TM43_MGlobal.Enable = 'off';
            app.EditField_TM43_MGlobal.Placeholder = '0';
            app.EditField_TM44_MGlobal = uieditfield(app.GridLayout68, 'text');
            app.EditField_TM44_MGlobal.Editable = 'off';
            app.EditField_TM44_MGlobal.Enable = 'off';
            app.EditField_TM44_MGlobal.Placeholder = '1';
            app.Label_TransformMatrix = uilabel(app.GridLayout45);
            app.Label_TransformMatrix.Text = '变换矩阵';
            app.GridLayout69 = uigridlayout(app.GridLayout45);
            app.CheckBox_ApplyTransformation = uicheckbox(app.GridLayout69);
            app.CheckBox_ApplyTransformation.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ApplyTransformationValueChanged, true);
            app.CheckBox_ApplyTransformation.Tooltip = {'指定变换并跳过选定控制点'};
            app.CheckBox_ApplyTransformation.Text = '应用';
            app.Tab_LTGlobal = uitab(app.TabGroup_AlgorithmSelection);
            app.Tab_LTGlobal.Tooltip = {'基于关键帧的自动全局几何变换'};
            app.Tab_LTGlobal.Title = '全局(长时程)';
            app.Tab_LTGlobal.Tag = 'LTGlobal';
            app.GridLayout46 = uigridlayout(app.Tab_LTGlobal);
            app.GridLayout47 = uigridlayout(app.GridLayout46);
            app.CheckBox_Lock_LTGlobal = uicheckbox(app.GridLayout47);
            app.CheckBox_Lock_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Lock_LTGlobalValueChanged, true);
            app.CheckBox_Lock_LTGlobal.Tooltip = {'启用长时程配准'};
            app.CheckBox_Lock_LTGlobal.Text = '锁定';
            app.Label_Helps_LTGlobal = uilabel(app.GridLayout47);
            app.Label_Helps_LTGlobal.Text = '';
            app.TabGroup_LTGlobal = uitabgroup(app.GridLayout46);
            app.Tab_NS_LTGlobal = uitab(app.TabGroup_LTGlobal);
            app.Tab_NS_LTGlobal.Title = '常用';
            app.GridLayout49 = uigridlayout(app.Tab_NS_LTGlobal);
            app.Panel_NS_Reg_LTGlobal = uipanel(app.GridLayout49);
            app.Panel_NS_Reg_LTGlobal.Title = '配准';
            app.GridLayout50 = uigridlayout(app.Panel_NS_Reg_LTGlobal);
            app.GridLayout51 = uigridlayout(app.GridLayout50);
            app.CheckBox_AutoTemplate_LTGlobal = uicheckbox(app.GridLayout51);
            app.CheckBox_AutoTemplate_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_AutoTemplate_LTGlobalValueChanged, true);
            app.CheckBox_AutoTemplate_LTGlobal.Text = '自动';
            app.CheckBox_AutoTemplate_LTGlobal.Value = true;
            app.Label_31 = uilabel(app.GridLayout51);
            app.Label_31.Text = '模板';
            app.EditField_Template_LTGlobal = uieditfield(app.GridLayout51, 'text');
            app.EditField_Template_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_Template_LTGlobalValueChanged, true);
            app.EditField_Template_LTGlobal.Value = '1';
            app.GridLayout52 = uigridlayout(app.GridLayout50);
            app.Label_62 = uilabel(app.GridLayout52);
            app.Label_62.Enable = 'off';
            app.Label_62.Text = '几何变换';
            app.DropDown_TformType_LTGlobal = uidropdown(app.GridLayout52);
            app.DropDown_TformType_LTGlobal.Items = {'仿射'};
            app.DropDown_TformType_LTGlobal.ItemsData = {'affine'};
            app.DropDown_TformType_LTGlobal.Enable = 'off';
            app.DropDown_TformType_LTGlobal.Tooltip = {'几何变换类型'};
            app.DropDown_TformType_LTGlobal.Value = 'affine';
            app.Label_30 = uilabel(app.GridLayout52);
            app.Label_30.Text = '采样';
            app.DropDown_TemplateSampling_LTGlobal = uidropdown(app.GridLayout52);
            app.DropDown_TemplateSampling_LTGlobal.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_TemplateSampling_LTGlobal.ItemsData = {'mean', 'median', 'min', 'max'};
            app.DropDown_TemplateSampling_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_TemplateSampling_LTGlobalValueChanged, true);
            app.DropDown_TemplateSampling_LTGlobal.Tooltip = {'帧运算'};
            app.DropDown_TemplateSampling_LTGlobal.Value = 'mean';
            app.GridLayout53 = uigridlayout(app.GridLayout50);
            app.Label_33 = uilabel(app.GridLayout53);
            app.Label_33.Text = '信号';
            app.DropDown_Functional_LTGlobal = uidropdown(app.GridLayout53);
            app.DropDown_Functional_LTGlobal.Items = {'红', '绿'};
            app.DropDown_Functional_LTGlobal.ItemsData = {'r', 'g'};
            app.DropDown_Functional_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_Functional_LTGlobalValueChanged, true);
            app.DropDown_Functional_LTGlobal.Tooltip = {''};
            app.DropDown_Functional_LTGlobal.Value = 'g';
            app.Label_32 = uilabel(app.GridLayout53);
            app.Label_32.Text = '定位';
            app.DropDown_Structured_LTGlobal = uidropdown(app.GridLayout53);
            app.DropDown_Structured_LTGlobal.Items = {'红', '绿'};
            app.DropDown_Structured_LTGlobal.ItemsData = {'r', 'g'};
            app.DropDown_Structured_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_Structured_LTGlobalValueChanged, true);
            app.DropDown_Structured_LTGlobal.Tooltip = {''};
            app.DropDown_Structured_LTGlobal.Value = 'r';
            app.GridLayout63 = uigridlayout(app.GridLayout50);
            app.GridLayout64 = uigridlayout(app.GridLayout63);
            app.Label_42 = uilabel(app.GridLayout64);
            app.Label_42.Text = '最小迭代步长';
            app.EditField_MinStep_LTGlobal = uieditfield(app.GridLayout64, 'numeric');
            app.EditField_MinStep_LTGlobal.Limits = [0 Inf];
            app.EditField_MinStep_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_MinStep_LTGlobalValueChanged, true);
            app.EditField_MinStep_LTGlobal.Tooltip = {'收敛判别步长'};
            app.EditField_MinStep_LTGlobal.Value = 1e-06;
            app.Label_43 = uilabel(app.GridLayout64);
            app.Label_43.Text = '最大迭代步长';
            app.EditField_MaxStep_LTGlobal = uieditfield(app.GridLayout64, 'numeric');
            app.EditField_MaxStep_LTGlobal.Limits = [0 Inf];
            app.EditField_MaxStep_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxStep_LTGlobalValueChanged, true);
            app.EditField_MaxStep_LTGlobal.Tooltip = {'起始步长'};
            app.EditField_MaxStep_LTGlobal.Value = 0.01;
            app.Label_45 = uilabel(app.GridLayout64);
            app.Label_45.Text = '迭代因子';
            app.EditField_IterCoeff_LTGlobal = uieditfield(app.GridLayout64, 'numeric');
            app.EditField_IterCoeff_LTGlobal.Limits = [0 1];
            app.EditField_IterCoeff_LTGlobal.ValueDisplayFormat = '%.3f';
            app.EditField_IterCoeff_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_IterCoeff_LTGlobalValueChanged, true);
            app.EditField_IterCoeff_LTGlobal.Tooltip = {''};
            app.EditField_IterCoeff_LTGlobal.Value = 0.5;
            app.Label_40 = uilabel(app.GridLayout64);
            app.Label_40.Text = '迭代数上限';
            app.EditField_MaxIterN_LTGlobal = uieditfield(app.GridLayout64, 'numeric');
            app.EditField_MaxIterN_LTGlobal.Limits = [1 Inf];
            app.EditField_MaxIterN_LTGlobal.ValueDisplayFormat = '%.0f';
            app.EditField_MaxIterN_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_LTGlobalValueChanged, true);
            app.EditField_MaxIterN_LTGlobal.Tooltip = {'最大迭代次数'};
            app.EditField_MaxIterN_LTGlobal.Value = 100;
            app.GridLayout65 = uigridlayout(app.GridLayout63);
            app.Image_Memory = uiimage(app.GridLayout65);
            app.Image_Memory.ImageClickedFcn = createCallbackFcn(app, @Image_MemoryClicked, true);
            app.Tab_AS_LTGlobal = uitab(app.TabGroup_LTGlobal);
            app.Tab_AS_LTGlobal.Title = '高级';
            app.GridLayout48 = uigridlayout(app.Tab_AS_LTGlobal);
            app.GridLayout62 = uigridlayout(app.GridLayout48);
            app.Panel_AS_PP_LTGlobal = uipanel(app.GridLayout48);
            app.Panel_AS_PP_LTGlobal.Title = '预处理';
            app.GridLayout56 = uigridlayout(app.Panel_AS_PP_LTGlobal);
            app.GridLayout57 = uigridlayout(app.GridLayout56);
            app.Label_34 = uilabel(app.GridLayout57);
            app.Label_34.Text = '最大Z漂移';
            app.EditField_OptimalZ_MaxShift_LTGlobal = uieditfield(app.GridLayout57, 'numeric');
            app.EditField_OptimalZ_MaxShift_LTGlobal.Limits = [0 Inf];
            app.EditField_OptimalZ_MaxShift_LTGlobal.ValueDisplayFormat = '%.1f';
            app.EditField_OptimalZ_MaxShift_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_OptimalZ_MaxShift_LTGlobalValueChanged, true);
            app.EditField_OptimalZ_MaxShift_LTGlobal.Value = 2;
            app.Label_71 = uilabel(app.GridLayout57);
            app.Label_71.Text = '膨胀滤波器';
            app.EditField_DilateFilter_LTGlobal = uieditfield(app.GridLayout57, 'text');
            app.EditField_DilateFilter_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_DilateFilter_LTGlobalValueChanged, true);
            app.EditField_DilateFilter_LTGlobal.Placeholder = '3,115,65535,1000';
            app.Panel_AS_Reg_LTGlobal = uipanel(app.GridLayout48);
            app.Panel_AS_Reg_LTGlobal.Title = '配准';
            app.GridLayout58 = uigridlayout(app.Panel_AS_Reg_LTGlobal);
            app.GridLayout59 = uigridlayout(app.GridLayout58);
            app.Label_29 = uilabel(app.GridLayout59);
            app.Label_29.Text = '关键帧';
            app.EditField_Keyframes_LTGlobal = uieditfield(app.GridLayout59, 'text');
            app.EditField_Keyframes_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @EditField_Keyframes_LTGlobalValueChanged, true);
            app.EditField_Keyframes_LTGlobal.Placeholder = '50,150,250,350,450...';
            app.CheckBox_AutoKeyframe_LTGlobal = uicheckbox(app.GridLayout59);
            app.CheckBox_AutoKeyframe_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_AutoKeyframe_LTGlobalValueChanged, true);
            app.CheckBox_AutoKeyframe_LTGlobal.Text = '自动';
            app.CheckBox_AutoKeyframe_LTGlobal.Value = true;
            app.GridLayout60 = uigridlayout(app.GridLayout58);
            app.Label_37 = uilabel(app.GridLayout60);
            app.Label_37.Text = '降采样';
            app.DropDown_DownSampling_LTGlobal = uidropdown(app.GridLayout60);
            app.DropDown_DownSampling_LTGlobal.Items = {'自动', '2X2', '3X3', '关闭'};
            app.DropDown_DownSampling_LTGlobal.ItemsData = {'auto', '2X2', '3X3', 'close'};
            app.DropDown_DownSampling_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_DownSampling_LTGlobalValueChanged, true);
            app.DropDown_DownSampling_LTGlobal.Value = 'auto';
            app.Label_70 = uilabel(app.GridLayout60);
            app.Label_70.Text = '插值';
            app.DropDown_Interp_LTGlobal = uidropdown(app.GridLayout60);
            app.DropDown_Interp_LTGlobal.Items = {'线性(快速)', '立方(高质量)'};
            app.DropDown_Interp_LTGlobal.ItemsData = {'linear', 'cubic'};
            app.DropDown_Interp_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @DropDown_Interp_LTGlobalValueChanged, true);
            app.DropDown_Interp_LTGlobal.Tooltip = {''};
            app.DropDown_Interp_LTGlobal.Value = 'linear';
            app.GridLayout61 = uigridlayout(app.GridLayout58);
            app.Label_68 = uilabel(app.GridLayout61);
            app.Label_68.Text = '最少分划';
            app.Spinner_TGridMax_LTGlobal = uispinner(app.GridLayout61);
            app.Spinner_TGridMax_LTGlobal.Limits = [2 100];
            app.Spinner_TGridMax_LTGlobal.ValueDisplayFormat = '%.0f';
            app.Spinner_TGridMax_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @Spinner_TGridMax_LTGlobalValueChanged, true);
            app.Spinner_TGridMax_LTGlobal.Value = 40;
            app.Spinner_TGridMin_LTGlobal = uispinner(app.GridLayout61);
            app.Spinner_TGridMin_LTGlobal.Limits = [2 100];
            app.Spinner_TGridMin_LTGlobal.ValueDisplayFormat = '%.0f';
            app.Spinner_TGridMin_LTGlobal.ValueChangedFcn = createCallbackFcn(app, @Spinner_TGridMin_LTGlobalValueChanged, true);
            app.Spinner_TGridMin_LTGlobal.Value = 10;
            app.Label_69 = uilabel(app.GridLayout61);
            app.Label_69.Text = '最多分划';
            % Show the figure after all components are created
            app.TCRSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TCRegSetter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TCRSUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TCRSUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TCRSUIFigure)
        end
    end
end