classdef TaskViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TaskViewerUIFigure           matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        Tab_RTOverview               matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        UITable_RTOverview           matlab.ui.control.Table
        Tab_Performance              matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        Panel_GPU                    matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        UIAxes_GPU                   matlab.ui.control.UIAxes
        Panel_Memory                 matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        UIAxes_Memory                matlab.ui.control.UIAxes
        Panel_CPU                    matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        UIAxes_CPU                   matlab.ui.control.UIAxes
        Tab_History                  matlab.ui.container.Tab
        GridLayout8                  matlab.ui.container.GridLayout
        UITable_History              matlab.ui.control.Table
        GridLayout2                  matlab.ui.container.GridLayout
        Spinner_GPUNumber_Protected  matlab.ui.control.Spinner
        GPULabel                     matlab.ui.control.Label
        Spinner_CPUNumber_Protected  matlab.ui.control.Spinner
        WorkerCPULabel               matlab.ui.control.Label
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu_RTUpdateSpeed           matlab.ui.container.Menu
        Menu_RTUpdate_Low            matlab.ui.container.Menu
        Menu_RTUpdate_Medium         matlab.ui.container.Menu
        Menu_RTUpdate_High           matlab.ui.container.Menu
        Menu_TaskPriority            matlab.ui.container.Menu
        Menu_TaskPriority_Low        matlab.ui.container.Menu
        Menu_TaskPriority_Medium     matlab.ui.container.Menu
        Menu_TaskPriority_High       matlab.ui.container.Menu
        Menu_KillTask                matlab.ui.container.Menu
    end

    properties (GetAccess = ?BugReporter, Constant)
        VER = "1.0.0"
    end
    
    properties (Access = private)
        caller;         % the parent app
        checker         % 1-by-1 timer, get register status and tasks information
        nwp             % 1-by-2 nonnegtive integer, number of workers protected
        langmap;        % mapping for language
    end

    methods(Access=?Register)
        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            app.Tab_RTOverview.Title = app.langmap.REAL_TIME_OVERVIEW;
            app.Tab_Performance.Title = app.langmap.PERFORMANCE;
            app.Tab_History.Title = app.langmap.HISTORY_RECORD;
            app.WorkerCPULabel.Text = app.langmap.NPC_CPU;
            app.GPULabel.Text = app.langmap.NPC_GPU;
            app.UITable_RTOverview.ColumnName = [app.langmap.USER, ...
                app.langmap.STATUS, app.langmap.PROGRESS, app.langmap.TIME_COST, ...
                "#CPU", "#GPU"];
            app.Panel_Memory.Title = app.langmap.MEMORY;
            app.UITable_History.ColumnName = [app.langmap.USER, ...
                app.langmap.CPU_TIME, app.langmap.MEMORY_TIME, app.langmap.GPU_TIME];
        end

        function Close(app)
            TaskViewerUIFigureCloseRequest(app, []);
        end
    end

    methods (Access = private)

        function InitTimer(app)
            app.checker = timer("Period",3,...
                "ExecutionMode","fixedRate","TasksToExecute",inf,...
                "Name","Timer_TaskChecker","TimerFcn",@app.UpdatePanels);
        end

        function InitPanels(app)
            CPU_MAX_N = GetCPUWorkersMaxN([],[]);
            GPU_MAX_N = GetGPUWorkersMaxN();
            app.Spinner_CPUNumber_Protected.Limits = [0, CPU_MAX_N];
            app.Spinner_GPUNumber_Protected.Limits = [0, GPU_MAX_N];

            r = app.caller.GetEngineInfo();
            app.nwp = r.NWP;
            app.Spinner_CPUNumber_Protected.Value = app.nwp(1);
            app.Spinner_GPUNumber_Protected.Value = app.nwp(2);
            app.UITable_RTOverview.Data = r.T;

            drawnow
        end

        function UpdatePanels(app, src, event)
            src; %#ok<VUNUS>
            event; %#ok<VUNUS>

            spn_locked = app.caller.GetEngineStatus();
            if spn_locked == RegisterController.KERNEL_ON
                app.Spinner_CPUNumber_Protected.Enable = "off";
                app.Spinner_GPUNumber_Protected.Enable = "off";
            else
                app.Spinner_CPUNumber_Protected.Enable = "on";
                app.Spinner_GPUNumber_Protected.Enable = "on";
            end

            r = app.caller.GetEngineInfo();
            app.UITable_RTOverview.Data = r.T;
            pawait = find(r.T.status == "Await");
            pawait = [pawait, repmat(2, numel(pawait), 1)];
            pready = find(r.T.status == "Ready");
            pready = [pready, repmat(2, numel(pready), 1)];
            prun = find(r.T.status == "Run");
            prun = [prun, repmat(2, numel(prun), 1)];

            if isMATLABReleaseOlderThan("R2022a")
                sawait = uistyle("BackgroundColor",[1, 0.4, 0.4]);
                sready = uistyle("BackgroundColor",[1, 1, 0]);
                srun = uistyle("BackgroundColor",[1, 0.4, 0]);
            else
                sawait = uistyle("Icon","error","IconAlignment","rightmargin");
                sready = uistyle("Icon","warning","IconAlignment","rightmargin");
                srun = uistyle("Icon","success","IconAlignment","rightmargin");
            end

            % refresh the table style
            removeStyle(app.UITable_RTOverview);
            if ~isempty(pawait)
                addStyle(app.UITable_RTOverview, sawait, "cell", pawait);
            end
            if ~isempty(pready)
                addStyle(app.UITable_RTOverview, sready, "cell", pready);
            end
            if ~isempty(prun)
                addStyle(app.UITable_RTOverview, srun, "cell", prun);
            end
            
            % update RTOverview


            % update Performance


            % update History
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % set the caller
            app.caller = mainapp;
            
            InitTimer(app);

            InitPanels(app);

            RefreshPanelLanguage(app);

            start(app.checker);
        end

        % Value changed function: Spinner_CPUNumber_Protected
        function Spinner_CPUNumber_ProtectedValueChanged(app, event)
            value = app.Spinner_CPUNumber_Protected.Value;

            app.nwp(1) = value;
            
            app.caller.SetNWorkersProtected(app.nwp);
        end

        % Value changed function: Spinner_GPUNumber_Protected
        function Spinner_GPUNumber_ProtectedValueChanged(app, event)
            value = app.Spinner_GPUNumber_Protected.Value;
            
            app.nwp(2) = value;

            app.caller.SetNWorkersProtected(app.nwp);
        end

        % Close request function: TaskViewerUIFigure
        function TaskViewerUIFigureCloseRequest(app, event)
            stop(app.checker);
            delete(app.checker);
            
            delete(app)
        end

        % Menu selected function: Menu_RTUpdate_Low
        function Menu_RTUpdate_LowSelected(app, event)
            app.Menu_RTUpdate_Low.Checked = "off";
            app.Menu_RTUpdate_Medium.Checked = "off";
            app.Menu_RTUpdate_High.Checked = "off";

            stop(app.checker);
            app.checker.Period = 5;
            start(app.checker);

            app.Menu_RTUpdate_Low.Checked = "on";
        end

        % Menu selected function: Menu_RTUpdate_Medium
        function Menu_RTUpdate_MediumSelected(app, event)
            app.Menu_RTUpdate_Low.Checked = "off";
            app.Menu_RTUpdate_Medium.Checked = "off";
            app.Menu_RTUpdate_High.Checked = "off";

            stop(app.checker);
            app.checker.Period = 3;
            start(app.checker);

            app.Menu_RTUpdate_Medium.Checked = "on";
        end

        % Menu selected function: Menu_RTUpdate_High
        function Menu_RTUpdate_HighSelected(app, event)
            app.Menu_RTUpdate_Low.Checked = "off";
            app.Menu_RTUpdate_Medium.Checked = "off";
            app.Menu_RTUpdate_High.Checked = "off";

            stop(app.checker);
            app.checker.Period = 1;
            start(app.checker);

            app.Menu_RTUpdate_High.Checked = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TaskViewerUIFigure = uifigure('Visible', 'off');
            app.TaskViewerUIFigure.Name = 'Task Viewer';
            app.TaskViewerUIFigure.Icon = 'task_viewer.png';
            app.TaskViewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @TaskViewerUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.TaskViewerUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.WorkerCPULabel = uilabel(app.GridLayout2);
            app.WorkerCPULabel.Text = '保留核心数(CPU)';
            app.Spinner_CPUNumber_Protected = uispinner(app.GridLayout2);
            app.Spinner_CPUNumber_Protected.Limits = [0 3];
            app.Spinner_CPUNumber_Protected.ValueDisplayFormat = '%.0f';
            app.Spinner_CPUNumber_Protected.ValueChangedFcn = createCallbackFcn(app, @Spinner_CPUNumber_ProtectedValueChanged, true);
            app.Spinner_CPUNumber_Protected.Tooltip = {'推荐保留1~2核心维持正常交互'};
            app.Spinner_CPUNumber_Protected.Value = 1;
            app.GPULabel = uilabel(app.GridLayout2);
            app.GPULabel.Text = '保留核心数(GPU)';
            app.Spinner_GPUNumber_Protected = uispinner(app.GridLayout2);
            app.Spinner_GPUNumber_Protected.Limits = [0 3];
            app.Spinner_GPUNumber_Protected.ValueDisplayFormat = '%.0f';
            app.Spinner_GPUNumber_Protected.ValueChangedFcn = createCallbackFcn(app, @Spinner_GPUNumber_ProtectedValueChanged, true);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab_RTOverview = uitab(app.TabGroup);
            app.Tab_RTOverview.Title = '实时总览';
            app.GridLayout3 = uigridlayout(app.Tab_RTOverview);
            app.UITable_RTOverview = uitable(app.GridLayout3);
            app.UITable_RTOverview.ColumnName = {'用户'; '状态'; '进度'; '用时'; '#CPU'; '#GPU'};
            app.UITable_RTOverview.RowName = {};
            app.UITable_RTOverview.Multiselect = 'off';
            app.Tab_Performance = uitab(app.TabGroup);
            app.Tab_Performance.Title = '性能';
            app.GridLayout4 = uigridlayout(app.Tab_Performance);
            app.Panel_CPU = uipanel(app.GridLayout4);
            app.Panel_CPU.Title = 'CPU';
            app.GridLayout5 = uigridlayout(app.Panel_CPU);
            app.UIAxes_CPU = uiaxes(app.GridLayout5);
            app.UIAxes_CPU.XTick = [0 1];
            app.UIAxes_CPU.YTick = [0 1];
            app.Panel_Memory = uipanel(app.GridLayout4);
            app.Panel_Memory.Title = '内存';
            app.GridLayout6 = uigridlayout(app.Panel_Memory);
            app.UIAxes_Memory = uiaxes(app.GridLayout6);
            app.UIAxes_Memory.XTick = [0 1];
            app.UIAxes_Memory.YTick = [0 1];
            app.Panel_GPU = uipanel(app.GridLayout4);
            app.Panel_GPU.Title = 'GPU';
            app.GridLayout7 = uigridlayout(app.Panel_GPU);
            app.UIAxes_GPU = uiaxes(app.GridLayout7);
            app.UIAxes_GPU.XTick = [0 1];
            app.UIAxes_GPU.YTick = [0 1];
            app.Tab_History = uitab(app.TabGroup);
            app.Tab_History.Title = '历史记录';
            app.GridLayout8 = uigridlayout(app.Tab_History);
            app.UITable_History = uitable(app.GridLayout8);
            app.UITable_History.ColumnName = {'用户'; 'CPU时间'; '内存时间'; 'GPU时间'};
            app.UITable_History.RowName = {};
            app.ContextMenu = uicontextmenu(app.TaskViewerUIFigure);
            app.Menu_RTUpdateSpeed = uimenu(app.ContextMenu);
            app.Menu_RTUpdateSpeed.Text = '实时更新速度';
            app.Menu_RTUpdate_Low = uimenu(app.Menu_RTUpdateSpeed);
            app.Menu_RTUpdate_Low.MenuSelectedFcn = createCallbackFcn(app, @Menu_RTUpdate_LowSelected, true);
            app.Menu_RTUpdate_Low.Text = '低';
            app.Menu_RTUpdate_Medium = uimenu(app.Menu_RTUpdateSpeed);
            app.Menu_RTUpdate_Medium.MenuSelectedFcn = createCallbackFcn(app, @Menu_RTUpdate_MediumSelected, true);
            app.Menu_RTUpdate_Medium.Checked = 'on';
            app.Menu_RTUpdate_Medium.Text = '中';
            app.Menu_RTUpdate_High = uimenu(app.Menu_RTUpdateSpeed);
            app.Menu_RTUpdate_High.MenuSelectedFcn = createCallbackFcn(app, @Menu_RTUpdate_HighSelected, true);
            app.Menu_RTUpdate_High.Text = '高';
            app.Menu_TaskPriority = uimenu(app.ContextMenu);
            app.Menu_TaskPriority.Text = '任务优先级';
            app.Menu_TaskPriority_Low = uimenu(app.Menu_TaskPriority);
            app.Menu_TaskPriority_Low.Enable = 'off';
            app.Menu_TaskPriority_Low.Text = '低';
            app.Menu_TaskPriority_Medium = uimenu(app.Menu_TaskPriority);
            app.Menu_TaskPriority_Medium.Checked = 'on';
            app.Menu_TaskPriority_Medium.Text = '中';
            app.Menu_TaskPriority_High = uimenu(app.Menu_TaskPriority);
            app.Menu_TaskPriority_High.Enable = 'off';
            app.Menu_TaskPriority_High.Text = '高';
            app.Menu_KillTask = uimenu(app.ContextMenu);
            app.Menu_KillTask.Enable = 'off';
            app.Menu_KillTask.Text = '结束任务';
            % Assign app.ContextMenu
            app.UITable_RTOverview.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.TaskViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TaskViewer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TaskViewerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TaskViewerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TaskViewerUIFigure)
        end
    end
end