classdef VarSelecter_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VarSelectorUIFigure  matlab.ui.Figure
        Button_Close         matlab.ui.control.Button
        Panel_MetaData       matlab.ui.container.Panel
        DropDown_T           matlab.ui.control.DropDown
        Label_5              matlab.ui.control.Label
        DropDown_Opts        matlab.ui.control.DropDown
        Label_4              matlab.ui.control.Label
        Panel_MovieData      matlab.ui.container.Panel
        UITable              matlab.ui.control.Table
        DropDown_Mix         matlab.ui.control.DropDown
        Label_3              matlab.ui.control.Label
        DropDown_Red         matlab.ui.control.DropDown
        Label_2              matlab.ui.control.Label
        DropDown_Green       matlab.ui.control.DropDown
        Label                matlab.ui.control.Label
        ButtonGroup          matlab.ui.container.ButtonGroup
        Button_Dual          matlab.ui.control.RadioButton
        Button_Single        matlab.ui.control.RadioButton
    end

    properties (Access = public, Constant)
        VER = "1.0.0"
    end
    
    properties (Access = private)
        var_names; % the variable names in base workspace, cell
    end

    properties (Access = public)
        is_closing; % the flag mark if window is closing, for outer listener
        mov_size;   % struct array, elements (1,2,3) for mov, mov_r, mov_g,
                    % with field {X,Y,C,Z,T}
        is_loaded;  % the logical flag for [mov, mov_r, mov_g], true for loaded
    end
    
    methods (Access = private)
        
        function InitData(app)
            app.var_names = evalin("base","who");
            app.is_closing = false;
            % 3 for mov, mov_r, mov_g
            app.mov_size = repmat(struct("X",[],"Y",[],"C",[],"Z",[],"T",[]),...
                3,1);

            app.is_loaded = false(1, 3);
        end

        function InitPanel(app)
            app.ButtonGroup.SelectedObject = app.Button_Single;

            app.DropDown_Red.Items = [{'none'}; app.var_names];
            app.DropDown_Red.Value = 'none';
            app.DropDown_Green.Items = [{'none'}; app.var_names];
            app.DropDown_Green.Value = 'none';
            app.DropDown_Red.Enable = "on";
            app.DropDown_Green.Enable = "on";

            app.DropDown_Mix.Items = [{'none'}; app.var_names];
            app.DropDown_Mix.Value = 'none';
            app.DropDown_Mix.Enable = "off";

            app.DropDown_Opts.Items = [{'none'}; app.var_names];
            app.DropDown_Opts.Value = 'none';
            app.DropDown_Opts.Enable = "on";

            app.DropDown_T.Items = [{'none'}; app.var_names];
            app.DropDown_T.Value = 'none';
            app.DropDown_T.Enable = "on";
        end

        function UpdateTable(app)
            % use movie size generate the table
            T = table('Size',[0 5],...
                'VariableTypes',{'double','double','double','double','double'},...
                'VariableNames',{'X','Y','C','Z','T'});
            switch app.ButtonGroup.SelectedObject
                case app.Button_Single
                    if app.is_loaded(2)
                        T = [T;struct2table(app.mov_size(2))];
                        app.UITable.Data = T;
                        uis_r = uistyle("BackgroundColor","r",...
                            "FontColor","w");
                        addStyle(app.UITable,uis_r,"row",1);
                    else
                        app.UITable.Data = T;
                    end
                    if app.is_loaded(3)
                        T = [T;struct2table(app.mov_size(3))];
                        app.UITable.Data = T;
                        uis_g = uistyle("BackgroundColor","g",...
                            "FontColor","k");
                        addStyle(app.UITable,uis_g,"row",size(T,1));
                    else
                        app.UITable.Data = T;
                    end
                case app.Button_Dual
                    if app.is_loaded(1)
                        T = [T;struct2table(app.mov_size(1))];
                        app.UITable.Data = T;
                        uis = uistyle("BackgroundColor","y",...
                            "FontColor","k");
                        addStyle(app.UITable,uis,"row",1);
                    else
                        app.UITable.Data = T;
                    end
            end
        end

        function SnapUserDatatoFigure(app, is_cancled)

            if ~is_cancled
                app.VarSelectorUIFigure.UserData.opts = app.DropDown_Opts.Value;

                app.VarSelectorUIFigure.UserData.rt = app.DropDown_T.Value;

                app.VarSelectorUIFigure.UserData.varnames = [string(app.VarSelectorUIFigure.UserData.opts);...
                    string(app.VarSelectorUIFigure.UserData.rt)];

                switch app.ButtonGroup.SelectedObject
                    case app.Button_Single
                        app.VarSelectorUIFigure.UserData.mov_r = app.DropDown_Red.Value;
                        app.VarSelectorUIFigure.UserData.mov_g = app.DropDown_Green.Value;
                        app.VarSelectorUIFigure.UserData.varnames = [app.VarSelectorUIFigure.UserData.varnames;...
                            string(app.VarSelectorUIFigure.UserData.mov_r);...
                            string(app.VarSelectorUIFigure.UserData.mov_g)];
                    case app.Button_Dual
                        app.VarSelectorUIFigure.UserData.mov = app.DropDown_Mix.Value;
                        app.VarSelectorUIFigure.UserData.varnames = [app.VarSelectorUIFigure.UserData.varnames;...
                            string(app.VarSelectorUIFigure.UserData.mov)];
                end
            else
                app.VarSelectorUIFigure.UserData.opts = [];
                app.VarSelectorUIFigure.UserData.rt = [];
                app.VarSelectorUIFigure.UserData.mov = [];
                app.VarSelectorUIFigure.UserData.varnames = ["none","none","none"];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            InitData(app);

            InitPanel(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;

            switch selectedButton
                case app.Button_Dual
                    app.DropDown_Mix.Enable = "on";
                    app.DropDown_Red.Enable = "off";
                    app.DropDown_Green.Enable = "off";

                    if ~isempty(app.VarSelectorUIFigure.UserData) && isstruct(app.VarSelectorUIFigure.UserData)
                        % keep only feld 'mov' exists
                        app.VarSelectorUIFigure.UserData = rmfield(app.VarSelectorUIFigure.UserData,'mov_r');
                        app.VarSelectorUIFigure.UserData = rmfield(app.VarSelectorUIFigure.UserData,'mov_g');
                    end
                case app.Button_Single
                    app.DropDown_Red.Enable = "on";
                    app.DropDown_Green.Enable = "on";
                    app.DropDown_Mix.Enable = "off";

                    if ~isempty(app.VarSelectorUIFigure.UserData) && isstruct(app.VarSelectorUIFigure.UserData)
                        % keep only feld 'mov_r', 'mov_g' exists
                        app.VarSelectorUIFigure.UserData = rmfield(app.VarSelectorUIFigure.UserData,'mov');
                    end
            end

            UpdateTable(app);
        end

        % Close request function: VarSelectorUIFigure
        function VarSelectorUIFigureCloseRequest(app, event)
            % enforce exit
            SnapUserDatatoFigure(app, true);

            app.is_closing = true;
        end

        % Value changed function: DropDown_Red
        function DropDown_RedValueChanged(app, event)
            value = app.DropDown_Red.Value;
            % selected canceled
            if value == "none"
                app.is_loaded(2) = false;
                UpdateTable(app);
                return;
            end

            size_movr =  size(evalin("base",value));
            % bad selection
            if numel(size_movr) ~= 4
                uialert(app.VarSelectorUIFigure,"数据维度不匹配.","Warning","Icon","warning");
                app.DropDown_Red.Value = "none";
                app.is_loaded(2) = false;
                UpdateTable(app);
                return;
            end

            app.mov_size(2).X = size_movr(2);
            app.mov_size(2).Y = size_movr(1);
            app.mov_size(2).C = 1;
            app.mov_size(2).Z = size_movr(3);
            app.mov_size(2).T = size_movr(4);

            app.is_loaded(2) = true;
            UpdateTable(app);
        end

        % Value changed function: DropDown_Green
        function DropDown_GreenValueChanged(app, event)
            value = app.DropDown_Green.Value;
            % selected canceled
            if value == "none"
                app.is_loaded(3) = false;
                UpdateTable(app);
                return;
            end
            
            size_movr =  size(evalin("base",value));
            % bad selection
            if numel(size_movr) ~= 4
                uialert(app.VarSelectorUIFigure,"Dimension not match.","Warning","Icon","warning");
                app.DropDown_Green.Value = "none";
                app.is_loaded(3) = false;
                UpdateTable(app);
                return;
            end

            app.mov_size(3).X = size_movr(2);
            app.mov_size(3).Y = size_movr(1);
            app.mov_size(3).C = 1;
            app.mov_size(3).Z = size_movr(3);
            app.mov_size(3).T = size_movr(4);

            app.is_loaded(3) = true;
            UpdateTable(app);
        end

        % Value changed function: DropDown_Mix
        function DropDown_MixValueChanged(app, event)
            value = app.DropDown_Mix.Value;
            % selected canceled
            if value == "none"
                app.is_loaded(1) = false;
                UpdateTable(app);
                return;
            end

            size_movr =  size(evalin("base",value));
            % bad selection
            if numel(size_movr) ~= 5 || size_movr(3) ~= 2
                uialert(app.VarSelectorUIFigure,"Dimension not match.","Warning","Icon","warning");
                app.DropDown_Mix.Value = "none";
                app.is_loaded(1) = false;
                UpdateTable(app);
                return;
            end

            app.mov_size(1).X = size_movr(2);
            app.mov_size(1).Y = size_movr(1);
            app.mov_size(1).C = size_movr(3);
            app.mov_size(1).Z = size_movr(4);
            app.mov_size(1).T = size_movr(5);

            app.is_loaded(1) = true;
            UpdateTable(app);
        end

        % Button pushed function: Button_Close
        function Button_ClosePushed(app, event)
            % normal exit
            SnapUserDatatoFigure(app, false);

            app.is_closing = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VarSelectorUIFigure = uifigure('Visible', 'off');
            app.VarSelectorUIFigure.Name = 'Var Selector';
            app.VarSelectorUIFigure.Icon = 'loading.png';
            app.VarSelectorUIFigure.CloseRequestFcn = createCallbackFcn(app, @VarSelectorUIFigureCloseRequest, true);
            app.Panel_MovieData = uipanel(app.VarSelectorUIFigure);
            app.Panel_MovieData.Title = '影像数据';
            app.ButtonGroup = uibuttongroup(app.Panel_MovieData);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '数据类型';
            app.Button_Single = uiradiobutton(app.ButtonGroup);
            app.Button_Single.Text = '单通道';
            app.Button_Single.Value = true;
            app.Button_Dual = uiradiobutton(app.ButtonGroup);
            app.Button_Dual.Text = '双通道';
            app.Label = uilabel(app.Panel_MovieData);
            app.Label.Text = '绿色通道';
            app.DropDown_Green = uidropdown(app.Panel_MovieData);
            app.DropDown_Green.Items = {};
            app.DropDown_Green.ValueChangedFcn = createCallbackFcn(app, @DropDown_GreenValueChanged, true);
            app.DropDown_Green.Value = {};
            app.Label_2 = uilabel(app.Panel_MovieData);
            app.Label_2.Text = '红色通道';
            app.DropDown_Red = uidropdown(app.Panel_MovieData);
            app.DropDown_Red.Items = {};
            app.DropDown_Red.ValueChangedFcn = createCallbackFcn(app, @DropDown_RedValueChanged, true);
            app.DropDown_Red.Value = {};
            app.Label_3 = uilabel(app.Panel_MovieData);
            app.Label_3.Text = '混合通道';
            app.DropDown_Mix = uidropdown(app.Panel_MovieData);
            app.DropDown_Mix.Items = {};
            app.DropDown_Mix.ValueChangedFcn = createCallbackFcn(app, @DropDown_MixValueChanged, true);
            app.DropDown_Mix.Value = {};
            app.UITable = uitable(app.Panel_MovieData);
            app.UITable.ColumnName = {'X'; 'Y'; 'C'; 'Z'; 'T'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.Panel_MetaData = uipanel(app.VarSelectorUIFigure);
            app.Panel_MetaData.Title = '元数据';
            app.Label_4 = uilabel(app.Panel_MetaData);
            app.Label_4.Text = '图像参数';
            app.DropDown_Opts = uidropdown(app.Panel_MetaData);
            app.DropDown_Opts.Items = {};
            app.DropDown_Opts.Value = {};
            app.Label_5 = uilabel(app.Panel_MetaData);
            app.Label_5.Text = '相机时间';
            app.DropDown_T = uidropdown(app.Panel_MetaData);
            app.DropDown_T.Items = {};
            app.DropDown_T.Value = {};
            app.Button_Close = uibutton(app.VarSelectorUIFigure, 'push');
            app.Button_Close.ButtonPushedFcn = createCallbackFcn(app, @Button_ClosePushed, true);
            app.Button_Close.Text = '关闭';
            % Show the figure after all components are created
            app.VarSelectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VarSelecter_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VarSelectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VarSelectorUIFigure)
        end
    end
end